classdef ClassificationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassificationModuleUIFigure   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SortClassesDropDown            matlab.ui.control.DropDown
        SortClassesDropDownLabel       matlab.ui.control.Label
        FilterVal                      matlab.ui.control.NumericEditField
        filter_CheckBox                matlab.ui.control.CheckBox
        ResetButton                    matlab.ui.control.Button
        XLimitsEditField_max           matlab.ui.control.NumericEditField
        XLimitsEditFieldLabel          matlab.ui.control.Label
        XLimitsEditField_min           matlab.ui.control.NumericEditField
        IterationsSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        RandomSeedSpinnerLabel         matlab.ui.control.Label
        RandomSeedSpinner              matlab.ui.control.Spinner
        ClearAllButton                 matlab.ui.control.Button
        DerivToClassify                matlab.ui.control.CheckBox
        SpecPlotSetDropDown            matlab.ui.control.DropDown
        SpecPlotSetDropDownLabel       matlab.ui.control.Label
        AutoSelectButton               matlab.ui.control.Button
        DerivativeSpinner              matlab.ui.control.Spinner
        DerivativeSpinner_2Label       matlab.ui.control.Label
        PointstoClassifySpinnerLabel   matlab.ui.control.Label
        PointstoClassifySpinner        matlab.ui.control.Spinner
        MaxClassesSpinner              matlab.ui.control.Spinner
        MaxClassesSpinnerLabel         matlab.ui.control.Label
        MinClassesSpinner              matlab.ui.control.Spinner
        MinClassesSpinnerLabel         matlab.ui.control.Label
        ClassTree                      matlab.ui.container.CheckBoxTree
        ClassifyButton                 matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        M2CButton                      matlab.ui.control.Button
        ResultsFigure                  matlab.ui.control.Button
        Figure2Button                  matlab.ui.control.Button
        CLR_CheckBox                   matlab.ui.control.CheckBox
        CMatButton                     matlab.ui.control.Button
        Norm_CheckBox                  matlab.ui.control.CheckBox
        Derivative_CheckBox            matlab.ui.control.CheckBox
        plot_spectra_checkbox          matlab.ui.control.CheckBox
        CancleButton                   matlab.ui.control.Button
        ReturnResultsButton            matlab.ui.control.Button
        SpectraPlot                    matlab.ui.control.UIAxes
        MapPlot                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        SelectAllMenu                  matlab.ui.container.Menu
        DeselectAllMenu                matlab.ui.container.Menu
        RemoveMenu                     matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        AddClassificationPointMenu     matlab.ui.container.Menu
        RemoveClassificationPointMenu  matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SpecMap

        PointsToClassifyLines
        hLineToDrag
        clickbuffer=0.05

        sampleinfo
        y_signal
        x_signal
        range
        
        mapsize
        % Description
        plotmat

        Classes
        ClassesNames
        MapNames
        
        CurvePos
cmap
        

    end

    methods (Access = private)

        function UpdateSpectraPlot(app)
            try
                xpos=[app.PointsToClassifyLines.Value];
            catch ME
                AutoSelectButtonPushed(app)
                xpos=[app.PointsToClassifyLines.Value];
            end
        cla(app.SpectraPlot)
            color_setting=lines(7);
            app.SpectraPlot.ColorOrder=color_setting;

            app.SpectraPlot.LineStyleOrder ='-';
            app.SpectraPlot.ColorOrderIndex=1;
        

            if strcmp(app.SpecPlotSetDropDown.Value,'Default')

                if size(app.y_signal,2)<=25
                    samplepoints=sort(randperm(size(app.y_signal,2),size(app.y_signal,2)));
                else
                    samplepoints=sort(randperm(size(app.y_signal,2),25));
                end
                app.plotmat=app.y_signal(:,samplepoints);

            elseif strcmp(app.SpecPlotSetDropDown.Value,'Sum')
                app.plotmat=sum(app.y_signal,2,"omitnan");
            elseif strcmp(app.SpecPlotSetDropDown.Value,'Mean')
                app.plotmat=mean(app.y_signal,2,"omitnan");

            elseif strcmp(app.SpecPlotSetDropDown.Value,'All')
                app.plotmat=app.y_signal;

            end

            if app.filter_CheckBox.Value==true
                 app.plotmat=movmean( app.plotmat,app.FilterVal.Value,"omitnan");
            end

            if app.Norm_CheckBox.Value==true
                xrange=app.x_signal>=min(app.range) & app.x_signal<=max(app.range);
                app.plotmat=app.plotmat./max(app.plotmat(xrange,:));
            end

            offset_wn=0;


            if app.plot_spectra_checkbox.Value
                p_h=plot(app.SpectraPlot,app.x_signal,app.plotmat,'LineWidth',2);
                set(p_h, 'hittest', 'off');

                ylim(app.SpectraPlot,'auto');
            end


            if app.Derivative_CheckBox.Value
                yyaxis(app.SpectraPlot,"right");
                cla(app.SpectraPlot)
                app.SpectraPlot.LineStyleOrder ='-';
                app.SpectraPlot.ColorOrderIndex=1;

                app.SpectraPlot.YAxis(2).Visible='on';

                if strcmp(app.SpecPlotSetDropDown.Value,'Default')
                    if size(app.y_signal,2) > 25
                        %samplepoints=sort(randperm(app..Datapoints,25));
                        deriv_plotmat=app.y_signal(:,samplepoints);
                    end
                elseif strcmp(app.SpecPlotSetDropDown.Value,'Sum')
                    deriv_plotmat=sum(app.y_signal,2,"omitnan");
                elseif strcmp(app.SpecPlotSetDropDown.Value,'Mean')
                    deriv_plotmat=mean(app.y_signal,2,"omitnan");
                elseif strcmp(app.SpecPlotSetDropDown.Value,'All')
                    deriv_plotmat=app.y_signal;
               
            
                end

                if not(app.DerivativeSpinner.Value==0)

                    for i=1:abs(app.DerivativeSpinner.Value)
                        for m=1:size(deriv_plotmat,2)
                            deriv_plotmat(:,m)=gradient(deriv_plotmat(:,m));
                        end
                    end

                    if app.DerivativeSpinner.Value<0
                        deriv_plotmat=-deriv_plotmat;
                    end

                end

               d_h= plot(app.SpectraPlot,app.x_signal,deriv_plotmat,'LineWidth',2,'LineStyle','-.');
                set(d_h, 'hittest', 'off');

                ylim(app.SpectraPlot,'auto');
                yyaxis(app.SpectraPlot,"left");

            else

                yyaxis(app.SpectraPlot,"right");
                cla(app.SpectraPlot)
                yyaxis(app.SpectraPlot,"left");
                app.SpectraPlot.YAxis(2).Visible='off';
            end
            if isequal(app.XLimitsEditField_max.Value,app.XLimitsEditField_min.Value)
                xlim(app.SpectraPlot,'auto')
            else
                xlim(app.SpectraPlot,sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]))
            end
            
            app.PointsToClassifyLines=xline(app.SpectraPlot,xpos,"LineWidth",2);
            set(app.PointsToClassifyLines, 'hittest', 'off');
        end

         function ResetTree(app)


            a = app.ClassTree.Children; 
            a.delete;


        end


        function UpdateTree(app)
            if isempty(app.ClassesNames)
                app.ClassTree.Visible=false;
                ResetTree(app)
            else
                ResetTree(app)


                %app.ClassesNames{mapnr}=['ClassMap' num2str(round(min(app.x_signal))) '_' num2str(round(max(app.x_signal)))];%%num2str(numberofclasses)

                for i=1:numel(app.ClassesNames)

                    p = uitreenode(app.ClassTree,'Text',char(app.ClassesNames{i}),'NodeData',[i,0]);
                    expand(p)

                    for j=1:numel(app.Classes(i).ClassMapNames)

                        p1 = uitreenode(p,'Text',char(app.Classes(i).ClassMapNames{j}),'NodeData',[i,j]);
                        expand(p1)

                        for k=1:numel(app.Classes(i).ClassMap(j).ClassesNames)
                            
                        p2=uitreenode(p1,'Text',app.Classes(i).ClassMap(j).ClassesNames{k},'NodeData',[i,j,k]); %#ok
                        end
                    end

                end

                app.ClassTree.Visible=true;

            end
        end

        function UpdateMapPlot(app,a2p,matrix,n_classes)
            cla(a2p)
            colormap(a2p,parula(n_classes))
            if any(size(matrix)==1)
                histogram(a2p,matrix)
            else
                caxis(a2p,[1 n_classes])
                imagesc(a2p,matrix, 'AlphaData', not(isnan(matrix)))
                axis(a2p,'equal')
                if not(isempty(app.cmap))
                    a2p.Colormap=app.cmap;
                end
            end
        end
        
        function ClassifyFun(app,DataIn,ix)
            sa=zeros(1,numel(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value));

            if app.Norm_CheckBox.Value==true
                DataIn=DataIn./max(DataIn);
            end


            if app.CLR_CheckBox.Value==true
                DataIn(DataIn<0)=0;
                DataIn=clr(DataIn);
            end
            if app.RandomSeedSpinner.Value==0
                rng('default');
            else
                rng(app.RandomSeedSpinner.Value);
            end

            if size(DataIn,1)<app.MaxClassesSpinner.Value
                app.MaxClassesSpinner.Value=size(DataIn,1)-2;
            end
            class_struc=struct();
            time_c(1)=toc;
            for k=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value
                %   [class_struc(k-app.MinClassesSpinner.Value+1).classNumber, class_struc(k-app.MinClassesSpinner.Value+1).classC, class_struc(k-app.MinClassesSpinner.Value+1).class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);
                [classNumber, classC, class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);


                if  strcmp(app.SortClassesDropDown.Value,'Integration')
                    if size(DataIn,2)>1
                    to_sort=(trapz(classC,2));
                    else
                    to_sort=classC;

                    end
                elseif  strcmp(app.SortClassesDropDown.Value,'n Pixel')
                    [to_sort, ~] =hist(classNumber,unique(classNumber(not(isnan(classNumber)))));
                elseif strcmp(app.SortClassesDropDown.Value,'C Distance')
%                     matrix=reshape(classNumber,[],app.mapsize(2))';
%                     for n=1:k
%                         [X,Y]= find(matrix==n);
% 
%                         %  ;
%                         try
%                             to_sort(n) = mean(hypot(bsxfun(@minus, X, X'), bsxfun(@minus, Y, Y')),'all');
%                         catch
%                             'too big'
%                             to_sort(n)=mean(dist([X,Y]'),'all');
%                         end
%                     end
                                            to_sort=class_sumd;

                                            % [~, sort_id]=sort(to_sort,'ascend');
                end

                if exist('to_sort','var')
                    [~, sort_id]=sort(to_sort,'descend');

                    classNumber=-classNumber;
                    for nn=1:numel(sort_id)
                        classNumber(classNumber==-sort_id(nn))=nn;
                    end
                end
                class_struc(k-app.MinClassesSpinner.Value+1).classNumber=classNumber;
                class_struc(k-app.MinClassesSpinner.Value+1).classC=classC;
                class_struc(k-app.MinClassesSpinner.Value+1).class_sumd=class_sumd;

                sa(k-app.MinClassesSpinner.Value+1)= sum(class_struc(k-app.MinClassesSpinner.Value+1).class_sumd,"omitnan");
                time_c(k+1)=toc;

            end
            time_c=time_c-time_c(1);


            rng('default');
            cla(app.UIAxes)
            plot(app.UIAxes,app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value,sa,'linewidth',2)
            app.UIAxes.Visible=true;


            ClassMapNr=0;
            mapnr=numel(app.ClassesNames)+1;
            app.ClassesNames{mapnr}=['ClassMap_' num2str(round(min(app.x_signal))) '_' num2str(round(max(app.x_signal))) '_' num2str(mapnr)];%%num2str(numberofclasses)
            app.Classes(mapnr).sa=[(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)' sa'];
            ns=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value;
            for k=1:numel(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)

                classNumber=class_struc(k).classNumber;
                ClassMapNr=ClassMapNr+1;
                app.Classes(mapnr).ClassMap(ClassMapNr).classNumber=classNumber;
                app.Classes(mapnr).ClassMap(ClassMapNr).MapPlotMatrix=reshape(classNumber,[],app.mapsize(2))';%ClassMap
                app.Classes(mapnr).ClassMapNames{ClassMapNr}=['Classmap_n' num2str(ns(k))];
                app.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames=cellstr(string(1:numel(unique(classNumber(not(isnan(classNumber))))))');
            end

            save_raw=true;
            time=toc;

            if save_raw==true
                app.Classes(mapnr).Classification_raw.Input=DataIn;
                if exist("ix","var") && not(isempty(app.x_signal))
                app.Classes(mapnr).Classification_raw.PointsToClassifyLines=app.x_signal(ix)'; % ath here this will create a problem if table and not spec
                end
                app.Classes(mapnr).Classification_raw.Results=class_struc;
                app.Classes(mapnr).Classification_raw.time=time;
                app.Classes(mapnr).Classification_raw.time_c=time_c(2:end);
            end
            UpdateTree(app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x_signal, y_signal, range, sampleinfo, mapsize, cmap)
            app.SpecMap = mainapp;
            app.x_signal=x_signal;
            y_signal(isinf(y_signal))=nan;
            app.y_signal=y_signal;
            app.range=range;
            app.sampleinfo=sampleinfo;

            app.mapsize=mapsize;
           if not(isempty(cmap))
            app.cmap=cmap;
           end

           if not (isempty(app.y_signal))
               app.XLimitsEditField_max.Value=max(app.range);
               app.XLimitsEditField_min.Value=min(app.range);
               app.SpectraPlot.XLim=sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);
               UpdateSpectraPlot(app)
           else
                           app.mapsize=flip(mapsize);

           end

           if isdeployed==false
               app.M2CButton.Visible=true;
               app.Figure2Button.Visible=true;
               app.ResultsFigure.Visible=true;
           end

        end

        % Value changed function: PointstoClassifySpinner
        function PointstoClassifySpinnerValueChanged(app, event)
            if not(isempty(app.PointsToClassifyLines))
                delete(app.PointsToClassifyLines)
            end

            xpos=linspace(min(app.range),max(app.range),app.PointstoClassifySpinner.Value+2);
            xpos=xpos(2:end-1);


            xrange=app.x_signal>=min([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]) & app.x_signal<=max([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);
            wn_=app.x_signal(xrange,:);
            if numel(xpos)>numel(wn_)
                app.PointstoClassifySpinner.Value=numel(wn_);
                xpos=wn_;
            end
            app.PointsToClassifyLines=xline(app.SpectraPlot,xpos,'LineWidth',2);
            set(app.PointsToClassifyLines, 'hittest', 'off');
            UpdateSpectraPlot(app)
        end

        % Button pushed function: AutoSelectButton
        function AutoSelectButtonPushed(app, event)
            if not(isempty(app.PointsToClassifyLines))
                delete(app.PointsToClassifyLines)
            end

            xrange=app.x_signal>=min([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]) & app.x_signal<=max([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);

            wn_=app.x_signal(xrange,:);
            in_=app.y_signal(xrange,:);
            if app.filter_CheckBox.Value==true
                in_=movmean(in_,app.FilterVal.Value,"omitnan");
            end

            if app.Derivative_CheckBox.Value==false || app.DerivativeSpinner.Value==0 
                DataIn= in_;
            else

                deriv=in_;


                for i=1:abs(app.DerivativeSpinner.Value)
                    for m=1:size(deriv,2)
                        deriv(:,m)=gradient(deriv(:,m));
                    end
                end

                if app.DerivativeSpinner.Value<0
                    deriv=-deriv;
                end

                DataIn= deriv;
                %             else
                %                 DataIn=app.y_signal;
            end


            if false
                if wn_(1)>wn_(end)
                    for n=1:size(DataIn,2)
                        [~,PeakPosition,W,Prominence] = findpeaks(flip(DataIn(:,(randi(size(DataIn,2),1)))),flip(wn_),'WidthReference','halfheight');

                        [~,idx]=sort(W,"descend");
                        if numel(PeakPosition)>=app.PointstoClassifySpinner.Value
                            xpos=PeakPosition(idx);
                            xpos_(n,:)=xpos(1:app.PointstoClassifySpinner.Value);
                        else
                            xpos_(n,:)=nan;
                            xpos_(n,1:numel(PeakPosition))=PeakPosition;
                        end
                    end
                   nhist= histogram(xpos_,'NumBins',size(DataIn,2));

                    [~,cn]=sort(nhist.BinCounts,'descend');
                    xpos=nhist.BinEdges(cn(1:app.PointstoClassifySpinner.Value));

                else
                    [~,PeakPosition,W,Prominence] = findpeaks(DataIn(:,(randi(9000,1))),flip(wn_),'WidthReference','halfheight');
                    %     findpeaks(mean(DataIn,2,"omitnan"),app.x_signal,'WidthReference','halfheight');
                end


            elseif app.Derivative_CheckBox.Value==true
                if wn_(1)>wn_(end)
                    [~,PeakPosition,W,Prominence] = findpeaks(flip(mean(DataIn,2,"omitnan")),flip(wn_),'WidthReference','halfheight');
                    % findpeaks(flip(mean(DataIn,2,"omitnan")),flip(app.x_signal),'WidthReference','halfheight');

                else
                    [~,PeakPosition,W,Prominence] = findpeaks(mean(DataIn,2,"omitnan"),wn_,'WidthReference','halfheight');
                    %     findpeaks(mean(DataIn,2,"omitnan"),app.x_signal,'WidthReference','halfheight');
                end

                [~,idx]=sort(W,"descend");
            if numel(PeakPosition)>=app.PointstoClassifySpinner.Value
                xpos=PeakPosition(idx);
                xpos=xpos(1:app.PointstoClassifySpinner.Value);
            else
                xpos=PeakPosition;
                app.PointstoClassifySpinner.Value=numel(xpos);
            end
            else
                mse=zeros(size(wn_));
                %            for n=1:size(in_,1);mse(n)=immse(wn_,DataIn(:,n));end
                DataIn=DataIn./max(DataIn);

                DataIn(DataIn<0.10| isnan(DataIn))=1;
                
                % mse=std(DataIn,0,2);
                for n=1:size(in_,1);mse(n)=snr(1:numel(DataIn(n,:)),1./DataIn(n,:));end


                if wn_(1)>wn_(end)
                    [~,PeakPosition,W,Prominence] = findpeaks(flip(mse),flip(wn_),'WidthReference','halfheight');
                    % findpeaks(flip(mean(DataIn,2,"omitnan")),flip(app.x_signal),'WidthReference','halfheight');

                else
                    [~,PeakPosition,W,Prominence] = findpeaks(mse,wn_,'WidthReference','halfheight');
                    %     findpeaks(mean(DataIn,2,"omitnan"),app.x_signal,'WidthReference','halfheight');
                end
                [~,idx]=sort(Prominence,"descend");
                if numel(PeakPosition)>=app.PointstoClassifySpinner.Value
                    xpos=PeakPosition(idx);
                    xpos=xpos(1:app.PointstoClassifySpinner.Value);
                else
                    xpos=PeakPosition;
                    if isempty(xpos)
                     app.PointstoClassifySpinner.Value=1;   
                     xpos=round(mean([app.XLimitsEditField_min.Value app.XLimitsEditField_max.Value]));
                    else
                    app.PointstoClassifySpinner.Value=numel(xpos);
                    end
                end
            end

            %for n=1:size(in_,2);mse(n)=immse(wn_,in_(:,n));end
            %


            app.PointsToClassifyLines=xline(app.SpectraPlot,xpos,"LineWidth",2);
            set(app.PointsToClassifyLines, 'hittest', 'off');
            UpdateSpectraPlot(app)
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)

            if not(isa(app.PointsToClassifyLines,'matlab.graphics.chart.decoration.ConstantLine')) || any(not(isvalid(app.PointsToClassifyLines)))
                AutoSelectButtonPushed(app)
            end
            tic
            PointsToClassifyStruc=get(app.PointsToClassifyLines);
            PointsToClassify=[PointsToClassifyStruc.Value];


            [ ~, ix ] = min(abs(app.x_signal-PointsToClassify));


            if app.DerivToClassify.Value==true
                deriv=app.y_signal;
                for i=1:abs(app.DerivativeSpinner.Value)

                    for m=1:size(deriv,2)
                        deriv(:,m)=gradient(deriv(:,m));
                    end
                end

                if app.DerivativeSpinner.Value<0
                    deriv=-deriv;
                end

                DataIn= deriv(ix,:)';
            else
                DataIn=app.y_signal(ix,:)';
            end

ClassifyFun(app,DataIn,ix)
UpdateTree(app)


        end

        % Selection changed function: ClassTree
        function ClassTreeSelectionChanged(app, event)
            
            
            N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                %oplot O in Curve plot with x Selected nodes(2) und y sa(Selected nodes(2))
                if not(isempty(app.CurvePos))
                    delete(app.CurvePos)
                end
                cla(app.UIAxes)
                plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(:,1),app.Classes(selectedNodes(1)).sa(:,2),'linewidth',2)

                app.CurvePos=plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(selectedNodes(2),1),app.Classes(selectedNodes(1)).sa(selectedNodes(2),2),"Color",'r','Marker','o','MarkerSize',10,'MarkerFaceColor','r');
                matrix=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                UpdateMapPlot(app,app.MapPlot,matrix,n_classes)
                
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                app.plotmat=zeros(size(app.y_signal,1),n_classes);
                for m=1:n_classes
                    selected_class=(matrix==m)';
                    selected_class=selected_class(:);
                    if not(isempty(app.y_signal))
                    app.plotmat(:,m)=mean(app.y_signal(:,selected_class),2,"omitnan");
                    end
                end


                %cla(app.SpectraPlot)
                %               plot(app.SpectraPlot,app.x_signal,app.plotmat)
                % color_setting= PlotMap_UpdateColormap(app,n_classes,1);


            elseif numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)
                cla(app.UIAxes)
                plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(:,1),app.Classes(selectedNodes(1)).sa(:,2),'linewidth',2)

                app.CurvePos=plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(selectedNodes(2),1),app.Classes(selectedNodes(1)).sa(selectedNodes(2),2),"Color",'r','Marker','o','MarkerSize',10,'MarkerFaceColor','r');
                matrix=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                                 n_classes=numel(unique(matrix(not(isnan(matrix)))));
                %                 matrix(not(matrix==selectedNodes(3)))=nan;
                UpdateMapPlot(app,app.MapPlot,matrix,n_classes)

                selected_class=(matrix==selectedNodes(3))';
                selected_class=selected_class(:);
                app.plotmat=mean(app.y_signal(:,selected_class),2,"omitnan");

                %                 matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                %         n_classes=numel(unique(matrix(not(isnan(matrix)))));
                %  color_setting= PlotMap_UpdateColormap(SpecMaps,n_classes,1);
                %                 app.SpectraPlot.ColorOrder=color_setting;
                %                 app.SpectraPlot.ColorOrderIndex=selectedNodes(3);
            end
            cla(app.SpectraPlot)
            if exist("n_classes","var") && not(isempty(n_classes))
                try
                    app.SpectraPlot.ColorOrder=app.cmap(round(linspace(1,size(app.cmap,1),n_classes)),:);
                    if size(app.plotmat,2)==1
                        app.SpectraPlot.ColorOrder=app.SpectraPlot.ColorOrder(selectedNodes(3),:);
                    end
                catch
                   app.SpectraPlot.ColorOrder=lines(n_classes);
                end
            end
            plot(app.SpectraPlot,app.x_signal,app.plotmat,"LineStyle","-","LineWidth",2)

            try
                if isfield(app.Classes(selectedNodes(1)).Classification_raw,'PointsToClassifyLines')
                xpos=[app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines];
                app.PointsToClassifyLines=xline(app.SpectraPlot,xpos,"LineWidth",2);
                set(app.PointsToClassifyLines, 'hittest', 'off');
                app.PointstoClassifySpinner.Value =numel(app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines);
                end
            catch ME
disp(ME)
            end
            %% specmaps

            %                     color_setting= PlotMap_UpdateColormap(app,n_classes,1);
            %                     app.SpectraPlot.ColorOrder=color_setting;
            %                 elseif numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)%
                                

        end

        % Value changed function: Derivative_CheckBox, Norm_CheckBox, 
        % SpecPlotSetDropDown, plot_spectra_checkbox
        function SpecPlotUpdate(app, event)
            UpdateSpectraPlot(app)
        end

        % Value changed function: DerivativeSpinner
        function DerivativeSpinnerValueChanged(app, event)
            if app.Derivative_CheckBox.Value==true
                UpdateSpectraPlot(app)
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
           app.ClassesNames=[];
            app.Classes=[];
            cla(app.MapPlot)        
            cla(app.UIAxes) 
            app.UIAxes.Visible=false;
            app.MapPlot.Visible=false;
            UpdateTree(app)

        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            %reset dropdown to eqinterval

            if not(isempty(app.PointsToClassifyLines))
                delete(app.PointsToClassifyLines)
            end

            app.PointstoClassifySpinner.Value=5;

            xpos=linspace(app.x_signal(1),app.x_signal(end),app.PointstoClassifySpinner.Value+2);
            xpos=xpos(2:end-1);
            app.PointsToClassifyLines=xline(app.SpectraPlot,xpos,'LineWidth',2);
                                        set(app.PointsToClassifyLines, 'hittest', 'off');

        end

        % Button pushed function: CancleButton
        function CancleButtonPushed(app, event)
            delete(app)
        end

        % Node text changed function: ClassTree
        function ClassTreeNodeTextChanged(app, event)
            node = event.Node;

            if numel(node.NodeData) == 3 && node.NodeData(3)>0
                app.Classes(node.NodeData(1)).ClassMap(node.NodeData(2)).ClassesNames{node.NodeData(3)}=node.Text;
            elseif numel(node.NodeData) == 2 && node.NodeData(2)>0
                app.Classes(node.NodeData(1)).ClassMapNames{node.NodeData(2)}=node.Text;
            elseif numel(node.NodeData) == 1 || node.NodeData(2)==0
                app.ClassesNames{node.NodeData(1)}=node.Text;
            end

        end

        % Button pushed function: ReturnResultsButton
        function ReturnResultsButtonPushed(app, event)
            if isempty(app.ClassTree.CheckedNodes)
                message='Error No Classification Selected';
                             uialert(app.ClassificationModuleUIFigure,message,'Error');
                return
            end
            checked_nodes={app.ClassTree.CheckedNodes.NodeData};
            No_of_elements = cellfun(@numel, checked_nodes);

            checked_nodes(No_of_elements==3)=[];

            if numel(checked_nodes)>0

                idx_to_keep=cell2mat(checked_nodes');

                k1=unique(idx_to_keep(:,1)); %ATH NAN 
                

                ClassificationReturnStruct.ClassesNames=app.ClassesNames(k1);
                ClassificationReturnStruct.Classes=app.Classes(k1);

                for n=1:numel(k1)
                    i=k1(n);
                    k2=idx_to_keep(idx_to_keep(:,1)==i,2);
                    ClassificationReturnStruct.Classes(n).ClassMapNames(not(ismember(1:numel(ClassificationReturnStruct.Classes(n).ClassMap),k2)))=[];
                    ClassificationReturnStruct.Classes(n).ClassMap(not(ismember(1:numel(ClassificationReturnStruct.Classes(n).ClassMap),k2)))=[];
                
                end
                ClassificationReturn(app.SpecMap,ClassificationReturnStruct,app.sampleinfo);
                delete(app)
            end

        end

        % Button down function: SpectraPlot
        function SpectraPlotButtonDown(app, event)
            for k=1:numel(app.PointsToClassifyLines)
                xVertLineCoord = get(app.PointsToClassifyLines(k),'Value');
                click_buffer=(max(app.SpectraPlot.XLim)-min(app.SpectraPlot.XLim))*0.01; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.PointsToClassifyLines(k).LineWidth=2;
                    app.PointsToClassifyLines(k).Color='r';
                    app.hLineToDrag = app.PointsToClassifyLines(k);
                    
                    break;
                end
            end
        end

        % Window button motion function: ClassificationModuleUIFigure
        function ClassificationModuleUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)

                    currentPoint   = app.SpectraPlot.CurrentPoint;
                    x            = currentPoint(1,1);
                    %y            = currentPoint(1,3);
                    xlim = app.SpectraPlot.XLim;
                    %ylim = app.SpectraPlot.YLim;
                    inside_SpectraPlot = x >= xlim(1)-(xlim(1)*app.clickbuffer) & x <= xlim(2)+(xlim(2)*app.clickbuffer); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                    if inside_SpectraPlot
                        set(app.hLineToDrag, 'Value', x);
                    else
                        UpdateSpectraPlot(app)
                        app.hLineToDrag.LineWidth=1;
                        app.hLineToDrag.Color='k';
                        app.hLineToDrag=[];
                    end

            end
        end

        % Window button up function: ClassificationModuleUIFigure
        function ClassificationModuleUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag.LineWidth=1;
                app.hLineToDrag.Color='k';
                app.hLineToDrag = [];
            end
        end

        % Value changed function: XLimitsEditField_max, 
        % XLimitsEditField_min
        function EditFieldValueChanged(app, event)
            app.SpectraPlot.XLim=sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);
            app.range=sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);            
            app.ResetButton.Visible=true;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.XLimitsEditField_max.Value=max(app.range);
           app.XLimitsEditField_min.Value=min(app.range);
            app.ResetButton.Visible=false;
            app.SpectraPlot.XLim=sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);

        end

        % Value changed function: FilterVal, filter_CheckBox
        function FilterValValueChanged(app, event)
                UpdateSpectraPlot(app)
        end

        % Button pushed function: CMatButton
        function CMatButtonPushed(app, event)
             N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
            %if numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)


            mapnr=selectedNodes(2);
            C=app.Classes(selectedNodes(1)).Classification_raw.Results(mapnr).classC; % centeroid
            idx=app.Classes(selectedNodes(1)).Classification_raw.Results(mapnr).classNumber;
            X=app.Classes(selectedNodes(1)).Classification_raw.Input;
            values=reshape(idx,[],app.mapsize(2))';
            try
                cmap_=PlotMap_UpdateColormap(app.SpecMap,numel(unique(values(not(isnan(idx))))));
            catch
                cmap_=jet(numel(unique(values(not(isnan(idx))))));
            end
            [fig] =fcnCorrMatrixPlot_NG(X,string(app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines),'',idx,cmap_);
            try
                MatrixLimits=update_MatixLimits(app.SpecMap,app.sampleinfo);

                ax = axes('Parent',fig,...
                    'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
                imagesc(MatrixLimits(1,:),(MatrixLimits(2,:)),flipud(values-0.5))
                daspect([1 1 1])
                cb=colorbar;
                cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));
           cb.TickLabels = app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
            set(cb,'TickLabelInterpreter','none')
            cb.FontSize = 8;
            catch

            end
            colormap(cmap_)

            caxis([0, numel(unique(values(not(isnan(values)))))])
            %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

           
            end
            %for mapnr=1:numel(app.Classes.Classification_raw.Results)

            %  end
        end

        % Button pushed function: Figure2Button
        function Figure2ButtonPushed(app, event)
            %               xrange=app.x_signal>=min([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]) & app.x_signal<=max([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]);
            %               wn_=app.x_signal(xrange,:);
            %               in_=app.y_signal(xrange,:);
            %               [coeff,score,latent,tsquared,explained,mu]=pca(wn_,in_);

            %hfig=figure;
            nexttile
            hold on
            plot(app.x_signal,app.y_signal,'-','markersize',0.5,'color', [0.2 0.2 0.2 0.05]);
            xlim(sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]))
            xpos=[app.PointsToClassifyLines.Value];
            xline(xpos,"LineWidth",2);
            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end
            hfig.Children.FontSize=15;
            xlabel('Wavenumber [cm^{-1}]', 'FontSize', 18);
            ylabel('Absorbance [cm^{-1}]', 'FontSize', 18);


            N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end
            

            hfig=figure;

            hold on

            matrix=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
            n_classes=numel(unique(matrix(not(isnan(matrix)))));
            colormap=app.cmap(round(linspace(1,size(app.cmap,1),n_classes)),:);
            for m=1:n_classes
                selected_class=(matrix==m)';
                selected_class=selected_class(:);
                if sum(selected_class)>500 %ath settings
                    thx=find(selected_class);
                    thx=thx(randi(sum(selected_class),500,1));
                    plot(app.x_signal,app.y_signal(:,thx),'-','markersize',0.5,'color',[ colormap(m,:) 0.05]);

                else
                    plot(app.x_signal,app.y_signal(:,selected_class),'-','markersize',0.5,'color',[ colormap(m,:) 0.05]);
                end
            end
            xlim(sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]))
            xpos=[app.PointsToClassifyLines.Value];
            xline(xpos,"LineWidth",2);
            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end
            hfig.Children.FontSize=15;
            xlabel('Wavenumber [cm^{-1}]', 'FontSize', 18);
            ylabel('Absorbance [cm^{-1}]', 'FontSize', 18);

        end

        % Button pushed function: ResultsFigure
        function ResultsFigurePushed(app, event)
            N=app.ClassTree.CheckedNodes;
            if not(isempty(N))
                selectedNodes = {N.NodeData};
                selectedNodes=selectedNodes(cellfun(@numel,{N.NodeData})>2);
                selectedNodes=cell2mat(selectedNodes');
                if isempty(selectedNodes)
                    return
                end
                [C,ia,ic]=unique(selectedNodes(:,1:2),'rows');
            else
                return
            end

            max_lines=5000;


            hfig=figure;
            hfig.Position=get(0,'screensize');

            %             subplot(numel(ia)+1,3,1:3)
            %             hold on
            %  plot(app.x_signal,app.y_signal,'-','markersize',0.5,'color', [0.2 0.2 0.2 0.05]);
            %            % xlim(sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]))
            %             xpos=[app.PointsToClassifyLines.Value];
            %             xline(xpos,"LineWidth",2);
            %             if strcmp(app.SpectraPlot.XDir,'reverse')
            %                 set (gca, 'xdir', 'reverse')
            %             end
            %             hfig.Children.FontSize=15;
            %             xlabel('Wavenumber [cm^{-1}]', 'FontSize', 18);
            %             ylabel('Absorbance [cm^{-1}]', 'FontSize', 18);
            %
            if app.RandomSeedSpinner.Value==0
                rng('default');
            else
                rng(app.RandomSeedSpinner.Value);
            end

            for n=1:numel(ia)
                subplot(numel(ia),3,n*3+1-3)
                hold on

                matrix=app.Classes(C(n,1)).ClassMap(C(n,2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                try
                    colormap=app.cmap(round(linspace(1,size(app.cmap,1),n_classes)),:);
                catch
                    colormap=parula(n_classes);

                end
                classes2plot=selectedNodes(ic==n,end);
                maxlines_class=max_lines/n_classes;
                xpos=[app.Classes(C(n,1)).Classification_raw.PointsToClassifyLines];

                if numel(xpos)<=20
                    xline(xpos,"LineWidth",2);
                elseif numel(xpos)<100
                    xline(xpos,"LineWidth",1);
                elseif numel(xpos)<200
                    xline(xpos,"LineWidth",0.25);
                end


                for m=1:numel(selectedNodes(ic==n,end))
                    selected_class=(matrix==classes2plot(m))';
                    selected_class=selected_class(:);
                    if sum(selected_class)>maxlines_class
                        
                        thx=find(selected_class);
                        thx=thx(randi(sum(selected_class),1,floor(maxlines_class)));
                        selected_class=false(size(selected_class));
                        selected_class(thx)=true;
                    end
                    plot(app.x_signal,app.y_signal(:,selected_class),'-','markersize',0.5,'color',[ colormap(classes2plot(m),:) 0.05]);
                end

                xlim(sort([app.XLimitsEditField_max.Value app.XLimitsEditField_min.Value]))
                
                if strcmp(app.SpectraPlot.XDir,'reverse')
                    set (gca, 'xdir', 'reverse')
                end
                if n==numel(ia)
                xlabel('Wavenumber [cm^{-1}]', 'FontSize', 14);
                end
                ylabel('Absorbance [cm^{-1}]', 'FontSize', 14);
                title({['Total classification time with ' num2str(numel(app.Classes(C(n,1)).Classification_raw.PointsToClassifyLines)) ' points: ' num2str(app.Classes(C(n,1)).Classification_raw.time) ' s'],'' });


                UpdateMapPlot(app,subplot(numel(ia),3,n*3+2-3),flipud(matrix),n_classes)
                title({['n pixel: '  num2str(sum(not(isnan(matrix(:)))))],'' });

                %                 lgd=legend({app.Classes(C(n,1)).ClassMap(C(n,2)).ClassesNames});
                %                 lgd(not(classes2plot))=[];
                splt=subplot(numel(ia),3,n*3+2-3);
                splt.YTickLabel=[];
                splt.XTickLabel=[];
                splt.XLim=splt.Children.XData;
                splt.YLim=splt.Children.YData;

                subplot(numel(ia),3,n*3+3-3)
                hold on
                plot(app.Classes(C(n,1)).sa(:,1),app.Classes(C(n,1)).sa(:,2),'linewidth',2)
                plot(app.Classes(C(n,1)).sa(C(n,end),1),app.Classes(C(n,1)).sa(C(n,end),2),"Color",'r','Marker','o','MarkerSize',10,'MarkerFaceColor','r');

                if n==numel(ia)
                    xlabel('n Classes', 'FontSize', 14);
                end
                ylabel('SUMD', 'FontSize', 14);


       %         title({['Total classification time with ' num2str(numel(app.Classes(C(n,1)).Classification_raw.PointsToClassifyLines)) ' points: ' num2str(app.Classes(C(n,1)).Classification_raw.time) ' s'],['Time for classification with ' num2str(C(n,2)+1) ' classes: ' num2str(app.Classes(C(n,1)).Classification_raw.time_c(C(n,2))) ' s'],'' });
                title({['Time for classification with ' num2str(C(n,2)+1) ' classes: ' num2str(app.Classes(C(n,1)).Classification_raw.time_c(C(n,2))) ' s'],'' });
                app.Classes(C(n,1)).Classification_raw.time_c(C(n,2))

            end

             [hfig.Children.FontSize]=deal(14);

            drawnow
        end

        % Button pushed function: M2CButton
        function M2CButtonPushed(app, event)
try
            mapnames=app.SpecMap.SpecMap.Spectra.Sample(app.sampleinfo(1)).Data(app.sampleinfo(2)).Maps(app.sampleinfo(3)).SubMapNames;
       
            [indx,tf] = listdlg('PromptString',{'Select Maps to Classify'},...
                'SelectionMode','multiple','ListString',mapnames);

            if isempty(indx)
                return
            end

            for n=1:numel(indx)
                thx=app.SpecMap.SpecMap.Spectra.Sample(app.sampleinfo(1)).Data(app.sampleinfo(2)).Maps(app.sampleinfo(3)).SubMap(indx(n)).MapPlotMatrix';
                DataIn(:,n)=thx(:);
            end


catch

    mapnames=app.SpecMap.SpecMap.Spectra.Sample(app.sampleinfo(1)).Data(app.sampleinfo(2)).MapNames;

    [indx,tf] = listdlg('PromptString',{'Select Maps to Classify'},...
        'SelectionMode','multiple','ListString',mapnames);

    if isempty(indx)
        return
    end


    for n=1:numel(indx)
        thx=app.SpecMap.SpecMap.Spectra.Sample(app.sampleinfo(1)).Data(app.sampleinfo(2)).Maps(indx(n)).SubMap(1).MapPlotMatrix';
        DataIn(:,n)=thx(:);
    end


end


            mask=app.SpecMap.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask';
            mask=not(mask(:));
            DataIn(mask,:)=nan;

            ClassifyFun(app,DataIn,[1 2 3 4 5 6])

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ClassificationModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {662, 662};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassificationModuleUIFigure = uifigure('Visible', 'off');
            app.ClassificationModuleUIFigure.Name = 'ClassificationModule';
            app.ClassificationModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ClassificationModuleUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ClassificationModuleUIFigureWindowButtonUp, true);
            app.ClassificationModuleUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ClassificationModuleUIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.ClassificationModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ClassifyButton = uibutton(app.GridLayout3, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ClassTree = uitree(app.GridLayout3, 'checkbox');
            app.ClassTree.SelectionChangedFcn = createCallbackFcn(app, @ClassTreeSelectionChanged, true);
            app.ClassTree.NodeTextChangedFcn = createCallbackFcn(app, @ClassTreeNodeTextChanged, true);
            app.ClassTree.Editable = 'on';
            app.ClassTree.Visible = 'off';
            app.MinClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MinClassesSpinnerLabel.Text = 'Min # Classes';
            app.MinClassesSpinner = uispinner(app.GridLayout3);
            app.MinClassesSpinner.Limits = [2 Inf];
            app.MinClassesSpinner.RoundFractionalValues = 'on';
            app.MinClassesSpinner.Value = 2;
            app.MaxClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaxClassesSpinnerLabel.Text = 'Max # Classes';
            app.MaxClassesSpinner = uispinner(app.GridLayout3);
            app.MaxClassesSpinner.Limits = [2 Inf];
            app.MaxClassesSpinner.RoundFractionalValues = 'on';
            app.MaxClassesSpinner.Value = 15;
            app.PointstoClassifySpinner = uispinner(app.GridLayout3);
            app.PointstoClassifySpinner.Limits = [1 Inf];
            app.PointstoClassifySpinner.RoundFractionalValues = 'on';
            app.PointstoClassifySpinner.ValueChangedFcn = createCallbackFcn(app, @PointstoClassifySpinnerValueChanged, true);
            app.PointstoClassifySpinner.Value = 5;
            app.PointstoClassifySpinnerLabel = uilabel(app.GridLayout3);
            app.PointstoClassifySpinnerLabel.Text = 'Points to Classify';
            app.DerivativeSpinner_2Label = uilabel(app.GridLayout3);
            app.DerivativeSpinner_2Label.Text = 'Derivative';
            app.DerivativeSpinner = uispinner(app.GridLayout3);
            app.DerivativeSpinner.Limits = [-10 10];
            app.DerivativeSpinner.RoundFractionalValues = 'on';
            app.DerivativeSpinner.ValueChangedFcn = createCallbackFcn(app, @DerivativeSpinnerValueChanged, true);
            app.DerivativeSpinner.Value = -2;
            app.AutoSelectButton = uibutton(app.GridLayout3, 'push');
            app.AutoSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSelectButtonPushed, true);
            app.AutoSelectButton.Text = 'AutoSelect';
            app.SpecPlotSetDropDownLabel = uilabel(app.GridLayout3);
            app.SpecPlotSetDropDownLabel.Text = 'SpecPlotSet';
            app.SpecPlotSetDropDown = uidropdown(app.GridLayout3);
            app.SpecPlotSetDropDown.Items = {'Default', 'Sum', 'Mean'};
            app.SpecPlotSetDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecPlotUpdate, true);
            app.SpecPlotSetDropDown.Value = 'Default';
            app.DerivToClassify = uicheckbox(app.GridLayout3);
            app.DerivToClassify.Tooltip = {'Use Derivative to Classify'};
            app.DerivToClassify.Text = '';
            app.ClearAllButton = uibutton(app.GridLayout3, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.RandomSeedSpinner = uispinner(app.GridLayout3);
            app.RandomSeedSpinner.Limits = [1 Inf];
            app.RandomSeedSpinner.RoundFractionalValues = 'on';
            app.RandomSeedSpinner.Value = 1;
            app.RandomSeedSpinnerLabel = uilabel(app.GridLayout3);
            app.RandomSeedSpinnerLabel.Text = 'Random Seed';
            app.IterationsSpinner = uispinner(app.GridLayout3);
            app.IterationsSpinner.Limits = [1 Inf];
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.Value = 5;
            app.IterationsSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.XLimitsEditField_min = uieditfield(app.GridLayout3, 'numeric');
            app.XLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.XLimitsEditField_min.Value = 600;
            app.XLimitsEditFieldLabel = uilabel(app.GridLayout3);
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField_max = uieditfield(app.GridLayout3, 'numeric');
            app.XLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.XLimitsEditField_max.Value = 4000;
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.filter_CheckBox = uicheckbox(app.GridLayout3);
            app.filter_CheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterValValueChanged, true);
            app.filter_CheckBox.Text = 'Filter';
            app.filter_CheckBox.Value = true;
            app.FilterVal = uieditfield(app.GridLayout3, 'numeric');
            app.FilterVal.Limits = [1 Inf];
            app.FilterVal.ValueChangedFcn = createCallbackFcn(app, @FilterValValueChanged, true);
            app.FilterVal.Value = 8;
            app.SortClassesDropDownLabel = uilabel(app.GridLayout3);
            app.SortClassesDropDownLabel.Text = 'Sort Classes';
            app.SortClassesDropDown = uidropdown(app.GridLayout3);
            app.SortClassesDropDown.Items = {'no sort', 'C Distance', 'Integration', 'n Pixel'};
            app.SortClassesDropDown.Value = 'Integration';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'n Classes')
            ylabel(app.UIAxes, 'sumd')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.MapPlot = uiaxes(app.GridLayout2);
            zlabel(app.MapPlot, 'Sample')
            app.MapPlot.XTickLabel = '';
            app.MapPlot.YTickLabel = '';
            app.MapPlot.Visible = 'off';
            app.SpectraPlot = uiaxes(app.GridLayout2);
            xlabel(app.SpectraPlot, 'Wavenumbers (cm-1)')
            ylabel(app.SpectraPlot, 'Absorbance')
            zlabel(app.SpectraPlot, 'Sample')
            app.SpectraPlot.XLim = [500 4000];
            app.SpectraPlot.XDir = 'reverse';
            app.SpectraPlot.ButtonDownFcn = createCallbackFcn(app, @SpectraPlotButtonDown, true);
            app.ReturnResultsButton = uibutton(app.GridLayout2, 'push');
            app.ReturnResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnResultsButtonPushed, true);
            app.ReturnResultsButton.Text = 'Return Results';
            app.CancleButton = uibutton(app.GridLayout2, 'push');
            app.CancleButton.ButtonPushedFcn = createCallbackFcn(app, @CancleButtonPushed, true);
            app.CancleButton.Text = 'Cancle';
            app.plot_spectra_checkbox = uicheckbox(app.GridLayout2);
            app.plot_spectra_checkbox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotUpdate, true);
            app.plot_spectra_checkbox.Text = 'Spectra';
            app.plot_spectra_checkbox.Value = true;
            app.Derivative_CheckBox = uicheckbox(app.GridLayout2);
            app.Derivative_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotUpdate, true);
            app.Derivative_CheckBox.Text = 'Derivative';
            app.Norm_CheckBox = uicheckbox(app.GridLayout2);
            app.Norm_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotUpdate, true);
            app.Norm_CheckBox.Text = 'Normalize';
            app.CMatButton = uibutton(app.GridLayout2, 'push');
            app.CMatButton.ButtonPushedFcn = createCallbackFcn(app, @CMatButtonPushed, true);
            app.CMatButton.Text = 'CMat';
            app.CLR_CheckBox = uicheckbox(app.GridLayout2);
            app.CLR_CheckBox.Text = 'CLR';
            app.Figure2Button = uibutton(app.GridLayout2, 'push');
            app.Figure2Button.ButtonPushedFcn = createCallbackFcn(app, @Figure2ButtonPushed, true);
            app.Figure2Button.Visible = 'off';
            app.Figure2Button.Text = 'Figure2';
            app.ResultsFigure = uibutton(app.GridLayout2, 'push');
            app.ResultsFigure.ButtonPushedFcn = createCallbackFcn(app, @ResultsFigurePushed, true);
            app.ResultsFigure.Visible = 'off';
            app.ResultsFigure.Text = 'Figure';
            app.M2CButton = uibutton(app.GridLayout2, 'push');
            app.M2CButton.ButtonPushedFcn = createCallbackFcn(app, @M2CButtonPushed, true);
            app.M2CButton.Visible = 'off';
            app.M2CButton.Text = 'M2C';
            app.ContextMenu = uicontextmenu(app.ClassificationModuleUIFigure);
            app.SelectAllMenu = uimenu(app.ContextMenu);
            app.SelectAllMenu.Text = 'Select All';
            app.DeselectAllMenu = uimenu(app.ContextMenu);
            app.DeselectAllMenu.Text = 'Deselect All';
            app.RemoveMenu = uimenu(app.ContextMenu);
            app.RemoveMenu.Text = 'Remove';
            % Assign app.ContextMenu
            app.ClassTree.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.ClassificationModuleUIFigure);
            app.AddClassificationPointMenu = uimenu(app.ContextMenu2);
            app.AddClassificationPointMenu.Text = 'Add Classification Point';
            app.RemoveClassificationPointMenu = uimenu(app.ContextMenu2);
            app.RemoveClassificationPointMenu.Text = 'Remove  Classification Point';
            app.ResetMenu = uimenu(app.ContextMenu2);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            % Assign app.ContextMenu2
            app.SpectraPlot.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.ClassificationModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassificationModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassificationModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassificationModuleUIFigure)
        end
    end
end