classdef CorrelationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CorrelationModuleUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        DataTreecheckbox           matlab.ui.container.CheckBoxTree
        ClassTree                  matlab.ui.container.CheckBoxTree
        SampleDropDown             matlab.ui.control.DropDown
        SampleDropDownLabel        matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        FontSizeEditField          matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel     matlab.ui.control.Label
        symbolsize_field           matlab.ui.control.NumericEditField
        NewPlotButton              matlab.ui.control.Button
        LogCheckBox                matlab.ui.control.CheckBox
        BiplotButton               matlab.ui.control.Button
        nCheckBox                  matlab.ui.control.CheckBox
        EditField_n                matlab.ui.control.NumericEditField
        nDropDown                  matlab.ui.control.DropDown
        DensityCheckBox            matlab.ui.control.CheckBox
        ScatterCheckBox            matlab.ui.control.CheckBox
        EditField_y                matlab.ui.control.NumericEditField
        EditField_x                matlab.ui.control.NumericEditField
        CalculateperAtomsCheckBox  matlab.ui.control.CheckBox
        YDropDown                  matlab.ui.control.DropDown
        YDropDownLabel             matlab.ui.control.Label
        XDropDown                  matlab.ui.control.DropDown
        XDropDownLabel             matlab.ui.control.Label
        SymbolDropDown             matlab.ui.control.DropDown
        SymbolDropDownLabel        matlab.ui.control.Label
        FigureTitleTextArea        matlab.ui.control.TextArea
        FigureTitleTextAreaLabel   matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        RemoveOutliersCheckBox     matlab.ui.control.CheckBox
        AddtopreviousPlotCheckBox  matlab.ui.control.CheckBox
        OldPlotButton              matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        Corrplot                   matlab.ui.control.UIAxes
        MapPlot                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SpecMaps
        sampleinfo
        TreeData_Main
        CorMaps
        xcorrmat
        ycorrmat
        ncorrmat
        CorrplotLegend
        checked_nodes
        cmap
        MatrixLimit
    end

    methods (Access = private)

        function UpdateTree(app)
            a = app.DataTreecheckbox.Children;
            a.delete;

%             nodes=app.TreeData_Main.Children(app.sampleinfo(1)).Children;
%             NodeNames={app.TreeData_Main.Children(app.sampleinfo(1)).Children.Text};
             app.CorMaps={};


            nodes= app.SpecMaps.TreeData_Main.Children(app.sampleinfo(1)).Children;
            NodeNames={nodes.Text};


            for m=1:numel(NodeNames)
                p = uitreenode(app.DataTreecheckbox,'Text',char(NodeNames{m}),'NodeData',[app.sampleinfo(1) m 1 1]);

                if numel(nodes(m).Children)>0
                    subnodes=nodes(m).Children;
                    subnodesNames={subnodes.Text};
                    for n=1:numel(subnodes)
                        p1 = uitreenode(p,'Text',char(subnodesNames{n}),'NodeData',[app.sampleinfo(1) m n 1]);
                        if numel(subnodes(n).Children)>0
                            subsubnodes=subnodes(n).Children;
                            subsubnodesNames={subsubnodes.Text};
                            for l=1:numel(subsubnodes)

                                if numel(subsubnodes(l).Children)>0
                                    subsubsubnodes=subsubnodes(l).Children;
                                    subsubsubnodesNames={subsubsubnodes.Text};
                                    p2 = uitreenode(p1,'Text',char(subsubnodesNames{l}),'NodeData',[subsubsubnodes(1).NodeData(2:end)]);

                                    for k=1:numel(subsubsubnodes)
                                        uitreenode(p2,'Text',char(subsubsubnodesNames{k}),'NodeData',[subsubsubnodes(k).NodeData(2:end)]);
                                        app.CorMaps(1,end+1)=subsubsubnodesNames(k);
                                        app.CorMaps{2,end}=[subsubsubnodes(k).NodeData(2:end)];
                                    end

                                else
                                    p2 = uitreenode(p1,'Text',char(subsubnodesNames{l}),'NodeData',[subsubnodes(l).NodeData(2:end)]);
                                    app.CorMaps(1,end+1)=subsubnodesNames(l);
                                    app.CorMaps{2,end}=[app.sampleinfo(1) subsubnodes(l).NodeData(2:end)];
                                end
                            end
                        else
                            app.CorMaps(1,end+1)=subnodesNames(n);
                            app.CorMaps{2,end}=[app.sampleinfo(1) m n 1 ];


                            %                         else
                            %                             app.CorMaps{end+1}=[app.CorMaps NodeNames(n)];
                        end
                    end

                end
            end
        end

        function UpdateCorrPlot(app)
            if app.AddtopreviousPlotCheckBox.Value==false
                cla(app.Corrplot)
                app.CorrplotLegend={};
            end

            if app.nCheckBox.Value == true
                if numel(app.nDropDown.Value)==4
                    app.ncorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value);
                else
                    app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value(2:end));
                end
            else
                app.ncorrmat=1;
            end

            if app.CalculateperAtomsCheckBox.Value==true
                x=(app.xcorrmat(:)./app.EditField_x.Value);
                y=(app.ycorrmat(:)./app.EditField_y.Value)./(app.ncorrmat(:)./app.EditField_n.Value);
            else
                x=app.xcorrmat(:);
                y=app.ycorrmat(:);%./app.ncorrmat(:);
            end

            if isempty(app.checked_nodes)
                xy_filt=(x>0 & x<inf & y>0 & y<inf);
                x=x(xy_filt);
                y=y(xy_filt);
                if app.RemoveOutliersCheckBox.Value==true
                    data=[x y];
                    data_rmo=rmoutliers(data);
                    x=data_rmo(:,1);
                    y=data_rmo(:,2);

                end
            else

                xy_filt=(x>0 & x<inf & y>0 & y<inf);
                values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:);
%                 mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);
%                 if numel(mask)>1
%                     values=values(mask);
%                 end

                x=x(xy_filt);
                y=y(xy_filt);
                values=values(xy_filt);
                if app.RemoveOutliersCheckBox.Value==true
                    data=[x y values];
                    data_rmo=rmoutliers(data);
                    x=data_rmo(:,1);
                    y=data_rmo(:,2);
                    values=data_rmo(:,3);
                end

            end


            if app.DensityCheckBox.Value==true 
                [values, centers] = hist3([x y],[75 75]);
                alpha=(values.'>0);
               imagesc(app.Corrplot,centers{:}, values.','AlphaData',alpha)
            end

            if app.DensityCheckBox.Value==true
                [values, ~] = hist3([x y],[numel(x) 1]);

                values(values==inf)=0;
                values((values==0))=nan;

                scatter(app.Corrplot,x',y',values.'.*10,values.','filled','s','Marker','o','MarkerEdgeColor','k')

            end
            if  app.ScatterCheckBox.Value==true

                if isempty(app.checked_nodes)
                    scatter(app.Corrplot,x',y','marker',app.SymbolDropDown.Value,'MarkerEdgeColor','k')
                else
                    mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);
                    %                         if numel(mask)>1
                    %                             values=values(mask);
                    %                         end

                    % scatter(app.Corrplot,x',y',50,values.','filled','s','Marker',app.SymbolDropDown.Value,'MarkerEdgeColor','k')
                    scatter(app.Corrplot,x',y',10,values.','filled','s','Marker',app.SymbolDropDown.Value)%,'MarkerEdgeColor','k')
                    %%
                    if true==false
                        mm=unique(values);
                        for n=1:numel(unique(values(not(isnan(values)))))
                            m=mm(n);
                            thx=x(values==m);
                            thy=y(values==m);

                            [Sx(m)] = std(thx,"omitnan");
                            Mx(m)= mean(thx,"omitnan");
                            My(m)= mean(thy,"omitnan");
                            [Sy(m)] = std(thy,"omitnan");
                        end
                        figure
                        hold on

                        scatter(x',y',10,values.','filled','s','Marker',app.SymbolDropDown.Value)%,'MarkerEdgeColor','k')
                        colormap(app.cmap)
                        for n=1:numel(unique(values(not(isnan(values)))))
                            m=mm(n);
                            errorbar(Mx(m),My(m),Sy(m),Sy(m),Sx(m),Sx(m),'o','Color',app.cmap(m,:),'LineWidth',2)
                        end
                    end
                    %%
                end

            end

            yindex = [];
            for k = 1:numel(app.YDropDown.ItemsData)
                if isequal(app.YDropDown.ItemsData{k}, app.YDropDown.Value)
                    yindex = k;
                    break;
                end
            end
            xindex = [];
            for k = 1:numel(app.XDropDown.ItemsData)
                if isequal(app.XDropDown.ItemsData{k}, app.XDropDown.Value)
                    xindex = k;
                    break;
                end
            end

            if app.nCheckBox.Value==true
                  nindex = [];
                for k = 1:numel(app.nDropDown.ItemsData)
                    if isequal(app.nDropDown.ItemsData{k}, app.nDropDown.Value)
                        nindex = k;
                        break;
                    end
                end
                ylabel(app.Corrplot,[app.nDropDown.Items(nindex) '/' app.YDropDown.Items(yindex)],'Interpreter','none');
               % xlabel(app.Corrplot,[app.XDropDown.Items(xindex) '*' app.nDropDown.Items(nindex)],'Interpreter','none');
                xlabel(app.Corrplot,[app.XDropDown.Items(xindex) '/' app.nDropDown.Items(nindex)],'Interpreter','none');
              %  xlabel(app.Corrplot,[app.nDropDown.Items(nindex) '/' app.XDropDown.Items(xindex)],'Interpreter','none');

            else
                ylabel(app.Corrplot,app.YDropDown.Items(yindex),'Interpreter','none');
                xlabel(app.Corrplot,app.XDropDown.Items(xindex),'Interpreter','none');

            end
            %%
            colormap(app.Corrplot,app.cmap)
            if app.LogCheckBox.Value==true
                set(app.Corrplot,'Yscale','log')
            else
                set(app.Corrplot,'Yscale','lin')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleinfo, TreeData_Main, SampleNames, checked_Classes, cmap)
   app.SpecMaps=mainapp;
            app.SampleDropDown.Items=SampleNames;
            app.SampleDropDown.Value=SampleNames(sampleinfo(1));
            app.sampleinfo=sampleinfo;
            app.TreeData_Main=TreeData_Main;
            UpdateTree(app)
            app.YDropDown.Items=app.CorMaps(1,:);
            app.XDropDown.Items=app.CorMaps(1,:);
            app.nDropDown.Items=app.CorMaps(1,:);
            app.YDropDown.ItemsData=app.CorMaps(2,:);
            app.XDropDown.ItemsData=app.CorMaps(2,:);
            app.nDropDown.ItemsData=app.CorMaps(2,:);

            if isdeployed
app.Switch.Visible=false;
app.CalculateperAtomsCheckBox.Visible=false;
app.RemoveOutliersCheckBox.Visible=false;
app.OldPlotButton.Visible=false;

            end
            if isempty(checked_Classes)
                app.checked_nodes=[];
            else
                app.checked_nodes=checked_Classes{:};
                if app.checked_nodes(2)==0
                    app.checked_nodes(2)=1;
                end
            end

            app.cmap=cmap;
            app.MatrixLimit=update_MatixLimits(app.SpecMaps);
            app.FigureTitleTextArea.Value=['Correlation Plot ',app.SampleDropDown.Value, '' ,'',''];

            update_class_tree(app.SpecMaps,app.ClassTree)

%             {app.SpecMaps.ClassTree.CheckedNodes.NodeData}'
%             
%             app.ClassTree.CheckedNodes=app.SpecMaps.ClassTree.CheckedNodes;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: DataTreecheckbox
        function DataTreecheckboxSelectionChanged(app, event)
            selectedNodes = app.DataTreecheckbox.SelectedNodes;
            if not(isempty(selectedNodes))
                app.sampleinfo(1)=find(ismember(app.SampleDropDown.Items,app.SampleDropDown.Value));
                for n=1:4%numel(selectedNodes.NodeData)
                    if numel(selectedNodes.NodeData)>=n && selectedNodes.NodeData(n)>0
                        app.sampleinfo(n)=selectedNodes.NodeData(n);
                    else
                        app.sampleinfo(n:end)=1;
                        break
                    end
                end
                if strcmp(app.Switch.Value,'Correlation')==false
                    MapPlot_fun_public(app.SpecMaps,app.MapPlot,app.sampleinfo);
                end
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.sampleinfo(1)= find(ismember(app.SampleDropDown.Items,app.SampleDropDown.Value));
            UpdateTree(app)
            app.YDropDown.Items=app.CorMaps(1,:);
            app.XDropDown.Items=app.CorMaps(1,:);
            app.nDropDown.Items=app.CorMaps(1,:);
            app.YDropDown.ItemsData=app.CorMaps(2,:);
            app.XDropDown.ItemsData=app.CorMaps(2,:);
            app.nDropDown.ItemsData=app.CorMaps(2,:);
            app.FigureTitleTextArea.Value=['Correlation Plot ',app.SampleDropDown.Value, '' ,'',''];
            %             app.YDropDown.Value=app.CorMaps(2);
            %             app.XDropDown.Value=app.CorMaps(1);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value,'Correlation')==true
                cla(app.MapPlot)
                app.YDropDown.Visible=true;
                app.XDropDown.Visible=true;
                app.nDropDown.Visible=true;
                app.nCheckBox.Visible=true;
                app.EditField_y.Visible=true;
                app.EditField_n.Visible=true;
                app.EditField_x.Visible=true;
                app.Corrplot.Visible=true;
                app.MapPlot.Visible=false; %ATH remove CBar
                c = colorbar(app.MapPlot);
                c.Visible='off';
                app.DensityCheckBox.Visible=true;
                app.ScatterCheckBox.Visible=true;

                if app.nCheckBox.Value == true
           if numel(app.nDropDown.Value)==4
                        app.ncorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value);
                    else
                        app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value(2:end));
           end               
                else
                   app.ncorrmat=1;
                end


                app.xcorrmat=MapPlot_fun_public(app.SpecMaps,0,app.XDropDown.Value);
                app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.YDropDown.Value);

                UpdateCorrPlot(app)

            else
                app.YDropDown.Visible=false;
                app.XDropDown.Visible=false;
                app.nDropDown.Visible=false;
                app.EditField_y.Visible=false;
                app.EditField_n.Visible=false;
                app.EditField_x.Visible=false;
                app.nCheckBox.Visible=false;

                app.Corrplot.Visible=false;
                app.DensityCheckBox.Visible=false;
                app.ScatterCheckBox.Visible=false;
                
                app.MapPlot.Visible=true;
                cla(app.Corrplot)
                MapPlot_fun_public(app.SpecMaps,app.MapPlot,app.sampleinfo);
            end
        end

        % Button pushed function: OldPlotButton
        function OldPlotButtonPushed(app, event)
          checkedNodes = app.DataTreecheckbox.CheckedNodes;
          data={checkedNodes.NodeData};
          VariableNames={checkedNodes.Text};
            vartest={checkedNodes.Children};
            vars2keep=cellfun(@isempty,vartest);
            VariableNames=VariableNames(vars2keep);
            data=data(vars2keep);


            for n=1:numel(data)
                thx=MapPlot_fun_public(app.SpecMaps,0,data{n});
                thx(not(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask))=nan;
                datamat(:,n)=thx(:)';
            end

            mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);

%             if numel(mask)>1
% 
%                 datamat(not(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask))=nan;
%             end
            %            if  not(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==4 || app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==5 || app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))>10) && isfield(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).SampleData,'Thickness_map') && (numel(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==numel(matrix) || numel(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1)
            %                 thx=(thx.*app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)./1e4;
            %            end

            % A=array2table(datamat);
            % A.Properties.VariableNames={checkedNodes.Text};
            datamat(datamat==0)=nan;
            datapoints_notnan=not(isnan(datamat(:,1)));

if sum(datapoints_notnan) > 10000
    datamat=datamat(datapoints_notnan,:);
    datapoints_randi=randi(sum(not(isnan(datamat(:,1)))),10000,1);
    datamat=datamat(datapoints_randi,:);
end

if app.LogCheckBox.Value==true


% datamat_alr=alr(datamat);
% datamat_alr_inv=alr_inv(datamat);
datamat_o=datamat;
datamat_o(isnan(datamat_o))=0;
datamat_o(datamat_o<0)=0;

datamat_clr=clr(datamat_o);
datamat_clr = log(datamat_o./repmat(geomean(datamat_o,2),1,size(datamat_o,2)));

 datamat_clr(isinf(datamat_clr))=nan;
% datamat_ilr=ilr(datamat);
% datamat_ilr_inv=ilrinv(datamat);
% datamat_ilc=ilc(datamat);

% 
figure;
nexttile;
hold on;
plot(datamat,'x');
title('Original data');

nexttile;
hold on;
plot(log(datamat),'x');
title('log data');

% 
% nexttile;
% hold on;
% plot(datamat_alr,'x');
% 
% title('ALR data');
% nexttile;
% hold on;
% plot(datamat_alr_inv,'x');
% title('ALR inverted data');
% 
nexttile;
hold on;
plot(datamat_clr,'x');
title('CLR data');

% nexttile;
% hold on;
% plot(datamat_ilr,'x');
% title('ILR data');
% % 
% nexttile;
% hold on;
% plot(datamat_ilr_inv,'x');
% title('ILR inverted data');
% 
% nexttile;
% hold on;
% plot(datamat_ilc,'x');
% title('ILC data');

%% 
try
                  values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:)';
              if exist('datapoints_randi','var')
                  values=values(datapoints_notnan,:);
                  values=values(datapoints_randi,:);
              end
              catch %ATH
                  values=ones(size(datamat_clr(:,1)));
              end
              mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);

              if numel(mask)>1
                  if exist('datapoints_randi','var')
                      mask=mask(datapoints_notnan,:);
                      mask=mask(datapoints_randi,:);
                  end
                  values(not(mask))=nan;
              end
              %% log

              [hFig] = fcnCorrMatrixPlot_NG(log(datamat),VariableNames,'',values,app.cmap); %ATH add marker

              ax = axes('Parent',hFig,...
                  'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
               values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
              
              imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(values-0.5))
              daspect([1 1 1])
              cb=colorbar;
              cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

              colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))

              caxis([0, numel(unique(values(not(isnan(values)))))])
              %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

              cb.TickLabels = app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
              set(cb,'TickLabelInterpreter','none')
              cb.FontSize = 8;


          ts=sgtitle([app.FigureTitleTextArea.Value ' log']);
          set(ts, 'Interpreter', 'none')
          %% clr
              [hFig] = fcnCorrMatrixPlot_NG(datamat_clr,VariableNames,'',values,app.cmap); %ATH add marker

              ax = axes('Parent',hFig,...
                  'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
              values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
              imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(values-0.5))
              daspect([1 1 1])
              cb=colorbar;
              cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

              colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))

              caxis([0, numel(unique(values(not(isnan(values)))))])
              %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

              cb.TickLabels = app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
              set(cb,'TickLabelInterpreter','none')
              cb.FontSize = 8;


          ts=sgtitle([app.FigureTitleTextArea.Value ' clr']);
          set(ts, 'Interpreter', 'none')

%%
end

          if app.RemoveOutliersCheckBox.Value==true
              [hFig] = fcnCorrMatrixPlot_NG(rmoutliers(datamat),VariableNames,''); %ATH add marker
          else
              try
                  values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
                  values(not(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask))=nan;
                  values=values(:)';

              if exist('datapoints_randi','var')
                  values=values(datapoints_notnan,:);
                  values=values(datapoints_randi,:);
              end
              catch %ATH
                  values=ones(size(datamat(:,1)));
              end
              mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);

              if numel(mask)>1
                  if exist('datapoints_randi','var')
                      mask=mask(datapoints_notnan,:);
                      mask=mask(datapoints_randi,:);
                  end
                  values(not(mask))=nan;
              end
              [hFig] = fcnCorrMatrixPlot_NG(datamat,VariableNames,'',values,app.cmap); %ATH add marker

              ax = axes('Parent',hFig,...
                  'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
              values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
              imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(values-0.5))
              daspect([1 1 1])
              cb=colorbar;
              cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));
    
              colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))

              caxis([0, numel(unique(values(not(isnan(values)))))])
              %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

              cb.TickLabels = app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
              set(cb,'TickLabelInterpreter','none')
              cb.FontSize = 8;
          end

          ts=sgtitle(app.FigureTitleTextArea.Value);
          set(ts, 'Interpreter', 'none')

           path=uigetdir;
            if isequal(path,0)
                return
            end

            file='temp';

%             S=dir([fullfile(path,'*.eps'))
%             file=matlab.lang.makeUniqueStrings(S,excludedStrings)

            FileName=fullfile(path,[file '_correlation_imgage.eps']);

            set(hFig,'Units','Inches');
            pos = get(hFig,'Position');
            set(hFig,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])

            exportgraphics(hFig,FileName,'BackgroundColor','none','ContentType','vector');

        end

        % Value changed function: EditField_x, EditField_y, LogCheckBox, 
        % SymbolDropDown, XDropDown, YDropDown, nDropDown
        function CorrPlotValueChanged(app, event)
            if strcmp(app.Switch.Value,'Correlation')==true
                if app.nCheckBox.Value == true
                    if numel(app.nDropDown.Value)==4
                        app.ncorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value);
                    else
                        app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.nDropDown.Value(2:end));
                    end
                else
                    app.ncorrmat=1;
                end
                if numel(app.XDropDown.Value)==4
                    app.xcorrmat=MapPlot_fun_public(app.SpecMaps,0,app.XDropDown.Value);%./ncorrmat; % ath here  changed (2:end)

                else
                    app.xcorrmat=MapPlot_fun_public(app.SpecMaps,0,app.XDropDown.Value(2:end));%./ncorrmat; % ath here  changed (2:end)
                end
                if numel(app.YDropDown.Value)==4
                    app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.YDropDown.Value);
                else
                    app.ycorrmat=MapPlot_fun_public(app.SpecMaps,0,app.YDropDown.Value(2:end));
                end
                if app.CalculateperAtomsCheckBox.Value==true
                    yindex = [];
                    for k = 1:numel(app.YDropDown.ItemsData)
                        if isequal(app.YDropDown.ItemsData{k}, app.YDropDown.Value)
                            yindex = k;
                            break;
                        end
                    end
                    xindex = [];
                    for k = 1:numel(app.XDropDown.ItemsData)
                        if isequal(app.XDropDown.ItemsData{k}, app.XDropDown.Value)
                            xindex = k;
                            break;
                        end
                    end
                    nindex = [];
                    for k = 1:numel(app.nDropDown.ItemsData)
                        if isequal(app.nDropDown.ItemsData{k}, app.nDropDown.Value)
                            nindex = k;
                            break;
                        end
                    end

                    Teststr{1}=app.XDropDown.Items(xindex);
                    Teststr{2}=app.YDropDown.Items(yindex);
                    Teststr{3}=app.nDropDown.Items(nindex);
                    boxes(1)=app.EditField_x;
                    boxes(2)=app.EditField_y;
                    boxes(3)=app.EditField_n;

                    ElementsMass=[1.00797 ,4.0026 ,6.941 ,9.01218 ,10.81 ,12.011 ,14.0067 ,15.9994 ,18.998403 ,20.179 ,22.98977 ,24.305 ,26.98154 ,28.0855 ,30.97376 ,32.06 ,35.453 ,39.0983 ,39.948 ,40.08 ,44.9559 ,47.9 ,50.9415 ,51.996 ,54.938 ,55.847 ,58.7 ,58.9332 ,63.546 ,65.38 ,69.72 ,72.59 ,74.9216 ,78.96 ,79.904 ,83.8 ,85.4678 ,87.62 ,88.9059 ,91.22 ,92.9064 ,95.94 ,-98 ,101.07 ,102.9055 ,106.4 ,107.868 ,112.41 ,114.82 ,118.69 ,121.75 ,126.9045 ,127.6 ,131.3 ,132.9054 ,137.33 ,138.9055 ,140.12 ,140.9077 ,144.24 ,-145 ,150.4 ,151.96 ,157.25 ,158.9254 ,162.5 ,164.9304 ,167.26 ,168.9342 ,173.04 ,174.967 ,178.49 ,180.9479 ,183.85 ,186.207 ,190.2 ,192.22 ,195.09 ,196.9665 ,200.59 ,204.37 ,207.2 ,208.9804 ,-209 ,-210 ,-222 ,-223 ,226.0254 ,227.0278 ,231.0359 ,232.0381 ,237.0482 ,238.029 ,-242 ,-243 ,-247 ,-247 ,-250 ,-251 ,-252 ,-255 ,-256 ,-257 ,-258 ,-260 ,-261 ,-262 ,-262 ,-263 ,-269 ,-272 ,-277];
                    ElementsSymb=[ "H","He","Li","Be","B","C","N","O","F","Ne","Na","Mg","Al","Si","P","S","Cl","K","Ar","Ca","Sc","Ti","V","Cr","Mn","Fe","Ni","Co","Cu","Zn","Ga","Ge","As","Se","Br","Kr","Rb","Sr","Y","Zr","Nb","Mo","Tc","Ru","Rh","Pd","Ag","Cd","In","Sn","Sb","I","Te","Xe","Cs","Ba","La","Ce","Pr","Nd","Pm","Sm","Eu","Gd","Tb","Dy","Ho","Er","Tm","Yb","Lu","Hf","Ta","W","Re","Os","Ir","Pt","Au","Hg","Tl","Pb","Bi","Po","At","Rn","Fr","Ra","Ac","Pa","Th","Np","U","Pu","Am","Bk","Cm","No","Cf","Es","Hs","Mt","Fm","Md","Lr","Rf","Bh","Db","Sg","Uun","Uuu","Uub"];
                    for m=1:3
                        for n=1:112
                            if strcmp(Teststr{m},char(ElementsSymb(n))) %any(strfind(Teststr{m},[char(ElementsSymb(n)) '.']) || strfind(Teststr{m},[' ' char(ElementsSymb(n)) ])|| strfind(Teststr{m},['_' char(ElementsSymb(n)) '_']) || strcmp(Teststr(m),char(ElementsSymb(n))))
                                boxes(m).Value=ElementsMass(n);
                            end
                        end
                    end
                else
                    app.EditField_x.Value=1;
                    app.EditField_y.Value=1;
                    app.EditField_n.Value=1;
                end
                UpdateCorrPlot(app)
            end

        end

        % Value changed function: CalculateperAtomsCheckBox, 
        % DensityCheckBox, EditField_n, RemoveOutliersCheckBox, 
        % ScatterCheckBox, nCheckBox
        function CalculateperAtomsCheckBoxValueChanged(app, event)
            if strcmp(app.Switch.Value,'Correlation')==true
                CorrPlotValueChanged(app)
            end
        end

        % Button pushed function: BiplotButton
        function BiplotButtonPushed(app, event)

            checkedNodes = app.DataTreecheckbox.CheckedNodes;
            data={checkedNodes.NodeData};
            VariableNames={checkedNodes.Text};
            vartest={checkedNodes.Children};
            vars2keep=cellfun(@isempty,vartest);
            VariableNames=VariableNames(vars2keep);
            data=data(vars2keep);


            for n=1:numel(data)
                thx=MapPlot_fun_public(app.SpecMaps,0,data{n});
                datamat(:,n)=thx(:)';
            end
            %            if  not(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==4 || app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==5 || app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))>10) && isfield(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).SampleData,'Thickness_map') && (numel(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==numel(matrix) || numel(app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1)
            %                 thx=(thx.*app.SpecMaps.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)./1e4;
            %            end


            % A=array2table(datamat);
            % A.Properties.VariableNames={checkedNodes.Text};
            datamat(datamat==0)=nan;
            datapoints_notnan=not(isnan(datamat(:,1)));

            if sum(datapoints_notnan) > 10000
                datamat=datamat(datapoints_notnan,:);
                datapoints_randi=randi(sum(not(isnan(datamat(:,1)))),10000,1);
                datamat=datamat(datapoints_randi,:);
            end
            datamat_orig=datamat;

            if app.LogCheckBox.Value==true
                datamat=(log(datamat));
            end

            try
                values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:);
                if exist('datapoints_randi','var')
                    values=values(datapoints_notnan,:);
                    values=values(datapoints_randi,:);
                end
            catch %ATH
                values=ones(size(datamat(:,1)));
            end


            if true
                figure
                X = rmmissing(datamat);
                [X,TF] = rmmissing(datamat);
                TF=not(TF);

                Z = zscore(X); % Standardized data
                [coefs,score] = pca(Z);
                hold on
                for n=1:numel(unique(values))
                    try
                        if n==1
                            biplot(coefs(:,1:3),'Scores',score(values(TF)==n,1:3),'VarLabels',VariableNames,Color=app.cmap(n,:));
                        else
                            biplot(coefs(:,1:3),'Scores',score(values(TF)==n,1:3),Color=app.cmap(n,:));

                        end
                    catch ME
                        ME
                    end
                end


                %biplot(coefs(:,1:3),'Scores',score(:,1:3),'VarLabels',VariableNames);


%                 figure('Units','normalized','Position',[0.3 0.3 0.3 0.5])
% 
%                 ax1 = subplot(2,1,1); % Top subplot
%                 for n=1:numel(unique(values))
%                                 hold on
%     try
%                         if n==1
%                             biplot(ax1,coefs(:,1:2),'Scores',score(values(TF)==n,1:2),'VarLabels',VariableNames,Color=app.cmap(n,:));
%                         else
%                             biplot(ax1,coefs(:,1:2),'Scores',score(values(TF)==n,1:2),Color=app.cmap(n,:));
% 
%                         end
%                     catch ME
%                         ME
%                     end
%                 end
%                 xlim(ax1,[-1 1])
%                 ylim(ax1,[-1 1])
% 
%                 ax2 = subplot(2,1,2); % Bottom subplot
%                 hold on
%                 for n=1:numel(unique(values))
%                     try
%                         if n==1
%                             biplot(ax2,coefs(:,3:4),'Scores',score(values(TF)==n,3:4),'VarLabels',VariableNames,Color=app.cmap(n,:));
%                         else
% 
%                             biplot(ax2,coefs(:,3:4),'Scores',score(values(TF)==n,3:4),Color=app.cmap(n,:));
% 
%                         end
%                     catch ME
%                         ME
%                     end
%                 end
%                 xlim(ax2,[-1 1])
%                 ylim(ax2,[-1 1])
%                 xlabel(ax2,'Component 3')
%                 ylabel(ax2,'Component 4')


                %%
                for h=[1 2 5 ]
                    switch h
                            case 1

                                title_str='Original data ';

                            case 2
                                datamat=log(datamat_orig);
                                title_str='Log data ';

                            case 3
                                datamat=alr(datamat_orig);
                                title_str='ALR data ';

                            case 4
                                datamat=alr_inv(datamat_orig);
                                title_str='ALR inverted data ';

                            case 5
                                %datamat=clr(datamat_orig);
                                datamat = log(datamat_orig./repmat(geomean(datamat_orig,2),1,size(datamat_orig,2)));
                                title_str='CLR data ';

                        case 6
                            datamat=ilr(datamat_orig);
                            title_str='ILR data ';

                        case 7
                            datamat=ilrinv(datamat_orig);
                            title_str='ILR inverted data ';

                        case 8
                            datamat=ilc(datamat_orig);
                            title_str='ILC data ';


                    end
                
                    [X,TF] = rmmissing(datamat);
                    TF=not(TF);

                    Z = zscore(X); % Standardized data
                    [coefs,score] = pca(Z);
                    figure
                    hold on
                    title(title_str)
                    for n=1:numel(unique(values))
                        try
                            if n==1
                                biplot(coefs(:,1:3),'Scores',score(values(TF)==n,1:3),'VarLabels',VariableNames,Color=app.cmap(n,:));
                            else
                                biplot(coefs(:,1:3),'Scores',score(values(TF)==n,1:3),Color=app.cmap(n,:));

                            end
                        catch ME
                            ME
                        end
                    end
                    figure
                    for n=1:numel(unique(values))
                        ax(n)= nexttile
                        try
                            biplot(coefs(:,1:3),'Scores',score(values(TF)==n,1:3),'VarLabels',VariableNames,Color=app.cmap(n,:));

                        catch ME
                            ME
                        end
                    end
                    Link = linkprop([ax(:)],{'CameraUpVector', 'CameraPosition', 'CameraTarget', 'XLim', 'YLim', 'ZLim'});
                    setappdata(gcf, 'StoreTheLink', Link);
                end

            else


                if app.RemoveOutliersCheckBox.Value==true
                    [hFig] = fcnCorrMatrixPlot_NG(rmoutliers(datamat),VariableNames,''); %ATH add marker
                    ts=sgtitle(app.FigureTitleTextArea.Value);
                    set(ts, 'Interpreter', 'none')
                else
                    try
                        values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:);
                        if exist('datapoints_randi','var')
                            values=values(datapoints_notnan,:);
                            values=values(datapoints_randi,:);
                        end
                    catch %ATH
                        values=ones(size(datamat(:,1)));
                    end
                    mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);

                    if numel(mask)>1
                        if exist('datapoints_randi','var')
                            mask=mask(datapoints_notnan,:);
                            mask=mask(datapoints_randi,:);
                        end
                        values(not(mask))=nan;
                    end
                    for h=[1 2 5 ]
                        switch h
                            case 1

                                datamat_orig=datamat;
                                title_str='Original data ';

                            case 2
                                datamat=log(datamat_orig);
                                title_str='Log data ';

                            case 3
                                datamat=alr(datamat_orig);
                                title_str='ALR data ';

                            case 4
                                datamat=alr_inv(datamat_orig);
                                title_str='ALR inverted data ';

                            case 5
                               % datamat=clr(datamat_orig);
                                                                datamat = log(datamat_orig./repmat(geomean(datamat_orig,2),1,size(datamat_orig,2)));

                                title_str='CLR data ';

                        case 6
                            datamat=ilr(datamat_orig);
                            title_str='ILR data ';

                        case 7
                            datamat=ilrinv(datamat_orig);
                            title_str='ILR inverted data ';

                        case 8
                            datamat=ilc(datamat_orig);
                            title_str='ILC data ';

                    end


                    [hFig] = fcnCorrMatrixPlot_NG(datamat,VariableNames,'',values,app.cmap); %ATH add marker

                    ax = axes('Parent',hFig,...
                        'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
                    values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
                    imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(values-0.5))
                    daspect([1 1 1])
                    cb=colorbar;
                    cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

                    colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))

                    caxis([0, numel(unique(values(not(isnan(values)))))])
                    %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

                    cb.TickLabels = app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
                    set(cb,'TickLabelInterpreter','none')
                    cb.FontSize = 8;
                    ts=sgtitle([title_str app.FigureTitleTextArea.Value]);
                    set(ts, 'Interpreter', 'none')


                end

            end
end


          %             checkedNodes = app.DataTreecheckbox.CheckedNodes;
          %             data={checkedNodes.NodeData};
%             VariableNames={checkedNodes.Text};
%      
%             vartest={checkedNodes.Children};
%             vars2keep=cellfun(@isempty,vartest);
%             VariableNames=VariableNames(vars2keep);
%             data=data(vars2keep);
% 
%             values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:);
%             mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);
%                 cc=numel(unique(values(not(isnan(values)))));
% 
%                           ElementsMass=[1.00797 ,4.0026 ,6.941 ,9.01218 ,10.81 ,12.011 ,14.0067 ,15.9994 ,18.998403 ,20.179 ,22.98977 ,24.305 ,26.98154 ,28.0855 ,30.97376 ,32.06 ,35.453 ,39.0983 ,39.948 ,40.08 ,44.9559 ,47.9 ,50.9415 ,51.996 ,54.938 ,55.847 ,58.7 ,58.9332 ,63.546 ,65.38 ,69.72 ,72.59 ,74.9216 ,78.96 ,79.904 ,83.8 ,85.4678 ,87.62 ,88.9059 ,91.22 ,92.9064 ,95.94 ,-98 ,101.07 ,102.9055 ,106.4 ,107.868 ,112.41 ,114.82 ,118.69 ,121.75 ,126.9045 ,127.6 ,131.3 ,132.9054 ,137.33 ,138.9055 ,140.12 ,140.9077 ,144.24 ,-145 ,150.4 ,151.96 ,157.25 ,158.9254 ,162.5 ,164.9304 ,167.26 ,168.9342 ,173.04 ,174.967 ,178.49 ,180.9479 ,183.85 ,186.207 ,190.2 ,192.22 ,195.09 ,196.9665 ,200.59 ,204.37 ,207.2 ,208.9804 ,-209 ,-210 ,-222 ,-223 ,226.0254 ,227.0278 ,231.0359 ,232.0381 ,237.0482 ,238.029 ,-242 ,-243 ,-247 ,-247 ,-250 ,-251 ,-252 ,-255 ,-256 ,-257 ,-258 ,-260 ,-261 ,-262 ,-262 ,-263 ,-269 ,-272 ,-277];
%                 ElementsSymb=[ "H","He","Li","Be","B","C","N","O","F","Ne","Na","Mg","Al","Si","P","S","Cl","K","Ar","Ca","Sc","Ti","V","Cr","Mn","Fe","Ni","Co","Cu","Zn","Ga","Ge","As","Se","Br","Kr","Rb","Sr","Y","Zr","Nb","Mo","Tc","Ru","Rh","Pd","Ag","Cd","In","Sn","Sb","I","Te","Xe","Cs","Ba","La","Ce","Pr","Nd","Pm","Sm","Eu","Gd","Tb","Dy","Ho","Er","Tm","Yb","Lu","Hf","Ta","W","Re","Os","Ir","Pt","Au","Hg","Tl","Pb","Bi","Po","At","Rn","Fr","Ra","Ac","Pa","Th","Np","U","Pu","Am","Bk","Cm","No","Cf","Es","Hs","Mt","Fm","Md","Lr","Rf","Bh","Db","Sg","Uun","Uuu","Uub"];
%   
% 
%                 for h=1:numel(unique(values(not(isnan(values)))))
%                     ElementsMass_active=1;
%               
% 
%                     for n=1:numel(data)
%                         for k=1:112
%                             if strcmp(VariableNames{n},char(ElementsSymb(k))) %any(strfind(Teststr{m},[char(ElementsSymb(n)) '.']) || strfind(Teststr{m},[' ' char(ElementsSymb(n)) ])|| strfind(Teststr{m},['_' char(ElementsSymb(n)) '_']) || strcmp(Teststr(m),char(ElementsSymb(n))))
%                                 ElementsMass_active=ElementsMass(k);
%                             end
%                         end
%                         thx2=MapPlot_fun_public(app.SpecMaps,0,data{n});
% 
%                         datamat(:,n)=thx2(:)'./ElementsMass_active;
%                         datamat(not((values==h)),n)=0;
%                     end
%               % A=array2table(datamat);
%               % A.Properties.VariableNames={checkedNodes.Text};
%               datamat(datamat==0)=nan;
% 
%    values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix(:);
%                   mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask(:);
%                   %              if numel(mask)>1
%                   %                  values=values(mask);
%                   %              end
%                   try
%                   [fig] = fcnCorrMatrixPlot_NG(datamat,VariableNames,'',values,app.cmap(h,:)); %ATH add marker
% 
%                   ax = axes('Parent',fig,...
%                       'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
% 
%                   imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix))
%                   daspect([1 1 1])
%                   colormap(app.cmap)
%                   cb=colorbar;
%                   cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));
%                  
%                ts=sgtitle([ app.FigureTitleTextArea.Value  ' class ' num2str(h)]);
%             set(ts, 'Interpreter', 'none')
%              catch
%                   end
%           end
%         
% 
% 
%            


%             disp('Organizing Data')
% 
%             checkedNodes = app.DataTreecheckbox.CheckedNodes;
%             data={checkedNodes.NodeData};
%             VariableNames={checkedNodes.Text};
%             for n=1:numel(data)
%                 thx=MapPlot_fun_public(app.SpecMaps,0,data{n});
%                 datamat(:,n)=thx(:)';
%             end
%             % A=array2table(datamat);
%             % A.Properties.VariableNames={checkedNodes.Text};
%             datamat(datamat==0)=nan;
% 
%             pairwise_corr_plot(datamat, VariableNames,'n_bins',51)
% 
% function [ah_bi, ah_uni ] = pairwise_corr_plot(X, VarName, varargin)
% ah=figure;
% % This function visualizes pairwise correlations among multiple variables
% % using 3D histogram and also plots univariate histograms. All of the axes
% % limits and color scales are synchronized across the subplots. The layout
% % of the subplots is designed to eliminate any duplicated axis labels/ticks.
% %
% % [ah_bi, ah_uni ] = pairwise_corr_plot(X, VarName, varargin)
% % 
% % Input arguments:
% %               X: a matrix where each column is a variable
% %         VarName: a cell array that contains the names of the variables
% %
% % Output arguments:
% %           ah_bi: a vector of axes handles for the bivariate histograms
% %          ah_uni: a vector of axes handles for the univariate histograms
% %
% % 0ptional input arguments that can be given in the name-value pair format:
% %     subplot_pos: a 3-by-1 vector [m, n, p1] that defined the subplot
% %                  position of the top-left subplot generated by this
% %                  function.
% %          n_bins: number of bins for the histograms
% %  label_fontsize: font size of the x/y labels
% %   text_fontsize: font size of the texts
% %   axis_fontsize: font size of the axis tick labels
% %
% % Yifan Gu, 8-Feb-207
% % yigu8115@gmail.com
% n_var = length(VarName); % number of variables
% subplot_pos = [n_var n_var 1];
% n_bins = 20; % number of bins for the histograms
% label_fontsize = 6;
% text_fontsize = 6;
% axis_fontsize = 6;
% % Read varargin
% var_len = length(varargin);
% if mod(var_len,2) ~= 0
%     error('0ptional input arguments that can be given in the name-value pair format.')
% end
% for i = 1:var_len/2
%     eval([varargin{i*2-1}, '= varargin{i*2};']);
% end
% m = subplot_pos(1);
% n = subplot_pos(2);
% p1 = subplot_pos(3);
% % check input
% [m1,n1] = ind2sub([m, n], p1);
% if m1 + n_var - 1 > m || n1 + n_var - 1 > n
%     error('Not enough subplot positions available!')
% end
% ah_bi = [];
% ah_uni = [];
% bi_caxis_max = 0;
% uni_yaxis_max = 0;
% for i = 1:n_var
%     for j = 1:n_var
%         % subplot position
%         p = p1 + n*(i-1) + j-1;
%         % histogram bin edges
%         bin_edge_x = linspace(min(X(:,j)), max(X(:,j)), n_bins+1)';
%         bin_edge_y = linspace(min(X(:,i)), max(X(:,i)), n_bins+1)';
%         bin_centre_x = (bin_edge_x(1:end-1)+bin_edge_x(2:end))/2;
%         bin_centre_y = (bin_edge_y(1:end-1)+bin_edge_y(2:end))/2;
%         if i == j % do univariate histogram
%             
%             ah = subplot(m,n, p);
%             ah_uni = [ah_uni ah]; %#ok<AGROW>
%             [N] = histc(X(:,j), bin_edge_x);
%             if i == n_var
%                 barh(bin_centre_x, N(1:end-1), 'FaceColor','none','EdgeColor','b');
%                 ylim(minmax(bin_edge_x'));
%                  uni_yaxis_max = max(uni_yaxis_max, max(xlim));
%             else
%                 bar(bin_centre_x, N(1:end-1), 'FaceColor','none','EdgeColor','b');
%                 xlim(minmax(bin_edge_x'));
%                  uni_yaxis_max = max(uni_yaxis_max, max(ylim));
%             end
%             if i > 1
%                 set(ah,'ytick',[]);
%             end
%             box on;
%             set(ah,'xtick',[], 'fontSize', axis_fontsize );
%            
%            
%             
%         elseif i > j % do bivariate histogram
%             ah = subplot(m,n, p);
%             ah_bi = [ah_bi ah]; %#ok<AGROW>
%             [N] = hist3([X(:,j) X(:,i)], 'Edges', {bin_edge_x, bin_edge_y});
%             imagesc(bin_centre_x, bin_centre_y, N(1:end-1,1:end-1));
%             if i == n_var
%                 xlabel(VarName{j},'FontSize', label_fontsize)
%             else
%                 set(ah,'xtick',[]);
%             end
%             if j == 1
%                 ylabel(VarName{i},'FontSize', label_fontsize)
%             else
%                 set(ah,'ytick',[]);
%             end
%             bi_caxis_max = max(bi_caxis_max, max(caxis));
%             % Calculate and show the correlation coefficient
%             cof = corrcoef(X(:,j), X(:,i), 'rows', 'complete');
%             r_str = sprintf('r = %.3f', cof(1,2));
%             pos = get(gca,'position');
%             set(gca,'fontsize', text_fontsize);
%             % use dummy axes to make positioning text easier
%             axes('Parent', gcf, 'Position', pos, 'Visible', 'off', 'XLim', [0, 1],'YLim', [0, 1], 'NextPlot', 'add'); %#ok<LAXES>
%             text (0.5, 1.02, r_str, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'center', 'FontSize', text_fontsize);
%             set(ah,'YDir','normal','fontsize', axis_fontsize );
%         end
%     end
% end
% % synchronize y limits for univariate histograms
% for i = 1:n_var
%     if i < n_var
%         set(ah_uni(i), 'Ylim', [0 uni_yaxis_max]);
%     else
%         set(ah_uni(i), 'Xlim', [0 uni_yaxis_max]);
%     end
% end
% % synchronize color scale for bivariate histograms
% for i = 1:length(bi_caxis_max)
%     caxis(ah_bi(i), [0, bi_caxis_max]);
% end
% end
        end

        % Button pushed function: NewPlotButton
        function NewPlotButtonPushed(app, event)
            checkedNodes = app.DataTreecheckbox.CheckedNodes;
            data={checkedNodes.NodeData};
            VariableNames={checkedNodes.Text};
            vartest={checkedNodes.Children};
            vars2keep=cellfun(@isempty,vartest);
            VariableNames=VariableNames(vars2keep);
            data=data(vars2keep);

            datamat=zeros(numel(MapPlot_fun_public(app.SpecMaps,0,data{1})),numel(data));
            for n=1:numel(data)
                thx=MapPlot_fun_public(app.SpecMaps,0,data{n});
                thx(not(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask))=nan;
                datamat(:,n)=thx(:)';
            end

            if app.LogCheckBox.Value==true
                datamat=(log(datamat));
            end


            datamat(datamat==0)=nan;
            datapoints_notnan=not(isnan(datamat(:,1)));

            if sum(datapoints_notnan) > 10000
                datamat=datamat(datapoints_notnan,:);
                datapoints_randi=randi(sum(not(isnan(datamat(:,1)))),10000,1);
                datamat=datamat(datapoints_randi,:);
            end


            %%
            n=1;
            %%
            if isempty(app.checked_nodes)
                app.cmap=app.cmap.*0;
                values= ones(size(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).Data(1).Maps(1).SubMap(1).MapPlotMatrix));
                ClassNames={'1'};
            else
                values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
                ClassNames=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
            end

            n_values=numel(unique(values(not(isnan(values)))));
            values(not(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask))=nan;
            values=values(:);

            if exist('datapoints_randi','var')
                values=values(datapoints_notnan,:);
                values=values(datapoints_randi,:);
            end


            %%
            DataIn.Active{n}=true(size(ClassNames(unique(values(not(isnan(values)))))));%:


            n_values=floor(linspace(1,size(app.cmap,1),n_values));

            DataIn.cmap{n}=app.cmap(n_values(unique(values(not(isnan(values))))),:);%:
            DataIn.Symbol{n}=repmat({app.SymbolDropDown.Value},size(ClassNames(unique(values(not(isnan(values)))))));%:
            DataIn.ClassNames{n}=ClassNames(unique(values(not(isnan(values)))));%:
            DataIn.VarNames=VariableNames;%:
            if app.symbolsize_field.Value==0
                DataIn.SymbolSize{n}=ones(size(ClassNames(unique(values(not(isnan(values)))))));%:
            else
                DataIn.SymbolSize{n}=ones(size(ClassNames(unique(values(not(isnan(values))))))).*app.symbolsize_field.Value;
            end

            DataIn.DataMat{n}=datamat;
            DataIn.classNumber{n}=values;
            DataIn.labelsize=14;
            DataIn.disp_set='half';


            [hFig] =   CorPlotFun(DataIn);
try
            hLegend = findobj(hFig.Children.Children, 'Type', 'Legend');
            hLegend.Visible=false;
catch ME
0
end
            if not(isempty(app.checked_nodes))

            ax = axes('Parent',hFig,...
                'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);


                values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
                ClassNames=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
            
            if  isfield(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask)==numel(values)
                mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask;
            else
                mask=ones(size(values));
            end

            alpha=ones(size(values));
            alpha(mask==0)=0;

            %imagesc(Axis2Plot,matrix,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:), 'AlphaData', alpha);
            hold on

            imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),(values-0.5), 'AlphaData', alpha)


            %             ax = axes('Parent',hFig,...
            %                 'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
            %
            %             values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
            %             imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),flipud(values-0.5))
            daspect([1 1 1])
            cb=colorbar;
            cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

            colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))
            %app.cmap(n_values(unique(values(not(isnan(values))))),:)
            %     colormap(DataIn.cmap{n})
            caxis([0, numel(unique(values(not(isnan(values)))))])
            %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

            cb.TickLabels = ClassNames;
            set(cb,'TickLabelInterpreter','none')
            cb.FontSize = 8;
            end
            %%


            if not(isempty(app.checked_nodes))
                for n=1:numel(DataIn.Active{1, 1})

                    mean_data(n,:)=mean(DataIn.DataMat{1,1}(DataIn.classNumber{1,1}==n,:),1,'omitnan');

                end
                DataIn.DataMat{1,1}=mean_data;
                DataIn.classNumber{1,1}=(1:numel(DataIn.Active{1, 1}))';
                DataIn.SymbolSize{1,1}(:)=40;
                [hFig] =   CorPlotFun(DataIn);
                hLegend = findobj(hFig.Children.Children, 'Type', 'Legend');
                hLegend.Visible=false;
                          if not(isempty(app.checked_nodes))
  ax = axes('Parent',hFig,...
                    'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);


                        values=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).MapPlotMatrix;
                        ClassNames=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Classification.Classes(app.checked_nodes(1)).ClassMap(app.checked_nodes(2)).ClassesNames;
                           
                if  isfield(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask)==numel(values)
                    mask=app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.Mask;
                else
                    mask=ones(size(values));
                end

                alpha=ones(size(values));
                alpha(mask==0)=0;

                %imagesc(Axis2Plot,matrix,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:), 'AlphaData', alpha);
                hold on
                imagesc(app.MatrixLimit(1,:),(app.MatrixLimit(2,:)),(values-0.5), 'AlphaData', alpha)


                daspect([1 1 1])
                cb=colorbar;
                cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

                colormap(PlotMap_UpdateColormap(app.SpecMaps,numel(unique(values(not(isnan(values)))))))

                %     colormap(DataIn.cmap{n})
                caxis([0, numel(unique(values(not(isnan(values)))))])
                %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

                cb.TickLabels = ClassNames;
                set(cb,'TickLabelInterpreter','none')
                cb.FontSize = 8;
            end
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CorrelationModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {678, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CorrelationModuleUIFigure = uifigure('Visible', 'off');
            app.CorrelationModuleUIFigure.Name = 'CorrelationModule';
            app.CorrelationModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CorrelationModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SampleDropDownLabel = uilabel(app.GridLayout2);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.GridLayout2);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.ClassTree = uitree(app.GridLayout2, 'checkbox');
            app.ClassTree.Visible = 'off';
            app.DataTreecheckbox = uitree(app.GridLayout2, 'checkbox');
            app.DataTreecheckbox.SelectionChangedFcn = createCallbackFcn(app, @DataTreecheckboxSelectionChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.MapPlot = uiaxes(app.GridLayout3);
            xlabel(app.MapPlot, 'X')
            ylabel(app.MapPlot, 'Y')
            zlabel(app.MapPlot, 'Sample')
            app.Corrplot = uiaxes(app.GridLayout3);
            xlabel(app.Corrplot, 'X')
            ylabel(app.Corrplot, 'Y')
            zlabel(app.Corrplot, 'Sample')
            app.Corrplot.Visible = 'off';
            app.CloseButton = uibutton(app.GridLayout3, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OldPlotButton = uibutton(app.GridLayout3, 'push');
            app.OldPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OldPlotButtonPushed, true);
            app.OldPlotButton.Text = 'OldPlot';
            app.AddtopreviousPlotCheckBox = uicheckbox(app.GridLayout3);
            app.AddtopreviousPlotCheckBox.Visible = 'off';
            app.AddtopreviousPlotCheckBox.Text = 'Add to previous Plot';
            app.RemoveOutliersCheckBox = uicheckbox(app.GridLayout3);
            app.RemoveOutliersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.RemoveOutliersCheckBox.Text = 'Remove Outliers';
            app.Switch = uiswitch(app.GridLayout3, 'slider');
            app.Switch.Items = {'Map', 'Correlation'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Map';
            app.FigureTitleTextAreaLabel = uilabel(app.GridLayout3);
            app.FigureTitleTextAreaLabel.Text = 'Figure Title';
            app.FigureTitleTextArea = uitextarea(app.GridLayout3);
            app.SymbolDropDownLabel = uilabel(app.GridLayout3);
            app.SymbolDropDownLabel.Text = 'Symbol';
            app.SymbolDropDown = uidropdown(app.GridLayout3);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram', 'No markers'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.SymbolDropDown.Value = '.';
            app.XDropDownLabel = uilabel(app.GridLayout3);
            app.XDropDownLabel.Visible = 'off';
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.GridLayout3);
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.XDropDown.Visible = 'off';
            app.YDropDownLabel = uilabel(app.GridLayout3);
            app.YDropDownLabel.Visible = 'off';
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.GridLayout3);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.YDropDown.Visible = 'off';
            app.CalculateperAtomsCheckBox = uicheckbox(app.GridLayout3);
            app.CalculateperAtomsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.CalculateperAtomsCheckBox.Visible = 'off';
            app.CalculateperAtomsCheckBox.Text = 'Calculate per Atoms';
            app.CalculateperAtomsCheckBox.Value = true;
            app.EditField_x = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_x.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.EditField_x.Visible = 'off';
            app.EditField_x.Value = 1;
            app.EditField_y = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_y.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.EditField_y.Visible = 'off';
            app.EditField_y.Value = 1;
            app.ScatterCheckBox = uicheckbox(app.GridLayout3);
            app.ScatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.ScatterCheckBox.Visible = 'off';
            app.ScatterCheckBox.Text = 'Scatter';
            app.ScatterCheckBox.Value = true;
            app.DensityCheckBox = uicheckbox(app.GridLayout3);
            app.DensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.DensityCheckBox.Visible = 'off';
            app.DensityCheckBox.Text = 'Density';
            app.nDropDown = uidropdown(app.GridLayout3);
            app.nDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.nDropDown.Visible = 'off';
            app.EditField_n = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_n.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.EditField_n.Visible = 'off';
            app.EditField_n.Value = 1;
            app.nCheckBox = uicheckbox(app.GridLayout3);
            app.nCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateperAtomsCheckBoxValueChanged, true);
            app.nCheckBox.Visible = 'off';
            app.nCheckBox.Text = 'n';
            app.BiplotButton = uibutton(app.GridLayout3, 'push');
            app.BiplotButton.ButtonPushedFcn = createCallbackFcn(app, @BiplotButtonPushed, true);
            app.BiplotButton.Text = 'Biplot';
            app.LogCheckBox = uicheckbox(app.GridLayout3);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrPlotValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.NewPlotButton = uibutton(app.GridLayout3, 'push');
            app.NewPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewPlotButtonPushed, true);
            app.NewPlotButton.Text = 'NewPlot';
            app.symbolsize_field = uieditfield(app.GridLayout3, 'numeric');
            app.FontSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.FontSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FontSizeEditField.Limits = [1 200];
            app.FontSizeEditField.Value = 8;
            % Show the figure after all components are created
            app.CorrelationModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorrelationModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CorrelationModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CorrelationModuleUIFigure)
        end
    end
end