classdef ExportModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportModuleUIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CheckBox_mat_exp            matlab.ui.control.CheckBox
        CheckBox_tif_exp            matlab.ui.control.CheckBox
        CheckBox_csv_exp            matlab.ui.control.CheckBox
        CheckBox_eps_exp            matlab.ui.control.CheckBox
        CheckBox_pdf_exp            matlab.ui.control.CheckBox
        CheckBox_png_exp            matlab.ui.control.CheckBox
        CheckBox_fig_exp            matlab.ui.control.CheckBox
        ScalebarCheckBox            matlab.ui.control.CheckBox
        SaveOpticalImagesCheckBox   matlab.ui.control.CheckBox
        SaveClassmapCheckBox        matlab.ui.control.CheckBox
        SaveThicknessmapCheckBox    matlab.ui.control.CheckBox
        SaveMaskCheckBox            matlab.ui.control.CheckBox
        FlipLeftRightCheckBox       matlab.ui.control.CheckBox
        ColorbarCheckBox            matlab.ui.control.CheckBox
        TitleCheckBox               matlab.ui.control.CheckBox
        CreateSubfolderCheckBox     matlab.ui.control.CheckBox
        CustStrEditField            matlab.ui.control.EditField
        CustStrEditFieldLabel       matlab.ui.control.Label
        FlipUpDownCheckBox          matlab.ui.control.CheckBox
        SelectButton                matlab.ui.control.Button
        PathEditField               matlab.ui.control.EditField
        PathEditFieldLabel          matlab.ui.control.Label
        SaveXYCorordinatesCheckBox  matlab.ui.control.CheckBox
        EditField_2                 matlab.ui.control.NumericEditField
        ReplaceInfwithCheckBox      matlab.ui.control.CheckBox
        EditField                   matlab.ui.control.NumericEditField
        ReplaceNaNwithCheckBox      matlab.ui.control.CheckBox
        ExportButton                matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        DataTreecheckbox            matlab.ui.container.CheckBoxTree
        MapPlot                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecMaps % Description
        sampleinfo
        TreeData_Main

    end

    methods (Access = private)

       
        function UpdateTree_Multi(app)
            a = app.DataTreecheckbox.Children;
            a.delete;

            nodes=app.TreeData_Main.Children;
            NodeNames={app.TreeData_Main.Children.Text};
            NodeData={app.TreeData_Main.Children.NodeData};

            for m=1:numel(NodeNames)
                nd_m=NodeData{m}(2);
                p = uitreenode(app.DataTreecheckbox,'Text',char(NodeNames{m}),'NodeData',[nd_m 1 1 1]);

                if numel(nodes(m).Children)>0
                    subnodes=nodes(m).Children;
                    subnodesNames={subnodes.Text};
                    subnodesNodeData={subnodes.NodeData};

                    for n=1:numel(subnodes)
                    snd_n=subnodesNodeData{n}(3);


                        p1 = uitreenode(p,'Text',char(subnodesNames{n}),'NodeData',[nd_m snd_n 1 1]);
                        if numel(subnodes(n).Children)>0
                            subsubnodes=subnodes(n).Children;
                            subsubnodesNames={subsubnodes.Text};
                            subsubnodesNodeData={subsubnodes.NodeData};

                            for l=1:numel(subsubnodes)
                                snd_l=subsubnodesNodeData{l}(4);

                                p2 = uitreenode(p1,'Text',char(subsubnodesNames{l}),'NodeData',[nd_m snd_n snd_l 1]);

                                if numel(subsubnodes(l).Children)>0
                                    subsubsubnodes=subsubnodes(l).Children;
                                    subsubsubnodesNames={subsubsubnodes.Text};
                                    subsubsubnodesNodeData={subsubsubnodes.NodeData};
                                    for k=1:numel(subsubsubnodes)
                                        snd_k=subsubsubnodesNodeData{k}(4);


                                            if numel(subsubsubnodes(k).Children)>0
                                                p3 = uitreenode(p2,'Text',char(subsubsubnodesNames{k}),'NodeData',[nd_m snd_n snd_k 1]);

                                                subsubsubsubnodes=subsubsubnodes(k).Children;
                                                subsubsubsubnodesNames={subsubsubsubnodes.Text};
                                                subsubsubsubnodesNodeData={subsubsubsubnodes.NodeData};
                                                for r=1:numel(subsubsubsubnodes)
                                                    snd_r=subsubsubsubnodesNodeData{r}(5);
                                                    p4 = uitreenode(p3,'Text',char(subsubsubsubnodesNames{r}),'NodeData',[nd_m snd_n  snd_k snd_r]);
                                                end
                                            else

                                                p3 = uitreenode(p2,'Text',char(subsubsubnodesNames{k}),'NodeData',[nd_m snd_n snd_l snd_k]);

                                            end
                                    end 
                                end
                            end
                        end
                    end

                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleinfo, TreeData_Main, SampleNames)
            app.SpecMaps=mainapp;

            if isequal(sampleinfo,[0 0 0 0])
                sampleinfo=[1 1 1 1];
            end

           app.PathEditField.Value=pwd;
            app.sampleinfo=sampleinfo;
            app.TreeData_Main=TreeData_Main;
            UpdateTree_Multi(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: DataTreecheckbox
        function DataTreecheckboxSelectionChanged(app, event)
            
            selectedNodes = app.DataTreecheckbox.SelectedNodes;
            if not(isempty(selectedNodes)) && numel(selectedNodes.NodeData)>1
                app.sampleinfo=[1 1 1 1];
                app.sampleinfo(1:numel(selectedNodes.NodeData))=selectedNodes.NodeData(1:end);
                MapPlot_fun_public(app.SpecMaps,app.MapPlot,app.sampleinfo);
            end
            MatrixLimits=update_MatixLimits(app.SpecMaps,app.sampleinfo);

            xlim(app.MapPlot,MatrixLimits(1,:))
            ylim(app.MapPlot,MatrixLimits(2,:))
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir;
            delete(f)
            if not(isequal(path,0))
                app.PathEditField.Value=path;
                app.PathEditField.Tooltip=app.PathEditField.Value;
            end
                                    figure(app.ExportModuleUIFigure); 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %check if path is valid if not create folder
            path=app.PathEditField.Value;
            if not(exist(path,"dir"))
                mkdir(path)
            end

            PlotMap_UpdateColormap(app.SpecMaps)
            checkedNodes = app.DataTreecheckbox.CheckedNodes;
            data={checkedNodes.NodeData};
            VariableNames={checkedNodes.Text};
            vartest={checkedNodes.Children};
            vars2keep=cellfun(@isempty,vartest);
            VariableNames=VariableNames(vars2keep);
            data=data(vars2keep);

            if isempty(checkedNodes)
                return
            end

            DataStruct=getDataStruct(app.SpecMaps);

            if numel(DataStruct.Names)<1
                return
            end


            mat=reshape(cell2mat(data),4,[])';
            mat1=sum(mat.*[1000 100 10 1],2);


%             [mm, x1]=unique((mat1),'row','last');
%             [mm, x]=unique((mat),'row','last');

%             mat=mat(x,:);
%             VariableNames=VariableNames(x);
%             data=data(x);

            [n,idx]=sort(sum(mat.*[1000 100 10 1],2));
            VariableNames=VariableNames(idx);
            data=data(idx);
            mat=mat(idx,:);

            % classmaps if new sample not subsample or subsubsample get all the classmaps plot them and save in classmap folder
            % plus save classmaps to figure 1) with mask 2) without mask
            % save thicknessmap 
            % save optical images 
            % save mask image and if matrix save mask matrix to file 
            
d = uiprogressdlg(app.ExportModuleUIFigure,'Title','Export',...
                'Message','Automatic Export Started','Cancelable','on');
            for n=1:size(mat,1)

                 d.Value = n/size(mat,1);
                    if d.CancelRequested
                        break
                    end


                subpath='';
                thx2=mat(n,:);
                if app.CreateSubfolderCheckBox.Value==true
                    %   if is submap
                    sn=app.SpecMaps.SpecMap.Spectra.Names{thx2(1)};
                    ssn=app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Names{thx2(2)};

                    if isempty(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).MapNames)
                        mn='';
                        smn='';
                    else
                        mn=app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).MapNames{thx2(3)};

                        if isfield(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).Maps(thx2(3)),'SubMapNames') && not(isempty(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).Maps(thx2(3)).SubMapNames))
                            smn=app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).Maps(thx2(3)).SubMapNames{thx2(4)};
                        
                        else
                            smn='';
                        end
                    end
                   % subpath=fullfile(sn,ssn,mn,smn)
                    subpath=fullfile(sn,ssn,mn);

                    if not(exist(fullfile(path,subpath),"dir"))
                        mkdir(fullfile(path,subpath));
                    end
                end
                MatrixLimits=update_MatixLimits(app.SpecMaps,thx2);


                try
                    thx=MapPlot_fun_public(app.SpecMaps,0,thx2);
                end

                %

                if app.ReplaceNaNwithCheckBox.Value==true
                    thx(isnan(thx))=app.EditField.Value;
                end
                if app.ReplaceInfwithCheckBox.Value==true
                    thx(thx==inf)=app.EditField_2.Value;
                end
                if app.FlipUpDownCheckBox.Value==true
                    thx=flipud(thx);
                end
                 if app.FlipLeftRightCheckBox.Value==true
                    thx=fliplr(thx);
                 end

                 if strcmp(app.CustStrEditField.Value,'')
                     c_str='';
                 else
                     c_str=['_' app.CustStrEditField.Value];
                 end

                 FileName=fullfile(path,subpath,[matlab.lang.makeValidName([VariableNames{n} c_str])]);
                 d.Message = sprintf(['Exporting Maps: ' num2str(n) '/'  num2str(size(mat,1)) '\n' FileName]);

                 if not(exist(fullfile(path,subpath),"dir"))
                     mkdir(fullfile(path,subpath));
                 end


                 if app.CheckBox_csv_exp.Value==true
                     writematrix(thx,[FileName '.csv']);
                 end


                 %plot image without plotting

                        %# centimeters units
                        X = 42.0;                  %# A3 paper size
                        Y = 29.7;                  %# A3 paper size
                        xMargin = 1;               %# left/right margins from page borders
                        yMargin = 1;               %# bottom/top margins from page borders
                        xSize = X - 2*xMargin;     %# figure size on paper (widht & hieght)
                        ySize = Y - 2*yMargin;     %# figure size on paper (widht & hieght)

                        %# create figure/axis
                        hFig=figure('visible', 'off');
                        %# figure size displayed on screen (50% scaled, but same aspect ratio)


                        %# figure size printed on paper
                        set(hFig, 'PaperUnits','centimeters')
                        set(hFig, 'PaperSize',[X Y])
                        set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
                        set(hFig, 'PaperOrientation','portrait')
                        hFig.Position=hFig.Position.*2;
                        Axis2Plot=nexttile;
                        hold on
                        try
                            if app.SaveOpticalImagesCheckBox.Value==true
                                if isfield(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).MetaPrivat,'ImageGroup_Id')
                                    for n_images=1:numel(app.SpecMaps.SpecMap.Images.Images(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).MetaPrivat.ImageGroup_Id).Image)

                                        Image=app.SpecMaps.SpecMap.Images.Images(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).MetaPrivat.ImageGroup_Id).Image{n_images};
                                        ImageLimits=app.SpecMaps.SpecMap.Images.Images(app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).MetaPrivat.ImageGroup_Id).ImageLimits{n_images};
                                        image(flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));

                                        r=rectangle('Position',[MatrixLimits(1,1) MatrixLimits(2,1) (MatrixLimits(1,2)-MatrixLimits(1,1)) (MatrixLimits(2,2)-MatrixLimits(2,1))]);

                                        r.EdgeColor = 'b';
                                        r.LineWidth = 3;

                                    end
                                end
                            end
                        catch ME
                            ME
                           disp('Error in Expoort Module - ExportButtonPushedExportButtonPushed')                            
                        end

                        hFig.Visible=true;

                        %imagesc(flipud(thx),'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));
                        thx=MapPlot_fun_public(app.SpecMaps,Axis2Plot,thx2);

                        try
                            climval=[app.SpecMaps.SpecMap.Spectra.Sample(thx2(1)).Data(thx2(2)).Maps(thx2(3)).SubMap(thx2(4)).climits];
                            if not(isempty(climval))
                                caxis(climval)
                            end

                        catch
                            climval=[];
                        end

                        if app.ColorbarCheckBox.Value==true
                           cbar=colorbar(Axis2Plot);

                        end
                        colormap(app.SpecMaps.ColorMapValues);

                        if app.TitleCheckBox.Value==true
                            title(VariableNames{n},'interpreter', 'none');
                        end

                        if app.ScalebarCheckBox.Value==true
                        %% add scale
                       
                        try
                            SizeMap=MatrixLimits(:,2)-MatrixLimits(:,1);


                            thx=[min(MatrixLimits')+SizeMap'*0.1; max(MatrixLimits')-SizeMap'*0.4];
                            X=thx(1,1);
                            Y=thx(1,2);
                            a = 0;
                            c = [SizeMap(1)/10];
                            test = sort([logspace(1,10,10) logspace(1,10,10)*5 ])   ;
                            for k=flip(1:numel(test))
                                b=test(k);
                                E = max(a:b:c);
                                if E>0
                                    break
                                end
                            end

                               thx=[min(MatrixLimits')+SizeMap'*0.3; min(MatrixLimits')-SizeMap'*0.05];
                    X=thx(1,1);
                    Y=min(thx(:,2));
                    %                 X=min(MatrixLimits(1,:))+;%min((Axis2Plot.XLim));
                    %                 Y=min(MatrixLimits(2,:))*0.95;%min((Axis2Plot.XLim));

                    hEb=errorbar(Axis2Plot,X',Y',E,'horizontal','b.', 'linewidth',3);  % add scales
                    hTxt=text(Axis2Plot,X,Y,[num2str(E*2) ' μm'], ...
                        'horizontalalignment','center','verticalalignment','top','fontsize',20);
                    hEb.Color='k';
                 
                    hEb.Color='r';

                    hTxt.Color='r'; %ath settings
                    hTxt.Extent;


                    Axis2Plot.YTickLabel=[];
                    Axis2Plot.YTick=[];
                    Axis2Plot.XTickLabel=[];
                    Axis2Plot.XTick=[];
                    yl=  [min([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)]) max([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)])];
                    xl=  [min([Axis2Plot.XLim hTxt.Extent(1)]) max([Axis2Plot.XLim hTxt.Extent(1) hTxt.Extent(1)+hTxt.Extent(3)] ) ];
                    ylim(Axis2Plot,yl)
                    xlim(Axis2Plot,xl)
                        catch ME
                            ME
                        end

                        %% scale end
                        end

                        if app.CheckBox_fig_exp.Value==true
                            set(hFig, 'CreateFcn', 'set(gcbo,''Visible'',''on'')');
                            savefig(hFig,[FileName '.fig'])
                        end
                        if app.CheckBox_eps_exp.Value==true
                            exportgraphics(hFig,[FileName '.eps'],'BackgroundColor','none','ContentType','vector');

                        end
                        if app.CheckBox_png_exp.Value==true
                            exportgraphics(hFig,[FileName '.png'],Resolution=300);
                        end
                        if app.CheckBox_pdf_exp.Value==true
                            exportgraphics(hFig,[FileName '.pdf']);
                        end


                        if app.SaveXYCorordinatesCheckBox.Value==true
                            FileNameXY=fullfile(path,subpath,[ VariableNames{n} '_XY.txt' ]);
                            MatrixLimits=update_MatixLimits(app.SpecMaps,thx2);
                            writematrix(MatrixLimits,FileNameXY);
                        end

                        disp(['Exported ' FileName])
                        if exist('hFig','var')
                            close(hFig)
                        end

            end
            disp('Export finished')
                        figure(app.ExportModuleUIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportModuleUIFigure = uifigure('Visible', 'off');
            app.ExportModuleUIFigure.Name = 'ExportModule';
            app.GridLayout = uigridlayout(app.ExportModuleUIFigure);
            app.MapPlot = uiaxes(app.GridLayout);
            zlabel(app.MapPlot, 'Sample')
            app.DataTreecheckbox = uitree(app.GridLayout, 'checkbox');
            app.DataTreecheckbox.SelectionChangedFcn = createCallbackFcn(app, @DataTreecheckboxSelectionChanged, true);
            app.DataTreecheckbox.Editable = 'on';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ReplaceNaNwithCheckBox = uicheckbox(app.GridLayout);
            app.ReplaceNaNwithCheckBox.Text = 'Replace NaN with';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.ReplaceInfwithCheckBox = uicheckbox(app.GridLayout);
            app.ReplaceInfwithCheckBox.Text = 'Replace Inf with ';
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.SaveXYCorordinatesCheckBox = uicheckbox(app.GridLayout);
            app.SaveXYCorordinatesCheckBox.Text = 'Save XY Corordinates';
            app.PathEditFieldLabel = uilabel(app.GridLayout);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.GridLayout, 'text');
            app.PathEditField.Tooltip = {''};
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.FlipUpDownCheckBox = uicheckbox(app.GridLayout);
            app.FlipUpDownCheckBox.Text = 'Flip Up Down';
            app.CustStrEditFieldLabel = uilabel(app.GridLayout);
            app.CustStrEditFieldLabel.Text = 'CustStr';
            app.CustStrEditField = uieditfield(app.GridLayout, 'text');
            app.CustStrEditField.Tooltip = {''};
            app.CreateSubfolderCheckBox = uicheckbox(app.GridLayout);
            app.CreateSubfolderCheckBox.Text = 'Create Subfolder';
            app.CreateSubfolderCheckBox.Value = true;
            app.TitleCheckBox = uicheckbox(app.GridLayout);
            app.TitleCheckBox.Text = 'Title';
            app.TitleCheckBox.Value = true;
            app.ColorbarCheckBox = uicheckbox(app.GridLayout);
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.ColorbarCheckBox.Value = true;
            app.FlipLeftRightCheckBox = uicheckbox(app.GridLayout);
            app.FlipLeftRightCheckBox.Text = 'Flip Left Right';
            app.SaveMaskCheckBox = uicheckbox(app.GridLayout);
            app.SaveMaskCheckBox.Visible = 'off';
            app.SaveMaskCheckBox.Text = 'Save Mask';
            app.SaveMaskCheckBox.Value = true;
            app.SaveThicknessmapCheckBox = uicheckbox(app.GridLayout);
            app.SaveThicknessmapCheckBox.Visible = 'off';
            app.SaveThicknessmapCheckBox.Text = 'Save Thicknessmap';
            app.SaveThicknessmapCheckBox.Value = true;
            app.SaveClassmapCheckBox = uicheckbox(app.GridLayout);
            app.SaveClassmapCheckBox.Visible = 'off';
            app.SaveClassmapCheckBox.Text = 'Save Classmap';
            app.SaveClassmapCheckBox.Value = true;
            app.SaveOpticalImagesCheckBox = uicheckbox(app.GridLayout);
            app.SaveOpticalImagesCheckBox.Text = 'Save Optical Images';
            app.SaveOpticalImagesCheckBox.Value = true;
            app.ScalebarCheckBox = uicheckbox(app.GridLayout);
            app.ScalebarCheckBox.Text = 'Scalebar';
            app.CheckBox_fig_exp = uicheckbox(app.GridLayout);
            app.CheckBox_fig_exp.Text = '.fig';
            app.CheckBox_png_exp = uicheckbox(app.GridLayout);
            app.CheckBox_png_exp.Text = '.png';
            app.CheckBox_png_exp.Value = true;
            app.CheckBox_pdf_exp = uicheckbox(app.GridLayout);
            app.CheckBox_pdf_exp.Text = '.pdf';
            app.CheckBox_eps_exp = uicheckbox(app.GridLayout);
            app.CheckBox_eps_exp.Text = '.eps';
            app.CheckBox_eps_exp.Value = true;
            app.CheckBox_csv_exp = uicheckbox(app.GridLayout);
            app.CheckBox_csv_exp.Text = '.csv';
            app.CheckBox_tif_exp = uicheckbox(app.GridLayout);
            app.CheckBox_tif_exp.Text = '.tif';
            app.CheckBox_mat_exp = uicheckbox(app.GridLayout);
            app.CheckBox_mat_exp.Text = '.mat';
            % Show the figure after all components are created
            app.ExportModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportModuleUIFigure)
        end
    end
end