classdef IntegrationSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntegrationSettingUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        xlimMin_p10                     matlab.ui.control.Button
        xlimMin_p1                      matlab.ui.control.Button
        xlimMin_m1                      matlab.ui.control.Button
        xlimMin_m10                     matlab.ui.control.Button
        xlimMax_m10                     matlab.ui.control.Button
        xlimMax_m1                      matlab.ui.control.Button
        xlimMax_p10                     matlab.ui.control.Button
        xlimMax_p1                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        MultiCalcButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        ApplyCalibrationFactorCheckBox  matlab.ui.control.CheckBox
        CloseafterCalculationCheckBox   matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        ColumnNameEditField             matlab.ui.control.EditField
        ColumnNameEditFieldLabel        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Int_range_min                   matlab.ui.control.NumericEditField
        Int_range_max                   matlab.ui.control.NumericEditField
        IntRangeLabel                   matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Image_8                         matlab.ui.control.Image
        HButton                         matlab.ui.control.RadioButton
        Image_7                         matlab.ui.control.Image
        GButton                         matlab.ui.control.RadioButton
        Image_6                         matlab.ui.control.Image
        FButton                         matlab.ui.control.RadioButton
        Image_5                         matlab.ui.control.Image
        EButton                         matlab.ui.control.RadioButton
        Image_4                         matlab.ui.control.Image
        DButton                         matlab.ui.control.RadioButton
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        CButton                         matlab.ui.control.RadioButton
        BButton                         matlab.ui.control.RadioButton
        AButton                         matlab.ui.control.RadioButton
        EditField_xlimMax               matlab.ui.control.NumericEditField
        EditField_xlimMin               matlab.ui.control.NumericEditField
        CalculateAllSpectraCheckBox     matlab.ui.control.CheckBox
        FigXlimLive                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecDB % Description
        hVerticalLines
        hLineToDrag
        clickbuffer=0.05
        x_signal
        y_signal
        settings_return
        nlines=2
        int_range
        xlimits
    end

    methods (Access = private)

        function update_str(app)
            if strcmp(app.SpecDB.FigXlimLive.XDir,'normal')
                val1=app.Int_range_min.Value;
                val2=app.Int_range_max.Value;
                                val3=val2;

            else
                val1=app.Int_range_max.Value;
                val2=app.Int_range_min.Value;
                                                val3=val1;

            end

            if app.AButton.Value==true
                ABC='A';
                app.ColumnNameEditField.Value=['IntAbs_cm_' num2str(round(val1)) '_' num2str(round(val2)) '_' ABC];

            elseif app.BButton.Value==true
                ABC='B';
                app.ColumnNameEditField.Value=['IntAbs_cm_' num2str(round(val1)) '_' num2str(round(val2)) '_' ABC];

            elseif app.CButton.Value==true
                ABC='C';
                app.ColumnNameEditField.Value=['IntAbs_cm_' num2str(round(val1)) '_' num2str(round(val2)) '_' ABC];
            elseif app.DButton.Value==true
                ABC='D';
                app.ColumnNameEditField.Value=['LinAbs_cm_' num2str(round(val3)) '_' ABC];
            elseif app.EButton.Value==true
                ABC='E';
                app.ColumnNameEditField.Value=['LinAbs_cm_' num2str(round(val3)) '_' ABC];
            elseif app.FButton.Value==true
                ABC='F';
                app.ColumnNameEditField.Value=['LinAbs_Norm_' num2str(round(val3)) '_' ABC];
        elseif app.GButton.Value==true
                ABC='G';
                app.ColumnNameEditField.Value=['Ratio_' num2str(round(val1)) '_' num2str(round(val2))  '_' ABC];
        elseif app.HButton.Value==true
                ABC='H';
                app.ColumnNameEditField.Value=['Ratio_' num2str(round(val1)) '_' num2str(round(val2))  '_' ABC];
            end
            if app.ApplyCalibrationFactorCheckBox.Value==true
                app.ColumnNameEditField.Value=[app.ColumnNameEditField.Value '_CalF_' num2str(app.EditField.Value)];
            end


            app.settings_return.ABC=ABC;
            app.settings_return.Int_range=[app.Int_range_min.Value app.Int_range_max.Value];
        end


        function update_lines(app)

            if app.nlines==1
                app.hVerticalLines(1) = xline(app.FigXlimLive,app.Int_range_max.Value,'Color','red',"LineWidth",3);
                set(app.hVerticalLines(1), 'hittest', 'off')
            else
                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
                set(app.hVerticalLines(1:2), 'hittest', 'off')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x_signal, y_signal, xlimits, int_range)
            app.SpecDB=mainapp;
            app.x_signal=x_signal;
            app.y_signal=y_signal;
            app.xlimits=xlimits;
            app.int_range=int_range;
            if size(y_signal,2)>100
                plot(app.FigXlimLive,x_signal,y_signal(:,randi(size(y_signal,2),100,1)),LineWidth=2)
            else
                plot(app.FigXlimLive,x_signal,y_signal,LineWidth=2)
            end
            app.Int_range_max.Value=max(int_range);
            app.EditField_xlimMax.Value=max(xlimits);
            app.Int_range_min.Value=min(int_range);
            app.EditField_xlimMin.Value=min(xlimits);
            Int_range_maxValueChanged(app)
            EditField_xlimMaxValueChanged(app)

            if strcmp(app.SpecDB.XDirSwitch.Value,'XDir normal')
                app.FigXlimLive.XDir='normal';

                swap_int_max=app.Int_range_max.Layout;
                swap_int_min=app.Int_range_min.Layout;
                app.Int_range_max.Layout=swap_int_min;
                app.Int_range_min.Layout=swap_int_max;

                swap_lim_min=app.EditField_xlimMin.Layout;
                swap_lim_max=app.EditField_xlimMax.Layout;
                app.EditField_xlimMin.Layout=swap_lim_max;
                app.EditField_xlimMax.Layout=swap_lim_min;

                swap_p1_max =app.xlimMax_p1.Layout;
                swap_m1_min=app.xlimMin_m1.Layout;
                app.xlimMin_m1.Layout=swap_p1_max;
                app.xlimMax_p1.Layout=swap_m1_min;

                swap_p10_max =app.xlimMax_p10.Layout;
                swap_m10_min=app.xlimMin_m10.Layout;
                app.xlimMin_m10.Layout=swap_p10_max;
                app.xlimMax_p10.Layout=swap_m10_min;

                swap_m1_max =app.xlimMax_m1.Layout;
                swap_p1_min =app.xlimMin_p1.Layout;
                app.xlimMax_m1.Layout=swap_p1_min;
                app.xlimMin_p1.Layout=swap_m1_max;

                swap_m10_max =app.xlimMax_m10.Layout;
                swap_p10_min =app.xlimMin_p10.Layout;
                app.xlimMax_m10.Layout=swap_p10_min;
                app.xlimMin_p10.Layout=swap_m10_max;

            end
            update_str(app)


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_xlimMaxValueChanged(app, event)
            app.FigXlimLive.XLim=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            update_str(app)

            if app.EButton.Value==true || app.DButton.Value==true
                app.nlines= 1;
                min_state=false;
                app.IntRangeLabel.Text = 'Linear Value';
                app.MultiCalcButton.Visible=true;
                app.Label.Text='-';

            elseif app.FButton.Value==true
                app.nlines= 1;
                min_state=false;
                app.IntRangeLabel.Text = 'Linear Value Norm';
                app.MultiCalcButton.Visible=true;
                app.Label.Text='-';
            elseif app.GButton.Value==true
                app.nlines=2;
                min_state=true;
                app.IntRangeLabel.Text = 'Ratio';
                app.MultiCalcButton.Visible=true;
                app.Label.Text='/';
            else
                app.nlines=2;
                min_state=true;
                app.IntRangeLabel.Text = 'Int. Range';
                app.MultiCalcButton.Visible=false;
                app.Label.Text='-';
            end
            cla(app.FigXlimLive)
            if size(app.y_signal,2)>100
                plot(app.FigXlimLive,app.x_signal,app.y_signal(:,randi(size(app.y_signal,2),100,1)),LineWidth=2)
            else
                plot(app.FigXlimLive,app.x_signal,app.y_signal,LineWidth=2)
            end
            app.Int_range_min.Visible=min_state;
            app.Label.Visible=min_state;
            update_lines(app)
        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            for k=1:app.nlines
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01; %ATH Settingspanel
                xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                end
            end
        end

        % Window button motion function: IntegrationSettingUIFigure
        function IntegrationSettingUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)
                currentPoint   = app.FigXlimLive.CurrentPoint;
                x            = currentPoint(1,1);
                %y            = currentPoint(1,3);
                xlim = app.FigXlimLive.XLim;
                %ylim = app.FigXlimLive.YLim;
                inside_FigXlimLive = x >= xlim(1)-(xlim(1)*app.clickbuffer) & x <= xlim(2)+(xlim(2)*app.clickbuffer); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                if inside_FigXlimLive
                    set(app.hLineToDrag, 'Value', x);
                    if app.nlines==1
                        app.Int_range_max.Value = get(app.hVerticalLines(1),'Value') ;
                    else

                        xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                        app.Int_range_min.Value = min(xposlines);
                        app.Int_range_max.Value = max(xposlines);
                    end
                    update_str(app)

                else
                    IntegrationSettingUIFigureWindowButtonUp(app)
                end
            end
        end

        % Value changed function: Int_range_max, Int_range_min
        function Int_range_maxValueChanged(app, event)
            cla(app.FigXlimLive)
            if size(app.y_signal,2)>100
                plot(app.FigXlimLive,app.x_signal,app.y_signal(:,randi(size(app.y_signal,2),100,1)),LineWidth=2)
            else
                plot(app.FigXlimLive,app.x_signal,app.y_signal,LineWidth=2)
            end
            update_str(app)
            update_lines(app)
        end

        % Window button up function: IntegrationSettingUIFigure
        function IntegrationSettingUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];
                if app.nlines==1
                    set(app.hVerticalLines(1),'Value',app.Int_range_max.Value)
                else
                    set(app.hVerticalLines(1),'Value',app.Int_range_min.Value)
                    set(app.hVerticalLines(2),'Value',app.Int_range_max.Value)
                end
                update_str(app)
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.settings_return.CalcAllSpecs=app.CalculateAllSpectraCheckBox.Value;
            app.settings_return.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            app.settings_return.fn_str=app.ColumnNameEditField.Value;
            app.settings_return.nlines=app.nlines;

            if app.ApplyCalibrationFactorCheckBox.Value==false
                app.settings_return.CalibrationFactor=1;
            else
                app.settings_return.CalibrationFactor=app.EditField.Value;
            end

            d = uiprogressdlg(app.IntegrationSettingUIFigure,'Title','Please Wait',...
                'Message','Calculation in progress');
 
            answer=app.SpecDB.CalcIntAbs_cm(app.settings_return);
            if  answer==true && app.CloseafterCalculationCheckBox.Value==true
                delete(app)
            else
                close(d)
                update_str(app)
            end
        end

        % Value changed function: ApplyCalibrationFactorCheckBox
        function ApplyCalibrationFactorCheckBoxValueChanged(app, event)
            value = app.ApplyCalibrationFactorCheckBox.Value;
            app.EditField.Visible=value;
            update_str(app)

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            update_str(app)
        end

        % Button pushed function: MultiCalcButton
        function MultiCalcButtonPushed(app, event)

            x = inputdlg('Enter bands to extract:',...
                'Enter Bands', [1 50]);

            cstate=app.CloseafterCalculationCheckBox.Value;
            app.CloseafterCalculationCheckBox.Value=false;
            Int_range_maxValueChanged(app)

            if ~isempty(x)
                x=unique(str2num(x{1})); %#ok<ST2NM>
                for n=1:numel(x)
                    if n==numel(x)
                        app.CloseafterCalculationCheckBox.Value=cstate;
                    end
                    app.Int_range_max.Value=x(n);
                    Int_range_maxValueChanged(app)
                    CalculateButtonPushed(app)

                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.FigXlimLive)
            if size(app.y_signal,2)>100
                plot(app.FigXlimLive,app.x_signal,app.y_signal(:,randi(size(app.y_signal,2),100,1)),LineWidth=2)
            else
                plot(app.FigXlimLive,app.x_signal,app.y_signal,LineWidth=2)
            end
            app.Int_range_max.Value=max(app.int_range);
            app.EditField_xlimMax.Value=max(app.xlimits);
            app.Int_range_min.Value=min(app.int_range);
            app.EditField_xlimMin.Value=min(app.xlimits);
            Int_range_maxValueChanged(app)
            EditField_xlimMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+10;
            EditField_xlimMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)
             app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+1;
            EditField_xlimMaxValueChanged(app)
        
        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-1;
            EditField_xlimMaxValueChanged(app)
     
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)
            
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-10;
            EditField_xlimMaxValueChanged(app)
        
        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)
                        app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-10;
            EditField_xlimMaxValueChanged(app)
       
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-1;
            EditField_xlimMaxValueChanged(app)          
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+1;
            EditField_xlimMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+10;
            EditField_xlimMaxValueChanged(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntegrationSettingUIFigure = uifigure('Visible', 'off');
            app.IntegrationSettingUIFigure.Name = 'IntegrationSetting';
            app.IntegrationSettingUIFigure.Icon = 'symbols_1_Integration A.png';
            app.IntegrationSettingUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @IntegrationSettingUIFigureWindowButtonUp, true);
            app.IntegrationSettingUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @IntegrationSettingUIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.IntegrationSettingUIFigure);
            app.FigXlimLive = uiaxes(app.GridLayout);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.CalculateAllSpectraCheckBox = uicheckbox(app.GridLayout);
            app.CalculateAllSpectraCheckBox.Text = 'Calculate All Spectra';
            app.CalculateAllSpectraCheckBox.Value = true;
            app.EditField_xlimMin = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AButton = uiradiobutton(app.ButtonGroup);
            app.AButton.Text = 'A';
            app.BButton = uiradiobutton(app.ButtonGroup);
            app.BButton.Text = 'B';
            app.BButton.Value = true;
            app.CButton = uiradiobutton(app.ButtonGroup);
            app.CButton.Text = 'C';
            app.Image = uiimage(app.ButtonGroup);
            app.Image.ImageSource = 'symbols_1_Integration A.png';
            app.Image_2 = uiimage(app.ButtonGroup);
            app.Image_2.ImageSource = 'symbols_1_Integration B.png';
            app.Image_3 = uiimage(app.ButtonGroup);
            app.Image_3.ImageSource = 'symbols_1_Integration C.png';
            app.DButton = uiradiobutton(app.ButtonGroup);
            app.DButton.Text = 'D';
            app.Image_4 = uiimage(app.ButtonGroup);
            app.Image_4.ImageSource = 'symbols_1_Lin A.png';
            app.EButton = uiradiobutton(app.ButtonGroup);
            app.EButton.Text = 'E';
            app.Image_5 = uiimage(app.ButtonGroup);
            app.Image_5.ImageSource = 'symbols_1_Lin C.png';
            app.FButton = uiradiobutton(app.ButtonGroup);
            app.FButton.Tooltip = {'Normalised to highest value in between XLimits'};
            app.FButton.Text = 'F';
            app.Image_6 = uiimage(app.ButtonGroup);
            app.Image_6.Tooltip = {'Normalised to highest value in between XLimits'};
            app.Image_6.ImageSource = 'symbols_1_Lin C.png';
            app.GButton = uiradiobutton(app.ButtonGroup);
            app.GButton.Tooltip = {'Normalised to highest value in between XLimits'};
            app.GButton.Text = 'G';
            app.Image_7 = uiimage(app.ButtonGroup);
            app.Image_7.Tooltip = {'Ratio of two X Values'};
            app.Image_7.ImageSource = 'symbols_1_Ratio G.png';
            app.HButton = uiradiobutton(app.ButtonGroup);
            app.HButton.Tooltip = {'Normalised to highest value in between XLimits'};
            app.HButton.Text = 'H';
            app.Image_8 = uiimage(app.ButtonGroup);
            app.Image_8.Tooltip = {'Ratio of two X Values'};
            app.Image_8.ImageSource = 'symbols_1_Ratio H.png';
            app.IntRangeLabel = uilabel(app.GridLayout);
            app.IntRangeLabel.Text = 'Int. Range';
            app.Int_range_max = uieditfield(app.GridLayout, 'numeric');
            app.Int_range_max.ValueChangedFcn = createCallbackFcn(app, @Int_range_maxValueChanged, true);
            app.Int_range_max.Value = 3720;
            app.Int_range_min = uieditfield(app.GridLayout, 'numeric');
            app.Int_range_min.ValueChangedFcn = createCallbackFcn(app, @Int_range_maxValueChanged, true);
            app.Int_range_min.Value = 3050;
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '-';
            app.ColumnNameEditFieldLabel = uilabel(app.GridLayout);
            app.ColumnNameEditFieldLabel.Text = 'Column Name';
            app.ColumnNameEditField = uieditfield(app.GridLayout, 'text');
            app.ColumnNameEditField.Value = 'IntAbs_cm_3720_3050_B';
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CloseafterCalculationCheckBox = uicheckbox(app.GridLayout);
            app.CloseafterCalculationCheckBox.Text = 'Close after Calculation';
            app.CloseafterCalculationCheckBox.Value = true;
            app.ApplyCalibrationFactorCheckBox = uicheckbox(app.GridLayout);
            app.ApplyCalibrationFactorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyCalibrationFactorCheckBoxValueChanged, true);
            app.ApplyCalibrationFactorCheckBox.Text = 'Apply Calibration Factor';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.MultiCalcButton = uibutton(app.GridLayout, 'push');
            app.MultiCalcButton.ButtonPushedFcn = createCallbackFcn(app, @MultiCalcButtonPushed, true);
            app.MultiCalcButton.Visible = 'off';
            app.MultiCalcButton.Text = 'Multi Calc';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.xlimMax_p1 = uibutton(app.GridLayout, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+ 1';
            app.xlimMax_p10 = uibutton(app.GridLayout, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+10';
            app.xlimMax_m1 = uibutton(app.GridLayout, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '- 1';
            app.xlimMax_m10 = uibutton(app.GridLayout, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-10';
            app.xlimMin_m10 = uibutton(app.GridLayout, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-10';
            app.xlimMin_m1 = uibutton(app.GridLayout, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '- 1';
            app.xlimMin_p1 = uibutton(app.GridLayout, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+ 1';
            app.xlimMin_p10 = uibutton(app.GridLayout, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+10';
            % Show the figure after all components are created
            app.IntegrationSettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegrationSetting(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IntegrationSettingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.IntegrationSettingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntegrationSettingUIFigure)
        end
    end
end