classdef MapCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapCalculatorUIFigure      matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        GridLayout_PlotSettings    matlab.ui.container.GridLayout
        InvertMask                 matlab.ui.control.Image
        UnmaskSelection            matlab.ui.control.Image
        ResetButton                matlab.ui.control.Button
        climMax_p10                matlab.ui.control.Button
        climMin_p10                matlab.ui.control.Button
        climMax_m10                matlab.ui.control.Button
        EditField_climMax          matlab.ui.control.NumericEditField
        climMin_m10                matlab.ui.control.Button
        EditField_climMin          matlab.ui.control.NumericEditField
        FigHistLive                matlab.ui.control.UIAxes
        GridLayout                 matlab.ui.container.GridLayout
        CalcMapNameEditField       matlab.ui.control.EditField
        CalcMapNameEditFieldLabel  matlab.ui.control.Label
        SampleDropDown             matlab.ui.control.DropDown
        SampleDropDownLabel        matlab.ui.control.Label
        CminEditField              matlab.ui.control.NumericEditField
        CmaxEditField              matlab.ui.control.NumericEditField
        Calculate_sel              matlab.ui.control.Button
        Calculate_all              matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        DevisionButton             matlab.ui.control.Button
        MultiplicationButton       matlab.ui.control.Button
        MinusButton                matlab.ui.control.Button
        PlusButton                 matlab.ui.control.Button
        DataTree                   matlab.ui.container.Tree
        AddButton                  matlab.ui.control.Button
        Calculate                  matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        MapPlot                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecMaps % Description
        sampleinfo
        TreeData_Main
    end

    methods (Access = public)

        function UpdateTree_Single(app,Tree,TreeData)

            if not(exist('Tree','var'))|| isempty(Tree)
                Tree=app.DataTree;
            end

            a = Tree.Children;
            a.delete;

            nodes=app.TreeData_Main.Children;
            NodeNames={app.TreeData_Main.Children.Text};
            NodeData={app.TreeData_Main.Children.NodeData};


            for m=app.sampleinfo(1)
                nd_m=NodeData{m}(2);
                % p = uitreenode(app.DataTree,'Text',char(NodeNames{m}),'NodeData',[nd_m 1 1 1]);

                if numel(nodes(m).Children)>0
                    subnodes=nodes(m).Children;
                    subnodesNames={subnodes.Text};
                    subnodesNodeData={subnodes.NodeData};

                    for n=1:numel(subnodes)
                        snd_n=subnodesNodeData{n}(3);
                        p1 = uitreenode(app.DataTree,'Text',char(subnodesNames{n}),'NodeData',[nd_m subnodesNodeData{n}(3) 1 1]);
                        if numel(subnodes(n).Children)>0
                            subsubnodes=subnodes(n).Children;
                            subsubnodesNames={subsubnodes.Text};
                            subsubnodesNodeData={subsubnodes.NodeData};

                            for l=1:numel(subsubnodes)
                                snd_l=subsubnodesNodeData{l}(4);

                                p2 = uitreenode(p1,'Text',char(subsubnodesNames{l}),'NodeData',[nd_m snd_n snd_l 1]);

                                if numel(subsubnodes(l).Children)>0
                                    subsubsubnodes=subsubnodes(l).Children;
                                    subsubsubnodesNames={subsubsubnodes.Text};
                                    subsubsubnodesNodeData={subsubsubnodes.NodeData};
                                    for k=1:numel(subsubsubnodes)
                                        snd_k=subsubsubnodesNodeData{k}(4);


                                        if numel(subsubsubnodes(k).Children)>0
                                            p3 = uitreenode(p2,'Text',char(subsubsubnodesNames{k}),'NodeData',[nd_m snd_n snd_k 1]);

                                            subsubsubsubnodes=subsubsubnodes(k).Children;
                                            subsubsubsubnodesNames={subsubsubsubnodes.Text};
                                            subsubsubsubnodesNodeData={subsubsubsubnodes.NodeData};
                                            for r=1:numel(subsubsubsubnodes)
                                                snd_r=subsubsubsubnodesNodeData{r}(5);
                                                p4 = uitreenode(p3,'Text',char(subsubsubsubnodesNames{r}),'NodeData',[nd_m snd_n  snd_k snd_r]);
                                            end
                                        else
                                            p3 = uitreenode(p2,'Text',char(subsubsubnodesNames{k}),'NodeData',[nd_m snd_n subsubsubnodesNodeData{k}(4:5)]);
                                        end
                                    end
                                end
                            end
                        end
                    end

                end
            end


        end

    end


    methods (Access = private)

        function matrix=CalculateMatrix_Fun(app,active_Sample,plotstate)

            str_ini=app.TextArea.Value;
            str2calc=str_ini;
            spltstr = split(string(str_ini),"'");
            map_idx=find(contains(spltstr,'['));
            if plotstate==true
                figure
            end
%             thx_maps_to_variables=struct();
%             str2calc_vardef='';
%             str2calc2=str_ini;
%             for n=1:numel(map_idx)
% 
%                 spltstr_2=replace(split(spltstr(map_idx(n)),']'),{'['},'');
%                 index=str2num(replace(split(spltstr_2(1),']'),{'_'},' '));
%                 %index(1)=active_Sample;
%                 %                 test={}
%                 %                 for k=1:numel(app.DataTree.Children)
%                 %                     test=[test; {app.DataTree.Children(k).NodeData}']
%                 %                     for kk=1:numel(app.DataTree.Children(k).Children)
%                 %                         test=[test;{app.DataTree.Children(k).Children(kk).NodeData}'];
%                 %
%                 %                         for kkk=1:numel(app.DataTree.Children(k).Children(kk).Children)
%                 %                             test=[test; {app.DataTree.Children(k).Children(kk).Children(kkk).NodeData}']
%                 % %                             for kkkk=1:numel(app.DataTree.Children(k).Children(kk).Children(kkk))
%                 % %                                 test=[test; {app.DataTree.Children(k).Children(kk).Children(kkk).Children.NodeData}']
%                 % %
%                 % %                             end
%                 %                         end
%                 %                     end
%                 %                 end
%                 %                 if isempty(0)
%                 %                     treeID=find(cellfun(@(c) isequal(c, index), {app.DataTree.Children.Children(:).NodeData}'));
%                 %                 end
%                 %                 str2Replace=char(join(["'" "[",join(string(app.DataTree.Children.Children(treeID).NodeData),"_"),"] " string(app.DataTree.Children.Children(treeID).Text) "'"],''));
%                 spltstr_3= [ "[" spltstr_2(1) "]" spltstr_2(2)];
%                 str2Replace= ['''' char(join(spltstr_3,'')) ''''];
%                 thx_maps_to_variables(n).matrix=['app.SpecMaps.SpecMap.Spectra.Sample(' num2str(active_Sample) ').Data(' num2str(index(2)) ').Maps(' num2str(index(3)) ').SubMap(' num2str(index(4)) ').MapPlotMatrix'];
%                 thx_maps_to_variables(n).str_in_textbox=str2Replace;
%                 thx_maps_to_variables(n).NewVariableName=['MtC_xirtam_Bl0B_' num2str(n,'%03.f')];% complicated variable name to have a low chance of the user entering the same string
% 
%                 str2calc_vardef=[str2calc_vardef,'MtC_xirtam_Bl0B_' num2str(n,'%03.f'), '=app.SpecMaps.SpecMap.Spectra.Sample(' num2str(active_Sample) ').Data(' num2str(index(2)) ').Maps(' num2str(index(3)) ').SubMap(' num2str(index(4)) ').MapPlotMatrix;'];
%                 str2calc2=replace(str2calc2,str2Replace,thx_maps_to_variables(n).NewVariableName);
% 
% 
%                 str2calc=replace(str2calc,str2Replace,['app.SpecMaps.SpecMap.Spectra.Sample(' num2str(active_Sample) ').Data(' num2str(index(2)) ').Maps(' num2str(index(3)) ').SubMap(' num2str(index(4)) ').MapPlotMatrix']);
%                 if plotstate==true
%                     Axis2Plot=nexttile;
%                     MapPlot_fun_public(app.SpecMaps,Axis2Plot,index,true,false); % used in CorelationModule
%                     title(str2Replace,'Interpreter','none')
% 
%                 end
% 
%             end

            % str=[str2calc_vardef;str2calc2]

            try
                %% new code using str2func
                str2calc_cell=str_ini;
                x=cell(1,numel(map_idx));
                for n=1:numel(map_idx)
                    spltstr_2=replace(split(spltstr(map_idx(n)),']'),{'['},'');
                    index=str2num(replace(split(spltstr_2(1),']'),{'_'},' '));
                    spltstr_3= [ "[" spltstr_2(1) "]" spltstr_2(2)];
                    str2Replace= ['''' char(join(spltstr_3,'')) ''''];

                    x{n}=app.SpecMaps.SpecMap.Spectra.Sample(active_Sample).Data(index(2)).Maps(index(3)).SubMap(index(4)).MapPlotMatrix;
                    str2Replace_cell=char(['x{',num2str(n),'}']);
                    str2calc_cell=replace(str2calc_cell,str2Replace,str2Replace_cell);
                end

                f=str2func(['@(x)', str2calc_cell{:}]);
                matrix=f(x);

                %%old code using eval
%                 matrix2=eval(str2calc{:});
%                 %matrix2=eval(str{:});
%                 message=num2str(isequal(matrix,matrix2));
%                 uialert(app.MapCalculatorUIFigure,message,'Error');

            catch ME
                disp('Error CalculateMatrix_Fun in MapCalculator')
                disp(ME)
                message=ME.message;
                uialert(app.MapCalculatorUIFigure,message,'Error');
                disp(message)
            end
            if plotstate==true

                Axis2Plot=nexttile;

                MapPlot_fun_public(app.SpecMaps,Axis2Plot,index,false,false,matrix); % used in CorelationModule
                title(str_ini,'Interpreter','none')

                Axis2Plot=nexttile;


                MapPlot_fun_public(app.SpecMaps,Axis2Plot,index,true,false,matrix); % used in CorelationModule
                title([str_ini 'thickness corrected'],'Interpreter','none')

            end
        end

        function  cfield_fun(app)

            c= (app.MapPlot.CLim);
            %c= round(app.MapPlot.CLim);

            caxis(app.MapPlot,c);
            app.CminEditField.Value=c(1);
            app.CmaxEditField.Value=c(2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleinfo, TreeData_Main, SampleNames)
            app.SpecMaps=mainapp;
            app.SampleDropDown.Items=SampleNames;
            if isequal(sampleinfo,[0 0 0 0])
                sampleinfo=[1 1 1 1];
            end
            app.SampleDropDown.Value=SampleNames(sampleinfo(1));
            app.sampleinfo=sampleinfo;
            app.TreeData_Main=TreeData_Main;

            UpdateTree_Single(app,app.DataTree,app.TreeData_Main)

            % ('insert map here') ./ (150*(1/3)*(3780-3400)) Paterson (1982) simplified
            % (('insert map here') ./ (246.6*(1/3)*(3753-3400)) Libowitzky and Rossman (1997)

            % Libowitzky and Rossman (1997)
            % (('insert map here')./((246.6*(1/3)*(3753-3400)))*10000 [μg/g H2O]
            %  ('insert map here')./((246.6*(1/3)*(3753-3400))) [wt% H2O]

            % Paterson (1982) simplified
            % (('insert map here')./(150*(1/3)*(3780-3400)))*10000 [μg/g H2O]
            %  ('insert map here')./(150*(1/3)*(3780-3400))[wt% H2O]

        end

        % Button pushed function: Calculate
        function CalculatePushed(app, event)
            active_Sample=app.sampleinfo(1);
            matrix= CalculateMatrix_Fun(app,active_Sample,true);
            caxis(app.MapPlot,'auto');


            MapPlot_fun_public(app.SpecMaps,app.MapPlot,[active_Sample 1 1 1],true,false,matrix); % used in CorelationModule
            cfield_fun(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            try

                if iscell(app.TextArea.Value) && isempty( app.TextArea.Value{:})
                    app.TextArea.Value= char(join(["'" "[",join(string(app.DataTree.SelectedNodes.NodeData),"_"),"] " string(app.DataTree.SelectedNodes.Text) "'"],''));
                elseif  iscell(app.TextArea.Value)
                    app.TextArea.Value=[app.TextArea.Value{:} ' ' char(join(["'" "[",join(string(app.DataTree.SelectedNodes.NodeData),"_"),"] " string(app.DataTree.SelectedNodes.Text) "'"],''))];
                end
            catch
                CalculatePushed(app)
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.sampleinfo(1)= find(ismember(app.SampleDropDown.Items,app.SampleDropDown.Value));
            UpdateTree_Single(app,app.DataTree,app.TreeData_Main)

        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            selectedNodes = app.DataTree.SelectedNodes;


            index=selectedNodes.NodeData;
            caxis(app.MapPlot,'auto');

            MapPlot_fun_public(app.SpecMaps,app.MapPlot,index,true,false); % used in CorelationModule
            cfield_fun(app)

        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' + '];
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' - '];
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' .* '];
        end

        % Button pushed function: DevisionButton
        function DevisionButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ./ '];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ( '];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ) '];
        end

        % Button pushed function: Calculate_sel
        function Calculate_selButtonPushed(app, event)
            if isempty(app.CalcMapNameEditField.Value)
                CalcMatrixName=app.TextArea.Value;
            else
                CalcMatrixName={app.CalcMapNameEditField.Value};
            end

            active_Sample=app.sampleinfo(1);
            matrix= CalculateMatrix_Fun(app,active_Sample,false);

            matrix_to_match(1).MapPlotMatrix=matrix;
            MapMatchReturn(app.SpecMaps,matrix_to_match,CalcMatrixName,app.sampleinfo,200)
            UpdateTree_Single(app,app.DataTree,app.TreeData_Main)

        end

        % Button pushed function: Calculate_all
        function Calculate_allButtonPushed(app, event)
            if isempty(app.CalcMapNameEditField.Value)
                CalcMatrixName=app.TextArea.Value;

                spltstr_2=split(replace(CalcMatrixName,"'",""),{'[',']'});
                spltstr_2=join(string(spltstr_2(3:2:end))','');
                CalcMatrixName=char(replace(spltstr_2,'  ',' '));
                if startsWith(spltstr_2,' ')
                    CalcMatrixName={CalcMatrixName(2:end)};
                else
                    CalcMatrixName={CalcMatrixName};
                end
            else
                CalcMatrixName={app.CalcMapNameEditField.Value};
            end

            d = uiprogressdlg(app.MapCalculatorUIFigure,'Title','Calculating Maps',...
                'Message','Automatic Map Calculation Started','Cancelable','on');
            drawnow

            for active_Sample=1:numel(app.SampleDropDown.Items)
                try
                    if d.CancelRequested
                        break
                    end
                    d.Value=active_Sample/numel(app.SampleDropDown.Items);
                    d.Message = sprintf(['Generating Maps: \nSample: ' app.SpecMaps.SpecMap.Spectra.Names{active_Sample} ' - ' CalcMatrixName{:}]);

                    matrix= CalculateMatrix_Fun(app,active_Sample,false);

                    matrix_to_match(1).MapPlotMatrix=matrix;
                    MapMatchReturn(app.SpecMaps,matrix_to_match,CalcMatrixName,[active_Sample 1 1 1],200)
                catch ME
                    disp('Error Calculate_allButtonPushed in MapCalculator')
                    disp(ME)
                    message=ME.message;
                    uialert(app.MapCalculatorUIFigure,message,'Error');
                    disp(message)
                end
            end
            UpdateTree_Single(app,app.DataTree,app.TreeData_Main)
            delete(d)

        end

        % Value changed function: CminEditField
        function CminEditFieldValueChanged(app, event)
            c= sort([app.CminEditField.Value,app.CmaxEditField.Value]);
            caxis(app.MapPlot,c);
            app.CminEditField.Value=c(1);
            app.CmaxEditField.Value=c(2);
        end

        % Value changed function: CmaxEditField
        function CmaxEditFieldValueChanged(app, event)
            c= sort([app.CminEditField.Value,app.CmaxEditField.Value]);
            caxis(app.MapPlot,c);
            app.CminEditField.Value=c(1);
            app.CmaxEditField.Value=c(2);
        end

        % Button down function: MapPlot
        function MapPlotButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapCalculatorUIFigure = uifigure('Visible', 'off');
            app.MapCalculatorUIFigure.Name = 'MapCalculator';
            app.GridLayout = uigridlayout(app.MapCalculatorUIFigure);
            app.MapPlot = uiaxes(app.GridLayout);
            app.MapPlot.XTick = [];
            app.MapPlot.YTick = [];
            app.MapPlot.ButtonDownFcn = createCallbackFcn(app, @MapPlotButtonDown, true);
            app.TextArea = uitextarea(app.GridLayout);
            app.Calculate = uibutton(app.GridLayout, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculatePushed, true);
            app.Calculate.Text = 'Check';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add >';
            app.DataTree = uitree(app.GridLayout);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.DataTree.Editable = 'on';
            app.DataTree.Tooltip = {''};
            app.PlusButton = uibutton(app.GridLayout, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.GridLayout, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.MultiplicationButton = uibutton(app.GridLayout, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'x';
            app.DevisionButton = uibutton(app.GridLayout, 'push');
            app.DevisionButton.ButtonPushedFcn = createCallbackFcn(app, @DevisionButtonPushed, true);
            app.DevisionButton.Text = ':';
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '(';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = ')';
            app.Calculate_all = uibutton(app.GridLayout, 'push');
            app.Calculate_all.ButtonPushedFcn = createCallbackFcn(app, @Calculate_allButtonPushed, true);
            app.Calculate_all.Text = 'Add for All';
            app.Calculate_sel = uibutton(app.GridLayout, 'push');
            app.Calculate_sel.ButtonPushedFcn = createCallbackFcn(app, @Calculate_selButtonPushed, true);
            app.Calculate_sel.Text = 'Add for Selected Sample';
            app.CmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.CmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CmaxEditFieldValueChanged, true);
            app.CminEditField = uieditfield(app.GridLayout, 'numeric');
            app.CminEditField.ValueChangedFcn = createCallbackFcn(app, @CminEditFieldValueChanged, true);
            app.SampleDropDownLabel = uilabel(app.GridLayout);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.GridLayout);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.CalcMapNameEditFieldLabel = uilabel(app.GridLayout);
            app.CalcMapNameEditFieldLabel.Text = 'CalcMapName';
            app.CalcMapNameEditField = uieditfield(app.GridLayout, 'text');
            app.Panel = uipanel(app.MapCalculatorUIFigure);
            app.GridLayout_PlotSettings = uigridlayout(app.Panel);
            app.FigHistLive = uiaxes(app.GridLayout_PlotSettings);
            app.EditField_climMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_climMin.ValueDisplayFormat = '%3.4g';
            app.climMin_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.climMin_m10.Text = '-10';
            app.EditField_climMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_climMax.ValueDisplayFormat = '%3.4g';
            app.EditField_climMax.Value = 1;
            app.climMax_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.climMax_m10.Text = '-10';
            app.climMin_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.climMin_p10.Text = '+10';
            app.climMax_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.climMax_p10.Text = '+10';
            app.ResetButton = uibutton(app.GridLayout_PlotSettings, 'push');
            app.ResetButton.Icon = 'symbols_1_Clear Mask.png';
            app.ResetButton.Text = '';
            app.UnmaskSelection = uiimage(app.GridLayout_PlotSettings);
            app.UnmaskSelection.ImageSource = 'symbols_1_Substract from Mask.png';
            app.InvertMask = uiimage(app.GridLayout_PlotSettings);
            app.InvertMask.ImageSource = 'symbols_1_Invert Mask.png';
            % Show the figure after all components are created
            app.MapCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapCalculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapCalculatorUIFigure)
        end
    end
end