classdef MapClassificationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassificationModuleUIFigure   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SortClassesDropDown            matlab.ui.control.DropDown
        SortClassesDropDownLabel       matlab.ui.control.Label
        IterationsSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        RandomSeedSpinnerLabel         matlab.ui.control.Label
        RandomSeedSpinner              matlab.ui.control.Spinner
        ClearAllButton                 matlab.ui.control.Button
        MaxClassesSpinner              matlab.ui.control.Spinner
        MaxClassesSpinnerLabel         matlab.ui.control.Label
        MinClassesSpinner              matlab.ui.control.Spinner
        MinClassesSpinnerLabel         matlab.ui.control.Label
        ClassTree                      matlab.ui.container.CheckBoxTree
        ClassifyButton                 matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ListBox                        matlab.ui.control.ListBox
        CLR_CheckBox                   matlab.ui.control.CheckBox
        CMatButton                     matlab.ui.control.Button
        Norm_CheckBox                  matlab.ui.control.CheckBox
        CancleButton                   matlab.ui.control.Button
        ReturnResultsButton            matlab.ui.control.Button
        DataMapPlot                    matlab.ui.control.UIAxes
        MapPlot                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        SelectAllMenu                  matlab.ui.container.Menu
        DeselectAllMenu                matlab.ui.container.Menu
        RemoveMenu                     matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        AddClassificationPointMenu     matlab.ui.container.Menu
        RemoveClassificationPointMenu  matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data
        MapNamesInput

        mainapp

        mapsize
        plotmat

        Classes
        ClassesNames
        MapNames

        CurvePos
        cmap

        ParentApp
        

    end

    methods (Access = private)

        function UpdateDataMapPlot(app)
            matrix=app.Data.maps(app.ListBox.Value(1)).matrix;

            imagesc(app.DataMapPlot,matrix, 'AlphaData', not(isnan(matrix)))
            axis(app.DataMapPlot,'equal')
            if not(isempty(app.cmap))
                app.DataMapPlot.Colormap=app.cmap;
            end
            xlim(app.DataMapPlot,'auto')
            ylim(app.DataMapPlot,'auto')
        end

        function update_map_ListBox(app)
        app.ListBox.Items=app.MapNamesInput;
        app.ListBox.ItemsData=1:numel(app.MapNamesInput);
        app.ListBox.Value=1;
        end

         function ResetTree(app)

            a = app.ClassTree.Children; 
            a.delete;
         end
  
        function UpdateTree(app)
            if isempty(app.ClassesNames)
                app.ClassTree.Visible=false;
                ResetTree(app)
            else
                ResetTree(app)


                %app.ClassesNames{mapnr}=['ClassMap' num2str(round(min(app.x_signal))) '_' num2str(round(max(app.x_signal)))];%%num2str(numberofclasses)

                for i=1:numel(app.ClassesNames)

                    p = uitreenode(app.ClassTree,'Text',char(app.ClassesNames{i}),'NodeData',[i,0]);
                    expand(p)

                    for j=1:numel(app.Classes(i).ClassMapNames)

                        p1 = uitreenode(p,'Text',char(app.Classes(i).ClassMapNames{j}),'NodeData',[i,j]);
                        expand(p1)

                        for k=1:numel(app.Classes(i).ClassMap(j).ClassesNames)
                            
                        p2=uitreenode(p1,'Text',app.Classes(i).ClassMap(j).ClassesNames{k},'NodeData',[i,j,k]); %#ok
                        end
                    end

                end

                app.ClassTree.Visible=true;

            end
        end

        function UpdateMapPlot(app,a2p,matrix,n_classes,ClassesNames)
            cla(a2p)
            colormap(a2p,parula(n_classes))
            if any(size(matrix)==1)
                histogram(a2p,matrix)
            else
                caxis(a2p,[1 n_classes])
                imagesc(a2p,matrix, 'AlphaData', not(isnan(matrix)))
                axis(a2p,'equal')
                if not(isempty(app.cmap))
                    a2p.Colormap=app.cmap(ceil(linspace(1,size(app.cmap,1),n_classes)),:);
                end
                if exist('ClassesNames','var')
                    cb=colorbar(a2p);
                    cb.YTick = 0.5 : 1 : n_classes;
                    cb.TickLabels = ClassesNames;
                end
            end
        end
        
        function ClassifyFun(app,DataIn,ix)
            sa=zeros(1,numel(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value));

            if app.Norm_CheckBox.Value==true
                DataIn=DataIn./max(DataIn);
            end


            if app.CLR_CheckBox.Value==true
                DataIn(DataIn<0)=0;
                DataIn=clr(DataIn);
            end
            if app.RandomSeedSpinner.Value==0
                rng('default');
            else
                rng(app.RandomSeedSpinner.Value);
            end

            if size(DataIn,1)<app.MaxClassesSpinner.Value
                app.MaxClassesSpinner.Value=size(DataIn,1)-2;
            end
            class_struc=struct();
            time_c(1)=toc;
            for k=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value
                %   [class_struc(k-app.MinClassesSpinner.Value+1).classNumber, class_struc(k-app.MinClassesSpinner.Value+1).classC, class_struc(k-app.MinClassesSpinner.Value+1).class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);
                tic
                [classNumber, classC, class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);
toc

                if  strcmp(app.SortClassesDropDown.Value,'Integration')
                    if size(DataIn,2)>1
                    to_sort=(trapz(classC,2));
                    else
                    to_sort=classC;

                    end
                elseif  strcmp(app.SortClassesDropDown.Value,'n Pixel')
                    [to_sort, ~] =hist(classNumber,unique(classNumber(not(isnan(classNumber)))));
                elseif strcmp(app.SortClassesDropDown.Value,'C Distance')

                                            to_sort=class_sumd;

                                            % [~, sort_id]=sort(to_sort,'ascend');
                end

                if exist('to_sort','var')
                    [~, sort_id]=sort(to_sort,'descend');

                    classNumber=-classNumber;
                    for nn=1:numel(sort_id)
                        classNumber(classNumber==-sort_id(nn))=nn;
                    end
                end
                class_struc(k-app.MinClassesSpinner.Value+1).classNumber=classNumber;
                class_struc(k-app.MinClassesSpinner.Value+1).classC=classC;
                class_struc(k-app.MinClassesSpinner.Value+1).class_sumd=class_sumd;

                sa(k-app.MinClassesSpinner.Value+1)= sum(class_struc(k-app.MinClassesSpinner.Value+1).class_sumd,"omitnan");
                time_c(k+1)=toc;

            end
            time_c=time_c-time_c(1);


            rng('default');
            cla(app.UIAxes)
            plot(app.UIAxes,app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value,sa,'linewidth',2)
            app.UIAxes.Visible=true;


            ClassMapNr=0;
            mapnr=numel(app.ClassesNames)+1;
            app.ClassesNames{mapnr}=['ClassMap_'  num2str(mapnr)];%%num2str(numberofclasses)
            app.Classes(mapnr).sa=[(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)' sa'];
            ns=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value;
            for k=1:numel(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)

                classNumber=class_struc(k).classNumber;
                ClassMapNr=ClassMapNr+1;
                app.Classes(mapnr).ClassMap(ClassMapNr).classNumber=classNumber;
                app.Classes(mapnr).ClassMap(ClassMapNr).MapPlotMatrix=reshape(classNumber,[],app.mapsize(2))';%ClassMap
                app.Classes(mapnr).ClassMapNames{ClassMapNr}=['Classmap_n' num2str(ns(k))];
                app.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames=cellstr(string(1:numel(unique(classNumber(not(isnan(classNumber))))))');
            end

            save_raw=true;
            time=toc;

            if save_raw==true
                app.Classes(mapnr).Classification_raw.Input=DataIn;
                if exist("ix","var") 
                app.Classes(mapnr).Classification_raw.MapsUsed=ix; % ath here this will create a problem if table and not spec
                end
                app.Classes(mapnr).Classification_raw.Results=class_struc;
                app.Classes(mapnr).Classification_raw.time=time;
                app.Classes(mapnr).Classification_raw.time_c=time_c(2:end);
            end
            UpdateTree(app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Data, cmap)
            app.mainapp = mainapp;
            if isfield(Data,'mapsize') && numel(Data.mapsize)==2
                app.mapsize=Data.mapsize;
            else
                app.mapsize=size(Data.maps(1).matrix);
            end

            if isfield(Data,'ParentApp')
                app.ParentApp=Data.ParentApp;
            end

            app.MapNamesInput=Data.MapNames;

            if not(isempty(cmap))
                app.cmap=cmap;
            end

            app.Data=Data;
            update_map_ListBox(app)
            UpdateDataMapPlot(app)


        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
          %  DataIn=zeros(numel(app.Data.maps(1).matrix),numel(app.ListBox.Value));
%             for n=1:numel(app.ListBox.Value)
%                 DataIn(:,n)=app.Data.maps(n).matrix(:);
%             end
%             app.ListBox.Value
                            DataIn=[app.Data.maps(app.ListBox.Value).matrix(:)];
size(DataIn)
            ix=app.MapNamesInput(app.ListBox.Value);
            ClassifyFun(app,DataIn,ix)
            UpdateTree(app)


        end

        % Selection changed function: ClassTree
        function ClassTreeSelectionChanged(app, event)


            N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                %oplot O in Curve plot with x Selected nodes(2) und y sa(Selected nodes(2))
                if not(isempty(app.CurvePos))
                    delete(app.CurvePos)
                end
                cla(app.UIAxes)
                plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(:,1),app.Classes(selectedNodes(1)).sa(:,2),'linewidth',2)

                app.CurvePos=plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(selectedNodes(2),1),app.Classes(selectedNodes(1)).sa(selectedNodes(2),2),"Color",'r','Marker','o','MarkerSize',10,'MarkerFaceColor','r');
                matrix=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                ClassesNamesC=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
                UpdateMapPlot(app,app.MapPlot,matrix,n_classes,ClassesNamesC);


            elseif numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)
                cla(app.UIAxes)
                plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(:,1),app.Classes(selectedNodes(1)).sa(:,2),'linewidth',2)
                ClassesNamesC=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;

                app.CurvePos=plot(app.UIAxes,app.Classes(selectedNodes(1)).sa(selectedNodes(2),1),app.Classes(selectedNodes(1)).sa(selectedNodes(2),2),"Color",'r','Marker','o','MarkerSize',10,'MarkerFaceColor','r');
                matrix=app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));

                matrix(not(matrix==selectedNodes(3)))=nan;
                UpdateMapPlot(app,app.MapPlot,matrix,n_classes,ClassesNamesC)

            end

            try
                if isfield(app.Classes(selectedNodes(1)).Classification_raw,'PointsToClassifyLines')
                    xpos=[app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines];
                    app.PointsToClassifyLines=xline(app.DataMapPlot,xpos,"LineWidth",2);
                    set(app.PointsToClassifyLines, 'hittest', 'off');
                    app.PointstoClassifySpinner.Value =numel(app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines);
                end
            catch ME
                disp(ME)
            end
                  
        end

        % Value changed function: Norm_CheckBox
        function PlotUpdate(app, event)
            UpdateDataMapPlot(app)
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
           app.ClassesNames=[];
            app.Classes=[];
            cla(app.MapPlot)        
            cla(app.UIAxes) 
            app.UIAxes.Visible=false;
            app.MapPlot.Visible=false;
            UpdateTree(app)

        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            %reset dropdown to eqinterval

            if not(isempty(app.PointsToClassifyLines))
                delete(app.PointsToClassifyLines)
            end

            app.PointstoClassifySpinner.Value=5;

            xpos=linspace(app.x_signal(1),app.x_signal(end),app.PointstoClassifySpinner.Value+2);
            xpos=xpos(2:end-1);
            app.PointsToClassifyLines=xline(app.DataMapPlot,xpos,'LineWidth',2);
                                        set(app.PointsToClassifyLines, 'hittest', 'off');

        end

        % Button pushed function: CancleButton
        function CancleButtonPushed(app, event)
            delete(app)
        end

        % Node text changed function: ClassTree
        function ClassTreeNodeTextChanged(app, event)
            node = event.Node;

            if numel(node.NodeData) == 3 && node.NodeData(3)>0
                app.Classes(node.NodeData(1)).ClassMap(node.NodeData(2)).ClassesNames{node.NodeData(3)}=node.Text;
            elseif numel(node.NodeData) == 2 && node.NodeData(2)>0
                app.Classes(node.NodeData(1)).ClassMapNames{node.NodeData(2)}=node.Text;
            elseif numel(node.NodeData) == 1 || node.NodeData(2)==0
                app.ClassesNames{node.NodeData(1)}=node.Text;
            end

        end

        % Button pushed function: ReturnResultsButton
        function ReturnResultsButtonPushed(app, event)
            
            
            if strcmp(app.Data.PatentApp,'XMapTools')
%@pierre here you need to return the data to XMapTools
%app.mainapp...=ClassificationReturnStruct
            elseif strcmp(app.Data.PatentApp,'SpecDB')

            if isempty(app.ClassTree.CheckedNodes)
                message='Error No Classification Selected';
                             uialert(app.ClassificationModuleUIFigure,message,'Error');
                return
            end
            checked_nodes={app.ClassTree.CheckedNodes.NodeData};
            No_of_elements = cellfun(@numel, checked_nodes);

            checked_nodes(No_of_elements==3)=[];

            if numel(checked_nodes)>0

                idx_to_keep=cell2mat(checked_nodes');

                k1=unique(idx_to_keep(:,1)); %ATH NAN 
                

                ClassificationReturnStruct.ClassesNames=app.ClassesNames(k1);
                ClassificationReturnStruct.Classes=app.Classes(k1);

                for n=1:numel(k1)
                    i=k1(n);
                    k2=idx_to_keep(idx_to_keep(:,1)==i,2);
                    ClassificationReturnStruct.Classes(n).ClassMapNames(not(ismember(1:numel(ClassificationReturnStruct.Classes(n).ClassMap),k2)))=[];
                    ClassificationReturnStruct.Classes(n).ClassMap(not(ismember(1:numel(ClassificationReturnStruct.Classes(n).ClassMap),k2)))=[];
                
                end
                ClassificationReturn(app.SpecMap,ClassificationReturnStruct,app.sampleinfo);
                delete(app)
            end
            end

        end

        % Button down function: DataMapPlot
        function DataMapPlotButtonDown(app, event)
            for k=1:numel(app.PointsToClassifyLines)
                xVertLineCoord = get(app.PointsToClassifyLines(k),'Value');
                click_buffer=(max(app.DataMapPlot.XLim)-min(app.DataMapPlot.XLim))*0.01; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.PointsToClassifyLines(k).LineWidth=2;
                    app.PointsToClassifyLines(k).Color='r';
                    app.hLineToDrag = app.PointsToClassifyLines(k);
                    
                    break;
                end
            end
        end

        % Button pushed function: CMatButton
        function CMatButtonPushed(app, event)
             N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
            %if numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)


            mapnr=selectedNodes(2);
            C=app.Classes(selectedNodes(1)).Classification_raw.Results(mapnr).classC; % centeroid
            idx=app.Classes(selectedNodes(1)).Classification_raw.Results(mapnr).classNumber;
            X=app.Classes(selectedNodes(1)).Classification_raw.Input;
            values=reshape(idx,[],app.mapsize(2))';
            cmap_=PlotMap_UpdateColormap(app.SpecMap,numel(unique(values(not(isnan(idx))))));
      
            [fig] =fcnCorrMatrixPlot_NG(X,string(app.Classes(selectedNodes(1)).Classification_raw.PointsToClassifyLines),'',idx,cmap_);

            ax = axes('Parent',fig,...
                'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
            MatrixLimits=update_MatixLimits(app.SpecMap,app.sampleinfo);
            imagesc(MatrixLimits(1,:),(MatrixLimits(2,:)),flipud(values-0.5))
            daspect([1 1 1])
            cb=colorbar;
            cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

            colormap(cmap_)

            caxis([0, numel(unique(values(not(isnan(values)))))])
            %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

            cb.TickLabels = app.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
            set(cb,'TickLabelInterpreter','none')
            cb.FontSize = 8;
            end
            %for mapnr=1:numel(app.Classes.Classification_raw.Results)

            %  end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            UpdateDataMapPlot(app)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ClassificationModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassificationModuleUIFigure = uifigure('Visible', 'off');
            app.ClassificationModuleUIFigure.Name = 'ClassificationModule';
            app.ClassificationModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ClassificationModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ClassifyButton = uibutton(app.GridLayout3, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ClassTree = uitree(app.GridLayout3, 'checkbox');
            app.ClassTree.SelectionChangedFcn = createCallbackFcn(app, @ClassTreeSelectionChanged, true);
            app.ClassTree.NodeTextChangedFcn = createCallbackFcn(app, @ClassTreeNodeTextChanged, true);
            app.ClassTree.Editable = 'on';
            app.ClassTree.Visible = 'off';
            app.MinClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MinClassesSpinnerLabel.Text = 'Min # Classes';
            app.MinClassesSpinner = uispinner(app.GridLayout3);
            app.MinClassesSpinner.Limits = [2 Inf];
            app.MinClassesSpinner.RoundFractionalValues = 'on';
            app.MinClassesSpinner.Value = 2;
            app.MaxClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaxClassesSpinnerLabel.Text = 'Max # Classes';
            app.MaxClassesSpinner = uispinner(app.GridLayout3);
            app.MaxClassesSpinner.Limits = [2 Inf];
            app.MaxClassesSpinner.RoundFractionalValues = 'on';
            app.MaxClassesSpinner.Value = 10;
            app.ClearAllButton = uibutton(app.GridLayout3, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.RandomSeedSpinner = uispinner(app.GridLayout3);
            app.RandomSeedSpinner.Limits = [1 Inf];
            app.RandomSeedSpinner.RoundFractionalValues = 'on';
            app.RandomSeedSpinner.Value = 1;
            app.RandomSeedSpinnerLabel = uilabel(app.GridLayout3);
            app.RandomSeedSpinnerLabel.Text = 'Random Seed';
            app.IterationsSpinner = uispinner(app.GridLayout3);
            app.IterationsSpinner.Limits = [1 Inf];
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.Value = 5;
            app.IterationsSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.SortClassesDropDownLabel = uilabel(app.GridLayout3);
            app.SortClassesDropDownLabel.Text = 'Sort Classes';
            app.SortClassesDropDown = uidropdown(app.GridLayout3);
            app.SortClassesDropDown.Items = {'no sort', 'C Distance', 'Integration', 'n Pixel'};
            app.SortClassesDropDown.Value = 'C Distance';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'n Classes')
            ylabel(app.UIAxes, 'sumd')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.MapPlot = uiaxes(app.GridLayout2);
            zlabel(app.MapPlot, 'Sample')
            app.MapPlot.XTickLabel = '';
            app.MapPlot.YTickLabel = '';
            app.MapPlot.Visible = 'off';
            app.DataMapPlot = uiaxes(app.GridLayout2);
            zlabel(app.DataMapPlot, 'Sample')
            app.DataMapPlot.XDir = 'reverse';
            app.DataMapPlot.XTick = [];
            app.DataMapPlot.XTickLabel = '';
            app.DataMapPlot.YTick = [];
            app.DataMapPlot.ButtonDownFcn = createCallbackFcn(app, @DataMapPlotButtonDown, true);
            app.ReturnResultsButton = uibutton(app.GridLayout2, 'push');
            app.ReturnResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnResultsButtonPushed, true);
            app.ReturnResultsButton.Text = 'Return Results';
            app.CancleButton = uibutton(app.GridLayout2, 'push');
            app.CancleButton.ButtonPushedFcn = createCallbackFcn(app, @CancleButtonPushed, true);
            app.CancleButton.Text = 'Cancle';
            app.Norm_CheckBox = uicheckbox(app.GridLayout2);
            app.Norm_CheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.Norm_CheckBox.Text = 'Normalize';
            app.CMatButton = uibutton(app.GridLayout2, 'push');
            app.CMatButton.ButtonPushedFcn = createCallbackFcn(app, @CMatButtonPushed, true);
            app.CMatButton.Text = 'CMat';
            app.CLR_CheckBox = uicheckbox(app.GridLayout2);
            app.CLR_CheckBox.Text = 'CLR';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ContextMenu = uicontextmenu(app.ClassificationModuleUIFigure);
            app.SelectAllMenu = uimenu(app.ContextMenu);
            app.SelectAllMenu.Text = 'Select All';
            app.DeselectAllMenu = uimenu(app.ContextMenu);
            app.DeselectAllMenu.Text = 'Deselect All';
            app.RemoveMenu = uimenu(app.ContextMenu);
            app.RemoveMenu.Text = 'Remove';
            % Assign app.ContextMenu
            app.ClassTree.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.ClassificationModuleUIFigure);
            app.AddClassificationPointMenu = uimenu(app.ContextMenu2);
            app.AddClassificationPointMenu.Text = 'Add Classification Point';
            app.RemoveClassificationPointMenu = uimenu(app.ContextMenu2);
            app.RemoveClassificationPointMenu.Text = 'Remove  Classification Point';
            app.ResetMenu = uimenu(app.ContextMenu2);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            % Assign app.ContextMenu2
            app.DataMapPlot.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.ClassificationModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapClassificationModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassificationModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassificationModuleUIFigure)
        end
    end
end