classdef MapMatchModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapMatchModuleUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        testButton              matlab.ui.control.Button
        LinkAxisCheckBox        matlab.ui.control.CheckBox
        ResetButton             matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Rotate90Button_2        matlab.ui.control.Button
        Rotate90Button          matlab.ui.control.Button
        FlipYButton             matlab.ui.control.Button
        FlipXButton             matlab.ui.control.Button
        MapToMatchMapDropDown   matlab.ui.control.DropDown
        MapReferenceLabel       matlab.ui.control.Label
        ReferenceDropDown       matlab.ui.control.DropDown
        ReferenceMapLabel       matlab.ui.control.Label
        SetMatchPointsButton    matlab.ui.control.Button
        MatchagainButton        matlab.ui.control.Button
        SendtoSpecMapButton     matlab.ui.control.Button
        CancleButton            matlab.ui.control.Button
        MapPlot_MapToMatch      matlab.ui.control.UIAxes
        MapPlot_ReferenceMap    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecMap
        sampleinfo % Description
        ReferenceMaps
        ReferenceMapsName
        MatrixLimits
        mapsize

        matrix_to_match
        matrix_to_matchNames


        active_map_to_match = [1 1]; %reference moving

        ColorMapValues

        ROIs
        roi_fixed1
        roi_fixed2
        roi_fixed3
        roi_match1
        roi_match2
        roi_match3

    end

    methods (Access = private)

        function UpdateMapPlots(app)
            if app.LinkAxisCheckBox.Value==true
                linkaxes([app.MapPlot_MapToMatch app.MapPlot_ReferenceMap],'xy');
            else
                linkaxes([app.MapPlot_MapToMatch app.MapPlot_ReferenceMap],'off');
            end
            if not(isempty(app.roi_fixed3)) && isprop(app.roi_fixed3, 'Position') && numel(app.roi_fixed3.Position) ==2

                roi_fixed1_position=app.roi_fixed1.Position;
                roi_fixed2_position=app.roi_fixed2.Position;
                roi_fixed3_position=app.roi_fixed3.Position;
                roi_match1_position=app.roi_match1.Position;
                roi_match2_position=app.roi_match2.Position;
                roi_match3_position=app.roi_match3.Position;
            end

            cla(app.MapPlot_ReferenceMap)
            msize=app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix;
            %imagesc(app.MapPlot_ReferenceMap,app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:))
            imagesc(app.MapPlot_ReferenceMap,app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix,'XData' ,[0,size(msize,2)],'YData' ,[0,size(msize,1)])

            xlim(app.MapPlot_ReferenceMap,'auto')
            ylim(app.MapPlot_ReferenceMap,'auto')

            app.MapPlot_ReferenceMap.Colormap=app.ColorMapValues;
%r=msize(1)/msize(2);%;abs
          r=  abs((abs(app.MatrixLimits(1,1))-abs(app.MatrixLimits(1,2)))./abs(abs(app.MatrixLimits(2,1))-abs(app.MatrixLimits(2,2))));
            daspect(app.MapPlot_ReferenceMap,[r 1 1])


            if exist('roi_match3_position','var')
                cla(app.MapPlot_MapToMatch)
                %imagesc(app.MapPlot_MapToMatch,app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:))
                imagesc(app.MapPlot_MapToMatch,app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)

                % uialert(app.MapMatchModuleUIFigure,'Select the same 3 Points in the Right Map','Select Points to Match')
                app.roi_fixed1 = drawpoint(app.MapPlot_ReferenceMap,'Position',roi_fixed1_position,'Color',[1 0 0]);
                app.roi_fixed2 = drawpoint(app.MapPlot_ReferenceMap,'Position',roi_fixed2_position,'Color',[0.3010 0.7450 0.9330]);
                app.roi_fixed3 = drawpoint(app.MapPlot_ReferenceMap,'Position',roi_fixed3_position,'Color',[0.9290 0.6940 0.1250]);


                app.roi_match1 = drawpoint(app.MapPlot_MapToMatch,'Position',roi_match1_position,'Color',[1 0 0]);
                app.roi_match2 = drawpoint(app.MapPlot_MapToMatch,'Position',roi_match2_position,'Color',[0.3010 0.7450 0.9330]);
                app.roi_match3 = drawpoint(app.MapPlot_MapToMatch,'Position',roi_match3_position,'Color',[0.9290 0.6940 0.1250]);
                %daspect(app.MapPlot_MapToMatch,[r 1 1])

            else
                cla(app.MapPlot_MapToMatch)
                imagesc(app.MapPlot_MapToMatch,app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)

            end
            try
                % caxis(app.MapPlot_MapToMatch,[mean2(app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)-std2(app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)*2 mean2(app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)+std2(app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix)*1.6])
                matrix=app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix;
                caxis(app.MapPlot_MapToMatch,[min(rmoutliers(matrix(:))) max(rmoutliers(matrix(:)))])
            end
            app.MapPlot_MapToMatch.Colormap=app.ColorMapValues;

        end

        function [points1, points2] = get_corresponding_points(app,matrix1, matrix2, num_points)

            % Create a figure with two subplots
            fig = figure();
            subplot(1,2,1);
            imagesc(matrix1);
            title('Matrix 1');
            subplot(1,2,2);
            imagesc(matrix2);
            title('Matrix 2');

            % Initialize variables
            points1 = zeros(1,num_points);
            points2 = zeros(1,num_points);
            i = 1;

            % Allow user to select points in both subplots
            while i <= num_points
                subplot(1,2,1);
                [x1,y1] = ginput(1);
                points1(i,1) = round(x1);
                points1(i,2) = round(y1);
                subplot(1,2,2);
                [x2,y2] = ginput(1);
                points2(i,1) = round(x2);
                points2(i,2) = round(y2);
                i = i + 1;
            end
points1=points1(:,1:2);
points2=points2(:,1:2);
            % Close the figure
            close(fig);


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ReferenceMaps, ReferenceMapsName, sampleinfo, MatrixLimits, mapsize, matrix_to_match, matrix_to_matchNames, ColorMapValues)
            %app,                ReferenceMaps, ReferenceMapsName, sampleinfo, MatrixLimits, mapsize, matrix_to_match, matrix_to_matchNames, app.ColorMapValues % in SpecMap open UI get files or get folder

            app.SpecMap=mainapp;
            app.ReferenceMaps=ReferenceMaps;
            app.ReferenceMapsName=ReferenceMapsName;
            app.sampleinfo=sampleinfo;
            app.MatrixLimits=MatrixLimits;
            app.mapsize=mapsize;

            app.matrix_to_match=matrix_to_match;
            app.matrix_to_matchNames=matrix_to_matchNames;

            app.ColorMapValues=ColorMapValues;

            if numel(app.matrix_to_matchNames)>1
                app.MapToMatchMapDropDown.Visible=true;
                app.MapToMatchMapDropDown.Items=app.matrix_to_matchNames;
            end

            if numel(app.ReferenceMapsName)>1
                app.ReferenceDropDown.Visible=true;
       try
           app.ReferenceDropDown.Items=app.ReferenceMapsName;
       catch ME
0
       end
            end


            app.MapToMatchMapDropDown.ItemsData=1:numel(app.MapToMatchMapDropDown.Items);
            app.ReferenceDropDown.ItemsData=1:numel(app.ReferenceDropDown.Items);

            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix_reset=app.matrix_to_match(n).MapPlotMatrix;
            end

            UpdateMapPlots(app)
        end

        % Button pushed function: CancleButton
        function CancleButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: MapToMatchMapDropDown, ReferenceDropDown
        function DropDownValueChanged(app, event)
            app.active_map_to_match(1) = app.ReferenceDropDown.Value;
            app.active_map_to_match(2) = app.MapToMatchMapDropDown.Value;

            UpdateMapPlots(app)
        end

        % Button pushed function: FlipXButton
        function FlipXButtonPushed(app, event)
            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix=flip(app.matrix_to_match(n).MapPlotMatrix,2);
            end
            UpdateMapPlots(app)
        end

        % Button pushed function: FlipYButton
        function FlipYButtonPushed(app, event)
            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix=flip(app.matrix_to_match(n).MapPlotMatrix,1);
            end
            UpdateMapPlots(app)
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix=rot90(app.matrix_to_match(n).MapPlotMatrix,3);
            end
            UpdateMapPlots(app)

        end

        % Button pushed function: Rotate90Button_2
        function Rotate90Button_2Pushed(app, event)
            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix=rot90(app.matrix_to_match(n).MapPlotMatrix,1);
            end
            UpdateMapPlots(app)

        end

        % Button pushed function: SetMatchPointsButton
        function SetMatchPointsButtonPushed(app, event)
            UpdateMapPlots(app)
            uialert(app.MapMatchModuleUIFigure,'Select 3 Points in the Reference Map','Select Points to Match')
            app.MapPlot_ReferenceMap.Box=true;
            app.roi_fixed1 = drawpoint(app.MapPlot_ReferenceMap,'Color',[1 0 0]);
            app.roi_fixed2 = drawpoint(app.MapPlot_ReferenceMap,'Color',[0.3010 0.7450 0.9330]);
            app.roi_fixed3 = drawpoint(app.MapPlot_ReferenceMap,'Color',[0.9290 0.6940 0.1250]);
            uialert(app.MapMatchModuleUIFigure,'Select the same 3 Points in the Map you want to match','Select Points to Match')
            app.MapPlot_ReferenceMap.Box=false;
            app.MapPlot_MapToMatch.Box=true;
            app.roi_match1 = drawpoint(app.MapPlot_MapToMatch,'Color',[1 0 0]);
            app.roi_match2 = drawpoint(app.MapPlot_MapToMatch,'Color',[0.3010 0.7450 0.9330]);
            app.roi_match3 = drawpoint(app.MapPlot_MapToMatch,'Color',[0.9290 0.6940 0.1250]);
            app.MapPlot_MapToMatch.Box=false;


            %   cpselect(J,I,movingPoints,fixedPoints);
            moving=app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix_reset ;
            fixed = app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix;
            movingPoints = ([app.roi_match1.Position;app.roi_match2.Position;app.roi_match3.Position]);
            fixedPoints = ([app.roi_fixed1.Position;app.roi_fixed2.Position;app.roi_fixed3.Position]);

            %tforms = estimateGeometricTransform2D( movingPoints,fixedPoints,'rigid', 'MaxNumTrials', 20000);
%            tform = fitgeotform2d(movingPoints,fixedPoints,'similarity');
            tform = fitgeotrans(movingPoints,fixedPoints,'similarity');

            %caxis([0 std2(moving)*0.10 ])


            % cpselect(fixed,moving,movingPoints,fixedPoints);
            for n=1:numel(app.matrix_to_matchNames)

                A=app.matrix_to_match(n).MapPlotMatrix;

                followOutput = affineOutputView(size(A),tform,'BoundsStyle','followOutput');

                %app.matrix_to_match(n).MapPlotMatrix = imwarp(A,tform,'OutputView',followOutput);
                app.matrix_to_match(n).MapPlotMatrix = imwarp(A,tform,'OutputView',imref2d( size(fixed)));
                %app.matrix_to_match(n).MapPlotMatrix=imwarp(moving, tform);

            end

            plotstuff=true;
            if plotstuff==true
                figure
                subplot(1,3,1)
                imagesc(flipud(fixed),'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:))
                colormap(app.ColorMapValues)
                r=abs(abs(app.MatrixLimits(1,1))-abs(app.MatrixLimits(1,2)))./abs(abs(app.MatrixLimits(2,1))-abs(app.MatrixLimits(2,2)));

                daspect([r 1 1])
                subplot(1,3,2)
                falseColorOverlay = imfuse( fixed, app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix);
                imshow( flipud(falseColorOverlay), 'initialMagnification', 'fit');      colormap(app.ColorMapValues)


                subplot(1,3,3)
                imagesc(flipud(imwarp(moving, tform)),'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:))
                colormap(app.ColorMapValues)
                daspect([r 1 1])
            end


            if plotstuff==true
                fixed(fixed==inf)=0;
                fixed(isnan(fixed))=0;
                moving=app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix;
                moving2=zeros(size(fixed));
                moving2(1:size(moving,1),1:size(moving,2))=moving;

                moving2(fixed==inf)=0;
                moving2(isnan(moving2))=0;

                corrmat=normxcorr2(moving2,fixed);
                [ypeak,xpeak] = find(corrmat==max(corrmat(:)));

                yoffSet = ypeak-size(moving2,1);
                xoffSet = xpeak-size(moving2,2);

                drawrectangle(gca,'Position',[xoffSet,yoffSet,size(moving2,2),size(moving2,1)], ...
                    'FaceAlpha',0);


                figure
                r=abs(abs(app.MatrixLimits(1,1))-abs(app.MatrixLimits(1,2)))./abs(abs(app.MatrixLimits(2,1))-abs(app.MatrixLimits(2,2)));
                subplot(1,3,1)
                imagesc(flipud(fixed))
                colormap(app.ColorMapValues)
                daspect([r 1 1])
                subplot(1,3,2)
                imagesc(flipud(moving2))
                colormap(app.ColorMapValues)
                daspect([r 1 1])
                subplot(1,3,3)
                imagesc(flipud(corrmat))
                colormap(app.ColorMapValues)
                daspect([r 1 1])
                colorbar
            end

            app.ROIs.fixed(1)=app.roi_fixed1;
            app.ROIs.fixed(2)=app.roi_fixed2;
            app.ROIs.fixed(3)=app.roi_fixed3;

            app.roi_match1.Position=app.roi_fixed1.Position;
            app.roi_match2.Position=app.roi_fixed2.Position;
            app.roi_match3.Position=app.roi_fixed3.Position;


            UpdateMapPlots(app)
            app.ResetButton.Visible=true;
            app.LinkAxisCheckBox.Visible=true;
            app.LinkAxisCheckBox.Value=true;
            app.MatchagainButton.Visible=true;
            app.SendtoSpecMapButton.Enable=true;


        end

        % Button pushed function: MatchagainButton
        function MatchagainButtonPushed(app, event)
            fixed = app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix;
            movingPoints = ([app.roi_match1.Position;app.roi_match2.Position;app.roi_match3.Position]);
            fixedPoints = ([app.roi_fixed1.Position;app.roi_fixed2.Position;app.roi_fixed3.Position]);

            %tforms = estimateGeometricTransform2D( movingPoints,fixedPoints,'rigid', 'MaxNumTrials', 20000);
            tform = fitgeotrans(movingPoints,fixedPoints,'nonreflectivesimilarity');


            % cpselect(fixed,moving,movingPoints,fixedPoints);
            for n=1:numel(app.matrix_to_matchNames)
                A=app.matrix_to_match(n).MapPlotMatrix;
                followOutput = affineOutputView(size(A),tform,'BoundsStyle','followOutput');
                app.matrix_to_match(n).MapPlotMatrix = imwarp(A,tform,'OutputView',imref2d( size(fixed) ));
            end

            app.ROIs.fixed(1)=app.roi_fixed1;
            app.ROIs.fixed(2)=app.roi_fixed2;
            app.ROIs.fixed(3)=app.roi_fixed3;

            app.roi_match1.Position=app.roi_fixed1.Position;
            app.roi_match2.Position=app.roi_fixed2.Position;
            app.roi_match3.Position=app.roi_fixed3.Position;


            UpdateMapPlots(app)


            app.SendtoSpecMapButton.Enable=true;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            for n=1:numel(app.matrix_to_matchNames)
                app.matrix_to_match(n).MapPlotMatrix=app.matrix_to_match(n).MapPlotMatrix_reset;
            end

            app.ResetButton.Visible=false;
            app.MatchagainButton.Visible=false;
            app.LinkAxisCheckBox.Visible=false;
            app.LinkAxisCheckBox.Value=false;

            app.SendtoSpecMapButton.Enable=false;

            cla(app.MapPlot_MapToMatch)
            cla(app.MapPlot_ReferenceMap)
            UpdateMapPlots(app)


        end

        % Button pushed function: SendtoSpecMapButton
        function SendtoSpecMapButtonPushed(app, event)
            MapMatchReturn(app.SpecMap,app.matrix_to_match,app.matrix_to_matchNames,app.sampleinfo)
            delete(app)
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            %
            matrix1=app.ReferenceMaps(app.active_map_to_match(1)).MapPlotMatrix;
            matrix2=app.matrix_to_match(app.active_map_to_match(2)).MapPlotMatrix;


% Detect SIFT features in both matrices
points1 = detectSURFFeatures(matrix1);
points2 = detectSURFFeatures(matrix2);

% Extract feature descriptors from both matrices
[features1, validPoints1] = extractFeatures(matrix1, points1);
[features2, validPoints2] = extractFeatures(matrix2, points2);

% Find matching feature pairs between the two matrices
indexPairs = matchFeatures(features1, features2);

% Get corresponding points from the matched feature pairs
matchedPoints1 = validPoints1(indexPairs(:,1));
matchedPoints2 = validPoints2(indexPairs(:,2));
if not(isempty(matchedPoints1)) &&not(isempty(matchedPoints2))
% Estimate the affine transformation matrix based on the corresponding points

T = estimateGeometricTransform2D(matchedPoints2, matchedPoints1, 'affine');
else
%%
 num_points=3;
  [points1, points2] = get_corresponding_points(app,matrix1, matrix2,  num_points);
  T = estimateGeometricTransform2D( points2,points1, 'affine');
end

% Apply the transformation matrix to matrix2
matrix2_aligned = imwarp(matrix2, T, 'OutputView', imref2d(size(matrix1)));

for n=1:numel(app.matrix_to_matchNames)

    A=app.matrix_to_match(n).MapPlotMatrix;

    app.matrix_to_match(n).MapPlotMatrix = imwarp(A,T,'OutputView',imref2d( size(matrix1)));
end

% Rescale the pixel size of matrix2_aligned to the pixel size of matrix1
pixel_size1 = 1; % define the pixel size of matrix1 here
pixel_size2 = 1; % define the pixel size of matrix2 here
scaling_factor = pixel_size1 / pixel_size2;
matrix2_aligned_rescaled = imresize(matrix2_aligned, scaling_factor);

% Display the aligned and rescaled matrix2 side by side with matrix1
figure();
subplot(1,2,1);
imagesc(matrix1);
axis image;
colormap(gray);
title('Matrix 1');
subplot(1,2,2);
imagesc(matrix2_aligned_rescaled);
axis image;
colormap(gray);
title('Matrix 2 (aligned and rescaled)');


matrix2_aligned_rescaled(isnan(matrix2_aligned_rescaled))=0;
matrix1(isnan(matrix1))=0;

[optimizer, metric] = imregconfig('multimodal');

optimizer.InitialRadius = 0.009;
optimizer.Epsilon = 1.5e-4;
optimizer.GrowthFactor = 1.01;
optimizer.MaximumIterations = 300;

% Register the images using imregister

tform = imregtform(matrix1, matrix2_aligned_rescaled, 'affine', optimizer, metric);

for n=1:numel(app.matrix_to_matchNames)

    A=app.matrix_to_match(n).MapPlotMatrix;

    app.matrix_to_match(n).MapPlotMatrix = imwarp(A,tform,'OutputView',imref2d( size(matrix1)));
end

% Apply the transformation to image2
image2_registered = imwarp(matrix2_aligned_rescaled, tform, 'OutputView', imref2d(size(matrix1)));

% Show the original and registered images side by side
figure;
imshowpair(matrix1, matrix2, 'montage');
title('Original images');

figure;
imshowpair(matrix1, image2_registered, 'montage');
title('Registered images');


figure
imshowpair(matrix1, image2_registered,'Scaling','joint')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapMatchModuleUIFigure = uifigure('Visible', 'off');
            app.MapMatchModuleUIFigure.Name = 'MapMatchModule';
            app.GridLayout = uigridlayout(app.MapMatchModuleUIFigure);
            app.MapPlot_ReferenceMap = uiaxes(app.GridLayout);
            xlabel(app.MapPlot_ReferenceMap, 'X')
            ylabel(app.MapPlot_ReferenceMap, 'Y')
            zlabel(app.MapPlot_ReferenceMap, 'Sample')
            app.MapPlot_MapToMatch = uiaxes(app.GridLayout);
            xlabel(app.MapPlot_MapToMatch, 'X')
            ylabel(app.MapPlot_MapToMatch, 'Y')
            zlabel(app.MapPlot_MapToMatch, 'Sample')
            app.CancleButton = uibutton(app.GridLayout, 'push');
            app.CancleButton.ButtonPushedFcn = createCallbackFcn(app, @CancleButtonPushed, true);
            app.CancleButton.Text = 'Cancle';
            app.SendtoSpecMapButton = uibutton(app.GridLayout, 'push');
            app.SendtoSpecMapButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoSpecMapButtonPushed, true);
            app.SendtoSpecMapButton.Enable = 'off';
            app.SendtoSpecMapButton.Text = 'Send to SpecMap';
            app.MatchagainButton = uibutton(app.GridLayout, 'push');
            app.MatchagainButton.ButtonPushedFcn = createCallbackFcn(app, @MatchagainButtonPushed, true);
            app.MatchagainButton.Visible = 'off';
            app.MatchagainButton.Text = 'Match again';
            app.SetMatchPointsButton = uibutton(app.GridLayout, 'push');
            app.SetMatchPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SetMatchPointsButtonPushed, true);
            app.SetMatchPointsButton.Text = 'Set Match Points';
            app.ReferenceMapLabel = uilabel(app.GridLayout);
            app.ReferenceMapLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.GridLayout);
            app.ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ReferenceDropDown.Visible = 'off';
            app.MapReferenceLabel = uilabel(app.GridLayout);
            app.MapReferenceLabel.Text = 'Map to Align';
            app.MapToMatchMapDropDown = uidropdown(app.GridLayout);
            app.MapToMatchMapDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.MapToMatchMapDropDown.Visible = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.FlipXButton = uibutton(app.GridLayout2, 'push');
            app.FlipXButton.ButtonPushedFcn = createCallbackFcn(app, @FlipXButtonPushed, true);
            app.FlipXButton.Text = 'Flip X';
            app.FlipYButton = uibutton(app.GridLayout2, 'push');
            app.FlipYButton.ButtonPushedFcn = createCallbackFcn(app, @FlipYButtonPushed, true);
            app.FlipYButton.Text = 'Flip Y';
            app.Rotate90Button = uibutton(app.GridLayout2, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Text = 'Rotate -90';
            app.Rotate90Button_2 = uibutton(app.GridLayout2, 'push');
            app.Rotate90Button_2.ButtonPushedFcn = createCallbackFcn(app, @Rotate90Button_2Pushed, true);
            app.Rotate90Button_2.Text = 'Rotate 90';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.LinkAxisCheckBox = uicheckbox(app.GridLayout);
            app.LinkAxisCheckBox.Visible = 'off';
            app.LinkAxisCheckBox.Text = 'Link Axis';
            app.testButton = uibutton(app.GridLayout, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            % Show the figure after all components are created
            app.MapMatchModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapMatchModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapMatchModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapMatchModuleUIFigure)
        end
    end
end