classdef PolPlotModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolPlotGUI                   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        First_and_Last_same          matlab.ui.control.CheckBox
        ResetButton_2                matlab.ui.control.Button
        PolTable                     matlab.ui.control.Table
        ColormapPreviewCheckBox      matlab.ui.control.CheckBox
        nPlotsRowEditField           matlab.ui.control.NumericEditField
        nPlotsRowEditFieldLabel      matlab.ui.control.Label
        ReloadPeakDBButton           matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        PeakSettingsTab              matlab.ui.container.Tab
        GridLayout12                 matlab.ui.container.GridLayout
        PeakTree                     matlab.ui.container.CheckBoxTree
        RangeSettingsTab             matlab.ui.container.Tab
        GridLayout10                 matlab.ui.container.GridLayout
        H2OOTratioEditField          matlab.ui.control.NumericEditField
        H2OOTratioEditFieldLabel     matlab.ui.control.Label
        OHEditField                  matlab.ui.control.NumericEditField
        OHEditFieldLabel             matlab.ui.control.Label
        OvertoneEditField            matlab.ui.control.NumericEditField
        OvertoneEditFieldLabel       matlab.ui.control.Label
        OHEditField_2                matlab.ui.control.NumericEditField
        OvertoneEditField_2          matlab.ui.control.NumericEditField
        DevButton                    matlab.ui.control.Button
        DeconSettingsTab             matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        MultiDeconPlotButton         matlab.ui.control.Button
        MixedDeconPlotButton         matlab.ui.control.Button
        DeconLimMinEditField         matlab.ui.control.NumericEditField
        DeconLimMaxEditField_2Label  matlab.ui.control.Label
        DeconLimMaxEditField         matlab.ui.control.NumericEditField
        DeconLimMaxEditFieldLabel    matlab.ui.control.Label
        LimitShiftCheckBox           matlab.ui.control.CheckBox
        ExtraEditField               matlab.ui.control.NumericEditField
        ExtraEditFieldLabel          matlab.ui.control.Label
        DELTAEditField               matlab.ui.control.NumericEditField
        DELTAEditFieldLabel          matlab.ui.control.Label
        PlotResultsCheckBox          matlab.ui.control.CheckBox
        MCIterationsSpinner          matlab.ui.control.Spinner
        IterationsSpinnerLabel_2     matlab.ui.control.Label
        MCSimulateSettingsButton     matlab.ui.control.Button
        AutozeroSpinner              matlab.ui.control.Spinner
        AutozeroSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner            matlab.ui.control.Spinner
        IterationsSpinnerLabel       matlab.ui.control.Label
        FitShapeDropDown             matlab.ui.control.DropDown
        FitShapeDropDownLabel        matlab.ui.control.Label
        ExportTab                    matlab.ui.container.Tab
        GridLayout11                 matlab.ui.container.GridLayout
        CustStrEditField             matlab.ui.control.EditField
        CustStrEditFieldLabel        matlab.ui.control.Label
        CloseFiguresafterexportCheckBox  matlab.ui.control.CheckBox
        CheckBox_tif_exp             matlab.ui.control.CheckBox
        CheckBox_eps_exp             matlab.ui.control.CheckBox
        CheckBox_pdf_exp             matlab.ui.control.CheckBox
        CheckBox_png_exp             matlab.ui.control.CheckBox
        CheckBox_fig_exp             matlab.ui.control.CheckBox
        SavePlotCheckBox             matlab.ui.control.CheckBox
        SelectButton                 matlab.ui.control.Button
        PathEditField                matlab.ui.control.EditField
        PathEditFieldLabel           matlab.ui.control.Label
        PeakTable                    matlab.ui.control.Table
        nHistEditField               matlab.ui.control.NumericEditField
        nHistLabel                   matlab.ui.control.Label
        PresetDropDown               matlab.ui.control.DropDown
        PresetDropDownLabel          matlab.ui.control.Label
        DetectButton                 matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        Cos2fitButton                matlab.ui.control.Button
        ShiftEditField               matlab.ui.control.NumericEditField
        ShiftEditFieldLabel          matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        EditField_xlimMax            matlab.ui.control.NumericEditField
        EditField_xlimMin            matlab.ui.control.NumericEditField
        maxEditField                 matlab.ui.control.NumericEditField
        maxEditFieldLabel            matlab.ui.control.Label
        minEditField                 matlab.ui.control.NumericEditField
        minEditFieldLabel            matlab.ui.control.Label
        PolSetDropDown               matlab.ui.control.DropDown
        PolSetDropDownLabel          matlab.ui.control.Label
        PlotBandsButton              matlab.ui.control.Button
        SpecTable                    matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecDB % Description
        Data
        click_time % Description
        Peaks % Description
    end

    methods (Access = private)

        function  polarplot_fun(app,selections,selected_bands,setting)
            xlm=app.UIAxes.XLim;

            selected_bands=selected_bands(selected_bands>min(xlm)&selected_bands>min(xlm));

            [x_signal,y_signal] = GetSpecs_cor(app,selections);
            try
                y_signal=circshift(y_signal(:,(app.minEditField.Value:app.maxEditField.Value)),-app.ShiftEditField.Value,2);
            catch
                app.minEditField.Value=1;
                app.maxEditField.Value=size(y_signal,2);
                y_signal=circshift(y_signal(:,(app.minEditField.Value:app.maxEditField.Value)),-app.ShiftEditField.Value,2);
            end


            if exist('setting','var')&& not(isempty(setting)) && strcmp(setting,'multidecon')
                peakshape=16;%sscanf(char(app.FitShapeDropDown.Value),'%d');
                NumTrials=20;

                autozero=0;
                fixedparameters=selected_bands;%[];
                plots=1;
                bipolar=0;
                minwidth=4;

                NumPeaks=numel(selected_bands);
                extra=[];
                Delta=[];
                %start=[];

                y_signal=y_signal(x_signal>xlm(1) & x_signal<xlm(2),:);
                x_signal= x_signal(x_signal>xlm(1) & x_signal<xlm(2));


                center=round(xlm(1)+(xlm(2)-xlm(1))/2);
                window=round((xlm(2)-xlm(1)));


                if plots==1
                    figure
                end
                [ FitResults_best1,GOF,~,~,~,~,~]=peakfit([x_signal mean(y_signal,2)],center,window,NumPeaks,peakshape,extra,NumTrials,bipolar,autozero,fixedparameters,plots,bipolar,minwidth);

                pos=FitResults_best1(:,2);
                amp=FitResults_best1(:,3);
                wid=FitResults_best1(:,4);

                nexttile
                hold on
                g=gaussplot(pos',amp',wid',x_signal);
                plot(x_signal,mean(y_signal,2),'k')
                plot(x_signal,g,'r')
                plot(x_signal,sum(g,2),':g')

                peakshape=1;
                [gaussianParameters,GOF]= multifit_dev3(x_signal,y_signal,NumPeaks,peakshape,extra,Delta,[pos wid]);

                gaussianParameters = sortrows(gaussianParameters, 2);
                pos=gaussianParameters(:,2);
                wid=gaussianParameters(:,3);


                %             nexttile
                %             hold on


                A=DeconPlotFun(peakshape,x_signal,pos',wid',1,extra,Delta);
                figure


                hold on

                view(-165,30)


                plot3(x_signal,ones(size(x_signal)).*(1:size(y_signal,2)),y_signal,'K',LineWidth=0.5)

                for k=1:size(y_signal,2)

                    c = A \ y_signal(:,k);
                    z = A * c;


                    z_=DeconPlotFun(peakshape,x_signal,pos',wid',c',extra,Delta);

                    plot3(x_signal,ones(size(x_signal)).*k,z,'g:',LineWidth=1)

                    z_(z_<0.001*max(z_))=nan;
                    plot3(x_signal,ones(size(x_signal)).*k,z_,'r',LineWidth=0.5)


                    FitResults  = FitResults_best1;
                    FitResults(:,3)=c;
                    FitResults(:,2)=pos;
                    FitResults(:,4)=wid;

                    b_all(:,k)=c;

                    if x_signal(1)>x_signal(end)
                        FitResults(:,5)=  trapz(flip(x_signal),c'.*A);
                    else
                        FitResults(:,5)=  trapz(x_signal,c'.*A);
                    end

                    PeakFit(1).signal(:,1)=x_signal;
                    PeakFit(1).signal(:,2)=y_signal(:,k);
                end
                all=b_all;

            end

            hFig=figure;
            t = tiledlayout(1+(ceil(numel(selected_bands)/5)),5); %ATH here
            %             t.Padding='tight';
            %             t.TileSpacing='tight';


            %             axis2plot=nexttile(1,[1 5]);
            %                         axis2plot.FontSize=14;


            axis2plot=nexttile(1,[1 2]);
            axis2plot.FontSize=14;
            hold on
            PlotSelectionFun(app.SpecDB,selections,axis2plot)
            xlim(axis2plot,sort([app.OHEditField_2.Value app.OHEditField.Value]))
            ylim(axis2plot,'auto')

            sort([app.OHEditField_2.Value app.OHEditField.Value])
            selected_bands_OH=selected_bands(selected_bands>min([app.OHEditField_2.Value app.OHEditField.Value])  & selected_bands<max([app.OHEditField_2.Value app.OHEditField.Value]));
            % MFC=lines(numel(selected_bands));
            MFC=app.PeakTable.Data.rgb(app.PeakTable.Data.Active,:);

            for n=1:numel(selected_bands)
                if any(ismember(selected_bands_OH,selected_bands))
                    xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',3,'Color',MFC(n,:))
                end
            end


            if numel(selections)==1

                title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
            end


            axis2plot=nexttile(3,[1 3]);
            axis2plot.FontSize=14;
            hold on


            PlotSelectionFun(app.SpecDB,selections,axis2plot)
            axis2plot.YLabel.String='';
            if exist('setting','var')&& not(isempty(setting)) && strcmp(setting,'multidecon')

                for n=1:numel(pos)
                    nexttile

                    b=b_all(n,:)';
                    a=deg2rad([0:360/((size(y_signal,2)*2)-1):360]);
                    b=[b; (b)];

                    polarplot(a,b,'o','MarkerFaceColor',MFC(n,:),'MarkerEdgeColor',MFC(n,:))
                    title(num2str(pos(n)))

                end

                selected_bands_OT=selected_bands(selected_bands>min([app.OvertoneEditField_2.Value app.OvertoneEditField.Value])  & selected_bands<max([app.OvertoneEditField_2.Value app.OvertoneEditField.Value]));
                for n=1:numel(selected_bands)
                    if any(ismember(selected_bands_OT,selected_bands))
                        xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',3,'Color',MFC(n,:))
                    end
                end

                %xline(axis2plot,selected_bands,'-',cellstr(string(selected_bands)),'linewidth',3)

                figure
                nexttile
                b_all=[b_all';b_all'];
                polarplot(a,b_all,'o-')
                nexttile

                polarplot(a,normalize(b_all,1,'range'),'o-')
                legend(string(selected_bands))

                b_all=[];
            else
                n=0;
                for selected_band=selected_bands
                    nexttile
                    n=n+1;


                    [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                    integration=y_signal(ix,:)';

                    b=integration;

                    %a=deg2rad([0:360/(size(y,1)-1):360]);

                    a=deg2rad([0:360/((size(y_signal,2)*2)-1):360]);


                    b=[b; (b)];
                    b_all(n,:)=b;
                    %       a_=[app.SpecDB.DB_table.Data.phi_deg(ismember(app.SpecDB.DB_table.Data.Id(app.SpecDB.DB_table.Data.n_meanSpec>0),app.SpecDB.DB_table.Data.Id(unique(app.SpecDB.DB_table.Selection(:,1)))))]
                    %   a=deg2rad([0:360/((numel(a_)*4)-1):360]);
                    %     b=[b; flip(b); b;-flip(b)];

                    polarplot(a,b,'o','MarkerFaceColor',MFC(n,:),'MarkerEdgeColor',MFC(n,:))
                    xline(axis2plot,selected_band,'-',cellstr(string(selected_band)),'linewidth',3,'Color',MFC(n,:))

                    title(num2str(selected_band))
                end
                xlim(axis2plot,sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]))


                figure
                nexttile
                polarplot(a,b_all,'o-')
                nexttile

                polarplot(a,normalize(b_all,2,'range'),'o-')
                legend(string(selected_bands))


                b_all=[];
            end


            hFig=figure;
            t = tiledlayout(1+(ceil(numel(selected_bands)/7)),7); %ATH here
            %             t.Padding='tight';
            %             t.TileSpacing='tight';
            axis2plot=nexttile(1,[1 7]);
            hold on
            if numel(selections)==1
                title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
            end

            %  PlotSelectionFun(app.SpecDB,selections,axis2plot)
            PlotSelectionFun(app.SpecDB,selections,axis2plot)

            if strcmp(app.PolSetDropDown.Value,'0-180')
            a=linspace(0,180,size(y_signal,2) );
            elseif strcmp(app.PolSetDropDown.Value,'0-180')
            a=deg2rad([0:360/(size(y,1)-1):360]);
            else

            end

            n=0;
            k=0;
            for selected_band=selected_bands
                k=k+1;
                nexttile


                [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                integration=y_signal(ix,:)';
                b=integration;
                b_all(k,:)=b;

                %       a_=[app.SpecDB.DB_table.Data.phi_deg(ismember(app.SpecDB.DB_table.Data.Id(app.SpecDB.DB_table.Data.n_meanSpec>0),app.SpecDB.DB_table.Data.Id(unique(app.SpecDB.DB_table.Selection(:,1)))))]
                %   a=deg2rad([0:360/((numel(a_)*4)-1):360]);
                %     b=[b; flip(b); b;-flip(b)];

                plot(a,b,'o-')

                title(num2str(selected_band))
            end
            all=b_all;

            xline(axis2plot,selected_bands,'linewidth',2)

            figure
            nexttile
            plot(1:numel(a),all,'o-')
            title('raw')
            xlim([1 numel(a)])
            nexttile
            plot(a,all,'o-')
            hold  on
            title('raw a')

            [min_x, min_xid]=min(all');
            [max_x, max_xid]=max(all');

            for n=1:numel(min_x)
                a_=max_x(n);
                b_=0;
                c_=min_x(n);
                %  a__=circshift(a,-max_xid(n));
                a__=a;

                phi=deg2rad(a__);
                teta=deg2rad(zeros(size(phi)));

                r=  a_.*(cos(teta).^2).*(sin(phi).^2)+...
                    b_.*(sin(teta).^2).*(sin(phi).^2)+...
                    c_.*(cos(phi).^2);
                %    plot(a__,r,'linewidth',2)
                % plot(circshift(a,-max_xid(n)),circshift(r,-max_xid(n)),':','linewidth',2)
                %                rotval=0;
                %                 plot(a,circshift(r,-rotval),':','linewidth',2)
            end

            nexttile

            plot(a,movmean(all,12,2),'o-')
            title('Movmean 12')


            nexttile
            %  plot(a,normalize(all,"range"),'o-')
            hold on
            for n=1:size(all,1)
                plot(a,normalize(all(n,:),"range"),'o-')

            end
            title('Normalized')
            nexttile
            %plot(a,normalize(movmean(all,12),"range"),'o-')
            hold on
            for n=1:size(all,1)
                plot(a,normalize(movmean(all(n,:),12,2),"range"),'o-')

            end
            title('Normalized Movmean 12')
        end

        function  polarplot_fun2(app,selections,selected_bands,setting)
            if numel(selections)==1
                update_settings(app,selections)
            end
            
            xlm=app.UIAxes.XLim;
            if not(exist('setting','var'))
                setting='LinAbs';
            end
            selected_bands=selected_bands(selected_bands>min(xlm)&selected_bands>min(xlm));

            [x_signal,y_signal] = GetSpecs_cor(app,selections);
            try
                y_signal=circshift(y_signal(:,(app.minEditField.Value:app.maxEditField.Value)),-app.ShiftEditField.Value,2);
            catch
                app.minEditField.Value=1;
                app.maxEditField.Value=size(y_signal,2);
                y_signal=circshift(y_signal(:,(app.minEditField.Value:app.maxEditField.Value)),-app.ShiftEditField.Value,2);
            end

            if  not(isempty(setting)) && (strcmp(setting,'multidecon') || strcmp(setting,'MixedDecon'))
                peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
                NumTrials=app.IterationsSpinner.Value;
                if strcmp(setting,'MixedDecon')
                    selected_bands_decon=selected_bands(selected_bands>min([app.OHEditField_2.Value app.OHEditField.Value]) & selected_bands<max([app.OHEditField_2.Value app.OHEditField.Value]));
                    selected_bands_OT_=selected_bands(not(selected_bands>min([app.OHEditField_2.Value app.OHEditField.Value]) & selected_bands<max([app.OHEditField_2.Value app.OHEditField.Value])));
                else
                    selected_bands_decon=selected_bands;
                end

                MFC=zeros(numel(unique(selected_bands_decon)),3);
                for n=1:numel(selected_bands_decon)
                    ismf=find(ismember(app.PeakTable.Data.Position,selected_bands_decon(n)));
                    MFC(n,:)=app.PeakTable.Data.rgb(ismf(1),:);
                end

                autozero=app.AutozeroSpinner.Value;
                fixedparameters=selected_bands_decon;%[];
                plots=1;
                bipolar=0;
                minwidth=4;

                NumPeaks=numel(selected_bands_decon);
                extra=[];
                Delta=[];
                %start=[];

                y_signal=y_signal(x_signal>xlm(1) & x_signal<xlm(2),:);
                x_signal= x_signal(x_signal>xlm(1) & x_signal<xlm(2));

                xlm_d= sort([app.DeconLimMinEditField.Value app.DeconLimMaxEditField.Value]);

                y_signal_decon=y_signal(x_signal>xlm_d(1) & x_signal<xlm_d(2),:);
                x_signal_decon= x_signal(x_signal>xlm_d(1) & x_signal<xlm_d(2));


                center=round(xlm(1)+(xlm(2)-xlm(1))/2);
                window=round((xlm(2)-xlm(1)));


                [ FitResults_best1,GOF,~,~,~,~,~]=peakfit([x_signal_decon mean(y_signal_decon,2)],center,window,NumPeaks,peakshape,extra,NumTrials,bipolar,autozero,fixedparameters,plots,bipolar,minwidth);

                pos=FitResults_best1(:,2);
                amp=FitResults_best1(:,3);
                wid=FitResults_best1(:,4);
               
                hFig=figure;
                nexttile
                hold on
                title('Mean Spectrum Fit')
                A=DeconPlotFun(peakshape,x_signal_decon,pos',wid',1,extra,Delta);
                c = A \ mean(y_signal_decon,2);
                z = A * c;


                z_=DeconPlotFun(peakshape,x_signal_decon,pos',wid',c',extra,Delta);

                plot(x_signal_decon,mean(y_signal_decon,2),'k',LineWidth=2)
                plot(x_signal_decon,z,'g:',LineWidth=2)

                z_(z_<0.001*max(z_))=nan;

                for n=1:size(MFC,1)
                    plot(x_signal_decon,z_(:,n),'color',MFC(n,:),LineWidth=0.5)
                end


                peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');

                [gaussianParameters,GOF]= multifit_dev3(x_signal_decon,y_signal_decon,NumPeaks,peakshape,extra,Delta,[pos wid]);

                gaussianParameters = sortrows(gaussianParameters, 2);
                pos=gaussianParameters(:,2);
                wid=gaussianParameters(:,3);


                %             nexttile
                %             hold on


                A=DeconPlotFun(peakshape,x_signal_decon,pos',wid',1,extra,Delta);
                figure
                nexttile

                hold on

                view(-165,30)


                plot3(x_signal,ones(size(x_signal)).*(1:size(y_signal,2)),y_signal,'K',LineWidth=0.5)

                for k=1:size(y_signal,2)

                    c = A \ y_signal_decon(:,k);
                    z = A * c;


                    z_=DeconPlotFun(peakshape,x_signal_decon,pos',wid',c',extra,Delta);

                    plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z,'g:',LineWidth=1)

                    z_(z_<0.001*max(z_))=nan;
                    % plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z_,'r',LineWidth=0.5)
                    %here color
                    for n=1:size(MFC,1)
                        plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z_(:,n),'color',MFC(n,:),LineWidth=0.5)
                    end
                    FitResults  = FitResults_best1;
                    FitResults(:,3)=c;
                    FitResults(:,2)=pos;
                   FitResults(:,4)=wid;

                    b_all1(:,k)=c;

                    if x_signal(1)>x_signal(end)
                        FitResults(:,5)=  trapz(flip(x_signal_decon),c'.*A);
                    else
                        FitResults(:,5)=  trapz(x_signal_decon,c'.*A);
                    end
                    try
                        Peak_integration_decon(:,k)=FitResults(:,5);
                        Peak_hight_decon(:,k)=FitResults(:,3);
                        pos_decon(:,k)=FitResults(:,2);
                    catch ME
                        'error here in multidecon'
                    end
                    PeakFit(1).signal_decon(:,1)=x_signal_decon;
                    PeakFit(1).signal_decon(:,2)=y_signal_decon(:,k);
                end

                nexttile
                view(-165,30)

                hold on
                plot3(x_signal_decon,ones(size(x_signal_decon)).*(1:size(y_signal_decon,2)),y_signal_decon,'K',LineWidth=0.5)

                view(-165,30)
                for k=1:size(y_signal,2)
                    c = A \ y_signal_decon(:,k);
                    z = A * c;
                    z_=DeconPlotFun(peakshape,x_signal_decon,pos',wid',c',extra,Delta);
                    plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z,'g:',LineWidth=1)
                    z_(z_<0.001*max(z_))=nan;
                    %   plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z_,'r',LineWidth=0.5)
                    for n=1:size(MFC,1)
                        plot3(x_signal_decon,ones(size(x_signal_decon)).*k,z_(:,n),'color',MFC(n,:),LineWidth=0.5)
                    end
                end


                all=b_all1;

            end
            %% figure Polarized Plot with spec Polplots

            hFig=figure;
            t = tiledlayout(1+(ceil(numel(selected_bands)/app.nPlotsRowEditField.Value)),app.nPlotsRowEditField.Value); %ATH here
            %     t.Padding='compact';       %     t.TileSpacing='compact';

            second_tile=floor(app.nPlotsRowEditField.Value*(app.H2OOTratioEditField.Value));
         %   second_tile=floor(app.nPlotsRowEditField.Value*(2/3));

            first_tile=app.nPlotsRowEditField.Value-second_tile;

            axis2plot=nexttile(1,[1 first_tile]);
            axis2plot.FontSize=14;
            hold on
            PlotSelectionFun(app.SpecDB,selections,axis2plot)
            xlim(axis2plot,sort([app.OHEditField_2.Value app.OHEditField.Value]))
            ylim(axis2plot,'auto')

            selected_bands_OH=selected_bands(selected_bands>min([app.OHEditField_2.Value app.OHEditField.Value])  & selected_bands<max([app.OHEditField_2.Value app.OHEditField.Value]));
            thx=flipud(reshape(1:ceil(numel(selected_bands_OH)/app.nPlotsRowEditField.Value)*app.nPlotsRowEditField.Value,app.nPlotsRowEditField.Value,[]));
            thx=thx(:);
            selected_bands_OH=selected_bands_OH(thx(ismember(thx,1:numel(selected_bands_OH))));


            selected_bands_OT=selected_bands(selected_bands>min([app.OvertoneEditField_2.Value app.OvertoneEditField.Value])  & selected_bands<max([app.OvertoneEditField_2.Value app.OvertoneEditField.Value]));
            thx=flipud(reshape(1:ceil(numel(selected_bands_OT)/app.nPlotsRowEditField.Value)*app.nPlotsRowEditField.Value,app.nPlotsRowEditField.Value,[]));
            thx=thx(:);
            selected_bands_OT=selected_bands_OT(thx(ismember(thx,1:numel(selected_bands_OT))));

                
            selected_bands=[selected_bands_OT selected_bands_OH];
            

            MFC=zeros(numel(unique(selected_bands)),3);
            for n=1:numel(selected_bands)
                ismf=find(ismember(app.PeakTable.Data.Position,selected_bands(n)));
                MFC(n,:)=app.PeakTable.Data.rgb(ismf(1),:);
            end


            % MFC=lines(numel(selected_bands));

            for n=1:numel(selected_bands)
                if any(ismember(selected_bands_OH,selected_bands))
                    xl= xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',4,'Color',MFC(n,:));
                    xl.FontSize=20;
                end
            end

            hold on
            if numel(selections)==1
                title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
            end

            axis2plot=nexttile(first_tile+1,[1 second_tile]);
            axis2plot.FontSize=14;
            hold on


            PlotSelectionFun(app.SpecDB,selections,axis2plot)
            axis2plot.YLabel.String='';


            for n=1:numel(selected_bands)
                if any(ismember(selected_bands_OT,selected_bands))
                    xl=  xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',4,'Color',MFC(n,:));
                    xl.FontSize=20;

                end
            end
            xlim(axis2plot,sort([app.OvertoneEditField_2.Value app.OvertoneEditField.Value]))
            ylim(axis2plot,'auto')
            if  not(isempty(setting)) && strcmp(setting,'multidecon')

                for n=1:numel(pos)
                    nexttile;

                    b=b_all1(n,:)';
                    %ath here
                    
                    a=deg2rad([0:360/((size(y_signal,2)*2)-1):360]);
                    b=[b; (b)];

                    polarplot(a,b,'o','MarkerFaceColor',MFC(n,:),'MarkerEdgeColor',MFC(n,:))
                    title(num2str(pos(n)))
                end
            else
                n=0;
                for selected_band=selected_bands

                    nexttile;
                    n=n+1;

                    if strcmp(setting,'MixedDecon')  && ismember(selected_band,fixedparameters)
                        %  integration=Peak_integration_decon(ismember(pos,selected_band),:)';
                        integration=Peak_hight_decon(ismember(fixedparameters,selected_band),:)';
                        %                         [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                        %                         integration2=y_signal(ix,:)';
                        % figure;plot(integration);hold on;plot(integration2)

                        Title_str{n}=([num2str(selected_band) '-(' num2str(pos(ismember(fixedparameters,selected_band))) ')']);

                    else
                        [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                        integration=y_signal(ix,:)';
                        Title_str{n}=num2str(selected_band);

                    end
                    b=integration;

                    a=deg2rad([0:360/((size(y_signal,2)*2)-1):360]);

                    b=[b; (b)];
                    b_all(n,:)=b;

                    %       a_=[app.SpecDB.DB_table.Data.phi_deg(ismember(app.SpecDB.DB_table.Data.Id(app.SpecDB.DB_table.Data.n_meanSpec>0),app.SpecDB.DB_table.Data.Id(unique(app.SpecDB.DB_table.Selection(:,1)))))]
                    %   a=deg2rad([0:360/((numel(a_)*4)-1):360]);
                    %     b=[b; flip(b); b;-flip(b)];

                    p_plot= polarplot(a,b,'o','MarkerFaceColor',MFC(n,:),'MarkerEdgeColor',MFC(n,:));
                    p_plot.Parent.FontSize=14;
                    xl  =   xline(axis2plot,selected_band,'-',cellstr(string(selected_band)),'linewidth',4,'Color',MFC(n,:));
                    xl.FontSize=20;
                    title(Title_str{n})

                end
              %  test_findpolmax 
                hFig.Position=[580 240 1100 500];
                drawnow
                hFig.Position=[580 240 1100 700];

                %% Start of figure 2

                hFig2=figure;
                t = tiledlayout(1+(ceil(numel(selected_bands)/app.nPlotsRowEditField.Value)),app.nPlotsRowEditField.Value); %ATH here

                axis2plot=nexttile(1,[1 first_tile]);
                axis2plot.FontSize=14;
                hold on
                PlotSelectionFun(app.SpecDB,selections,axis2plot)
                xlim(axis2plot,sort([app.OHEditField_2.Value app.OHEditField.Value]))
                ylim(axis2plot,'auto')

                for n=1:numel(selected_bands)
                    if any(ismember(selected_bands_OH,selected_bands))
                        xl= xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',4,'Color',MFC(n,:));
                        xl.FontSize=20;
                    end
                end

                hold on
                if numel(selections)==1
                    title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
                end

                axis2plot=nexttile(first_tile+1,[1 second_tile]);
                axis2plot.FontSize=14;
                hold on


                PlotSelectionFun(app.SpecDB,selections,axis2plot)
                axis2plot.YLabel.String='';

                for n=1:numel(selected_bands)
                    if any(ismember(selected_bands_OT,selected_bands))
                        xl=  xline(axis2plot,selected_bands(n),'-',cellstr(string(selected_bands(n))),'linewidth',4,'Color',MFC(n,:));
                        xl.FontSize=20;

                    end
                end
                xlim(axis2plot,sort([app.OvertoneEditField_2.Value app.OvertoneEditField.Value]))
                ylim(axis2plot,'auto')

                for n=1:size(b_all,1)
                    axis2plot=nexttile;
                    scatter(rad2deg(a),b_all(n,:),100,'marker','o','MarkerFaceColor',MFC(n,:),'MarkerEdgeColor',MFC(n,:),'MarkerFaceAlpha',0.5)
                    hold on
                    a_sample=max(b_all(n,:));
                    b_sample=0;
                    c_sample=min(b_all(n,:));

                    min_test=zeros(1,numel(a));
                    for x=1:numel(a)
                        min_test(x)=norm(abs(a_sample.*(cos(zeros(size(a))).^2).*(sin(a+a(x)).^2)+b_sample.*(sin(zeros(size(a))).^2).*(sin(a+a(x)).^2)+c_sample.*(cos(a+a(x)).^2)-b_all(n,:)));
                    end
                    [ ~, ix]=min(min_test);
                    r_2=  a_sample.*(cos(zeros(size(a))).^2).*(sin(a+a(ix)).^2)+...
                        b_sample.*(sin(zeros(size(a))).^2).*(sin(a+a(ix)).^2)+...
                        c_sample.*(cos(a+a(ix)).^2);


                    plot(rad2deg(a),r_2,'linestyle','-.','linewidth',3,'Color',MFC(n,:))

                    title(Title_str{n})
               %     xlim(axis2plot,[0 360])
                    xlim(axis2plot,[0 180])
                    ylim(axis2plot,[0 axis2plot.YLim(2)])
                xlabel('Polarisation Angle [°]')
                    ylabel('Wavenumber [cm^{-1}]')   

                end
                [axis2plot.Parent(1).Children.FontSize]=deal(14);
            end
            hFig2.Position=[580 240 1100 700];
%             %% end of figure 2x
%             figure
% 
%             x=repmat(x_signal,1,size(y_signal,2));
%             y=repmat(rad2deg(a),size(y_signal,1),1);
%             
%                         spec_step=0.01;
% 
%             
%             z=[y_signal y_signal];
%             x=[x x];
%  subplot(3,2,5:6)
%             [c,h]=contourf(x,y,normalize(z,'range'),[min(y_signal(:)):spec_step:max(y_signal(:))]);
%             
%             set(h,'LineStyle','none')
%             %  colormap('default')
%             colorbar('vert')
%             title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
% 
%             ylabel('Polarisation angle [°]')
%             xlabel('Wavenumber [cm^{-1}]')
% 
%             z(z<0)=0;
%             %z=z+(min(z,2));
%             z=log(z);
%             subplot(3,2,3:4)
%             [c,h]=contourf(x,y,z,[min(y_signal(:)):spec_step:max(y_signal(:))]);
%             
%             set(h,'LineStyle','none')
%             %  colormap('default')
%             colorbar('vert')
%             title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
% 
%             ylabel('Polarisation angle [°]')
%             xlabel('Wavenumber [cm^{-1}]')
%  
%            
%             subplot(3,2,1)
%             x_=x;
%             y_=y;
%             z_=z;
% 
%             x=x_(x_>3000&x_<3800,:);
%             y=y_(x_>3000&x_<3800,:);
%             z=z_(x_>3000&x_<3800,:);
% 
%             [c,h]=contourf(x,y,z,[min(z(:)):spec_step:max(z(:))]);
% hold on
%             set(h,'LineStyle','none')
%             %  colormap('default')
%             colorbar('vert')
%             title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
% 
%             ylabel('Polarisation angle [°]')
%             xlabel('Wavenumber [cm^{-1}]')
% 
%             subplot(3,2,2)
% 
%             x=x_(x_>1400&x_<2300,:);
%             y=y_(x_>1400&x_<2300,:);
%             z=z_(x_>1400&x_<2300,:);
% 
% 
% 
%             [c,h]=contourf(x,y,z,[min(z(:)):spec_step:max(z(:))]);
% hold on
% 
%             set(h,'LineStyle','none')
% 
% 
%             %  colormap('default')
%             colorbar('vert')
%             title(app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections),'interpreter','none')
% 
%             ylabel('Polarisation angle [°]')
%             xlabel('Wavenumber [cm^{-1}]')
% 
%             %%


            FileName=join(['Id_' num2str(app.SpecTable.Data.Id(app.SpecTable.Data.Id==selections)) '_' app.SpecTable.Data.SampleName(app.SpecTable.Data.Id==selections) '_' setting]);
            if app.SavePlotCheckBox.Value==true
                export_polplot(app,FileName,hFig)
                export_polplot(app,join([FileName '_A_0_360']),hFig2)
                try   %ATH
                    if app.CloseFiguresafterexportCheckBox.Value==true
                        delete(hFig)
                        delete(hFig2)
                    end
                end
            end

        end

        function [x_signal,y_signal] = GetSpecs_cor(app,selections)
            [x, y, SampleName, Thickness, Id, eID] = get_specs(app.SpecDB,selections,true);

            x=  x(not(cellfun(@iscell,x)));
            y=    y(not(cellfun(@iscell,x)));
            SampleName=     SampleName(not(cellfun(@iscell,x)));
            Thickness=    Thickness(not(cellfun(@iscell,x)));
            Id=    Id(not(cellfun(@iscell,x)));
            eID=    eID(not(cellfun(@iscell,x)));


            min_x_signal=min(cellfun(@min,x));
            max_x_signal=max(cellfun(@max,x));
            delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));

            if isnan(delta_wn)
                delta_wn=cellfun(@diff,(x),'UniformOutput',false);
                delta_wn=[delta_wn{:}];
                delta_wn=delta_wn(isnumeric(delta_wn));
                delta_wn=mean(delta_wn);
            end

            x_signal=(max_x_signal:-delta_wn:min_x_signal)';
            y_signal=nan(size(x_signal,1),numel(selections));
            if numel(selections)==1
                if Thickness{1}==0
                    Thickness{1}=10000;
                end
                y_signal=(y{1}./Thickness{1}).*10000;
            else
                for n=1:numel(selections)
                    if isempty(y{n})
                        continue
                    end
                    if Thickness{n}==0

                        y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]))),x_signal);
                        y_resampled= mean(y_resampled,2,'omitnan');
                        y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;
                        y_signal(:,n)=nan(size(y_resampled));
                    elseif isequal(x_signal,x{n})

                        y_signal(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');

                    else
                        y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]))./Thickness{n}).*10000,x_signal);
                        y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;
                        y_signal(:,n)=mean(y_resampled,2,'omitnan');
                    end
                end
            end
        end

        function  update_peaktable_fun(app)
            %app.Data.Peaks.UI_Peaks

            T1=table();


            if not(isempty(app.Data.Peaks.UI_Peaks))
                T1.Active(:)=app.Data.Peaks.UI_Peaks_Active';
                T1.Position(:)=app.Data.Peaks.UI_Peaks';
                T1.Type(:)="UI_Peaks";
                try
                    T1.rgb=app.Data.Peaks.UI_Peaks_color;
                catch

                    T1.rgb=repmat([0 0 0],numel(app.Data.Peaks.UI_Peaks),1);

                end
            end


            T2=table();
            if not(isempty(app.Data.Peaks.AutoPeaks))
                T2.Active(:)=app.Data.Peaks.AutoPeaks_Active';
                T2.Position(:)=app.Data.Peaks.AutoPeaks';
                T2.Type(:)="AutoPeaks";
                T2.rgb=repmat([0 0 0],numel(app.Data.Peaks.AutoPeaks),1);
            end

            T3=table();
            checkedNodes = app.PeakTree.CheckedNodes;

            if not(isempty(checkedNodes))
                Data={checkedNodes.NodeData};
                test=  cellfun(@iscell,Data);
                Data_=Data(test);

                id=1;

                for n=1:numel(Data_)
                    idx=Data_{n}{1};
                    pk=app.Peaks.PeakDB.Mineral(idx(1)).Groups(idx(2)).Peaks(idx(3));
                    if numel(idx)==4
                        id=idx(4);
                    else
                        id=1;
                    end
                    T3.Active(n)=true;
                    T3.Position(n)=pk.PeakPosition(id);
                    T3.Type(n)=pk.PeakNames(id);%"PeakDB";
                    if any(isnan([pk.R(id) pk.G(id) pk.B(id)]))
                        T3.rgb(n,:)=[0 0 0 ];
                    else
                        T3.rgb(n,:)=[pk.R(id) pk.G(id) pk.B(id)];
                    end
                end
            end

            T=[T1;T2;T3];

            if isempty(T)
                app.PeakTable.Data=[];

            else
                [pkx, idx]=sort(T.Position);
                T.(1)=logical(T.(1));
                app.PeakTable.Data=T(idx,:);

                if app.ColormapPreviewCheckBox.Value==true
                    for n=1:numel(app.PeakTable.Data.Active)
                        %                         s = uistyle('BackgroundColor',app.PeakTable.Data.rgb(n,:));
                        %                         addStyle(app.PeakTable,s,'row',n)

                        s = uistyle('BackgroundColor',app.PeakTable.Data.rgb(n,:));
                        addStyle(app.PeakTable,s,'cell',[n,4])

                    end
                    %                     s = uistyle('BackgroundColor',[1 1 1 ]);
                    %                     addStyle(app.PeakTable,s,'column',[1:3])
                else
                    s = uistyle('BackgroundColor',[1 1 1 ]);
                    addStyle(app.PeakTable,s,'column',1:4)
                end
            end


        end

        function update_spectra_plot(app)
            cla(app.UIAxes)
            PlotSelectionFun(app.SpecDB,app.Data.selections,app.UIAxes)
            if not(isempty(app.PeakTable.Data)) && not(isempty(app.PeakTable.Data.Position))
                selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';
                cmap=app.PeakTable.Data.rgb(app.PeakTable.Data.Active,:);
                for n=1:numel(selected_bands)
                    xline(app.UIAxes,selected_bands(n),'linewidth',2,'Color',cmap(n,:))
                end

            end
            app.UIAxes.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

        end

        function  export_polplot(app,FileName,hFig)

            path=app.PathEditField.Value;
            if strcmp(app.CustStrEditField.Value,'')
                c_str='';
            else
                c_str=['_' app.CustStrEditField.Value];
            end
            thx_dir=dir(path);
            existingnames={thx_dir.name};
            [P,existingnames,E] = cellfun(@fileparts,existingnames,'uni',0);
            exp_name=matlab.lang.makeUniqueStrings(replace(matlab.lang.makeValidName([char(FileName) c_str]),'__','_'),existingnames);
            FileName=char(fullfile(path,exp_name));


            if app.CheckBox_fig_exp.Value==true
                set(hFig, 'CreateFcn', 'set(gcbo,''Visible'',''on'')');
                savefig(hFig,[FileName '.fig'])
            end

            if app.CheckBox_eps_exp.Value==true
                exportgraphics(hFig,[FileName '.eps'],'BackgroundColor','none','ContentType','vector');
            end

            if app.CheckBox_png_exp.Value==true
                exportgraphics(hFig,[FileName '.png'],Resolution=300);
            end

            if app.CheckBox_pdf_exp.Value==true
                %  exportgraphics(hFig,[FileName '.pdf']);
                exportgraphics(hFig,[FileName,'.pdf'],'ContentType','vector')

            end

            disp(['Exported ' FileName])


            figure(app.PolPlotGUI);

        end

        function update_PeakTree(app)
            a = app.PeakTree.Children;
            a.delete;

            for i=1:numel(app.Peaks.PeakDB.MineralNames)
                p = uitreenode(app.PeakTree,'Text',string(app.Peaks.PeakDB.MineralNames{i}),'NodeData',[i,0]);
                expand(p)

                for j=1:numel(app.Peaks.PeakDB.Mineral(i).GroupNames)
                    p1 = uitreenode(p,'Text',string(app.Peaks.PeakDB.Mineral(i).GroupNames{j}),'NodeData',[i,j]);
                    expand(p1)

                    for k=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames)
                        if numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)==1
                            pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                            name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                            p2=uitreenode(p1,'Text',[num2str(pos) '_' app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',{[i,j,k],pos,name}); %# ok
                        else
                            p2=uitreenode(p1,'Text',[app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',[i,j,k]);

                            for l=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)
                                pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                                name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                                p3=uitreenode(p2,'Text',[num2str(pos(l))],'NodeData',{[i,j,k l],pos(l),name}); %# ok
                            end
                        end
                    end


                end
            end

        end
        
        function  update_settings(app,selection)
               eId=find(ismember([app.SpecDB.Data.Meta.Id],selection));
            if numel(eId)==1
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupStart') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart))&& isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart)
                    app.minEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart;
                end
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupEnd') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd))&& isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd)
                    app.maxEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd;
                end
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupShift') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift)) && isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift)
                    app.ShiftEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift;
                end
            end
        end

        function update_PolTable_fun(app)
            try
            if not(isempty(app.SpecTable.Data)) && numel(app.SpecTable.Selection(:,1))==1
                eId=find(ismember([app.SpecDB.Data.Meta.Id],app.Data.selections));

                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolTable') && istable(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolTable) && strcmp(app.PolSetDropDown.Value,'custom')
                  app.PolTable.Data=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolTable;
                else
                    [x_signal,y_signal] = GetSpecs_cor(app,app.Data.selections);
                    if app.First_and_Last_same.Value==true
                        app.PolTable.Data=table(true(size(y_signal,2),1),(1:size(y_signal,2))',round(linspace(0,180,size(y_signal,2)),1)','VariableNames',{'Active','Id','Angle'});
                    else
                        app.PolTable.Data=table(true(size(y_signal,2),1),(1:size(y_signal,2))',round(linspace(0,180-(180/(size(y_signal,2))),size(y_signal,2)),1)','VariableNames',{'Active','Id','Angle'});
                    end
                    app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolTable= app.PolTable.Data;
                end
           
            else
                app.PolTable.Data=[];
            end
            catch ME
DisplayError(ME)
            end

    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selections)
            app.SpecDB=mainapp;

            app.Data.selections=selections;

            app.Data.Peaks.UI_Peaks=[];
            app.Data.Peaks.UI_Peaks_Active=[];
            app.Data.Peaks.UI_Peaks_color=[];
            app.Data.Peaks.AutoPeaks=[];
            app.Data.Peaks.AutoPeaks_Active=[];
            app.Data.Peaks.AutoPeaks_color=[];

            app.SpecTable.Data=app.SpecDB.DB_table.Data(ismember(app.SpecDB.DB_table.Data.Id(app.SpecDB.DB_table.Data.n_meanSpec>0),selections),:);
            app.SpecTable.ColumnName=app.SpecDB.DB_table.ColumnName;
            %update_spectra_plot(app)
            ReloadPeakDBButtonPushed(app)
        end

        % Button pushed function: PlotBandsButton
        function PlotBandsButtonPushed(app, event)
            selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';
            if isempty(selected_bands)
                uialert(app.PolPlotGUI,'No peak selected!','Error!')
                return
            end

            if isempty(selected_bands)
                DetectButtonPushed(app)
                selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';
            end

            for n=1:numel(app.Data.selections)
                polarplot_fun2(app,app.Data.selections(n),selected_bands)
            end

            if app.CloseFiguresafterexportCheckBox.Value==true
                close all
            end

            if isempty(selected_bands)
    uialert(app.PolPlotGUI,'No peak selected!','Error!')
                return
            end


        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            [x_signal,y_signal] = GetSpecs_cor(app,app.Data.selections);


            range=[app.SpecDB.EditField_xlimMin.Value app.SpecDB.EditField_xlimMax.Value ];

            y_signal=y_signal(x_signal>min(range)&x_signal<max(range),:);
            x_signal=x_signal(x_signal>min(range)&x_signal<max(range),:);
            figure


            for n=1:size(y_signal,2)
                if x_signal(1)>x_signal(end)
                    [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(flip(y_signal(:,n)),flip(x_signal),'MinPeakHeight',max(y_signal(:,n),[],'all')*0.05,'MinPeakDistance',5,'WidthReference','halfheight');

                    findpeaks(flip(y_signal(:,n)),flip(x_signal),'MinPeakHeight',max(y_signal(:,n),[],'all')*0.05,'MinPeakDistance',5,'WidthReference','halfheight');
                else
                    [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(y_signal(:,n),x_signal,'MinPeakHeight',max(y_signal(:,n),[],'all')*0.05,'MinPeakDistance',5,'WidthReference','halfheight');
                end

                max_n_peaks=7*3;

                [~,idx]=sort(Prominence,"descend");
                if numel(PeakPosition)>=max_n_peaks
                    PeakIntensity=PeakIntensity(idx);
                    PeakIntensity=PeakIntensity(1:max_n_peaks);

                    PeakPosition=PeakPosition(idx);
                    PeakPosition=PeakPosition(1:max_n_peaks);

                    Width=Width(idx);
                    Width=Width(1:max_n_peaks);

                    Prominence=Prominence(idx);
                    Prominence=Prominence(1:max_n_peaks);

                else
                    max_n_peaks=numel(PeakPosition);
                end


                [~,idx]=sort(Prominence,"descend");
                if numel(PeakPosition)>=max_n_peaks
                    PeakIntensity=PeakIntensity(idx);
                    PeakIntensity=PeakIntensity(1:max_n_peaks);

                    PeakPosition=PeakPosition(idx);
                    PeakPosition=PeakPosition(1:max_n_peaks);

                    Width=Width(idx);
                    Width=Width(1:max_n_peaks);

                    Prominence=Prominence(idx);
                    Prominence=Prominence(1:max_n_peaks);
                end

                PeakPosition_all(1:numel(PeakPosition),n)=PeakPosition';
                Width_all(1:numel(PeakPosition),n)=Width';
                PeakIntensity_all(1:numel(PeakPosition),n)=PeakIntensity';
                ProminenceProminence_all(1:numel(PeakPosition),n)=Prominence';
            end

            h=histogram((PeakPosition_all(PeakPosition_all>0)));
            h.NumBins=app.nHistEditField.Value;

            Values=h.Values;
            x_allpks=h.BinEdges;

            x_allpks_=linspace(min(x_allpks),max(x_allpks),numel(Values));

            x_allpks_=[x_allpks_(1)-(x_allpks_(2)-x_allpks_(1)) x_allpks_ x_allpks_(end)+(x_allpks_(2)-x_allpks_(1))];


            plot(x_allpks_ ,[0 Values 0])

            [PeakIntensity,PeakPosition,Width,Prominence] =     findpeaks([0 Values 0],x_allpks_);
            findpeaks([0 Values 0],x_allpks_)

            PeakPosition=round(PeakPosition);
            [~,idx]=sort(PeakIntensity,"descend");
            if numel(PeakPosition)>=max_n_peaks
                PeakIntensity=PeakIntensity(idx);
                PeakIntensity=PeakIntensity(1:max_n_peaks);

                PeakPosition=PeakPosition(idx);
                PeakPosition=PeakPosition(1:max_n_peaks);

                Width=Width(idx);
                Width=Width(1:max_n_peaks);

                Prominence=Prominence(idx);
                Prominence=Prominence(1:max_n_peaks);
            end

            app.Data.Peaks.AutoPeaks=PeakPosition;
            app.Data.Peaks.AutoPeaks_Active=true(size(PeakPosition));
            app.Data.Peaks.AutoPeaks_color=repmat([0 0 0],numel(PeakPosition),1);
            update_peaktable_fun(app)

            polarplot_fun2(app,app.Data.selections,sort([app.Data.Peaks.AutoPeaks, app.Data.Peaks.UI_Peaks   ]));
            update_spectra_plot(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            new_UI_peaks = inputdlg('Enter space-separated numbers:',...
                'Enter Peaks', [1 50]);
            figure(app.PolPlotGUI);

            if ~isempty(new_UI_peaks)
                app.Data.Peaks.UI_Peaks=[app.Data.Peaks.UI_Peaks unique(str2num(new_UI_peaks{1}))]; %#ok<ST2NM>
                app.Data.Peaks.UI_Peaks_Active=true(size(app.Data.Peaks.UI_Peaks)); %#ok<ST2NM>
                app.Data.Peaks.UI_Peaks_color=[app.Data.Peaks.UI_Peaks_color;repmat([0 0 0],numel(new_UI_peaks),1)];
            end
            update_peaktable_fun(app)

            update_spectra_plot(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if isempty(app.PeakTable.Selection)
                return
            end
            for n=flip(1:numel(app.PeakTable.Selection(:,1)))
                idx=app.PeakTable.Selection(n,1);

                if strcmp(app.PeakTable.Data.Type{idx},'UI_Peaks')
                    app.Data.Peaks.UI_Peaks_Active(app.Data.Peaks.UI_Peaks==app.PeakTable.Data.Position(idx))=[];
                    app.Data.Peaks.UI_Peaks_color(app.Data.Peaks.UI_Peaks==app.PeakTable.Data.Position(idx),:)=[];
                    app.Data.Peaks.UI_Peaks(app.Data.Peaks.UI_Peaks==app.PeakTable.Data.Position(idx))=[];

                else
                    app.Data.Peaks.AutoPeaks_color(app.Data.Peaks.AutoPeaks==app.PeakTable.Data.Position(idx),:)=[];
                    app.Data.Peaks.AutoPeaks_Active(app.Data.Peaks.AutoPeaks==app.PeakTable.Data.Position(idx))=[];
                    app.Data.Peaks.AutoPeaks(app.Data.Peaks.AutoPeaks==app.PeakTable.Data.Position(idx))=[];

                end
            end
            update_peaktable_fun(app)
            update_spectra_plot(app)
        end

        % Button pushed function: MultiDeconPlotButton
        function MultiDeconPlotButtonPushed(app, event)
            selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';
            polarplot_fun(app,app.Data.selections,sort(selected_bands),'multidecon')
        end

        % Button pushed function: MixedDeconPlotButton
        function MixedDeconPlotButtonPushed(app, event)
            selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';
            for n=1:numel(app.Data.selections)
                polarplot_fun2(app,app.Data.selections(n),sort(selected_bands),'MixedDecon')
              
            end

           if app.CloseFiguresafterexportCheckBox.Value==true
                    close all
                end

        end

        % Cell selection callback: SpecTable
        function SpecTableCellSelection(app, event)
            indices = event.Indices;
            cla(app.UIAxes)


            app.Data.selections=app.SpecTable.Data.Id(indices(:,1));
            eId=find(ismember([app.SpecDB.Data.Meta.Id],app.SpecTable.Data.Id(indices(:,1))));
            if numel(eId)==1
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupStart') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart))&& isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart)
                    app.minEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart;
                end
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupEnd') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd))&& isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd)
                    app.maxEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd;
                end
                if isfield(app.SpecDB.Data.MetaPrivat(eId),'PolSettings') && isfield(app.SpecDB.Data.MetaPrivat(eId).PolSettings,'PolGroupShift') && not(isempty(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift)) && isnumeric(app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift)
                    app.ShiftEditField.Value=app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift;
                end
            end

            PlotSelectionFun(app.SpecDB,app.Data.selections,app.UIAxes)
            update_spectra_plot(app)


            EditField_xlimMinValueChanged(app)
            update_PolTable_fun(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            [x_signal] = GetSpecs_cor(app,app.Data.selections);
            app.UIAxes.XLim=[min(x_signal) max(x_signal)];

            xlim=  app.UIAxes.XLim;
            app.EditField_xlimMin.Value=min(xlim);
            app.EditField_xlimMax.Value=max(xlim);
        end

        % Value changed function: EditField_xlimMax
        function EditField_xlimMaxValueChanged(app, event)
            try
                app.UIAxes.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            catch
                ResetButtonPushed(app)
            end
        end

        % Value changed function: EditField_xlimMin
        function EditField_xlimMinValueChanged(app, event)
            try
                app.UIAxes.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            catch
                ResetButtonPushed(app)
            end
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            value = app.PresetDropDown.Value;
            new_UI_Peaks_color=[];
            %  OH_00=[0        0.447        0.741];
            OH_00=[0.0745	0.6235	1.0000];
            %  OT_00=[0.635        0.078        0.184];
            OT_00=[0.6353	0.0784	0.1843];

            %     OH_90=[0.301        0.745        0.933];
            OH_90=[0.20        0.25        1];
            %    OT_90=[ 0.85        0.325        0.098];
            OT_90=[1.0000	0.4118	0.1608];


            if strcmp(value,'CPX')
                new_UI_peaks=      [3645 3630 3613 3544 3522 3531 3471 3462 3350 2200 2105 2060 1973 1925 1892 1870 1771 1731 1684 1614 1554 1421 ];
            elseif           strcmp(value,'OPX')
                new_UI_peaks=      [1029 1062 1083 1116 1149 1171 1225 1312 1356 1435 1509 1585 1607 1661 1714 1749 1836 1897 1944 2070 2146 3216 3410 3512];

            elseif           strcmp(value,'Olivine')
                %   new_UI_peaks=      [3410 3355 3325 3160 3612 3572 3525 3353 3387 3328 3220 3160];
                new_UI_peaks=      [3691 3672 3640   3598 3591 3570 3534 3524 3514 3502 3477 3431 2033 1922 1961 1835 1786 1719 1674 1600];


            elseif           strcmp(value,'Diopside')
                %                 new_UI_peaks=      [1388 1482 1548 1642 1711 1780 1872 1891 1973 2045 2105 2200 3355 3338 3460 3522 3544 3645];
                %                 new_UI_Peaks_color=[OT_90;OT_90;OT_90;OT_00;OT_00;OT_00;OT_00;OT_00;OT_00;OT_90;OT_90;OT_90;OH_90;OH_90;OH_90;OH_90;OH_90;OH_00];
                new_UI_peaks=      [ 1382 1548 1710 1872  1973 2045 2105 3355 3460 3522 3550 3645];
                new_UI_Peaks_color=[OT_90;OT_90;OT_00;OT_00; OT_90;OT_90;OT_90;OH_90;OH_90;OH_90;OH_90;OH_00];

                new_UI_peaks=      [   1710 1875  1973 2045 2105 3355 3460 3522 3550 3645];
                new_UI_Peaks_color=[OT_90;OT_90; OT_90;OT_90;OT_00;OH_90;OH_90;OH_90;OH_90;OH_00];


            elseif           strcmp(value,'Diopside AB')
                %                 new_UI_peaks=      [1388 1482 1548 1642 1711 1780 1872 1891 1973 2045 2105 2200 3355 3338 3460 3522 3544 3645];
                %                 new_UI_Peaks_color=[OT_90;OT_90;OT_90;OT_00;OT_00;OT_00;OT_00;OT_00;OT_00;OT_90;OT_90;OT_90;OH_90;OH_90;OH_90;OH_90;OH_90;OH_00];
                new_UI_peaks=      [ 1382 1548 1710 1872  1973 2045 2105 3355 3460 3522 3550 3645];
                new_UI_Peaks_color=[OT_90;OT_90;OT_00;OT_00; OT_90;OT_90;OT_90;OH_90;OH_90;OH_90;OH_90;OH_00];
                % 1850
                % 1872
                % 1973
                % 2045
                % 2105
                % 3350
                % 3460
                % 3522
                % 3544
                % 3645
            elseif           strcmp(value,'Omphacite')
                new_UI_peaks=      [1548 1638 1711 1850  1895 1973 2045 2105 2200 3350 3432 3471 3522 3544];
                new_UI_Peaks_color=[OT_90;OT_90;OT_90;OT_90;OT_00;OT_00;OT_00;OT_00;OT_00;OH_90;OH_90;OH_90;OH_90;OH_90];

                %                  new_UI_peaks=      [1548 1638 1711  1973 2105 2200  3432 3476 3522];
                %                 new_UI_Peaks_color=[OT_90;OT_90;OT_90;OT_00;OT_00;OT_00;OH_90;OH_90;OH_90];


            elseif           strcmp(value,'OPX Pakistan')
                new_UI_peaks=      [1509 1585 1661 1749 1836 1944 2070 2146 3410 3512 ];
                new_UI_Peaks_color=[OT_90;OT_90;OT_90;OT_90;OT_00;OT_00;OT_00;OT_00;OH_90;OH_90];


            else
                return
            end

            if ~isempty(new_UI_peaks)
                app.Data.Peaks.UI_Peaks=[app.Data.Peaks.UI_Peaks unique(new_UI_peaks)];
                app.Data.Peaks.UI_Peaks_Active=[app.Data.Peaks.UI_Peaks_Active true(size(unique(new_UI_peaks)))];

                if isempty(new_UI_Peaks_color)
                    new_UI_Peaks_color=repmat([0 0 0],numel(new_UI_peaks),1);
                end
                app.Data.Peaks.UI_Peaks_color=[app.Data.Peaks.UI_Peaks_color; new_UI_Peaks_color];

            end
            update_peaktable_fun(app)
            update_spectra_plot(app)


        end

        % Cell edit callback: PeakTable
        function PeakTableCellEdit(app, event)
            indices = event.Indices;
            indices = indices(indices(:,2)==1,1);
            newData = event.NewData;
            if not(isempty(indices))
                for idx=unique(indices)
                    if strcmp(app.PeakTable.Data.Type{idx},'UI_Peaks')
                        app.Data.Peaks.UI_Peaks_Active(app.Data.Peaks.UI_Peaks==app.PeakTable.Data.Position(idx))=newData;
                    else
                        app.Data.Peaks.AutoPeaksActive(app.Data.Peaks.AutoPeaks==app.PeakTable.Data.Position(idx))=newData;
                    end
                end


            end
        end

        % Button pushed function: Cos2fitButton
        function Cos2fitButtonPushed(app, event)
            xlm=app.UIAxes.XLim;
setting='cos2';

if isempty(app.PeakTable.Data)

    uialert(app.PolPlotGUI,'No peak selected!','Error!')
    return
end
            selected_bands=(app.PeakTable.Data.Position(app.PeakTable.Data.Active))';

            selected_bands=selected_bands(selected_bands>min(xlm)&selected_bands>min(xlm));
figure
            for k=1:numel(app.Data.selections)
                [x_signal,y_signal] = GetSpecs_cor(app,app.Data.selections(k));


                start_val=nan(1,numel(selected_bands));
                shift_val=nan(1,numel(selected_bands));
                end_val=nan(1,numel(selected_bands));
                n=0;
                y_all= nan(size(y_signal,2),numel(selected_bands));
                for selected_band=selected_bands
                    n=n+1;
                    if strcmp(setting,'minmax')
                        [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                        y=movmean(y_signal(ix,:)',4);
                        [ ~, iy ]=max(y);
                        %                 nexttile
                        %                 hold on
                        %                 plot(y,':')
                        y=circshift(y,1-iy);
                        %  plot(y)
                        %  title(num2str(selected_band))
                        %     [a,idx,b,p]=findpeaks(-gradient(gradient(y)));
                        %     [~,id_sort]=sort(p,'descend');
                        %     if numel(id_sort)>1
                        %         y_all(:,n)=y;
                        %
                        %         start_val(n)=1;%min(idx(id_sort(1:2)));
                        %         shift_val(n)=-start_val(n)+1;
                        %
                        %         end_val(n)=max(idx(id_sort(1:2)));
                        %                 end
                        [~, iz]=max(y(ceil(numel(y)/2):end));
                        start_val(n)=1;
                        end_val(n)=iz+ceil(numel(y)/2);
                        shift_val(n)=0;
                        y_all(:,n)=y;
                    elseif strcmp(setting,'cos2')
                        [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                        y=y_signal(ix,:);
                        B = min(y);
                        A = max(y) - min(y);


                        theta_calc = acosd( sqrt( (y - B) ./ A ) );
                        theta=[0 cumsum(abs(diff(theta_calc)))];
                        end_val(n)=numel(y);


                        nexttile
                        hold on

                        %plot(linspace(1,180), y, 'o');
                        plot(linspace(0,(max(theta)),numel(y)), y(1)*ones(size(y)), '-k','LineWidth',2);
                        plot(linspace(0,(max(theta)),numel(y)), y , 'o','MarkerSize',10);
                        plot(theta, y,'Marker', 'd','MarkerSize',10);
                        ylabel('Band intensity');
                        yyaxis right
                        plot(theta, [nan diff(theta)-median(diff(theta))], '-d','MarkerSize',10);

                        legend({'Start Intensity','Linspace 0-max fit','cos^2 fit','Angle between measurements'})
                        ylabel('\delta-median(\delta) °');
                        xlabel('polarisation angle (deg)');
                        title(['cos^2 Fit to Polarisation Data - ' num2str(selected_band)]);

                        [ ~, ix ] = min(abs(x_signal-max(selected_band)));
                        y=movmean(y_signal(ix,:),4);
                        B = min(y);
                        A = max(y) - min(y);


                        theta_calc = acosd( sqrt( (y - B) ./ A ) );
                        theta=[0 cumsum(abs(diff(theta_calc)))];

                    end
                end

                eId=find(ismember([app.SpecDB.Data.Meta.Id],app.Data.selections(k)));
                app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupStart=app.minEditField.Value;
                app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupEnd=app.maxEditField.Value;
                app.SpecDB.Data.MetaPrivat(eId).PolSettings.PolGroupShift=app.ShiftEditField.Value;


                app.minEditField.Value=1;%mode(start_val);
                app.ShiftEditField.Value=0;%mode(shift_val);
                %   app.maxEditField.Value=mode(end_val);
            end

        end

        % Button down function: PeakTable
        function PeakTableButtonDown(app, event)
            if isempty(app.PeakTable.Selection)
                return
            end
            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else

                time_between_clicks = toc( app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    c = uisetcolor;  % Enter desired callback / action here.
                    figure(app.PolPlotGUI)
                    %    app.PeakTable.Data.rgb(app.PeakTable.Selection(1,:))=repmat(c',1,numel(unique(app.PeakTable.Selection(1,:))));

                    indices=unique(app.PeakTable.Selection(:,1));


                    for n=1:numel(indices)
                        if strcmp(app.PeakTable.Data.Type(indices(n)),'UI_Peaks')
                            app.Data.Peaks.UI_Peaks_color(ismember(app.Data.Peaks.UI_Peaks,app.PeakTable.Data.Position(indices(n))),:)=c;
                        elseif strcmp(app.PeakTable.Data.Type(indices(n)),'AutoPeaks')
                            app.Data.Peaks.AutoPeaks_color(ismember(app.Data.Peaks.AutoPeaks,app.PeakTable.Data.Position(indices(n))),:)=c;
                        else

                            checkedNodes = app.PeakTree.CheckedNodes;

                            if not(isempty(checkedNodes))
                                Data_thx={checkedNodes.NodeData};
                                test=  cellfun(@iscell,Data_thx);
                                Data_=Data_thx(test);

                            end
                            app.PeakTable.Data(indices,:).Position
                            logic_test1=false(numel(Data_),1);
                            for p=1:numel(Data_)
                                logic_test1(p)=isequal(app.PeakTable.Data(indices,:).Position,Data_{p}{2});
                            end

                            if sum(logic_test1)>1
                                Data_=Data_(logic_test1);
                                for p=1:numel(Data_)
                                    logic_test1(p)=isequal(app.PeakTable.Data(indices,:).Type,Data_{p}{3});
                                end

                            end
                            if sum(logic_test1)==1
                                idx=Data_{logic_test1}{1};
                                if numel(idx)==3
                                    idx(4)=1;
                                end
                                app.Peaks.PeakDB.Mineral(idx(1)).Groups(idx(2)).Peaks(idx(3)).R(idx(4))=c(1);
                                app.Peaks.PeakDB.Mineral(idx(1)).Groups(idx(2)).Peaks(idx(3)).G(idx(4))=c(2);
                                app.Peaks.PeakDB.Mineral(idx(1)).Groups(idx(2)).Peaks(idx(3)).B(idx(4))=c(3);
                            end

                            
                        end
                    end

                    %  end
                end % if double-click
                update_peaktable_fun(app)
            end
        end

        % Value changed function: ColormapPreviewCheckBox
        function ColormapPreviewCheckBoxValueChanged(app, event)
            update_peaktable_fun(app)

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir;
            delete(f)
            if not(isequal(path,0))
                app.PathEditField.Value=path;
                app.PathEditField.Tooltip=app.PathEditField.Value;
            end
            figure(app.PolPlotGUI);
        end

        % Button pushed function: ReloadPeakDBButton
        function ReloadPeakDBButtonPushed(app, event)
            path=which('PeakDB.xlsm'); %ATH deploy

            app.Peaks.PeakDB=ReadPeakdata_fun(path);
            update_PeakTree(app)
            update_peaktable_fun(app)


        end

        % Callback function: PeakTree
        function PeakTreeCheckedNodesChanged(app, event)

            update_peaktable_fun(app)
            update_spectra_plot(app)

        end

        % Value changed function: ShiftEditField, maxEditField, 
        % minEditField
        function PolGroupSetValueChanged(app, event)
            eId=find(ismember([app.SpecDB.Data.Meta.Id],app.Data.selections));
            for n=1:numel(eId)
                app.SpecDB.Data.MetaPrivat(eId(n)).PolSettings.PolGroupStart=app.minEditField.Value;
                app.SpecDB.Data.MetaPrivat(eId(n)).PolSettings.PolGroupEnd=app.maxEditField.Value;
                app.SpecDB.Data.MetaPrivat(eId(n)).PolSettings.PolGroupShift=app.ShiftEditField.Value;
            end

        end

        % Button pushed function: DevButton
        function DevButtonPushed(app, event)
keyboard
        end

        % Cell edit callback: PolTable
        function PolTableCellEdit(app, event)
                      
            eId=find(ismember([app.SpecDB.Data.Meta.Id],app.Data.selections));

            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolPlotGUI = uifigure('Visible', 'off');
            app.PolPlotGUI.Name = 'PolPlotModule';
            app.GridLayout = uigridlayout(app.PolPlotGUI);
            app.UIAxes = uiaxes(app.GridLayout);
            app.SpecTable = uitable(app.GridLayout);
            app.SpecTable.ColumnName = {'Id'; 'Group'; 'Pos'; 'Column 4'};
            app.SpecTable.RowName = {};
            app.SpecTable.CellSelectionCallback = createCallbackFcn(app, @SpecTableCellSelection, true);
            app.PlotBandsButton = uibutton(app.GridLayout, 'push');
            app.PlotBandsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBandsButtonPushed, true);
            app.PlotBandsButton.Text = 'Plot Bands';
            app.PolSetDropDownLabel = uilabel(app.GridLayout);
            app.PolSetDropDownLabel.Visible = 'off';
            app.PolSetDropDownLabel.Text = 'PolSet';
            app.PolSetDropDown = uidropdown(app.GridLayout);
            app.PolSetDropDown.Items = {'0-180', '0-360', 'custom'};
            app.PolSetDropDown.Visible = 'off';
            app.PolSetDropDown.Value = '0-180';
            app.minEditFieldLabel = uilabel(app.GridLayout);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout, 'numeric');
            app.minEditField.Limits = [1 Inf];
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @PolGroupSetValueChanged, true);
            app.minEditField.Value = 1;
            app.maxEditFieldLabel = uilabel(app.GridLayout);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @PolGroupSetValueChanged, true);
            app.maxEditField.Value = 180;
            app.EditField_xlimMin = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMinValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShiftEditFieldLabel = uilabel(app.GridLayout);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @PolGroupSetValueChanged, true);
            app.Cos2fitButton = uibutton(app.GridLayout, 'push');
            app.Cos2fitButton.ButtonPushedFcn = createCallbackFcn(app, @Cos2fitButtonPushed, true);
            app.Cos2fitButton.Visible = 'off';
            app.Cos2fitButton.Text = 'Cos2 fit';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DetectButton = uibutton(app.GridLayout, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.PresetDropDownLabel = uilabel(app.GridLayout);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.GridLayout);
            app.PresetDropDown.Items = {'Custom', 'CPX', 'Diopside', 'Omphacite', 'OPX', 'OPX Pakistan', 'Olivine'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Value = 'Custom';
            app.nHistLabel = uilabel(app.GridLayout);
            app.nHistLabel.Text = 'nHist';
            app.nHistEditField = uieditfield(app.GridLayout, 'numeric');
            app.nHistEditField.Limits = [5 10000];
            app.nHistEditField.Value = 250;
            app.PeakTable = uitable(app.GridLayout);
            app.PeakTable.ColumnName = {'Active'; 'Position'; 'Type'; 'RGB'};
            app.PeakTable.RowName = {};
            app.PeakTable.ColumnSortable = [true true true true];
            app.PeakTable.ColumnEditable = [true false false false];
            app.PeakTable.CellEditCallback = createCallbackFcn(app, @PeakTableCellEdit, true);
            app.PeakTable.ButtonDownFcn = createCallbackFcn(app, @PeakTableButtonDown, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PeakSettingsTab = uitab(app.TabGroup);
            app.PeakSettingsTab.Title = 'PeakSettings';
            app.GridLayout12 = uigridlayout(app.PeakSettingsTab);
            app.PeakTree = uitree(app.GridLayout12, 'checkbox');
            app.PeakTree.Editable = 'on';
            app.PeakTree.Tooltip = {'Load SpecDB Project'};
            % Assign Checked Nodes
            app.PeakTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PeakTreeCheckedNodesChanged, true);
            app.RangeSettingsTab = uitab(app.TabGroup);
            app.RangeSettingsTab.Title = 'Range Settings';
            app.GridLayout10 = uigridlayout(app.RangeSettingsTab);
            app.DevButton = uibutton(app.GridLayout10, 'push');
            app.DevButton.ButtonPushedFcn = createCallbackFcn(app, @DevButtonPushed, true);
            app.DevButton.Visible = 'off';
            app.DevButton.Text = 'DevButton';
            app.OvertoneEditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.OvertoneEditField_2.Value = 2300;
            app.OHEditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.OHEditField_2.Value = 3720;
            app.OvertoneEditFieldLabel = uilabel(app.GridLayout10);
            app.OvertoneEditFieldLabel.Text = 'Overtone';
            app.OvertoneEditField = uieditfield(app.GridLayout10, 'numeric');
            app.OvertoneEditField.Value = 1400;
            app.OHEditFieldLabel = uilabel(app.GridLayout10);
            app.OHEditFieldLabel.Text = 'OH';
            app.OHEditField = uieditfield(app.GridLayout10, 'numeric');
            app.OHEditField.Value = 3050;
            app.H2OOTratioEditFieldLabel = uilabel(app.GridLayout10);
            app.H2OOTratioEditFieldLabel.Text = 'H2O / OT ratio';
            app.H2OOTratioEditField = uieditfield(app.GridLayout10, 'numeric');
            app.H2OOTratioEditField.Value = 0.5;
            app.DeconSettingsTab = uitab(app.TabGroup);
            app.DeconSettingsTab.Title = 'DeconSettings';
            app.GridLayout9 = uigridlayout(app.DeconSettingsTab);
            app.FitShapeDropDownLabel = uilabel(app.GridLayout9);
            app.FitShapeDropDownLabel.Text = 'Fit Shape';
            app.FitShapeDropDown = uidropdown(app.GridLayout9);
            app.FitShapeDropDown.Items = {'1=Gaussian (default)', '2=Lorentzian', '3=logistic distribution', '4=Pearson', '5=exponentionally broadened Gaussian;', '6=equal-width Gaussians', '7=Equal-width Lorentzians', '8=exponentionally broadened equal-width Gaussian', '9=exponential pulse', '10=up-sigmoid (logistic function)', '11=Fixed-width Gaussian', '12=Fixed- width Lorentzian;', '13=Gaussian/Lorentzian blend ', '14=Bifurcated Gaussian,', '15=Breit-Wigner-Fano', '16=Fixed-position Gaussians', '17=Fixed-position Lorentzians', '18=exponentionally broadened Lorentzian', '19=alpha function; ', '20=Voigt profile', '21=triangular', '22=?plus extra?', '23=down-sigmoid', '24=Negative Binomial Distribution', '25=lognormal', '26=slope;', '27=Gaussian first derivative', '28=polynomial', '29=piecewise linear;', '30=variable-alpha Voigt', '31=variable time constant ExpGaussian;', '32=variable shape Pearson', '33=variable Gaussian/Lorentzian blend,', '34=fixed-width Voigt', '35=fixed-width Gaussian/Lorentzian blend; ', '36=fixed-width exponentionally-broadened Gaussian', '37=fixed-width Pearson', '38=variable time constant ExpLorentzian', '39=variable timeconstant ExpGaussian2', '40=equal-width GL blend', '41=rectangle;', '42=flattened Gaussian', '43:PearsonIV function (3 variables);', '44=1-exp(-k*x)', '45 Four-parameter logistic', '46=quadratic/parabola;', '47=blackbody emission', '48=equal-width exponential pulses', '49=Pearson IV (variable asymmetrical peak)', '50=multilinear regression (known peak positions and widths)'};
            app.FitShapeDropDown.Value = '1=Gaussian (default)';
            app.IterationsSpinnerLabel = uilabel(app.GridLayout9);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.GridLayout9);
            app.IterationsSpinner.Limits = [0 Inf];
            app.IterationsSpinner.Value = 20;
            app.AutozeroSpinnerLabel = uilabel(app.GridLayout9);
            app.AutozeroSpinnerLabel.Text = 'Autozero';
            app.AutozeroSpinner = uispinner(app.GridLayout9);
            app.AutozeroSpinner.Limits = [0 3];
            app.MCSimulateSettingsButton = uibutton(app.GridLayout9, 'push');
            app.MCSimulateSettingsButton.Text = 'MC Simulate Settings';
            app.IterationsSpinnerLabel_2 = uilabel(app.GridLayout9);
            app.IterationsSpinnerLabel_2.Text = 'MC Iterations';
            app.MCIterationsSpinner = uispinner(app.GridLayout9);
            app.MCIterationsSpinner.Limits = [0 Inf];
            app.MCIterationsSpinner.Value = 25;
            app.PlotResultsCheckBox = uicheckbox(app.GridLayout9);
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.DELTAEditFieldLabel = uilabel(app.GridLayout9);
            app.DELTAEditFieldLabel.Text = 'DELTA';
            app.DELTAEditField = uieditfield(app.GridLayout9, 'numeric');
            app.DELTAEditField.Value = 1;
            app.ExtraEditFieldLabel = uilabel(app.GridLayout9);
            app.ExtraEditFieldLabel.Text = 'Extra';
            app.ExtraEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LimitShiftCheckBox = uicheckbox(app.GridLayout9);
            app.LimitShiftCheckBox.Text = 'Limit Shift';
            app.DeconLimMaxEditFieldLabel = uilabel(app.GridLayout9);
            app.DeconLimMaxEditFieldLabel.Text = 'DeconLimMax';
            app.DeconLimMaxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.DeconLimMaxEditField.Value = 3720;
            app.DeconLimMaxEditField_2Label = uilabel(app.GridLayout9);
            app.DeconLimMaxEditField_2Label.Text = 'DeconLimMax';
            app.DeconLimMinEditField = uieditfield(app.GridLayout9, 'numeric');
            app.DeconLimMinEditField.Value = 3235;
            app.MixedDeconPlotButton = uibutton(app.GridLayout9, 'push');
            app.MixedDeconPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MixedDeconPlotButtonPushed, true);
            app.MixedDeconPlotButton.Text = 'Mixed Decon Plot';
            app.MultiDeconPlotButton = uibutton(app.GridLayout9, 'push');
            app.MultiDeconPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MultiDeconPlotButtonPushed, true);
            app.MultiDeconPlotButton.Text = 'Multi Decon Plot';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout11 = uigridlayout(app.ExportTab);
            app.PathEditFieldLabel = uilabel(app.GridLayout11);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.GridLayout11, 'text');
            app.PathEditField.Tooltip = {''};
            app.SelectButton = uibutton(app.GridLayout11, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SavePlotCheckBox = uicheckbox(app.GridLayout11);
            app.SavePlotCheckBox.Text = 'Save Plot';
            app.CheckBox_fig_exp = uicheckbox(app.GridLayout11);
            app.CheckBox_fig_exp.Text = '.fig';
            app.CheckBox_png_exp = uicheckbox(app.GridLayout11);
            app.CheckBox_png_exp.Text = '.png';
            app.CheckBox_pdf_exp = uicheckbox(app.GridLayout11);
            app.CheckBox_pdf_exp.Text = '.pdf';
            app.CheckBox_pdf_exp.Value = true;
            app.CheckBox_eps_exp = uicheckbox(app.GridLayout11);
            app.CheckBox_eps_exp.Text = '.eps';
            app.CheckBox_tif_exp = uicheckbox(app.GridLayout11);
            app.CheckBox_tif_exp.Text = '.tif';
            app.CloseFiguresafterexportCheckBox = uicheckbox(app.GridLayout11);
            app.CloseFiguresafterexportCheckBox.Text = 'Close Figures after export';
            app.CustStrEditFieldLabel = uilabel(app.GridLayout11);
            app.CustStrEditFieldLabel.Text = 'CustStr';
            app.CustStrEditField = uieditfield(app.GridLayout11, 'text');
            app.CustStrEditField.Tooltip = {''};
            app.ReloadPeakDBButton = uibutton(app.GridLayout, 'push');
            app.ReloadPeakDBButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadPeakDBButtonPushed, true);
            app.ReloadPeakDBButton.Text = 'Reload PeakDB';
            app.nPlotsRowEditFieldLabel = uilabel(app.GridLayout);
            app.nPlotsRowEditFieldLabel.Text = 'n Plots / Row';
            app.nPlotsRowEditField = uieditfield(app.GridLayout, 'numeric');
            app.nPlotsRowEditField.Value = 5;
            app.ColormapPreviewCheckBox = uicheckbox(app.GridLayout);
            app.ColormapPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColormapPreviewCheckBoxValueChanged, true);
            app.ColormapPreviewCheckBox.Text = 'Colormap Preview';
            app.PolTable = uitable(app.GridLayout);
            app.PolTable.ColumnName = {'Active'; 'Id'; 'Angle'};
            app.PolTable.RowName = {};
            app.PolTable.ColumnSortable = true;
            app.PolTable.ColumnEditable = [true false true];
            app.PolTable.CellEditCallback = createCallbackFcn(app, @PolTableCellEdit, true);
            app.ResetButton_2 = uibutton(app.GridLayout, 'push');
            app.ResetButton_2.Visible = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.First_and_Last_same = uicheckbox(app.GridLayout);
            app.First_and_Last_same.Text = 'First==Last (0 and 180)';
            app.First_and_Last_same.Value = true;
            % Show the figure after all components are created
            app.PolPlotGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolPlotModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolPlotGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolPlotGUI)
        end
    end
end