classdef ReshapeModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReshapeModuleUIFigure     matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ReshapeDButton            matlab.ui.control.Button
        ReshapeAButton            matlab.ui.control.Button
        ReshapeCButton            matlab.ui.control.Button
        ReshapeBButton            matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        SpectraTypeDropDown       matlab.ui.control.DropDown
        SpectraTypeDropDownLabel  matlab.ui.control.Label
        IntRangeEditField         matlab.ui.control.NumericEditField
        IntRangeEditFieldLabel    matlab.ui.control.Label
        SetPixelSizeButton        matlab.ui.control.Button
        ConfirmXYRatioButton      matlab.ui.control.Button
        NXTilesSpinner            matlab.ui.control.Spinner
        NXTilesSpinnerLabel       matlab.ui.control.Label
        NYTilesSpinner            matlab.ui.control.Spinner
        NYTilesSpinnerLabel       matlab.ui.control.Label
        XPxTileSpinner            matlab.ui.control.Spinner
        XPxTileSpinnerLabel       matlab.ui.control.Label
        YPxTileSpinner            matlab.ui.control.Spinner
        YPxTileSpinnerLabel       matlab.ui.control.Label
        ImportMetadataButton      matlab.ui.control.Button
        IntRangeEditField2        matlab.ui.control.NumericEditField
        info_table                matlab.ui.control.Table
        ListBox                   matlab.ui.control.ListBox
        CancleButton              matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
        FlipXButton               matlab.ui.control.Button
        FlipYButton               matlab.ui.control.Button
        Rotate90Button            matlab.ui.control.Button
        Rotate90Button_2          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpecMaps
        x_signal
        y_signal_raw
        y_signal% Description
        mapsize_raw
        mapsize_raw_ini
        MatrixLimits
        MatrixLimits_ini
        sampleinfo
        reset_state_pixelsize=true
        reset_state_MatrixLimits=true
        pixelsize

        UI_MatrixLimits
        UI_pixelsize

        cmap_available
        ColorMapValues

        PossibleDivisions
        

    end

    methods (Access = private)

        function  UpdateMapPlot(app)
            region= sort([app.IntRangeEditField.Value app.IntRangeEditField2.Value]);
            if min(region)<min(app.x_signal) || min(region)>max(app.x_signal)
                region=[min(app.x_signal) max(region)];
                app.IntRangeEditField2.Value=min(app.x_signal);
            end
            if max(region)>max(app.x_signal) || max(region)<min(app.x_signal)
                region=[min(region) max(app.x_signal)];
                app.IntRangeEditField.Value=max(app.x_signal);
            end
            % end
            % is within app.wn;else region= [min(app.wn)...
            % max(app.wn)]
            integration=trapz(app.y_signal(app.x_signal>min(region)&app.x_signal<max(region),:));

            matrix=reshape(integration,[],app.mapsize_raw(2))';
            matrix(isinf(matrix))=0;
            cla(app.UIAxes)
            if app.cmap_available==true
                app.UIAxes.Colormap=app.ColorMapValues;
            end

            if any(any(isnan(app.MatrixLimits(1,:))))
                app.MatrixLimits=[ 0 1; 0 1];
                ResetButtonPushed(app)
            end

            imagesc(app.UIAxes,matrix,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:));

            app.UIAxes.XLim=(app.MatrixLimits(1,:));
            app.UIAxes.YLim=(app.MatrixLimits(2,:));

            try
                caxis(app.UIAxes,[min(min(matrix)) std2(matrix)+mean(matrix,'all','omitnan')])
            catch
                caxis(app.UIAxes,'auto')
            end
            axis(app.UIAxes, 'image')

        end

        function  update_info_table(app)
            app.mapsize_raw(1)=app.PossibleDivisions(2,app.ListBox.Value)';
            app.mapsize_raw(2)=app.PossibleDivisions(3,app.ListBox.Value)';


            if isempty(app.MatrixLimits) && isempty(app.pixelsize)
                app.MatrixLimits=[0,app.mapsize_raw(1);0 app.mapsize_raw(2)];
                app.reset_state_MatrixLimits=false;

                X=diff(app.MatrixLimits(1,:))./app.mapsize_raw(1) ;
                Y=diff(app.MatrixLimits(2,:))./app.mapsize_raw(2);
                app.pixelsize=[X Y];
                app.reset_state_pixelsize=false;
            end

            if isempty(app.MatrixLimits) || app.reset_state_MatrixLimits==true
                app.MatrixLimits=[0,app.mapsize_raw(1);0 app.mapsize_raw(2)];
                app.reset_state_MatrixLimits=false;
            end

            if isempty(app.pixelsize) || app.reset_state_pixelsize==true
               app.UI_pixelsize=[];
                X=diff(app.MatrixLimits(1,:))./app.mapsize_raw(1) ;
                Y=diff(app.MatrixLimits(2,:))./app.mapsize_raw(2);
                app.pixelsize=[X Y];
                app.reset_state_pixelsize=false;
            end

            if not(isempty(app.UI_pixelsize))
                app.pixelsize=(app.UI_pixelsize);
                app.MatrixLimits=[app.MatrixLimits(:,1) app.MatrixLimits(:,2).*app.pixelsize'];
            end

      app.info_table.Data=[app.MatrixLimits';app.pixelsize];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x_signal, y_signal, sampleinfo, mapsize, MatrixLimits, type, cmap, FPA_Px_tile, FPA_tiles)
            app.SpecMaps=mainapp;

            app.x_signal=x_signal;
            app.y_signal_raw=y_signal;
            app.y_signal=y_signal;
            app.sampleinfo=sampleinfo;
            
            Datapoints=size(y_signal,2);

            c=Datapoints./(1:Datapoints);
            c=c(mod(c,1)==0);

            app.PossibleDivisions = [1:length(c);c; fliplr(c)]; % 1=index - 2=x - 3=y

             app.ListBox.Items=cellstr([num2str( app.PossibleDivisions(1,:)') repmat(') X: ',size( app.PossibleDivisions(1,:),2),1)  num2str( app.PossibleDivisions(2,:)') repmat(' / Y: ',size( app.PossibleDivisions(1,:),2),1) num2str( app.PossibleDivisions(3,:)')]);
            app.ListBox.ItemsData=app.PossibleDivisions(1,:);
            app.ListBox.Value=app.ListBox.ItemsData(round(numel(app.ListBox.Items)/2));

            if exist("mapsize","var") && not(isempty(mapsize))
                app.mapsize_raw_ini=mapsize;
                app.mapsize_raw=mapsize;
                if ismember(app.PossibleDivisions(2:3,:)',app.mapsize_raw_ini,'row')
                    app.ListBox.Value=app.ListBox.ItemsData(ismember(app.PossibleDivisions(2:3,:)',app.mapsize_raw_ini,'row'));
                end
            else
                app.mapsize_raw=[];

            end
        
            if exist("MatrixLimits","var") && not(isempty(MatrixLimits))
                app.MatrixLimits=MatrixLimits;
                app.reset_state_MatrixLimits=false;
                app.reset_state_pixelsize=false;
                app.MatrixLimits_ini=MatrixLimits;
            else

                app.MatrixLimits=[];
                            ListBoxValueChanged(app)

            end

            if exist("cmap","var")
                app.cmap_available=true;
                app.ColorMapValues=cmap;
            else
                app.cmap_available=false;

            end

            app.info_table.RowName={'min' 'max' 'Pixel Size'};
              

            if exist("type","var") && not(isequal(type,0))% && isempty(type,{'FTIR-MCT','FTIR-FPA','other'})
                app.SpectraTypeDropDown.Value=type;
                if str2double(type)==200 && not(isempty(FPA_tiles)) %'FTIR-FPA') && not(isempty(FPA_tiles))
                    app.NXTilesSpinner.Value=FPA_tiles(1);
                    app.NYTilesSpinner.Value=FPA_tiles(2);
                end
                if str2double(type)==200  && not(isempty(FPA_Px_tile))
                    app.XPxTileSpinner.Value=FPA_Px_tile(1);
                    app.YPxTileSpinner.Value=FPA_Px_tile(2);
                end
                SpectraTypeDropDownValueChanged(app)
                app.ConfirmXYRatioButton.Visible=true;
            end
            
            update_info_table(app)
            UpdateMapPlot(app)

        end

        % Button pushed function: ImportMetadataButton
        function ImportMetadataButtonPushed(app, event)
         %   return2SpecMaps('import_metadata')
            delete(app)
        end

        % Button pushed function: CancleButton
        function CancleButtonPushed(app, event)
delete(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)


            switch app.SpectraTypeDropDown.Items{ismember(app.SpectraTypeDropDown.ItemsData,app.SpectraTypeDropDown.Value)}
                case 'FTIR-FPA'
                    pxx=app.XPxTileSpinner.Value;
                    pxy=app.YPxTileSpinner.Value;
                    nx=app.NXTilesSpinner.Value;
                    ny=app.NYTilesSpinner.Value;
                    test= isequal((nx*pxx)*(ny*pxy),app.mapsize_raw(1)*app.mapsize_raw(2));
                    test1=isequal([nx*pxx ny*pxy],app.mapsize_raw);
                    if not(test1 && test)
                        message = {'Number of datapoints does not match set number of tyles and pixel per tile'};
                        uialert(app.ReshapeModuleUIFigure,message,'Something is wrong!','Icon','error');
                        return
                    end
                    app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=200;

                    app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).Settings(app.sampleinfo(2)).FPA_Px_tile=[app.XPxTileSpinner.Value app.YPxTileSpinner.Value];
                    app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).Settings(app.sampleinfo(2)).FPA_tiles=[app.NXTilesSpinner.Value app.NYTilesSpinner.Value];
                
                    MetaTable=table();
                    MetaTable.x=app.MatrixLimits(1,1)+(range(app.MatrixLimits(1,:))/2);
                    MetaTable.y=app.MatrixLimits(2,1)+(range(app.MatrixLimits(2,:))/2);
                    MetaTable.CX=range(app.MatrixLimits(1,:));
                    MetaTable.CY=range(app.MatrixLimits(2,:));
                    MetaTable.NX=app.NXTilesSpinner.Value;
                    MetaTable.NY=app.NYTilesSpinner.Value;

                    MetaTable.Group=ones(size(MetaTable.y));
                    MetaTable.Type=9;
                                    app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).Settings(:).PixelSize=[];

                otherwise
                    switch app.SpectraTypeDropDown.Items{ismember(app.SpectraTypeDropDown.ItemsData,app.SpectraTypeDropDown.Value)}


                        case 'FTIR-MCT'
                            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=100;
                        case 'Raman'
                            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=300;
                        case 'HyperDC'
                            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=400;
                        case 'other'
                            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=500;
                        case 'other (reverse)'
                            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.SpecType=501;
                    end
                    x=fliplr(linspace(app.MatrixLimits(1,1),app.MatrixLimits(1,2),app.mapsize_raw(2)));
                    y=fliplr(linspace(app.MatrixLimits(2,1),app.MatrixLimits(2,2),app.mapsize_raw(1)));

                    [x_pos,y_pos] = meshgrid(x,y);

                    MetaTable=array2table([(1:numel(x_pos(:)))' x_pos(:) y_pos(:) ones(size(x_pos(:))) ones(size(x_pos(:)))],"VariableNames",{'Fileindex','x','y','Group','Type'});

                    if not(isempty(app.pixelsize))
                app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).Settings(:).PixelSize=app.pixelsize;
            end
            end

            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.MetaTable=MetaTable;


            app.SpecMaps.SpecMap.Spectra.Sample(app.sampleinfo(1)).MetaPrivat.mapsize_raw=app.mapsize_raw;
            

            update_SpecMaps_public(app.SpecMaps)
            delete(app)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.OKButton.Enable=false;

         %   app.reset_state_MatrixLimits=true;
            update_info_table(app)
            UpdateMapPlot(app)
        end

        % Value changed function: SpectraTypeDropDown
        function SpectraTypeDropDownValueChanged(app, event)
          value = str2double(app.SpectraTypeDropDown.Value);
            if value==0
                app.ImportMetadataButton.Visible=true;

            elseif value==200% 'FTIR-FPA')
                state1=true;
                app.ConfirmXYRatioButton.Visible=state1;

                app.ImportMetadataButton.Visible=not(state1);
                app.XPxTileSpinner.Visible=state1;
                app.XPxTileSpinnerLabel.Visible=state1;
                app.YPxTileSpinner.Visible=state1;
                app.YPxTileSpinnerLabel.Visible=state1;


                app.NXTilesSpinner.Visible=state1;
                app.NXTilesSpinnerLabel.Visible=state1;
                app.NYTilesSpinner.Visible=state1;
                app.NYTilesSpinnerLabel.Visible=state1;
            else
                state1=false;

                app.ImportMetadataButton.Visible=state1;
                app.ConfirmXYRatioButton.Visible=not(state1);
                app.XPxTileSpinner.Visible=state1;
                app.XPxTileSpinnerLabel.Visible=state1;
                app.YPxTileSpinner.Visible=state1;
                app.YPxTileSpinnerLabel.Visible=state1;

                app.NXTilesSpinner.Visible=state1;
                app.NXTilesSpinnerLabel.Visible=state1;
                app.NYTilesSpinner.Visible=state1;
                app.NYTilesSpinnerLabel.Visible=state1;

            end
        end

        % Button pushed function: ConfirmXYRatioButton
        function ConfirmXYRatioButtonPushed(app, event)

            if str2double(app.SpectraTypeDropDown.Value)==200%'FTIR-FPA')
                pxx=app.XPxTileSpinner.Value;
                pxy=app.YPxTileSpinner.Value;
                nx=app.NXTilesSpinner.Value;
                ny=app.NYTilesSpinner.Value;
                test= isequal((nx*pxx)*(ny*pxy),app.mapsize_raw(1)*app.mapsize_raw(2));
                test1=isequal([nx*pxx ny*pxy],app.mapsize_raw);
                if not(test1 && test)
                    message = {'Number of datapoints does not match set number of tyles and pixel per tile'};
                    uialert(app.ReshapeModuleUIFigure,message,'Peak Deconvolution Done','Icon','error');
                    app.OKButton.Enable=false;
                    return
                end
                app.reset_state_pixelsize=true;
                update_info_table(app)

            end

            if not(isempty(app.pixelsize))
                app.OKButton.Enable=true;
            end

        end

        % Button pushed function: SetPixelSizeButton
        function SetPixelSizeButtonPushed(app, event)
            definput = {'1','1'};
            x = inputdlg({'X', 'Y'}, 'Set Pixel Size ',[1 15; 1 15],definput);
            X=str2double(x(1));
            Y=str2double(x(2));
            app.UI_pixelsize=[X Y];
            app.reset_state_MatrixLimits=true;
            update_info_table(app)
            UpdateMapPlot(app)
        end

        % Value changed function: IntRangeEditField
        function IntRangeEditFieldValueChanged(app, event)
            UpdateMapPlot(app)
        end

        % Cell edit callback: info_table
        function info_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(1)==1
                old_vals=app.MatrixLimits(indices(2),:);
                app.MatrixLimits(indices(2),:)=app.MatrixLimits(indices(2),:)+newData-old_vals(1);
                app.reset_state_pixelsize=true;
            elseif indices(1)==2
                old_vals=app.MatrixLimits(indices(2),:);
                app.MatrixLimits(indices(2),:)=app.MatrixLimits(indices(2),:)+newData-old_vals(2);
                app.reset_state_pixelsize=true;
            elseif indices(1)==3
                if isempty(app.UI_pixelsize)
                    app.UI_pixelsize=app.pixelsize;
                end
                app.UI_pixelsize(indices(2))=newData;
                app.reset_state_MatrixLimits=true;
            end
            update_info_table(app)
            UpdateMapPlot(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset_state_MatrixLimits=true;
            app.reset_state_pixelsize=true;
            app.y_signal=app.y_signal_raw;

            if ismember(app.PossibleDivisions(2:3,:)',app.mapsize_raw_ini,'row')
                app.ListBox.Value=app.ListBox.ItemsData(ismember(app.PossibleDivisions(2:3,:)',app.mapsize_raw_ini,'row'));
            end

                if exist("MatrixLimits_ini","var") && not(isempty(app.MatrixLimits_ini))
app.MatrixLimits=app.MatrixLimits_ini;
                                app.reset_state_MatrixLimits=fakse;

                end

            update_info_table(app)
            UpdateMapPlot(app)
        end

        % Button pushed function: ReshapeBButton
        function ReshapeBButtonPushed(app, event)
          thx=reshape((1:app.mapsize_raw(1)*app.mapsize_raw(2)),[],app.mapsize_raw(2))';
            app.y_signal=app.y_signal(:,thx(:));

            update_info_table(app)
            UpdateMapPlot(app)
        end

        % Button pushed function: ReshapeAButton
        function ReshapeAButtonPushed(app, event)
             thx=reshape((1:app.mapsize_raw(1)*app.mapsize_raw(2)),[],app.mapsize_raw(1))';
            app.y_signal=app.y_signal(:,thx(:));

            update_info_table(app)
            UpdateMapPlot(app)       
        end

        % Button pushed function: ReshapeCButton
        function ReshapeCButtonPushed(app, event)
          thx=reshape((1:app.mapsize_raw(1)*app.mapsize_raw(2)),[],app.mapsize_raw(1));
            thx(1:2:end,:)=flipud(thx(1:2:end,:));

            app.y_signal=app.y_signal(:,thx(:));

            update_info_table(app)
            UpdateMapPlot(app)     
            
        end

        % Button pushed function: ReshapeDButton
        function ReshapeDButtonPushed(app, event)
              thx=reshape((1:app.mapsize_raw(1)*app.mapsize_raw(2)),[],app.mapsize_raw(1))';
            thx(1:2:end,:)=fliplr(thx(1:2:end,:));

            app.y_signal=app.y_signal(:,thx(:));

            update_info_table(app)
            UpdateMapPlot(app)     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReshapeModuleUIFigure = uifigure('Visible', 'off');
            app.ReshapeModuleUIFigure.Name = 'ReshapeModule';
            app.GridLayout = uigridlayout(app.ReshapeModuleUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Preview')
            zlabel(app.UIAxes, 'Z')
            app.Rotate90Button_2 = uibutton(app.GridLayout, 'push');
            app.Rotate90Button_2.Visible = 'off';
            app.Rotate90Button_2.Text = 'Rotate 90';
            app.Rotate90Button = uibutton(app.GridLayout, 'push');
            app.Rotate90Button.Visible = 'off';
            app.Rotate90Button.Text = 'Rotate -90';
            app.FlipYButton = uibutton(app.GridLayout, 'push');
            app.FlipYButton.Visible = 'off';
            app.FlipYButton.Text = 'Flip Y';
            app.FlipXButton = uibutton(app.GridLayout, 'push');
            app.FlipXButton.Visible = 'off';
            app.FlipXButton.Text = 'Flip X';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.CancleButton = uibutton(app.GridLayout, 'push');
            app.CancleButton.ButtonPushedFcn = createCallbackFcn(app, @CancleButtonPushed, true);
            app.CancleButton.Text = 'Cancle';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.info_table = uitable(app.GridLayout);
            app.info_table.ColumnName = {'x'; 'y'};
            app.info_table.RowName = {'min'; 'max'; 'Pixel Size'};
            app.info_table.ColumnEditable = [true true];
            app.info_table.CellEditCallback = createCallbackFcn(app, @info_tableCellEdit, true);
            app.IntRangeEditField2 = uieditfield(app.GridLayout, 'numeric');
            app.IntRangeEditField2.Value = 3050;
            app.ImportMetadataButton = uibutton(app.GridLayout, 'push');
            app.ImportMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMetadataButtonPushed, true);
            app.ImportMetadataButton.Text = 'Import Metadata';
            app.YPxTileSpinnerLabel = uilabel(app.GridLayout);
            app.YPxTileSpinnerLabel.Visible = 'off';
            app.YPxTileSpinnerLabel.Text = 'Y Px/Tile';
            app.YPxTileSpinner = uispinner(app.GridLayout);
            app.YPxTileSpinner.Limits = [0 Inf];
            app.YPxTileSpinner.Visible = 'off';
            app.YPxTileSpinner.Value = 64;
            app.XPxTileSpinnerLabel = uilabel(app.GridLayout);
            app.XPxTileSpinnerLabel.Visible = 'off';
            app.XPxTileSpinnerLabel.Text = 'X Px/Tile';
            app.XPxTileSpinner = uispinner(app.GridLayout);
            app.XPxTileSpinner.LowerLimitInclusive = 'off';
            app.XPxTileSpinner.Limits = [0 Inf];
            app.XPxTileSpinner.Visible = 'off';
            app.XPxTileSpinner.Value = 64;
            app.NYTilesSpinnerLabel = uilabel(app.GridLayout);
            app.NYTilesSpinnerLabel.Visible = 'off';
            app.NYTilesSpinnerLabel.Text = 'NY Tiles';
            app.NYTilesSpinner = uispinner(app.GridLayout);
            app.NYTilesSpinner.Limits = [0 Inf];
            app.NYTilesSpinner.Visible = 'off';
            app.NYTilesSpinner.Value = 1;
            app.NXTilesSpinnerLabel = uilabel(app.GridLayout);
            app.NXTilesSpinnerLabel.Visible = 'off';
            app.NXTilesSpinnerLabel.Text = 'NX Tiles';
            app.NXTilesSpinner = uispinner(app.GridLayout);
            app.NXTilesSpinner.LowerLimitInclusive = 'off';
            app.NXTilesSpinner.Limits = [0 Inf];
            app.NXTilesSpinner.Visible = 'off';
            app.NXTilesSpinner.Value = 1;
            app.ConfirmXYRatioButton = uibutton(app.GridLayout, 'push');
            app.ConfirmXYRatioButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmXYRatioButtonPushed, true);
            app.ConfirmXYRatioButton.Text = 'Confirm XY Ratio';
            app.SetPixelSizeButton = uibutton(app.GridLayout, 'push');
            app.SetPixelSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetPixelSizeButtonPushed, true);
            app.SetPixelSizeButton.Text = 'Set Pixel Size';
            app.IntRangeEditFieldLabel = uilabel(app.GridLayout);
            app.IntRangeEditFieldLabel.Text = 'Int. Range';
            app.IntRangeEditField = uieditfield(app.GridLayout, 'numeric');
            app.IntRangeEditField.ValueChangedFcn = createCallbackFcn(app, @IntRangeEditFieldValueChanged, true);
            app.IntRangeEditField.Value = 3800;
            app.SpectraTypeDropDownLabel = uilabel(app.GridLayout);
            app.SpectraTypeDropDownLabel.Text = 'Spectra Type';
            app.SpectraTypeDropDown = uidropdown(app.GridLayout);
            app.SpectraTypeDropDown.Items = {'Select or', 'FTIR-MCT', 'FTIR-FPA', 'Raman', 'HyperDC', 'other', 'other (reverse)'};
            app.SpectraTypeDropDown.ItemsData = {'0', '100', '200', '300', '400', '500', '501'};
            app.SpectraTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectraTypeDropDownValueChanged, true);
            app.SpectraTypeDropDown.Value = '100';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ReshapeBButton = uibutton(app.GridLayout, 'push');
            app.ReshapeBButton.ButtonPushedFcn = createCallbackFcn(app, @ReshapeBButtonPushed, true);
            app.ReshapeBButton.Text = 'Reshape B';
            app.ReshapeCButton = uibutton(app.GridLayout, 'push');
            app.ReshapeCButton.ButtonPushedFcn = createCallbackFcn(app, @ReshapeCButtonPushed, true);
            app.ReshapeCButton.Text = 'Reshape C';
            app.ReshapeAButton = uibutton(app.GridLayout, 'push');
            app.ReshapeAButton.ButtonPushedFcn = createCallbackFcn(app, @ReshapeAButtonPushed, true);
            app.ReshapeAButton.Text = 'Reshape A';
            app.ReshapeDButton = uibutton(app.GridLayout, 'push');
            app.ReshapeDButton.ButtonPushedFcn = createCallbackFcn(app, @ReshapeDButtonPushed, true);
            app.ReshapeDButton.Text = 'Reshape D';
            % Show the figure after all components are created
            app.ReshapeModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReshapeModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReshapeModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReshapeModuleUIFigure)
        end
    end
end