classdef SimpleDeconModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleDeconModuleUIFigure      matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        DeselectAllButton_2            matlab.ui.control.Button
        DeselectAllButton              matlab.ui.control.Button
        SelectAllButton                matlab.ui.control.Button
        ResetShiftButton               matlab.ui.control.Button
        FullTableCheckBox              matlab.ui.control.CheckBox
        ClassTree                      matlab.ui.container.CheckBoxTree
        LoadPeakDBButton               matlab.ui.control.Button
        DeletePeakButton               matlab.ui.control.Button
        AddPeaksButton                 matlab.ui.control.Button
        TabGroup4                      matlab.ui.container.TabGroup
        DeconGroupsTab                 matlab.ui.container.Tab
        ListBox                        matlab.ui.control.ListBox
        AutoPeakDetectionTab           matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        PointstoClassifySpinnerLabel   matlab.ui.control.Label
        PointstoClassifySpinner        matlab.ui.control.Spinner
        DerivativeCheckBox             matlab.ui.control.CheckBox
        MinPeakDistanceSpinner         matlab.ui.control.Spinner
        MinPeakDistanceSpinnerLabel    matlab.ui.control.Label
        MinPeakPromenanceSpinner       matlab.ui.control.Spinner
        MinPeakPromenanceSpinnerLabel  matlab.ui.control.Label
        MinPeakHightSpinner            matlab.ui.control.Spinner
        MinPeakHightSpinnerLabel       matlab.ui.control.Label
        AutoPeakDetectionButton        matlab.ui.control.Button
        DeleteUIPeaksButton            matlab.ui.control.Button
        SaveAsPeaksetButton            matlab.ui.control.Button
        AddSinglePeakButton            matlab.ui.control.Button
        PeakTable                      matlab.ui.control.Table
        PeakTree                       matlab.ui.container.CheckBoxTree
        RightPanel                     matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        TabGroup5                      matlab.ui.container.TabGroup
        DeconvolutionSettingsTab       matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        MultiFitButton                 matlab.ui.control.Button
        GridLayout_PlotSettings        matlab.ui.container.GridLayout
        ResetButton                    matlab.ui.control.Button
        xlimMax_m10                    matlab.ui.control.Button
        xlimMax_p1                     matlab.ui.control.Button
        xlimMin_p1                     matlab.ui.control.Button
        xlimMin_m10                    matlab.ui.control.Button
        xlimMin_p10                    matlab.ui.control.Button
        xlimMin_m1                     matlab.ui.control.Button
        xlimMax_m1                     matlab.ui.control.Button
        xlimMax_p10                    matlab.ui.control.Button
        EditField_xlimMax              matlab.ui.control.NumericEditField
        EditField_xlimMin              matlab.ui.control.NumericEditField
        FigXlimLive                    matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DeconGroupNameEditField        matlab.ui.control.EditField
        DeconGroupNameEditFieldLabel   matlab.ui.control.Label
        LimitShiftCheckBox             matlab.ui.control.CheckBox
        ExtraEditField                 matlab.ui.control.NumericEditField
        ExtraEditFieldLabel            matlab.ui.control.Label
        DELTAEditField                 matlab.ui.control.NumericEditField
        DELTAEditFieldLabel            matlab.ui.control.Label
        PlotResultsCheckBox            matlab.ui.control.CheckBox
        MCIterationsSpinner            matlab.ui.control.Spinner
        IterationsSpinnerLabel_2       matlab.ui.control.Label
        MCSimulateSettingsButton       matlab.ui.control.Button
        AutozeroSpinner                matlab.ui.control.Spinner
        AutozeroSpinnerLabel           matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        IterationsSpinnerLabel         matlab.ui.control.Label
        FitShapeDropDown               matlab.ui.control.DropDown
        FitShapeDropDownLabel          matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        TypeDropDown                   matlab.ui.control.DropDown
        LegendCheckBox                 matlab.ui.control.CheckBox
        nEditField                     matlab.ui.control.NumericEditField
        PeaksLabel                     matlab.ui.control.Label
        DerivativeSpinner              matlab.ui.control.Spinner
        DerivativeLabel                matlab.ui.control.Label
        SpectraLabel                   matlab.ui.control.Label
        plot_spectra_checkbox          matlab.ui.control.CheckBox
        Derivative_CheckBox            matlab.ui.control.CheckBox
        Peaks_CheckBox                 matlab.ui.control.CheckBox
        CloseButton                    matlab.ui.control.Button
        DeconvoluteSelectedButton      matlab.ui.control.Button
        SpectraPlot                    matlab.ui.control.UIAxes
        ResultsTab                     matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        OHCalcQuantSelectionButton     matlab.ui.control.Button
        PeakResultsCheckBox            matlab.ui.control.CheckBox
        AddbesttoDBButton              matlab.ui.control.Button
        AddalltoDBButton               matlab.ui.control.Button
        FindBestR2Button               matlab.ui.control.Button
        ClearAllButton_2               matlab.ui.control.Button
        DeleteGroupButton              matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        SpectraPlot_2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        SpecDB % Description
        SpecMap

        MatrixLimits
        ROI
        DeconData
        selected_sample

        xlimits
        plotmat

        Peaks
        PeakLines
        hLineToDrag
        hVerticalLines
        hLineToDragPks
        hLineToDragPksIDX

        clickbuffer=0.05

        DeconStart
        xdir_state % Description
        NormFact
    end


    methods (Access = private)
        function UpdateSpectraPlot(app)
            update_FigXlimLive(app)

            cla(app.SpectraPlot)
           if not(isempty(app.selected_sample))

                [x, y, SampleName, Thickness] = get_specs(app.SpecDB,[app.SpecDB.Data.Meta(app.selected_sample).Id],true);


                xlim(app.SpectraPlot,app.xlimits)

                x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                xlim(app.SpectraPlot,x_limits)


                if strcmp(app.TypeDropDown.Value,'Stacked')
                    offset=zeros(1,numel(x));
                    for n=1:numel(x)
                        if cell2mat(Thickness(n))==0
                            Thickness(n)={app.NormFact};
                        end
                        y1=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact)),2,"omitnan");
                        
                        x1=cell2mat(x(n));

                        y1=y1(x1>min(x_limits) & x1 < max(x_limits));
                        offset(n)=max(y1,[],"omitnan");
                    end

                    offset=[0 cumsum(offset)];
                    offset=offset*1.05;
                    max_val_prev=0;

                    for n=1:numel(x)
                        y1=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact)),2,"omitnan")+offset(n);
                        x1=cell2mat(x(n));
                        if app.plot_spectra_checkbox.Value==true

                            plt(n)=plot(app.SpectraPlot,cell2mat(x(n)),y1,LineWidth=2);%#ok
                        end
                        if app.Derivative_CheckBox.Value==true
                            yg=y1;
                            if not(app.DerivativeSpinner.Value==0)
                                for i=1:abs(app.DerivativeSpinner.Value)
                                    for m=1:size(yg,2)
                                        yg(:,m)=gradient(yg(:,m));
                                    end
                                end
                                if app.DerivativeSpinner.Value<0
                                    yg=-yg;
                                end
                            end

                            yg=yg+offset(n);
                            
                            plot(app.SpectraPlot,cell2mat(x(n)),yg,LineWidth=2);%#ok;
                        end
                       
                        y1=y1(x1>min(x_limits) & x1 < max(x_limits));
                        x2=x1(x1>min(x_limits) & x1 < max(x_limits));

%                         if app.Peaks_CheckBox.Value==true && not(isempty(app.Peaks)) && not(isempty(app.PeakTable.Data))&& not(isempty(app.PeakTable.Data.PeakPosition))
%                             PeakPosition=app.PeakTable.Data.PeakPosition;
% 
%                             if n==numel(x)
%                                 label=app.PeakTable.Data.PeakNames;
%                             else
%                                 label='';
%                             end
%                             PlotPeaksFun(app.SpectraPlot,x2,y1,PeakPosition,'Line',label)
%                         end

                        max_val_prev=max(mean(y1,"omitnan")+max_val_prev+offset(n));
                        %max_val_prev=max(mean(y1,"omitnan")+max_val_prev+offset(n));


                    end
                    if app.LegendCheckBox.Value==true
                        legend(app.SpectraPlot,flip(plt),flip(SampleName),'Location','eastoutside',Interpreter='none');
                    end

                else
                    for n=1:numel(x)
                        if cell2mat(Thickness(n))==0
                            Thickness(n)={app.NormFact};
                        end
if app.plot_spectra_checkbox.Value==true
                        plot(app.SpectraPlot,cell2mat(x(n)),mean(cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact),2,"omitnan"),LineWidth=2);
end
                        if app.Derivative_CheckBox.Value==true
                            yg=mean(cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact),2,"omitnan");
                            if not(app.DerivativeSpinner.Value==0)
                                for i=1:abs(app.DerivativeSpinner.Value)
                                    for m=1:size(yg,2)
                                        yg(:,m)=gradient(yg(:,m));
                                    end
                                end
                                if app.DerivativeSpinner.Value<0
                                    yg=-yg;
                                end
                            end
                            plot(app.SpectraPlot,cell2mat(x(n)),yg,LineWidth=2,LineStyle=":");
                        end

                   
                    end

                    %                     if app.Peaks_CheckBox.Value==true && not(isempty(app.Peaks)) && isfield(app.Peaks,'UIpeaks') && not(isempty(app.Peaks.UIpeaks))
                    %                         PeakPosition=app.Peaks.UIpeaks.PeakPosition;
                    %                         label='test';
                    %
                    %                         PlotPeaksFun(app.SpectraPlot,x{n},cell2mat(y(n)),PeakPosition,'XLine',label)
                    %                     end


                    if app.LegendCheckBox.Value==true
                        legend(app.SpectraPlot,SampleName,'Location','eastoutside',Interpreter='none')
                    end

                end

            end

            if app.Peaks_CheckBox.Value==true && not(isempty(app.Peaks)) && not(isempty(app.PeakTable.Data))&& not(isempty(app.PeakTable.Data.PeakPosition))
                PeakPosition=app.PeakTable.Data.PeakPosition;
                peaklabels=join([cellfun(@num2str,(num2cell(round(PeakPosition))'),'UniformOutput',false)' app.PeakTable.Data.PeakNames])';
                app.PeakLines=xline(app.SpectraPlot,PeakPosition','-',peaklabels,'LineWidth',2);


                set(app.PeakLines, 'hittest', 'off');
            else
                if not(isempty(app.PeakLines))
                    delete(app.PeakLines)
                end
            end

        end


        function update_class_tree(app)
            %reset tree
            a = app.ClassTree.Children;
            a.delete;


            %build tree
            try
                for n=1:numel(app.DeconData.SampleNames)
                    p = uitreenode(app.ClassTree,'Text',string(app.DeconData.SampleNames{n}),'NodeData',0);
                    expand(p)

                    for j=1:numel(app.DeconData.SampleData(n).MineralNames)

                        p1 = uitreenode(p,'Text',string(app.DeconData.SampleData(n).MineralNames{j}),'NodeData',0);
                        expand(p1)

                        for k=1:numel(app.DeconData.SampleData(n).Mineral(j).SampleName)
                          %  if sum(ismember(app.DeconData.SampleData(n).Mineral(j).SampleName{k},app.DeconData.SampleData(n).Mineral(j).SampleName))==1
                                p2= uitreenode(p1,'Text',string(app.DeconData.SampleData(n).Mineral(j).SampleName{k}),'NodeData',app.DeconData.SampleData(n).Mineral(j).Id(k));
%                             else
%                                 p2= uitreenode(p1,'Text',string(app.DeconData.SampleData(n).Mineral(j).SampleName{k}),'NodeData',0);
%                                 for l=1:sum(ismember(app.DeconData.SampleData(n).Mineral(j).SampleName{k},app.DeconData.SampleData(n).Mineral(j).SampleName))
%                                p3= uitreenode(p1,'Text',string([app.DeconData.SampleData(n).Mineral(j).SampleName{k} '_' app.DeconData.SampleData(n).Mineral(j).Orientation{k}]),'NodeData',app.DeconData.SampleData(n).Mineral(j).Id(k));
%                                 end
%                            end
                        end
                    end
                end


            catch ME
                disp(ME.message)
            end
        end

        function UpdatePeakTable(app)
            if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                peaktabledata=app.Peaks.PeakDB_raw;
            else
                peaktabledata=app.Peaks.PeakDB_UI;
            end

            if not(isempty(app.PeakTree.CheckedNodes)) && sum(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData}))>0

                Peak_Id=zeros(sum(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData})),1);
                iter=0;

                for n=find(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData}))
                    iter=iter+1;
                    Peak_Id(iter)=app.PeakTree.CheckedNodes(n).NodeData{end};
                    % Peak_eID(iter,:)=app.PeakTree.CheckedNodes(n).NodeData{1};
                end


                app.Peaks.PeakDB_selection=peaktabledata(Peak_Id,:);
                [~, idx]=sort(app.Peaks.PeakDB_selection.PeakPosition);

                app.Peaks.PeakDB_selection=app.Peaks.PeakDB_selection(idx,:);

                if app.FullTableCheckBox.Value==false
                    app.PeakTable.Data=app.Peaks.PeakDB_selection(:,find(ismember(peaktabledata.Properties.VariableNames,{'RefPeakPosition','PeakPosition','PeakShift','PeakNames'})));

                    app.PeakTable.ColumnName=peaktabledata.Properties.VariableNames(find(ismember(peaktabledata.Properties.VariableNames,{'RefPeakPosition','PeakPosition','PeakShift','PeakNames'})));
                else
                    app.PeakTable.Data=app.Peaks.PeakDB_selection;
                    app.PeakTable.ColumnName=peaktabledata.Properties.VariableNames;
                end


            else
                app.PeakTable.Data=[];
            end
        end

        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax,"Visible","off");
            x = roi.Position(1);
            y = roi.Position(2);
        end

        function g=gaussplot(~,pos,amp,wid,x)
            g = amp.*exp(-((x-pos)./(0.60056120439323.*wid)).^2);
        end


        function [FitResults_best1, pos_ini, GOF_best1,PeakFit,x_signal,y_signal]= PrepareDeconFun(app,selected_sampleID)


            center=app.xlimits(1)+((app.xlimits(2)-app.xlimits(1))/2);
            window=(app.xlimits(2)-app.xlimits(1));
            extra=app.ExtraEditField.Value;
            DELTA=app.DELTAEditField.Value;
            clipheight=[];
            start=0;
            BaselineMode=app.AutozeroSpinner.Value;

            if app.PlotResultsCheckBox.Value==true
                plots=1;
            else
                plots=0;
            end

            bipolar=0;
            minwidth=2;
            if exist("selected_sampleID","var")
                [x_signal, y_signal]=get_spec_mat(app,selected_sampleID);
            else
                [x_signal, y_signal]=get_spec_mat(app,app.selected_sample);
            end

            range=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

            y_signal=y_signal(x_signal<max(range)&x_signal>min(range),:);
            x_signal=x_signal(x_signal<max(range)&x_signal>min(range),:);

            GOF_best1=[100 0];

            iter=false;
            try
                NumPeaks=numel(unique(app.PeakTable.Data.PeakPosition));
            catch
                NumPeaks=0;

            end
            %N=app.ClassTree.SelectedNodes;


            for h=1:size(y_signal,2)

                if numel(unique(y_signal(:,h)))==1
                    continue
                end

                if app.PlotResultsCheckBox.Value==true
                    figure(h)
                end
                signal=[x_signal y_signal(:,h)];

                if sscanf(char(app.FitShapeDropDown.Value),'%d')==2
                    peakshape=17;
                else
                    peakshape=16;
                end

                if NumPeaks==0
                    NumPeaks=app.PointstoClassifySpinner.Value;
                    fixedparameters=0;
                    NumTrials=5;

                    try
                        %                          [FitResults,~,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,peakshape,extra,NumTrials,start,BaselineMode,fixedparameters,plots,bipolar,minwidth,DELTA);
                        [FitResults,~,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,1,extra,NumTrials,start,BaselineMode,fixedparameters,plots,bipolar,minwidth,DELTA);
                    catch ME
                        disp(ME.message)
                    end
                    pos_ini=FitResults(:,2)';
                    fixedparameters=pos_ini';

                else
                    fixedparameters=unique(app.PeakTable.Data.PeakPosition);
                    pos_ini=fixedparameters';
                    NumTrials=5;
                    try
                        [FitResults,~,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,peakshape,extra,NumTrials,start,BaselineMode,fixedparameters,plots,bipolar,minwidth,DELTA);
                    catch ME
                        disp(ME.message)
                    end
                end
                %[ FitResults,GOF,baseline,coeff,residual,xi,yi, BootResults]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,BaselineMode,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight)

                pos=FitResults(:,2)';
                wid=FitResults(:,4)';
                s=[pos;wid];
                start=s(:);
                NumTrials=app.IterationsSpinner.Value;

                peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
                if strcmp(char(app.FitShapeDropDown.Value),'Iterative Gauss Fit')

                    [gaussianParameters,GOF] = iterative_Gauss_fit(x_signal',y_signal(:,h)',NumPeaks,plots,start);
                    %                             GOF=[(max(Rsquared)),min(Rsquared)];
                    g=exp(-((x_signal' - gaussianParameters(:,2)) ./ (0.60056120439323 .* gaussianParameters(:,3))) .^ 2)';
                    g2=g.*gaussianParameters(:,1)';
                    area=trapz(g2);
                    FitResults=[(1:size(gaussianParameters,1))' gaussianParameters(:,2)  gaussianParameters(:,1)  gaussianParameters(:,3) area'];

                else

                    if app.LimitShiftCheckBox.Value
                        fixedparameters_shift=repmat([-DELTA DELTA],NumPeaks,1);
                    else
                        fixedparameters_shift=[0 0];
                    end

                    [FitResults,GOF,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,peakshape,extra,NumTrials,start,BaselineMode,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight,fixedparameters_shift);
                end

                if GOF(1)<=GOF_best1(1) || iter==false
                    %                                 signal_best1=signal;
                    GOF_best1=GOF;
                    FitResults_best1=FitResults;
                    iter=true;
                end

                if app.PlotResultsCheckBox.Value==true
                    subplot(2,1,1)
                    set (gca, 'xdir', app.xdir_state)
                    subplot(2,1,2)
                    set (gca, 'xdir', app.xdir_state)
                end

                pos=FitResults_best1(:,2)';
                amp=FitResults_best1(:,3)';
                wid=FitResults_best1(:,4)';


                % sorting the output peaks so they match the closest value of the input
                % peaks

                %%
                pos_ini2=(pos_ini);
                pos_t2=(pos);
                idxxx=1:size(pos_ini,2);
                idyyy=1:size(pos_ini,2);
                sort1=zeros(1,size(pos_ini,2));
                sort2=zeros(1,size(pos_ini,2));
                for n=1:size(pos_ini,2)

                    ix_pos = abs(pos_ini2(:) - pos_t2(:)');
                    [xy, col2delete] = min(ix_pos,[],1);

                    [~, row2delete ]=min(xy,[],2);

                    pos_ini2(col2delete(row2delete))=[];

                    sort1(n)=idxxx(col2delete(row2delete));
                    idxxx(col2delete(row2delete))=[];

                    sort2(n)=idyyy((row2delete));
                    idyyy((row2delete))=[];

                    pos_t2(row2delete)=[];
                end


                sorted_ini=pos_ini(sort1);

                FRsort=FitResults_best1(sort2,:);

                [~,idx] = sort(pos_ini);
                [~,idx(idx)] = sort(sorted_ini);

                FitResults_best1=FRsort(idx,:);

                %test1=round([pos_ini' FitResults_best1(:,2) FitResults_best1(:,2)-pos_ini' ])

                try
                    y_calc=DeconPlotFun(peakshape,x_signal',pos',wid',amp',extra',DELTA');
                    plot(app.SpectraPlot,x_signal,sum(y_calc),'LineWidth',2,'Color','k','LineStyle','- -')
                    plot(app.SpectraPlot,x_signal,y_calc,'LineWidth',0.5,'Color','k','LineStyle','-')
                catch ME
                    disp(ME.message)
                end

                %% store everything in structure
                %peakfit input
                if  true%save_all_data
                    PeakFit.signal=signal;
                end
                PeakFit.center=center;
                PeakFit.window=window;
                PeakFit.NumPeaks=NumPeaks;
                PeakFit.peakshape=peakshape;
                PeakFit.extra=extra;
                PeakFit.NumTrials=NumTrials;
                PeakFit.start=start;
                PeakFit.BaselineMode=BaselineMode;
                PeakFit.fixedparameters=fixedparameters;
                PeakFit.plots=plots;
                PeakFit.bipolar=bipolar;
                PeakFit.minwidth=minwidth;
                PeakFit.DELTA=DELTA;
                PeakFit.clipheight=clipheight;
                PeakFit.fixedparameters_shift=fixedparameters_shift;


            end

        end


        function update_PeakTree(app)
            checked=app.PeakTree.CheckedNodes;
ch_n1=[];
ch_n2=[];
            if not(isempty(checked))
                for n=1:numel(checked)
                    if iscell(app.PeakTree.CheckedNodes(n).NodeData)
                        if numel(app.PeakTree.CheckedNodes(n).NodeData{1})==3
                        ch_n1(end+1,:)   =app.PeakTree.CheckedNodes(n).NodeData{1};
                        elseif numel(app.PeakTree.CheckedNodes(n).NodeData{1})==4
                        ch_n2(end+1,:)   =app.PeakTree.CheckedNodes(n).NodeData{1};
                        end
                    end
                end
            end
            a = app.PeakTree.Children;
            a.delete;

            for i=1:numel(app.Peaks.PeakDB.MineralNames)
                p = uitreenode(app.PeakTree,'Text',string(app.Peaks.PeakDB.MineralNames{i}),'NodeData',[i,0]);
                expand(p)

                for j=1:numel(app.Peaks.PeakDB.Mineral(i).GroupNames)
                    p1 = uitreenode(p,'Text',string(app.Peaks.PeakDB.Mineral(i).GroupNames{j}),'NodeData',[i,j]);
                    expand(p1)

                    for k=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames)
                        if numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)==1
                            pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                            name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                            Id=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).Id;
                            p2=uitreenode(p1,'Text',[num2str(pos) '_' app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',{[i,j,k],pos,name,Id}); %# ok
                        else
                            p2=uitreenode(p1,'Text',[app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',[i,j,k]);

                            for l=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)
                                pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                                name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                                Id=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).Id(l);
                                p3=uitreenode(p2,'Text',[num2str(pos(l))],'NodeData',{[i,j,k l],pos(l),name,Id}); %# ok
                            end
                        end
                    end
                end
            end
            try
                if ~isempty(ch_n1) || ~isempty(ch_n2)
                    chkt1=[];
                    chkt2=[];

                    if ~isempty(ch_n1)
                        for n=1:size(ch_n1,1)
                            chkt1=[chkt1 app.PeakTree.Children(ch_n1(n,1)).Children(ch_n1(n,2)).Children(ch_n1(n,3))];
                        end
                    end
                    if ~isempty(ch_n2)
                        for n=1:size(ch_n1,1)

                            chkt2=[chkt2 app.PeakTree.Children(ch_n2(n,1)).Children(ch_n2(n,2)).Children(ch_n2(n,3)).Children(ch_n2(n,4))];
                        end
                    end
                    app.PeakTree.CheckedNodes=[chkt1 chkt2];
                end


            catch ME
                disp('SimpleDecon PeakTree Checkbox update failed')
                ME
            end
        end

        function  update_FigXlimLive(app)
            cla(app.FigXlimLive)
            if isempty(app.selected_sample)

                sample_IDs=[];
            else
                sample_IDs=[app.SpecDB.Data.Meta(app.selected_sample).Id];
            end

            [x, y, ~, Thickness] = get_specs(app.SpecDB,sample_IDs,true);
            xlim(app.SpectraPlot,app.xlimits)

            x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            xlim(app.SpectraPlot,x_limits)


            for n=1:numel(x)
                if cell2mat(Thickness(n))==0
                    Thickness(n)={app.NormFact};
                end
                %y1=cell2mat(y(n));
                y1=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact)),2,"omitnan");
                x1=cell2mat(x(n));

            end

            plotmat_lim= mean(y1,2,"omitnan");


            a_h=area(app.FigXlimLive,x1,plotmat_lim,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
            set(a_h, 'hittest', 'off');


            range=x1>=app.EditField_xlimMin.Value & x1<=app.EditField_xlimMax.Value;
            x2=x1(range,:);
            y2=y1(range,:);


            y2 = max(plotmat_lim)./(max(mean(y2,2,"omitnan"))./mean(y2,2,"omitnan"));

            l_h=plot(app.FigXlimLive,x2,y2,'LineStyle','--','Color','k');
            set(l_h, 'hittest', 'off');

            app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];

            set(app.hVerticalLines(1:2), 'hittest', 'off');

            xlimval=[min([app.EditField_xlimMin.Value min(x1)])...
                max([app.EditField_xlimMax.Value max(x1)])];

            xlim(app.FigXlimLive,xlimval)


        end

        function [x1, y1]=get_spec_mat(app,sample_IDs)

            [x, y, ~, Thickness] = get_specs(app.SpecDB,[app.SpecDB.Data.Meta(sample_IDs).Id],true);
            x1=cell2mat(x(1));

            y1=nan(size(x1,1),numel(sample_IDs));
            for n=1:numel(x)
                if cell2mat(Thickness(n))==0
                    Thickness(n)={app.NormFact};
                end
                %y1=cell2mat(y(n));
                try
                    y1(:,n)=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./app.NormFact)),2,"omitnan");
                catch ME
                    disp('get_spec_mat Error')

                end
            end
        end

        
        function update_decon_results(app,selected_decon)
            if not(exist("selected_decon","var"))


                selected_decon = app.UITable_2.Selection;
                if isempty(selected_decon)
                    selected_decon=1;
                else
                    selected_decon= unique(selected_decon(:,1));
                end
            end
            if not(isfield(app.SpecDB.Data,'DeconData')) || isempty(app.SpecDB.Data.DeconData)
                cla(app.SpectraPlot_2)
                app.UITable_2.Data=[];
                app.UITable.Data=[];
                app.ListBox.Items={};
                return
            end
            ValidDecondata=app.SpecDB.Data.DeconData(not(cellfun(@isempty ,{app.SpecDB.Data.DeconData.DeconvolutionName})));

            if numel(selected_decon)>1
                app.UITable.Data=[];
                app.ListBox.Items={};

            else
                selection = app.UITable_2.Selection;
                if isempty(selection)
                    DeconID=1;
                    selected_decon=1;
                else
                    DeconID=app.SpecDB.Data.DeconData(app.UITable_2.Data.e_Id(unique(selection(:,1)))).ActiveDeconGroup;
                    if isempty(DeconID)
                        app.SpecDB.Data.DeconData(app.UITable_2.Data.e_Id(unique(selection(:,1)))).ActiveDeconGroup=1;
                        DeconID=1;

                    end
                    %                 catch ME
                    %                     disp(ME.message)
                    %                     DeconID=1;
                    %                     selected_decon=1;
                end
                try
                    ValidDecondata(selected_decon).DeconResults(DeconID).Use2Quant(isnan(ValidDecondata(selected_decon).DeconResults(DeconID).Use2Quant))=true;
                    if isfield(ValidDecondata(selected_decon).DeconInput(DeconID).Peaks,'PeakDB')
                        app.UITable.Data=table(ValidDecondata(selected_decon).DeconInput(DeconID).Peaks.PeakDB.PeakNames,...
                            logical(ValidDecondata(selected_decon).DeconResults(DeconID).Use2Quant'),...
                            ValidDecondata(selected_decon).DeconResults(DeconID).pos',...
                            ValidDecondata(selected_decon).DeconResults(DeconID).shift', ...
                            ValidDecondata(selected_decon).DeconResults(DeconID).width',...
                            ValidDecondata(selected_decon).DeconResults(DeconID).hight', ...
                            ValidDecondata(selected_decon).DeconResults(DeconID).area',...
                            ValidDecondata(selected_decon).DeconResults(DeconID).GL_blend',...
                            ValidDecondata(selected_decon).DeconResults(DeconID).QuantFactor',...
                            ValidDecondata(selected_decon).DeconResults(DeconID).H2O_FactorQuant'...
                            );
                    else
                        app.UITable.Data= table(string(ValidDecondata(selected_decon).DeconResults(DeconID).pos)',...
                        logical(false(size(string(ValidDecondata(selected_decon).DeconResults(DeconID).pos)'))),...
                        ValidDecondata(selected_decon).DeconResults(DeconID).pos',...
                        ValidDecondata(selected_decon).DeconResults(DeconID).shift', ...
                        ValidDecondata(selected_decon).DeconResults(DeconID).width',...
                        ValidDecondata(selected_decon).DeconResults(DeconID).hight', ...
                        ValidDecondata(selected_decon).DeconResults(DeconID).area',...
                                                ValidDecondata(selected_decon).DeconResults(DeconID).GL_blend',...
    nan(size(string(ValidDecondata(selected_decon).DeconResults(DeconID).pos)')),...
                        ValidDecondata(selected_decon).DeconResults(DeconID).H2O_FactorQuant'...
                        );


                end

                app.UITable.ColumnName={'PeakNames','Use2Quant','PeakPosition','PeakShift', 'PeakWidth','PeakHight','PeakArea','QuantFactor','H2O_FactorQuant'};
                app.UITable.ColumnEditable=[true,true, false, false, false, false, false,true,false];
                % update Listbox
                
                app.ListBox.Items=ValidDecondata(selected_decon).DeconvolutionName;
                app.ListBox.ItemsData=1:numel(ValidDecondata(selected_decon).DeconvolutionName);
                app.ListBox.Value=DeconID;
                catch ME
                    disp(ME)
                    
                end 

            end


            % update SpectraPlot_2
            cla(app.SpectraPlot_2)
            for n=1:numel(selected_decon)
                 DeconID=app.SpecDB.Data.DeconData(app.UITable_2.Data.e_Id(selected_decon(n))).ActiveDeconGroup;
                plot(app.SpectraPlot_2,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1),ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,2:end),'LineWidth',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).Signal.LineWidth,'Color',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).Signal.Color,'LineStyle',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).Signal.LineStyle)

                y_calc=DeconPlotFun(ValidDecondata(selected_decon(n)).PeakFit(DeconID).peakshape,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1)',ValidDecondata(selected_decon(n)).DeconResults(DeconID).pos',ValidDecondata(selected_decon(n)).DeconResults(DeconID).width',ValidDecondata(selected_decon(n)).DeconResults(DeconID).hight',ValidDecondata(selected_decon(n)).PeakFit(DeconID).extra',ValidDecondata(selected_decon(n)).PeakFit(DeconID).DELTA');
                plot(app.SpectraPlot_2,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1),sum(y_calc),'LineWidth',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconTotal.LineWidth,'Color',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconTotal.Color,'LineStyle',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconTotal.LineStyle)
                for m=1:size(ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.Color,1)
                    try
                        if iscell(ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineStyle)
                            plot(app.SpectraPlot_2,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1),y_calc(m,:)','LineWidth',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineWidth(m),'Color',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.Color(m,:),'LineStyle',erase(ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineStyle{m},'A'))
                        else
                            try
                                plot(app.SpectraPlot_2,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1),y_calc(m,:)','LineWidth',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineWidth(m),'Color',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.Color(m,:),'LineStyle',erase(ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineStyle,'A'))
                            catch
                                plot(app.SpectraPlot_2,ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1),y_calc(m,:)','LineWidth',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineWidth(m),'Color',ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.Color(m,:),'LineStyle',erase(ValidDecondata(selected_decon(n)).PlotSettings(DeconID).DeconSingle.LineStyle(m),'A'))

                            end

                        end
                    catch ME
                        ME.message
                    end
                end
                app.SpectraPlot_2.XLim=[min(ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1)) max(ValidDecondata(selected_decon(n)).PeakFit(DeconID).signal(:,1))];
            end
        end

        function Update_Results_Table(app)
            if not(isfield(app.SpecDB.Data,'DeconData')) || isempty(app.SpecDB.Data.DeconData)
                app.UITable_2.Data=[];
                return
            end
            ValidDecondata=app.SpecDB.Data.DeconData(not(cellfun(@isempty ,{app.SpecDB.Data.DeconData.DeconvolutionName})));

            e_Id=zeros(numel(ValidDecondata),1);
            SumOfPeaks=e_Id;
            Total_H2O_FactorQuant=e_Id;
            error=e_Id;
            error_r2=e_Id;
            name=cell(numel(ValidDecondata),1);
            ActiveDeconGroup=name;
            for n=1:numel(ValidDecondata)
                DeconID=numel(ValidDecondata(n).DeconvolutionName);

                e_Id(n)=ValidDecondata(n).DeconInput(DeconID).SampleInfo.eId;
                
                if not(isempty(app.SpecDB.Data.DeconData(e_Id(n)).ActiveDeconGroup))
                    DeconID=app.SpecDB.Data.DeconData(e_Id(n)).ActiveDeconGroup;
                else
                    app.SpecDB.Data.DeconData(e_Id(n)).ActiveDeconGroup=1;
                end
                ActiveDeconGroup{n}=ValidDecondata(n).DeconvolutionName{DeconID};

                name{n}=ValidDecondata(n).DeconInput(DeconID).SampleInfo.Name;
                if not(isempty(ValidDecondata(n).DeconResults(DeconID).SumOfPeaks))
                SumOfPeaks(n)=ValidDecondata(n).DeconResults(DeconID).SumOfPeaks;
                else
SumOfPeaks(n)=nan;
                end
                Total_H2O_FactorQuant(n)=ValidDecondata(n).DeconResults(DeconID).Total_H2O_FactorQuant; 
                error(n)=ValidDecondata(n).DeconResults(DeconID).error;
                error_r2(n)=ValidDecondata(n).DeconResults(DeconID).error_r2;
            end

            app.UITable_2.Data=table(e_Id,name,SumOfPeaks,Total_H2O_FactorQuant,error,error_r2,ActiveDeconGroup);
            app.UITable_2.ColumnName={'e_Id','SampleName','SumOfPeaks','Total_H2O_FactorQuant','Error','Error_R2','ActiveDeconGroup'};
        end
        
        function add_peaks_fun(app,n_peaks,Peaktype)
                if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                    app.Peaks.PeakDB_UI=app.Peaks.PeakDB_raw;
                end
                try
                    newname=char(matlab.lang.makeUniqueStrings(string(Peaktype),app.Peaks.PeakDB_UI.PeakgroupNames(1:end-1)));
                    for n=1:numel(n_peaks)
                        app.Peaks.PeakDB_UI.Id(end+1)=app.Peaks.PeakDB_UI.Id(end)+1;
                        app.Peaks.PeakDB_UI.PeakPosition(end)=n_peaks(n);
                        app.Peaks.PeakDB_UI.RefPeakPosition(end)=n_peaks(n);
                        app.Peaks.PeakDB_UI.Defect_WN(end)=n_peaks(n);
                        app.Peaks.PeakDB_UI.PeakShift(end)=0;
                        app.Peaks.PeakDB_UI.Use2Quant(end)=0;
                        app.Peaks.PeakDB_UI.PeakNames{end}=newname;
                        app.Peaks.PeakDB_UI.Element{end}='';
                        app.Peaks.PeakDB_UI.PeakgroupNames{end}=char(Peaktype);
                        app.Peaks.PeakDB_UI.MineralNames{end}='UI Peak';
                        app.Peaks.PeakDB_UI.R(end)=0;
                        app.Peaks.PeakDB_UI.G(end)=0;
                        app.Peaks.PeakDB_UI.B(end)=0;
                        app.Peaks.PeakDB_UI.LineStyle{end}='A-';
                        app.Peaks.PeakDB_UI.LineWidth(end)=1;
                        app.Peaks.PeakDB_UI.Active(end)=1;
                        app.Peaks.PeakDB_UI.FaceAlpha(end)=0.2;
                    end
catch 
0
end


                [app.Peaks.PeakDB, ~]=ReadPeakdata_fun(app.Peaks.PeakDB_UI);

                update_PeakTree(app)
                UpdatePeakTable(app)
                UpdateSpectraPlot(app)
            end
            
        
        function [gaussianParameters,GOF]= multifit_dev_in(app,x,y_in,NumPeaks,startingGuesses)


NumTrials=0;
TrialError=[];

options.TolFun = 1e-4;
 options.Display = 'iter';
%options.Display = 'off';
options.TolX = 1e-4;
options.MaxIter = 10;
NORM = startingGuesses; 
startingGuesses = startingGuesses./NORM;
[parameter, fval, flag, output] = fminsearch(@multi_fit_NG2,startingGuesses, options, NORM, x', y_in,NumPeaks,TrialError);

parameter = parameter.*NORM;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%----------------------------------------------------------------------------------------------------------------

pos=parameter(:,1);
wid=parameter(:,2);
A=gaussplot(pos,1,wid,x);

c = A' \ y_in';

gaussianParameters = [c parameter ];
% Now sort parameters in order of increasing mean
gaussianParameters = sortrows(gaussianParameters, 2);
pos=gaussianParameters(:,2);
wid=gaussianParameters(:,3);
amp=gaussianParameters(:,1);
y_calc=sum(gaussplot(pos,amp,wid,x));


SStot=sum((y_in-mean(y_in)).^2);
SSres=sum((y_in-y_calc).^2);
Rsquared=min(SSres./SStot);
MeanFitError=norm(y_in-y_calc)./(sqrt(length(x))*max(y_in));
GOF=[MeanFitError Rsquared];

        end

        
function save_decon_results(app,FitResults, pos_ini,GOF,PeakFit,SampleID,name ,DeconType)
    if isfield(app.Peaks,'PeakDB_selection') && not(isempty(app.Peaks.PeakDB_selection))         
    QuantFactor=app.Peaks.PeakDB_selection.QuantFactor_total';
            IntQuant=app.Peaks.PeakDB_selection.MolarAbsCoeffs';
            LinQuant=app.Peaks.PeakDB_selection.LinAbsCoeffs';
            Use2Quant=app.Peaks.PeakDB_selection.Use2Quant';
    else
 QuantFactor=[];
            IntQuant=[];
            LinQuant=[];
            Use2Quant=[];
    end

amp(1,:)=FitResults(:,3)'; %#ok
pos(1,:)=FitResults(:,2)';%#ok
wid(1,:)=FitResults(:,4)';%#ok
area(1,:)=FitResults(:,5)';%#ok
shift(1,:)= pos(1,:)-pos_ini;%#ok
GOF_(1,:)=GOF;%#ok

if not(isfield(app.SpecDB.Data,'DeconData')) || numel(app.SpecDB.Data.DeconData)<SampleID|| not(isfield(app.SpecDB.Data.DeconData(SampleID),'DeconvolutionName')) ||isempty(app.SpecDB.Data.DeconData(SampleID).DeconvolutionName)
    app.SpecDB.Data.DeconData(SampleID).DeconvolutionName{1}=app.DeconGroupNameEditField.Value;
else
    DeconvolutionName= matlab.lang.makeUniqueStrings(app.DeconGroupNameEditField.Value,string(app.SpecDB.Data.DeconData(SampleID).DeconvolutionName)');
    app.SpecDB.Data.DeconData(SampleID).DeconvolutionName{end+1}=matlab.lang.makeValidName(DeconvolutionName);
end

DeconID=numel(app.SpecDB.Data.DeconData(SampleID).DeconvolutionName);
app.SpecDB.Data.DeconData(SampleID).ActiveDeconGroup=DeconID;

app.SpecDB.Data.DeconData(SampleID).DeconType(DeconID)={DeconType};

app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).MCiterations=[];
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).Peaks.PeakPos_ini=pos_ini;
    if isfield(app.Peaks,'PeakDB_selection') && not(isempty(app.Peaks.PeakDB_selection))         

app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).Peaks.PeakDB=app.Peaks.PeakDB_selection;
    end

%decon results
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).pos=pos(1,:);
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).area=area(1,:);
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).QuantFactor=QuantFactor;
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant=Use2Quant;
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant(isnan(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant))=false;
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).SumOfPeaks=sum(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).area(logical(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant)),2,"omitnan");%sum(area(k,:),2);

if not(isempty(QuantFactor))
    app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).H2O_FactorQuant=QuantFactor.*area(1,:);
    app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Total_H2O_FactorQuant= sum(QuantFactor(logical(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant)).*app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).area(logical(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Use2Quant)),2,"omitnan");
else
  app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).H2O_FactorQuant=nan.*area(1,:);
    app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).Total_H2O_FactorQuant= nan;

end
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).IntQuant=IntQuant;
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).hight=amp(1,:);
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).LinQuant=LinQuant;
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).width=wid(1,:);

try
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).GL_blend=FitResults(:,6)';
catch
    app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).GL_blend=nan(size(wid(1,:)));
end

app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).shift=shift(1,:);
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).error=GOF_(1,1);
app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).error_r2=GOF_(1,2);

lowest_r2=false;
if lowest_r2==true%ath here
    app.SpecDB.Data.DeconData(SampleID).ActiveDeconGroup=find(min([app.SpecDB.Data.DeconData(SampleID).DeconResults(:).error_r2]));
end

%PeakFitInput
app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID)=PeakFit;

%Meta input
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).SampleInfo.eId=SampleID;%map, singlespec or multidata % check
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).SampleInfo.Name=name;%app.DeconData.SampleData(n).Mineral(j).SampleName{k};%map, singlespec or multidata % check
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).SampleInfo.Type='Single';%map, singlespec or multidata % check
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).SampleInfo.Meta=app.SpecDB.Data.Meta(SampleID);%map, singlespec or multidata % check
app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).SampleInfo.MetaPrivat=app.SpecDB.Data.MetaPrivat(SampleID);%map, singlespec or multidata % check

% plot settings
%% plot settings default
if isfield(app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).Peaks,'PeakDB')
PeakDB=app.SpecDB.Data.DeconData(SampleID).DeconInput(DeconID).Peaks.PeakDB;
else
    PeakDB=table();
    PeakDB.Active=true;

PeakDB.R=0;
PeakDB.G=0;
PeakDB.B=0;
PeakDB.LineWidth=1;
PeakDB.LineStyle='-';
PeakDB.PeakNames='Peak';
PeakDB(1:numel(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).pos),:)=repmat(PeakDB,numel(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).pos),1);
PeakDB.PeakNames=string(round(app.SpecDB.Data.DeconData(SampleID).DeconResults(DeconID).pos,1))';
end
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconSingle.Color=[PeakDB.R PeakDB.G PeakDB.B];
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconSingle.Active=PeakDB.Active;
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconSingle.LineWidth=PeakDB.LineWidth;
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconSingle.LineStyle=PeakDB.LineStyle;

app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).PeakPos.LineType=repmat({'Line'}, app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID).NumPeaks,1);
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).PeakPos.LineStyle=repmat({'--'}, app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID).NumPeaks,1);
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).PeakPos.Color=repmat([0 0 0], app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID).NumPeaks,1);
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).PeakPos.LineWidth=ones(app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID).NumPeaks,1);
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).PeakPos.Label=PeakDB.PeakNames;


app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconTotal.Color=[0 0 0];
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconTotal.LineWidth=2;
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).DeconTotal.LineStyle='--';

app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).Signal.Color=[0 0 0];
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).Signal.LineWidth=2;
app.SpecDB.Data.DeconData(SampleID).PlotSettings(DeconID).Signal.LineStyle='-';
% now loop through peaks and add in DB values
for h=1:app.SpecDB.Data.DeconData(SampleID).PeakFit(DeconID).NumPeaks

end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DeconData)
            app.SpecDB=mainapp;
            % if isdeployed
            %    app.MultiFitButton.Visible=false;
            % end

            if strcmp(app.SpecDB.XDirSwitch.Value,'XDir normal')
                app.xdir_state='normal';
                               app.NormFact=1;

                swap_lim_min=app.EditField_xlimMin.Layout;
                swap_lim_max=app.EditField_xlimMax.Layout;
                app.EditField_xlimMin.Layout=swap_lim_max;
                app.EditField_xlimMax.Layout=swap_lim_min;
                
                   swap_p1_max =app.xlimMax_p1.Layout;
                swap_m1_min=app.xlimMin_m1.Layout;
                app.xlimMin_m1.Layout=swap_p1_max;
                app.xlimMax_p1.Layout=swap_m1_min;

                swap_p10_max =app.xlimMax_p10.Layout;
                swap_m10_min=app.xlimMin_m10.Layout;
                app.xlimMin_m10.Layout=swap_p10_max;
                app.xlimMax_p10.Layout=swap_m10_min;


                swap_m1_max =app.xlimMax_m1.Layout;
                swap_p1_min =app.xlimMin_p1.Layout;
                app.xlimMax_m1.Layout=swap_p1_min;
                app.xlimMin_p1.Layout=swap_m1_max;

                swap_m10_max =app.xlimMax_m10.Layout;
                swap_p10_min =app.xlimMin_p10.Layout;
                app.xlimMax_m10.Layout=swap_p10_min;
                app.xlimMin_p10.Layout=swap_m10_max;


                try
                    app.SpectraPlot.XLabel.String=app.SpecDB.xlabel_str;
                    app.SpectraPlot_2.XLabel.String=app.SpecDB.xlabel_str;

                    app.SpectraPlot.YLabel.String=app.SpecDB.ylabel_str;
                    app.SpectraPlot_2.YLabel.String=app.SpecDB.ylabel_str;
                catch
                end
            else
                app.xdir_state='reverse';
                app.NormFact=10000;
            end

            app.SpectraPlot.XDir=app.xdir_state;
            app.SpectraPlot_2.XDir=app.xdir_state;
            app.FigXlimLive.XDir=app.xdir_state;


            app.DeconData=DeconData;

            app.xlimits=sort([app.SpecDB.Int_range_min.Value app.SpecDB.Int_range_max.Value]);
            app.xlimits=sort([app.SpecDB.Int_range_min.Value app.SpecDB.Int_range_max.Value]);
            app.EditField_xlimMax.Value=app.xlimits(2);
            app.EditField_xlimMin.Value=app.xlimits(1);

            update_class_tree(app)
            UpdateSpectraPlot(app)
            try
                if isdeployed
                    path=ctfroot;
                    path_split=split(path,{'SpecXY.app','SpecXY.exe'});
                    path=fullfile(path_split{1},'PeakDB.csv');
                    if not(exist(path,'file'))
                        path='PeakDB.csv';
                    end
                else
                    path=which('PeakDB.csv');
                end
                [app.Peaks.PeakDB, app.Peaks.PeakDB_raw]=ReadPeakdata_fun(path);
                update_PeakTree(app)

            catch ME
                %                message='Error not a valid PeakDB file';

                uialert(app.SimpleDeconModuleUIFigure,ME.message,'Error');
            end
             Update_Results_Table(app)
            update_decon_results(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: DerivativeSpinner, Derivative_CheckBox, 
        % Peaks_CheckBox, TypeDropDown, nEditField, plot_spectra_checkbox
        function SpecPlotSetChanged(app, event)
            UpdateSpectraPlot(app)
        end

        % Button pushed function: AutoPeakDetectionButton
        function AutoPeakDetectionButtonPushed(app, event)
            [x_signal, y_signal]=get_spec_mat(app,app.selected_sample);

            range=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

            y_signal=mean(y_signal(x_signal<max(range)&x_signal>min(range),:),2,"omitnan");
            x_signal=x_signal(x_signal<max(range)&x_signal>min(range),:);

            if app.DerivativeCheckBox.Value==true
                %%%%% ath add -derive here

                for i=1:abs(app.DerivativeSpinner.Value)
                    y_signal=gradient(y_signal);
                end

                if app.DerivativeSpinner.Value<0
                    y_signal=-y_signal;
                end
                %%%
                y_signal=-gradient(gradient(y_signal));
            end


            if x_signal(1)>x_signal(end)
                [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(flip(y_signal),flip(x_signal),'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
                %  findpeaks(flip(y_signal),flip(x_signal),'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
            else
                [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
             %      findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
            end


            [~,idx]=sort(Prominence,"descend");
            if numel(PeakPosition)>=app.PointstoClassifySpinner.Value
                PeakIntensity=PeakIntensity(idx);
                PeakIntensity=PeakIntensity(1:app.PointstoClassifySpinner.Value);

                PeakPosition=PeakPosition(idx);
                PeakPosition=PeakPosition(1:app.PointstoClassifySpinner.Value);

                Width=Width(idx);
                Width=Width(1:app.PointstoClassifySpinner.Value);

                Prominence=Prominence(idx);
                Prominence=Prominence(1:app.PointstoClassifySpinner.Value);

            else
                app.PointstoClassifySpinner.Value=numel(PeakPosition);
            end


%             app.Peaks.Autopeaks=table(PeakPosition,PeakIntensity,Width,Prominence,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
%             app.Peaks.Autopeaks.Spec(:)={''};
%             app.Peaks.Autopeaks.Source(:)={'Autodetected Peak'};
%             UpdatePeakTable(app)
%             UpdateSpectraPlot(app)

message={[num2str(numel(PeakPosition)), ' Peaks detected'],['Positions: ' char(join(string(round(PeakPosition,1))'))]};
uialert(app.SimpleDeconModuleUIFigure,message,'Autopeakdetection','Icon','success');


                new_peaks=PeakPosition; 
                Peaktype='PeakDB_Autodetected';
                add_peaks_fun(app,new_peaks,Peaktype)


        end

        % Button pushed function: AddSinglePeakButton
        function AddSinglePeakButtonPushed(app, event)
            fhv = app.SimpleDeconModuleUIFigure.HandleVisibility;        % Current status
            app.SimpleDeconModuleUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.SimpleDeconModuleUIFigure)       % Make fig current
            [x, ~] = selectDataPoints(app, app.SpectraPlot);

            x=round(x,1);
            app.SimpleDeconModuleUIFigure.HandleVisibility = fhv;
            %Spec=app.datatable.SpecName(app.datatable.active==true); ath
            %add here multiple specnames option

            UIpeaks=x;

            if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                app.Peaks.PeakDB_UI=app.Peaks.PeakDB_raw;
            end

            for n=1:numel(UIpeaks)
                app.Peaks.PeakDB_UI.Id(end+1)=app.Peaks.PeakDB_UI.Id(end)+1;
                app.Peaks.PeakDB_UI.PeakPosition(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.RefPeakPosition(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.Defect_WN(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.PeakShift(end)=0;
                app.Peaks.PeakDB_UI.Use2Quant(end)=0;
                app.Peaks.PeakDB_UI.PeakNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.Element{end}='';
                app.Peaks.PeakDB_UI.PeakgroupNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.MineralNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.R(end)=0;
                app.Peaks.PeakDB_UI.G(end)=0;
                app.Peaks.PeakDB_UI.B(end)=0;
                app.Peaks.PeakDB_UI.LineStyle{end}='A-';
                app.Peaks.PeakDB_UI.LineWidth(end)=1;
                app.Peaks.PeakDB_UI.Active(end)=1;
                app.Peaks.PeakDB_UI.FaceAlpha(end)=0.2;

            end

               [app.Peaks.PeakDB, ~]=ReadPeakdata_fun(app.Peaks.PeakDB_UI);

            update_PeakTree(app)
            UpdatePeakTable(app)
            UpdateSpectraPlot(app)

        end

        % Button pushed function: AddPeaksButton
        function AddPeaksButtonPushed(app, event)
            new_UI_peaks = inputdlg('Enter space-separated numbers:',...
                'Enter Peaks', [1 50]);
            if ~isempty(new_UI_peaks)
                %                 if not(isfield(app,'Peaks'))
                %                     app.Peaks=[];
                %                 end
                new_UI_peaks=unique(str2num(new_UI_peaks{1})); %#ok<ST2NM>
                Peaktype='PeakDB_UI';
                add_peaks_fun(app,new_UI_peaks,Peaktype)
            end
            figure(app.SimpleDeconModuleUIFigure);

        end

        % Button pushed function: DeleteUIPeaksButton
        function DeleteUIPeaksButtonPushed(app, event)
          

            UpdatePeakTable(app)
            UpdateSpectraPlot(app)
        end

        % Button pushed function: SaveAsPeaksetButton
        function SaveAsPeaksetButtonPushed(app, event)

        end

        % Button pushed function: DeletePeakButton
        function DeletePeakButtonPushed(app, event)
           app.Peaks= rmfield(app.Peaks,'PeakDB_UI');
          UpdatePeakTable(app)

        end

        % Button down function: SpectraPlot
        function SpectraPlotButtonDown(app, event)
            for k=1:numel(app.PeakLines)
                xVertLineCoord = get(app.PeakLines(k),'Value');
                click_buffer=(max(app.SpectraPlot.XLim)-min(app.SpectraPlot.XLim))*0.01; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.PeakLines(k).LineWidth=2;
                    app.PeakLines(k).Color='r';
                    app.hLineToDragPks = app.PeakLines(k);
                    app.hLineToDragPksIDX=k;
                    break;
                end
            end
        end

        % Window button motion function: SimpleDeconModuleUIFigure
        function SimpleDeconModuleUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDragPks) || ~isempty(app.hLineToDrag)
                inside_SpectraPlot=false;
                inside_FigXlimLive=false;
                if ~isempty(app.hLineToDragPks)
                    currentPoint   = app.SpectraPlot.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,2);
                    xlim = app.SpectraPlot.XLim;
                    ylim = app.SpectraPlot.YLim;
                    inside_SpectraPlot = x >= xlim(1)-(xlim(1)*app.clickbuffer) & x <= xlim(2)+(xlim(2)*app.clickbuffer) & y >= ylim(1) & y <= ylim(2); % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                end
                if  ~isempty(app.hLineToDrag)
                    currentPoint   = app.FigXlimLive.CurrentPoint;
                    xlim = app.FigXlimLive.XLim;
                    % ylim = app.FigXlimLive.YLim;
                    x            = currentPoint(1,1);
                    %  y            = currentPoint(1,3);

                    inside_FigXlimLive = x >= xlim(1) & x <= xlim(2); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                end
                if inside_SpectraPlot
                    set(app.hLineToDragPks, 'Value', x);
                    app.hLineToDragPks.Label=num2str(x);
                    %                     if app.PeakTable. if peak is from peakset shift else
                    % app.Peaks.(char(test)).Shift_=app.hLineToDragPksIDX;

                    app.Peaks.UIpeaks.PeakPosition(app.hLineToDragPksIDX)=get(app.hLineToDragPks, 'Value');

                elseif inside_FigXlimLive
                    set(app.hLineToDrag, 'Value', x);
                    xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                    app.EditField_xlimMin.Value = min(xposlines);
                    app.EditField_xlimMax.Value = max(xposlines);

                else
                    SimpleDeconModuleUIFigureWindowButtonUp(app)
                end

            end

        end

        % Window button up function: SimpleDeconModuleUIFigure
        function SimpleDeconModuleUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDragPks)


                app.Peaks.PeakDB_raw.PeakPosition(app.Peaks.PeakDB_raw.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))=get(app.hLineToDragPks, 'Value');
                shift=-(app.Peaks.PeakDB_raw.RefPeakPosition(app.Peaks.PeakDB_raw.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))-app.Peaks.PeakDB_raw.PeakPosition(app.Peaks.PeakDB_raw.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX)));
                app.Peaks.PeakDB_raw.PeakShift(app.Peaks.PeakDB_raw.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))=shift;

                if  isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI))
                    app.Peaks.PeakDB_UI.PeakPosition(app.Peaks.PeakDB_UI.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))=get(app.hLineToDragPks, 'Value');
                    shift=-(app.Peaks.PeakDB_UI.RefPeakPosition(app.Peaks.PeakDB_UI.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))-app.Peaks.PeakDB_UI.PeakPosition(app.Peaks.PeakDB_UI.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX)));
                    app.Peaks.PeakDB_UI.PeakShift(app.Peaks.PeakDB_UI.Id==app.Peaks.PeakDB_selection.Id(app.hLineToDragPksIDX))=shift;


                end


                UpdatePeakTable(app)

                app.hLineToDragPksIDX=[];

                app.hLineToDragPks.LineWidth=1;
                app.hLineToDragPks.Color='k';
                app.hLineToDragPks = [];
                UpdatePeakTable(app)
                UpdateSpectraPlot(app)

            elseif ~isempty(app.hLineToDrag)

                app.hLineToDrag = [];


                app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                set(app.hVerticalLines(1),'Value',app.EditField_xlimMin.Value)
                set(app.hVerticalLines(2),'Value',app.EditField_xlimMax.Value)

%                 xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];


                UpdateSpectraPlot(app)

                update_FigXlimLive(app)


            end
        end

        % Callback function
        function PrepareDeconvolutionButtonPushed(app, event)
            app.DeconStart = PrepareDeconFun(app);

            disp(app.DeconStart)

        end

        % Button pushed function: MCSimulateSettingsButton
        function MCSimulateSettingsButtonPushed(app, event)


if numel(app.selected_sample)>1

 message='Error too many spectra selected. Please select a single spectrum';
                uialert(app.SimpleDeconModuleUIFigure,message,'Error');
                return
end
            d = uiprogressdlg(app.SimpleDeconModuleUIFigure,'Title','Calculating Monte Carlo Fit',...
                'Message','Starting...','Cancelable','on');


            numTrials= app.MCIterationsSpinner.Value;
            try
                pos_ini=unique(app.PeakTable.Data.PeakPosition)';
                amp = nan(numTrials,numel(unique(app.PeakTable.Data.PeakPosition))); % Preallocate space.
                pos = nan(numTrials,numel(unique(app.PeakTable.Data.PeakPosition))); % Preallocate space.
                wid = nan(numTrials,numel(unique(app.PeakTable.Data.PeakPosition))); % Preallocate space.
                area = nan(numTrials,numel(unique(app.PeakTable.Data.PeakPosition))); % Preallocate space.
                shift = nan(numTrials,numel(unique(app.PeakTable.Data.PeakPosition))); % Preallocate space.
                GOF = nan(numTrials,3); % Preallocate space.

            catch
                pos_ini=[];
            end

            for k = 1:numTrials
                if d.CancelRequested
                                                        [FitResults, pos_ini, GOF_best1,PeakFit,x_signal,y_signal]= PrepareDeconFun(app);

                    break
                end
                d.Value = k/numTrials;
                d.Message = sprintf(['Iteration: ' num2str(k) ' / '  num2str(numTrials)]);


                if isempty (pos_ini)%k==1
                    [FitResults, pos_ini, GOF_best1]= PrepareDeconFun(app);
                    amp = nan(numTrials,numel(pos_ini)); % Preallocate space.
                    pos = nan(numTrials,numel(pos_ini)); % Preallocate space.
                    wid = nan(numTrials,numel(pos_ini)); % Preallocate space.
                    area = nan(numTrials,numel(pos_ini)); % Preallocate space.
                    shift = nan(numTrials,numel(pos_ini)); % Preallocate space.
                    GOF = nan(numTrials,3); % Preallocate space.

                end

                if k==numTrials
                    [FitResults, pos_ini, GOF_best1,PeakFit,x_signal,y_signal]= PrepareDeconFun(app);
                else
                    [FitResults, pos_ini, GOF_best1]= PrepareDeconFun(app);
                end
                amp(k,:)=FitResults(:,3)';
                pos(k,:)=FitResults(:,2)';
                wid(k,:)=FitResults(:,4)';
                area(k,:)=FitResults(:,5)';
                GOF(k,:) = GOF_best1;


                shift(k,:)= pos(k,:)-pos_ini;

                disp([num2str(k) ' of ' num2str(numTrials)])

            end
            % Get the standard deviation of those estimates:
            pos_std = std(pos,1,"omitnan");
            wid_std = std(wid,1,"omitnan");
            amp_std = std(amp,1,"omitnan");
            area_std = std(area,1,"omitnan");
            shift_std = std(shift,1,"omitnan");

            app.Peaks.DeconPeaks=table(mean(pos)',mean(amp)',mean(wid)',mean(shift)','VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
            app.Peaks.DeconPeaks.Spec(:)={''};
            app.Peaks.DeconPeaks.Source(:)={'DeconPeaks'};

            UpdatePeakTable(app)

            if app.PlotResultsCheckBox.Value==true

                fig=figure;
                tiledlayout(fig,5,size(pos,2))

                for n=1:size(pos,2)
                    nexttile
                    hold on

                    h(n)=histogram(pos(:,n),100);%#ok
                    title({ num2str(pos_ini(n)), ['pos - std: ' num2str(pos_std(n))]})
                    xline(pos_ini)
                    set (gca, 'xdir', app.xdir_state)

                end

                for n=1:size(pos,2)
                    nexttile
                    hold on
                    title(['shift - std: ' num2str(shift_std(n))])
                    h(n)=histogram(shift(:,n),100);
                    set (gca, 'xdir', app.xdir_state)
                end


                for n=1:size(pos,2)
                    nexttile
                    hold on
                    title(['amp - std: ' num2str(amp_std(n))])
                    h(n)=histogram(amp(:,n),100);

                end

                for n=1:size(pos,2)
                    nexttile
                    hold on

                    title(['wid - std: ' num2str(wid_std(n))])
                    h(n)=histogram(wid(:,n),100);
                end
                for n=1:size(pos,2)
                    nexttile
                    hold on
                    title(['area - std: ' num2str(area_std(n))])
                    h(n)=histogram(area(:,n),100);
                end


                figure;
                plot(pos,(1:size(pos,1)),'linewidth',2);
                hold on; xline(pos_ini)
                set (gca, 'xdir', app.xdir_state)
            end

            %
            % figure
            % for n=1:size(pos,2)
            % subplot(size(pos,2),1,n)
            % hold on
            % title(['amp ' num2str(pos_ini(n)) ' - std: ' num2str(amp_std(n))])
            % h(n)=histogram(amp(:,n),100);
            %
            % end
            %
            % figure
            % for n=1:size(pos,2)
            % subplot(size(pos,2),1,n)
            % hold on
            %
            % title(['wid ' num2str(pos_ini(n)) ' - std: ' num2str(wid_std(n))])
            % h(n)=histogram(wid(:,n),100);
            % end
            %
            % figure
            % for n=1:size(pos,2)
            % subplot(size(pos,2),1,n)
            % hold on
            %
            % title(['area ' num2str(pos_ini(n)) ' - std: ' num2str(area_std(n))])
            % h(n)=histogram(area(:,n),100);
            % end
            %
            % figure
            % for n=1:size(pos,2)
            % subplot(size(pos,2),1,n)
            % hold on
            %
            % title(['shift ' num2str(pos_ini(n)) ' - std: ' num2str(shift_std(n))])
            % h(n)=histogram(shift(:,n),100);
            % end


            assignin('base','pos_std',pos_std);
            assignin('base','wid_std',wid_std);
            assignin('base','amp_std',amp_std);
            assignin('base','area_std',area_std);
            assignin('base','shift_std',shift_std);
            assignin('base','pos_ini',pos_ini);
            assignin('base','pos',pos);
            assignin('base','wid',wid);
            assignin('base','amp',amp);
            assignin('base','area',area);
            assignin('base','shift',shift);
            assignin('base','Peaks',app.Peaks);
            assignin('base','GOF',GOF);
            assignin('base','x_signal',x_signal);
            assignin('base','y_signal',y_signal);
            assignin('base','PeakFit',PeakFit);


        end

        % Button pushed function: LoadPeakDBButton
        function LoadPeakDBButtonPushed(app, event)

            [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select Metadata for' char('')]);
            if not(isequal(file,0))
                [app.Peaks.PeakDB, app.Peaks.PeakDB_raw]=ReadPeakdata_fun(fullfile(path,file));
                update_PeakTree(app)
                
            else
                message='Error not a valid PeakDB file';
                uialert(app.SimpleDeconModuleUIFigure,message,'Error');
            end
            update_PeakTree(app)

        end

        % Callback function: ClassTree
        function ClassTreeCheckedNodesChanged(app, event)
            checkedNodes = app.ClassTree.CheckedNodes;
            if isempty (checkedNodes)
                app.selected_sample=[];
            else
                data=[checkedNodes.NodeData]';

                app.selected_sample=data(data>0);
            end
            UpdateSpectraPlot(app)

        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_LiveXMinMaxValueChanged(app, event)
            ValueMin = app.EditField_xlimMin.Value;
            ValueMax = app.EditField_xlimMax.Value;

            if ValueMin==ValueMax
                0;
            end

            if ValueMin < ValueMax
                try
                    set(app.hVerticalLines(1),'Value',ValueMin)
                    set(app.hVerticalLines(2),'Value',ValueMax)
                catch
                    app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                        xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                end

            end


            app.xlimits=[round(ValueMin) round(ValueMax)];

            if isempty(app.selected_sample)
                xlim(app.FigXlimLive,app.xlimits)

            else
                xlimval=sort([app.EditField_xlimMin.Value,app.EditField_xlimMax.Value]);
                xlim(app.FigXlimLive,xlimval)
            end


            UpdateSpectraPlot(app)


        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01; %ATH Settingspanel
                xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;

                end
            end
        end

        % Callback function: PeakTree
        function PeakTreeCheckedNodesChanged(app, event)
            %             checkedNodes = app.PeakTree.CheckedNodes;
            %
            %             if isempty(checkedNodes)
            %                 return
            %             end
            %             DeleteUIPeaksButtonPushed(app)
            %             Peak_eID=zeros(sum(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData})),3);
            %             iter=0;
            %             for n=find(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData}))
            %                 iter=iter+1;
            %                 Peak_eID(iter,:)=app.PeakTree.CheckedNodes(n).NodeData{1};
            %                 Peakdata(iter)=app.Peaks.PeakDB.Mineral(Peak_eID(iter,1)).Groups(Peak_eID(iter,2)).Peaks(Peak_eID(iter,3));
            %                 new_UI_peaks(iter)=Peakdata(iter).PeakPosition;
            %             end
            %
            %
            %             %                  if not(isfield(app,'Peaks'))
            %             %                      app.Peaks=[];
            %             %                  end
            %
            %             for n=1:length(new_UI_peaks)
            %                 x=new_UI_peaks(n);
            %                 Spec='';
            %
            %                 y=0;
            %
            %                 if ~isnan(y)
            %                     if isfield(app.Peaks,'UIpeaks')
            %                         app.Peaks.UIpeaks=[app.Peaks.UIpeaks;table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'})];
            %                     else
            %
            %                         app.Peaks.UIpeaks=table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
            %                     end
            %                 end
            %             end
            UpdatePeakTable(app)
            UpdateSpectraPlot(app)

        end

        % Button pushed function: DeconvoluteSelectedButton
        function DeconvoluteSelectedButtonPushed(app, event)

            k=0;
            % check if peaks from DB with quant info
      
            names={app.ClassTree.CheckedNodes([app.ClassTree.CheckedNodes.NodeData]>0).Text};

            % app.SpecDB.Data.DeconData=struct();
            d = uiprogressdlg(app.SimpleDeconModuleUIFigure,'Title','Calculating Deconvolution',...
                'Message','Starting...','Cancelable','on');

            for sampleID=1:numel(app.selected_sample)
                %%
                if d.CancelRequested
                    break
                end
                d.Value = sampleID/numel(app.selected_sample);

                d.Message = sprintf(['Calculating Sample: ' names{sampleID}]);
                %%
                [FitResults, pos_ini,GOF,PeakFit]= PrepareDeconFun(app,app.selected_sample(sampleID));
                k=k+1;
                save_decon_results(app,FitResults, pos_ini,GOF,PeakFit,app.selected_sample(sampleID),names{sampleID},'SimpleDecon')

                disp([num2str(sampleID) ' of ' num2str(numel(app.selected_sample))])
            end


            Update_Results_Table(app)

            update_decon_results(app,1)


            %             IntQuant=false;
            %             LinQuant=false;
            %             FactorQuant=false;
            %             insert_results=false;
            %
            %             if insert_results==true
            %                 pos_ini=round(pos_ini);
            %                 for m=1:numel(pos_ini)
            %                     for n=1:numel(app.selected_sample)
            %                         if m==1
            %                             [app.SpecDB.Data.Meta(app.selected_sample(n)).(char('SumOfPeaks'))]=deal(sum(area(n,:)));
            %                         end
            %                         [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_area']))]=deal(area(n,m));
            %                         if IntQuant==true
            %                             [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_IntQuant']))]=deal(area(n,m));
            %                         end
            %
            %                         if LinQuant==true
            %                             [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_LinQuant']))]=deal(area(n,m));
            %                         end
            %
            %                         if FactorQuant==true
            %                             [app.SpecDB.Data.Meta(app.selected_sample(n)).(matlab.lang.makeValidName(char(['peak_' num2str(pos_ini(m)) '_FactorQuant_' num2str(QantFactor)])))]=deal(area(n,m).*quantfactor);
            %                         end
            %
            %                         [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_amp']))]=deal(amp(n,m));
            %                         [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_wid']))]=deal(wid(n,m));
            %                         [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_pos']))]=deal(pos(n,m));
            %                         [app.SpecDB.Data.Meta(app.selected_sample(n)).(char(['peak_' num2str(pos_ini(m)) '_shift']))]=deal(shift(n,m));
            %                         if m==numel(pos_ini)
            %                             [app.SpecDB.Data.Meta(app.selected_sample(n)).(char('R2'))]=deal(GOF_(n,2));
            %                         end
            %                     end
            %                 end
            %                 update_public(app.SpecDB,1)
            %             end
            app.SpecDB.edit_state=true;
        end

        % Button pushed function: ResetShiftButton
        function ResetShiftButtonPushed(app, event)
            app.Peaks.PeakDB_raw.PeakPosition=app.Peaks.PeakDB_raw.RefPeakPosition;
            shift=-(app.Peaks.PeakDB_raw.RefPeakPosition-app.Peaks.PeakDB_raw.PeakPosition);
            app.Peaks.PeakDB_raw.PeakShift=shift;

            if  isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI))
           app.Peaks.PeakDB_UI.PeakPosition=app.Peaks.PeakDB_UI.RefPeakPosition;
            shift=-(app.Peaks.PeakDB_UI.RefPeakPosition-app.Peaks.PeakDB_UI.PeakPosition);
            app.Peaks.PeakDB_UI.PeakShift=shift;
            end
          

            UpdatePeakTable(app)
            UpdateSpectraPlot(app)
        end

        % Value changed function: FullTableCheckBox
        function FullTableCheckBoxValueChanged(app, event)
            UpdatePeakTable(app)
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ClassTree.CheckedNodes=app.ClassTree.Children;

            ClassTreeCheckedNodesChanged(app)

        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            app.ClassTree.CheckedNodes=[];
            ClassTreeCheckedNodesChanged(app)

        end

        % Button pushed function: DeselectAllButton_2
        function DeselectAllButton_2Pushed(app, event)
            app.PeakTree.CheckedNodes=[];
            PeakTreeCheckedNodesChanged(app)
        end

        % Callback function: UITable_2
        function UITable_2SelectionChanged(app, event)
            if isempty(app.UITable_2.Selection)
                return
            end
            selection = app.UITable_2.Selection;

            update_decon_results(app,unique(selection(:,1)))
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            selection = app.UITable_2.Selection;

            app.SpecDB.Data.DeconData(app.UITable_2.Data.e_Id(unique(selection(:,1)))).ActiveDeconGroup= app.ListBox.Value;
            Update_Results_Table(app)            
            update_decon_results(app,unique(selection(:,1)))
            app.SpecDB.edit_state=true;
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            app.SpecDB.Data.DeconData=[];
            update_decon_results(app)
                        app.SpecDB.edit_state=true;
        end

        % Button pushed function: FindBestR2Button
        function FindBestR2ButtonPushed(app, event)
            for n=1:numel(app.UITable_2.Data.e_Id)
                sampleID=app.UITable_2.Data.e_Id(n);
                posbestr2=find(max([app.SpecDB.Data.DeconData(sampleID).DeconResults(:).error_r2])==[app.SpecDB.Data.DeconData(sampleID).DeconResults(:).error_r2]);

                if numel(posbestr2)>1
                    posbestr2=posbestr2(1);
                end

                app.SpecDB.Data.DeconData(sampleID).ActiveDeconGroup=posbestr2;
            end
            Update_Results_Table(app)
            update_decon_results(app)
            app.SpecDB.edit_state=true;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            selection = app.UITable_2.Selection;
            if isempty(app.UITable_2.Selection)
                return
            end
            sampleID=app.UITable_2.Data.e_Id(unique(selection(:,1)));

            DeconID=app.SpecDB.Data.DeconData(sampleID).ActiveDeconGroup;

            if strcmp('Use2Quant',app.UITable.ColumnName(indices(2)))
                app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).Use2Quant(indices(1))=newData;
            elseif strcmp('QuantFactor',app.UITable.ColumnName(indices(2)))
                app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).QuantFactor(indices(1))=newData;
            elseif strcmp('PeakNames',app.UITable.ColumnName(indices(2)))
                app.SpecDB.Data.DeconData(sampleID).DeconInput(DeconID).Peaks.PeakDB.PeakNames(indices(1))={newData};
            end

            app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).SumOfPeaks=sum(app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).area(logical(app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).Use2Quant)),2,"omitnan");%sum(area(k,:),2);
            app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).Total_H2O_FactorQuant= sum(app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).QuantFactor(logical(app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).Use2Quant)).*app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).area(logical(app.SpecDB.Data.DeconData(sampleID).DeconResults(DeconID).Use2Quant)),2,"omitnan");

            Update_Results_Table(app)
            update_decon_results(app)

            %             app.SpecDB.Data.DeconData(app.selected_sample(sampleID)).DeconResults(DeconID).Total_H2O_FactorQuant= sum(QuantFactor(logical(app.SpecDB.Data.DeconData(app.selected_sample(sampleID)).DeconResults(DeconID).Use2Quant)).*app.SpecDB.Data.DeconData(app.selected_sample(sampleID)).DeconResults(DeconID).area(logical(app.SpecDB.Data.DeconData(app.selected_sample(sampleID)).DeconResults(DeconID).Use2Quant)),2,"omitnan");
        end

        % Button pushed function: DeleteGroupButton
        function DeleteGroupButtonPushed(app, event)

            selection = app.UITable_2.Selection;
            sampleID=app.UITable_2.Data.e_Id(unique(selection(:,1)));
            for n=1:numel(sampleID)
            DeconID=app.SpecDB.Data.DeconData(sampleID(n)).ActiveDeconGroup;

            app.SpecDB.Data.DeconData(sampleID(n)).DeconInput(DeconID)=[];
            app.SpecDB.Data.DeconData(sampleID(n)).DeconResults(DeconID)=[];
            app.SpecDB.Data.DeconData(sampleID(n)).PlotSettings(DeconID)=[];
            app.SpecDB.Data.DeconData(sampleID(n)).DeconvolutionName(DeconID)=[];
            app.SpecDB.Data.DeconData(sampleID(n)).PeakFit(DeconID)=[];
            app.SpecDB.Data.DeconData(sampleID(n)).DeconType(DeconID)=[];

            if numel(app.SpecDB.Data.DeconData(sampleID(n)).DeconvolutionName)==1
                app.SpecDB.Data.DeconData(sampleID(n)).ActiveDeconGroup=[];
            else
                if app.SpecDB.Data.DeconData(sampleID(n)).ActiveDeconGroup>1
                    app.SpecDB.Data.DeconData(sampleID(n)).ActiveDeconGroup=app.SpecDB.Data.DeconData(sampleID(n)).ActiveDeconGroup-1;
                end

            end
            end
            Update_Results_Table(app)
            update_decon_results(app)
            app.SpecDB.edit_state=true;
        end

        % Button pushed function: AddbesttoDBButton
        function AddbesttoDBButtonPushed(app, event)
            ValidDecondata=app.SpecDB.Data.DeconData(not(cellfun(@isempty ,{app.SpecDB.Data.DeconData.DeconvolutionName})));
DeconvolutionName='Decon';
            for n=1:numel(ValidDecondata)
                e_Id=ValidDecondata(n).DeconInput(1).SampleInfo.eId;
                for m=app.SpecDB.Data.DeconData(e_Id).ActiveDeconGroup

                 %   DeconvolutionName=ValidDecondata(n).DeconvolutionName{m};

                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_SumOfPeaks'])))]=ValidDecondata(n).DeconResults(m).SumOfPeaks;
                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_Total_H2O_FactorQuant'])))]=ValidDecondata(n).DeconResults(m).Total_H2O_FactorQuant;
                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_Total_H2O_error_r2'])))]=ValidDecondata(n).DeconResults(m).error_r2;
               
                    names=matlab.lang.makeUniqueStrings(ValidDecondata(n).DeconInput(m).Peaks.PeakDB.PeakNames);

                    if app.PeakResultsCheckBox.Value==true
                        for k=1:numel(ValidDecondata(n).DeconInput(m).Peaks.PeakDB.PeakNames)
                            name=names{k};

                        %    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_Use2Quant' ])))]=logical(ValidDecondata(n).DeconResults(m).Use2Quant(k));
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_pos' ])))]=ValidDecondata(n).DeconResults(m).pos(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_shift' ])))]=ValidDecondata(n).DeconResults(m).shift(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_width' ])))]=ValidDecondata(n).DeconResults(m).width(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_hight' ])))]=ValidDecondata(n).DeconResults(m).hight(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_area' ])))]=ValidDecondata(n).DeconResults(m).area(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_GL_blend' ])))]=ValidDecondata(n).DeconResults(m).GL_blend(k);
                       %     [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_QuantFactor' ])))]=ValidDecondata(n).DeconResults(m).QuantFactor(k);
                        %    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_H2O_FactorQuant' ])))]=ValidDecondata(n).DeconResults(m).H2O_FactorQuant(k);
                        end
                    end
                end

            end


            update_public(app.SpecDB,1)

        end

        % Button pushed function: AddalltoDBButton
        function AddalltoDBButtonPushed(app, event)
            ValidDecondata=app.SpecDB.Data.DeconData(not(cellfun(@isempty ,{app.SpecDB.Data.DeconData.DeconvolutionName})));


            for n=1:numel(ValidDecondata)
                DeconID=numel(ValidDecondata(n).DeconvolutionName);

                for m=1:numel(DeconID)
                    e_Id=ValidDecondata(n).DeconInput(m).SampleInfo.eId;

                    DeconvolutionName=ValidDecondata(n).DeconvolutionName{m};

                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_SumOfPeaks'])))]=ValidDecondata(n).DeconResults(m).SumOfPeaks;
                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_Total_H2O_FactorQuant'])))]=ValidDecondata(n).DeconResults(m).Total_H2O_FactorQuant;
                    [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_Total_H2O_error_r2'])))]=ValidDecondata(n).DeconResults(m).error_r2;
                    if app.PeakResultsCheckBox.Value==true
                    for k=1:numel(ValidDecondata(n).DeconInput(m).Peaks.PeakDB.PeakNames)
                        name=ValidDecondata(n).DeconInput(m).Peaks.PeakDB.PeakNames{k};
                        [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_Use2Quant' ])))]=logical(ValidDecondata(n).DeconResults(m).Use2Quant(k));
                        [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_pos' ])))]=ValidDecondata(n).DeconResults(m).pos(k);
                        [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_shift' ])))]=ValidDecondata(n).DeconResults(m).shift(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_width' ])))]=ValidDecondata(n).DeconResults(m).width(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_hight' ])))]=ValidDecondata(n).DeconResults(m).hight(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_area' ])))]=ValidDecondata(n).DeconResults(m).area(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_QuantFactor' ])))]=ValidDecondata(n).DeconResults(m).QuantFactor(k);
                            [app.SpecDB.Data.Meta(e_Id).(matlab.lang.makeValidName(char([DeconvolutionName '_' name '_H2O_FactorQuant' ])))]=ValidDecondata(n).DeconResults(m).H2O_FactorQuant(k);
                    end
                    end
                end

            end


            update_public(app.SpecDB,1)

        end

        % Button pushed function: OHCalcQuantSelectionButton
        function OHCalcQuantPushed(app, event)
            selection = app.UITable_2.Selection;
            sampleID=app.UITable_2.Data.e_Id(unique(selection(:,1)));
            UniqueNames=unique(app.UITable_2.Data.name);

            for n=1:numel(UniqueNames)
                sampleID_n=app.UITable_2.Data.e_Id(find(ismember(app.UITable_2.Data.name,UniqueNames(n))));
                y_signal= cell(1,numel(sampleID_n));
                x_signal= cell(1,numel(sampleID_n));
                for m=1:numel(sampleID_n)
                    DeconID=app.SpecDB.Data.DeconData(sampleID_n(m)).ActiveDeconGroup;
                    y_signal{m}=DeconPlotFun(app.SpecDB.Data.DeconData(sampleID_n(m)).PeakFit(DeconID).peakshape,app.SpecDB.Data.DeconData(sampleID_n(m)).PeakFit(DeconID).signal(:,1)',app.SpecDB.Data.DeconData(sampleID_n(m)).DeconResults(DeconID).pos',app.SpecDB.Data.DeconData(sampleID_n(m)).DeconResults(DeconID).width',app.SpecDB.Data.DeconData(sampleID_n(m)).DeconResults(DeconID).hight',app.SpecDB.Data.DeconData(sampleID_n(m)).PeakFit(DeconID).extra',app.SpecDB.Data.DeconData(sampleID_n(m)).PeakFit(DeconID).DELTA')';
                    y_signal{m}(:,logical(~app.SpecDB.Data.DeconData(sampleID_n(m)).DeconResults(DeconID).Use2Quant))=0;
                    sum(sum(y_signal{m},[]))
                    x_signal{m}=[app.SpecDB.Data.DeconData(sampleID_n(m)).PeakFit(DeconID).signal(:,1)];
                end
                CalculateH2O(app.SpecDB,x_signal,y_signal,sampleID_n,[UniqueNames{n} '_DeconCalc' ],[],true)
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            xlimval=[min(app.FigXlimLive.XLim)...
                max(app.FigXlimLive.XLim)];
            app.EditField_xlimMin.Value=min(xlimval);
            app.EditField_xlimMax.Value=max(xlimval);
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)
              app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)
              app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)
              app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)
             app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)
             app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)
                         app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)
                         app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)
             app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-10;
             EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: MultiFitButton
        function MultiFitButtonPushed(app, event)
            d = uiprogressdlg(app.SimpleDeconModuleUIFigure,'Title','Calculating Multi Monte Carlo Fit',...
                'Message','Starting...','Cancelable','on');

            names={app.ClassTree.CheckedNodes([app.ClassTree.CheckedNodes.NodeData]>0).Text};

            for sampleID=1:numel(app.selected_sample)
                 if d.CancelRequested
                    break
                end
                d.Value = sampleID/(numel(app.selected_sample)+1);

                d.Message = sprintf(['Organising Spectra - Estimating starting Guess: ' names{sampleID}]);
                [FitResults_best1, pos_ini, GOF_best1,PeakFit,x_signal,in_thx]= PrepareDeconFun(app,app.selected_sample(sampleID));
                if numel(app.selected_sample)==1

                    [x, y, ~, Thickness] = get_specs(app.SpecDB,app.selected_sample,true);
                    wn_thx =cell2mat(x(1));
                    if cell2mat(Thickness(1))==0
                        Thickness(1)={app.NormFact};
                    end
                    %y1=cell2mat(y(n));
                    y_signal=(cell2mat(y(1))./(cell2mat(Thickness(1))./app.NormFact));

                    y_signal=y_signal(wn_thx>=min(x_signal)&wn_thx<=max(x_signal),:);
                    x_signal=wn_thx(wn_thx>=min(x_signal)&wn_thx<=max(x_signal));
                else
                    y_signal(:,sampleID)=mean(in_thx,2,'omitnan');
                end
            end

            pos=FitResults_best1(:,2);
            amp=FitResults_best1(:,3);
            wid=FitResults_best1(:,4);
%             figure
%             nexttile
%             hold on
%             g=gaussplot(app,pos',amp',wid',x_signal);
%             plot(x_signal,y_signal,'k')
%             plot(x_signal,g,'r')
%             plot(x_signal,sum(g,2),':g')
            NumPeaks=numel(pos);
            extra=app.ExtraEditField.Value;
            Delta=app.DELTAEditField.Value;
                                       peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
                d.Message = sprintf(['MultiFit Deconvolution']);

           [gaussianParameters,GOF]= multifit_dev3(x_signal,y_signal,NumPeaks,peakshape,extra,Delta,[pos wid]);


            gaussianParameters = sortrows(gaussianParameters, 2);
            pos=gaussianParameters(:,2);
            wid=gaussianParameters(:,3);


%             nexttile
%             hold on


A=DeconPlotFun(peakshape,x_signal,pos',wid',1,extra,Delta);
figure
         %   plot(x_signal,y_signal,'k')


plot3(x_signal,ones(size(x_signal)).*(1:size(y_signal,2)),y_signal,'K',LineWidth=0.5)
            hold on

            for k=1:size(y_signal,2)
                      d.Value = k/(size(y_signal,2)+1);
                d.Message = sprintf(['Saving Results: ' names{sampleID}]);
      
                c = A \ y_signal(:,k);
                z = A * c;
            %    plot(x_signal,z,':g','linewidth',2)
             %   z_=gaussplot(pos,c,wid,x_signal');
                z_=DeconPlotFun(peakshape,x_signal,pos',wid',c',extra,Delta);

              %  plot(x_signal,z_,'r','linewidth',1)
plot3(x_signal,ones(size(x_signal)).*k,z,'g:',LineWidth=1)

z_(z_<0.001*max(z_))=nan;
plot3(x_signal,ones(size(x_signal)).*k,z_,'r',LineWidth=0.5)


                FitResults  = FitResults_best1;
                FitResults(:,3)=c;
                FitResults(:,2)=pos;
                FitResults(:,4)=wid;

                if x_signal(1)>x_signal(end)
                    FitResults(:,5)=  trapz(flip(x_signal),c'.*A);
                else
                    FitResults(:,5)=  trapz(x_signal,c'.*A);
                end

                PeakFit(1).signal(:,1)=x_signal;
                PeakFit(1).signal(:,2)=y_signal(:,k);
                
% try
%                 save_decon_results(app,FitResults, pos_ini,GOF,PeakFit,app.selected_sample(k),names{k} ,'MultiDecon')
% catch  ME
%     ME.stack.line
% end
            end

            Update_Results_Table(app)
            update_decon_results(app,1)
            delete(d)
            %             plot(x_signal,sum(g),':g','linewidth',2)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimpleDeconModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {949, 949};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleDeconModuleUIFigure = uifigure('Visible', 'off');
            app.SimpleDeconModuleUIFigure.Name = 'SimpleDeconModule';
            app.SimpleDeconModuleUIFigure.Icon = 'symbols_1_SimpleDecon.png';
            app.SimpleDeconModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SimpleDeconModuleUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @SimpleDeconModuleUIFigureWindowButtonUp, true);
            app.SimpleDeconModuleUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SimpleDeconModuleUIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.SimpleDeconModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.PeakTree = uitree(app.GridLayout6, 'checkbox');
            app.PeakTree.Editable = 'on';
            % Assign Checked Nodes
            app.PeakTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PeakTreeCheckedNodesChanged, true);
            app.PeakTable = uitable(app.GridLayout6);
            app.PeakTable.ColumnName = {'Position'; 'Intensity'; 'Width'; 'Shift'; 'Source'};
            app.PeakTable.RowName = {};
            app.PeakTable.ColumnSortable = [true true true true true true];
            app.PeakTable.ColumnEditable = [false false true true false false];
            app.AddSinglePeakButton = uibutton(app.GridLayout6, 'push');
            app.AddSinglePeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddSinglePeakButtonPushed, true);
            app.AddSinglePeakButton.Text = 'Add Single Peak';
            app.SaveAsPeaksetButton = uibutton(app.GridLayout6, 'push');
            app.SaveAsPeaksetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsPeaksetButtonPushed, true);
            app.SaveAsPeaksetButton.Visible = 'off';
            app.SaveAsPeaksetButton.Text = 'Save As Peakset';
            app.DeleteUIPeaksButton = uibutton(app.GridLayout6, 'push');
            app.DeleteUIPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUIPeaksButtonPushed, true);
            app.DeleteUIPeaksButton.Visible = 'off';
            app.DeleteUIPeaksButton.Text = 'Delete UI Peaks';
            app.TabGroup4 = uitabgroup(app.GridLayout6);
            app.DeconGroupsTab = uitab(app.TabGroup4);
            app.DeconGroupsTab.Title = 'DeconGroups';
            app.ListBox = uilistbox(app.DeconGroupsTab);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AutoPeakDetectionTab = uitab(app.TabGroup4);
            app.AutoPeakDetectionTab.Title = 'AutoPeakDetection';
            app.GridLayout5 = uigridlayout(app.AutoPeakDetectionTab);
            app.AutoPeakDetectionButton = uibutton(app.GridLayout5, 'push');
            app.AutoPeakDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutoPeakDetectionButtonPushed, true);
            app.AutoPeakDetectionButton.Text = 'Auto Peak Detection';
            app.MinPeakHightSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakHightSpinnerLabel.Text = 'Min Peak Hight';
            app.MinPeakHightSpinner = uispinner(app.GridLayout5);
            app.MinPeakHightSpinner.Step = 0.1;
            app.MinPeakHightSpinner.Limits = [0 Inf];
            app.MinPeakHightSpinner.Tooltip = {'Use Derivative to Classify'};
            app.MinPeakPromenanceSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakPromenanceSpinnerLabel.Text = 'Min Peak Promenance';
            app.MinPeakPromenanceSpinner = uispinner(app.GridLayout5);
            app.MinPeakPromenanceSpinner.Step = 0.1;
            app.MinPeakPromenanceSpinner.Limits = [0 Inf];
            app.MinPeakDistanceSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakDistanceSpinnerLabel.Text = 'Min Peak Distance';
            app.MinPeakDistanceSpinner = uispinner(app.GridLayout5);
            app.MinPeakDistanceSpinner.Limits = [0 Inf];
            app.DerivativeCheckBox = uicheckbox(app.GridLayout5);
            app.DerivativeCheckBox.Text = 'Derivative ';
            app.PointstoClassifySpinner = uispinner(app.GridLayout5);
            app.PointstoClassifySpinner.Limits = [1 Inf];
            app.PointstoClassifySpinner.RoundFractionalValues = 'on';
            app.PointstoClassifySpinner.Value = 5;
            app.PointstoClassifySpinnerLabel = uilabel(app.GridLayout5);
            app.PointstoClassifySpinnerLabel.Text = 'Max Nr. Peaks';
            app.AddPeaksButton = uibutton(app.GridLayout6, 'push');
            app.AddPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddPeaksButtonPushed, true);
            app.AddPeaksButton.Text = 'Add Peaks';
            app.DeletePeakButton = uibutton(app.GridLayout6, 'push');
            app.DeletePeakButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePeakButtonPushed, true);
            app.DeletePeakButton.Visible = 'off';
            app.DeletePeakButton.Text = 'Delete Peak';
            app.LoadPeakDBButton = uibutton(app.GridLayout6, 'push');
            app.LoadPeakDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeakDBButtonPushed, true);
            app.LoadPeakDBButton.Text = 'Load PeakDB';
            app.ClassTree = uitree(app.GridLayout6, 'checkbox');
            % Assign Checked Nodes
            app.ClassTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ClassTreeCheckedNodesChanged, true);
            app.FullTableCheckBox = uicheckbox(app.GridLayout6);
            app.FullTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @FullTableCheckBoxValueChanged, true);
            app.FullTableCheckBox.Text = 'Full Table';
            app.ResetShiftButton = uibutton(app.GridLayout6, 'push');
            app.ResetShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ResetShiftButtonPushed, true);
            app.ResetShiftButton.Text = 'Reset Shift';
            app.SelectAllButton = uibutton(app.GridLayout6, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.GridLayout6, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.DeselectAllButton_2 = uibutton(app.GridLayout6, 'push');
            app.DeselectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_2Pushed, true);
            app.DeselectAllButton_2.Text = 'Deselect All';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.RightPanel);
            app.TabGroup5 = uitabgroup(app.GridLayout12);
            app.DeconvolutionSettingsTab = uitab(app.TabGroup5);
            app.DeconvolutionSettingsTab.Title = 'DeconvolutionSettings';
            app.GridLayout11 = uigridlayout(app.DeconvolutionSettingsTab);
            app.SpectraPlot = uiaxes(app.GridLayout11);
            xlabel(app.SpectraPlot, 'Wavenumbers [cm^{-1}]')
            ylabel(app.SpectraPlot, 'Absorbance')
            zlabel(app.SpectraPlot, 'Sample')
            app.SpectraPlot.XLim = [500 4000];
            app.SpectraPlot.XDir = 'reverse';
            app.SpectraPlot.ButtonDownFcn = createCallbackFcn(app, @SpectraPlotButtonDown, true);
            app.DeconvoluteSelectedButton = uibutton(app.GridLayout11, 'push');
            app.DeconvoluteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvoluteSelectedButtonPushed, true);
            app.DeconvoluteSelectedButton.Text = 'Deconvolute Selected';
            app.CloseButton = uibutton(app.GridLayout11, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.GridLayout11);
            app.GridLayout8 = uigridlayout(app.Panel_2);
            app.Peaks_CheckBox = uicheckbox(app.GridLayout8);
            app.Peaks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.Peaks_CheckBox.Text = '';
            app.Peaks_CheckBox.Value = true;
            app.Derivative_CheckBox = uicheckbox(app.GridLayout8);
            app.Derivative_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.Derivative_CheckBox.Text = '';
            app.plot_spectra_checkbox = uicheckbox(app.GridLayout8);
            app.plot_spectra_checkbox.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.plot_spectra_checkbox.Text = '';
            app.plot_spectra_checkbox.Value = true;
            app.SpectraLabel = uilabel(app.GridLayout8);
            app.SpectraLabel.Text = 'Spectra';
            app.DerivativeLabel = uilabel(app.GridLayout8);
            app.DerivativeLabel.Text = 'Derivative';
            app.DerivativeSpinner = uispinner(app.GridLayout8);
            app.DerivativeSpinner.Limits = [-10 10];
            app.DerivativeSpinner.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.DerivativeSpinner.Value = -2;
            app.PeaksLabel = uilabel(app.GridLayout8);
            app.PeaksLabel.Text = 'Peaks';
            app.nEditField = uieditfield(app.GridLayout8, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.nEditField.Value = 25;
            app.LegendCheckBox = uicheckbox(app.GridLayout8);
            app.LegendCheckBox.Visible = 'off';
            app.LegendCheckBox.Text = 'Legend';
            app.TypeDropDown = uidropdown(app.GridLayout8);
            app.TypeDropDown.Items = {'Default', 'Stacked', 'Sum', 'Sample Sum', 'Mean'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecPlotSetChanged, true);
            app.TypeDropDown.Value = 'Default';
            app.Panel = uipanel(app.GridLayout11);
            app.GridLayout9 = uigridlayout(app.Panel);
            app.FitShapeDropDownLabel = uilabel(app.GridLayout9);
            app.FitShapeDropDownLabel.Text = 'Fit Shape';
            app.FitShapeDropDown = uidropdown(app.GridLayout9);
            app.FitShapeDropDown.Items = {'1=Gaussian (default)', '2=Lorentzian', '3=logistic distribution', '4=Pearson', '5=exponentionally broadened Gaussian;', '6=equal-width Gaussians', '7=Equal-width Lorentzians', '8=exponentionally broadened equal-width Gaussian', '9=exponential pulse', '10=up-sigmoid (logistic function)', '11=Fixed-width Gaussian', '12=Fixed- width Lorentzian;', '13=Gaussian/Lorentzian blend ', '14=Bifurcated Gaussian,', '15=Breit-Wigner-Fano', '16=Fixed-position Gaussians', '17=Fixed-position Lorentzians', '18=exponentionally broadened Lorentzian', '19=alpha function; ', '20=Voigt profile', '21=triangular', '22=?plus extra?', '23=down-sigmoid', '24=Negative Binomial Distribution', '25=lognormal', '26=slope;', '27=Gaussian first derivative', '28=polynomial', '29=piecewise linear;', '30=variable-alpha Voigt', '31=variable time constant ExpGaussian;', '32=variable shape Pearson', '33=variable Gaussian/Lorentzian blend,', '34=fixed-width Voigt', '35=fixed-width Gaussian/Lorentzian blend; ', '36=fixed-width exponentionally-broadened Gaussian', '37=fixed-width Pearson', '38=variable time constant ExpLorentzian', '39=variable timeconstant ExpGaussian2', '40=equal-width GL blend', '41=rectangle;', '42=flattened Gaussian', '43:PearsonIV function (3 variables);', '44=1-exp(-k*x)', '45 Four-parameter logistic', '46=quadratic/parabola;', '47=blackbody emission', '48=equal-width exponential pulses', '49=Pearson IV (variable asymmetrical peak)', '50=multilinear regression (known peak positions and widths)'};
            app.FitShapeDropDown.Value = '1=Gaussian (default)';
            app.IterationsSpinnerLabel = uilabel(app.GridLayout9);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.GridLayout9);
            app.IterationsSpinner.Limits = [0 Inf];
            app.AutozeroSpinnerLabel = uilabel(app.GridLayout9);
            app.AutozeroSpinnerLabel.Text = 'Autozero';
            app.AutozeroSpinner = uispinner(app.GridLayout9);
            app.AutozeroSpinner.Limits = [0 3];
            app.MCSimulateSettingsButton = uibutton(app.GridLayout9, 'push');
            app.MCSimulateSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @MCSimulateSettingsButtonPushed, true);
            app.MCSimulateSettingsButton.Text = 'MC Simulate Settings';
            app.IterationsSpinnerLabel_2 = uilabel(app.GridLayout9);
            app.IterationsSpinnerLabel_2.Text = 'MC Iterations';
            app.MCIterationsSpinner = uispinner(app.GridLayout9);
            app.MCIterationsSpinner.Limits = [0 Inf];
            app.MCIterationsSpinner.Value = 25;
            app.PlotResultsCheckBox = uicheckbox(app.GridLayout9);
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.DELTAEditFieldLabel = uilabel(app.GridLayout9);
            app.DELTAEditFieldLabel.Text = 'DELTA';
            app.DELTAEditField = uieditfield(app.GridLayout9, 'numeric');
            app.DELTAEditField.Value = 1;
            app.ExtraEditFieldLabel = uilabel(app.GridLayout9);
            app.ExtraEditFieldLabel.Text = 'Extra';
            app.ExtraEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LimitShiftCheckBox = uicheckbox(app.GridLayout9);
            app.LimitShiftCheckBox.Text = 'Limit Shift';
            app.DeconGroupNameEditFieldLabel = uilabel(app.GridLayout9);
            app.DeconGroupNameEditFieldLabel.Text = 'Decon Group Name';
            app.DeconGroupNameEditField = uieditfield(app.GridLayout9, 'text');
            app.DeconGroupNameEditField.Value = 'Deconvolution';
            app.GridLayout_PlotSettings = uigridlayout(app.GridLayout11);
            app.FigXlimLive = uiaxes(app.GridLayout_PlotSettings);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.EditField_xlimMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.xlimMax_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+10';
            app.xlimMax_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '- 1';
            app.xlimMin_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '- 1';
            app.xlimMin_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+10';
            app.xlimMin_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-10';
            app.xlimMin_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+ 1';
            app.xlimMax_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+ 1';
            app.xlimMax_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-10';
            app.ResetButton = uibutton(app.GridLayout_PlotSettings, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MultiFitButton = uibutton(app.GridLayout11, 'push');
            app.MultiFitButton.ButtonPushedFcn = createCallbackFcn(app, @MultiFitButtonPushed, true);
            app.MultiFitButton.Text = 'MultiFit';
            app.ResultsTab = uitab(app.TabGroup5);
            app.ResultsTab.Title = 'Results';
            app.GridLayout13 = uigridlayout(app.ResultsTab);
            app.SpectraPlot_2 = uiaxes(app.GridLayout13);
            xlabel(app.SpectraPlot_2, 'Wavenumbers [cm^{-1}]')
            ylabel(app.SpectraPlot_2, 'Absorbance')
            zlabel(app.SpectraPlot_2, 'Sample')
            app.SpectraPlot_2.XLim = [3050 3720];
            app.SpectraPlot_2.XDir = 'reverse';
            app.UITable = uitable(app.GridLayout13);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable_2 = uitable(app.GridLayout13);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.UITable_2.SelectionChangedFcn = createCallbackFcn(app, @UITable_2SelectionChanged, true);
            app.DeleteGroupButton = uibutton(app.GridLayout13, 'push');
            app.DeleteGroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGroupButtonPushed, true);
            app.DeleteGroupButton.Text = 'Delete Group';
            app.ClearAllButton_2 = uibutton(app.GridLayout13, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All ';
            app.FindBestR2Button = uibutton(app.GridLayout13, 'push');
            app.FindBestR2Button.ButtonPushedFcn = createCallbackFcn(app, @FindBestR2ButtonPushed, true);
            app.FindBestR2Button.Text = 'Find Best R2';
            app.AddalltoDBButton = uibutton(app.GridLayout13, 'push');
            app.AddalltoDBButton.ButtonPushedFcn = createCallbackFcn(app, @AddalltoDBButtonPushed, true);
            app.AddalltoDBButton.Text = 'Add all to DB';
            app.AddbesttoDBButton = uibutton(app.GridLayout13, 'push');
            app.AddbesttoDBButton.ButtonPushedFcn = createCallbackFcn(app, @AddbesttoDBButtonPushed, true);
            app.AddbesttoDBButton.Text = 'Add best to DB';
            app.PeakResultsCheckBox = uicheckbox(app.GridLayout13);
            app.PeakResultsCheckBox.Text = 'Peak Results';
            app.OHCalcQuantSelectionButton = uibutton(app.GridLayout13, 'push');
            app.OHCalcQuantSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @OHCalcQuantPushed, true);
            app.OHCalcQuantSelectionButton.Text = 'OHCalc Quant Selection';
            % Show the figure after all components are created
            app.SimpleDeconModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleDeconModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleDeconModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleDeconModuleUIFigure)
        end
    end
end