classdef SpecDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecDBUIFigure                  matlab.ui.Figure
        SpecDBMenu                      matlab.ui.container.Menu
        NewDBProjectMenu                matlab.ui.container.Menu
        LoadDBProjectMenu               matlab.ui.container.Menu
        SaveDBProjectMenu               matlab.ui.container.Menu
        SaveDBProjectAsMenu             matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportSelectedSpectracsvMenu    matlab.ui.container.Menu
        ExportProjectcsvMenu_2          matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        AutoImportMenu                  matlab.ui.container.Menu
        FTIR_IFGMenu                    matlab.ui.container.Menu
        Raman_MKSMenu                   matlab.ui.container.Menu
        ImportSpectraFromFilesMenu      matlab.ui.container.Menu
        ImportSpectraFromFolderMenu     matlab.ui.container.Menu
        ImportSpectraFromFoldermatonlyMenu  matlab.ui.container.Menu
        ImportSpectraFromFoldermatonlyLimitSizeMenu  matlab.ui.container.Menu
        ImportSpectraFromFolder0OpusMenu  matlab.ui.container.Menu
        ImportSpectraFromFolderspPerkinElmerMenu  matlab.ui.container.Menu
        ImportSpectraFromExcelMenu      matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        ExtractSpectraMenu              matlab.ui.container.Menu
        MergeSpectraMenu                matlab.ui.container.Menu
        DuplicateSpectraMenu            matlab.ui.container.Menu
        CalculateAbscmMenu              matlab.ui.container.Menu
        EditSampleNameMenu              matlab.ui.container.Menu
        RemovefromSampleNameMenu        matlab.ui.container.Menu
        ReplaceinSampleNameMenu         matlab.ui.container.Menu
        CombineFieldsMenu               matlab.ui.container.Menu
        AutoGroupsMenu                  matlab.ui.container.Menu
        FieldtoGroupsMenu_2             matlab.ui.container.Menu
        SampleMenu_2                    matlab.ui.container.Menu
        SampleNameMenu                  matlab.ui.container.Menu
        MineralMenu                     matlab.ui.container.Menu
        SelectFieldMenu                 matlab.ui.container.Menu
        DefaultGroupsMenu_2             matlab.ui.container.Menu
        ShiftXMenu_2                    matlab.ui.container.Menu
        SetShiftMenu_2                  matlab.ui.container.Menu
        ResetShiftMenu_2                matlab.ui.container.Menu
        ThicknessfromMetaTableMenu      matlab.ui.container.Menu
        ClassificationMenu              matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LoadProject_Button              matlab.ui.control.Button
        GeoCPlotButton                  matlab.ui.control.Button
        CalculateAbs_Button             matlab.ui.control.Button
        SpecVis_Button                  matlab.ui.control.Button
        SimpleDecon_Button              matlab.ui.control.Button
        SpotVisModule_Button            matlab.ui.control.Button
        x3DPlot_Button                  matlab.ui.control.Button
        StackedPlot_Button              matlab.ui.control.Button
        SimplePlot_Button               matlab.ui.control.Button
        SaveDBProjectAs_Button          matlab.ui.control.Button
        SaveDBProject_Button            matlab.ui.control.Button
        ImportSpectraFromFolder_Button  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        DefaultGroupsButton             matlab.ui.control.Button
        DropDown2                       matlab.ui.control.DropDown
        AddColFilt                      matlab.ui.control.Button
        ResetColFilt                    matlab.ui.control.Button
        WaterCalcABCButton              matlab.ui.control.Button
        NotOrFilterButton               matlab.ui.control.StateButton
        NotAndFilterButton              matlab.ui.control.StateButton
        XDirSwitch                      matlab.ui.control.Switch
        OrFilterButton                  matlab.ui.control.StateButton
        AndFilterButton                 matlab.ui.control.StateButton
        SetasXLimitButton               matlab.ui.control.Button
        IntRangeLabel                   matlab.ui.control.Label
        Int_range_min                   matlab.ui.control.NumericEditField
        LinBCButton                     matlab.ui.control.StateButton
        SpecsButton                     matlab.ui.control.StateButton
        ntoplotEditField                matlab.ui.control.NumericEditField
        ntoplotEditFieldLabel           matlab.ui.control.Label
        SearchEditField                 matlab.ui.control.EditField
        SearchEditFieldLabel            matlab.ui.control.Label
        DeleteColumn                    matlab.ui.control.Button
        SearchButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.StateButton
        MeanButton                      matlab.ui.control.StateButton
        EditField_xlimMax               matlab.ui.control.NumericEditField
        EditField_xlimMin               matlab.ui.control.NumericEditField
        AddColumn                       matlab.ui.control.Button
        DeleteSpectraButton             matlab.ui.control.Button
        AddSpectraButton                matlab.ui.control.Button
        RenameGroupButton               matlab.ui.control.Button
        RemoveGroupButton               matlab.ui.control.Button
        CreateGroupButton               matlab.ui.control.Button
        DB_table                        matlab.ui.control.Table
        GroupListBox                    matlab.ui.control.ListBox
        Int_range_max                   matlab.ui.control.NumericEditField
        FigXlimLive                     matlab.ui.control.UIAxes
        OHCalcTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        RoundTableButton                matlab.ui.control.Button
        PlotSpecOHCalc_Button           matlab.ui.control.Button
        ExportTableButton               matlab.ui.control.Button
        ClearResultsButton_2            matlab.ui.control.Button
        DeleteEntryButton               matlab.ui.control.Button
        IntRangeLabel_3                 matlab.ui.control.Label
        Int_range_min_3                 matlab.ui.control.NumericEditField
        Int_range_max_3                 matlab.ui.control.NumericEditField
        AbsBetaEditField                matlab.ui.control.NumericEditField
        AbsBetaEditFieldLabel           matlab.ui.control.Label
        ThicknessBetamicronEditFieldLabel  matlab.ui.control.Label
        AbsGammaEditFieldLabel          matlab.ui.control.Label
        ThicknessGammamicronEditFieldLabel  matlab.ui.control.Label
        ThicknessGammamicronEditField   matlab.ui.control.NumericEditField
        AbsGammaEditField               matlab.ui.control.NumericEditField
        ThicknessBetamicronEditField    matlab.ui.control.NumericEditField
        IntRangeLabel_2                 matlab.ui.control.Label
        Int_range_min_2                 matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Int_range_max_2                 matlab.ui.control.NumericEditField
        SampleNameEditField             matlab.ui.control.EditField
        SampleNameEditFieldLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        MineralName_EditField           matlab.ui.control.EditField
        CustomIEditFieldLabel_2         matlab.ui.control.Label
        CustomIEditField                matlab.ui.control.EditField
        CustomIEditFieldLabel           matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        MineralDropDown                 matlab.ui.control.DropDown
        MineralDropDownLabel            matlab.ui.control.Label
        ThicknessAlphamicronEditField   matlab.ui.control.NumericEditField
        ThicknessAlphamicronEditFieldLabel  matlab.ui.control.Label
        AbsAlphaEditField               matlab.ui.control.NumericEditField
        AbsAlphaEditFieldLabel          matlab.ui.control.Label
        FigH2O_calc_specs               matlab.ui.control.UIAxes
        MetadataMatchTab                matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        DBDropDown                      matlab.ui.control.DropDown
        DBDropDownLabel                 matlab.ui.control.Label
        ImportDropDown                  matlab.ui.control.DropDown
        ImportDropDownLabel             matlab.ui.control.Label
        InsertinDBButton                matlab.ui.control.Button
        MetadataTestButton              matlab.ui.control.Button
        LoadMetaFileButton              matlab.ui.control.Button
        DB_table_2                      matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        SendtoFigureMenu                matlab.ui.container.Menu
        SimplePlotMenu                  matlab.ui.container.Menu
        x3DPlotMenu                     matlab.ui.container.Menu
        StackedPlotMenu                 matlab.ui.container.Menu
        H2OcalcMenu_2                   matlab.ui.container.Menu
        CalculateSelectedMenu           matlab.ui.container.Menu
        CalculateEverythingMenu         matlab.ui.container.Menu
        GetMeanIntegraionMenu           matlab.ui.container.Menu
        AlphaMenu                       matlab.ui.container.Menu
        BetaMenu                        matlab.ui.container.Menu
        GammaMenu                       matlab.ui.container.Menu
        CopySpecstoClipboardMenu        matlab.ui.container.Menu
        MulticolumnMeanspecsMenu        matlab.ui.container.Menu
        MulticolumnAllMenu              matlab.ui.container.Menu
        SingleColumnMenu                matlab.ui.container.Menu
        MetadataMenu                    matlab.ui.container.Menu
        EditMetadataMenu                matlab.ui.container.Menu
        CalculateAbscmMenu_2            matlab.ui.container.Menu
        GroupsMenu                      matlab.ui.container.Menu
        CreateNewGroupwithSelectionMenu  matlab.ui.container.Menu
        AddToExistingGroupMenu          matlab.ui.container.Menu
        RemovefromgroupMenu             matlab.ui.container.Menu
        SendtoSpecVisMenu               matlab.ui.container.Menu
        DeleteSpectraMenu               matlab.ui.container.Menu
    end


    properties (Access = private)

        %   protected_fns={'Active','Id','SampleName','SampleType','DataType','Mineral','Density','Thickness','Comment','Source','Image_Id','n_Images'};
        protected_fns={'Active','Id','SampleName','Mineral','Density','Thickness','Comment','Source','Image_Id','n_Images'};


        active_Group
        active_tmp


        hVerticalLines
        hLineToDrag
        clickbuffer=0.05


    end

    properties (Access = public)
        Data=struct() % Description
        edit_state=false;
    end


    methods (Access = private)
        % Update Functions start
        function update_DB_list(app)
            if not(isfield(app.Data,'Group_names'))
                app.Data.Group_names={'Full Database'};
                app.Data.Groups=[];
            end

            app.GroupListBox.Items=app.Data.Group_names;
            if ~ isempty(app.active_Group)
                app.GroupListBox.Value=app.Data.Group_names(app.active_Group);
            end
        end

        function update_UI(app)
            cla(app.FigXlimLive)
            update_DB_list(app)
            if isfield(app.Data,'Meta')
                update_DB_table(app)
            end
            update_plots(app)

        end

        function update_DB_table(app)
            if not(isfield(app.Data,'Meta'))
                app.DB_table.Data=[];
                return

            end

            thx_table_Meta=struct2table(app.Data.Meta,'AsArray',true);
            % cellfun(@isnumeric,table2cell(thx_table_Meta))
            nums_in_cells=sum(cellfun(@isnumeric,table2cell(thx_table_Meta)))==height(thx_table_Meta);
            %thx_table_Meta=thx_table_Meta(not(cellfun(@isempty,thx_table_Meta.Id)),:);
            for i = 1:width(thx_table_Meta)

                if iscell(thx_table_Meta.(i)) && numel(nums_in_cells)>=i && nums_in_cells(i)==true
                    thx=thx_table_Meta.(i);
                    thx(cellfun(@isempty, thx_table_Meta.(i)))={nan};
                    try
                        thx_table_Meta.(i) = [thx{:}]';
                    catch ME
                        disp(ME)
                        disp('Error in update_DB_table')
                    end
                end
            end


            for n=1:numel(app.active_Group)
                if app.AndFilterButton.Value==true && not(app.active_Group(n)==1)
                    if not(app.active_Group(n)==1)
                        thx_table_Meta=thx_table_Meta(ismember(thx_table_Meta.Id,app.Data.Groups(app.active_Group(n)).Members),:);
                    end
                elseif app.OrFilterButton.Value==true  && not(app.active_Group(n)==1)
                    test=ismember(thx_table_Meta.Id,unique(unique([app.Data.Groups(app.active_Group).Members])));
                    if not(any(test))
                        app.DB_table.Data=[];
                        return
                    end
                    thx_table_Meta=thx_table_Meta(test,:);
                    break
                elseif app.NotAndFilterButton.Value==true  && not(app.active_Group(n)==1)
                    thx_table_Meta=thx_table_Meta(not(ismember(thx_table_Meta.Id,app.Data.Groups(app.active_Group(n)).Members)),:);
                elseif app.NotOrFilterButton.Value==true  && not(app.active_Group(n)==1)
                    test=not(ismember(thx_table_Meta.Id,unique(unique([app.Data.Groups(app.active_Group).Members]))));
                    if not(any(test))
                        app.DB_table.Data=[];
                        return
                    end
                    thx_table_Meta=thx_table_Meta(test,:);
                    break
                end

            end

            %% if filter_cols==true
            if isfield(app.Data,'ColumnFilter') && not(isempty(app.Data.ColumnFilter))
                thx_table_Meta=thx_table_Meta(:,app.Data.ColumnFilter);
            end
            %thx_table_Meta=thx_table_Meta(:,[1:4, (5) 10 15 82:end]);


            if numel(app.SearchEditField.Value)>0
                match2=false(size(thx_table_Meta));
                for m=1:size(thx_table_Meta,2)
                    try
                        match2(:,m)=contains(lower(string(thx_table_Meta.(m))),lower(app.SearchEditField.Value));
                    catch
                        match2(:,m)=zeros(size(thx_table_Meta,1),1);
                    end
                end
                app.DB_table.Data=thx_table_Meta(sum(match2,2)>0,:);

            else
                app.DB_table.Data=thx_table_Meta;
            end

            nums_in_cells=sum(cellfun(@isnumeric,table2cell(app.DB_table.Data)),1)==height(app.DB_table.Data);


            for i = 1:width(app.DB_table.Data)
                if iscell(app.DB_table.Data.(i)) && nums_in_cells(i)==true

                    thx=app.DB_table.Data.(i);
                    thx(cellfun(@isempty, app.DB_table.Data.(i)))={nan};
                    try
                        app.DB_table.Data.(i) = [thx{:}]';
                    catch ME
                        disp('Error - update_DB_table(app)')
                        disp(ME)
                    end
                end
            end


            app.DB_table.ColumnName=thx_table_Meta.Properties.VariableNames;
            app.DB_table.ColumnSortable = true(1,size(app.DB_table.Data,2));

        end

        function update_plots(app,axis2plot)
            if not(exist('axis2plot','var'))
                axis2plot=app.FigXlimLive;
            end

            cla(axis2plot)
            axis2plot.XLim=sort([app.EditField_xlimMax.Value app.EditField_xlimMin.Value]);
            if app.ResetButton.Value==true
                xlim(axis2plot,'auto')
                ylim(axis2plot,'auto')
                app.EditField_xlimMin.Value=min(axis2plot.XLim);
                app.EditField_xlimMax.Value=max(axis2plot.XLim);
            else
                xlim(axis2plot,sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value ]))
                ylim(axis2plot,'auto')
            end
            update_lines(app,axis2plot)
        end

        function update_lines(app,axis2plot)
            if not(exist('axis2plot','var'))
                axis2plot=app.FigXlimLive;
            end
            app.hVerticalLines(1:2) = [xline(axis2plot,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                xline(axis2plot, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
            set(app.hVerticalLines(1:2), 'hittest', 'off')
        end

        % Update Functions end
        % Import Functions start
        function autoimport_MKS_Raman(app,path,file,SampleName,type)
            input_spectra_name_orig=(replace(file,{'.txt','.csv','.mat'},''));

            data_in=readmatrix(fullfile(path,file));


            %
            if exist('type','var') && type==1
                x_signal=unique(data_in(:,3));

                in_=data_in(:,4);
                y_signal=reshape(in_,numel(unique(x_signal)),[]);

                x=mean(reshape(data_in(:,1),numel(unique(x_signal)),[]))';
                y=mean(reshape(data_in(:,2),numel(unique(x_signal)),[]))';

                %
            elseif  exist('type','var') && type==2
                x_signal=unique(data_in(:,3));

                in_=data_in(:,4);
                y_signal=reshape(in_,numel(unique(x_signal)),[]);

                unique_rows=unique(data_in(:,1:2),'rows');
                x=unique_rows(:,1);
                y=unique_rows(:,2);
                %
            else
                x_signal= data_in(1,3:end)';
                y=data_in(2:end,2);
                x=data_in(2:end,1);
                y_signal=data_in(2:end,3:end)';

            end
            [data.Meta.Active]=true;
            [data.Meta.Id]=app.active_tmp;
            [data.DB.x_signal]=x_signal;
            [data.DB.y_signal]=y_signal;
            [data.Meta.Sample]='';

            if exist("SampleName","var")
                [data.Meta.SampleName]=SampleName;
                [data.Meta.Sample]=input_spectra_name_orig;
            else
                [data.Meta.SampleName]=input_spectra_name_orig;
                [data.Meta.Sample]='';
            end

            [data.Meta.Mineral]='Unknown';
            if contains(lower(file),'_alpha')

                [data.Meta.Orientation]='alpha';
            elseif contains(lower(file),'_beta')
                [data.Meta.Orientation]='beta';
            elseif contains(lower(file),'_gamma')
                [data.Meta.Orientation]='gamma';

            else
                [data.Meta.Orientation]='';
            end

            check_str=[replace(join([repmat("_pol0",size(string((0:0.5:10)'))) ...
                string((0:0.5:10)')...
                repmat("_",size(string((0:0.5:10)')))],''),'.','_'); replace(join([repmat("_pol",size(string((0:0.5:360)'))) ...
                string((0:0.5:360)')...
                ],''),'.','_')];


            check_str=[check_str;replace(join([repmat("pol_",size(string((0:0.5:10)'))) ...
                string((0:0.5:10)')...
                repmat("_",size(string((0:0.5:10)')))],''),'.','_'); replace(join([repmat("pol",size(string((0:0.5:360)'))) ...
                string((0:0.5:360)')...
                ],''),'.','_')];

            check_str2=join([repmat("_",size(string((0:0.5:360)'))) ...
                string((0:0.5:360)')...
                repmat("pol_",size(string((0:0.5:360)')))],'');

            if contains(lower(file),'_unpol')
                [data.Meta.InfraredRadiation]='unpol';
            elseif contains(lower(file),'_polmax')
                [data.Meta.InfraredRadiation]='polmax';
            elseif contains(lower(file),'_polmin')
                [data.Meta.InfraredRadiation]='polmin';
            elseif contains(lower(file),'_pol_')
                [data.Meta.InfraredRadiation]='pol';
            elseif contains(lower(file),'_polhalf_')
                [data.Meta.InfraredRadiation]='polhalf';

            elseif contains(string(lower(file)),check_str')
                for n=1:numel(check_str)
                    test2=strfind(lower(file),check_str(n));
                    if ~isempty(test2)
                        rest=split(file(test2+1:end),'_');
                        if numel(rest)>1 && (strcmp(rest{2},'5') || strcmp(rest{2},'0'))
                            [data.Meta.InfraredRadiation]=[rest{1} '_' rest{2}];

                        else
                            [data.Meta.InfraredRadiation]=rest{1};
                        end
                        break
                    end
                end
            elseif contains(string(lower(file)),check_str2')
                for n=1:numel(check_str2)
                    test2=strfind(lower(file),check_str2(n));
                    if ~isempty(test2)
                        rest=split(file(test2+1:end),'_');

                        [data.Meta.InfraredRadiation]=['pol',replace(rest{1},'pol','')];

                        break
                    end
                end
            else
                [data.Meta.InfraredRadiation]='';
            end

            check_str3=join([repmat("_",size(string((0:1:1024)'))) ...
                string((0:1:1024)')...
                repmat("sc_",size(string((0:1:1024)')))],'');
            if contains(string(lower(file)),check_str3')
                for n=1:numel(check_str3)
                    test2=strfind(lower(file),check_str3(n));
                    if ~isempty(test2)
                        rest=split(file(test2+1:end),'_');
                        [data.Meta.Scans]=str2double(replace(rest{1},'sc',''));
                        break
                    end
                end
            else
                [data.Meta.Scans]=nan;
            end

            check_str4=join([repmat("_",size(string((0:1:1024)'))) ...
                string((0:1:1024)')...
                repmat("ap_",size(string((0:1:1024)')))],'');
            if contains(string(lower(file)),check_str4')
                for n=1:numel(check_str3)
                    test2=strfind(lower(file),check_str4(n));
                    if ~isempty(test2)
                        rest=split(file(test2+1:end),'_');
                        [data.Meta.Aperture]=str2double(replace(rest{1},'ap',''));
                        break
                    end
                end
            else
                [data.Meta.Aperture]=nan;
            end
            [data.Meta.SpecType]=300; %RAMAN
            [data.Meta.Thickness]=10000;
            [data.MetaPrivat.Thickness]=10000;
            [data.Meta.Density]=0;
            [data.Meta.Comment]='';
            [data.Meta.path]=path;
            [data.Meta.file]=file;
            [data.Meta.Source]='raman_file_import';
            [data.Meta.n_meanSpec]=size(y_signal,2);
            [data.Meta.Mask]=true(data.Meta.n_meanSpec,1);%

            thx= [(1:size(y_signal,2))' x y ones(size(y_signal,2),1)];
            [data.Meta.MetaTable]=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});

            insert_data(app,data,false);
        end

        function load_spec_to_import(app,file,path,SampleName,info)
            [~,name,~]=fileparts(file);
            input_spectra_name_orig=name;
            if endsWith(file,'.mat')
                numberOfDots = length(find(file=='.'));
                if numberOfDots>1
                    org_filename=file;

                    file=replace(replace(file,'.','_'),{'_mat','.mat.mat'},'.mat');
                    try
                        movefile(fullfile(path, org_filename),fullfile(path,file));
                    catch
                        disp(['error in changing filename ' fullfile(path,file)])
                    end
                end
                try
                    if contains(who('-file', fullfile(path,file)),'/')% any(endsWith(who('-file', fullfile(path,file)),'/'),true)
                        newfilename=pyrunfile("fixname_single_file.py","newname",root=path,file_in=file);
                        file=char(newfilename);
                    end
                catch ME
                    disp(ME)
                    disp(['Renamed-' fullfile(path, file)])
                end

                try
                    if  any(endsWith(who('-file', fullfile(path,file)),'ABMultiple'),true)
                        load(fullfile(path,file),'ABMultiple');
                        raw_spectrum_input=(ABMultiple);
                    elseif any(endsWith(who('-file', fullfile(path,file)),'AB'),true)
                        load(fullfile(path,file),'AB');
                        raw_spectrum_input=(AB);

                    end
                catch
                    try
                        pyrunfile("fix_mat.py",input=fullfile(path,file));
                        disp(['.mat file fixed - opus export error: ' fullfile(path,file)])
                    catch
                        disp(['Import Error - could not import or fix file: ' fullfile(path,file)])
                    end
                    return
                end

            elseif endsWith(file,'.sp')
                [data, xAxis, misc] = spload(app,fullfile(path,file)); %#ok
                raw_spectrum_input=[xAxis' data ];

            elseif endsWith(file,'.spa')
                [y_signal, x_signal, SpectraTitles, Filenames, SpectraComments] = spa_load(fullfile(path,file));%#ok
                raw_spectrum_input=[x_signal y_signal ];

            elseif any(endsWith(file,join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))
                opus_data=pyrunfile("opusread_NG.py",'opus_data',opusfile=fullfile(path,file));
                %opus_data{'AB Data Parameter'};
                wn0=opus_data{'AB Data Parameter'}{'FXV'};
                wnEND=opus_data{'AB Data Parameter'}{'LXV'};
                wnNPT=double(opus_data{'AB Data Parameter'}{'NPT'});
                x_signal=linspace(wn0,wnEND,wnNPT);
                y_signal=double(opus_data{'AB'});
                if y_signal(end)==0 && numel(y_signal)- 1== wnNPT
                    y_signal=y_signal(1:end-1);
                end
                if numel(y_signal)~= wnNPT
                    return
                end
                raw_spectrum_input=[x_signal' y_signal'];


            else
                try

                    raw_spectrum_input=table2array(readtable(fullfile(path,file)));
                catch
                    try
                        raw_spectrum_input=importdata(fullfile(path,file));
                        if not(isnumeric(raw_spectrum_input))
                            disp(['Import Error 1 - readtable/table2array' file])
                            return

                        end
                    catch
                        disp(['Import Error 2 - readtable/table2array' file])
                        return
                    end
                end
            end


            try
                [data.Meta.Active]=true;
                [data.Meta.Id]=app.active_tmp;
                [data.DB.x_signal]=raw_spectrum_input(:,1);
                [data.DB.y_signal]=raw_spectrum_input(:,2:end);
                if exist("SampleName","var")
                    [data.Meta.SampleName]=input_spectra_name_orig;
                    [data.Meta.Sample]=SampleName;
                else
                    [data.Meta.SampleName]=input_spectra_name_orig;
                    [data.Meta.Sample]=input_spectra_name_orig;
                end
                if contains(lower(file),'_grt_') || contains(lower(file),'garnet')
                    [data.Meta.Mineral]='Garnet';
                elseif contains(lower(file),'_cpx_') || contains(lower(file),'clinopyroxene')
                    [data.Meta.Mineral]='CPX';
                elseif contains(lower(file),'_opx_') || contains(lower(file),'orthopyroxene')
                    [data.Meta.Mineral]='OPX';
                elseif contains(lower(file),'_omph_') || contains(lower(file),'omphacite')
                    [data.Meta.Mineral]='Omphacite';
                elseif contains(lower(file),'_kya_') || contains(lower(file),'_ky_') || contains(lower(file),'kyanite')
                    [data.Meta.Mineral]='Kyanite';
                elseif contains(lower(file),'_rt_') || contains(lower(file),'rutile')
                    [data.Meta.Mineral]='Rutile';
                elseif contains(lower(file),'_diamond') ||contains(lower(file),'_diamant_')
                    [data.Meta.Mineral]='Diamond';
                elseif contains(lower(file),'_qtz_') ||contains(lower(file),'_quartz_')||contains(lower(file),'_quarz_')
                    [data.Meta.Mineral]='Quartz';
                elseif contains(lower(file),'_ol_') ||contains(lower(file),'_olivine_')||contains(lower(file),'_olivin_')
                    [data.Meta.Mineral]='Olivine';
                elseif contains(lower(file),'_calcite')
                    [data.Meta.Mineral]='Calcite';
                elseif contains(lower(file),'_apatite_') 
                    [data.Meta.Mineral]='Apatite';
                elseif contains(lower(file),'_fsp_') || contains(lower(file),'_Feldspar_')
                    [data.Meta.Mineral]='Feldspar';
                elseif contains(lower(file),'titanite')
                    [data.Meta.Mineral]='Titanite';
                else
                    [data.Meta.Mineral]='Unknown';
                end
                if contains(lower(file),'_alpha_')
                    [data.Meta.Orientation]='α';
                elseif contains(lower(file),'_beta_')
                    [data.Meta.Orientation]='β';
                elseif contains(lower(file),'_gamma_')
                    [data.Meta.Orientation]='γ';

                elseif contains(lower(file),'IIC')
                    [data.Meta.Orientation]='IIC';

                elseif contains(lower(file),'TC')
                    [data.Meta.Orientation]='TC';
                else
                    [data.Meta.Orientation]='';
                end

                check_str=[replace(join([repmat("_pol0",size(string((0:0.5:10)'))) ...
                    string((0:0.5:10)')...
                    repmat("_",size(string((0:0.5:10)')))],''),'.','_'); replace(join([repmat("_pol",size(string((0:0.5:360)'))) ...
                    string((0:0.5:360)')...
                    ],''),'.','_')];


                check_str=[check_str;replace(join([repmat("pol_",size(string((0:0.5:10)'))) ...
                    string((0:0.5:10)')...
                    repmat("_",size(string((0:0.5:10)')))],''),'.','_'); replace(join([repmat("pol",size(string((0:0.5:360)'))) ...
                    string((0:0.5:360)')...
                    ],''),'.','_')];


                check_str2=join([repmat("_",size(string((0:0.5:360)'))) ...
                    string((0:0.5:360)')...
                    repmat("pol_",size(string((0:0.5:360)')))],'');

                if contains(lower(file),'_unpol')
                    [data.Meta.InfraredRadiation]='unpol';
                elseif contains(lower(file),'_polmax')
                    [data.Meta.InfraredRadiation]='polmax';
                elseif contains(lower(file),'_polmin')
                    [data.Meta.InfraredRadiation]='polmin';
                elseif contains(lower(file),'_pol_')
                    [data.Meta.InfraredRadiation]='pol';
                elseif contains(lower(file),'_polround')
                    [data.Meta.InfraredRadiation]='polround';
                elseif contains(lower(file),'_polhalf')
                    [data.Meta.InfraredRadiation]='polhalf';

                elseif contains(string(lower(file)),check_str')
                    for n=1:numel(check_str)
                        test2=strfind(lower(file),check_str(n));
                        if ~isempty(test2)
                            rest=lower(split(file(test2+1:end),'_'));
                            if numel(rest)>1 && (strcmp(rest{2},'5') || strcmp(rest{2},'0'))
                                [data.Meta.InfraredRadiation]=[rest{1} '_' rest{2}];

                            else
                                ths=split(rest{1},'.');
                                [data.Meta.InfraredRadiation]=ths{1};
                            end
                            break
                        end
                    end
                elseif contains(string(lower(file)),check_str2')
                    for n=1:numel(check_str2)
                        test2=strfind(lower(file),check_str2(n));
                        if ~isempty(test2)
                            rest=split(file(test2+1:end),'_');

                            [data.Meta.InfraredRadiation]=['pol',replace(rest{1},'pol','')];

                            break
                        end
                    end
                else
                    [data.Meta.InfraredRadiation]='';
                end

                check_str3=join([repmat("_",size(string((0:1:1024)'))) ...
                    string((0:1:1024)')...
                    repmat("sc_",size(string((0:1:1024)')))],'');
                if contains(string(lower(file)),check_str3')
                    for n=1:numel(check_str3)
                        test2=strfind(lower(file),check_str3(n));
                        if ~isempty(test2)
                            rest=split(file(test2+1:end),'_');
                            [data.Meta.Scans]=str2double(replace(rest{1},'sc',''));
                            break
                        end
                    end
                else
                    [data.Meta.Scans]=nan;
                end

                check_str4=join([repmat("_",size(string((0:1:1024)'))) ...
                    string((0:1:1024)')...
                    repmat("ap_",size(string((0:1:1024)')))],'');
                if contains(string(lower(file)),check_str4')
                    for n=1:numel(check_str3)
                        test2=strfind(lower(file),check_str4(n));
                        if ~isempty(test2)
                            rest=split(file(test2+1:end),'_');
                            [data.Meta.Aperture]=str2double(replace(rest{1},'ap',''));
                            break
                        end
                    end
                else
                    [data.Meta.Aperture]=nan;
                end


                thickness=dir([path '/**/*thickness.txt']);
                thickness=thickness(~startsWith({thickness.name}, '._'));

                thickness_files={thickness.name};


                if numel(thickness_files(ismember({thickness.folder},path)))>=1
                    file_t=thickness(ismember({thickness.folder},path)).name;
                    path_t=thickness(ismember({thickness.folder},path)).folder;

                    T = import_thickness(app,path_t,file_t);
                    if istable(T)
                        [data.Meta.Thickness_min] =table2array(T(1,1));
                        [data.Meta.Thickness_max] =table2array(T(1,2));
                        [data.Meta.Thickness] =table2array(T(1,3));
                        [data.MetaPrivat.Thickness]=table2array(T(1,3));

                    elseif isnumeric(T) && numel(T)==1
                        [data.Meta.Thickness_min] =T;
                        [data.Meta.Thickness_max] =T;
                        [data.Meta.Thickness] =T;
                        [data.MetaPrivat.Thickness]=T;

                    end
                elseif contains((file),'_deltaT_')

                    test2=strfind((file),'_deltaT_');
                    rest=split(file(test2+1:end),{'_','.'});
                    T=str2double(rest{2});
                    if isnan(T)
                        T=0;
                    end

                    [data.Meta.Thickness] =T;
                    [data.MetaPrivat.Thickness]=T;
                else

                    [data.Meta.Thickness]=0;
                    [data.MetaPrivat.Thickness]=0;
                end


                [data.Meta.Density]=0;
                [data.Meta.Comment]='';
                [data.Meta.path]=path;
                [data.Meta.file]=file;
                [data.Meta.Source]='file_import';
                [data.Meta.n_meanSpec]=size(raw_spectrum_input(1,2:end),2);
                [data.Meta.Mask]=true(data.Meta.n_meanSpec,1);%

                if exist('info','var') && isstruct(info)
                    fns=fieldnames(info);
                    for nfns=1:numel(fns)
                        [data.Meta.(char(fns{nfns}))]=info.(char(fns{nfns}));
                    end
                end

                insert_data(app,data,false);
            catch ME
                disp('Error - load_spec_to_import(app,file,path,SampleName,info)')
                disp(ME)
            end


        end

        %% Data Functions start


        function [data, xAxis, misc] = spload(app,filename)
            % Reads in spectra from PerkinElmer block structured files.
            % This version supports 'Spectrum' SP files.
            % Note that earlier 'Data Manager' formats are not supported.
            %
            % [data, xAxis, misc] = spload(filename):
            %   data:  1D array of doubles
            %   xAxis: vector for abscissa (e.g. Wavenumbers).
            %   misc: miscellanous information in name,value pairs
            % Copyright (C)2007 PerkinElmer Life and Analytical Sciences
            % Stephen Westlake, Seer Green
            %
            % History
            % 2007-04-24 SW     Initial version
            % Block IDs
            DSet2DC1DIBlock               =  120;
            HistoryRecordBlock            =  121;
            InstrHdrHistoryRecordBlock    =  122;
            InstrumentHeaderBlock         =  123;
            IRInstrumentHeaderBlock       =  124;
            UVInstrumentHeaderBlock       =  125;
            FLInstrumentHeaderBlock       =  126;
            % Data member IDs
            DataSetDataTypeMember              =  -29839;
            DataSetAbscissaRangeMember         =  -29838;
            DataSetOrdinateRangeMember         =  -29837;
            DataSetIntervalMember              =  -29836;
            DataSetNumPointsMember             =  -29835;
            DataSetSamplingMethodMember        =  -29834;
            DataSetXAxisLabelMember            =  -29833;
            DataSetYAxisLabelMember            =  -29832;
            DataSetXAxisUnitTypeMember         =  -29831;
            DataSetYAxisUnitTypeMember         =  -29830;
            DataSetFileTypeMember              =  -29829;
            DataSetDataMember                  =  -29828;
            DataSetNameMember                  =  -29827;
            DataSetChecksumMember              =  -29826;
            DataSetHistoryRecordMember         =  -29825;
            DataSetInvalidRegionMember         =  -29824;
            DataSetAliasMember                 =  -29823;
            DataSetVXIRAccyHdrMember           =  -29822;
            DataSetVXIRQualHdrMember           =  -29821;
            DataSetEventMarkersMember          =  -29820;
            % Type code IDs
            ShortType               = 29999;
            UShortType              = 29998;
            IntType                 = 29997;
            UIntType                = 29996;
            LongType                = 29995;
            BoolType                = 29988;
            CharType                = 29987;
            CvCoOrdPointType        = 29986;
            StdFontType             = 29985;
            CvCoOrdDimensionType    = 29984;
            CvCoOrdRectangleType    = 29983;
            RGBColorType            = 29982;
            CvCoOrdRangeType        = 29981;
            DoubleType              = 29980;
            CvCoOrdType             = 29979;
            ULongType               = 29978;
            PeakType                = 29977;
            CoOrdType               = 29976;
            RangeType               = 29975;
            CvCoOrdArrayType        = 29974;
            EnumType                = 29973;
            LogFontType             = 29972;
            fid = fopen(filename,'r');
            if fid == -1
                error('Cannot open the file.');
                return
            end
            % Fixed file header of signature and description
            signature = setstr(fread(fid, 4, 'uchar')');
            if ~strcmp(signature, 'PEPE')

                error('This is not a PerkinElmer block structured file.');
                return
            end
            description = setstr(fread(fid, 40, 'uchar')');
            % Initialize a variable so we can tell if we have read it.
            xLen = int32(0);
            % The rest of the file is a list of blocks
            while ~feof(fid)
                blockID = fread(fid,1,'int16');
                blockSize = fread(fid,1,'int32');

                % feof does not go true until after the read has failed.
                if feof(fid)
                    break
                end

                switch blockID
                    case DSet2DC1DIBlock
                        % Wrapper block.  Read nothing.
                    case DataSetAbscissaRangeMember
                        innerCode = fread(fid, 1, 'int16');
                        %_ASSERTE(CvCoOrdRangeType == nInnerCode);
                        x0 = fread(fid, 1, 'double');
                        xEnd = fread(fid, 1, 'double');

                    case DataSetIntervalMember
                        innerCode = fread(fid, 1, 'int16');
                        xDelta = fread(fid, 1, 'double');
                    case DataSetNumPointsMember
                        innerCode = fread(fid, 1, 'int16');
                        xLen = fread(fid, 1, 'int32');
                    case DataSetXAxisLabelMember
                        innerCode = fread(fid, 1, 'int16');
                        len = fread(fid, 1, 'int16');
                        xLabel = setstr(fread(fid, len, 'uchar')');
                    case DataSetYAxisLabelMember
                        innerCode = fread(fid, 1, 'int16');
                        len = fread(fid, 1, 'int16');
                        yLabel = setstr(fread(fid, len, 'uchar')');

                    case DataSetAliasMember
                        innerCode = fread(fid, 1, 'int16');
                        len = fread(fid, 1, 'int16');
                        alias = setstr(fread(fid, len, 'uchar')');

                    case DataSetNameMember
                        innerCode = fread(fid, 1, 'int16');
                        len = fread(fid, 1, 'int16');
                        originalName = setstr(fread(fid, len, 'uchar')');

                    case DataSetDataMember
                        innerCode = fread(fid, 1, 'int16');
                        len = fread(fid, 1, 'int32');
                        % innerCode should be CvCoOrdArrayType
                        % len should be xLen * 8
                        if xLen == 0
                            xLen = len / 8;
                        end
                        data = fread(fid, xLen, 'double');

                    otherwise               % unknown block, just seek past it
                        fseek(fid, blockSize, 'cof');
                end
            end
            fclose(fid);
            if xLen == 0
                disp('The file does not contain spectral data.');
                return
            end
            % Expand the axes specifications into vectors
            xAxis = x0 : xDelta : xEnd;
            % Return the other details as name,value pairs
            misc(1,:) = {'xLabel', xLabel};
            misc(2,:) = {'yLabel', yLabel};
            misc(3,:) = {'alias', alias};
            misc(4,:) = {'original name', originalName};
        end

        function update_PeakPlotSet_fun(app)
            try
                if not(isfield(app.Data,'PlotSettings'))
                    if isdeployed
                        path=ctfroot;
                        path_split=split(path,{'SpecXY.app','SpecXY.exe'});
                        path=fullfile(path_split{1},'SpecXY_PlotSettings.csv');
                        disp('ctfroot: ' )
                        disp(path)
                        if not(exist(path,'file'))
                            path=fullfile('SpecXY_PlotSettings.csv');
                        end
                    else
                        path=which('SpecXY_PlotSettings.csv');
                    end
                    app.Data.PlotSettings.Default=GetPlotSettingsFun(path);
                    if not(isfield(app.Data.PlotSettings,'Custom'))
                        app.Data.PlotSettings.Custom=struct();
                    end
                end
            catch ME
                disp(ME.message)
                app.SpecMapsUIFigure.Visible=true;
                answr=uiconfirm(app.SpecMapsUIFigure,{'No PeakPotSettings found - Do you want to load a SpecXY_PlotSettings.csv file'},'Select directory','Options',{'Yes','No'});
                if strcmp(answr,"Yes")
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    [file,path]=uigetfile({'*.csv;*.txt;*.dpt;'},"MultiSelect","off", ['Select PeakPlotSettings file' char('')]);
                    delete(f)

                    if not(isequal(file,0))
                        app.Data.PlotSettings.Default=GetPlotSettingsFun(fullfile(path,file));
                    end
                end

            end
        end


        function [signal_dxdy]=deriv(~,signal)
            x=signal(:,1);
            signal_dxdy=zeros(length(x),size(signal,2));
            signal_dxdy(:,1)=x;

            for k=2:size(signal,2)
                y=signal(:,k);
                signal_dxdy(:,k)= gradient(y)./gradient(x);
            end
        end

        function [corrected_signal] = norm_signal_HighestPeak(~,signal)
            %             min_signal_y=round(signal(1,2));
            %             max_signal_y=round(signal(end,2));
            region_min=round(signal(1,1));
            region_max=round(signal(end,1));
            x=signal(:,1);

            xq=linspace(region_min,region_max,region_max-region_min);
            corrected_signal=zeros(region_max-region_min,size(signal,2));
            corrected_signal(:,1)= xq;

            for k=2:size(signal,2)
                v=signal(:,k);
                max_peak_in_region=max(v);

                vq = interp1(x,v,xq);
                vq(isnan(vq))=0;

                norm_factor=1./max_peak_in_region;

                corrected_signal(:,k)=vq.*norm_factor;
            end

        end

        function [corrected_signal] = norm_signal_HighestPeakinRange(~,signal,region_min,region_max)

            x=signal(:,1);


            xq=linspace(round(signal(1,1)),round(signal(end,1)),round(signal(end,1))-round(signal(1,1)));
            corrected_signal=zeros(round(signal(end,1))-round(signal(1,1)),size(signal,2));
            corrected_signal(:,1)= xq;

            for k=2:size(signal,2)

                v=signal(:,k);

                max_peak_in_region=max(v(x>region_min & x<region_max));

                vq = interp1(x,v,xq);
                vq(isnan(vq))=0;

                norm_factor=1./max_peak_in_region;

                corrected_signal(:,k)=vq.*norm_factor;
            end


        end

        function [corrected_signal] = norm_signal_HighestPeakinRangeCut(~,signal,region_min,region_max)

            x=signal(:,1);

            xq=linspace(region_min,region_max,region_max-region_min);
            corrected_signal=zeros(region_max-region_min,size(signal,2));
            corrected_signal(:,1)= xq;

            for k=2:size(signal,2)

                v=signal(:,k);

                max_peak_in_region=max(v(x>region_min & x<region_max));

                vq = interp1(x,v,xq);
                vq(isnan(vq))=0;

                norm_factor=1./max_peak_in_region;

                corrected_signal(:,k)=vq.*norm_factor;
            end


        end

        function [corrected_signal] = norm_signal_custPeak(~,signal,max_peak,norm_value)

            x=signal(:,1);

            xq=linspace(round(signal(1,1)),round(signal(end,1)),round(signal(end,1))-round(signal(1,1)));
            corrected_signal=zeros(round(signal(end,1))-round(signal(1,1)),size(signal,2));
            corrected_signal(:,1)= xq;

            for k=2:size(signal,2)

                v=signal(:,k);


                vq = interp1(x,v,xq);
                vq(isnan(vq))=0;

                [~,closestIndex] = min(abs(max_peak-xq'));


                norm_factor=norm_value./vq(closestIndex);

                corrected_signal(:,k)=vq.*norm_factor;
            end
        end
        % Data Functions end

        function result = tblvertcat(app,input_table)
            arguments
                app %#ok
            end
            arguments (Repeating)
                input_table table
            end

            n = length(input_table);
            nrowslist = cellfun(@height,input_table); %number of rows for each table
            ID_to_not_mix_up_the_tables_tmp = [0 cumsum(nrowslist)];
            for n = 1:n
                ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables_tmp(n)+1:ID_to_not_mix_up_the_tables_tmp(n+1);
                input_table{n}.ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables.';
            end
            t1 = input_table{1};
            for n = 2:n
                t2 = input_table{n};
                t1names = t1.Properties.VariableNames;
                t2names = t2.Properties.VariableNames;
                sharednames = intersect(t1names,t2names);
                t1 = outerjoin(t1,input_table{n},'Key',['ID_to_not_mix_up_the_tables',sharednames],'MergeKeys',true);
            end
            result = removevars(t1,'ID_to_not_mix_up_the_tables');
        end

        function str=selected2clipbord_DB(app,setting,return_str_state,delta_wn,range)
            if return_str_state==false
                %selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
                selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
                if iscell(app.DB_table.Data.Active)
                    selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
                else
                    selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
                end
            else
                selections = app.DB_table.Data.Id;
            end
            id_last=0;
            thx=0;
            [val ]=ismember([app.Data.Meta(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);
            size_sel=zeros(length(selections),1);
            for i=1:length(selections)
                thx=  thx+numel([app.Data.DB(idx(i)).x_signal]);
                size_sel(i)=size([app.Data.DB(idx(i)).y_signal],2);
            end

            ab=cell(thx,4);
            switch setting
                case 'single col'

                    for i=1:length(selections)
                        [x, y, SampleName, Thickness] = get_specs(app,selections(i));
                        if Thickness{:}==0
                            Thickness=10000;
                        end
                        y=(y{1}./Thickness{1}).*10000;

                        %                         x=app.Data.DB(idx(i)).x_signal;
                        %                         y=app.Data.DB(idx(i)).y_signal;


                        id=repmat(idx(i),size(x{1}));
                        sample=repmat(app.Data.Meta(idx(i)).SampleName,size(x{1}));
                        n_entry=size(x{1},1);

                        [ab(((1:n_entry)+id_last),1:4)]=[num2cell(id)  cellstr(sample) num2cell([x{1} mean(y,2,'omitnan')])];
                        id_last=size(ab,1);
                    end

                    ab = ab'; %transpose so the values arrive in the right order for the 2nd sprintf
                    str = sprintf('Id\tSample\tx\ty\n'); %print first row exclusively as text
                    str = [str sprintf('%d\t%s\t%.2f\t%.4f\n', ab{:})];

                    %             cb_data = cb_data((double(cb_data)~=32 | double(shift)~=32) & ~(double(shift==10) & double(cb_data)==32) );
                    %             cb_data(double(cb_data)==32) = char(9);
                case 'single col norm'

                    for i=1:length(selections)
                        [x, y, SampleName, Thickness] = get_specs(app,selections(i));

                        signal = norm_signal_HighestPeakinRange(app,[x{:} y{:}]',1150,3800);

                        y=signal(:,2);

                        id=repmat(idx(i),size(x{:}));
                        sample=repmat(SampleName,size(x{:}));
                        n_entry=size(x{:},1);

                        [ab(((1:n_entry)+id_last),1:4)]=[num2cell(id)  cellstr(sample) num2cell([x mean(y,2,'omitnan')])];
                        id_last=size(ab,1);
                    end

                    ab = ab'; %transpose so the values arrive in the right order for the 2nd sprintf
                    str = sprintf('Id\tSample\tx\ty\n'); %print first row exclusively as text
                    str = [str sprintf('%d\t%s\t%.2f\t%.4f\n', ab{:})];

                    %             cb_data = cb_data((double(cb_data)~=32 | double(shift)~=32) & ~(double(shift==10) & double(cb_data)==32) );
                    %             cb_data(double(cb_data)==32) = char(9);
                case 'multicol all'
                    if return_str_state==false
                        selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));
                    else
                        selections = app.DB_table.Data.Id;
                    end
                    [x, y, SampleName, Thickness] = get_specs(app,selections);
                    n_entries=sum(app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections)));
                    if range==0
                        min_wn=min(cellfun(@min,x));
                        max_wn=max(cellfun(@max,x));
                    else
                        min_wn=min(range);
                        max_wn=max(range);
                    end
                    if delta_wn==0
                        delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
                    end
                    x_new=(max_wn:-delta_wn:min_wn)';
                    in_new=nan(size(x_new,1),n_entries);
                    thx=0;

                    for n=1:numel(selections)
                        if isempty(y{n})
                            continue
                        end
                        if Thickness{n}==0
                            str_thx=sprintf('%.0f,' , selections(n));
                            message=['Error No Thickness Set for: ' str_thx(1:end-1)];
                            uialert(app.SpecDBUIFigure,message,'Error');
                            return
                        end
                        thx=thx(end)+1:thx(end)+app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections(n)));
                        if isequal(x_new,x{n})
                            in_new(:,thx)=(y{n}./Thickness{n}).*10000;
                        else
                            y_resampled=interp1(x{n}(isfinite([x{n}]),:),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_new);
                            y_resampled(not(x_new>min(x{n})&x_new<max(x{n})),:)=nan;

                            in_new(:,thx)=y_resampled;

                        end
                    end

                    try
                        data=[x_new in_new];
                    catch ME

                        disp(['Error copying to clipbord - multicol all'])
                        disp(ME.message)

                    end

                    index=1;
                    header=cell(1,sum(size_sel));
                    for n=1:numel(size_sel)
                        if size_sel(n)==1
                            header{index} = SampleName{n};
                        else
                            header(index:index-1+size_sel(n))=cellstr(strcat(repmat([SampleName{n} '_'],size_sel(n),1),string(1:size_sel(n))'));

                        end
                        index=index+size_sel(n);
                    end

                    header = string([{'x_signal'}, header]);
                    str = sprintf(['%s' repmat('\t%s',1,size(header,2)-1) '\n'], header);
                    str = [str sprintf(['%.2f' repmat('\t%.2f',1,size(data,2)-1) '\n'], data')];
                case 'multicol mean'
                    if return_str_state==false
                        selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));
                    else
                        selections = app.DB_table.Data.Id;
                    end
                    [x, y, SampleName, Thickness] = get_specs(app,selections,true);
                    if range==0
                        min_wn=min(cellfun(@min,x));
                        max_wn=max(cellfun(@max,x));
                    else
                        min_wn=min(range);
                        max_wn=max(range);
                    end
                    if delta_wn==0
                        delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
                    end

                    x_new=(max_wn:-delta_wn:min_wn)';
                    in_new=nan(size(x_new,1),numel(selections));

                    for n=1:numel(selections)
                        if isempty(y{n})
                            continue
                        end
                        if Thickness{n}==0
                            str_thx=sprintf('%.0f,' , selections(n));
                            str =['Error No Thickness Set for: ' str_thx(1:end-1)];
                            % uialert(app.SpecDBUIFigure,message,'Error');

                            return
                        end
                        if isequal(x_new,x{n})
                            in_new(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');
                        else
                            y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_new);
                            y_resampled(not(x_new>min(x{n})&x_new<max(x{n})))=nan;
                            in_new(:,n)=mean(y_resampled,2,'omitnan');
                        end
                    end

                    try
                        data=[x_new in_new];
                    catch ME

                        disp(['Error copying to clipbord - multicol mean'])
                        disp(ME.message)

                    end

                    header = string([{'x_signal'}, SampleName']);
                    str = sprintf(['%s' repmat('\t%s',1,size(header,2)-1) '\n'], header);
                    str = [str sprintf(['%.2f' repmat('\t%.2f',1,size(data,2)-1) '\n'], data')];
            end
            if return_str_state==false
                clipboard('copy',str);
            end
        end

        function Sample = import_mapinfo(app,path,file)
            %spot info
            MetaTable=readtable(fullfile(path,file));
            if strcmp(MetaTable.Properties.VariableNames{1},'Var1')
                vars={'x','y','CX','CY','Type','Fileindex','Color','ROP2','Hidden','Group','AryType','AryX','AryY','AryCX','AryCY','AryNX','AryNY','AryXpos','AryYpos','AptExists','AptCX','AptCY','AptAngle','Text','Z','CZ','AryZ','AryCZ','AryNZ','AryZPos','AryAngle'};
                MetaTable.Properties.VariableNames=vars(1:numel(MetaTable.Properties.VariableNames));
                % this works only for Old Opus files or missing headers
            end
            Sample.MetaTable=MetaTable;
            if any(strcmp("Type",fieldnames(Sample.MetaTable))) && not(isempty(Sample.MetaTable.Type))
                if numel(Sample.MetaTable.Properties.VariableNames)>0 && any(strcmp('Type',Sample.MetaTable.Properties.VariableNames)) && any(ismember(unique(Sample.MetaTable.Type(Sample.MetaTable.Type~=13)),0))
                    if endsWith(file,'_map_info.txt')
                        Sample.Types=1; %FTIR-MCT
                    elseif endsWith(file,'_spotmap_info.txt')
                        Sample.Types=6; %FTIR-MCT Spots + xy coord
                    elseif endsWith(file,'_spotlinemap_info.txt')
                        Sample.Types=7; %FTIR-MCT Lines + xy coord
                    elseif endsWith(file,'_mixedmap_info.txt')
                        Sample.Types=8; %FTIR-MCT Mixed Spots/lines/maps + xy coord
                    else
                        Sample.Types=9; %FTIR-MCT Mixed Spots/lines/maps
                    end
                    %disp('FTIR-MCT')
                elseif numel(Sample.MetaTable.Properties.VariableNames)>0 && ismember(unique(Sample.MetaTable.Type(Sample.MetaTable.Type~=13)),9)
                    Sample.Types=2; %FTIR-FPA


                    if any(strfind(file,'_bin0')) || any(strfind(file,'_nobin'))
                        binval=64;
                        FPA_Px_tile=[binval binval];
                    elseif any(strfind(file,'_bin2'))
                        binval=32;
                        FPA_Px_tile=[binval binval];
                    elseif any(strfind(file,'_bin4'))
                        binval=16;
                        FPA_Px_tile=[binval binval];
                    elseif any(strfind(file,'_bin8'))
                        binval=8;
                        FPA_Px_tile=[binval binval];
                    elseif any(strfind(file,'_bin16'))
                        binval=4;
                        FPA_Px_tile=[binval binval];
                    else
                        %                         binval=0;
FPA_Px_tile=[];
                        return 
                    end

                    number_X_pixels=Sample.MetaTable.AryNY(Sample.MetaTable.Type==9).*FPA_Px_tile(2);
                    number_Y_pixels=Sample.MetaTable.AryNX(Sample.MetaTable.Type==9).*FPA_Px_tile(1);

                    newNumberOfRows = number_Y_pixels*FPA_Px_tile(2)/FPA_Px_tile(2);  % set the number of rows interpolated rows you want
                    newNumberOfCols = number_X_pixels*FPA_Px_tile(1)/FPA_Px_tile(1);   % set the number of columns interpolated rows you want

                    % [x, y] = meshgrid(1:size(image_matrix,2), 1:size(image_matrix,1));
                    % [xq, yq] = meshgrid(linspace(1, size(image_matrix, 2), newNumberOfCols), linspace(1, size(image_matrix, 1), newNumberOfRows));
                    % image_matrix_int=interp2(x, y, image_matrix, xq, yq);
                    xmid=Sample.MetaTable.x(Sample.MetaTable.Type==9);
                    dx=Sample.MetaTable.CX(Sample.MetaTable.Type==9);

                    ymid=Sample.MetaTable.y_signal(Sample.MetaTable.Type==9);
                    dy=Sample.MetaTable.CY(Sample.MetaTable.Type==9);

                    xmin=xmid-(0.5*dx);
                    xmax=xmid+(0.5*dx);
                    ymin=ymid-(0.5*dy);
                    ymax=ymid+(0.5*dy);
                    Sample.MatrixLimits=[xmin,xmax;ymin,ymax];

                    [yq, xq] = meshgrid(linspace(ymin, ymax, newNumberOfCols), linspace(xmin, xmax, newNumberOfRows));

                    T=table();
                    T.x=xq(:);
                    T.y=yq(:);
                    T.Group(:)=1;
                    T.Type(:)=0;
                    T.Fileindex(:)=1:numel(xq(:));

                    Sample.FPA_Px_tile=FPA_Px_tile;
                    Sample.number_Y_pixels=number_Y_pixels;
                    Sample.number_X_pixels=number_X_pixels;
                    Sample.MetaTableRaw=MetaTable;
                    Sample.MetaTable=T;

                    %   disp('FTIR-FPA')


                else
                    Sample.Types=3; %other
                    %      disp('other')
                end

            else
                Sample.Types=3; %other
                %   disp('other')
            end
        end

        function Thickness=import_thickness(app,path,file)
            Thickness=readtable(fullfile(path,file));
            if ischar(Thickness)
                Thickness=str2double(replace(Thickness,',','.'));
                disp('Thickness import "," changed to "."')
            end

            if not(istable(Thickness)) && (not(isnumeric(Thickness)) && not(numel(Thickness)==1))
                Thickness=0;
                disp('Error in Thickness import')
            end
        end

        function import_image(app,path,file,ImageGroup_Id,ImageId)
            %             if isempty(app.Data.Images) || not(isfield(app.Data.Images.Images,'ImageNames'))
            %                 ImageGroup_Id=1;
            %                 ImageId=1;
            %             end
            %% m=numel(Sample(ImageGroup_Id).ImageNames)+1;
            try
                app.Data.Images.Images(ImageGroup_Id).Image{ImageId}=imread(fullfile(path,file));

                % here if image info field is empty copy 1
                %  if isempty(Sample(ImageGroup_Id).ImageLimits{m})
                if   ImageId>numel(app.Data.Images.Images(ImageGroup_Id).ImageLimits)

                    app.Data.Images.Images(ImageGroup_Id).ImageLimits{ImageId}=app.Data.Images.Images(ImageGroup_Id).ImageLimits{1};
                end
            catch ME
                disp([fullfile(path,file) ' - image not imported'])
                return
            end

            app.Data.Images.Images(ImageGroup_Id).ImageId{ImageId}=ImageId;
            app.Data.Images.ImageGroupID(ImageGroup_Id)=ImageGroup_Id;

            app.Data.Images.Images(ImageGroup_Id).ImageNames{ImageId}=replace(file,'_image.bmp','');
            app.Data.Images.ImagePath{(ImageGroup_Id)}=path;
            app.Data.Images.ImagefullPath{(ImageGroup_Id)}=fullfile(path,file);
            app.Data.Images.ImageFilename{(ImageGroup_Id)}=file;
            %image info
        end

        function ImageLimits = import_image_info(app,path,file)
            thx=readtable(fullfile(path,file),ReadVariableNames=false);
            name_cells=table2cell(thx(:,1))';
            name_cells=replace(name_cells,{'._','. ',' ',':','_'},'');
            T=array2table(table2array(thx(:,2))');
            T.Properties.VariableNames=name_cells;
            try
                ImageLimits=[T.PosX*T.PixelSizeX,(T.PosX+T.SizeX)*T.PixelSizeX;[T.PosY*T.PixelSizeY,(T.PosY+T.SizeY)*T.PixelSizeY]];
            catch
                temp = fileread(fullfile(path,file));

                temp = replace(temp,'Pos. X','Pos. X:');
                temp = replace(temp,'Pos. Y','Pos. Y:');

                fid = fopen(fullfile(path,file),'w');    % open file for writing (overwrite if necessary)
                fprintf(fid,'%s',temp);          % Write the char array, interpret newline as new line
                fclose(fid);                  % Close the file (important)

                thx=readtable(fullfile(path,file),ReadVariableNames=false);
                name_cells=table2cell(thx(:,1))';
                name_cells=replace(name_cells,{'._','. ',' ',':','_'},'');
                T=array2table(table2array(thx(:,2))');
                T.Properties.VariableNames=name_cells;
                ImageLimits=[T.PosX*T.PixelSizeX,(T.PosX+T.SizeX)*T.PixelSizeX;[T.PosY*T.PixelSizeY,(T.PosY+T.SizeY)*T.PixelSizeY]];

            end
        end

        function multipol_info_table =  import_multipol_info(app,file)
            multipol_info_table= readtable(file,'Format','auto');
        end

        function int_val=calc_mean_int_B(app)
            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,[],true);
            x_limits=sort([app.Int_range_min.Value app.Int_range_max.Value]);

            int_val=nan(numel(x),1);
            for n=1:numel(x)
                y1=cell2mat(y(n));
                x1=cell2mat(x(n));
                if isstring(Thickness{n}) || ischar(Thickness{n})
                    Thickness1=str2double(Thickness{n});
                else
                    Thickness1=Thickness{n};
                end
                y1=y1(x1> x_limits(1)& x1 < x_limits(2),:);
                x1=x1(x1> x_limits(1)& x1 < x_limits(2),:);
                % int_val(n)=trapz(mean(linCorrect((y1./Thickness1).*10000),2,'omitnan'));
                int_val(n)=mean(trapz_BC(x1,(y1./Thickness1).*10000),'omitnan');
            end
        end

        function version_update_fun(app)
            for sample=1:numel(app.Data.DB)

                if isfield(app.Data.DB(sample),'x') && isfield(app.Data.DB(sample),'y')
                    app.Data.DB(sample).x_signal=app.Data.DB(sample).x;
                    app.Data.DB(sample).y_signal=app.Data.DB(sample).y;
                end
                if isfield(app.Data.DB(sample),'x_raw') && isfield(app.Data.DB(sample),'y_raw')
                    app.Data.DB(sample).x_signal_raw=app.Data.DB(sample).x_raw;
                    app.Data.DB(sample).y_signal_raw=app.Data.DB(sample).y_raw;
                end
            end
                if isfield( app.Data.DB,'x') && isfield( app.Data.DB,'y')
                    app.Data.DB = rmfield( app.Data.DB,'y');
                    app.Data.DB = rmfield( app.Data.DB,'x');
                end
                if isfield( app.Data.DB,'y_raw') && isfield( app.Data.DB,'x_raw')
                    app.Data.DB = rmfield( app.Data.DB,'y_raw');
                    app.Data.DB = rmfield( app.Data.DB,'x_raw');
                end
        end
    end

    methods (Access = public)

        function insert_data(app,data,update_set)
            if ~exist('update_set','var')
                update_set=true;
            end

            n_data_in=numel([data.Meta.Id]);

            try
                max_id=max([app.Data.Meta.Id]);
                max_entry=numel([app.Data.Meta.Id]);
                if isempty(max_id)
                    max_id=0;
                    max_entry=0;
                end
            catch
                max_id=0;
                max_entry=0;

            end
            input_range=(1:n_data_in)+max_entry;
            id_range=(1:n_data_in)+max_id;
            C=num2cell(id_range);
            [app.Data.Meta(input_range).Id]=C{:};
            [app.Data.DB(input_range).Id]=C{:};
            [app.Data.Meta(input_range).Active]=deal(true);
            fn1=lower(fieldnames(data));
            for n=1:numel(fn1)
                switch fn1{n}
                    case 'db'
                        fn2=fieldnames(data.DB);
                        for m=1:numel(fn2)
                            switch fn2{m}
                                case {'x'}
                                    [app.Data.DB(input_range).x_signal]=data.DB.x;
                                case {'y'}
                                    [app.Data.DB(input_range).y_signal]=data.DB.y;
                                case {'x_signal'}
                                    [app.Data.DB(input_range).x_signal]=data.DB.x_signal;
                                case {'y_signal'}
                                    [app.Data.DB(input_range).y_signal]=data.DB.y_signal;
                            end
                        end
                

            case 'meta'
                        fn2=lower(fieldnames(data.Meta));
                        for m=1:numel(fn2)
                            switch fn2{m}
                                case 'active'
                                    [app.Data.Meta(input_range).Active]=data.Meta.Active;%
                                case 'samplename'
                                    [app.Data.Meta(input_range).SampleName]=data.Meta.SampleName;%
                                case 'sampletype'
                                    [app.Data.Meta(input_range).SampleType]=data.Meta.SampleType;%
                                case 'datatype'
                                    [app.MetaPrivat.Meta(input_range).DataType]=data.Meta.DataType;%
                                case 'mineral'
                                    [app.Data.Meta(input_range).Mineral]=data.Meta.Mineral;%;%
                                case 'density'
                                    [app.Data.Meta(input_range).Density]=data.Meta.Density;%
                                case 'thickness'
                                    thx=cellfun(@mean, {data.Meta.Thickness},'UniformOutput', false);
                                    [app.Data.Meta(input_range).Thickness]=thx{:};

                                    % [app.Data.Meta(input_range).Thickness]=mean(data.Meta.Thickness);%

                                    [app.Data.MetaPrivat(input_range).Thickness]=data.Meta.Thickness;%
                                case 'mask'
                                    [app.Data.MetaPrivat(input_range).Mask]=data.Meta.Mask;
                                case 'comment'
                                    [app.Data.Meta(input_range).Comment]=data.Meta.Comment;%
                                case 'path'
                                    [app.Data.MetaPrivat(input_range).path]=data.Meta.path;
                                    [app.Data.Meta(input_range).path]=data.Meta.path;
                                case 'file'
                                    [app.Data.MetaPrivat(input_range).file]=data.Meta.file;%
                                    [app.Data.Meta(input_range).file]=data.Meta.file;%
                                case 'id'
                                    [app.Data.MetaPrivat(input_range).Import_Id]=data.Meta.Id;
                                case 'source'
                                    [app.Data.MetaPrivat(input_range).Source]=data.Meta.Source;%
                                    case 'SpecType'
                                    [app.Data.MetaPrivat(input_range).SpecType]=data.Meta.SpecType;                                
                              case 'metatable'
                                    [app.Data.MetaPrivat(input_range).MetaTable]=data.Meta.MetaTable;%
                                case 'multi_info'
                                    [app.Data.MetaPrivat(input_range).multi_info]=data.Meta.multi_info;%
                                case 'multi_info_table'
                                    [app.Data.MetaPrivat(input_range).multi_info_table]=data.Meta.multi_info_table;%
                                case 'xoi'
                                    [app.Data.MetaPrivat(input_range).xOI]=data.Meta.xOI;%

                                case 'imagegroup_id'

                                    %ImageGroup_Id=data.MetaPrivat.ImageGroup_Id;
                                    ImageGroup_Id=data.Meta.ImageGroup_Id;
                                    if isnumeric(ImageGroup_Id) && not (isempty(ImageGroup_Id))
                                        [app.Data.MetaPrivat(input_range).ImageGroup_Id]=deal(ImageGroup_Id);
                                        [app.Data.Meta(input_range).ImageGroup_Id]=deal(ImageGroup_Id);
                                        [app.Data.Meta(input_range).n_Images]=deal(numel(app.Data.Images.Images(ImageGroup_Id).ImageNames));
                                    end
                                case 'image_id'
                                    [app.Data.MetaPrivat(input_range).Image_Id]=data.Meta.Image_Id;%
                                    [app.Data.Meta(input_range).Image_Id]=data.Meta.Image_Id;%
                                case 'n_images'
                                    [app.Data.Meta(input_range).n_Images]=data.Meta.n_Images;%
                                case 'matrixlimits'
                                    [app.Data.MetaPrivat(input_range).MatrixLimits]=data.Meta.MatrixLimits;
                                case 'images'
                                    imtest=false;
                                    ImageGroup_Id=[];
                                    if isempty(ImageGroup_Id) && (not(isfield(app.Data,'Images')) || numel(app.Data.Images)==0)
                                        ImageGroup_Id=1;
                                    else
                                        try
                                            for h=1:numel(app.Data.Images.Images)

                                                imtest=any(isequal(app.Data.Images.Images(h).Image{:},data(1).Meta(1).Images.Image{1}));
                                                if imtest== true
                                                    ImageGroup_Id=h;
                                                    break
                                                end
                                            end
                                            if imtest== false

                                                ImageGroup_Id=numel(app.Data.Images.Images)+1;
                                            end
                                        catch
                                            ImageGroup_Id=numel(app.Data.Images.Images)+1;
                                        end
                                    end
                                    if imtest==false
                                        [app.Data.Images.Images(ImageGroup_Id).ImageLimits]=data(1).Meta(1).Images.ImageLimits;
                                        [app.Data.Images.Images(ImageGroup_Id).Image]=data(1).Meta(1).Images.Image;
                                        [app.Data.Images.Images(ImageGroup_Id).ImageId]=data(1).Meta(1).Images.ImageId;
                                        [app.Data.Images.Images(ImageGroup_Id).ImageNames]=data(1).Meta(1).Images.ImageNames;
                                    end
                                    [app.Data.MetaPrivat(input_range).ImageGroup_Id]=deal(ImageGroup_Id);
                                    [app.Data.Meta(input_range).ImageGroup_Id]=deal(ImageGroup_Id);

                                    [app.Data.Meta(input_range).n_Images]=deal(numel(app.Data.Images.Images(ImageGroup_Id).ImageNames));
                            end
                        end
                end
            end


            % fill the missing
            fns=fieldnames(data.Meta);
            nametest=not(ismember(fns,app.protected_fns));
            fns2add=fns(nametest);

            fns2ignore=lower({'source','path','DataType','Import_Id','MetaTable','Types','Mask','MatrixLimits','Images','ImageGroup_Id','n_Images','multi_info_table','xOI'});

            if not(isempty(nametest(nametest==true)))
                for k=1:numel(fns2add)
                    switch lower(fns2add{k})
                        case fns2ignore
                            %  disp(fns2add{k})
                        otherwise
                            [app.Data.Meta(input_range).(char(fns2add{k}))]=data.Meta.(char(fns2add{k}));
                    end
                end
            end


            if update_set==true
                update_DB_table(app)
            end
            app.edit_state=true;
        end

        function [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,mask_specs)

            if not(exist('mask_specs',"var"))
                mask_specs=false;
            end
            if not(exist('selections',"var")) || isempty(selections)
                if not(isempty(app.DB_table.Selection))
                    selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
                else
                    selections = app.DB_table.Data.Id(app.DB_table.Data.Active);
                end
            end

            % [val s]=ismember([app.Data.Meta(app.Data.Meta(:).Active).Id],selections);
            idx=zeros(1,numel(selections));
            for n=1:numel(selections)
                idx(n)=find(ismember([app.Data.Meta(:).Id],selections(n)));
            end

            %             [val ]=ismember([app.Data.Meta(:).Id],selections);
            %             idx=1:numel(val);
            %             idx=idx(val);
            thx=0;

            for i=1:numel(idx)
                thx=  thx+numel([app.Data.DB(idx(i)).x_signal]);
            end
            x=cell(length(selections),1);
            y=cell(length(selections),1);
            SampleName=cell(length(selections),1);
            Thickness=cell(length(selections),1);

            for i=1:numel(idx)
                x{i}=app.Data.DB(idx(i)).x_signal;
                if mask_specs==true && isfield(app.Data.MetaPrivat,'Mask') && numel(app.Data.MetaPrivat(idx(i)).Mask) == size(app.Data.DB(idx(i)).y_signal,2) && isfield(app.Data.MetaPrivat(idx(i)),'Thickness') &&  numel(app.Data.MetaPrivat(idx(i)).Mask) == numel(app.Data.MetaPrivat(idx(i)).Thickness)
                    y{i}=app.Data.DB(idx(i)).y_signal(:,app.Data.MetaPrivat(idx(i)).Mask);
                    Thickness{i}=app.Data.MetaPrivat(idx(i)).Thickness(app.Data.MetaPrivat(idx(i)).Mask)';
                elseif mask_specs==true && isfield(app.Data.MetaPrivat,'Mask') && numel(app.Data.MetaPrivat(idx(i)).Mask) == size(app.Data.DB(idx(i)).y_signal,2) && isfield(app.Data.MetaPrivat(idx(i)),'Thickness') &&  numel(app.Data.MetaPrivat(idx(i)).Thickness) ==1
                    y{i}=app.Data.DB(idx(i)).y_signal(:,app.Data.MetaPrivat(idx(i)).Mask);
                    Thickness{i}=app.Data.MetaPrivat(idx(i)).Thickness';

                elseif isfield(app.Data.MetaPrivat(idx(i)),'Thickness')
                    y{i}=app.Data.DB(idx(i)).y_signal;
                    Thickness{i}=app.Data.MetaPrivat(idx(i)).Thickness';
                else
                    y{i}=app.Data.DB(idx(i)).y_signal;
                    Thickness{i}=app.Data.Meta(idx(i)).Thickness';

                end
                SampleName{i}=app.Data.Meta(idx(i)).SampleName;
            end
            Id=selections;
            eID=idx;
        end

        function answer=CalcIntAbs_cm(app,settings_return)
            if settings_return.CalcAllSpecs==true
                %   selections=[app.DB_table.Data.Id]];
                selections=[app.Data.Meta.Id];
            else
                selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));
            end
            region=settings_return.Int_range;
            xlimits=settings_return.xlimits;

            %[x, y, ~, Thickness] = get_specs(app,selections,true);
            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);

            y=    y(not(cellfun(@iscell,x)));
            Thickness=    Thickness(not(cellfun(@iscell,x)));
            Id=    Id(not(cellfun(@iscell,x)));
            eID=    eID(not(cellfun(@iscell,x)));
            SampleName=     SampleName(not(cellfun(@iscell,x)));
            x=  x(not(cellfun(@iscell,x)));


            min_x_signal=min(cellfun(@min,x));
            max_x_signal=max(cellfun(@max,x));
            delta_x_signal=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));

            if isnan(delta_x_signal)
                delta_x_signal=cellfun(@diff,(x),'UniformOutput',false);
                delta_x_signal=[delta_x_signal{:}];
                delta_x_signal=delta_x_signal(isnumeric(delta_x_signal));
                delta_x_signal=mean(delta_x_signal);
            end

            x_signal=(max_x_signal:-delta_x_signal:min_x_signal)';
            y_signal=nan(size(x_signal,1),numel(selections));
            try
                for n=1:numel(selections)
                   
                    if isempty(y{n})
                        continue
                    end
                    if Thickness{n}==0
          
                        y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]))),x_signal);
                        y_resampled= mean(y_resampled,2,'omitnan');
                        y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;
                        y_signal(:,n)=nan(size(y_resampled));
                    elseif isequal(x_signal,x{n})

                        y_signal(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');
        
                    else
                        y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_signal);
                        y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;
                        y_signal(:,n)=mean(y_resampled,2,'omitnan');
                    end
                end
            catch ME
                'error integration preperation'
            end
            

            switch settings_return.ABC
                case 'A'
                    if any(isnan(y_signal),'all')
                        integration=zeros(1,size(y_signal,2));
                        for n=1:size(y_signal,2)
                            y_signal_2=y_signal(x_signal>min(region)&x_signal<max(region),n);
                            x_signal_2=x_signal(x_signal>min(region)&x_signal<max(region));
                            y_signal_2=y_signal_2(not(isnan(y_signal_2)));
                            x_signal_2=x_signal_2(not(isnan(y_signal_2)));
                            if x_signal_2(1)>x_signal_2(end)
                                x_signal_2=flipud(x_signal_2);
                                y_signal_2=flipud(y_signal_2);
                            end
                            integration(n)=trapz(x_signal_2,y_signal_2);
                        end
                    else
                        integration=trapz(x_signal(x_signal>min(region)&x_signal<max(region)),y_signal(x_signal>min(region)&x_signal<max(region),:));
                    end
                case 'B'
                    y_signal(isnan(y_signal))=0;
                    integration=trapz_BC(x_signal(x_signal>min(region)&x_signal<max(region)),y_signal(x_signal>min(region)&x_signal<max(region),:));
                case 'C'
                    if any(isnan(y_signal),'all')
                        integration=zeros(1,size(y_signal,2));
                        for n=1:size(y_signal,2)
                            y_signal_2=y_signal(x_signal>min(region)&x_signal<max(region),n);
                            y_signal_2=y_signal_2(not(isnan(y_signal_2)));
                            y_signal_2=linCorrect(y_signal_2);
                            x_signal_2=x_signal(x_signal>min(region)&x_signal<max(region));
                            y_signal_2=y_signal_2(not(isnan(y_signal_2)));
                            x_signal_2=x_signal_2(not(isnan(y_signal_2)));
                            if x_signal_2(1)>x_signal_2(end)
                                x_signal_2=flipud(x_signal_2);
                                y_signal_2=flipud(y_signal_2);
                            end
                            integration(n)=trapz(x_signal_2,y_signal_2);
                        end
                    else
                        y_signal=y_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);
                        x_signal=x_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);

                        y_signal=linCorrect(y_signal);
                        integration=trapz(x_signal(x_signal>min(region)&x_signal<max(region)),y_signal(x_signal>min(region)&x_signal<max(region),:));
                    end
                case 'D'
                    [ ~, ix ] = min(abs(x_signal-max(region(2))));
                    integration=y_signal(ix,:)';
                case 'E'
                    y_signal=y_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);
                    x_signal=x_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);

                    y_signal=linCorrect(y_signal);

                    [ ~, ix ] = min(abs(x_signal-max(region(2))));
                    integration=y_signal(ix,:)';
                case 'F'
                    y_signal=y_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);
                    x_signal=x_signal(x_signal>=min(xlimits) & x_signal<=max(xlimits),:);

                    y_signal=linCorrect(y_signal);
                    y_signal=normalize(y_signal,'range');
                    [ ~, ix ] = min(abs(x_signal-max(region(2))));
                    integration=y_signal(ix,:)';
            end


            new_name=settings_return.fn_str;
            fns=app.DB_table.ColumnName;
            %             if any(ismember(new_name,fns)) && not(startsWith(new_name,'Abs_cm_'))
            %                 message='Error Invalid Name';
            %                 uialert(app.SpecDBUIFigure,message,'Error');
            %                 answer=false;
            %                 return
            %             end

            if not(isempty(new_name))
                new_name = matlab.lang.makeValidName(new_name);
                if ismember(new_name,fns)
                    answer = questdlg({'Do you want to Upddate the existing field'},...
                        'Field exists already!','Yes','No','Cancle','Cancle');

                    if strcmp(answer,'No')
                        new_name = matlab.lang.makeUniqueStrings([fns;{new_name}]);
                        new_name=new_name{end};
                    elseif strcmp(answer,'Cancle') ||isempty(answer)
                        return
                    end
                end
                %                 for n=1:numel(integration)
                %                     [app.Data(1).Meta(selections(n)).(char(new_name))] = integration(n);
                %                 end
                %                 [val]=ismember([app.Data.Meta(:).Id],selections);
                %                 idx=1:numel(val);
%                 idx=idx(val);

                for n=1:numel(eID)
                    [app.Data.Meta(eID(n)).(char(new_name))]=integration(n).*settings_return.CalibrationFactor;
                end

                app.Int_range_min.Value   = min(region);
                app.Int_range_max.Value   = max(region);
                app.EditField_xlimMin.Value   = min(xlimits);
                app.EditField_xlimMax.Value   = max(xlimits);

                update_UI(app)
            end

            answer=true;
            app.edit_state=true;

        end

        function MatchWithDB(app,x_signal,y_signal,n_match,mmv_input,mmv_DB,baseline_cor)

            if not(exist("n_match","var"))
                n_match=6;
            end

            if not(exist("baseline_cor","var"))
                baseline_cor=false;
            end

            %% get_specDB_to_match
            selections = app.DB_table.Data.Id;

            thx=0;
            [val ]=ismember([app.Data.Meta(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);
            size_sel=zeros(length(selections),1);
            for i=1:length(selections)
                thx=  thx+numel([app.Data.DB(idx(i)).x_signal]);
                size_sel(i)=size([app.Data.DB(idx(i)).y_signal],2);
            end

            selections = app.DB_table.Data.Id;
            [x, y, SampleName, Thickness,Id, eID] = get_specs(app,selections,true);

            range=[min(x_signal) max(x_signal)];
            %range=[app.Int_range_min.Value app.Int_range_max.Value];
            min_x_signal=min(range);
            max_x_signal=max(range);

            % delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
            delta_x_signal=abs(mean(diff(x_signal),'omitnan'));

            %% resample database data to input data
            x_new=(max_x_signal:-delta_x_signal:min_x_signal)';
            in_new=nan(size(x_new,1),numel(selections));

            for n=1:numel(selections)
                try
                    if not(isempty(x{n}))
                        if x{n}(1)>x{n}(end)
                            x{n}=flipud(x{n});
                            y{n}=flipud(y{n});
                            Thickness{n}=flipud(Thickness{n});
                        end

                        if  isempty(Thickness{n}) || Thickness{n}==0
                            Thickness{n}=10000;
                        end
                        if isequal(x_new,x{n})
                            in_new(:,n)=Mean((y{n}./Thickness{n}).*10000,2,'omitnan');
                        else
                            [~,unique_xval]=unique(x{n});
                            y_resampled=interp1(x{n}(unique_xval),(y{n}(unique_xval)./Thickness{n}).*10000,x_new);
                            y_resampled(not(x_new>min(x{n})&x_new<max(x{n})))=nan;
                            y_resampled(isnan(y_resampled))=0;

                            if exist("mmv_DB","var")
                                %     in_new(:,n)=movmean(baseline(mean(y_resampled,2,'omitnan')),mmv_DB);
                                in_new(:,n)=movmean(mean(y_resampled,2,'omitnan'),mmv_DB);
                            end
                            in_new(isnan(in_new(:,n)),n)=0;
                        end
                        in_new(:,n)=in_new(:,n)-min(in_new(:,n));
                        in_new(:,n)=in_new(:,n)./max(in_new(:,n));
                    else
                        in_new(:,n)=zeros(size(in_new(:,1)));

                    end
                catch
                    in_new(:,n)=zeros(size(in_new(:,1)));

                end

            end
            in_new(isnan(in_new))=0;


            %% correct input data
            if exist("mmv_input","var")
                movmean(mean(y_signal,2,'omitnan'),mmv_input)
            end
            try
                if  baseline_cor==true
                    [~, y_signal]  =baseline_raman(y_signal);
                end
            catch
                disp("Baseline Failed")
            end

            spec2match=y_signal;
            spec2match=spec2match-min(spec2match);
            spec2match=spec2match./max(spec2match);

            %% Match with DB
            y_DB=in_new;
            y_unknown=spec2match;

            testfit=100-(mean((y_DB-y_unknown).^2));
            %% extract results
            [~,idx]=sort(testfit,"descend");
            results_id=selections(idx(1:n_match));

            RT=app.DB_table.Data(ismember(app.DB_table.Data.Id,results_id),:);
            disp(RT)

            %% plot the figure
            figure
            for m=1:n_match
                nexttile
                hold on
                plot(x_new,spec2match,LineWidth=2,LineStyle="-")

                legend_cell(1)="Unknown Sample";
                hold on
                plot(x_new,in_new(:,results_id(m)),LineWidth=2,LineStyle=":")
                if strcmp(app.XDirSwitch.Value,'Reverse')
                    set (gca, 'xdir', 'reverse')
                end
                RT=app.DB_table.Data(ismember(app.DB_table.Data.Id,results_id(m)),:);
                legend_cell(2)=join([num2str(RT.Id), string(RT.Mineral),string(RT.SampleName)]);
                Id(results_id)
                eID(results_id)
                legend(legend_cell,Interpreter="none")
                R2 = 1 - (norm(spec2match-in_new(:,idx(m)))^2)/(norm(spec2match-mean(spec2match))^2);

                title(['r^2:' num2str(R2)])
            end
        end

        function update_public(app,what2update)
            if what2update==0
                update_UI(app)
            elseif what2update==1
                update_DB_table(app)
            elseif what2update==2
                update_DB_list(app)
            end
        end

        function OH_table=Calc_OH_from_selection(app,selection,custom_sample_name)
                                   thx=app.Data.OHCalcResults(1).OHCalcResults_Table;

                            [~, ~, ~, ~,~, eId] = get_specs(app,selection);

     int_range=[3200 3720];
I=[];


            y_signal=cell(1,numel(eId));
            x_signal=cell(1,numel(eId));
            thickness=zeros(1,numel(eId));
            density=zeros(1,numel(eId));

            %      [x, y, ~, Thickness,Id, eID] = get_specs(app,idx,true);

            for n=1:numel(eId)

                y_signal{n}=app.Data.DB(eId(n)).y_signal;
                x_signal{n}=app.Data.DB(eId(n)).x_signal;

                try
                    if numel(app.Data.MetaPrivat(eId(n)).Thickness)>1

                        y_signal{n}=(y_signal{n}./app.Data.MetaPrivat(eId(n)).Thickness').*10000;
                        thickness(n)=10000;

                    else
                        thickness(n)=app.Data.MetaPrivat(eId(n)).Thickness;
                        if thickness(n)==0
                            thickness(n)=10000;
                        end
                    end
                catch
                    thickness(n)=inf;
                end
                try
                    density(n)=app.Data.Meta(eId(n)).Density;
                catch
                    density(n)=0;
                end
                try
                    sample=app.Data.Meta(eId(n)).Sample;
                catch
                    sample='unknown';
                end
                try
                    mineral=app.Data.Meta(eId(n)).Mineral;
                catch
                    mineral='unknown';

                end
            end
            intensity=zeros(max(cellfun(@max,(cellfun(@size,y_signal,'UniformOutput',false)))),numel(eId));
            wavenumber=zeros(max(cellfun(@max,(cellfun(@size,x_signal,'UniformOutput',false)))),numel(eId));


            for n=1:numel(eId)

                mean_data=mean(y_signal{n},2,"omitnan");
                intensity(1:numel(mean_data),n)=mean_data;

                wavenumber(1:length(x_signal{n}),n)=x_signal{n};
            end
            intensity(intensity==inf)=0;
            intensity(isnan(intensity))=0;

            if exist('custom_sample_name','var') && not(isempty(custom_sample_name))
                if startsWith(custom_sample_name,'_')
                    sample=[sample custom_sample_name];
                else
                    sample=[custom_sample_name];
                end
            end

            try
                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                %if isfield(app.Data,'OHCalcResults')
                if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                    app.Data.OHCalcResults(1).OHCalcResults_Table=OH_table;

                    %                     try ATH here check if multiple results
                    %                         app.Data.OH_results_OH_T2(end+1)=OH_table
                    %                         app.Data.MetaPrivat(selections).OH_T2_Id=numel(app.Data.OH_results_OH_T2)
                    %                     catch
                    %                         app.Data.OH_results(1).OH_T2(end+1)=OH_table
                    %                     end
                else
                    % app.Data.OHCalcResults(1).OHCalcResults_Table(end+1,:)=OH_table;
                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',2,'sample',sample,'custom_I',I);
                    disp(OH_table)

                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                    if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                        app.Data.OHCalcResults(1).OHCalcResults_Table=table();
                    end
                    thx=app.Data.OHCalcResults(1).OHCalcResults_Table;

                    thx=tblvertcat(app,thx,OH_table);
                end

                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',3,'sample',sample,'custom_I',I);

            catch ME
                disp(['Error - ' sample])
                disp(ME.message)
                OH_table=['Error - ' sample];
                return
            end


            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;

            pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);

            app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
            app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
            app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
            app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;

            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};
        end

        function OH_table = CalculateH2O(app,x_signal,y_signal,eID,sample,int_range,individual_peak)
            if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                app.Data.OHCalcResults(1).OHCalcResults_Table=table();
            end
            thx=app.Data.OHCalcResults(1).OHCalcResults_Table;
            I=sscanf(replace(app.CustomIEditField.Value,{',' ';'},' '),'%f',[1,Inf]);
            %density_input=app.DensityEditField.Value;

            if iscell(y_signal)
                if individual_peak==true
                    intensity=cellfun(@(x) {sum(x,2)}, y_signal);
                    intensity=[intensity{:}];
                else
                    intensity=[y_signal{:}];

                end
                wavenumber=[x_signal{:}];
            else
                intensity=sum(y_signal,2,'omitnan');
                wavenumber=x_signal;
            end

            intensity(intensity==inf)=0;
            intensity(isnan(intensity))=0;
            if not(exist('int_range','var')) || isempty(int_range)
                Int_range_max=max(wavenumber,[],'all');
                Int_range_min=min(wavenumber,[],'all');
                int_range=[Int_range_min Int_range_max];
            end

            thickness=10000;

            try
                if numel(unique([app.Data.Meta(eID).Density]))==1
                    density=app.Data.Meta(eID(1)).Density;
                else
                    OH_table="density values not the same";
                    return
                end
            catch
                density=0;
            end

            try
                if numel(unique({app.Data.Meta(eID).Sample}))==1
                    if not(exist('sample','var'))
                        sample=app.Data.Meta(eID(1)).Sample;
                    end
                else
                    OH_table="Sample values not the same";
                    return
                end
            catch
                sample='unknown';
            end
            try
                if numel(unique({app.Data.Meta(eID).Mineral}))==1
                    mineral=app.Data.Meta(eID(1)).Mineral;
                else
                    OH_table="Mineral values not the same";
                    return
                end

            catch
                mineral='unknown';
            end
            try
                Orientation={app.Data.Meta(eID).Orientation};

                a_pos=find(ismember(Orientation,{'alpha','a','x','[100]','α'}));
                b_pos=find(ismember(Orientation,{'beta','b','y','[010]','β'}));
                c_pos=find(ismember(Orientation,{'gamma','c','z','[001]','γ'}));

                sort_vector=[a_pos b_pos c_pos];

                if size(intensity,2)==3 && not(isempty(a_pos)) && not(isempty(b_pos)) && not(isempty(c_pos))
                    if not(isempty(sort_vector))
                        intensity=intensity(:,sort_vector);
                    end
                elseif size(intensity,2)>3 && not(isempty(a_pos)) && not(isempty(b_pos)) && not(isempty(c_pos))
                                            intensity=[mean(intensity(:,a_pos),2,'omitnan'),mean(intensity(:,b_pos),2,'omitnan'),mean(intensity(:,c_pos),2,'omitnan')];
                elseif size(intensity,2)==2 && strcmp(mineral,'Olivine')
                    if not(isempty(a_pos))
                        intensity=[mean(intensity(:,a_pos),2), mean(intensity(:,not(ismember(Orientation,{'alpha','a','x','[100]','α'}))),2), mean(intensity(:,not(ismember(Orientation,{'alpha','a','x','[100]','α'}))),2)];
                    end
                elseif size(intensity,2)==2 && strcmp(mineral,'CPX')
                    if not(isempty(a_pos))
                        intensity=[mean(intensity(:,not(ismember(Orientation,{'gamma','c','z','[001]','γ'}))),2), mean(intensity(:,not(ismember(Orientation,{'gamma','c','z','[001]','γ'}))),2), mean(intensity(:,c_pos),2)];
                    end
                elseif size(intensity,2)>3 && not(isempty(sort_vector)) && size(intensity,2)==sum(ismember(Orientation,{'alpha','a','x','[100]','α','beta','b','y','[010]','β','gamma','c','z','[001]','γ'}))
                    intensity=[mean(intensity(:,a_pos),2),mean(intensity(:,b_pos),2),mean(intensity(:,c_pos),2)];

                    %elseif %ATH here it might be good to check if any have
                    %a mix between alpha beta or gamma
                end
            catch
                Orientation='unknown';
            end


            try
                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                %if isfield(app.Data,'OHCalcResults')
                if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                    app.Data.OHCalcResults(1).OHCalcResults_Table=OH_table;

                else
                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',2,'sample',sample,'custom_I',I);

                    disp(OH_table)

                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                    thx=tblvertcat(app,thx,OH_table);
                end

                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',3,'sample',sample,'custom_I',I);

            catch ME
                disp(['Error - ' sample])
                disp(ME.message)
            end


            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;

            pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);

            app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
            app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
            app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
            app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;

            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};
            app.edit_state=true;
        end

        function ClassificationReturn(app,Classification,sampleinfo)
            for n=1:numel(Classification.ClassesNames)

                for m=1:numel(Classification.Classes(n).ClassMapNames)
                    try
                        new_name = matlab.lang.makeValidName([Classification.ClassesNames{n} '_' Classification.Classes(n).ClassMapNames{m}]);
                    catch ME
                        ME
                    end

                    for l=1:numel(Classification.Classes(n).ClassMap(m).ClassesNames)
                        eId=sampleinfo(ismember(Classification.Classes(n).ClassMap(m).classNumber,l));
                        [app.Data.Meta(eId).(char(new_name))]=deal(Classification.Classes(n).ClassMap(m).ClassesNames{l});
                    end
                end


            end
            disp('DONE')
            app.edit_state=true;
            update_DB_table(app)
        end

        function  ExtractGroups(app,selection)

            for n=1:numel(selection)
                data=[];
                data.Meta=app.Data.Meta(selection(n));

                [x, y, ~, ~,~, eID] = get_specs(app,selection(n));
                selection(n)=eID;
                if not(isfield(app.Data.MetaPrivat,'MetaTable'))
                    return
                end

                if isempty(app.Data.MetaPrivat(selection(n)).MetaTable) || not(istable(app.Data.MetaPrivat(selection(n)).MetaTable))
                    continue
                end

                Groups2Extract=app.Data.MetaPrivat(selection(n)).MetaTable.Group(app.Data.MetaPrivat(selection(n)).MetaTable.Group>0);
                uGroups2Extract=unique(Groups2Extract);

                for m=1:numel(uGroups2Extract)%size(y{n},2)

                    data.Meta=app.Data.Meta(eID);

                    data.Meta.n_meanSpec=sum(app.Data.MetaPrivat(selection(n)).MetaTable.Group==uGroups2Extract(m));

                    data.Meta.n_Groups=1;

                    data.MetaPrivat=app.Data.MetaPrivat(eID);

                    if numel(data.MetaPrivat.Mask)>1
                        % Mask_Group=app.Data.MetaPrivat(selection(n)).Mask((app.Data.MetaPrivat(selection(n)).MetaTable.Group>0 & app.Data.MetaPrivat(selection(n)).MetaTable.Group==uGroups2Extract(m)));
                        Mask_Group=app.Data.MetaPrivat(selection(n)).Mask(app.Data.MetaPrivat(selection(n)).MetaTable.Group(app.Data.MetaPrivat(selection(n)).MetaTable.Group>0)==uGroups2Extract(m));
                        data.MetaPrivat.Mask=Mask_Group;
                    end


                    %data.MetaPrivat.MetaTable=app.Data.MetaPrivat(selection(n)).MetaTable(app.Data.MetaPrivat(selection(n)).MetaTable.Group>0 &app.Data.MetaPrivat(selection(n)).MetaTable.Group==uGroups2Extract(m),:);
                    data.MetaPrivat.MetaTable=app.Data.MetaPrivat(selection(n)).MetaTable(app.Data.MetaPrivat(selection(n)).MetaTable.Group>0 &app.Data.MetaPrivat(selection(n)).MetaTable.Group==uGroups2Extract(m),:);

                    fns=fieldnames(data.MetaPrivat);
                    for h=1:numel(fns)
                        data.Meta.(char(fns(h)))=data.MetaPrivat.(char(fns(h)));
                    end

                    data.Meta.SampleName=[data.Meta.SampleName '_Group_' num2str(uGroups2Extract(m))];
                    data.DB.x_signal=x{n};
                    data.DB.y_signal=y{n}(:,app.Data.MetaPrivat(selection(n)).MetaTable.Group(app.Data.MetaPrivat(selection(n)).MetaTable.Group>0)==uGroups2Extract(m));

                    insert_data(app,data,false)
                end
            end
            update_DB_table(app)

        end

        function  PlotSelectionFun(app,selections,axis2plot)
            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);

            grid(axis2plot,'on');
            hold(axis2plot,'on');
            %   set(gcf,'position',screensize)

            xlabel(axis2plot,'Wavenumber [cm^{-1}]')
            ylabel(axis2plot,'Absorbance [cm^{-1}]')


            for n=1:numel(x)
                if cell2mat(Thickness(n))==0
                    Thickness(n)={10000};
                end
                if size(cell2mat(y(n)),2)<200
                    plot(axis2plot,cell2mat(x(n)),(cell2mat(y(n))./(cell2mat(Thickness(n))./10000)),'-','markersize',1,'color', [0.4 0.4 0.4 0.15]);
                    plot(axis2plot,cell2mat(x(n)),mean(cell2mat(y(n))./(cell2mat(Thickness(n))./10000),2,"omitnan"),LineWidth=2);

                else
                    plot(axis2plot,cell2mat(x(n)),mean(cell2mat(y(n))./(cell2mat(Thickness(n))./10000),2,"omitnan"),LineWidth=2);
                end
            end

            if strcmp(app.XDirSwitch.Value,'Reverse')
                axis2plot.XDir='reverse';
            end

            xlim(axis2plot,sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]))


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Project)
            format('shortG')
            app.SpecDBUIFigure.Name = 'SpecDB';


            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            % drawnow;
            app.SpecDBUIFigure.Position = [left bottom width height];


            if not(isfield(app.Data,'Group_names'))
                app.Data.Group_names={'Full Database'};
            end
            if exist('Project','var')
                try
                    if strcmp(Project,'open')

                        f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                        [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
                        delete(f);
                        figure(app.SpecDBUIFigure)
                        if not(isequal(file,0))
                            Project=fullfile(path,file);
                        else
                            return
                        end
                    end

                    thx=load(Project);

                    app.Data=thx.data2save;

                    app.Data.projectpath=Project;
                    app.SpecDBUIFigure.Name = app.Data.projectpath;
                    version_update_fun(app)
                catch
                    error('not a valid file')
                end
            end

            update_PeakPlotSet_fun(app)


            update_UI(app)

        end

        % Button pushed function: CreateGroupButton
        function CreateGroupButtonPushed(app, event)
            new_name=inputdlg('Enter New Group Name','New_DB', 1);
            if not(isempty(new_name))
                app.Data.Group_names(end+1)=new_name;
                app.active_Group=numel(app.Data.Group_names);
                update_DB_list(app)
            end
            figure(app.SpecDBUIFigure);

        end

        % Button pushed function: RenameGroupButton
        function RenameGroupButtonPushed(app, event)
            if app.active_Group==1
                return
            else
                new_name=inputdlg('Enter New Group Name',...
                    'New_DB', 1);
                if not(isempty(new_name))

                    app.Data.Group_names(app.active_Group)=new_name;
                    app.GroupListBox.Items=app.Data.Group_names;
                    app.GroupListBox.Value=new_name;
                end
            end
            figure(app.SpecDBUIFigure);
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            % update the group matrix
            [indx,tf] = listdlg('PromptString',{'Select Groups'},...
                'SelectionMode','multiple','ListString',app.Data.Group_names(2:end),'InitialValue',...
                find(ismember(app.GroupListBox.Value,app.GroupListBox.Items(2:end))));
            for n=fliplr(1:numel(indx))
                app.Data.Group_names(indx(n)+1)=[];
                app.Data.Groups(indx(n)+1)=[];
            end
            app.active_Group=1;
            update_UI(app)
            figure(app.SpecDBUIFigure);

        end

        % Value changed function: GroupListBox
        function GroupListBoxValueChanged(app, event)
            app.active_Group=find(ismember(app.GroupListBox.Items,app.GroupListBox.Value));
            update_UI(app)

        end

        % Callback function: AddSpectraButton, ImportSpectraFromFilesMenu
        function AddSpectraButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.dpt;*.sp;*.spa;*.mat;*.*'},"MultiSelect","on",'Select Spectrum');
            delete(f);
            figure(app.SpecDBUIFigure)
            app.active_tmp=0;
            if not(iscell(file))
                file={file};
            end
            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(file)
                if any(strfind(file{k},'.mat')) || any(strfind(file{k},'.csv')) || any(strfind(file{k},'.sp')) || any(strfind(file{k},'.txt')) || any(contains(file{k},join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))  || any(strfind(file{k},'.dpt'))

                    d.Value = k/length(file);
                    d.Message = sprintf(['Importing Spectrum:' num2str(k) ' of ' num2str(length(file)) ': '   file{k} ]);
                    if d.CancelRequested
                        break
                    end
                    try

                        load_spec_to_import(app,file{k},path);
                        app.active_tmp=app.active_tmp+1;

                    catch
                        disp(['Error could not open file: ' fullfile(path,file)])
                    end
                end
            end
            update_DB_table(app)
            app.edit_state=true;

        end

        % Menu selected function: ImportSpectraFromFolderMenu
        function ImportSpectrafromFolderButtonPushed(app, event)


            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure)
            if isequal(path,0)
                return
            end
            test=join([string(repmat('.',size(string((0:999)')))), string((0:999)')],'');
            dirttest=dir([path '/**/*.*']);
            test_str=false(1,numel({(dirttest.name)}));
            for n=1:numel({(dirttest.name)})
                test_str(n)=contains(string({dirttest(n).name}'),(test));
            end
            dir2keep=dirttest(test_str);


            spectra_in_dir=[dir([path '/**/*.mat']);dir([path '/**/*.txt']);dir([path '/**/*.dpt']);dir([path '/**/*.csv']);dir([path '/**/*.sp']);dir([path '/**/*.spa']);dir([path '/**/*.CSV']);dir2keep];
            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %disp(length(spectra_to_load))
            app.active_tmp=0;
            error_log={};

            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);
                if d.CancelRequested
                    break
                end
                %disp(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ])
                %if any(strfind(spectra_to_load{k},'.mat')) || any(strfind(spectra_to_load{k},'.csv'))|| any(strfind(spectra_to_load{k},'.CSV')) || any(strfind(spectra_to_load{k},'.txt')) || any(strfind(spectra_to_load{k},'.sp')) || any(strfind(spectra_to_load{k},'.spa')) || any(contains(spectra_to_load{k},join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))
                app.active_tmp=app.active_tmp+1;
                try
                    load_spec_to_import(app,spectra_to_load{k},path_to_load{k});
                catch ME
                    ME
                    error_log{end+1,1}=[path_to_load{k},spectra_to_load{k}];
                    error_log{end,2}=ME;
                end

            end

            update_DB_table(app)
            app.edit_state=true;
            if not(isempty(error_log))
                % function save_error_log(app,error_log)
                % questiondlg do you want to save the error log?
                % if yes where to save it?
                % save as txt error_log{:,1}
                % save as mat error_log
                % end
            end
        end

        % Callback function: ImportSpectraFromFolder_Button, 
        % ImportSpectraFromFoldermatonlyMenu
        function ImportSpectraFromFoldermatonlyMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);
            if isequal(path,0)
                return
            end


            spectra_in_dir=dir([path '/**/*.mat']);
            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            if isempty(spectra_in_dir)
                spectra_in_dir=dir([path '/**/*.txt']);
                spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            end

            if isempty(spectra_in_dir)
                spectra_in_dir=dir([path '/**/*.csv']);
                spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            end
            %             mb_thx=50;
            %             spectra_in_dir = spectra_in_dir([spectra_in_dir.bytes]<mb_thx*1e+6);


            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %% Image and Map Info
            %check for image files with image[].bmp
            image_input=[dir([path,'/**/*.bmp'])];
            image_input = image_input(~startsWith({image_input.name}, '.'));

            %             check_str_img=["_image.bmp" ; join([repmat("_image",size(string((0:99)'))) ...
            %                 string((0:99)')...
            %                 repmat(".bmp",size(string((0:99)')))],'')];
            check_str_img=["_image.bmp" ; join([repmat("_image",size(string((0:99)'))) ...
                string((0:99)')...
                repmat(".bmp",size(string((0:99)')))],''); join([repmat("_image0",size(string((0:99)'))) ...
                string((0:99)')...
                repmat(".bmp",size(string((0:99)')))],'')];
            test_str=false(1,numel(image_input));
            for n=1:numel({(image_input.name)})
                test_str(n)=contains(string({image_input(n).name}'),(check_str_img));
            end

            image_input=image_input(test_str);

            %             image_input=dir([path '/**/*image.bmp']);
            %             image_input=image_input(~startsWith({image_input.name}, '._'));
            %check for image info files with image[]_info.bmp
            image_info=dir([path,'/**/*_info.txt']);
            image_info = image_info(~startsWith({image_info.name}, '.'));

            check_str_img=["_image_info.txt" ; join([repmat("_image",size(string((0:99)'))) ...
                string((0:99)')...
                repmat("_info.txt",size(string((0:99)')))],''); join([repmat("_image0",size(string((0:99)'))) ...
                string((0:99)')...
                repmat("_info.txt",size(string((0:99)')))],'')];
            %test_str2=false(1,numel(image_info));
            test_str2=false(numel({(image_info.name)}),1);

            for n=1:numel({(image_info.name)})
                test_str2(n)=contains(string({image_info(n).name}'),(check_str_img)) & not((endsWith(string({image_info(n).name}'),'map_info.txt')));
                %                 test_str3(n)=not(endsWith(string({image_info(n).name}'),'map_info.txt'));
                %                 test_str4(n)=contains(string({image_info(n).name}'),(check_str_img));
            end
            %ATh if empty
            image_info=image_info(test_str2);

            map_info=dir([path '/**/*map_info.txt']);
            map_info=map_info(~startsWith({map_info.name}, '._'));

            multi_info=dir([path '/**/*multi_info.txt']);
            multi_info=multi_info(~startsWith({multi_info.name}, '._'));


            multi_info_files={multi_info.name};
            mapinfo_files={map_info.name};
            image_files={image_input.name};
            image_info_files={image_info.name};

            thickness=dir([path '/**/*thickness.txt']);
            thickness=thickness(~startsWith({thickness.name}, '._'));

            thickness_files={thickness.name};

            app.active_tmp=0;
            answer = questdlg({['Sample'],'Is sample name in the folder structure?','If your data is organized with Sample/multi or Sample/single, close this window'},...
                '','Automatic','Yes Sample/Files','Yes Sample/Subfolder/Files','No');
            folder_name_as_SampleName=false;
            switch answer
                case 'Yes Sample/Files'
                    folder_name_as_SampleName=1;
                case 'Yes Sample/Subfolder/Files'
                    folder_name_as_SampleName=2;
                case 'Automatic'
                    folder_name_as_SampleName=3;
                case 'No'
                    folder_name_as_SampleName=false;
            end
            figure(app.SpecDBUIFigure)


            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);
                if d.CancelRequested
                    break
                end
                try
                    if any(strfind(spectra_to_load{k},'.mat')) || any(strfind(spectra_to_load{k},'.csv')) || any(strfind(spectra_to_load{k},'.CSV')) || any(strfind(spectra_to_load{k},'.txt'))
                        app.active_tmp=app.active_tmp+1;


                        if folder_name_as_SampleName==1 || folder_name_as_SampleName==2%|| folder_name_as_SampleName==3
                            c=strsplit(path_to_load{k},filesep);
                            c=c(~cellfun(@isempty,c));
                                           if folder_name_as_SampleName==2
                                SampleName=c{end-1};
                            elseif folder_name_as_SampleName==1
                                SampleName=c{end};
                            end
                            load_spec_to_import(app,spectra_to_load{k},path_to_load{k},SampleName);
                        else
                            SampleName='';

                            c=strsplit(path_to_load{k},filesep);
                            c=c(~cellfun(@isempty,c));

                            if sum(strcmp(c,{'multi'}))==1 && find(strcmp(c,{'multi'}))-1>=1
                                SampleName=c{find(strcmp(c,{'multi'}))-1};
                            elseif sum(strcmp(c,{'single'}))==1&& find(strcmp(c,{'single'}))-1>=1
                                SampleName=c{find(strcmp(c,{'single'}))-1};

                            end

                            load_spec_to_import(app,spectra_to_load{k},path_to_load{k},SampleName);
                        end
                        %try to find metadata
                        if not(isfield(app.Data,'Meta'))
                            continue
                        end
                        id=numel([app.Data.Meta.Id]);
                        if numel(mapinfo_files(ismember({map_info.folder},spectra_in_dir(k).folder)))>=1
                            file=map_info(ismember({map_info.folder},spectra_in_dir(k).folder)).name;
                            path=map_info(ismember({map_info.folder},spectra_in_dir(k).folder)).folder;
                            s= import_mapinfo(app,path,file);
                            app.Data.MetaPrivat(id).MetaTable=s.MetaTable;
                            app.Data.MetaPrivat(id).Types=s.Types;
                            if not(isempty(app.Data.MetaPrivat(id).MetaTable))   && contains('Group',fieldnames(app.Data.MetaPrivat(id).MetaTable)) && numel(app.Data.MetaPrivat(id).MetaTable.Group)>0
                                [app.Data.Meta(id).n_Groups] = numel(unique(app.Data.MetaPrivat(id).MetaTable.Group(app.Data.MetaPrivat(id).MetaTable.Group>0)));
                            end
                        end

                        try
                            if numel(thickness_files(ismember({thickness.folder},spectra_in_dir(k).folder)))>=1
                                file=thickness(ismember({thickness.folder},spectra_in_dir(k).folder)).name;
                                path=thickness(ismember({thickness.folder},spectra_in_dir(k).folder)).folder;

                                T = import_thickness(app,path,file);
                                if istable(T)
                                    app.Data.Meta(id).Thickness_min =table2array(T(1,1));
                                    app.Data.Meta(id).Thickness_max =table2array(T(1,2));
                                    app.Data.Meta(id).Thickness =table2array(T(1,3));
                                    app.Data.MetaPrivat(id).Thickness =table2array(T(1,3));
                                elseif isnumeric(T) && numel(T)==1
                                    app.Data.Meta(id).Thickness_min =T;
                                    app.Data.Meta(id).Thickness_max =T;
                                    app.Data.Meta(id).Thickness =T;
                                end
                                % end
                            end
                        catch
                            0
                        end

                        %try  to find imagedata
                        if numel(image_files(ismember({image_input.folder},spectra_in_dir(k).folder)))>=1
                            if not(isfield(app.Data,'Images'))
                                app.Data.Images=[];
                            end

                            if isfield(app.Data.Images,'ImagePath') && not(isempty(app.Data.Images)) && not(isempty({app.Data.Images.ImagePath}))
                                for n=1:numel(app.Data.Images.ImagePath)
                                    file2find=unique({image_input(ismember({image_input.folder},spectra_in_dir(k).folder)).folder});
                                    test=any(strcmp(file2find ,app.Data.Images.ImagePath));
                                    if test==true
                                        ImageGroup_Id=find((strcmp(file2find ,app.Data.Images.ImagePath)));
                                        break
                                    else
                                        test=false;
                                        ImageGroup_Id=[];
                                    end
                                end

                            else
                                test=false;
                                ImageGroup_Id=[];
                            end

                            if isfield(app.Data.Images,'ImagefullPath') && (any(test))
                                file=image_input(ismember({image_input.folder},spectra_in_dir(k).folder)).name;
                                path=image_input(ismember({image_input.folder},spectra_in_dir(k).folder)).folder;
                            else
                                if test==true
                                    %ImageGroup_Id
                                elseif test==false &&  isempty(ImageGroup_Id) && numel(app.Data.Images)==0
                                    ImageGroup_Id=1;
                                else
                                    ImageGroup_Id=numel(app.Data.Images.Images)+1;
                                end


                                for ImageId=1:numel(image_info_files(ismember({image_info.folder},spectra_in_dir(k).folder)))
                                    test_file=find(ismember({image_info.folder},spectra_in_dir(k).folder));
                                    file=image_info(test_file(ImageId)).name;
                                    path=image_info(test_file(ImageId)).folder;
                                    app.Data.Images.Images(ImageGroup_Id).ImageLimits{ImageId} = import_image_info(app,path,file);
                                end

                                for ImageId=1:numel(image_files(ismember({image_input.folder},spectra_in_dir(k).folder)))
                                    test_file=find(ismember({image_input.folder},spectra_in_dir(k).folder));
                                    file=image_input(test_file(ImageId)).name;
                                    path=image_input(test_file(ImageId)).folder;
                                    import_image(app,path,file,ImageGroup_Id,ImageId);%numel(app.Data.Images),m);
                                end

                            end

                            try
                                app.Data.MetaPrivat(id).ImageGroup_Id=ImageGroup_Id;%find image id;
                                app.Data.Meta(id).ImageGroup_Id=ImageGroup_Id;%find image id;
                                [app.Data.Meta(id).n_Images]=numel(app.Data.Images.Images(ImageGroup_Id).ImageId);

                            catch  ME
                                disp(['Error could not open file: ' fullfile(path,file)])
                            end
                        end
                    else
                        disp(['Error could not open file: ' fullfile(path,file)])
                    end


                    if numel(multi_info_files(ismember({multi_info.folder},spectra_in_dir(k).folder)))>=1
                        file=multi_info(ismember({multi_info.folder},spectra_in_dir(k).folder)).name;
                        path=multi_info(ismember({multi_info.folder},spectra_in_dir(k).folder)).folder;

                        app.Data.MetaPrivat(id).multi_info_table= import_multipol_info(app,fullfile(path,file));

                        if any(strcmp(app.Data.MetaPrivat(id).multi_info_table.Properties.VariableNames,'Thickness'))
                            [app.Data.MetaPrivat(id).Thickness]=app.Data.MetaPrivat(id).multi_info_table.Thickness;
                            [app.Data.Meta(id).Thickness]=mean([app.Data.MetaPrivat(id).Thickness],'omitnan');
                        end


                        [app.Data.Meta(id).multi_info] = true;
                        [app.Data.MetaPrivat(id).multi_info] = true;

                    end

                catch ME
                    disp(['Error could not open file: ' fullfile(path,file)])

                end


            end
            if isfield(app.Data,'Meta') && not(isempty(app.Data.Meta))
                update_DB_table(app)
                app.edit_state=true;
            end


        end

        % Menu selected function: 
        % ImportSpectraFromFoldermatonlyLimitSizeMenu
        function ImportSpectraFromFoldermatonlyLimitSizeMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);

            if isequal(path,0)
                return
            end
            test=join([string(repmat('.',size(string((0:999)')))), string((0:999)')],'');
            dirttest=dir([path '/**/*.*']);
            test_str=false(1,numel({(dirttest.name)}));
            for n=1:numel({(dirttest.name)})
                test_str(n)=contains(string({dirttest(n).name}'),(test));
            end

            %ath add variable size_limit settings
            dir2keep=dirttest(test_str & [dirttest.bytes]<100000);

            spectra_in_dir=[dir([path '/**/*.mat']);dir([path '/**/*.txt']);dir([path '/**/*.dpt']);dir([path '/**/*.csv']);dir([path '/**/*.sp']);dir([path '/**/*.spa']);dir([path '/**/*.CSV']);dir2keep];
            spectra_in_dir=[dir2keep];
            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %disp(length(spectra_to_load))
            app.active_tmp=0;
            error_log={};
            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);

                if d.CancelRequested
                    break
                end
                %       disp(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k},spectra_to_load{k}])
                if any(strfind(spectra_to_load{k},'.xlsx')) || any(strfind(spectra_to_load{k},'.xls')) || any(strfind(spectra_to_load{k},'.mat')) || any(strfind(spectra_to_load{k},'.csv')) || any(strfind(spectra_to_load{k},'.CSV')) || any(strfind(spectra_to_load{k},'.txt')) || any(strfind(spectra_to_load{k},'.sp')) || any(strfind(spectra_to_load{k},'.spa')) || any(contains(spectra_to_load{k},join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))
                    app.active_tmp=app.active_tmp+1;
                    try
                        load_spec_to_import(app,spectra_to_load{k},path_to_load{k});
                    catch

                        error_log{end+1}=[path_to_load{k},spectra_to_load{k}];
                    end

                else
                    error(['Error could not open file: ' path file])
                end
            end
            update_DB_table(app)
            app.edit_state=true;


        end

        % Menu selected function: ImportSpectraFromFolder0OpusMenu
        function ImportSpectraFromFolder0OpusMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);

            if isequal(path,0)
                return
            end

            test=join([string(repmat('.',size(string((0:999)')))), string((0:999)')],'');
            dirttest=dir([path '/**/*.*']);
            test_str=false(1,numel({(dirttest.name)}));
            for n=1:numel({(dirttest.name)})
                test_str(n)=contains(string({dirttest(n).name}'),(test));
            end
            %spectra_in_dir=dirttest(test_str & [dirttest.bytes]<100000);
            spectra_in_dir=dirttest(test_str);


            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %disp(length(spectra_to_load))
            app.active_tmp=0;
            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);
                if d.CancelRequested
                    break
                end
                %       disp(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': ' path_to_load{k} spectra_to_load{k} ])
                if  any(contains(spectra_to_load{k},join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))
                    app.active_tmp=app.active_tmp+1;
                    try
                        load_spec_to_import(app,spectra_to_load{k},path_to_load{k});
                    catch ME
                        disp(['Error Importing file: ' fullfile(path_to_load{k},spectra_to_load{k})])
                        disp(ME.message)
                    end
                else
                    disp(['Error Importing file: ' fullfile(path,file)])
                end
            end
            update_DB_table(app)
            app.edit_state=true;


        end

        % Menu selected function: ImportSpectraFromFolderspPerkinElmerMenu
        function ImportSpectraFromFolderspPerkinElmerMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);

            if isequal(path,0)
                return
            end

            test='*.sp';
            dirttest=dir([path '/**/*.*']);
            test_str=false(1,numel({(dirttest.name)}));
            for n=1:numel({(dirttest.name)})
                test_str(n)=contains(string({dirttest(n).name}'),(test));
            end

            dir2keep=dirttest(test_str);

            spectra_in_dir=[dir([path '/**/*.sp*']);dir2keep];
            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %disp(length(spectra_to_load))
            app.active_tmp=0;
            error_log={};
            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);
                if d.CancelRequested
                    break
                end
                %      disp(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': ' path_to_load{k},spectra_to_load{k} ])
                if any(strfind(spectra_to_load{k},'.xlsx')) || any(strfind(spectra_to_load{k},'.xls')) || any(strfind(spectra_to_load{k},'.mat')) || any(strfind(spectra_to_load{k},'.csv')) || any(strfind(spectra_to_load{k},'.CSV')) || any(strfind(spectra_to_load{k},'.txt')) || any(strfind(spectra_to_load{k},'.sp')) || any(strfind(spectra_to_load{k},'.spa')) || any(contains(spectra_to_load{k},join([repmat(".",size(string((0:999)'))) string((0:999)')],'')))
                    app.active_tmp=app.active_tmp+1;
                    try
                        load_spec_to_import(app,spectra_to_load{k},path_to_load{k});
                    catch

                        error_log{end+1}=[path_to_load{k},spectra_to_load{k}];
                    end

                else
                    error(['Error could not open file: ' path file])
                end
            end
            update_DB_table(app)
            app.edit_state=true;

        end

        % Callback function: DB_table, EditField_xlimMax, EditField_xlimMin, 
        % LinBCButton, MeanButton, ResetButton, SpecsButton, 
        % ntoplotEditField
        function DB_tableCellSelection(app, event)
            cla(app.FigXlimLive)

            if isempty(app.DB_table.Selection) || isempty(app.DB_table.Selection(:,1))
                return
            end

            %selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

            if iscell(app.DB_table.Data.Active)
                selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
            else
                selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
            end

            if numel(selections)>100
                samplepoints=sort(randperm(numel(selections),100));
                selections=selections(samplepoints);
            end

            %             if iscell(selections)
            %               selections= [selections{:}]
            %             end
            [val ]=ismember([app.Data.Meta(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);

            for i=1:length(selections)

                if not(isempty(idx))
                    if isfield(app.Data.MetaPrivat(idx(i)),'Mask') && not(isempty(app.Data.MetaPrivat(idx(i)).Mask))
                        mask=app.Data.MetaPrivat(idx(i)).Mask;
                        x=app.Data.DB(idx(i)).x_signal;
                        try
                            y=app.Data.DB(idx(i)).y_signal(:,mask);
                        catch
                            disp(['error differnt size Meta/Spec - SampleEntry: ' num2str(idx(i)) ' - Id: ' num2str(app.Data.Meta(idx(i)).Id)])
                            continue
                        end
                        if  isfield(app.Data.MetaPrivat(idx(i)),'Thickness') && numel(mask)==numel(app.Data.MetaPrivat(idx(i)).Thickness)
                            thickness=app.Data.MetaPrivat(idx(i)).Thickness(mask);
                        else
                            thickness=app.Data.Meta(idx(i)).Thickness;
                        end
                    else
                        x=app.Data.DB(idx(i)).x_signal;
                        y=app.Data.DB(idx(i)).y_signal;
                        thickness=app.Data.Meta(idx(i)).Thickness;
                    end

                    %                     x=app.Data.DB(idx(i)).x_signal;
                    %                     y=app.Data.DB(idx(i)).y_signal;

                    %   try
                    %thickness=app.Data.Meta(idx(i)).Thickness;
                    if iscell(thickness)
                        thickness=thickness{:};
                        if isstring(thickness) ||ischar(thickness)
                            thickness=str2double(thickness);
                        end
                    end
                    thickness(thickness==0)=10000;
                    if   isempty(thickness) || isempty(y)
                        continue
                    end
                    if size(thickness,1) == size(y,2)
                        thickness=thickness';
                        try
                            y=y./(thickness./10000);
                        catch
                        end
                    elseif isempty(thickness)
                        y=y;
                    else
                        y=y./(thickness./10000);
                    end

                    if app.LinBCButton.Value==true
                        y=y(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                        x=x(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                        try
                            y=linCorrect(y);
                        catch
                            0;
                        end
                    end

                    if app.SpecsButton.Value==true
                        if size(y,2)>100
                            datapoints=1:size(y,2);
                            if numel(datapoints)>=app.ntoplotEditField.Value && not(app.ntoplotEditField.Value==0)
                                samplepoints=sort(randperm(numel(datapoints),app.ntoplotEditField.Value));
                                samplepoints=datapoints(samplepoints);
                            else
                                samplepoints=datapoints;
                            end

                            plot(app.FigXlimLive,x,y(:,samplepoints))
                        else
                            if isempty(y)
                                y=nan(size(x));
                            end
                            plot(app.FigXlimLive,x,y)
                        end
                    end

                    if app.MeanButton.Value==true
                        y_mean=mean(y,2,"omitnan");
                        plot(app.FigXlimLive,x,y_mean ,"LineWidth",2,"Color",'k')
                    end


                    if app.ResetButton.Value==true
                        xlim(app.FigXlimLive,'auto')
                        ylim(app.FigXlimLive,'auto')
                        app.EditField_xlimMin.Value=min(app.FigXlimLive.XLim);
                        app.EditField_xlimMax.Value=max(app.FigXlimLive.XLim);
                    else
                        xlim(app.FigXlimLive,sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value ]))
                        ylim(app.FigXlimLive,'auto')
                    end


                end
            end
            update_lines(app,app.FigXlimLive)

            % ok for now but move plot to plot function
        end

        % Callback function: DeleteSpectraButton, DeleteSpectraMenu
        function DeleteSpectraButtonPushed(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            [idx]=ismember([app.Data.DB.Id],selections);

            app.Data.DB(idx)=[];
            app.Data.Meta(idx)=[];

            %    Group


            indx=1:numel(app.Data.Group_names(2:end));
            for n=1:numel(indx)
                if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups) >= indx(n)+1 && not(isempty(app.Data.Groups(indx(n)+1).Members))
                    app.Data.Groups(indx(n)+1).Members(ismember(app.Data.Groups(indx(n)+1).Members,selections))=[];
                else
                    continue
                end
            end

            try
                app.Data.PlotSettings.Custom(idx)=[];
            catch
            end

            app.Data.MetaPrivat(idx)=[];
            %             empty_rows=not(cellfun(@isempty,{app.Data.Meta(:).Id}));
            %            [app.Data.DB]=app.Data(empty_rows).DB;
            %            [app.Data.MetaPrivat]=app.Data(empty_rows).MetaPrivat;
            %            [app.Data.Meta]=app.Data(empty_rows).Meta;

            app.edit_state=true;
            update_UI(app)

            % ok
        end

        % Menu selected function: EditMetadataMenu
        function EditMetadataMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            
            %ath add different input options like string, numbers and categories

            if size(app.DB_table.Selection(1,:))>1
                error('Single Column editable only')
            elseif strcmp(app.DB_table.ColumnName(app.DB_table.Selection(1,2)),'Id')
                error('Id cant be edited')
            elseif strcmp(app.DB_table.ColumnName(app.DB_table.Selection(1,2)),'n_meanSpec')
                error('n_meanSpec cant be edited')
            else

                fn=app.DB_table.ColumnName{app.DB_table.Selection(1,2)};

                newval= inputdlg(['Enter new Value for ' fn],...
                    'Enter Value', 1);
                if isempty(newval)
                    return
                elseif iscell(newval)
                    newval=newval{:};
                end

                selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

                [val ]=ismember([app.Data.Meta(:).Id],selections);
                idx=1:numel(val);
                idx=idx(val);


                for n=1:numel(idx)

                    %testvar =  app.Data.Meta(idx(n)).(char(fn));

                    if any(strcmp(char(fn),{'Thickness'}))
                        [app.Data.Meta(idx(n)).(char(fn))]=str2double(newval);
                        [app.Data.MetaPrivat(idx(n)).(char(fn))]=str2double(newval);
                    elseif any(strcmp(char(fn),{'Density','PolGroup','PolGroupStart','PolGroupEnd','PolGroupShift'}))
                        testvar=100;
                    elseif any(strcmp(char(fn),{'Active'}))
                        testvar=true;
                    else
                        testvar='test';
                    end

                    if any(strcmp(char(fn),{'Thickness'}))
                        continue
                    elseif islogical(testvar)
                        [app.Data.Meta(idx(n)).(char(fn))]={logical(newval)};
                    elseif ischar(testvar)
                        [app.Data.Meta(idx(n)).(char(fn))]=char(newval);
                    elseif isstring(testvar)
                        [app.Data.Meta(idx(n)).(char(fn))]=string(newval);
                    elseif iscell(testvar)
                        [app.Data.Meta(idx(n)).(char(fn))]={newval};
                    else
                        [app.Data.Meta(idx(n)).(char(fn))]=str2double(newval);
                    end
                end

                app.edit_state=true;
            end
            update_DB_table(app)
            figure(app.SpecDBUIFigure);


        end

        % Cell edit callback: DB_table
        function DB_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            fn=app.DB_table.ColumnName{indices(2)};
            if isempty(app.DB_table.Selection)
                return
            end
            selections = app.DB_table.Data.Id(unique(indices(:,1)));
            %             else
            %                 selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            %             end
            [val ]=ismember([app.Data.DB(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);

            % testvar =  app.Data.Meta(idx).(char(fn));
            if any(strcmp(char(fn),{'Thickness'}))
                if ischar(newData)
                    [app.Data.Meta(idx).Thickness]=str2double(newData);
                    [app.Data.MetaPrivat(idx).Thickness]=str2double(newData);
                else
                    [app.Data.Meta(idx).(char(fn))]=newData;
                    [app.Data.MetaPrivat(idx).(char(fn))]=newData;
                end
                update_DB_table(app)
                return
            elseif any(strcmp(char(fn),{'Density','PolGroup','PolGroupStart','PolGroupEnd','PolGroupShift'}))
                testvar=100;
            elseif any(strcmp(char(fn),{'Active'}))
                testvar=true;
            else
                testvar='test';
            end

            if islogical(testvar)
                for n=1:numel(idx)
                    [app.Data.Meta(idx(n)).(char(fn))]=newData;
                end
                update_UI(app)
            elseif ischar(testvar)
                [app.Data.Meta(idx).(char(fn))]=char(newData);
            elseif isstring(testvar)
                [app.Data.Meta(idx).(char(fn))]=string(newData);
            elseif iscell(testvar)
                [app.Data.Meta(idx).(char(fn))]={newData};

            else
                if ischar(newData)
                    [app.Data.Meta(idx).(char(fn))]=str2double(newData);
                else
                    [app.Data.Meta(idx).(char(fn))]=newData;
                end
            end
            app.edit_state=true;


        end

        % Button pushed function: DeleteEntryButton
        function DeleteEntryButtonPushed(app, event)
            selection=unique(app.UITable.Selection(:,1));
            app.UITable.Data(selection,:)=[];
            app.Data.OHCalcResults(1).OHCalcResults_Table(selection,:)=[];

            app.Data.OHCalcResults(1).OHCalcResults_x(selection)=[];
            app.Data.OHCalcResults(1).OHCalcResults_y(selection)=[];
            app.Data.OHCalcResults(1).OHCalcResult_int_range(selection)=[];
            app.Data.OHCalcResults(1).OHCalcResult_spectra(selection)=[];

            if size(app.UITable.Data,1)==0
                cla(app.FigH2O_calc_specs)
                app.FigH2O_calc_specs.Visible=false;
                app.PlotSpecOHCalc_Button.Visible=false;
            end
        end

        % Button pushed function: ClearResultsButton_2
        function ClearResultsButton_2Pushed(app, event)
            app.UITable.Data=[];
            app.Data.OHCalcResults(1).OHCalcResults_Table=table();

            app.Data.OHCalcResults(1).OHCalcResults_x=[];
            app.Data.OHCalcResults(1).OHCalcResults_y=[];
            app.Data.OHCalcResults(1).OHCalcResult_int_range=[];
            app.Data.OHCalcResults(1).OHCalcResult_spectra=[];

            cla(app.FigH2O_calc_specs)
            app.FigH2O_calc_specs.Visible=false;
            app.PlotSpecOHCalc_Button.Visible=false;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % read the variables
            mineral=app.MineralName_EditField.Value;
            density=app.DensityEditField.Value;

            if app.DropDown.Value==2
                abs_val=app.AbsAlphaEditField.Value;
                app.AbsBetaEditField.Value=abs_val;

                t_val=app.ThicknessAlphamicronEditField.Value;
                app.ThicknessBetamicronEditField.Value=t_val;
            elseif app.DropDown.Value==1
                abs_val=app.AbsAlphaEditField.Value;
                app.AbsBetaEditField.Value=abs_val;
                app.AbsGammaEditField.Value=abs_val;

                t_val=app.ThicknessAlphamicronEditField.Value;
                app.ThicknessBetamicronEditField.Value=t_val;
                app.ThicknessGammamicronEditField.Value=t_val;
            end


            I=sscanf(replace(app.CustomIEditField.Value,{',' ';'},' '),'%f',[1,Inf]);
            Int_range_max=app.Int_range_max.Value;
            Int_range_min=app.Int_range_min.Value;
            int_range=[Int_range_min Int_range_max];

            thickness(1)=app.ThicknessAlphamicronEditField.Value;
            thickness(2)=app.ThicknessBetamicronEditField.Value;
            thickness(3)=app.ThicknessGammamicronEditField.Value;

            intensity=[app.AbsAlphaEditField.Value app.AbsBetaEditField.Value app.AbsGammaEditField.Value];
            sample=app.SampleNameEditField.Value;
                [~,OH_table]=OH_calc_function_NG(intensity,'mineral',mineral,'custom_I',I,...
                    'density',density,'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample);
           
            if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                app.Data.OHCalcResults(1).OHCalcResults_Table=table();
            end
            thx=app.Data.OHCalcResults(1).OHCalcResults_Table;

            thx=tblvertcat(app,thx,OH_table);
            format('shortG')
            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;
            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};


        end

        % Menu selected function: CalculateEverythingMenu
        function CalculateEverythingMenuSelected(app, event)
            % read the variables
            if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                app.Data.OHCalcResults(1).OHCalcResults_Table=table();
            end
            thx=app.Data.OHCalcResults(1).OHCalcResults_Table;

  
            int_range=sort([app.Int_range_min.Value app.Int_range_max.Value ]);

            % get the xy Data
            I=sscanf(replace(app.CustomIEditField.Value,{',' ';'},' '),'%f',[1,Inf]);
            [~, ~, ~, ~,Id, eID_] = get_specs(app,app.DB_table.Data.Id,true);
            [unique_samples, ~]=unique({app.Data.Meta(eID_).SampleName},'stable');

            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Calculating H2O',...
                'Message','Automatic H2O Calculation Started','Cancelable','on');
            drawnow

            for m=1:numel(unique_samples)

                if d.CancelRequested
                    break
                end
                d.Value = m/numel(unique_samples);
                try

                    [val ]=ismember({app.Data.Meta(eID_).SampleName},unique_samples(m));
                    %idx=app.DB_table.Data.Id(val);

                    %idx=[app.Data.Meta(val).Id];
                    idx=app.DB_table.Data.Id(val);
                    [x, y, SampleName, Thickness,Id, eID] = get_specs(app,idx,true);
                    d.Message = sprintf(['Calculating Sample: ' unique_samples{m}]);

                    try
                        sample=unique_samples(m);
                    catch
                        sample='unknown';
                    end
                    try
                        mineral=app.Data.Meta(eID).Mineral;
                    catch
                        mineral='unknown';
                    end

                    min_wn=min(cellfun(@min,x));
                    max_wn=max(cellfun(@max,x));
                    delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
                    if isnan(delta_wn)
                        delta_wn=cellfun(@diff,(x),'UniformOutput',false);
                        delta_wn=[delta_wn{:}];
                        delta_wn=delta_wn(isnumeric(delta_wn));
                        delta_wn=mean(delta_wn);
                    end
                    wavenumber=(max_wn:-delta_wn:min_wn)';

                    intensity=nan(size(wavenumber,1),numel(idx));

                    for n=1:numel(idx)
                        if isempty(y{n})
                            continue
                        end
                        if Thickness{n}==0
                            %                       str_thx=sprintf('%.0f,' , idx(n));
                            %                         message=['Error No Thickness Set for: ' str_thx(1:end-1)]
                            %                         uialert(app.SpecDBUIFigure,message,'Error');
                            %                         return
                            continue
                        end
                        if isequal(wavenumber,x{n})
                            intensity(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');
                        else
                            y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,wavenumber);
                            y_resampled(not(wavenumber>min(x{n})&wavenumber<max(x{n})))=nan;
                            intensity(:,n)=mean(y_resampled,2,'omitnan');
                        end
                    end

                    intensity(intensity==inf)=[];%0
                    intensity(isnan(intensity))=[];%0
                    try
                        density=mean([app.Data.Meta(eID).Density]);
                    catch
                        density=0;
                    end

                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',10000,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                    thx=tblvertcat(app,thx,OH_table);
                    app.Data.OHCalcResults(1).OHCalcResults_Table=thx;

                    pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);

                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density,'thickness',10000,'int_range',int_range,'lincor',true,'full_report',3,'sample',sample,'custom_I',I);

                    app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
                    app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
                    app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
                    app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;
                catch ME
                    disp(['Error calculating Id-' num2str(Id(n)) ' ' SampleName{n} ' :'])
                    disp(ME.message)
                end


                OH_table=CalculateH2O(app,{wavenumber(wavenumber>=app.Int_range_min.Value & wavenumber<=app.Int_range_max.Value,:)},{intensity(wavenumber>=app.Int_range_min.Value & wavenumber<=app.Int_range_max.Value,:)},eID,sample,[],false);

                thx=tblvertcat(app,thx,OH_table.results_table_1);
                app.Data.OHCalcResults(1).OHCalcResults_Table=thx;
                pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);
                app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
                app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
                app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
                app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;

            end


            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;
            format('shortG')
            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};

        end

        % Menu selected function: CalculateSelectedMenu
        function CalculateSelectedMenuSelected(app, event)
            % read the variables
            if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                app.Data.OHCalcResults(1).OHCalcResults_Table=table();
            end
            thx=app.Data.OHCalcResults(1).OHCalcResults_Table;
            I=sscanf(replace(app.CustomIEditField.Value,{',' ';'},' '),'%f',[1,Inf]);
            %density_input=app.DensityEditField.Value;
     
            int_range=[app.Int_range_min.Value app.Int_range_max.Value];

            %selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            if iscell(app.DB_table.Data.Active)
                selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
            else
                selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
            end
            [val ]=ismember([app.Data.Meta(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);

            y_signal=cell(1,numel(idx));
            x_signal=cell(1,numel(idx));
            thickness=zeros(1,numel(idx));
            density=zeros(1,numel(idx));

            %      [x, y, ~, Thickness,Id, eID] = get_specs(app,idx,true);

            for n=1:numel(idx)

                y_signal{n}=app.Data.DB(idx(n)).y_signal;
                x_signal{n}=app.Data.DB(idx(n)).x_signal;

                try
                    if numel(app.Data.MetaPrivat(idx(n)).Thickness)>1

                        y_signal{n}=(y_signal{n}./app.Data.MetaPrivat(idx(n)).Thickness').*10000;
                        thickness(n)=10000;

                    else
                        thickness(n)=app.Data.MetaPrivat(idx(n)).Thickness;
                        if thickness(n)==0
                            thickness(n)=10000;
                        end
                    end
                catch
                    thickness(n)=inf;
                end
                try
                    density(n)=app.Data.Meta(idx(n)).Density;
                catch
                    density(n)=0;
                end
                try
                    sample=app.Data.Meta(idx(n)).Sample;
                catch
                    sample='unknown';
                end
                try
                    mineral=app.Data.Meta(idx(n)).Mineral;
                catch
                    mineral='unknown';

                end
            end
            intensity=zeros(max(cellfun(@max,(cellfun(@size,y_signal,'UniformOutput',false)))),numel(idx));
            wavenumber=zeros(max(cellfun(@max,(cellfun(@size,x_signal,'UniformOutput',false)))),numel(idx));


            for n=1:numel(idx)

                mean_data=mean(y_signal{n},2,"omitnan");
                intensity(1:numel(mean_data),n)=mean_data;

                wavenumber(1:length(x_signal{n}),n)=x_signal{n};
            end
            intensity(intensity==inf)=0;
            intensity(isnan(intensity))=0;

      
            try
                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                    app.Data.OHCalcResults(1).OHCalcResults_Table=OH_table;

                else
                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',2,'sample',sample,'custom_I',I);
                    disp(OH_table)

                    [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',1,'sample',sample,'custom_I',I);
                    thx=tblvertcat(app,thx,OH_table);
                end

                [~,OH_table]=OH_calc_function_NG(intensity,'wavenumber',wavenumber,'mineral',mineral,'density',density(n),'thickness',thickness,'int_range',int_range,'lincor',true,'full_report',3,'sample',sample,'custom_I',I);

            catch ME
                disp(['Error - ' sample])
                disp(ME.message)
            end


            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;

            pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);

            app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
            app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
            app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
            app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;

            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};


        end

        % Callback function: SaveDBProjectMenu, SaveDBProject_Button
        function SaveDBProjectMenuSelected(app, event)
            if isfield(app.Data,'projectpath') && not(isempty(app.Data.projectpath))
                app.SpecDBUIFigure.Name = [app.Data.projectpath ' [... Saving Project]'] ;

                data2save=app.Data;
                save(app.Data.projectpath, 'data2save', '-v7.3');
                app.SpecDBUIFigure.Name = app.Data.projectpath;

            else
                SaveDBProjectAsMenuSelected(app)
            end
            if isfield(app.Data,'projectpath')
                app.SpecDBUIFigure.Name = app.Data.projectpath;
                app.edit_state=false;
            end

        end

        % Callback function: SaveDBProjectAsMenu, SaveDBProjectAs_Button
        function SaveDBProjectAsMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            delete(f);
            figure(app.SpecDBUIFigure);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);
                app.Data.projectpath=FileName;
                app.SpecDBUIFigure.Name = [app.Data.projectpath ' [... Saving Project]'] ;

                data2save=app.Data;
                save(FileName, 'data2save', '-v7.3');
                app.SpecDBUIFigure.Name = app.Data.projectpath;
            end
            app.edit_state=false;
        end

        % Callback function: LoadDBProjectMenu, LoadProject_Button
        function LoadDBProjectMenuSelected(app, event)
            if app.edit_state==true
                if isfield(app.Data,'projectpath') && not(isempty(app.Data.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.Data.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end
                answer = questdlg({['Do you want to save your project' projectname '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveDBProjectMenuSelected(app)

                    case 'Cancel'
                        return
                end

            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            delete(f);
            figure(app.SpecDBUIFigure);

            if not(isequal(file,0))
                thx=load(fullfile(path,file));
                app.Data=thx.data2save;
            else
                return
            end
            update_PeakPlotSet_fun(app)

            app.Data.projectpath=fullfile(path,file);
            app.SpecDBUIFigure.Name = app.Data.projectpath;
version_update_fun(app)
            app.active_Group=[];
            try
                update_UI(app)
            catch
                disp('Update UI after load project failed')
            end
            try

                if isfield(app.Data,'OHCalcResults') && istable(app.Data.OHCalcResults(1).OHCalcResults_Table)
                    format('shortG')

                    app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
                    app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
                    app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
                    app.UITable.RowName = {};
                end

            catch
                disp('OH table update failed')
            end
        end

        % Callback function: SearchEditField, SearchEditField
        function SearchEditFieldValueChanged(app, event)
            update_DB_table(app)
        end

        % Callback function: SimplePlotMenu, SimplePlot_Button
        function SimplePlotMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            %selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);

            if iscell(app.DB_table.Data.Active)
                selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
            else
                selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
            end

            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);


            %   screensize = get( groot, 'Screensize' );
            X = 42.0;                  %# A3 paper size
            Y = 29.7;                  %# A3 paper size
            xMargin = 1;               %# left/right margins from page borders
            yMargin = 1;               %# bottom/top margins from page borders

            xSize = X - 2*xMargin;     %# figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     %# figure size on paper (widht & hieght)

            %# create figure/axis
            hFig=figure('visible', 'on');
            hold on

            %# figure size displayed on screen (50% scaled, but same aspect ratio)
            %# figure size printed on paper
            set(hFig, 'PaperUnits','centimeters')
            set(hFig, 'PaperSize',[X Y])
            set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig, 'PaperOrientation','portrait')
            fig_pos = get( groot, 'Screensize' );

            fig_pos(3:4)=fig_pos(3:4)*0.5;
            hFig.Position=fig_pos;

            grid on
            if strcmp(app.XDirSwitch.Value,'Reverse')
                set (gca, 'xdir', 'reverse')
            end
            %   set(gcf,'position',screensize)

            xlabel('Wavenumber [cm^{-1}]')
            ylabel('Absorbance [cm^{-1}]')

            xlim(sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]))


            for n=1:numel(x)
                if cell2mat(Thickness(n))==0
                    Thickness(n)={10000};
                end


                plot(cell2mat(x(n)),mean(cell2mat(y(n))./(cell2mat(Thickness(n))./10000),2,"omitnan"),LineWidth=2);
            end

            legend(join([string(Id) SampleName]),'Location','eastoutside',Interpreter='none')


        end

        % Callback function: StackedPlotMenu, StackedPlot_Button
        function StackedPlotMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            if iscell(app.DB_table.Data.Active)
                selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
            else
                selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
            end
            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);
            %   screensize = get( groot, 'Screensize' );
            X = 42.0;                  %# A3 paper size
            Y = 29.7;                  %# A3 paper size
            xMargin = 1;               %# left/right margins from page borders
            yMargin = 1;               %# bottom/top margins from page borders

            xSize = X - 2*xMargin;     %# figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     %# figure size on paper (widht & hieght)

            %# create figure/axis
            hFig=figure('visible', 'on');
            hold on

            %# figure size displayed on screen (50% scaled, but same aspect ratio)
            %# figure size printed on paper
            set(hFig, 'PaperUnits','centimeters')
            set(hFig, 'PaperSize',[X Y])
            set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig, 'PaperOrientation','portrait')

            fig_pos = get( groot, 'Screensize' );
            fig_pos(3:4)=fig_pos(3:4)*0.5;
            hFig.Position=fig_pos;

            grid on
            if strcmp(app.XDirSwitch.Value,'Reverse')
                set (gca, 'xdir', 'reverse')
            end            %   set(gcf,'position',screensize)

             xlabel('Wavenumber [cm^{-1}]')
            ylabel('Absorbance [cm^{-1}]')

           x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            xlim(x_limits)


            offset=zeros(1,numel(x));
            for n=1:numel(x)
                if cell2mat(Thickness(n))==0
                    Thickness(n)={10000};
                end
                y1=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./10000)),2,"omitnan");
                x1=cell2mat(x(n));
                y1=y1(x1>min(x_limits) & x1 < max(x_limits));
                offset(n)=max(y1,[],"omitnan");
            end
            offset=[0 cumsum(offset)];
            offset=offset*1.05;

            max_val_prev=0;

            for n=1:numel(x)
                y1=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./10000)),2,"omitnan")+offset(n);
                x1=cell2mat(x(n));

                plt(n)=plot(cell2mat(x(n)),y1,LineWidth=2);
                y1=y1(x1>min(x_limits) & x1 < max(x_limits));
                max_val_prev=max(mean(y1,"omitnan")+max_val_prev+offset(n));

            end

            legend(flip(plt),join([string(flip(Id)) flip(SampleName)]),'Location','eastoutside',Interpreter='none');


        end

        % Callback function: x3DPlotMenu, x3DPlot_Button
        function DPlotMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end

            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            if iscell(app.DB_table.Data.Active)
                selections = selections([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1),'stable')}]==true);
            else
                selections = selections(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1),'stable'))==true);
            end

            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);

            %   screensize = get( groot, 'Screensize' );
            X = 42.0;                  %# A3 paper size
            Y = 29.7;                  %# A3 paper size
            xMargin = 1;               %# left/right margins from page borders
            yMargin = 1;               %# bottom/top margins from page borders

            xSize = X - 2*xMargin;     %# figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     %# figure size on paper (widht & hieght)

            %# create figure/axis
            hFig=figure('visible', 'on');
            hold on

            %# figure size displayed on screen (50% scaled, but same aspect ratio)
            %# figure size printed on paper
            set(hFig, 'PaperUnits','centimeters')
            set(hFig, 'PaperSize',[X Y])
            set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig, 'PaperOrientation','portrait')

            fig_pos = get( groot, 'Screensize' );
            fig_pos(3:4)=fig_pos(3:4)*0.5;
            hFig.Position=fig_pos;

            grid on
            if strcmp(app.XDirSwitch.Value,'Reverse')
                set (gca, 'xdir', 'reverse')
            end
            %   set(gcf,'position',screensize)

            xlabel('Wavenumber [cm^{-1}]')
            zlabel('Absorbance [cm^{-1}]')

           view(-10,10)
            rotate3d on
            xlim(sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]))


            legend_str=cell(1,numel(x));
            for n=1:numel(x)
                if not(isempty(x(n)))
                    if cell2mat(Thickness(n))==0
                        Thickness(n)={10000};
                    end
                    if numel(y)==1
                        for m=1: size(y{n},2)
                            plot3(cell2mat(x(n)),ones(size(cell2mat(x(n)))).*m,y{n}(:,m)./(cell2mat(Thickness(n))./10000),LineWidth=2);
                        end
                    else
                        plot3(cell2mat(x(n)),ones(size(cell2mat(x(n)))).*n,mean(cell2mat(y(n))./(cell2mat(Thickness(n))./10000),2,"omitnan"),LineWidth=2);

                    end
                    if isempty(SampleName(n))
                        legend_str{n}=[num2str(Id(n)) ' Unknown'];
                    else
                        legend_str{n}=[num2str(Id(n)) ' ' char(SampleName{n})];
                    end
                end
            end

            legend(legend_str,Interpreter='none')

        end

        % Menu selected function: AlphaMenu
        function AlphaMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end

            int_val=calc_mean_int_B(app);
            app.AbsAlphaEditField.Value=mean(int_val,'omitnan');
            app.ThicknessAlphamicronEditField.Value=10000;
        end

        % Menu selected function: BetaMenu
        function BetaMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            int_val=calc_mean_int_B(app);

            app.AbsBetaEditField.Value=mean(int_val,'omitnan');
            app.ThicknessBetamicronEditField.Value=10000;

        end

        % Menu selected function: GammaMenu
        function GammaMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            int_val=calc_mean_int_B(app);

            app.AbsGammaEditField.Value=mean(int_val,'omitnan');
            app.ThicknessGammamicronEditField.Value=10000;

        end

        % Button pushed function: AddColumn
        function AddColumnPushed(app, event)
            new_name=inputdlg('Enter New Column Name','New_Col', 1);
            fns=app.DB_table.ColumnName;
            if any(ismember(new_name,fns))
                message='Error Invalid Name';
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end


            if not(isempty(new_name))
                new_name = matlab.lang.makeValidName(new_name);
                [app.Data(1).Meta(1).(char(new_name{1}))] = '';
                app.edit_state=true;
                update_UI(app)
            end
        end

        % Button pushed function: DeleteColumn
        function DeleteColumnPushed(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No Column selected!','Please select at least one Column'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections=(unique(app.DB_table.Selection(:,2)));
            fns=app.DB_table.ColumnName;

            not_selected_fns=fns(not(ismember(fns,fns(selections))));
            not2delete=[{'Id','SampleName','Mineral','Density','Thickness','Comment','Sample','n_meanSpec'},not_selected_fns'];
            fns=fns(not(ismember(fns,not2delete)));

            if not(isempty(selections))
                for n=1:numel(fns)
                    app.Data.Meta=rmfield(app.Data.Meta,(fns{n}));
                end
                app.DB_table.Selection=[];
                update_UI(app)
            end
        end

        % Menu selected function: DuplicateSpectraMenu
        function DuplicateSpectraMenu_2Selected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            % douplicate entries
            selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));
            %[x, y, ~, ~] = get_specs(app,selections);
            [x, y, ~, ~,Id, eID] = get_specs(app,selections);


            for n=1:numel(selections)
                data.Meta=app.Data.Meta(eID(n));
                data.Meta.Duplicate_of=selections(n);


                data.MetaPrivat=app.Data.MetaPrivat(eID(n));
                fns=fieldnames(data.MetaPrivat);
                for h=1:numel(fns)
                    data.Meta.(char(fns(h)))=app.Data.MetaPrivat(eID(n)).(char(fns(h)));
                end

                data.DB.x_signal=x{n};
                data.DB.y_signal=y{n};
                insert_data(app,data,false)
            end
            update_DB_table(app)
        end

        % Menu selected function: ExtractSpectraMenu
        function ExtractSpecsMenuSelected(app, event)
            ansqest=0;
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            % split in individual entries
            %ath possible to also add split in Classes with classification module

            possible2split=app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>1);

            selections=app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));%seleced ids
            selections=possible2split(ismember(possible2split,selections));


            for n=1:numel(selections)
                data=struct();
                data.Meta=app.Data.Meta(ismember([app.Data.Meta(:).Id],selections(n)));
                [x, y, ~, ~,Id, eID] = get_specs(app,selections(n));

                if ansqest==0
                    if size(y{1},2)>100
                        answer = questdlg({['The selected Entry contains ' num2str(size(y{1},2)) ' Spectra.'],'Are you shure you want to Split all entries?'},...
                            '','Yes Split All!','No Skip It','Cancel','');
                        switch answer
                            case 'Yes Split All!'
                                disp('Splitting Entry')
                                ansqest=1;
                            case 'Cancel'
                                return
                            case 'No Skip It'
                                continue
                        end
                    end

                end

                for m=1:size(y{1},2)
                    data.Meta=app.Data.Meta(eID);
                    data.MetaPrivat=app.Data.MetaPrivat(eID);
                    fns=fieldnames(data.MetaPrivat);
                    for h=1:numel(fns)
                        data.Meta.(char(fns(h)))=app.Data.MetaPrivat(eID).(char(fns(h)));
                    end

                    data.Meta.SfplitIndex=m;
                    data.Meta.n_meanSpec=1;
                    data.Meta.n_Groups=1;
                    data.DB.x_signal=x{1};
                    data.DB.y_signal=y{1}(:,m);

                    if numel(app.Data.MetaPrivat(eID).Mask)==size(y{1},2)
                        data.Meta.Mask=app.Data.MetaPrivat(eID).Mask(m);
                    else
                        data.Meta.Mask=true;
                    end

                    try
                        meta_T=app.Data.MetaPrivat(eID).MetaTable(app.Data.MetaPrivat(eID).MetaTable.Fileindex>0,:);
                        if size(meta_T,1) == size(y{1},2)
                                data.Meta.MetaTable=meta_T(m,:);
                            end
                    catch ME
                        disp(ME)
                        
                        disp('Error in Split Specs')
                    end
                    %                                         if isfield(app.Data.Meta,'n_Images')
                    %                                             data.Meta.n_Images=app.Data.Meta(ismember(app.DB_table.Data.Id,selections(n))).n_Images;
                    %                                         end
                    %                     try
                    %                        data.Meta.Image_Id=app.Data.MetaPrivat(ismember(app.DB_table.Data.Id,selections(n))).Image_Id;%
                    %                     catch
                    %                     end
                    insert_data(app,data,false)
                end
            end
            update_DB_table(app)
        end

        % Menu selected function: MergeSpectraMenu
        function CombineSpecsMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            % split in individual entries
            %selections_Id=app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            selections_Id = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));
            if iscell(app.DB_table.Data.Active)
                selections_Id = selections_Id([app.DB_table.Data.Active{unique(app.DB_table.Selection(:,1))}]==true);
            else
                selections_Id = selections_Id(app.DB_table.Data.Active(unique(app.DB_table.Selection(:,1)))==true);
            end
            selections_entry=find(ismember([app.Data.Meta(:).Id],selections_Id));%seleced entry field number

            % [x, y, ~, Thickness] = get_specs(app,selections);
            [x, y, ~, Thickness] = get_specs(app,selections_Id,true);

            n_entries=sum(app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections_Id)));
            min_wn=min(cellfun(@min,x));
            max_wn=max(cellfun(@max,x));
            delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
            x_new=(max_wn:-delta_wn:min_wn)';
            in_new=nan(size(x_new,1),n_entries);
            thx=0;
            for n=1:numel(selections_Id)
                if isempty(y{n})
                    continue
                end
                if Thickness{n}==0
                    str_thx=sprintf('%.0f,' , selections_Id(n));
                    message=['Error No Thickness Set for: ' str_thx(1:end-1)];
                    uialert(app.SpecDBUIFigure,message,'Error');
                    return
                end
                try
                    thx=thx(end)+1:thx(end)+size(y{n},2);
                    %                     if numel(app.Data.MetaPrivat(selections_Id(n)).Mask)>1
                    %                    thx=thx(end)+1:thx(end)+sum(app.Data.MetaPrivat(selections_Id(n)).Mask);
                    %                     else
                    %                 thx=thx(end)+1:thx(end)+app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections_Id(n)));
                    %                     end
                catch
                    thx=thx(end)+1:thx(end)+app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections_Id(n)));
                end
                if isequal(x_new,x{n})
                    in_new(:,thx)=(y{n}./Thickness{n}).*10000;
                else
                    y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_new);
                    y_resampled(not(x_new>min(x{n})&x_new<max(x{n})))=nan;
                    in_new(:,thx)=y_resampled;
                end
                try
                    meta_tbl=app.Data.MetaPrivat(selections_entry(n)).MetaTable;
                    if not(isempty(meta_tbl))
                        meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);

                        if isfield(app.Data.MetaPrivat(selections_entry(n)),'Mask') && not(isempty(app.Data.MetaPrivat(selections_entry(n)).Mask)) && numel(app.Data.MetaPrivat(selections_entry(n)).Mask) == size(app.Data.DB(selections_entry(n)).y_signal,2)
                            mask=app.Data.MetaPrivat(selections_entry(n)).Mask;
                        else
                            %mask=true(size(app.Data.MetaPrivat(selections_entry(n)).MetaTable(app.Data.MetaPrivat(selections_entry(n)).MetaTable.Fileindex>0),1),1);
                            mask=true(size(meta_tbl,1),1);
                            app.Data.MetaPrivat(selections_entry(n)).Mask=mask;
                        end
                        if n==1
                            meta_T=meta_tbl(mask,:);
                        else
                            meta_T=[meta_T; meta_tbl(mask,:)]; %#ok
                        end
                    else
                        meta_T=[];

                    end
                catch ME
                    meta_T=[];
                    disp(['Error in metacombination ' num2str(selections_Id(n))])
                    disp(ME.message)
                end

            end
            data.Meta.MetaTable=meta_T;
            % data_old=app.Data.Meta(ismember(app.DB_table.Data.Id,selections_Id));
            data_old=app.Data.Meta(selections_entry);
            data.Meta.SampleName='';
            data.Meta.Mineral='';
            data.Meta.Comment='';
            data.Meta.SampleName='';

            fns=fieldnames(data_old);
            fields2check.Id=[];
            %UI_Edit_Meta_state=false;

            for n=1:numel(fieldnames(data_old))

                try
                    unique_test=unique({data_old.(char(fns(n)))});
                catch
                    unique_test=unique([data_old.(char(fns(n)))]);
                end

                if  numel(unique_test)==1% &not(isempty(unique_test))
                    data.Meta.(char(fns(n)))=data_old.(char(fns(n)));
                elseif isempty(unique_test)
                    data.Meta.(char(fns(n)))='';
                else
                    %UI_Edit_Meta_state=true;
                    fields2check.Id(end+1)=n;
                    fields2check.name(numel(fields2check.Id))={char(fns(n))};
                    fields2check.Values(numel(fields2check.Id))={unique_test};
                    if not(isnumeric(unique_test(1)))
                        data.Meta.(char(fns(n)))='';
                    end
                end
            end


            data.Meta.Id=1;
            data.Meta.n_meanSpec=size(in_new,2);

            data.Meta.Thickness=10000;
            data.MetaPrivat.Thickness=10000;
            str_thx=sprintf('%.0f,' , selections_Id);

            data.Meta.Comment=['Combined Spectra of ' str_thx(1:end-1)];
            data.DB.x_signal=x_new;
            data.DB.y_signal=in_new;

            answer = questdlg({'Do you want to keep the original entries ',''},...
                '','Yes Keep Them!','No Delete Them!','');
            switch answer
                case 'Yes Split It!'
                    disp('Splitting Entry')
                case 'Yes Keep Them!'

                case 'No Delete Them!'
                    [idx]=ismember([app.Data.DB.Id],selections_Id);
                    app.Data.DB(idx)=[];
                    app.Data.Meta(idx)=[];

                    app.Data.MetaPrivat(idx)=[];
            end
            insert_data(app,data,true)
        end

        % Menu selected function: MulticolumnMeanspecsMenu
        function MulticolumnMeanspecsMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selected2clipbord_DB(app,'multicol mean',false,0,0);
        end

        % Menu selected function: MulticolumnAllMenu
        function MulticolumnAllMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selected2clipbord_DB(app,'multicol all',false,0,0);
        end

        % Menu selected function: SingleColumnMenu
        function SingleColumnMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selected2clipbord_DB(app,'single col',false,0,0);
        end

        % Menu selected function: MetadataMenu
        function MetadataMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end

            data=cellfun(@string,table2cell(app.DB_table.Data(unique(app.DB_table.Selection(:,1)),:)),'UniformOutput',false);
            data_new=cell(size(data,1),(size(data,2)*2)+1);

            [data_new{:,1:2:end-1}]=data{:,1:end};
            [data_new(:,2:2:end)]={char(9)};
            [data_new(:,end)]={newline};
            data_new=cellfun(@string,data_new,'UniformOutput',false);


            columnNames=app.DB_table.ColumnName;
            columnNames_new=cell((size(columnNames,1)*2)+1,1);
            [columnNames_new{1:2:end-1}]=columnNames{1:end};
            [columnNames_new(2:2:end)]={char(9)};
            [columnNames_new(end)]={newline};
            columnNames_new=cellfun(@string,columnNames_new,'UniformOutput',false);

            str = sprintf('%s', columnNames_new{:});
            try
                for n=1:size(data_new)
                    thx=[data_new{n,:}];
                    thx(ismissing([data_new{n,:}]))="";
                    str = sprintf('%s',str,char(thx));
                end
            catch

            end
            str=replace(str,'                                     ','');

            clipboard('copy',str);

        end

        % Menu selected function: ExportSelectedSpectracsvMenu
        function ExportSelectedSpectracsvMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);

            if isequal(path,0)
                return
            end

            selections=app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Exporting...',...
                'Message','Export Started','Cancelable','on');

            for m=1:numel(selections)
                SampleId=selections(m);


                if d.CancelRequested
                    break
                end

                [x, y, SampleName, Thickness, Id, eID]  = get_specs(app,SampleId,true);

                d.Value = m/numel(selections);
                d.Message = sprintf(['Exporting: ' SampleName{1}]);


                x_signal= x{1};

                if Thickness{1}==0
                    y_signal= y{1};
                else
                    y_signal=(y{1}./Thickness{1}).*10000;
                end

                % str=replace(str,char(9),',');
                writematrix([x_signal y_signal],fullfile(path,[num2str(Id) ' ' SampleName{1} '.csv']));
            end
        end

        % Menu selected function: ExportProjectcsvMenu_2
        function ExportProjectcsvMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure);

            if isequal(path,0)
                return
            end
            if isfield(app.Data,'projectpath') && not(isempty(app.Data.projectpath))
                [~,filename,~] = fileparts(app.Data.projectpath);
            else
                filename=inputdlg('Enter Filename','FileExport', 1);
                filename=char(filename{:});
                if isempty(filename)
                    filename='';
                end
            end

            selections = app.DB_table.Data.Id;

            [x, ~, ~, ~] = get_specs(app,selections);

            min_wn=min(cellfun(@min,x));
            max_wn=max(cellfun(@max,x));

            delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
            definput = {num2str(delta_wn),num2str(min_wn),num2str(max_wn)};
            x = inputdlg({'Wavenumber Resolution','Min Wavenumber','Max Wavenumber'}, 'Export Settings',[1 15; 1 15; 1 15],definput);
            if isempty(x)
                return
            end

            delta_wn=str2double(x(1));
            range=sort([str2double(x(2)) str2double(x(3))]);


            str=selected2clipbord_DB(app,'multicol mean',true,delta_wn,range);
            if startsWith('Error No Thickness Set for: ',str)
                message=str;
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end

            % str=replace(str,char(9),',');
            writematrix(str,fullfile(path,[filename '_multicol_mean.csv']));

            T=readtable(fullfile(path,[filename '_multicol_mean.csv']));
            writetable(T,fullfile(path,[filename '_multicol_mean.csv']));
            % %             str= selected2clipbord_DB(app,'multicol all',true,delta_wn,range);
            % %             %str=replace(str,char(9),',');
            % %             writematrix(str,fullfile(path,[filename '_multicol_all.csv']));
            % %
            % %             str=selected2clipbord_DB(app,'single col',true,delta_wn,range);
            % %            % str=replace(str,char(9),',');
            % %             writematrix(str,fullfile(path,[filename '_single_col.csv']));

            data=cellfun(@string,table2cell(app.DB_table.Data),'UniformOutput',false);
            data_new=cell(size(data,1),(size(data,2)*2)+1);

            [data_new{:,1:2:end-1}]=data{:,1:end};
            [data_new(:,2:2:end)]={char(9)};
            [data_new(:,end)]={newline};
            data_new=cellfun(@string,data_new,'UniformOutput',false);


            columnNames=app.DB_table.ColumnName;
            columnNames_new=cell((size(columnNames,1)*2)+1,1);
            [columnNames_new{1:2:end-1}]=columnNames{1:end};
            [columnNames_new(2:2:end)]={char(9)};
            [columnNames_new(end)]={newline};
            columnNames_new=cellfun(@string,columnNames_new,'UniformOutput',false)';

            str = sprintf('%s',join([columnNames_new{1:2:end}],'","'));

            for n=1:size(data_new)
                %  str = [sprintf('%s',str,char([data_new{n,:}]))];
                try
                    str_in=[data_new{n,1:2:end}];
                    str_in(ismissing(str_in))='';
                    str = sprintf('%s',str,join(str_in,'","'));
                catch ME

                    disp(['Export Error ' num2str(n)])
                    disp(ME.message)
                end
            end

            T=app.DB_table.Data;
            writetable(T,fullfile(path,[filename '_metadata.csv']));


        end

        % Callback function: CalculateAbs_Button, CalculateAbscmMenu, 
        % CalculateAbscmMenu_2
        function CalculateAbscmMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            int_range=[app.Int_range_max.Value app.Int_range_min.Value];
            xlimits=[app.EditField_xlimMax.Value app.EditField_xlimMin.Value];

            selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));
            [x, y, ~, Thickness] = get_specs(app,[],true);
            min_wn=min(cellfun(@min,x));
            max_wn=max(cellfun(@max,x));
            delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
            x_signal=(max_wn:-delta_wn:min_wn)';
            y_signal=nan(size(x_signal,1),numel(selections));

            for n=1:numel(selections)
                if isempty(y{n})
                    continue
                end

                if Thickness{n}==0
                    str_thx=sprintf('%.0f,' , selections(n));
                    message=['Error No Thickness Set for: ' str_thx(1:end-1)];
                    uialert(app.SpecDBUIFigure,message,'Error');
                    return
                end
                if isequal(x_signal,x{n})
                    y_signal(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');
                else

                    y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_signal);
                    y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;
                    y_signal(:,n)=mean(y_resampled,2,'omitnan');
                end
            end
            IntegrationSetting(app, x_signal, y_signal, xlimits, int_range)
        end

        % Value changed function: MineralDropDown
        function MineralDropDownValueChanged(app, event)
            value = app.MineralDropDown.Value;
            app.MineralName_EditField.Value=value;
            switch value
                case 'Other'
                    v_state=true;
                otherwise
                    v_state=false;
            end

            app.DropDown.Enable=false;
            app.MineralName_EditField.Enable=v_state;

            switch value
                case {'CPX','OPX','Olivine','Kyanite'}
                    app.DropDown.Value=3;
                case {'Rutile','Quartz'}
                    app.DropDown.Value=2;
                case {'Garnet'}
                    app.DropDown.Value=1;
                case 'Other'
                    app.DropDown.Value=3;
                    app.DropDown.Enable=true;
            end

                app.DensityEditField.Value=0; 
                %ATH add density change
            DropDownValueChanged(app)

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            v_state=false;

            if value==3
                app.AbsAlphaEditField.Visible=not(v_state);
                app.AbsBetaEditField.Visible=not(v_state);
                app.AbsGammaEditField.Visible=not(v_state);

                app.AbsAlphaEditFieldLabel.Visible=not(v_state);
                app.AbsBetaEditFieldLabel.Visible=not(v_state);
                app.AbsGammaEditFieldLabel.Visible=not(v_state);

                app.ThicknessAlphamicronEditField.Visible=not(v_state);
                app.ThicknessBetamicronEditField.Visible=not(v_state);
                app.ThicknessGammamicronEditField.Visible=not(v_state);

                app.ThicknessAlphamicronEditFieldLabel.Visible=not(v_state);
                app.ThicknessBetamicronEditFieldLabel.Visible=not(v_state);
                app.ThicknessGammamicronEditFieldLabel.Visible=not(v_state);

            elseif value==2
                app.AbsAlphaEditField.Visible=not(v_state);
                app.AbsBetaEditField.Visible=v_state;
                app.AbsGammaEditField.Visible=not(v_state);

                app.AbsAlphaEditFieldLabel.Visible=not(v_state);
                app.AbsBetaEditFieldLabel.Visible=v_state;
                app.AbsGammaEditFieldLabel.Visible=not(v_state);

                app.ThicknessAlphamicronEditField.Visible=not(v_state);
                app.ThicknessBetamicronEditField.Visible=v_state;
                app.ThicknessGammamicronEditField.Visible=not(v_state);

                app.ThicknessAlphamicronEditFieldLabel.Visible=not(v_state);
                app.ThicknessBetamicronEditFieldLabel.Visible=v_state;
                app.ThicknessGammamicronEditFieldLabel.Visible=not(v_state);

            elseif value==1
                app.DropDown.Value=1;
                app.AbsAlphaEditField.Visible=not(v_state);
                app.AbsBetaEditField.Visible=v_state;
                app.AbsGammaEditField.Visible=v_state;

                app.AbsAlphaEditFieldLabel.Visible=not(v_state);
                app.AbsBetaEditFieldLabel.Visible=v_state;
                app.AbsGammaEditFieldLabel.Visible=v_state;

                app.ThicknessAlphamicronEditField.Visible=not(v_state);
                app.ThicknessBetamicronEditField.Visible=v_state;
                app.ThicknessGammamicronEditField.Visible=v_state;

                app.ThicknessAlphamicronEditFieldLabel.Visible=not(v_state);
                app.ThicknessBetamicronEditFieldLabel.Visible=v_state;
                app.ThicknessGammamicronEditFieldLabel.Visible=v_state;
            end

        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01; %ATH settigs
                xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                end
            end
        end

        % Window button motion function: SpecDBUIFigure
        function SpecDBUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)
                currentPoint   = app.FigXlimLive.CurrentPoint;
                x            = currentPoint(1,1);
                %y            = currentPoint(1,3);
                xlim = app.FigXlimLive.XLim;
                %ylim = app.FigXlimLive.YLim;
                inside_FigXlimLive = x >= xlim(1)-(xlim(1)*app.clickbuffer) & x <= xlim(2)+(xlim(2)*app.clickbuffer); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                if inside_FigXlimLive
                    set(app.hLineToDrag, 'Value', x);
                    xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                    app.Int_range_min.Value = min(xposlines);
                    app.Int_range_max.Value = max(xposlines);
                else
                    SpecDBUIFigureWindowButtonUp(app)
                end
            end
        end

        % Value changed function: Int_range_max, Int_range_min
        function Int_range_minValueChanged(app, event)
            ValueMin = app.Int_range_min.Value;
            ValueMax = app.Int_range_max.Value;
            app.Int_range_max_3.Value=app.Int_range_max.Value ;
            app.Int_range_min_3.Value=app.Int_range_min.Value;
            app.Int_range_max_2.Value=app.Int_range_max.Value ;
            app.Int_range_min_2.Value=app.Int_range_min.Value;

            if ValueMin < ValueMax
                try
                    set(app.hVerticalLines(1),'Value',ValueMin)
                    set(app.hVerticalLines(2),'Value',ValueMax)
                catch
                    app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                        xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
                end
            end
        end

        % Window button up function: SpecDBUIFigure
        function SpecDBUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];
                set(app.hVerticalLines(1),'Value',app.Int_range_min.Value)
                set(app.hVerticalLines(2),'Value',app.Int_range_max.Value)

                if app.Int_range_min.Value<app.EditField_xlimMin.Value
                    app.EditField_xlimMin.Value=app.Int_range_min.Value;
                    app.FigXlimLive.XLim=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

                end

                if app.Int_range_max.Value>app.EditField_xlimMax.Value
                    app.EditField_xlimMax.Value=app.Int_range_max.Value;
                    app.FigXlimLive.XLim=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                end
            end
        end

        % Button pushed function: SetasXLimitButton
        function SetasXLimitButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.Int_range_min.Value;
            app.EditField_xlimMax.Value=app.Int_range_max.Value;
            app.FigXlimLive.XLim=sort([app.EditField_xlimMax.Value app.EditField_xlimMin.Value]);
            app.ResetButton.Value=false;
        end

        % Close request function: SpecDBUIFigure
        function SpecDBUIFigureCloseRequest(app, event)
            if app.edit_state==true
                if isfield(app.Data,'projectpath') && not(isempty(app.Data.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.Data.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end
                answer = questdlg({['Do you want to save your project' projectname '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveDBProjectMenuSelected(app)
                        delete(app)
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                delete(app)
            end


        end

        % Menu selected function: NewDBProjectMenu
        function NewDBProjectMenuSelected(app, event)
            if app.edit_state==true
                if isfield(app.Data,'projectpath') && not(isempty(app.Data.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.Data.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end
                answer = questdlg({['Do you want to save your project' projectname '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        app.edit_state=false;

                        SaveDBProjectMenuSelected(app)
                    case 'No'
                    case 'Cancel'
                        return
                end
            end
            if isfield(app.Data,'projectpath')
                app.Data=rmfield(app.Data,'projectpath');
                app.SpecDBUIFigure.Name = 'SpecDB';
            end
            

            delete(app)
            SpecDB;

        end

        % Value changed function: Int_range_max_3, Int_range_min_3
        function Int_range_max_3ValueChanged(app, event)
            app.Int_range_max.Value = app.Int_range_max_3.Value;
            app.Int_range_min.Value = app.Int_range_min_3.Value;
        end

        % Button pushed function: SpotVisModule_Button
        function SpotVisModuleMenuSelected(app, event)
            int_range=[app.Int_range_max.Value app.Int_range_min.Value];
            xlimits=[app.EditField_xlimMax.Value app.EditField_xlimMin.Value];
            SpotVisModule(app,xlimits, int_range)
        end

        % Menu selected function: ClassificationMenu
        function ClassificationModuleMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end


            if numel(unique(app.DB_table.Selection(:,1)))==1 % if a single spectra return as MetaPrivat.Classification.(same structure as SpecMaps)
                SampleId=app.DB_table.Data.Id(unique(app.DB_table.Selection(1,1)));
                range=[app.Int_range_max.Value app.Int_range_min.Value];


                [x, y, ~, Thickness, Id, eID]  = get_specs(app,SampleId,true);

                x_signal= x{1};

                if Thickness{1}==0
                    y_signal= y{1};
                else
                    y_signal=(y{1}./Thickness{1}).*10000;
                end

            else
                SampleId=app.DB_table.Data.Id(unique(app.DB_table.Selection(1,1)));
                range=[app.EditField_xlimMax.Value app.EditField_xlimMin.Value];
                selections=app.DB_table.Data.Id(ismember(app.DB_table.Data.Id(app.DB_table.Data.n_meanSpec>0 & app.DB_table.Data.Active==true),app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)))));

                [x, y, ~, Thickness, Id, eID]  = get_specs(app,selections,true);

                %  n_entries=sum(app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections)));
                min_x_signal=min(cellfun(@min,x));
                max_x_signal=max(cellfun(@max,x));
                delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
                x_signal=(max_x_signal:-delta_wn:min_x_signal)';

                y_signal=nan(size(x_signal,1),numel(selections));
                thx=0;
                for n=1:numel(selections)
                    if isempty(y{n})
                        continue
                    end
                    if Thickness{n}==0
                        %                         str_thx=sprintf('%.0f,' , selections(n));
                        %                         message=['Error No Thickness Set for: ' str_thx(1:end-1)];
                        %                         uialert(app.SpecDBUIFigure,message,'Error');
                        %                         return
                        Thickness{n}=10000;
                    end
                    thx=thx(end)+1:thx(end)+app.DB_table.Data.n_meanSpec(ismember(app.DB_table.Data.Id,selections(n)));
                    if isequal(x_signal,x{n})
                        y_signal(:,thx)=(y{n}./Thickness{n}).*10000;
                    else
                        if isfinite(y{n})
                            y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,x_signal);
                            y_resampled(not(x_signal>min(x{n})&x_signal<max(x{n})))=nan;

                            y_signal(:,n)=mean(y_resampled,2,'omitnan');
                        else

                        end
                    end
                end
            end

            mapsize=[size(y_signal,2),1];
            cmap=[];            %ATH change colormap
            ClassificationModule(app,  x_signal, y_signal, range, eID, mapsize,cmap);

        end

        % Menu selected function: RemovefromgroupMenu
        function RemovefromgroupMenuSelected(app, event)
            [indx,tf] = listdlg('PromptString',{'Select Groups'},...
                'SelectionMode','multiple','ListString',app.Data.Group_names(2:end),'InitialValue',...
                find(ismember(app.GroupListBox.Value,app.GroupListBox.Items(2:end))));
            figure(app.SpecDBUIFigure);    

            for n=1:numel(indx)
                if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups) >= indx(n)+1 && not(isempty(app.Data.Groups(indx(n)+1).Members))
                    app.Data.Groups(indx(n)+1).Members(ismember(app.Data.Groups(indx(n)+1).Members,unique(app.DB_table.Data.Id(app.DB_table.Selection(:,1),1))))=[];
                else
                    continue
                end
            end
            update_UI(app)
        end

        % Menu selected function: AddToExistingGroupMenu
        function AddToExistingGroupMenuSelected(app, event)
            % get groupnames create callback list and ask user which group
            % update the group matrix
           Selection=app.DB_table.Selection;
            [indx,~] = listdlg('PromptString',{'Select Groups'},...
                'SelectionMode','multiple','ListString',app.Data.Group_names(2:end),'InitialValue',...
                find(ismember(app.GroupListBox.Value,app.GroupListBox.Items(2:end))));

            for n=1:numel(indx)
                if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups)>=indx(n)+1 && not(isempty(app.Data.Groups(indx(n)+1).Members))
                    app.Data.Groups(indx(n)+1).Members=unique([app.Data.Groups(indx(n)+1).Members; app.DB_table.Data.Id(Selection(:,1),1)]);
                else
                    app.Data.Groups(indx(n)+1).Members=app.DB_table.Data.Id(Selection(:,1),1);
                end
            end
            figure(app.SpecDBUIFigure);

        end

        % Menu selected function: CreateNewGroupwithSelectionMenu
        function CreateNewGroupwithSelectionMenuSelected(app, event)
            app.CreateGroupButtonPushed(app)
            app.Data.Groups(app.active_Group).Members=app.DB_table.Data.Id(app.DB_table.Selection(:,1),1);
        end

        % Value changed function: AndFilterButton
        function AndFilterButtonValueChanged(app, event)
            if app.AndFilterButton.Value==true
                app.OrFilterButton.Value=false;
                app.NotAndFilterButton.Value=false;
                app.NotOrFilterButton.Value=false;
            end
            update_UI(app)
        end

        % Value changed function: NotAndFilterButton
        function NotAndFilterButtonValueChanged(app, event)
            if app.NotAndFilterButton.Value==true
                app.AndFilterButton.Value=false;
                app.OrFilterButton.Value=false;
                app.NotOrFilterButton.Value=false;
            end
            update_UI(app)
        end

        % Value changed function: OrFilterButton
        function OrFilterButtonValueChanged(app, event)
            if app.OrFilterButton.Value==true
                app.AndFilterButton.Value=false;
                app.NotAndFilterButton.Value=false;
                app.NotOrFilterButton.Value=false;
            end
            update_UI(app)
        end

        % Value changed function: NotOrFilterButton
        function NotOrFilterButtonValueChanged(app, event)
            if app.NotOrFilterButton.Value==true
                app.AndFilterButton.Value=false;
                app.OrFilterButton.Value=false;
                app.NotAndFilterButton.Value=false;
            end
            update_UI(app)
        end

        % Value changed function: XDirSwitch
        function XDirSwitchValueChanged(app, event)
            value = app.XDirSwitch.Value;
            switch value
                case 'XDir normal'
                    app.FigXlimLive.XDir='normal';
                case 'Reverse'
                    app.FigXlimLive.XDir='reverse';
            end


        end

        % Menu selected function: Raman_MKSMenu
        function Raman_MKSMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.dpt;*.mat;*.*'},"MultiSelect","on",'Select Spectrum');
            delete(f);
            figure(app.SpecDBUIFigure)
            if isequal(file,0)
                return
            end
            app.active_tmp=0;
            if not(iscell(file))
                file={file};
            end
            for k=1:length(file)
                if any(strfind(file{k},'.csv')) || any(strfind(file{k},'.txt')) || any(strfind(file{k},'.dpt'))
                    %   try
                    autoimport_MKS_Raman(app,path,file{k})
                    app.active_tmp=app.active_tmp+1;

                    %  catch
                    %      disp(['Error could not open file: ' fullfile(path,file)])
                    %  end
                end
            end

            update_DB_table(app)
            app.edit_state=true;
        end

        % Button pushed function: SpecVis_Button
        function SpecVisMenuSelected(app, event)
            SpecVis(app)
        end

        % Menu selected function: SetShiftMenu_2
        function RamanShiftXMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections=app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);


            shift = inputdlg('Enter shift:','Shift Correction', 1,{'0'});
            if ~isempty(shift)
                shift=unique(str2num(shift{1})); %ok<ST2NM>
            else
                return
            end
            if isnumeric(shift) && not(isnan(shift)) && not(isinf(shift))


                for n=1:numel(eID)
                    if not(isfield(app.Data.DB(eID(n)),'x_shift_reset')) || isempty(app.Data.DB(eID(n)).x_shift_reset)
                        app.Data.DB(eID(n)).x_shift_reset=shift;
                    else
                        app.Data.DB(eID(n)).x_shift_reset=app.Data.DB(eID(n)).x_shift_reset+shift;
                    end

                    app.Data.DB(eID(n)).x_signal=app.Data.DB(eID(n)).x_signal+shift;
                end
            end

        end

        % Menu selected function: ResetShiftMenu_2
        function ResetShiftMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end
            selections=app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));

            [x, y, SampleName, Thickness, Id, eID] = get_specs(app,selections,true);

            for n=1:numel(eID)
                if isfield(app.Data.DB(eID(n)),'x_shift_reset') && not(isempty(app.Data.DB(eID(n)).x_shift_reset))

                    shift=-app.Data.DB(eID(n)).x_shift_reset;
                    app.Data.DB(eID(n)).x_shift_reset=[];
                end

                app.Data.DB(eID(n)).x_signal=app.Data.DB(eID(n)).x_signal+shift;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            cla(app.FigH2O_calc_specs)
            app.FigH2O_calc_specs.Visible=false;
            app.PlotSpecOHCalc_Button.Visible=false;

            pos=unique(indices(:,1));
            for n=1:numel(pos)
                if isfield(app.Data,'OHCalcResults') && isfield(app.Data.OHCalcResults,'OHCalcResult_spectra') && numel(app.Data.OHCalcResults(1).OHCalcResult_spectra)>=pos(n) && not(isempty(app.Data.OHCalcResults(1).OHCalcResult_spectra{pos(n)})) && app.Data.OHCalcResults(1).OHCalcResult_spectra{pos(n)}==true
                    app.FigH2O_calc_specs.Visible=true;
                    app.PlotSpecOHCalc_Button.Visible=true;

                    plot(app.FigH2O_calc_specs,app.Data.OHCalcResults(1).OHCalcResults_x{pos(n)},app.Data.OHCalcResults(1).OHCalcResults_y{pos(n)},LineWidth=2)
                    xline(app.FigH2O_calc_specs,app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)},'linewidth',2)

                    app.FigH2O_calc_specs.XLim=[min(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)})-diff(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)})*0.10 max(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)})+diff(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)})*0.10 ];

                    % x=min(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}):max(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos});
                    %ind = (vgrf2>bw)&x>lcom;
                    %spec(~ind) = NaN;
                    %area(x,spec,bw,'EdgeColor','none','FaceColor','g','ShowBaseLine','off')
                end
            end


        end

        % Button pushed function: PlotSpecOHCalc_Button
        function PlotSpecOHCalc_ButtonPushed(app, event)
            figure
            hold on
            pos=unique(app.UITable.Selection(:,1));
            for n=1:numel(pos)
                plot(app.Data.OHCalcResults(1).OHCalcResults_x{pos(n)},app.Data.OHCalcResults(1).OHCalcResults_y{pos(n)},LineWidth=2)
                xline(app.Data.OHCalcResults(1).OHCalcResult_int_range{pos(n)},'linewidth',2)
            end
            xlabel('Wavenumber [cm^{-1}]')
            ylabel('Absorbance [cm^{-1}]')
            if strcmp(app.XDirSwitch.Value,'Reverse')
                set (gca, 'xdir', 'reverse')
            end
        end

        % Button pushed function: InsertinDBButton
        function InsertinDBButtonPushed(app, event)


            %overwrite=-1;
            Samples_in_DB=unique({app.Data.Meta.(char(app.DBDropDown.Value))}');

            %       for n=1:numel(unique({app.Data.Meta.Sample}'))

            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Meta Matching',...
                'Message','Automatic Meta Matching Started','Cancelable','on');


            for n=1:numel(unique({app.Data.Meta.(char(app.DBDropDown.Value))}'))
                if d.CancelRequested
                    break
                end

                d.Value = n/numel(unique({app.Data.Meta.(char(app.DBDropDown.Value))}'));
                d.Message = sprintf(['Matching Sample ' num2str(n) '/' num2str(numel(unique({app.Data.Meta.(char(app.DBDropDown.Value))}'))) ': ' Samples_in_DB{n}]);
                  pos_in_meta_import=find(strcmpi(string(app.Data.meta_data_in.(char(app.ImportDropDown.Value))),(Samples_in_DB(n))));

                if not(isempty(pos_in_meta_import))
                    meta2import=app.Data.meta_data_in(pos_in_meta_import,:);
                    fns=meta2import.Properties.VariableNames; %% ath here remove forbidden fieldnames

                    eIds=find(contains({app.Data.Meta.(char(app.DBDropDown.Value))}',Samples_in_DB{n}));
                    for m=1:numel(fns)
                        if d.CancelRequested
                            break
                        end

                        if any(strcmp(fns{m},app.protected_fns))
                            continue
                        else
                            try
                                if iscell(meta2import.(char(fns{m})))
                                    try
                                        [app.Data.Meta(eIds).(char(['Meta_' fns{m}]))]=meta2import.(char(fns{m})){:};
                                    catch
                                        if numel(unique(meta2import.(char(fns{m}))(:)))==1
                                            [app.Data.Meta(eIds).(char(['Meta_' fns{m}]))]=deal(meta2import.(char(fns{m})){1});
                                        else
                                            [app.Data.Meta(eIds).(char(['Meta_' fns{m}]))]=deal(meta2import.(char(fns{m})){1});
                                            %  disp([num2str(n) ' multiple entries with different content first value imported (cell)'])
                                        end
                                    end
                                else
                                    if numel(unique(meta2import.(char(fns{m}))(:)))==1
                                        [app.Data.Meta(eIds).(char(['Meta_' fns{m}]))]=deal(meta2import.(char(fns{m}))(1));
                                    else
                                        [app.Data.Meta(eIds).(char(['Meta_' fns{m}]))]=deal(meta2import.(char(fns{m}))(1));
                                        % disp('multiple entries with different content first value imported')
                                        %    disp([num2str(n) ' multiple entries with different content first value imported' ])


                                    end
                                end
                            catch ME
                                ME
                                disp('Error - Match Spectra')
                            end
                        end
                    end
                    
                end
            end
            update_DB_table(app)

        end

        % Button pushed function: MetadataTestButton
        function MetadataTestButtonPushed(app, event)
            Samples_in_DB=unique({app.Data.Meta.(char(app.DBDropDown.Value))}');
            count_single=0;
            count_multi=0;
            %       for n=1:numel(unique({app.Data.Meta.Sample}'))
            for n=1:numel(unique({app.Data.Meta.(char(app.DBDropDown.Value))}'))
                pos_in_meta_import=find(contains(lower(app.Data.meta_data_in.(char(app.ImportDropDown.Value))),lower(Samples_in_DB(n))));
                if not(isempty(pos_in_meta_import))


                    meta2import=app.Data.meta_data_in(pos_in_meta_import,strcmp(app.Data.meta_data_in.Properties.VariableNames,char(app.ImportDropDown.Value)));
                    fns=meta2import.Properties.VariableNames; %% ath here remove forbidden fieldnames

                    for m=1:numel(fns)


                        if iscell(meta2import.(char(fns{m})))

                            if numel(unique(meta2import.(char(fns{m}))(:)))==1
                                count_single=count_single+1;
                            else
                                count_multi=count_multi+1;
                            end

                        else
                            if numel(unique(meta2import.(char(fns{m}))(:)))==1
                                count_single=count_single+1;
                            else
                                count_multi=count_multi+1;
                            end
                        end


                    end
                end
            end
            disp('Metadata Matching test finished!')
            disp(['Single Matches: ' num2str(count_single)])
            disp(['Multi Matches: ' num2str(count_multi)])

            message={'Metadata Matching test finished!',['Single Matches: ' num2str(count_single)],['Multi Matches: ' num2str(count_multi)]};
            uialert(app.SpecDBUIFigure,message,'info');
        end

        % Button pushed function: LoadMetaFileButton
        function LoadMetaFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},"MultiSelect","off", 'Import Meta File');
            delete(f);
            figure(app.SpecDBUIFigure)
            if not(isequal(file,0))
                try
                    app.Data.meta_data_in=readtable(fullfile(path,file));
                    %
                    app.DB_table_2.Data=app.Data.meta_data_in;
                    app.DB_table_2.ColumnName=app.Data.meta_data_in.Properties.VariableNames;
                    %table(unique({app.Data.Meta.Sample}'),'VariableNames',{'Samples'});

                    app.DBDropDown.Items=app.DB_table.ColumnName;
                    app.ImportDropDown.Items=app.Data.meta_data_in.Properties.VariableNames;
                catch ME
                    ME
                end
            else
                return
            end
        end

        % Button pushed function: SimpleDecon_Button
        function SimpleDeconMenuSelected(app, event)
            [~, ~, ~, ~,Id, eID_] = get_specs(app,app.DB_table.Data.Id,true);
            [unique_samples, ~]=unique({app.Data.Meta(eID_).Sample},'stable');
            DeconData.SampleNames=unique_samples;

            for m=1:numel(unique_samples)
                [val ]=ismember({app.Data.Meta(eID_).Sample},unique_samples(m));
                idx=eID_(val);


                [unique_minerals, ~]=unique({app.Data.Meta(idx).Mineral},'stable');

                DeconData.SampleData(m).MineralNames=unique_minerals;
                for n=1:numel(unique_minerals)
                    [val ]=ismember({app.Data.Meta(idx).Mineral},unique_minerals(n));
                    idx2=idx(val);

                    DeconData.SampleData(m).Mineral(n).Id=idx2;
                    DeconData.SampleData(m).Mineral(n).SampleName={app.Data.Meta(idx2).SampleName};

                end

                %                 SampleData{1,m}=unique_samples{m};
                %                 [~, ~, SampleName, ~,~, eID] = get_specs(app,idx,true);
                %                 SampleData{2,m}=SampleName;
                %                 SampleData{3,m}={eID};

            end

            SimpleDeconModule(app,DeconData)


        end

        % Button pushed function: WaterCalcABCButton
        function WaterCalcABCButtonPushed(app, event)
            % read the variables
            if not(isfield(app.Data,'OHCalcResults')) || not(isfield(app.Data.OHCalcResults,'OHCalcResults_Table')) || not(istable(app.Data.OHCalcResults(1).OHCalcResults_Table))
                app.Data.OHCalcResults(1).OHCalcResults_Table=table();
            end
            thx=app.Data.OHCalcResults(1).OHCalcResults_Table;

            Int_range_max=app.Int_range_max.Value;
            Int_range_min=app.Int_range_min.Value;
            int_range=[Int_range_min Int_range_max];

            % get the xy Data
            I=sscanf(replace(app.CustomIEditField.Value,{',' ';'},' '),'%f',[1,Inf]);
            [~, ~, ~, ~,Id, eID_] = get_specs(app,app.DB_table.Data.Id,true);
            [unique_samples, ~]=unique({app.Data.Meta(eID_).(char(app.DropDown2.Value))},'stable');

            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Calculating H2O',...
                'Message','Automatic H2O Calculation Started','Cancelable','on');
            drawnow

                  for m=1:numel(unique_samples)

                if d.CancelRequested
                    break
                end
                d.Value = m/numel(unique_samples);
                try


                    [val ]=ismember({app.Data.Meta(eID_).(char(app.DropDown2.Value))},unique_samples(m));


                    %idx=app.DB_table.Data.Id(val);

                    %idx=[app.Data.Meta(val).Id];
                    idx=app.DB_table.Data.Id(val);
                    [x, y, SampleName, Thickness,Id, eID] = get_specs(app,idx,true);
                    d.Message = sprintf(['Calculating Sample: ' unique_samples{m}]);

                    try
                        sample=unique_samples(m);
                    catch
                        sample='unknown';
                    end

                    min_wn=min(cellfun(@min,x));
                    max_wn=max(cellfun(@max,x));
                    delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))));
                    if isnan(delta_wn)
                        delta_wn=cellfun(@diff,(x),'UniformOutput',false);
                        delta_wn=[delta_wn{:}];
                        delta_wn=delta_wn(isnumeric(delta_wn));
                        delta_wn=mean(delta_wn);
                    end
                    wavenumber=(max_wn:-delta_wn:min_wn)';

                    intensity=nan(size(wavenumber,1),numel(idx));

                    for n=1:numel(idx)
                        if isempty(y{n})
                            continue
                        end
                        if Thickness{n}==0

                            continue
                        end
                        if isequal(wavenumber,x{n})
                            intensity(:,n)=mean((y{n}./Thickness{n}).*10000,2,'omitnan');
                        else
                            y_resampled=interp1(x{n}(isfinite([x{n}])),(y{n}(isfinite([x{n}]),:)./Thickness{n}).*10000,wavenumber);
                            y_resampled(not(wavenumber>min(x{n})&wavenumber<max(x{n})))=nan;
                            intensity(:,n)=mean(y_resampled,2,'omitnan');
                        end
                    end

                    intensity(intensity==inf)=0;
                    intensity(isnan(intensity))=0;


                    OH_table=CalculateH2O(app,{wavenumber},{intensity},eID,sample,int_range,false);

                    thx=tblvertcat(app,thx,OH_table.results_table_1);
                    app.Data.OHCalcResults(1).OHCalcResults_Table=thx;
                    pos=size(app.Data.OHCalcResults(1).OHCalcResults_Table,1);
                    app.Data.OHCalcResults(1).OHCalcResults_x{pos}=OH_table.data.wavenumber;
                    app.Data.OHCalcResults(1).OHCalcResults_y{pos}=OH_table.data.intensity;
                    app.Data.OHCalcResults(1).OHCalcResult_int_range{pos}=OH_table.data.int_range;
                    app.Data.OHCalcResults(1).OHCalcResult_spectra{pos}=true;
                catch ME
                    disp(['Error calculating ' unique_samples(m)])
                    disp(ME.message)

                end
            end


            app.Data.OHCalcResults(1).OHCalcResults_Table=thx;

            format('shortG')
            app.UITable.Data=app.Data.OHCalcResults(1).OHCalcResults_Table;
            app.UITable.ColumnName=app.Data.OHCalcResults(1).OHCalcResults_Table.Properties.VariableNames;
            app.UITable.ColumnSortable = true(1,size(app.UITable.Data,2));
            app.UITable.RowName = {};

        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uiputfile({'*.xlsx;*.xls;*.csv;*.txt'}, 'Save Table');
            delete(f);
            figure(app.SpecDBUIFigure)
            if isequal(file,0)
                return
            end
            writetable(app.UITable.Data,fullfile(path,file))
        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotMenuSelected(app, event)
            T=app.DB_table.Data;
            GeoCPlot(T)
        end

        % Menu selected function: RemovefromSampleNameMenu
        function RemovefromSampleName2MenuSelected(app, event)
            str2rmv=inputdlg('Enter string to remove','Remove from SampleName', 1);


            [~, ~, ~, ~,~, eID] = get_specs(app,app.DB_table.Data.Id);

            if not(isempty(str2rmv))
                thx=replace({app.Data.Meta(eID).SampleName},str2rmv{:},'');
                [app.Data.Meta(eID).SampleName]=thx{:};
                update_DB_table(app)
            end


        end

        % Menu selected function: ReplaceinSampleNameMenu
        function ReplaceinSampleName2MenuSelected(app, event)
            str2rmv=inputdlg('Enter string to find','Find in SampleName', 1);
            str2rep=inputdlg('Enter replacement','Replace with in SampleName', 1);
            [~, ~, ~, ~,~, eID] = get_specs(app,app.DB_table.Data.Id);

            if not(isempty(str2rmv))
                thx=replace({app.Data.Meta(eID).SampleName},str2rmv{:},str2rep{:});
                [app.Data.Meta(eID).SampleName]=thx{:};
                update_DB_table(app)
            end
        end

        % Menu selected function: CombineFieldsMenu
        function CombineFieldsMenuSelected(app, event)
            C_name=app.DB_table.ColumnName;
            CombineColModule(app)

        end

        % Button pushed function: RoundTableButton
        function RoundOHTableMenuSelected(app, event)
            for n=6:size(app.UITable.Data,2)
                app.UITable.Data.(n)(:)=round(app.UITable.Data.(n)(:));
            end
        end

        % Menu selected function: ImportSpectraFromExcelMenu
        function ImportSpectraFromExcelMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.SpecDBUIFigure)
            if isequal(path,0)
                return
            end
            test='xls';
            dirttest=dir([path '/**/*.*']);
            test_str=false(1,numel({(dirttest.name)}));
            for n=1:numel({(dirttest.name)})
                test_str(n)=contains(string({dirttest(n).name}'),(test))||contains(string({dirttest(n).name}'),'.csv');
            end

            dir2keep=dirttest(test_str);

            spectra_in_dir=[dir([path '/**/*.xls*']);dir2keep];
            spectra_in_dir = spectra_in_dir(~startsWith({spectra_in_dir.name}, '.'));

            spectra_to_load={spectra_in_dir(:).name};
            path_to_load={spectra_in_dir(:).folder};

            %disp(length(spectra_to_load))
            app.active_tmp=0;
            error_log={};
            d = uiprogressdlg(app.SpecDBUIFigure,'Title','Import...',...
                'Message','Automatic Import Started','Cancelable','on');

            for k=1:length(spectra_to_load)
                d.Value = k/length(spectra_to_load);
                d.Message = sprintf(['Import Spectra from Folder :' num2str(k) ' of ' num2str(length(spectra_to_load)) ': '  path_to_load{k} spectra_to_load{k} ]);
                if d.CancelRequested
                    break
                end
                if any(strfind(spectra_to_load{k},'.xlsx')) || any(strfind(spectra_to_load{k},'.xls'))|| any(strfind(spectra_to_load{k},'.csv')) || any(strfind(spectra_to_load{k},'.CSV')) || any(strfind(spectra_to_load{k},'.txt'))
                    app.active_tmp=app.active_tmp+1;
                    try
                        thx= readtable(fullfile(path_to_load{k},spectra_to_load{k}),'readvariablenames',true);
                        thc= readcell(fullfile(path_to_load{k},spectra_to_load{k}));
                        cnames=thx.Properties.VariableNames;
                        for p=2:size(thx,2)

                            thx_=table2array([thx(3:end,1),thx(3:end,p)]);
                            T=thx.(p)(1);
                            if ismissing(thc{3,p})
                                C='';
                            else
                                C=thc{3,p};
                                if isnumeric(C)
                                    C=num2str(C);
                                end
                            end

                            cname=cnames{p};
                            if isnumeric(T) && not(isnan(T))
                                T=num2str(T);
                            else
                                T='0';
                            end
                            thxpath=[path_to_load{k}, '/thx/', replace(spectra_to_load{k},{'.xls','xlxs','.csv'},'') ,'/single/'];

                            if not(exist(thxpath,'dir'))
                                mkdir(thxpath);
                            end


                            if isequal(thx_(:,1),thx_(:,2)) || (sum(isnan(thx_(:,2)))==numel(isnan(thx_(:,2))))
                                disp('skip')
                            else
                                writematrix(thx_,[thxpath,cname,'_deltaT_',T, '.txt']);
                                thx_file=[cname,'_deltaT_',T, '.txt'];
                                thx_SampleName=cname;
                                thx_path=thxpath;%[path_to_load{k} '/thx/'];
                                info.Comment=C;
                                load_spec_to_import(app,thx_file,thx_path,thx_SampleName,info);
                            end
                        end
                    catch
                        error_log{end+1}=[path_to_load{k},spectra_to_load{k}];
                    end

                    %    rmdir(thxpath,'s')


                else
                    error(['Error could not open file: ' path file])
                end

            end

            update_DB_table(app)
            app.edit_state=true;
        end

        % Menu selected function: ThicknessfromMetaTableMenu
        function ThicknessfromMetaMenuSelected(app, event)
            if isempty(unique(app.DB_table.Selection))
                message={'No spectra selected!','Please select at least one spectrum'};
                uialert(app.SpecDBUIFigure,message,'Error');
                return
            end


            selections = app.DB_table.Data.Id(unique(app.DB_table.Selection(:,1)));


            list = app.DB_table.ColumnName;
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            figure(app.SpecDBUIFigure);    


            newval=app.DB_table.Data.(char(list(indx)))(unique(app.DB_table.Selection(:,1)));


            [val ]=ismember([app.Data.Meta(:).Id],selections);
            idx=1:numel(val);
            idx=idx(val);

            if any(ischar(newval))
                newval(ischar(newval))=str2double(newval(ischar(newval)));
            end

            if  any(isnan(newval)) || any(isinf(newval))
                idx=idx(not(isnan(newval) | isinf(newval)));
                newval=newval(not(isnan(newval) | isinf(newval)));
            end
            newval=num2cell(newval);
            [app.Data.Meta(idx).Thickness]=newval{:};
            [app.Data.MetaPrivat(idx).Thickness]=newval{:};

            app.edit_state=true;
            update_DB_table(app)
        end

        % Button pushed function: AddColFilt
        function AddColFiltButtonPushed(app, event)
            thx_table_Meta=struct2table(app.Data.Meta,'AsArray',true);
            list = thx_table_Meta.Properties.VariableNames;
            not2remove={'Id','Active','SampleName','Sample','n_meanSpec'};

            list2=list(not(ismember(list,not2remove)));

            [indx,~] = listdlg('ListString',list2,'SelectionMode','multi');
            figure(app.SpecDBUIFigure);    


            app.Data.ColumnFilter=find(ismember(list, [not2remove list2(indx)]));

            update_DB_table(app)

        end

        % Button pushed function: ResetColFilt
        function ResetColFiltButtonPushed(app, event)
            app.Data.ColumnFilter=[];
            update_DB_table(app)

        end

        % Drop down opening function: DropDown2
        function DropDown2Opening(app, event)
            if isfield(app.Data,'Meta')&& not(isempty(app.Data.Meta))

                thx_table_Meta=struct2table(app.Data.Meta,'AsArray',true);
                list = thx_table_Meta.Properties.VariableNames;
                app.DropDown2.Items=list;
            end
        end

        % Callback function: DefaultGroupsButton, DefaultGroupsMenu_2
        function DefaultGroupsMenuSelected(app, event)
            new_name='Active';

            if ismember(new_name,app.Data.Group_names)
                indx = find(strcmp(new_name,app.Data.Group_names));
                app.active_Group=1;
            else
                app.Data.Group_names(end+1)={new_name};
                indx=numel(app.Data.Group_names);
            end

            if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups)>=indx && not(isempty(app.Data.Groups(indx).Members))
                app.Data.Groups(indx).Members=unique([app.Data.Groups(indx).Members [app.Data.Meta([app.Data.Meta.Active]==1).Id] ]);
            else
                app.Data.Groups(indx).Members=[app.Data.Meta([app.Data.Meta.Active]==1).Id];
            end

            new_name='Single';

            if ismember(new_name,app.Data.Group_names)
                indx = find(strcmp(new_name,app.Data.Group_names));
                app.active_Group=1;
            else
                app.Data.Group_names(end+1)={new_name};
                indx=numel(app.Data.Group_names);
            end

            if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups)>=indx && not(isempty(app.Data.Groups(indx).Members))
                app.Data.Groups(indx).Members=unique([app.Data.Groups(indx).Members [app.Data.Meta([app.Data.Meta.n_meanSpec]==1).Id] ]);
            else
                app.Data.Groups(indx).Members=[app.Data.Meta([app.Data.Meta.n_meanSpec]==1).Id];
            end

            new_name='Multi';

            if ismember(new_name,app.Data.Group_names)
                indx = find(strcmp(new_name,app.Data.Group_names));
                app.active_Group=1;
            else
                app.Data.Group_names(end+1)={new_name};
                indx=numel(app.Data.Group_names);
            end

            if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups)>=indx && not(isempty(app.Data.Groups(indx).Members))
                app.Data.Groups(indx).Members=unique([app.Data.Groups(indx).Members [app.Data.Meta([app.Data.Meta.n_meanSpec]>1).Id] ]);
            else
                app.Data.Groups(indx).Members=[app.Data.Meta([app.Data.Meta.n_meanSpec]>1).Id];
            end
            app.active_Group=1;

            update_DB_list(app)
            figure(app.SpecDBUIFigure);

        end

        % Menu selected function: MineralMenu, SampleMenu_2, 
        % SampleNameMenu, SelectFieldMenu
        function FieldtoGroupsMenuSelected(app, event)
            if ~isfield(app.Data,'Meta') || isempty(app.Data.Meta)
                return
            end
            thx_table_Meta=struct2table(app.Data.Meta,'AsArray',true);
            list = thx_table_Meta.Properties.VariableNames;

            if strcmp(event.Source.Text,'Select Field')

                [indx_g,~] = listdlg('ListString',list,'SelectionMode','multi');

                if isempty(indx_g)
                    return
                end
            else
                indx_g = find(strcmp(list,event.Source.Text));
            end
            for n=1:numel(indx_g)
                newGroupNames=unique({app.Data.Meta.(char(list(indx_g(n))))},'stable');

                for k=1:numel(newGroupNames)
                    new_name=newGroupNames(k);
                    if ismember(new_name,app.Data.Group_names)
                        indx = find(strcmp(new_name,app.Data.Group_names));
                        app.active_Group=1;
                    else
                        app.Data.Group_names(end+1)=new_name;
                        indx=numel(app.Data.Group_names);
                    end

                    if isfield(app.Data,'Groups') && isfield(app.Data.Groups,'Members') && numel(app.Data.Groups)>=indx && not(isempty(app.Data.Groups(indx).Members))
                        app.Data.Groups(indx).Members=unique([app.Data.Groups(indx).Members [app.Data.Meta(ismember({app.Data.Meta.(char(list(indx_g(n))))},newGroupNames(k))).Id] ]);
                    else
                        app.Data.Groups(indx).Members=[app.Data.Meta(ismember({app.Data.Meta.(char(list(indx_g(n))))},newGroupNames(k))).Id];
                    end
                end

            end

            update_DB_list(app)
            app.active_Group=1;
            figure(app.SpecDBUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecDBUIFigure = uifigure('Visible', 'off');
            app.SpecDBUIFigure.Name = 'SpeDB';
            app.SpecDBUIFigure.Icon = 'symbols_1_logoSpecDB.png';
            app.SpecDBUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpecDBUIFigureCloseRequest, true);
            app.SpecDBUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @SpecDBUIFigureWindowButtonUp, true);
            app.SpecDBUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SpecDBUIFigureWindowButtonMotion, true);
            app.SpecDBMenu = uimenu(app.SpecDBUIFigure);
            app.SpecDBMenu.Text = 'SpecDB';
            app.NewDBProjectMenu = uimenu(app.SpecDBMenu);
            app.NewDBProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewDBProjectMenuSelected, true);
            app.NewDBProjectMenu.Accelerator = 'n';
            app.NewDBProjectMenu.Text = 'New DB Project';
            app.LoadDBProjectMenu = uimenu(app.SpecDBMenu);
            app.LoadDBProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDBProjectMenuSelected, true);
            app.LoadDBProjectMenu.Text = 'Load DB Project';
            app.SaveDBProjectMenu = uimenu(app.SpecDBMenu);
            app.SaveDBProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDBProjectMenuSelected, true);
            app.SaveDBProjectMenu.Accelerator = 's';
            app.SaveDBProjectMenu.Text = 'Save DB Project';
            app.SaveDBProjectAsMenu = uimenu(app.SpecDBMenu);
            app.SaveDBProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDBProjectAsMenuSelected, true);
            app.SaveDBProjectAsMenu.Text = 'Save DB Project As';
            app.ExportMenu = uimenu(app.SpecDBMenu);
            app.ExportMenu.Text = 'Export';
            app.ExportSelectedSpectracsvMenu = uimenu(app.ExportMenu);
            app.ExportSelectedSpectracsvMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSelectedSpectracsvMenuSelected, true);
            app.ExportSelectedSpectracsvMenu.Text = 'Export Selected Spectra (.csv)';
            app.ExportProjectcsvMenu_2 = uimenu(app.ExportMenu);
            app.ExportProjectcsvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExportProjectcsvMenuSelected, true);
            app.ExportProjectcsvMenu_2.Text = 'Export Project (.csv)';
            app.ImportMenu = uimenu(app.SpecDBUIFigure);
            app.ImportMenu.Text = 'Import';
            app.AutoImportMenu = uimenu(app.ImportMenu);
            app.AutoImportMenu.Text = 'AutoImport';
            app.FTIR_IFGMenu = uimenu(app.AutoImportMenu);
            app.FTIR_IFGMenu.Text = 'FTIR_IFG';
            app.Raman_MKSMenu = uimenu(app.AutoImportMenu);
            app.Raman_MKSMenu.MenuSelectedFcn = createCallbackFcn(app, @Raman_MKSMenuSelected, true);
            app.Raman_MKSMenu.Text = 'Raman_MKS';
            app.ImportSpectraFromFilesMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @AddSpectraButtonPushed, true);
            app.ImportSpectraFromFilesMenu.Text = 'Import Spectra From Files';
            app.ImportSpectraFromFolderMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectrafromFolderButtonPushed, true);
            app.ImportSpectraFromFolderMenu.Text = 'Import Spectra From Folder';
            app.ImportSpectraFromFoldermatonlyMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFoldermatonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectraFromFoldermatonlyMenuSelected, true);
            app.ImportSpectraFromFoldermatonlyMenu.Text = 'Import Spectra From Folder (.mat only)';
            app.ImportSpectraFromFoldermatonlyLimitSizeMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFoldermatonlyLimitSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectraFromFoldermatonlyLimitSizeMenuSelected, true);
            app.ImportSpectraFromFoldermatonlyLimitSizeMenu.Text = 'Import Spectra From Folder (.mat only) Limit Size';
            app.ImportSpectraFromFolder0OpusMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFolder0OpusMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectraFromFolder0OpusMenuSelected, true);
            app.ImportSpectraFromFolder0OpusMenu.Text = 'Import Spectra From Folder (.0 Opus)';
            app.ImportSpectraFromFolderspPerkinElmerMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromFolderspPerkinElmerMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectraFromFolderspPerkinElmerMenuSelected, true);
            app.ImportSpectraFromFolderspPerkinElmerMenu.Text = 'Import Spectra From Folder (.sp PerkinElmer)';
            app.ImportSpectraFromExcelMenu = uimenu(app.ImportMenu);
            app.ImportSpectraFromExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSpectraFromExcelMenuSelected, true);
            app.ImportSpectraFromExcelMenu.Text = 'Import Spectra From Excel';
            app.EditMenu = uimenu(app.SpecDBUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ExtractSpectraMenu = uimenu(app.EditMenu);
            app.ExtractSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractSpecsMenuSelected, true);
            app.ExtractSpectraMenu.Accelerator = 'e';
            app.ExtractSpectraMenu.Text = 'Extract Spectra';
            app.MergeSpectraMenu = uimenu(app.EditMenu);
            app.MergeSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @CombineSpecsMenuSelected, true);
            app.MergeSpectraMenu.Accelerator = 'm';
            app.MergeSpectraMenu.Text = 'Merge Spectra ';
            app.DuplicateSpectraMenu = uimenu(app.EditMenu);
            app.DuplicateSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateSpectraMenu_2Selected, true);
            app.DuplicateSpectraMenu.Accelerator = 'd';
            app.DuplicateSpectraMenu.Text = 'Duplicate Spectra';
            app.CalculateAbscmMenu = uimenu(app.EditMenu);
            app.CalculateAbscmMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateAbscmMenuSelected, true);
            app.CalculateAbscmMenu.Accelerator = '.';
            app.CalculateAbscmMenu.Text = 'Calculate Abs/cm';
            app.EditSampleNameMenu = uimenu(app.EditMenu);
            app.EditSampleNameMenu.Text = 'Edit SampleName';
            app.RemovefromSampleNameMenu = uimenu(app.EditSampleNameMenu);
            app.RemovefromSampleNameMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovefromSampleName2MenuSelected, true);
            app.RemovefromSampleNameMenu.Text = 'Remove from SampleName';
            app.ReplaceinSampleNameMenu = uimenu(app.EditSampleNameMenu);
            app.ReplaceinSampleNameMenu.MenuSelectedFcn = createCallbackFcn(app, @ReplaceinSampleName2MenuSelected, true);
            app.ReplaceinSampleNameMenu.Text = 'Replace in SampleName';
            app.CombineFieldsMenu = uimenu(app.EditSampleNameMenu);
            app.CombineFieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @CombineFieldsMenuSelected, true);
            app.CombineFieldsMenu.Text = 'Combine Fields';
            app.AutoGroupsMenu = uimenu(app.EditMenu);
            app.AutoGroupsMenu.Text = 'AutoGroups';
            app.FieldtoGroupsMenu_2 = uimenu(app.AutoGroupsMenu);
            app.FieldtoGroupsMenu_2.Text = 'Field to Groups';
            app.SampleMenu_2 = uimenu(app.FieldtoGroupsMenu_2);
            app.SampleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FieldtoGroupsMenuSelected, true);
            app.SampleMenu_2.Text = 'Sample';
            app.SampleNameMenu = uimenu(app.FieldtoGroupsMenu_2);
            app.SampleNameMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldtoGroupsMenuSelected, true);
            app.SampleNameMenu.Text = 'SampleName';
            app.MineralMenu = uimenu(app.FieldtoGroupsMenu_2);
            app.MineralMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldtoGroupsMenuSelected, true);
            app.MineralMenu.Text = 'Mineral';
            app.SelectFieldMenu = uimenu(app.FieldtoGroupsMenu_2);
            app.SelectFieldMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldtoGroupsMenuSelected, true);
            app.SelectFieldMenu.Text = 'Select Field';
            app.DefaultGroupsMenu_2 = uimenu(app.AutoGroupsMenu);
            app.DefaultGroupsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DefaultGroupsMenuSelected, true);
            app.DefaultGroupsMenu_2.Text = 'Default Groups';
            app.ShiftXMenu_2 = uimenu(app.EditMenu);
            app.ShiftXMenu_2.Text = 'Shift X';
            app.SetShiftMenu_2 = uimenu(app.ShiftXMenu_2);
            app.SetShiftMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RamanShiftXMenuSelected, true);
            app.SetShiftMenu_2.Text = 'Set Shift';
            app.ResetShiftMenu_2 = uimenu(app.ShiftXMenu_2);
            app.ResetShiftMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResetShiftMenuSelected, true);
            app.ResetShiftMenu_2.Text = 'Reset Shift';
            app.ThicknessfromMetaTableMenu = uimenu(app.EditMenu);
            app.ThicknessfromMetaTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ThicknessfromMetaMenuSelected, true);
            app.ThicknessfromMetaTableMenu.Text = 'Thickness from MetaTable';
            app.ClassificationMenu = uimenu(app.EditMenu);
            app.ClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassificationModuleMenuSelected, true);
            app.ClassificationMenu.Text = 'Classification';
            app.GridLayout = uigridlayout(app.SpecDBUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.GridLayout2 = uigridlayout(app.DatabaseTab);
            app.FigXlimLive = uiaxes(app.GridLayout2);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.Int_range_max = uieditfield(app.GridLayout2, 'numeric');
            app.Int_range_max.ValueChangedFcn = createCallbackFcn(app, @Int_range_minValueChanged, true);
            app.Int_range_max.Value = 3720;
            app.GroupListBox = uilistbox(app.GridLayout2);
            app.GroupListBox.Items = {'Full Database'};
            app.GroupListBox.Multiselect = 'on';
            app.GroupListBox.ValueChangedFcn = createCallbackFcn(app, @GroupListBoxValueChanged, true);
            app.GroupListBox.Value = {'Full Database'};
            app.DB_table = uitable(app.GridLayout2);
            app.DB_table.ColumnName = '';
            app.DB_table.RowName = {};
            app.DB_table.ColumnSortable = true;
            app.DB_table.ColumnEditable = true;
            app.DB_table.CellEditCallback = createCallbackFcn(app, @DB_tableCellEdit, true);
            app.DB_table.CellSelectionCallback = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.CreateGroupButton = uibutton(app.GridLayout2, 'push');
            app.CreateGroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGroupButtonPushed, true);
            app.CreateGroupButton.Text = 'Create Group';
            app.RemoveGroupButton = uibutton(app.GridLayout2, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = 'Remove Group';
            app.RenameGroupButton = uibutton(app.GridLayout2, 'push');
            app.RenameGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RenameGroupButtonPushed, true);
            app.RenameGroupButton.Text = 'Rename Group';
            app.AddSpectraButton = uibutton(app.GridLayout2, 'push');
            app.AddSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpectraButtonPushed, true);
            app.AddSpectraButton.Icon = 'symbols_1_Add Spec.png';
            app.AddSpectraButton.Tooltip = {'Add Spectra to Database'};
            app.AddSpectraButton.Text = '';
            app.DeleteSpectraButton = uibutton(app.GridLayout2, 'push');
            app.DeleteSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSpectraButtonPushed, true);
            app.DeleteSpectraButton.Icon = 'symbols_1_Delete Spec.png';
            app.DeleteSpectraButton.Tooltip = {'Delete Spectra from Database'};
            app.DeleteSpectraButton.Text = '';
            app.AddColumn = uibutton(app.GridLayout2, 'push');
            app.AddColumn.ButtonPushedFcn = createCallbackFcn(app, @AddColumnPushed, true);
            app.AddColumn.Icon = 'symbols_1_Add Column-32.png';
            app.AddColumn.Tooltip = {'Add Column to Database Table'};
            app.AddColumn.Text = '';
            app.EditField_xlimMin = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.EditField_xlimMax.Value = 4000;
            app.MeanButton = uibutton(app.GridLayout2, 'state');
            app.MeanButton.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.ResetButton = uibutton(app.GridLayout2, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.ResetButton.Text = 'Reset';
            app.SearchButton = uibutton(app.GridLayout2, 'push');
            app.SearchButton.Text = 'Search';
            app.DeleteColumn = uibutton(app.GridLayout2, 'push');
            app.DeleteColumn.ButtonPushedFcn = createCallbackFcn(app, @DeleteColumnPushed, true);
            app.DeleteColumn.Icon = 'symbols_1_Delete Column-33.png';
            app.DeleteColumn.Tooltip = {'Delete Column from Database Table'};
            app.DeleteColumn.Text = '';
            app.SearchEditFieldLabel = uilabel(app.GridLayout2);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchEditField = uieditfield(app.GridLayout2, 'text');
            app.SearchEditField.ValueChangedFcn = createCallbackFcn(app, @SearchEditFieldValueChanged, true);
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanged, true);
            app.ntoplotEditFieldLabel = uilabel(app.GridLayout2);
            app.ntoplotEditFieldLabel.Text = 'n to plot';
            app.ntoplotEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ntoplotEditField.Limits = [1 Inf];
            app.ntoplotEditField.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.ntoplotEditField.Tooltip = {'Maximum number of spectra displayd for each selected entry'};
            app.ntoplotEditField.Value = 15;
            app.SpecsButton = uibutton(app.GridLayout2, 'state');
            app.SpecsButton.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.SpecsButton.Text = 'Specs';
            app.SpecsButton.Value = true;
            app.LinBCButton = uibutton(app.GridLayout2, 'state');
            app.LinBCButton.ValueChangedFcn = createCallbackFcn(app, @DB_tableCellSelection, true);
            app.LinBCButton.Visible = 'off';
            app.LinBCButton.Text = 'LinBC';
            app.Int_range_min = uieditfield(app.GridLayout2, 'numeric');
            app.Int_range_min.ValueChangedFcn = createCallbackFcn(app, @Int_range_minValueChanged, true);
            app.Int_range_min.Value = 3050;
            app.IntRangeLabel = uilabel(app.GridLayout2);
            app.IntRangeLabel.Text = 'Int. Range';
            app.SetasXLimitButton = uibutton(app.GridLayout2, 'push');
            app.SetasXLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetasXLimitButtonPushed, true);
            app.SetasXLimitButton.Text = 'Set as X Limit';
            app.AndFilterButton = uibutton(app.GridLayout2, 'state');
            app.AndFilterButton.ValueChangedFcn = createCallbackFcn(app, @AndFilterButtonValueChanged, true);
            app.AndFilterButton.Tooltip = {'Filter &'};
            app.AndFilterButton.Icon = 'symbols_1_Filter Group And.png';
            app.AndFilterButton.Text = '';
            app.OrFilterButton = uibutton(app.GridLayout2, 'state');
            app.OrFilterButton.ValueChangedFcn = createCallbackFcn(app, @OrFilterButtonValueChanged, true);
            app.OrFilterButton.Tooltip = {'Filter ||'};
            app.OrFilterButton.Icon = 'symbols_1_Filter Group Or.png';
            app.OrFilterButton.Text = '';
            app.XDirSwitch = uiswitch(app.GridLayout2, 'slider');
            app.XDirSwitch.Items = {'XDir normal', 'Reverse'};
            app.XDirSwitch.ValueChangedFcn = createCallbackFcn(app, @XDirSwitchValueChanged, true);
            app.XDirSwitch.Value = 'Reverse';
            app.NotAndFilterButton = uibutton(app.GridLayout2, 'state');
            app.NotAndFilterButton.ValueChangedFcn = createCallbackFcn(app, @NotAndFilterButtonValueChanged, true);
            app.NotAndFilterButton.Tooltip = {'Filter &'};
            app.NotAndFilterButton.Icon = 'symbols_1_Filter Group not And.png';
            app.NotAndFilterButton.Text = '';
            app.NotOrFilterButton = uibutton(app.GridLayout2, 'state');
            app.NotOrFilterButton.ValueChangedFcn = createCallbackFcn(app, @NotOrFilterButtonValueChanged, true);
            app.NotOrFilterButton.Tooltip = {'Filter ||'};
            app.NotOrFilterButton.Icon = 'symbols_1_Filter Group not Or.png';
            app.NotOrFilterButton.Text = '';
            app.WaterCalcABCButton = uibutton(app.GridLayout2, 'push');
            app.WaterCalcABCButton.ButtonPushedFcn = createCallbackFcn(app, @WaterCalcABCButtonPushed, true);
            app.WaterCalcABCButton.Text = 'Water Calc ABC';
            app.ResetColFilt = uibutton(app.GridLayout2, 'push');
            app.ResetColFilt.ButtonPushedFcn = createCallbackFcn(app, @ResetColFiltButtonPushed, true);
            app.ResetColFilt.Icon = 'symbols_1_Reset Col. Filter.png';
            app.ResetColFilt.Tooltip = {'Reset Column Filter'};
            app.ResetColFilt.Text = '';
            app.AddColFilt = uibutton(app.GridLayout2, 'push');
            app.AddColFilt.ButtonPushedFcn = createCallbackFcn(app, @AddColFiltButtonPushed, true);
            app.AddColFilt.Icon = 'symbols_1_Col Filter.png';
            app.AddColFilt.Tooltip = {'Add Column Filter'};
            app.AddColFilt.Text = '';
            app.DropDown2 = uidropdown(app.GridLayout2);
            app.DropDown2.Items = {'SampleName', 'Sample', 'Other'};
            app.DropDown2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown2Opening, true);
            app.DropDown2.Tooltip = {'Column used to finde equal strings for automatic H2O calculation'};
            app.DropDown2.Value = 'SampleName';
            app.DefaultGroupsButton = uibutton(app.GridLayout2, 'push');
            app.DefaultGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultGroupsMenuSelected, true);
            app.DefaultGroupsButton.Tooltip = {'Update the default groups'};
            app.DefaultGroupsButton.Text = 'Default Groups';
            app.OHCalcTab = uitab(app.TabGroup);
            app.OHCalcTab.Title = 'OH Calc';
            app.GridLayout3 = uigridlayout(app.OHCalcTab);
            app.FigH2O_calc_specs = uiaxes(app.GridLayout3);
            xlabel(app.FigH2O_calc_specs, 'Wavenumbers [cm^{-1}]')
            ylabel(app.FigH2O_calc_specs, 'Absorbance  [cm^{-1}]')
            app.FigH2O_calc_specs.XDir = 'reverse';
            app.FigH2O_calc_specs.YTickLabel = '';
            app.FigH2O_calc_specs.Visible = 'off';
            app.AbsAlphaEditFieldLabel = uilabel(app.GridLayout3);
            app.AbsAlphaEditFieldLabel.Text = 'Abs. Alpha';
            app.AbsAlphaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AbsAlphaEditField.Value = 100;
            app.ThicknessAlphamicronEditFieldLabel = uilabel(app.GridLayout3);
            app.ThicknessAlphamicronEditFieldLabel.Text = 'Thickness. Alpha [micron]';
            app.ThicknessAlphamicronEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ThicknessAlphamicronEditField.Limits = [0 Inf];
            app.ThicknessAlphamicronEditField.ValueDisplayFormat = '%11g';
            app.ThicknessAlphamicronEditField.Value = 10000;
            app.MineralDropDownLabel = uilabel(app.GridLayout3);
            app.MineralDropDownLabel.Text = 'Mineral';
            app.MineralDropDown = uidropdown(app.GridLayout3);
            app.MineralDropDown.Items = {'CPX', 'OPX', 'Garnet', 'Olivine', 'Rutile', 'Kyanite', 'Quartz', 'Other'};
            app.MineralDropDown.ValueChangedFcn = createCallbackFcn(app, @MineralDropDownValueChanged, true);
            app.MineralDropDown.Value = 'CPX';
            app.DensityEditFieldLabel = uilabel(app.GridLayout3);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DensityEditField.Value = 3.3;
            app.CustomIEditFieldLabel = uilabel(app.GridLayout3);
            app.CustomIEditFieldLabel.Text = 'Custom I';
            app.CustomIEditField = uieditfield(app.GridLayout3, 'text');
            app.CustomIEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.CustomIEditFieldLabel_2.Text = 'Mineral Name';
            app.MineralName_EditField = uieditfield(app.GridLayout3, 'text');
            app.MineralName_EditField.Value = 'CPX';
            app.DropDownLabel = uilabel(app.GridLayout3);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'A1=A2=A3', 'A1=A2 / C', 'A / B / C'};
            app.DropDown.ItemsData = [1 2 3];
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 3;
            app.CalculateButton = uibutton(app.GridLayout3, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout3);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.GridLayout3, 'text');
            app.SampleNameEditField.Value = 'SampleName';
            app.Int_range_max_2 = uieditfield(app.GridLayout3, 'numeric');
            app.Int_range_max_2.Visible = 'off';
            app.Int_range_max_2.Value = 3720;
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '-';
            app.Int_range_min_2 = uieditfield(app.GridLayout3, 'numeric');
            app.Int_range_min_2.Visible = 'off';
            app.Int_range_min_2.Value = 3050;
            app.IntRangeLabel_2 = uilabel(app.GridLayout3);
            app.IntRangeLabel_2.Visible = 'off';
            app.IntRangeLabel_2.Text = 'Int. Range';
            app.ThicknessBetamicronEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ThicknessBetamicronEditField.Limits = [0 Inf];
            app.ThicknessBetamicronEditField.RoundFractionalValues = 'on';
            app.ThicknessBetamicronEditField.ValueDisplayFormat = '%11g';
            app.ThicknessBetamicronEditField.Value = 10000;
            app.AbsGammaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AbsGammaEditField.Limits = [0 Inf];
            app.AbsGammaEditField.Value = 100;
            app.ThicknessGammamicronEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ThicknessGammamicronEditField.Limits = [0 Inf];
            app.ThicknessGammamicronEditField.ValueDisplayFormat = '%11g';
            app.ThicknessGammamicronEditField.Value = 10000;
            app.ThicknessGammamicronEditFieldLabel = uilabel(app.GridLayout3);
            app.ThicknessGammamicronEditFieldLabel.Text = 'Thickness. Gamma [micron]';
            app.AbsGammaEditFieldLabel = uilabel(app.GridLayout3);
            app.AbsGammaEditFieldLabel.Text = 'Abs. Gamma';
            app.ThicknessBetamicronEditFieldLabel = uilabel(app.GridLayout3);
            app.ThicknessBetamicronEditFieldLabel.Text = 'Thickness. Beta [micron]';
            app.AbsBetaEditFieldLabel = uilabel(app.GridLayout3);
            app.AbsBetaEditFieldLabel.Text = 'Abs. Beta';
            app.AbsBetaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AbsBetaEditField.Value = 100;
            app.Int_range_max_3 = uieditfield(app.GridLayout3, 'numeric');
            app.Int_range_max_3.ValueChangedFcn = createCallbackFcn(app, @Int_range_max_3ValueChanged, true);
            app.Int_range_max_3.Value = 3720;
            app.Int_range_min_3 = uieditfield(app.GridLayout3, 'numeric');
            app.Int_range_min_3.ValueChangedFcn = createCallbackFcn(app, @Int_range_max_3ValueChanged, true);
            app.Int_range_min_3.Value = 3050;
            app.IntRangeLabel_3 = uilabel(app.GridLayout3);
            app.IntRangeLabel_3.Text = 'Int. Range';
            app.DeleteEntryButton = uibutton(app.GridLayout3, 'push');
            app.DeleteEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEntryButtonPushed, true);
            app.DeleteEntryButton.Text = 'Delete Entry';
            app.ClearResultsButton_2 = uibutton(app.GridLayout3, 'push');
            app.ClearResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButton_2Pushed, true);
            app.ClearResultsButton_2.Text = 'Clear Results';
            app.ExportTableButton = uibutton(app.GridLayout3, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.PlotSpecOHCalc_Button = uibutton(app.GridLayout3, 'push');
            app.PlotSpecOHCalc_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotSpecOHCalc_ButtonPushed, true);
            app.PlotSpecOHCalc_Button.Icon = 'symbols_1_Simple Plot.png';
            app.PlotSpecOHCalc_Button.Visible = 'off';
            app.PlotSpecOHCalc_Button.Text = '';
            app.RoundTableButton = uibutton(app.GridLayout3, 'push');
            app.RoundTableButton.ButtonPushedFcn = createCallbackFcn(app, @RoundOHTableMenuSelected, true);
            app.RoundTableButton.Text = 'Round Table';
            app.MetadataMatchTab = uitab(app.TabGroup);
            app.MetadataMatchTab.Title = 'Metadata Match';
            app.GridLayout4 = uigridlayout(app.MetadataMatchTab);
            app.DB_table_2 = uitable(app.GridLayout4);
            app.DB_table_2.ColumnName = '';
            app.DB_table_2.RowName = {};
            app.DB_table_2.ColumnSortable = true;
            app.DB_table_2.ColumnEditable = true;
            app.LoadMetaFileButton = uibutton(app.GridLayout4, 'push');
            app.LoadMetaFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetaFileButtonPushed, true);
            app.LoadMetaFileButton.Text = 'Load Meta File';
            app.MetadataTestButton = uibutton(app.GridLayout4, 'push');
            app.MetadataTestButton.ButtonPushedFcn = createCallbackFcn(app, @MetadataTestButtonPushed, true);
            app.MetadataTestButton.Text = 'Metadata Test';
            app.InsertinDBButton = uibutton(app.GridLayout4, 'push');
            app.InsertinDBButton.ButtonPushedFcn = createCallbackFcn(app, @InsertinDBButtonPushed, true);
            app.InsertinDBButton.Text = 'Insert in DB';
            app.ImportDropDownLabel = uilabel(app.GridLayout4);
            app.ImportDropDownLabel.Text = 'Import';
            app.ImportDropDown = uidropdown(app.GridLayout4);
            app.DBDropDownLabel = uilabel(app.GridLayout4);
            app.DBDropDownLabel.Text = 'DB';
            app.DBDropDown = uidropdown(app.GridLayout4);
            app.ImportSpectraFromFolder_Button = uibutton(app.GridLayout, 'push');
            app.ImportSpectraFromFolder_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportSpectraFromFoldermatonlyMenuSelected, true);
            app.ImportSpectraFromFolder_Button.Icon = 'symbols_1_Import Specs From Folder.png';
            app.ImportSpectraFromFolder_Button.Tooltip = {'Load Spectra From Folder'};
            app.ImportSpectraFromFolder_Button.Text = '';
            app.SaveDBProject_Button = uibutton(app.GridLayout, 'push');
            app.SaveDBProject_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveDBProjectMenuSelected, true);
            app.SaveDBProject_Button.Icon = 'symbols_1_Save.png';
            app.SaveDBProject_Button.Tooltip = {'Save Project'};
            app.SaveDBProject_Button.Text = '';
            app.SaveDBProjectAs_Button = uibutton(app.GridLayout, 'push');
            app.SaveDBProjectAs_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveDBProjectAsMenuSelected, true);
            app.SaveDBProjectAs_Button.Icon = 'symbols_1_Save As.png';
            app.SaveDBProjectAs_Button.Tooltip = {'Save Project As'};
            app.SaveDBProjectAs_Button.Text = '';
            app.SimplePlot_Button = uibutton(app.GridLayout, 'push');
            app.SimplePlot_Button.ButtonPushedFcn = createCallbackFcn(app, @SimplePlotMenuSelected, true);
            app.SimplePlot_Button.Icon = 'symbols_1_Simple Plot.png';
            app.SimplePlot_Button.Tooltip = {'Simple Plot'};
            app.SimplePlot_Button.Text = '';
            app.StackedPlot_Button = uibutton(app.GridLayout, 'push');
            app.StackedPlot_Button.ButtonPushedFcn = createCallbackFcn(app, @StackedPlotMenuSelected, true);
            app.StackedPlot_Button.Icon = 'symbols_1_Stacked Plot.png';
            app.StackedPlot_Button.Tooltip = {'Stacked Plot'};
            app.StackedPlot_Button.Text = '';
            app.x3DPlot_Button = uibutton(app.GridLayout, 'push');
            app.x3DPlot_Button.ButtonPushedFcn = createCallbackFcn(app, @DPlotMenuSelected, true);
            app.x3DPlot_Button.Icon = 'symbols_1_3D Figure.png';
            app.x3DPlot_Button.Tooltip = {'3D Plot'};
            app.x3DPlot_Button.Text = '';
            app.SpotVisModule_Button = uibutton(app.GridLayout, 'push');
            app.SpotVisModule_Button.ButtonPushedFcn = createCallbackFcn(app, @SpotVisModuleMenuSelected, true);
            app.SpotVisModule_Button.Icon = 'symbols_1_Spotvis.png';
            app.SpotVisModule_Button.Tooltip = {'SpotVis Module'};
            app.SpotVisModule_Button.Text = '';
            app.SimpleDecon_Button = uibutton(app.GridLayout, 'push');
            app.SimpleDecon_Button.ButtonPushedFcn = createCallbackFcn(app, @SimpleDeconMenuSelected, true);
            app.SimpleDecon_Button.Icon = 'symbols_1_SimpleDecon.png';
            app.SimpleDecon_Button.Tooltip = {'SimpleDecon Module'};
            app.SimpleDecon_Button.Text = '';
            app.SpecVis_Button = uibutton(app.GridLayout, 'push');
            app.SpecVis_Button.ButtonPushedFcn = createCallbackFcn(app, @SpecVisMenuSelected, true);
            app.SpecVis_Button.Icon = 'symbols_1_SpecVis-39.png';
            app.SpecVis_Button.Tooltip = {'SpecVis Module'};
            app.SpecVis_Button.Text = '';
            app.CalculateAbs_Button = uibutton(app.GridLayout, 'push');
            app.CalculateAbs_Button.ButtonPushedFcn = createCallbackFcn(app, @CalculateAbscmMenuSelected, true);
            app.CalculateAbs_Button.Icon = 'symbols_1_Integration A.png';
            app.CalculateAbs_Button.Tooltip = {'Extract integration and intensity from spectra'};
            app.CalculateAbs_Button.Text = '';
            app.GeoCPlotButton = uibutton(app.GridLayout, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotMenuSelected, true);
            app.GeoCPlotButton.Icon = 'symbols_1_GeoCPlot.png';
            app.GeoCPlotButton.Tooltip = {'Start GeoCPlot'};
            app.GeoCPlotButton.Text = '';
            app.LoadProject_Button = uibutton(app.GridLayout, 'push');
            app.LoadProject_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadDBProjectMenuSelected, true);
            app.LoadProject_Button.Icon = 'symbols_1_Load Project.png';
            app.LoadProject_Button.Tooltip = {'Load SpecDB Project'};
            app.LoadProject_Button.Text = '';
            app.ContextMenu = uicontextmenu(app.SpecDBUIFigure);
            app.SendtoFigureMenu = uimenu(app.ContextMenu);
            app.SendtoFigureMenu.Text = 'Send to Figure';
            app.SimplePlotMenu = uimenu(app.SendtoFigureMenu);
            app.SimplePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @SimplePlotMenuSelected, true);
            app.SimplePlotMenu.Text = 'Simple Plot';
            app.x3DPlotMenu = uimenu(app.SendtoFigureMenu);
            app.x3DPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DPlotMenuSelected, true);
            app.x3DPlotMenu.Text = '3D Plot';
            app.StackedPlotMenu = uimenu(app.SendtoFigureMenu);
            app.StackedPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @StackedPlotMenuSelected, true);
            app.StackedPlotMenu.Text = 'Stacked Plot';
            app.H2OcalcMenu_2 = uimenu(app.ContextMenu);
            app.H2OcalcMenu_2.Text = 'H2O calc';
            app.CalculateSelectedMenu = uimenu(app.H2OcalcMenu_2);
            app.CalculateSelectedMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateSelectedMenuSelected, true);
            app.CalculateSelectedMenu.Text = 'Calculate Selected';
            app.CalculateEverythingMenu = uimenu(app.H2OcalcMenu_2);
            app.CalculateEverythingMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateEverythingMenuSelected, true);
            app.CalculateEverythingMenu.Text = 'Calculate Everything';
            app.GetMeanIntegraionMenu = uimenu(app.H2OcalcMenu_2);
            app.GetMeanIntegraionMenu.Text = 'Get Mean Integraion';
            app.AlphaMenu = uimenu(app.GetMeanIntegraionMenu);
            app.AlphaMenu.MenuSelectedFcn = createCallbackFcn(app, @AlphaMenuSelected, true);
            app.AlphaMenu.Text = 'Alpha';
            app.BetaMenu = uimenu(app.GetMeanIntegraionMenu);
            app.BetaMenu.MenuSelectedFcn = createCallbackFcn(app, @BetaMenuSelected, true);
            app.BetaMenu.Text = 'Beta';
            app.GammaMenu = uimenu(app.GetMeanIntegraionMenu);
            app.GammaMenu.MenuSelectedFcn = createCallbackFcn(app, @GammaMenuSelected, true);
            app.GammaMenu.Text = 'Gamma';
            app.CopySpecstoClipboardMenu = uimenu(app.ContextMenu);
            app.CopySpecstoClipboardMenu.Text = 'Copy Spectra to Clipboard';
            app.MulticolumnMeanspecsMenu = uimenu(app.CopySpecstoClipboardMenu);
            app.MulticolumnMeanspecsMenu.MenuSelectedFcn = createCallbackFcn(app, @MulticolumnMeanspecsMenuSelected, true);
            app.MulticolumnMeanspecsMenu.Text = 'Multicolumn Meanspecs';
            app.MulticolumnAllMenu = uimenu(app.CopySpecstoClipboardMenu);
            app.MulticolumnAllMenu.MenuSelectedFcn = createCallbackFcn(app, @MulticolumnAllMenuSelected, true);
            app.MulticolumnAllMenu.Text = 'Multicolumn All';
            app.SingleColumnMenu = uimenu(app.CopySpecstoClipboardMenu);
            app.SingleColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleColumnMenuSelected, true);
            app.SingleColumnMenu.Text = 'Single Column';
            app.MetadataMenu = uimenu(app.CopySpecstoClipboardMenu);
            app.MetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @MetadataMenuSelected, true);
            app.MetadataMenu.Text = 'Metadata';
            app.EditMetadataMenu = uimenu(app.ContextMenu);
            app.EditMetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMetadataMenuSelected, true);
            app.EditMetadataMenu.Text = 'Edit Metadata';
            app.CalculateAbscmMenu_2 = uimenu(app.ContextMenu);
            app.CalculateAbscmMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CalculateAbscmMenuSelected, true);
            app.CalculateAbscmMenu_2.Text = 'Calculate Abs/cm';
            app.GroupsMenu = uimenu(app.ContextMenu);
            app.GroupsMenu.Text = 'Groups';
            app.CreateNewGroupwithSelectionMenu = uimenu(app.GroupsMenu);
            app.CreateNewGroupwithSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateNewGroupwithSelectionMenuSelected, true);
            app.CreateNewGroupwithSelectionMenu.Text = 'Create New Group with Selection';
            app.AddToExistingGroupMenu = uimenu(app.GroupsMenu);
            app.AddToExistingGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @AddToExistingGroupMenuSelected, true);
            app.AddToExistingGroupMenu.Text = 'Add To Existing Group';
            app.RemovefromgroupMenu = uimenu(app.GroupsMenu);
            app.RemovefromgroupMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovefromgroupMenuSelected, true);
            app.RemovefromgroupMenu.Text = 'Remove from group';
            app.SendtoSpecVisMenu = uimenu(app.ContextMenu);
            app.SendtoSpecVisMenu.Visible = 'off';
            app.SendtoSpecVisMenu.Text = 'Send to SpecVis';
            app.DeleteSpectraMenu = uimenu(app.ContextMenu);
            app.DeleteSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSpectraButtonPushed, true);
            app.DeleteSpectraMenu.Text = 'Delete Spectra';
            % Assign app.ContextMenu
            app.DB_table.ContextMenu = app.ContextMenu;
            app.DB_table_2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SpecDBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecDB(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpecDBUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpecDBUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecDBUIFigure)
        end
    end
end