classdef SpecMaps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecMapsUIFigure                matlab.ui.Figure
        SpecMapsMenu                    matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        LoadProjectMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        SaveProjectAsMenu               matlab.ui.container.Menu
        ExportMapMenu                   matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        CloseSpecMapsMenu               matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        AutoImportMenu                  matlab.ui.container.Menu
        FTIRMenu                        matlab.ui.container.Menu
        BrukerIFGBernMenu               matlab.ui.container.Menu
        OMNICmapMenu                    matlab.ui.container.Menu
        fsmMenu                         matlab.ui.container.Menu
        RAMANMenu                       matlab.ui.container.Menu
        RAMANGUMKSMenu                  matlab.ui.container.Menu
        RAMAN1Menu                      matlab.ui.container.Menu
        RAMAN2Menu                      matlab.ui.container.Menu
        HyperDC_GTKMenu                 matlab.ui.container.Menu
        ImportMapdataMenu               matlab.ui.container.Menu
        ImportMetadataMenu              matlab.ui.container.Menu
        ImportImageMenu                 matlab.ui.container.Menu
        EditDataMenu                    matlab.ui.container.Menu
        SetXYRatioMenu                  matlab.ui.container.Menu
        EditSpectraMenu                 matlab.ui.container.Menu
        DataSet2NewEntryMenu            matlab.ui.container.Menu
        InvertMenu                      matlab.ui.container.Menu
        RemContMenu                     matlab.ui.container.Menu
        ApplyEdittoallSpectraMenu       matlab.ui.container.Menu
        RamanBaselineMenu               matlab.ui.container.Menu
        DuplicateEntryMenu              matlab.ui.container.Menu
        ThicknessCallibrationMenu       matlab.ui.container.Menu
        OpenThicknessCallibrationMenu   matlab.ui.container.Menu
        SetConstantThicknessMenu        matlab.ui.container.Menu
        ResetThicknessMapMenu_2         matlab.ui.container.Menu
        ClassificationMenu              matlab.ui.container.Menu
        GenerateMapMenu                 matlab.ui.container.Menu
        GenerateMultipleMapsMenu        matlab.ui.container.Menu
        PresetMapsforallSamplesMenu     matlab.ui.container.Menu
        PresetMapsforselectedSamplesMenu  matlab.ui.container.Menu
        GenerateMapforallSamplesMenu_2  matlab.ui.container.Menu
        GenerateMapforselectedSamplesMenu_2  matlab.ui.container.Menu
        AbsoluteValueMapMenu            matlab.ui.container.Menu
        QuantFactorMapMenu              matlab.ui.container.Menu
        QuantLinAbsMapFromSpectraMenu   matlab.ui.container.Menu
        QuantLinAbsMapFromMapMenu       matlab.ui.container.Menu
        NormMapFromMapMenu              matlab.ui.container.Menu
        NormMapFromSpectraMenu          matlab.ui.container.Menu
        QuantMapsMenu                   matlab.ui.container.Menu
        IntQuantMapwtH2OMenu            matlab.ui.container.Menu
        IntQuantMapggH2OMenu            matlab.ui.container.Menu
        Paterson1982Menu                matlab.ui.container.Menu
        LibowitzkyandRossman1997Menu    matlab.ui.container.Menu
        Balanetal2008Menu               matlab.ui.container.Menu
        ModulesMenu                     matlab.ui.container.Menu
        ThicknessCallibrationModuleMenu  matlab.ui.container.Menu
        ClassificationModuleMenu        matlab.ui.container.Menu
        ImportMatchModuleMenu           matlab.ui.container.Menu
        CorrelationModuleMenu           matlab.ui.container.Menu
        ExportModuleMenu                matlab.ui.container.Menu
        GeoCPlotMenu                    matlab.ui.container.Menu
        MapCalculatorMenu               matlab.ui.container.Menu
        SpecDBMenu                      matlab.ui.container.Menu
        DevelopMenu                     matlab.ui.container.Menu
        DebuggModeMenu_2                matlab.ui.container.Menu
        SubMapstoFigureMenu             matlab.ui.container.Menu
        ROIstoFigureMenu                matlab.ui.container.Menu
        ClassestoFigureMenu             matlab.ui.container.Menu
        CompiledFigureMenu              matlab.ui.container.Menu
        GenerateReportMenu              matlab.ui.container.Menu
        testbuttonsMenu                 matlab.ui.container.Menu
        SpecMaptoFigureMenu             matlab.ui.container.Menu
        ConvertProjecttoSingleprecisionMenu  matlab.ui.container.Menu
        RamanShiftXMenu                 matlab.ui.container.Menu
        SetShiftMenu                    matlab.ui.container.Menu
        ResetShiftMenu                  matlab.ui.container.Menu
        DPlotMenu                       matlab.ui.container.Menu
        GIFPlotMenu                     matlab.ui.container.Menu
        PlotMultiMenu                   matlab.ui.container.Menu
        Add2MultiPlotMenu               matlab.ui.container.Menu
        ImportMapMenu                   matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LoadProject_Button              matlab.ui.control.Button
        AlphaSlider                     matlab.ui.control.Slider
        plot_image_checkbox             matlab.ui.control.CheckBox
        ImageDropDown                   matlab.ui.control.DropDown
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        AutoImport_Button               matlab.ui.control.Button
        GridLayout8_2                   matlab.ui.container.GridLayout
        UpdateInfoButton                matlab.ui.control.Button
        GenerateMapButton               matlab.ui.control.Button
        Int_range_max                   matlab.ui.control.NumericEditField
        Int_range_min                   matlab.ui.control.NumericEditField
        IntRangeLabel                   matlab.ui.control.Label
        MapGenDropDown                  matlab.ui.control.DropDown
        MapGenDropDownLabel             matlab.ui.control.Label
        ThicknessCorrectionCheckBox     matlab.ui.control.CheckBox
        MeanThicknessEditField          matlab.ui.control.EditField
        MeanThicknessEditFieldLabel     matlab.ui.control.Label
        PxSizeXYEditField               matlab.ui.control.EditField
        PxSizeXYEditFieldLabel          matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        InformationTab                  matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        MapInfo_TextArea                matlab.ui.control.TextArea
        SamplingTab                     matlab.ui.container.Tab
        GridLayout9_2                   matlab.ui.container.GridLayout
        FullROIExportButton             matlab.ui.control.Button
        Sampling_SelectStripeButton     matlab.ui.control.Button
        Sampling_SelectTransectButton   matlab.ui.control.Button
        Sampling_SelectAreaButton       matlab.ui.control.Button
        Sampling_SelectCircleButton     matlab.ui.control.Button
        Sampling_ResetButton            matlab.ui.control.Button
        Sampling_ExportButton           matlab.ui.control.Button
        SaveROIMeanSpectraButton        matlab.ui.control.Button
        MapROISpinner                   matlab.ui.control.Spinner
        MapROISpinnerLabel              matlab.ui.control.Label
        Sampling_Plot2                  matlab.ui.control.UIAxes
        Sampling_Plot1                  matlab.ui.control.UIAxes
        Plot_Limit_Settings             matlab.ui.container.TabGroup
        XLimitTab                       matlab.ui.container.Tab
        GridLayout_PlotSettings         matlab.ui.container.GridLayout
        xlimMin_m10                     matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        xlimMax_m10                     matlab.ui.control.Button
        xlimMax_p1                      matlab.ui.control.Button
        xlimMin_p1                      matlab.ui.control.Button
        xlimMin_p10                     matlab.ui.control.Button
        xlimMin_m1                      matlab.ui.control.Button
        xlimMax_m1                      matlab.ui.control.Button
        xlimMax_p10                     matlab.ui.control.Button
        EditField_xlimMax               matlab.ui.control.NumericEditField
        EditField_xlimMin               matlab.ui.control.NumericEditField
        FigXlimLive                     matlab.ui.control.UIAxes
        CLimitTab                       matlab.ui.container.Tab
        GridLayout_PlotSettings_2       matlab.ui.container.GridLayout
        InvertMask                      matlab.ui.control.Image
        UnmaskSelection                 matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        GenerateMaskButton              matlab.ui.control.Button
        climMax_m1                      matlab.ui.control.Button
        climMax_p10                     matlab.ui.control.Button
        climMin_m1                      matlab.ui.control.Button
        climMin_p10                     matlab.ui.control.Button
        climMax_p1                      matlab.ui.control.Button
        climMax_m10                     matlab.ui.control.Button
        EditField_climMax               matlab.ui.control.NumericEditField
        cimMin_p1                       matlab.ui.control.Button
        climMin_m10                     matlab.ui.control.Button
        EditField_climMin               matlab.ui.control.NumericEditField
        FigHistLive                     matlab.ui.control.UIAxes
        DataSettingsTab                 matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        ShowOriginalCheckBox            matlab.ui.control.CheckBox
        SubstractDeconButton            matlab.ui.control.Button
        BCvalueSpinner_2                matlab.ui.control.Spinner
        IgnoreCO2CheckBox               matlab.ui.control.CheckBox
        ResetEditsButton                matlab.ui.control.Button
        ApplytoallSpectraButton         matlab.ui.control.Button
        BackgroundMethodDropDown_2      matlab.ui.control.DropDown
        BackgroundDegreeSpinner         matlab.ui.control.Spinner
        BackgroundMethodDropDown        matlab.ui.control.DropDown
        FilterDegree                    matlab.ui.control.Spinner
        FilterIterationSpinner          matlab.ui.control.Spinner
        FilterMethod                    matlab.ui.control.DropDown
        BackgroundCheckBox              matlab.ui.control.CheckBox
        FilterSpan                      matlab.ui.control.Spinner
        FilterCheckBox                  matlab.ui.control.CheckBox
        ClassesTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        SaveMeanSpectraButton_2         matlab.ui.control.Button
        GeoCPlotButton                  matlab.ui.control.Button
        SaveSpectraButton               matlab.ui.control.Button
        CorrelationModuleButton         matlab.ui.control.Button
        MaskSelectedButton              matlab.ui.control.Button
        ClassificationModuleButton      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ClassTree                       matlab.ui.container.CheckBoxTree
        SampleInfoTab                   matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        MineralEditField                matlab.ui.control.EditField
        MineralEditFieldLabel           matlab.ui.control.Label
        InfraredRadiationEditField      matlab.ui.control.EditField
        PolarisationEditFieldLabel      matlab.ui.control.Label
        OrientationEditField            matlab.ui.control.EditField
        OrientationEditFieldLabel       matlab.ui.control.Label
        SpectraNameEditField            matlab.ui.control.EditField
        SpectraNameEditFieldLabel       matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.EditField
        SampleEditFieldLabel            matlab.ui.control.Label
        ColormapTab                     matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        Options_InvertColormap          matlab.ui.control.CheckBox
        Options_ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel_2         matlab.ui.control.Label
        Options_LogColormap             matlab.ui.control.CheckBox
        Options_ColormapResEditField    matlab.ui.control.NumericEditField
        Options_UpperColor              matlab.ui.control.DropDown
        Options_UpperCheckBox           matlab.ui.control.CheckBox
        Options_LowerColor              matlab.ui.control.DropDown
        Options_LowerCheckBox           matlab.ui.control.CheckBox
        DisplayValuesOutsideData        matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        SpectraTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        DeleteSpectraButton             matlab.ui.control.Button
        AddSpectraButton                matlab.ui.control.Button
        TreeData_Main                   matlab.ui.container.Tree
        Maptable                        matlab.ui.control.Table
        PeaksTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        LoadPeakDBButton                matlab.ui.control.Button
        Peaks_CheckBox                  matlab.ui.control.CheckBox
        SavePeaksButton                 matlab.ui.control.Button
        PeakTree                        matlab.ui.container.CheckBoxTree
        AddPeaksButton                  matlab.ui.control.Button
        PeakDispDropDown                matlab.ui.control.DropDown
        PeakPreviewSwitch               matlab.ui.control.Switch
        PeakPreviewSwitchLabel          matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        AutoPeakDetectionTab            matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        PointstoClassifySpinnerLabel    matlab.ui.control.Label
        PointstoClassifySpinner         matlab.ui.control.Spinner
        DerivativeCheckBox              matlab.ui.control.CheckBox
        MinPeakDistanceSpinner          matlab.ui.control.Spinner
        MinPeakDistanceSpinnerLabel     matlab.ui.control.Label
        MinPeakPromenanceSpinner        matlab.ui.control.Spinner
        MinPeakPromenanceSpinnerLabel   matlab.ui.control.Label
        MinPeakHightSpinner             matlab.ui.control.Spinner
        MinPeakHightSpinnerLabel        matlab.ui.control.Label
        AutoPeakDetectionButton         matlab.ui.control.Button
        PeakDeconvolutionTab            matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ParalellDropDown                matlab.ui.control.DropDown
        ParalellDropDownLabel           matlab.ui.control.Label
        LimitShiftCheckBox              matlab.ui.control.CheckBox
        DELTAEditField                  matlab.ui.control.NumericEditField
        DELTAEditFieldLabel             matlab.ui.control.Label
        ExtraEditField                  matlab.ui.control.NumericEditField
        ExtraEditFieldLabel             matlab.ui.control.Label
        MinWidthEditField               matlab.ui.control.NumericEditField
        MinWidthEditFieldLabel          matlab.ui.control.Label
        MaxPeaksSpinner                 matlab.ui.control.Spinner
        MaxPeaksSpinnerLabel            matlab.ui.control.Label
        AutozeroSpinner                 matlab.ui.control.Spinner
        AutozeroSpinnerLabel            matlab.ui.control.Label
        MinPeaksSpinner                 matlab.ui.control.Spinner
        MinPeaksSpinnerLabel            matlab.ui.control.Label
        IterationsSpinner               matlab.ui.control.Spinner
        IterationsSpinnerLabel          matlab.ui.control.Label
        FitShapeDropDown                matlab.ui.control.DropDown
        FitShapeDropDownLabel           matlab.ui.control.Label
        UIPeaksStartCheckBox            matlab.ui.control.CheckBox
        BipolarCheckBox                 matlab.ui.control.CheckBox
        PlotFigureCheckBox              matlab.ui.control.CheckBox
        MeanAutofitButton               matlab.ui.control.Button
        ClearPeaksButton                matlab.ui.control.Button
        DeletePeakButton                matlab.ui.control.Button
        AddSinglePeakButton             matlab.ui.control.Button
        PeakTable                       matlab.ui.control.Table
        DerivativeSpinnerLabel_2        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SpecMapPlotTab                  matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        ROICheckBox                     matlab.ui.control.CheckBox
        SimpleMapDeconButton            matlab.ui.control.Button
        AdvancedPeakDeconButton         matlab.ui.control.Button
        MaskCheckBox                    matlab.ui.control.CheckBox
        Derivative_CheckBox             matlab.ui.control.CheckBox
        DerivativeSpinnerLabel          matlab.ui.control.Label
        DerivativeSpinner               matlab.ui.control.Spinner
        nEditField                      matlab.ui.control.NumericEditField
        plot_spectra_checkbox           matlab.ui.control.CheckBox
        SpecPlotSetDropDown             matlab.ui.control.DropDown
        SpecPlotSetDropDownLabel        matlab.ui.control.Label
        SpectraTypeDropDown             matlab.ui.control.DropDown
        SpectraTypeDropDownLabel        matlab.ui.control.Label
        TestButton1                     matlab.ui.control.Button
        SpectraPlot                     matlab.ui.control.UIAxes
        MapPlot                         matlab.ui.control.UIAxes
        SingleMapPlotTab                matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        MapPlot_2                       matlab.ui.control.UIAxes
        MultiMapPlotsTab                matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        MapPlot_UR                      matlab.ui.control.UIAxes
        MapPlot_UL                      matlab.ui.control.UIAxes
        MapPlot_OR                      matlab.ui.control.UIAxes
        MapPlot_OL                      matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        SavetoDatabaseModuleMenu        matlab.ui.container.Menu
        ThicknessContextMenu            matlab.ui.container.ContextMenu
        SetConstantThicknessMenu_2      matlab.ui.container.Menu
        OpenThicknessCallibrationMenu_2  matlab.ui.container.Menu
        ContextMenu_Main_Tree           matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
    end


    properties (Access = private)

        selected_sample=0
        selected_subsample=0
        selected_image=[];
        selected_map=0
        selected_submap=0

        SelectedNodes_MainTree

        clickbuffer=0.05
        xlimits = [0 4000]

        plotmat

        fwb=[] %Waitbar

        hVerticalLines
        hLineToDrag

        hVerticalLines_SP
        hLineToDrag_SP

        Always_Update_Map=true


        start_peaks % will be changed

        edit_state

        Peaks

        ROI_sampling % Description

        hFig_tmp
    end

    properties (Access = public)
        SpecMap % Description
        ColorMaps
        ColorMapValues
        ColorScale
        Settings
        SpecXY
        SpecXY_session
    end


    methods (Access = private)

        function import_MapData(app,path,filename,SampleName)
            if contains(filename,'.mat')
                File       = fullfile(path, filename);

                if  any(contains(who('-file', File),'/'),true)
                    newfilename=pyrunfile("fixname_single_file.py","newname",root=path,file_in=filename);
                    newfilename=char(newfilename);
                    File=fullfile(path, newfilename);
                end
                try
                    temp=load(File);
                catch ME

                    ButtonName = questdlg( 'Do you want fix the map (Python needed)?', ...
                        'Mat file corrupt', ...
                        'Yes', 'No','');
                    if strcmp(ButtonName,'Yes')
                        try
                            pyrunfile("fix_mat.py",input=File);
                            disp(['File Fixed: ' File])
                        catch ME
                            disp(ME)
                            disp('Error fixing the file:')
                            disp(File)
                            disp('')
                            disp('Python might be missing. Try fixing file in Python')
                            disp('def fix_mat(f):')
                            disp('    struct=sio.loadmat(str(f))')
                            disp('    sio.savemat(str(f),struct)')
                            disp('')
                            disp('fix_mat(File)')
                            uialert(app.SpecMapsUIFigure,'Error - Python might be missing. Try fixing file in Python','Import Error');

                        end
                        temp=load(File);
                    else
                        return
                    end

                    disp(ME.message)
                end
                SubsampleName=split(filename,'.');
                SubsampleName=char(SubsampleName(1));


                if isfield(temp,'ABMultiple')
                    DataIn=temp.ABMultiple;

                elseif isfield(temp,'AB')
                    DataIn=temp.AB;
                else
                    uialert(app.SpecMapsUIFigure,['Error! ' filename ' was not imported']','Import Error');
                    disp(['Import Error! - ' filename])
                end


            elseif contains(filename,'.csv') || contains(filename,'.txt')
                SubsampleName=split(filename,'.');
                SubsampleName=char(SubsampleName(1));
                DataIn=readmatrix(fullfile(path,filename));
            else
                uialert(app.SpecMapsUIFigure,['Error! ' filename ' was not imported. Filetype not supported.']','Import Error');
                disp(['error importing - ' filename ' -  filetype not supported'])
                %add import wizzard blank
                return
            end

            Mask=1;
            Thickness_map=1e4;
            SpecType=500;
            MetaTable=[];
            Classification=[];
            mapsize_raw=[nan nan];
            etc=[];
            if any(strfind(filename,'_FPA_'))
                if any(strfind(filename,'_bin0')) || any(strfind(filename,'_nobin'))
                    binval=64;
                    etc.FPA_Px_tile=[binval binval];
                elseif any(strfind(filename,'_bin2'))
                    binval=32;
                    etc.FPA_Px_tile=[binval binval];
                elseif any(strfind(filename,'_bin4'))
                    binval=16;
                    etc.FPA_Px_tile=[binval binval];
                elseif any(strfind(filename,'_bin8'))
                    binval=8;
                    etc.FPA_Px_tile=[binval binval];
                elseif any(strfind(filename,'_bin16'))
                    binval=4;
                    etc.FPA_Px_tile=[binval binval];
                end
                SpecType=200;
            end

            if not(exist('SampleName','var'))
                SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
            end

            if exist('newfilename','var')
                delete(fullfile(path, newfilename));
            end

            import_Map(app,SampleName,SubsampleName,DataIn,mapsize_raw,SpecType,Mask,Thickness_map,MetaTable,Classification,etc);
        end

        function update_active_plot(app)
            if isempty(app.SpecMap.Spectra.Names)
                return
            end
            if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))
                update_FigXlimLive(app)
                UpdateMapPlotMatrix(app);


                UpdateSpectraPlot(app)


                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Maps') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map))) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap), 'MapPlotMatrix')
                    update_FigHistLive(app)
                    if not(isequal(app.EditField_climMin.Value,app.EditField_climMax.Value)) % && caxislimits is within data bounds
                        caxis(app.MapPlot,   [min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_2,   [min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_UR,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_UL,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_OL,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_OR,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                    end

                    PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

                    colormap(app.MapPlot,app.ColorMapValues);
                    colormap(app.MapPlot_2,app.ColorMapValues);
                    colormap(app.MapPlot_UR,app.ColorMapValues);
                    colormap(app.MapPlot_UL,app.ColorMapValues);
                    colormap(app.MapPlot_OL,app.ColorMapValues);
                    colormap(app.MapPlot_OR,app.ColorMapValues);

                    app.MapPlot.ColorScale = app.ColorScale;
                    app.MapPlot_2.ColorScale = app.ColorScale;
                    app.MapPlot_UR.ColorScale = app.ColorScale;
                    app.MapPlot_UL.ColorScale = app.ColorScale;
                    app.MapPlot_OL.ColorScale = app.ColorScale;
                    app.MapPlot_OR.ColorScale = app.ColorScale;

                    update_pixelsize(app)

                end
            end
        end

        function UpdateSpectraPlot(app,axis2plot)
            if not(exist('axis2plot','var'))
                axis2plot=app.SpectraPlot;
            end
            cla(axis2plot)
            %color_setting=lines(7);
            color_setting=lines(7).*0+0.5;
            axis2plot.ColorOrder=color_setting;

            axis2plot.LineStyleOrder ='-';
            axis2plot.ColorOrderIndex=1;
            if isempty(app.selected_sample) || (app.plot_spectra_checkbox.Value==false&& app.Derivative_CheckBox.Value ==false)
                return
            end
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);

            if isequal(x_signal,0) || isempty(y_signal)
                return
            end


            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==size(y_signal,2)
                tm=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map';
                y_signal=(y_signal./tm(:)').*1e4;
            elseif  app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1 %&& not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map,1))
                y_signal=(y_signal./app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map).*1e4;
            end

            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==size(y_signal,2) || (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1 && app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map>1))

                ylabel(axis2plot,'Absorbance [cm^{-1}]')

            else
                ylabel(axis2plot,'Absorbance')
            end


            if strcmp(app.SpecPlotSetDropDown.Value,'Default')
                if size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2) > app.nEditField.Value
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2) && sum(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask(:))>0
                        mapsize_val=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                        all_ids=reshape(1:mapsize_val(1)*mapsize_val(2),[],mapsize_val(2))';

                        mask_vector=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                        all_ids(mask_vector==0)=nan;
                        datapoints=sort(all_ids(not(isnan(all_ids))));
                        if numel(datapoints)>=app.nEditField.Value
                            samplepoints=sort(randperm(numel(datapoints),app.nEditField.Value));
                            samplepoints=datapoints(samplepoints);
                        else
                            samplepoints=datapoints;
                        end

                    elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2) && sum(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask(:))==0
                        return
                    elseif size(y_signal,2)<=app.nEditField.Value
                        samplepoints=sort(randperm(size(y_signal,2),size(y_signal,2)));
                    else
                        samplepoints=sort(randperm(size(y_signal,2),app.nEditField.Value));
                    end

                    app.plotmat=y_signal(:,samplepoints);
                else
                    app.plotmat=y_signal;


                end
            elseif strcmp(app.SpecPlotSetDropDown.Value,'Sum')
                mask=(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)';
                if numel(mask)==size(y_signal,2)
                    app.plotmat=sum(y_signal(:,mask(:)),2,"omitnan");
                else
                    app.plotmat=sum(y_signal,2,"omitnan");
                end
            elseif strcmp(app.SpecPlotSetDropDown.Value,'Mean')
                mask=(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)';
                if numel(mask)==size(y_signal,2)
                    app.plotmat=mean(y_signal(:,mask(:)),2,"omitnan");
                else
                    app.plotmat=mean(y_signal,2,"omitnan");
                end
            elseif strcmp(app.SpecPlotSetDropDown.Value,'All')
                app.plotmat=y_signal;
            elseif strcmp(app.SpecPlotSetDropDown.Value,'All ROI') || strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked') || strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')   % ROI & ROI Stacked

                try

                    data=struct();
                    settings=struct();
                    settings(1).x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                    %settings(1).y_offset=1+(app.YOffsetSpinner.Value/100);
                    settings(1).y_offset=1+(15/100);

                    settings(1).reset=true;
                    if strcmp(app.SpecPlotSetDropDown.Value,'All ROI') || strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')
                        settings(1).type='Default';
                    elseif strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked')
                        settings(1).type='Stacked';
                    end
                    settings(1).PlotLegend=false;%app.LegendCheckBox.Value;
                    settings(1).PlotSpecNames=false;%app.Annotation_CheckBox.Value;

                    if strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')
                        selected_ROI_n=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI;
                    else
                        selected_ROI_n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI);
                    end

                    app.plotmat=zeros(size(y_signal,1),numel(selected_ROI_n));

                    mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');%all_ids(not(mask_vector(:)));


                    for n=1:numel(selected_ROI_n)
                        selected_ROI=selected_ROI_n(n);
                        settings(n).PlotSpectra=true;
                        settings(n).PlotSignal=false;
                        settings(n).PlotMean=true;
                        settings(n).PlotSum=false;
                        settings(n).PlotRaw=false;
                        settings(n).PlotBaseline=false;
                        settings(n).PlotPeaks=false;

                        settings(n).Color.Spectra=[0 0 0];
                        settings(n).Color.Mean=[0 0 0];
                        settings(n).Color.Sum=[0 0 0];
                        settings(n).Color.Raw=[0 0 0];
                        settings(n).Color.Baseline=[0 0 0];
                        settings(n).Color.Peaks=[0 0 0];

                        settings(n).LineStyle.Spectra={'A-'};
                        settings(n).LineStyle.Signal={'-'};
                        settings(n).LineStyle.Mean={'-'};
                        settings(n).LineStyle.Sum={'-'};
                        settings(n).LineStyle.Raw={'-'};
                        settings(n).LineStyle.Baseline={'--'};
                        settings(n).LineStyle.Peaks={'Line'};

                        settings(n).LineWidth.Spectra=2;%0.1;
                        settings(n).LineWidth.Signal=2;
                        settings(n).LineWidth.Mean=2;
                        settings(n).LineWidth.Sum=1;
                        settings(n).LineWidth.Raw=1;
                        settings(n).LineWidth.Baseline=1;
                        settings(n).LineWidth.Peaks=1;

                        settings(n).FaceAlpha.Spectra=0.2;
                        settings(n).FaceAlpha.Signal=0.2;
                        settings(n).FaceAlpha.Mean=2;
                        settings(n).FaceAlpha.Sum=0.2;
                        settings(n).FaceAlpha.Raw=0.2;
                        settings(n).FaceAlpha.Baseline=0.2;
                        settings(n).FaceAlpha.Peaks=0.2;

                        %  ROI=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Idx;
                        ROI=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.createMask()');%app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.createMask()');
                        ROI=ROI(:);
                        if numel(mask_ids)>1
                            ROI=intersect(ROI,mask_ids);
                        end
                        if isempty(ROI)
                            app.plotmat(:,n)=0;

                            data(n).y_signal_mean= app.plotmat(:,selected_ROI);
                            data(n).y_signal= app.plotmat(:,selected_ROI);
                            data(n).x_signal= x_signal;
                        else

                            [wn_cor,in_cor,~] = get_specs_cor(app,sampleinfo,app.xlimits,[x_signal,y_signal(:,ROI)],false);

                            app.plotmat(:,n)=mean(y_signal(:,ROI),2,"omitnan");
                            data(n).y_mean=mean(in_cor,2,"omitnan");
                            data(n).y_signal=in_cor;
                            data(n).x_signal=wn_cor;
                        end
                    end


                    PlotSpectra_fun(axis2plot,data,settings)

                catch ME
                    WhichOperation = 'Update SpectraPlot - ROI error';
                    DisplayError(ME,WhichOperation)
                end


                %             elseif strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked')
                %                 app.plotmat=sum(in(:,ROI),2,"omitnan");
            elseif strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Mean')
                try
                    app.plotmat=zeros(size(y_signal,1),numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));
                    for selected_ROI=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)
                        mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');%all_ids(not(mask_vector(:)));
                        % ROI=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.createMask()');
                        ROI=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Idx;
                        if numel(mask_ids)>1
                            ROI=intersect(ROI,mask_ids);
                        end

                        app.plotmat(:,selected_ROI)=mean(y_signal(:,ROI),2,"omitnan");
                    end
                catch ME
                    WhichOperation = 'Update SpectraPlot - ROI error Mean';
                    DisplayError(ME,WhichOperation)
                end

                axis2plot.ColorOrder= PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));

                axis2plot.LineStyleOrder ='-';
                axis2plot.ColorOrderIndex=1;

            elseif strcmp(app.SpecPlotSetDropDown.Value,'Classes Mean')

                N=app.ClassTree.SelectedNodes;
                if isempty(N)
                    return
                end
                selectedNodes = N.NodeData;


                if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                    matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                    n_classes=numel(unique(matrix(not(isnan(matrix)))));
                    app.plotmat=zeros(size(y_signal,1),n_classes);

                    test=true(1,n_classes);
                    if sum(ismember(app.ClassTree.SelectedNodes.Children,app.ClassTree.CheckedNodes))>0
                        test=ismember(app.ClassTree.SelectedNodes.Children,app.ClassTree.CheckedNodes);
                    end

                    for m=1:n_classes
                        selected_class=(matrix==m)';
                        mask_vector=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                        if isequal(size(selected_class),size(mask_vector))
                            selected_class=(matrix==m & mask_vector)';
                        end

                        selected_class=selected_class(:);
                        if  test(m)==true
                            app.plotmat(:,m)=mean(y_signal(:,selected_class),2,"omitnan");
                        else
                            app.plotmat(:,m)=nan(size(mean(y_signal(:,selected_class),2,"omitnan")));

                        end
                    end
                    color_setting= PlotMap_UpdateColormap(app,n_classes,1);
                    axis2plot.ColorOrder=color_setting;
                elseif numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)
                    matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                    selected_class=(matrix==selectedNodes(3))';

                    if app.MaskCheckBox.Value==true
                        mask_vector=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                        if isequal(size(selected_class),size(mask_vector))
                            selected_class=(selected_class & mask_vector)';
                        end
                    end
                    selected_class=selected_class(:);
                    app.plotmat=mean(y_signal(:,selected_class),2,"omitnan");

                    matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                    n_classes=numel(unique(matrix(not(isnan(matrix)))));
                    color_setting= PlotMap_UpdateColormap(app,n_classes,1);
                    axis2plot.ColorOrder=color_setting;
                    axis2plot.ColorOrderIndex=selectedNodes(3);

                end


            end


            %offset_wn=0;


            if app.plot_spectra_checkbox.Value && not(any([strcmp(app.SpecPlotSetDropDown.Value,'All ROI'), strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked') , strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')]))
                if app.ShowOriginalCheckBox.Value==true
                    c_order_index=   axis2plot.ColorOrderIndex;
                    plot(axis2plot,x_signal,app.plotmat,'LineWidth',0.5,'LineStyle',':')
                    axis2plot.ColorOrderIndex=c_order_index;
                end

                [x_signal,app.plotmat,~] = get_specs_cor(app,sampleinfo,app.xlimits,[x_signal,app.plotmat],false);
                %   plot(axis2plot,x_signal,app.plotmat,'LineWidth',0.05)
                plot(axis2plot,x_signal,app.plotmat,'LineWidth',2)
            else
                if app.plot_spectra_checkbox.Value==true
                    plot(axis2plot,x_signal,app.plotmat,'LineWidth',2)
                end
                % plot(axis2plot,x_signal,app.plotmat,'LineWidth',0.2)


                % max_in=max(app.plotmat,[],'all');
            end

            if not(isempty(app.PeakTable.Data)) %&& app.Peaks_CheckBox.Value==true || strcmp(app.PeakPreviewSwitch.Value,'On')
                Peak_marker_offset_value=0.25; %ATH add settings
                offset=max(app.plotmat,[],'all')*Peak_marker_offset_value*1.1;

                PeakPosition=app.PeakTable.Data.PeakPosition;
                PeakPosition=PeakPosition(PeakPosition>min(app.xlimits) & PeakPosition<max(app.xlimits));
                PeakIntensity=nan(size(PeakPosition));
                for m=1:length(PeakPosition)
                    [ ~, ix ] = min( abs(x_signal-PeakPosition(m) ) );
                    PeakIntensity(m)=max(app.plotmat(ix,:));
                end

                if strcmp(app.PeakPreviewSwitch.Value,'On')
                    if strcmp(app.PeakDispDropDown.Value,'Default')
                        plot(axis2plot,PeakPosition,PeakIntensity+offset,"Marker",'v','MarkerFaceColor','k','MarkerSize',10,'LineStyle','none')
                    end
                    if strcmp(app.PeakDispDropDown.Value,'Line') || strcmp(app.PeakDispDropDown.Value,'Default')

                        x=[PeakPosition';PeakPosition'];
                        y=[zeros(size(PeakIntensity'));PeakIntensity'];

                        if strcmp(app.PeakDispDropDown.Value,'Stacked')
                            y=[repmat(min(app.plotmat,[],"omitnan"),size(PeakIntensity'));PeakIntensity'];
                        end
                        plot(axis2plot,x,y ,'--k','linewidth',2);

                    elseif strcmp(app.PeakDispDropDown.Value,'Xline')
                        xline(axis2plot,PeakPosition,'--k','linewidth',2)
                    end
                end

                if app.Peaks_CheckBox.Value==true

                    offset_wn=max(app.plotmat,[],'all')*Peak_marker_offset_value*1.5; %ATH settings add peak number offset value
                    text(axis2plot,PeakPosition,PeakIntensity+offset_wn,num2cell(PeakPosition),"HorizontalAlignment","center")

                    pos=app.PeakTable.Data.PeakPosition;
                    wid=app.PeakTable.Data.Width;
                    amp=app.PeakTable.Data.PeakIntensity;
                    xwn=x_signal';
                    %
                    peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
                    extra=0;
                    DELTA=0;


                    y_calc=DeconPlotFun(peakshape,x_signal,pos',wid',amp',extra',DELTA')';

                    if not(isequal(amp,0) && isequal(wid,0))

                        plot(axis2plot,xwn',sum(y_calc)','LineWidth',2,'Color','g','LineStyle','-')
                        plot(axis2plot,xwn',y_calc','LineWidth',1,'Color','g','LineStyle','-')
                    end
                end
            end

            if app.Derivative_CheckBox.Value
                yyaxis(axis2plot,"right");
                cla(axis2plot)
                axis2plot.LineStyleOrder ='-';
                axis2plot.ColorOrderIndex=1;

                axis2plot.YAxis(2).Visible='on';

                deriv_plotmat=app.plotmat;

                if not(app.DerivativeSpinner.Value==0)

                    for i=1:abs(app.DerivativeSpinner.Value)
                        for m=1:size(deriv_plotmat,2)
                            deriv_plotmat(:,m)=gradient(deriv_plotmat(:,m));
                        end
                    end

                    if app.DerivativeSpinner.Value<0
                        deriv_plotmat=-deriv_plotmat;
                    end

                end

                %%%%%%%

                plot(axis2plot,x_signal,deriv_plotmat,'LineWidth',2,'LineStyle','-.');

                ylabel(axis2plot,'Derivative')
                yyaxis(axis2plot,"left");

            else

                yyaxis(axis2plot,"right");
                cla(axis2plot)
                yyaxis(axis2plot,"left");
                axis2plot.YAxis(2).Visible='off';

                %%%%%%%
                %                 plot(axis2plot,x_signal,deriv_plotmat,'LineWidth',2)
            end
            if min(app.xlimits)==max(app.xlimits)
                xlim(axis2plot,'auto')
            else
                xlim(axis2plot,sort([min(app.xlimits) max(app.xlimits)]))
            end

            update_lines(app,axis2plot)
        end

        function update_lines(app,axis2plot)
            if not(exist('axis2plot','var'))
                axis2plot=app.SpectraPlot;
            end
            try
                %                 c2d=[];
                %                 for n=1:numel(axis2plot.Children)
                %                     if isa(axis2plot.Children(n),'matlab.graphics.chart.decoration.ConstantLine')
                %                         c2d(n)=n;
                %                     end
                %                 end
                %                 delete(axis2plot.Children(c2d))

                delete(findobj(axis2plot.Children,'Type','ConstantLine'));


                if numel(app.hVerticalLines_SP)==2
                    if isa(app.hVerticalLines_SP(2),'matlab.graphics.chart.decoration.ConstantLine')
                        delete(app.hVerticalLines_SP(2))
                    else
                        app.hVerticalLines_SP(2)=[];
                    end
                end

                if not(isempty(app.hVerticalLines_SP)) && isa(app.hVerticalLines_SP(1),'matlab.graphics.chart.decoration.ConstantLine')
                    delete(app.hVerticalLines_SP(1))
                elseif isempty(app.hVerticalLines_SP)
                    % nothing to do here?
                else
                    app.hVerticalLines_SP(1)=[];
                end
            catch ME
                WhichOperation = 'update_lines error';
                DisplayError(ME,WhichOperation)
            end

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                app.hVerticalLines_SP(1) = xline(axis2plot,app.Int_range_max.Value,'Color','red',"LineWidth",3);
                set(app.hVerticalLines_SP(1), 'hittest', 'off')
            else
                app.hVerticalLines_SP(1:2) = [xline(axis2plot,app.Int_range_max.Value,'Color','k',"LineWidth",3)...
                    xline(axis2plot, app.Int_range_min.Value,'Color','k',"LineWidth",3)];
                set(app.hVerticalLines_SP(1:2), 'hittest', 'off')
            end
        end

        function  sampleinfo=UpdateMapPlotMatrix(app,sampleinfo,maptype2generate,density,E)
            update_state=false;

            if ~exist('maptype2generate','var') || isempty(maptype2generate)
                maptype2generate=app.MapGenDropDown.Value;
            else
                update_state=true;
                app.Always_Update_Map=true;
            end

            if ~exist('sampleinfo','var') || isempty(sampleinfo)
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                update_state=true;
            end
            if ~exist('E','var')
                E=[];
            end

            if ~exist('density','var')
                density=[];
            end

            xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];


            if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize),1)>=sampleinfo(3) && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:))) && not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:),[0 0]))
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:);
            else
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
            end

            MatrixLimits=update_MatixLimits(app);

            if any(isnan(MatrixLimits),'all') || any(isnan(mapsize),'all')
                autoshape_matrix(app)
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
                if any(isnan(MatrixLimits),'all') || any(isnan(mapsize),'all')
                    return
                end
            end

            Settings_new.mapsize=mapsize;
            Settings_new.MatrixLimits=MatrixLimits;
            Settings_new.SpecType=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.SpecType;
            Settings_new.MapGen=maptype2generate;

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                region=app.Int_range_max.Value;
            else
                region=[app.Int_range_min.Value app.Int_range_max.Value];
                % check here if region within x_signal otherwise uialert and
                % reset intrange
            end
            Settings_new.region=region;
            Settings_new.xregion=xregion;
            Settings_new.E=[];
            Settings_new.density=[];

            new_mat_state=false;

            reset=true;
            plotimage=true;
            new_name=true;

            update=[1 10 30 31 32];
            %no_t_correction=[30 31 53 54 55 56 110 111 112 113 114 140 151 152 153 154 155 1000];

            if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)),'MapType') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType)>=sampleinfo(3)
                MapType=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3));


            else
                MapType=false;
                new_mat_state=true;
            end


            if app.Always_Update_Map==true
                new_mat_state=true;
                new_name=true;
                app.Always_Update_Map=false;
            elseif ~ismember(MapType,update) && not(new_mat_state==true)
                new_mat_state=false;
                new_name=false;

            elseif  not(isfield(app.SpecMap.Spectra.Sample,'Settings')) || not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'MapPlotSettings'))
                new_mat_state=true;
                new_name=true;
            elseif isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)),'MapType') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType)>=sampleinfo(3) && app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))>50
                new_mat_state=false;
            elseif not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)), 'Maps')) || isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)), 'Maps') && isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps) || numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps)<sampleinfo(3)
                new_mat_state=true;
                new_name=true;
            elseif not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)), 'SubMap')) || isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)), 'SubMap') && isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap) || numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap)<sampleinfo(4)
                new_mat_state=true;
                new_name=true;
            elseif not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)), 'MapPlotMatrix')) || isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)), 'MapPlotMatrix') && isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix)
                new_mat_state=true;
                new_name=true;
                %  elseif isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)),'MapPlotSettings') && numel(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings))<=sampleinfo(2) || isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings)
            elseif not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)),'MapPlotSettings')) || numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings)<sampleinfo(2) || isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings)
                new_mat_state=true;
                new_name=true;
            end


            if new_mat_state==true
                % get the uncorrected Spectra
                [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);


                [matrix,MapType,MapName]= CalcMatrixFromSpec(x_signal,y_signal,maptype2generate,region,mapsize,density,E,new_name);


                Settings_new.E=E;

                Settings_new.density=density;


                %% insert the data
                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)),'MapNames')
                    if new_name==true
                        app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=MapName;
                    end
                    sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
                    sampleinfo(4)=1;
                else
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{1}=MapName;
                    sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
                    sampleinfo(4)=1;

                end
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=MapType;


                if update_state==true
                    app.selected_sample=sampleinfo(1);
                    app.selected_subsample=sampleinfo(2);
                    app.selected_map=sampleinfo(3);
                    app.selected_submap=sampleinfo(4);
                    UpdateTree(app);
                end

                app.edit_state=true;

            else
                try
                    matrix=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix;
                catch ME
                    WhichOperation = 'Error in UpdateMapPlotMatrix - empty map';
                    DisplayError(ME,WhichOperation)
                    return
                end
            end

            if update_state==true
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                    matrix(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask
                end

                matrix=ThicknessCorrectionFun(app,matrix);

                % disp('matrix already exists')

                %linlogchange start
                if app.Options_LogColormap.Value==true
                    if any(matrix(:)<0)
                        message={'Negative numbers ignored for log plot.','Generate normalized map to display all values.'};
                        uialert(app.SpecMapsUIFigure,message,'Error');
                        matrix(matrix<0)=nan;
                        %app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=[];
                    end
                    matrix=log(matrix);
                end
                %linlogchange end
                plt_settings.plt_ROI=app.ROICheckBox.Value;
                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings)>=sampleinfo(2) && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings)>=sampleinfo(3)&& not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)),'E'))
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).E=[];
                end

                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings)>=sampleinfo(2) && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings)>=sampleinfo(3)&& not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)),'density'))
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).density=[];
                end

                try
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3))=Settings_new;
                catch ME
                    WhichOperation = 'Error in UpdateMapPlotMatrix - Settings_new';
                    DisplayError(ME,WhichOperation)

                end
                if strcmp(app.TabGroup.SelectedTab.Title,'SpecMapPlot')
                    UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,reset,plotimage,[],plt_settings)
                elseif  strcmp(app.TabGroup.SelectedTab.Title,'Single Map Plot')
                    UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,reset,plotimage,[],plt_settings)
                end

                if strcmp(app.TabGroup.SelectedTab.Title,'MultiMapPlots') && ~any(mapsize(:) == 1)
                    if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                        mask=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                    else
                        mask=ones(size(matrix));
                    end


                    alpha=zeros(size(matrix))+0.25;
                    alpha(mask==0)=0;
                    UpdateMapPlot(app,app.MapPlot_UL,matrix,MatrixLimits,reset,true,alpha,plt_settings)

                    alpha=zeros(size(matrix));
                    nanmat=nan(size(matrix));
                    UpdateMapPlot(app,app.MapPlot_OL,nanmat,MatrixLimits,reset,true,alpha,plt_settings)

                    alpha=zeros(size(matrix))+0.75;
                    alpha(mask==0)=0;
                    UpdateMapPlot(app,app.MapPlot_OR,matrix,MatrixLimits,reset,true,alpha,plt_settings)


                    alpha=ones(size(matrix));
                    UpdateMapPlot(app,app.MapPlot_UR,matrix,MatrixLimits,reset,false,alpha,plt_settings)

                    xlim(app.MapPlot_UL,'auto')
                    ylim(app.MapPlot_UL,'auto')
                    linkaxes([app.MapPlot_UL,app.MapPlot_UR,app.MapPlot_OR,app.MapPlot_OL],'xy')
                end

            end

            N=[]; %#ok
            try
                selectedNodes=sampleinfo;

                N=app.TreeData_Main.Children(selectedNodes(1)); %#ok
                N=app.TreeData_Main.Children(selectedNodes(1)).Children(selectedNodes(2)); %#ok
                N=app.TreeData_Main.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3)); %#ok
                N=app.TreeData_Main.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3)).Children(selectedNodes(4)); %#ok
                N=app.TreeData_Main.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3)).Children(selectedNodes(4)).Children(selectedNodes(5)); %#ok

            catch
                N=app.TreeData_Main.SelectedNodes;

                if isempty(N)|| N(end)==0
                    return
                end
                app.TreeData_Main.SelectedNodes=N;
            end
            N=app.TreeData_Main.SelectedNodes;
            if isempty(N)|| N(end)==0
                return
            end


            app.TreeData_Main.SelectedNodes=N;
        end

        function  UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings,meta_x,meta_y)
            if reset==1
                cla(Axis2Plot)
            end

            if (app.plot_image_checkbox.Value==true && plotimage==true) || (isempty(matrix)==true && plotimage==true)
                if not(isempty(app.selected_image)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id))
                    try
                        Image=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image{app.selected_image};
                        ImageLimits=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits{app.selected_image};
                        image(Axis2Plot,flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));

                        r=rectangle(Axis2Plot,'Position',[MatrixLimits(1,1) MatrixLimits(2,1) (MatrixLimits(1,2)-MatrixLimits(1,1)) (MatrixLimits(2,2)-MatrixLimits(2,1))]);
                        r.EdgeColor = 'b';
                        r.LineWidth = 3;
                    catch ME
                        WhichOperation = 'Error UpdateMapPlot - image';
                        DisplayError(ME,WhichOperation)
                    end
                end
            end


            if not(isempty(matrix))
                if ~any(size(matrix) == 1) && any(~isnan(MatrixLimits),'all')

                    % this section could be implemented later if masked pixels need to be nan
                    % alpha data for nan pixels have to be changed as well
                    %                     if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2)
                    %                         mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                    %                         matrix(mask==false)     =nan;
                    %                     end

                    if exist('alpha', 'var') && not(isempty(alpha))
                        imagesc(Axis2Plot,matrix,'XData', MatrixLimits(1,:), 'YData', MatrixLimits(2,:), 'AlphaData', alpha);
                    else
                        alpha=ones(size(matrix))*app.AlphaSlider.Value./100;

                        imagesc(Axis2Plot,matrix,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:), 'AlphaData', alpha);
                    end

                    daspect(Axis2Plot,[1 1 1])
                else
                    daspect(Axis2Plot,'auto')

                    try
                        if not(exist('meta_x', 'var') && not(isempty(meta_x)) && exist('meta_y', 'var') && not(isempty(meta_y)))
                            meta_x=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type~=13);
                            meta_y=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type~=13);
                        end

                        if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2)
                            mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask';
                        else
                            mask= true(size(meta_x));
                        end

                        px_size=mode(abs((diff(meta_y))));

                        if px_size==0 || not(isfinite(px_size))
                            px_size=10;
                        end
                        scatter(Axis2Plot,meta_x(mask),meta_y(mask),px_size,matrix(mask),"filled",'MarkerEdgeAlpha',1,'MarkerFaceAlpha',app.AlphaSlider.Value/100,Marker='square')

                        %                          xlim(Axis2Plot,'auto')
                        %                          ylim(Axis2Plot,'auto')

                        xlim(Axis2Plot,[min(meta_x)*0.95 max(meta_x)*1.1])
                        ylim(Axis2Plot,[min(meta_y)*0.95 max(meta_y)*1.1])

                        originalUnits = Axis2Plot.Units;
                        Axis2Plot.Units = 'Points';
                        axSizePix = Axis2Plot.Position(3:4);
                        Axis2Plot.Units = originalUnits;

                        pixPerUnit = axSizePix ./ [range(xlim(Axis2Plot)),range(ylim(Axis2Plot))];  %pixels per unit
                        pxsz=ceil(max(pixPerUnit)*px_size)^2;


                        cla(Axis2Plot)
                        %daspect(Axis2Plot,[1 1 1])
                        scatter(Axis2Plot,meta_x(mask),meta_y(mask),pxsz,matrix(mask),"filled",'MarkerEdgeAlpha',1,'MarkerFaceAlpha',app.AlphaSlider.Value/100,Marker='square')

                        xlim(Axis2Plot,[min(meta_x)*0.95 max(meta_x)*1.1])
                        ylim(Axis2Plot,[min(meta_y)*0.95 max(meta_y)*1.1])
                        axis(Axis2Plot, 'image')
                    catch ME
                        WhichOperation = 'Error UpdateMapPlot - map';
                        DisplayError(ME,WhichOperation)
                    end

                    %                     if size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2) > 250 && not(strcmp(app.SpecPlotSetDropDown.Value,'All'))
                    %                         plot(Axis2Plot,matrix(round(linspace(1,size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2),250))),'LineWidth',2);
                    %                     else
                    %                         plot(Axis2Plot,matrix,'LineWidth',2);
                    %                     end
                end

                %colorbar(Axis2Plot) %ATH Settings
                cb = colorbar(Axis2Plot);
                if exist('plt_settings','var') && isfield(plt_settings,'cb_label') && not(isempty(plt_settings.cb_label))
                    cb.Label.String = plt_settings.cb_label;
                else
                    cb.Label.String = '';
                end
            end

            if exist('ImageLimits','var') && not(isempty(ImageLimits))&& not(isempty(app.selected_image)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id))&&app.plot_image_checkbox.Value==true && plotimage==true
                Axis2Plot.XLim= ImageLimits(1,:);
                Axis2Plot.YLim= ImageLimits(2,:);
            else
                Axis2Plot.XLim= MatrixLimits(1,:);
                Axis2Plot.YLim= MatrixLimits(2,:);
            end
            Axis2Plot.YDir='normal';
            %% new ROI


            if not(isempty(app.selected_sample))
                try
                    if not(exist('plt_settings','var')) || not(isfield(plt_settings,'plt_ROI')) || plt_settings.plt_ROI==true

                        if  not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) || not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling')) ||isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling)

                            if  not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI'))
                                app.SpecMap.Spectra.Sample(app.selected_sample).ROI=struct();
                            end

                        else
                            c_=  PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));
                            PlotMap_UpdateColormap(app);
                            for selected_ROI=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)
                                if strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Mean')
                                    %  color_setting=PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI),1);

                                    %  c=Axis2Plot.ColorOrder(selected_ROI,:);
                                    c=c_(selected_ROI,:);
                                    % c=color_setting(selected_ROI,:);
                                    interactions='none';
                                    rotatable=false;

                                else
                                    if selected_ROI==app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI && app.plot_image_checkbox.Value==false && not(isempty(matrix))
                                        c=[1,0,0];
                                        interactions='all';
                                        rotatable=true;
                                    else
                                        c=[0.47,0.67,0.19];
                                        interactions='none';
                                        rotatable=false;

                                    end
                                end
                                if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save))
                                    Method = char(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Method);
                                else
                                    Method = [];
                                end
                                if isempty(Method)
                                    return
                                end

                                Coordinates = app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Coordinates;
                                switch Method
                                    case 'Rectangle ROI'
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling= drawrectangle(Axis2Plot,'Position',Coordinates,'Color',c,'InteractionsAllowed',interactions,'Rotatable',rotatable);
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.RotationAngle=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.RotationAngle;
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_strip(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling));
                                        if selected_ROI==app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI
                                            Sampling_ROI_changed_strip(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling);
                                        end
                                    case 'Polygon ROI'
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling= drawpolygon(Axis2Plot,'Position',Coordinates,'Color',c,'InteractionsAllowed',interactions);
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_shape(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling,Method));
                                        if selected_ROI==app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI
                                            Sampling_ROI_changed_shape(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling,'Polygon ROI');
                                        end
                                    case 'Ellipse ROI'
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling = drawellipse(Axis2Plot,'Center',Coordinates(1:2),'SemiAxes',Coordinates(3:4),'RotationAngle',Coordinates(5),'Color',c,'InteractionsAllowed',interactions);
                                    case 'Polyline ROI'
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling = drawpolyline(Axis2Plot,'Position',Coordinates,'Color',c,'InteractionsAllowed',interactions);
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_line(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling));
                                        if selected_ROI==app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI
                                            Sampling_ROI_changed_line(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling);
                                        end
                                    case 'Circle ROI'
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling= drawcircle(Axis2Plot,'Center',Coordinates(1:2),'Radius',Coordinates(3),'Color',c,'InteractionsAllowed',interactions);
                                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_shape(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling,Method));
                                        if selected_ROI==app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI
                                            Sampling_ROI_changed_shape(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling,'Circle ROI');
                                        end
                                end
                                if strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Mean') %ATH settings
                                    app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Label=num2str(selected_ROI);
                                end
                            end
                        end
                    end

                catch ME
                    disp('Error in UpdateMapPlot ROI Update')
                    disp(ME.message)

                end
            end
        end

        function matrix=ThicknessCorrectionFun(app,matrix,sampleinfo)
            if ~exist('sampleinfo','var')
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            end
            if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map')
                tmap=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map;
            else
                tmap=1e4;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map=tmap;
            end
            no_t_correction=[30 31 32 33 53 54 55 56 110 111 112 113 114 140 150 151 152 153 154 155  997 998 1000];
            MapType=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3));
            if app.ThicknessCorrectionCheckBox.Value==true && ~ismember(MapType,no_t_correction) && numel(tmap)==1 || numel(tmap)==numel(matrix)
                matrix=(matrix./tmap).*1e4;
            end
        end

        function  update_FigXlimLive(app)
            cla(app.FigXlimLive)
            if isempty(app.selected_sample) && isempty(app.selected_subsample)

                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                set(app.hVerticalLines(1:2), 'hittest', 'off');
                xlim(app.FigXlimLive,[app.FigXlimLive,app.EditField_xlimMin.Value app.EditField_xlimMax.Value])

            else
                y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'MetaPrivat') &&isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(y_signal,2)
                    mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask';
                    matrix_masked=y_signal.*mask(:)';

                    plotmat_lim= mean(matrix_masked,2,"omitnan");
                else
                    plotmat_lim= mean(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2,"omitnan");
                end

                a_h=area(app.FigXlimLive,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal,plotmat_lim,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
                set(a_h, 'hittest', 'off');

                x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;

                range=x_signal>=app.EditField_xlimMin.Value & x_signal<=app.EditField_xlimMax.Value;
                x_signal=x_signal(range,:);
                y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal(range,:);

                if app.FilterCheckBox.Value==true
                    for n=1:app.FilterIterationSpinner.Value

                        if strcmp(app.FilterMethod.Value,'sgolay')
                            y_signal=smoothdata(y_signal,1,'sgolay',app.FilterSpan.Value,'Degree',app.FilterDegree.Value);
                        else
                            y_signal=smoothdata(y_signal,1,app.FilterMethod.Value,app.FilterSpan.Value);
                        end
                    end
                end


                y_signal = max(plotmat_lim)./(max(mean(y_signal,2,"omitnan"))./mean(y_signal,2,"omitnan"));

                l_h=plot(app.FigXlimLive,x_signal,y_signal,'LineStyle',':','Color','k');
                set(l_h, 'hittest', 'off');

                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];

                set(app.hVerticalLines(1:2), 'hittest', 'off');

                xlimval=[min([app.EditField_xlimMin.Value min(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)])...
                    max([app.EditField_xlimMax.Value max(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)])];
                try
                    xlim(app.FigXlimLive,xlimval)
                catch
                    disp('no xlim update in xlim(app.FigXlimLive,xlimval')
                end

            end
        end

        function  update_FigHistLive(app)

            if isempty(app.selected_sample)

                app.EditField_climMin.Value=0;
                app.EditField_climMax.Value=1;
                cla(app.FigHistLive)
                return
            end

            data=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
            if isempty(data)
                return
            end

            data=ThicknessCorrectionFun(app,data);
            if app.Options_LogColormap.Value==true
                data(data<0)=nan;
                data=log(data);
            end
            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix)
                data=data(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask);
            end
            update_state=false;
            if app.DisplayValuesOutsideData.Value==false

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Maps') &&...
                        isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map),'SubMap') &&...
                        isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap),'climits')
                    if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits))
                        if app.Options_LogColormap.Value==false
                            app.EditField_climMin.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1);
                            app.EditField_climMax.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2);
                        else
                            try
                                app.EditField_climMin.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1));
                                app.EditField_climMax.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2));
                            catch
                                update_state=true;
                            end
                        end
                    else
                        data(isinf(data))=0;
                        climit=[min(min(data)) std2(data)+mean(data,'all','omitnan')];

                        app.EditField_climMin.Value=min(climit);
                        app.EditField_climMax.Value=max(climit);
                    end
                else

                    data(isinf(data))=0;
                    %      climit=[min(min(data)) std2(data)+mean(data,'all','omitnan')];
                    %                 if std2(data)-mean(data,'all','omitnan')>min(data,[],'all')
                    %                     climit=[std2(data)-mean(data,'all','omitnan') std2(data)+mean(data,'all','omitnan')];
                    %                 else
                    climit=[min(data,[],'all') std2(data)+mean(data,'all','omitnan')];

                    % end
                    app.EditField_climMin.Value=min(climit);
                    app.EditField_climMax.Value=max(climit);
                end
            end

            %    [N,EDGES] = histcounts(data);
            num_bars=100;

            [N, EDGES] = histcounts(data(:),num_bars); %// use two-output version of hist to get values


            EDGES=linspace(EDGES(1),EDGES(end),num_bars);

            %N = N/numel(data)/(EDGES(2)-EDGES(1)); %// normalize to unit area


            a_h=area(app.FigHistLive,EDGES,N,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);


            set(a_h, 'hittest', 'off');


            xlimits_test = [min(EDGES) max(EDGES)];
            if app.DisplayValuesOutsideData.Value==false
                if update_state==true || app.EditField_climMin.Value< min(xlimits_test)|| app.EditField_climMax.Value> max(xlimits_test)

                    if app.Options_LogColormap.Value==true
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1)=exp(min(EDGES));
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)=exp(max(EDGES));
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1)=min(EDGES);
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)=max(EDGES);
                    end
                    if app.Options_LogColormap.Value==false
                        app.EditField_climMin.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1);
                        app.EditField_climMax.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2);
                    else
                        app.EditField_climMin.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1));
                        app.EditField_climMax.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2));
                    end
                    app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMin.Value,'Color','black',"LineWidth",3)...
                        xline(app.FigHistLive, app.EditField_climMax.Value,'Color','black',"LineWidth",3)];
                else
                    if app.Options_LogColormap.Value==true
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1)=exp(app.EditField_climMin.Value);
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)=exp(app.EditField_climMax.Value);
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1)=app.EditField_climMin.Value;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)=app.EditField_climMax.Value;
                    end

                    %                 app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1)=app.EditField_climMin.Value;
                    %                 app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)=app.EditField_climMax.Value;

                    app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMin.Value,'Color','black',"LineWidth",3)...
                        xline(app.FigHistLive, app.EditField_climMax.Value,'Color','black',"LineWidth",3)];

                end

                set(app.hVerticalLines(3:4), 'hittest', 'off');
                xlim(app.FigHistLive,[min(EDGES) max(EDGES)])
            else
                app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMin.Value,'Color','black',"LineWidth",3)...
                    xline(app.FigHistLive, app.EditField_climMax.Value,'Color','black',"LineWidth",3)];

                set(app.hVerticalLines(3:4), 'hittest', 'off');

                xlim_vals = [ ( findobj(app.FigHistLive,'Type','Area').XData)  app.EditField_climMax.Value app.EditField_climMin.Value ];
                xlim(app.FigHistLive,[min(xlim_vals) max(xlim_vals)])

            end

        end

        function matrix=rawmap2matrix(app,x_signal,y_signal,region,mapsize)
            region=sort(region);

            integration=zeros(1,size(y_signal,2));

            for m= 1:size(y_signal,2)
                integration(m)=trapz_BC(x_signal(x_signal>min(region)&x_signal<max(region)),y_signal(x_signal>min(region)&x_signal<max(region),m)');
            end

            if ~any(mapsize(:) == 1)
                if (numel(integration)/mapsize(2))/round(numel(integration)/mapsize(2))==1
                    matrix=reshape(integration,[],mapsize(2))';
                else
                    matrix=[];
                    autoshape_matrix(app)
                    return
                end

            elseif mapsize(1) == 1
                matrix=reshape(integration,[],1)';
            elseif mapsize(2) == 1
                matrix=fliplr(reshape(integration,[],1)');

            end
        end

        function  read_metadata(app,path,filename)
            MetaTable=readtable(fullfile(path,filename));
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=MetaTable;

            %%%%%% ATH here error return needs to be implemented if metatable has no header
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=500; %other

            if numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Properties.VariableNames)>0 && any(strcmp('Type',app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Properties.VariableNames)) && any(ismember(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type~=13)),0))
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=100; %FTIR-MCT
                disp('FTIR-MCT')
            elseif numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Properties.VariableNames)>0 && any(strcmp('Type',app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Properties.VariableNames)) && ismember(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type~=13)),9)
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=200; %FTIR-FPA
                disp('FTIR-FPA')
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings')) || not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'FPA_Px_tile')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile)
                    if any(strfind(filename,'_bin0')) || any(strfind(filename,'_nobin')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_bin0')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_nobin'))
                        binval=64;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[binval binval];
                    elseif any(strfind(filename,'_bin2')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_bin2'))
                        binval=32;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[binval binval];
                    elseif any(strfind(filename,'_bin4')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_bin4'))
                        binval=16;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[binval binval];
                    elseif any(strfind(filename,'_bin8')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_bin8'))
                        binval=8;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[binval binval];
                    elseif any(strfind(filename,'_bin16')) || any(strfind(app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_bin16'))
                        binval=4;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[binval binval];
                    else
                        % ask for bin
                        disp('Error no binning value found')
                    end

                end

            end
            %autoshape_matrix(app)
        end

        function Thickness=import_thickness(app,path,file) %#ok
            Thickness=readtable(fullfile(path,file));
        end

        function autoshape_matrix(app)
            if  app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200
                try
                    if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'FPA_Px_tile')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile)
                        definput = {'32','32'};

                        x = inputdlg({'Px/Tile X','Px/Tile Y'}, ['Set Binning for ' name],[1 15; 1 15],definput);
                        X=str2double(x(1));
                        Y=str2double(x(2));

                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=[X Y];
                    end

                    number_Y_pixels=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.AryNY(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type==9).*app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile(2);
                    number_X_pixels=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.AryNX(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type==9).*app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile(1);
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_tiles=[app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.AryNX(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type==9) app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.AryNY(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type==9)];
                    app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=[abs(number_X_pixels) number_Y_pixels]; % Change here
                catch
                    start_ReshapeModule(app)
                    return
                end


            elseif not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && sum(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group>0))==1
                number_Y_pixels = length(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group>0)));
                number_X_pixels = length(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group>0)));
                if not((size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2)/number_Y_pixels)/round(size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2)/number_Y_pixels)==1)
                    %                     sampleinfo=[app.selected_sample app.selected_subsample];
                    %                     ReshapeModule(app,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,sampleinfo)
                    start_ReshapeModule(app)
                    return
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=[abs(number_X_pixels) number_Y_pixels]; % Change here
                end
            else
                %                 sampleinfo=[app.selected_sample app.selected_subsample];
                %                 ReshapeModule(app,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,sampleinfo)
                start_ReshapeModule(app)
                return
            end
        end

        function Interpolate_in2(app)

            x = inputdlg({'Interpolation X','Interpolation Y'}, 'Set Binning',[1 15; 1 15],{'1','1'});
            interpolation_val=[str2double(x(1)) str2double(x(2))];

            new_sample_Id=numel(app.SpecMap.Spectra.Names)+1;
            app.SpecMap.Spectra.Names(new_sample_Id)=app.SpecMap.Spectra.Names(app.selected_sample);

            for n=1: numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(n),'y_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).y_signal_raw)
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).y_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).y_signal;
                end
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(n),'x_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).x_signal_raw)
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).x_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).x_signal;
                end
                y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(n).y_signal;
                mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;

                V=reshape(y_signal',mapsize(1),mapsize(2),size(y_signal,1));
                X=1:mapsize(2);
                Y=1:mapsize(1);
                Z=1:size(y_signal,1);

                newNumberOfRows = mapsize(2)*interpolation_val(1);  % set the number of rows interpolated rows you want
                newNumberOfCols = mapsize(1)*interpolation_val(2);
                tic
                x_int=linspace(1, size(V, 2), newNumberOfRows);
                y_int=linspace(1, size(V, 1), newNumberOfCols);
                z_int=1:size(y_signal,1);


                [Xq, Yq,Zq ] = meshgrid(x_int,y_int,z_int);
                toc
                tic
                Vq = interp3(X,Y,Z,V,Xq,Yq,Zq);
                toc
                y_signal_new=reshape(Vq,[],size(y_signal,1))';
                mapsize_new=size(Vq);


                app.SpecMap.Spectra.Sample(new_sample_Id).Data(n).y_signal=y_signal_new;
                app.SpecMap.Spectra.Sample(new_sample_Id).Data(n).x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.mapsize_raw=(mapsize_new(1:2));


                app.SpecMap.Spectra.Sample(new_sample_Id).Names(n)=app.SpecMap.Spectra.Sample(app.selected_sample).Names;
                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.SpecType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
                % thickness,
                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.Mask=1;% mask
                % metadata

                MatrixLimit=update_MatixLimits(app);

                %px_size=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size
                mapsize_raw=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                y=fliplr(linspace(MatrixLimit(2,1),MatrixLimit(2,2),mapsize_raw(1)));
                x=fliplr(linspace(MatrixLimit(1,1),MatrixLimit(1,2),mapsize_raw(2)));
                [x_pos,y_pos] = meshgrid(x,y);
                x_pos=x_pos';
                y_pos=y_pos';

                thx= [(1:numel(y_pos))' x_pos(:) flipud(y_pos(:)) ones(size(y_pos(:)))];

                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});


                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.MatrixLimits=MatrixLimit;
                app.SpecMap.Spectra.Sample(new_sample_Id).MetaPrivat.SpecType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
            end

            UpdateTree(app);
        end

        function import_image(app,path,file,ImageGroup_Id,ImageId)

            %             if isempty(app.SpecMap.Images) || not(isfield(app.SpecMap.Images.Images,'ImageNames'))
            %                 ImageGroup_Id=1;
            %                 ImageId=1;
            %             end
            %% m=numel(Sample(ImageGroup_Id).ImageNames)+1;
            try
                app.SpecMap.Images.Images(ImageGroup_Id).Image{ImageId}=imread(fullfile(path,file));

                % here if image info field is empty copy 1
                %  if isempty(Sample(ImageGroup_Id).ImageLimits{m})
                if   ImageId>numel(app.SpecMap.Images.Images(ImageGroup_Id).ImageLimits)
                    app.SpecMap.Images.Images(ImageGroup_Id).ImageLimits{ImageId}=app.SpecMap.Images.Images(ImageGroup_Id).ImageLimits{1};
                end
            catch
                disp([fullfile(path,file) ' - image not imported'])
                return
            end

            app.SpecMap.Images.Images(ImageGroup_Id).ImageId{ImageId}=ImageId;
            app.SpecMap.Images.ImageGroupID(ImageGroup_Id)=ImageGroup_Id;

            app.SpecMap.Images.Images(ImageGroup_Id).ImageNames{ImageId}=replace(file,'_image.bmp','');
            app.SpecMap.Images.ImagePath{(ImageGroup_Id)}=path;
            app.SpecMap.Images.ImagefullPath{(ImageGroup_Id)}=fullfile(path,file);
            app.SpecMap.Images.ImageFilename{(ImageGroup_Id)}=file;
            %image info
        end

        function ImageLimits = import_image_info(app,path,file) %#ok
            thx=readtable(fullfile(path,file),ReadVariableNames=false);
            name_cells=table2cell(thx(:,1))';
            name_cells=replace(name_cells,{'._','. ',' ',':','_'},'');
            T=array2table(table2array(thx(:,2))');
            T.Properties.VariableNames=name_cells;
            ImageLimits=[T.PosX*T.PixelSizeX,(T.PosX+T.SizeX)*T.PixelSizeX;[T.PosY*T.PixelSizeY,(T.PosY+T.SizeY)*T.PixelSizeY]];

        end


        function autoimport_data_from_folder(app,path)
            map_info=dir([path '/*map_info.txt']);
            map_info=map_info(~startsWith({map_info.name}, '._'));

            mapdata=dir([path '/*.mat']);
            mapdata=mapdata(~startsWith({mapdata.name}, '._'));

            %             image_input=dir([path '/*image.bmp']);
            %             image_input=image_input(~startsWith({image_input.name}, '._'));
            %
            %             image_info=dir([path '/*image_info.txt']);
            %             image_info=image_info(~startsWith({image_info.name}, '._'));
            %%
            image_input=dir([path,'/**/*.bmp']);
            image_input = image_input(~startsWith({image_input.name}, '.'));

            %             check_str_img=["_image.bmp" ; join([repmat("_image",size(string((0:99)'))) ...
            %                 string((0:99)')...
            %                 repmat(".bmp",size(string((0:99)')))],'')];
            check_str_img=["_image.bmp" ; join([repmat("_image",size(string((0:99)'))) ...
                string((0:99)')...
                repmat(".bmp",size(string((0:99)')))],''); join([repmat("_image0",size(string((0:99)'))) ...
                string((0:99)')...
                repmat(".bmp",size(string((0:99)')))],'')];

            test_str=false(1,numel(image_input));
            for n=1:numel({(image_input.name)})
                test_str(n)=contains(string({image_input(n).name}'),(check_str_img));
            end

            image_input=image_input(test_str);

            image_info=dir([path,'/**/*_info.txt']);
            image_info = image_info(~startsWith({image_info.name}, '.'));

            check_str_img=["_image_info.txt" ; join([repmat("_image",size(string((0:99)'))) ...
                string((0:99)')...
                repmat("_info.txt",size(string((0:99)')))],''); join([repmat("_image0",size(string((0:99)'))) ...
                string((0:99)')...
                repmat("_info.txt",size(string((0:99)')))],'')];
            test_str2=false(numel({(image_info.name)}),1);

            for n=1:numel({(image_info.name)})
                test_str2(n)=contains(string({image_info(n).name}'),(check_str_img)) & not((endsWith(string({image_info(n).name}'),'map_info.txt')));

            end
            image_info=image_info(test_str2);


            thickness=dir([path '/*thickness.txt']);
            thickness=thickness(~startsWith({thickness.name}, '._'));

            thickness_files={thickness.name};


            SampleName=split(path,{'/','\'});
            SampleName=SampleName{end};


            if ~isempty(mapdata)
                mat_files={mapdata.name};
                image_files=image_input;
                image_info_files=image_info;


                for n=1:length(mat_files)

                    try
                        import_MapData(app,path,mat_files{n},SampleName)
                    catch ME
                        disp(['Spectra Import Error - ' mat_files{n}])
                        disp(ME.message)
                    end
                    if n==1
                        %% read thickness
                        try
                            if  numel(thickness_files)==1
                                file=thickness.name;
                                path=thickness.folder;

                                T = import_thickness(app,path,file);
                                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_min =table2array(T(1,1));
                                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_max =table2array(T(1,2));
                                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map =table2array(T(1,3));

                            elseif contains(mat_files{n},'deltaT_')

                                test2=strfind((mat_files{n}),'deltaT_');
                                rest=split(mat_files{n}(test2+1:end),{'_','.'});
                                T=str2double(rest{2});
                                if isnan(T)
                                    T=10000;
                                end
                                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=T;
                            end
                        catch ME
                            disp(['Error Thickness Import: ' mat_files{n}])
                            disp(ME.message)
                        end

                        %% read images
                        if ~isempty(image_input)
                            try

                                if not(isfield(app.SpecMap,'Images'))
                                    app.SpecMap.Images=[];
                                end

                                if isfield(app.SpecMap.Images,'ImagePath') && not(isempty(app.SpecMap.Images)) && not(isempty({app.SpecMap.Images.ImagePath}))
                                    test=false;
                                    ImageGroup_Id=[];

                                    % here load images test if isequal to images in specmaps and if so find
                                    % imagegroupid

                                    % image2find=l
                                    % for n=1:numel(app.SpecMap.Images.Images)
                                    %             test=any(strcmp(file2find ,app.SpecMap.Images.ImagePath));
                                    %             if test==true
                                    %                 ImageGroup_Id=find((strcmp(file2find ,app.SpecMap.Images.ImagePath)));
                                    %                 break
                                    %             else
                                    %                 test=false;
                                    %                 ImageGroup_Id=[];
                                    %             end
                                    %         end
                                    %
                                    %         % Image_Id=find(strcmp(file_tthat_i_want_to_find ,app.SpecMap.Images.ImageFullPath));
                                else
                                    test=false;
                                    ImageGroup_Id=[];
                                end

                                if isfield(app.SpecMap.Images,'ImagefullPath') && (any(test))
                                    %         file=image_input(ismember({image_input.folder},spectra_in_dir(k).folder)).name;
                                    %         path=image_input(ismember({image_input.folder},spectra_in_dir(k).folder)).folder;
                                else

                                    if test==false &&  isempty(ImageGroup_Id) && numel(app.SpecMap.Images)==0
                                        ImageGroup_Id=1;
                                    else
                                        ImageGroup_Id=numel(app.SpecMap.Images.Images)+1;
                                    end


                                    for ImageId=1:numel(image_info_files)
                                        file=image_info(ImageId).name;
                                        path=image_info(ImageId).folder;
                                        app.SpecMap.Images.Images(ImageGroup_Id).ImageLimits{ImageId} = import_image_info(app,path,file);
                                    end

                                    for ImageId=1:numel(image_files)
                                        file=image_files(ImageId).name;
                                        path=image_files(ImageId).folder;
                                        import_image(app,path,file,ImageGroup_Id,ImageId);
                                    end

                                end

                                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=ImageGroup_Id;%find image id;
                                [app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images]=numel(app.SpecMap.Images.Images(ImageGroup_Id).ImageId);

                            catch ME
                                disp(['Error: Image Import' mat_files{n}])
                                disp(ME.message)
                            end
                        end
                    end
                    if ~isempty(map_info)
                        try
                            read_metadata(app,path,map_info.name)
                        catch ME
                            disp(['Metadata Import Error - ' mat_files{n}])
                            disp(ME.message)
                        end
                        if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable))
                            Group=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group;
                            Groups=unique((Group(Group>0)));
                            if numel(Groups)>1
                                SplitMapsFun(app);
                            end
                        end
                    end

                end
            end
        end


        function SelectionChangeFun(app)
            tic
            N = app.TreeData_Main.SelectedNodes;

            if isempty(N)
                app.MapGenDropDown.Value='Integration';
                app.IntRangeLabel.Text='Int. Range';
                app.Int_range_min.Visible=true;
                app.Int_range_min.Value=min([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                app.Int_range_max.Value=max([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                app.ImageDropDown.Visible='off';
                app.AlphaSlider.Visible='off';
                app.AlphaSlider.Value=100;

                app.plot_image_checkbox.Visible='off';
                app.plot_image_checkbox.Value=false;
                app.selected_image=[];

                UpdateInfoWindow(app)
                update_class_tree(app,app.ClassTree);
                update_peaktable_Data(app)
                update_thickness_field(app)
                update_ROI_spinner(app)
                Menu_Sampling_ResetROIMenuSelected(app);


                return
            end

            selectedNodes = N.NodeData;
            %             if  selectedNodes(1)>2
            %                 selectedNodes(end+1)=1
            %             end

            if numel(selectedNodes)>=4 && not(isempty(selectedNodes(4)))
                try
                    app.EditField_xlimMin.Value=round(min(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(selectedNodes(4)).xregion));
                    app.EditField_xlimMax.Value=round(max(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(selectedNodes(4)).xregion));
                    EditField_LiveXMinMaxValueChanged(app)
                catch ME
                    disp(ME.message)
                    disp('Error in SelectionChangeFun - no x update was made')
                end
            elseif numel(selectedNodes)>3
                app.EditField_xlimMin.Value=round(min(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion));
                app.EditField_xlimMax.Value=round(max(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion));

            end

            try
                app.SpectraTypeDropDown.Value=app.SpectraTypeDropDown.ItemsData(find(ismember(str2double(app.SpectraTypeDropDown.ItemsData),app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType)));
                TypeChangeFun(app)
            catch ME
                disp('Error in SelectionChangeFun - TypeChangeError')
                disp(ME.message)
            end

            try
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapType') &&numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)))
                    maptype=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map);
                    if not(ismember(maptype,(51:59)))
                        app.MapGenDropDown.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).MapGen;
                    end
                    if ismember(maptype,(51:59))
                        app.Int_range_max.Value=app.EditField_xlimMax.Value;
                        app.Int_range_min.Value=app.EditField_xlimMin.Value;
                        app.MapGenDropDown.Value='Integration';
                        app.IntRangeLabel.Text='Int. Range';
                        app.Int_range_min.Visible=true;
                    elseif strcmp(app.MapGenDropDown.Value,'Absolute')
                        app.IntRangeLabel.Text='XoI';
                        app.Int_range_min.Visible=false;
                        app.Int_range_max.Value=round(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region);

                    elseif strcmp(app.MapGenDropDown.Value,'Ratio')
                        app.IntRangeLabel.Text='Ratio';
                        app.Int_range_min.Visible=true;
                        app.Int_range_min.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(1);
                        app.Int_range_max.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(2);

                    elseif strcmp(app.MapGenDropDown.Value,'Ratio log/log')
                        app.IntRangeLabel.Text='Ratio log/log';
                        app.Int_range_min.Visible=true;

                        app.Int_range_min.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(1);
                        app.Int_range_max.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(2);

                    elseif strcmp(app.MapGenDropDown.Value,'Entropy')
                        app.IntRangeLabel.Text='Range';
                        app.Int_range_min.Visible=true;
                        app.Int_range_min.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(1);
                        app.Int_range_max.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region(2);

                    else

                        app.Int_range_max.Value=round(max(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region));
                        app.Int_range_min.Value=round(min(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region));
                        app.IntRangeLabel.Text='Int. Range';
                        app.Int_range_min.Visible=true;

                    end
                else
                    ResetButton_2Pushed(app)
                    app.MapGenDropDown.Value='Integration';
                    app.IntRangeLabel.Text='Int. Range';
                    app.Int_range_min.Visible=true;
                    app.Int_range_min.Value=min([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                    app.Int_range_max.Value=max([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                end
            catch ME
                disp('Error 1 in SelectionChangeFun')
                disp(ME.message)

                try
                    ResetButton_2Pushed(app)
                catch ME
                    disp('Error 2 in SelectionChangeFun - ResetButton_2Pushed')
                    disp(ME.message)
                end
                app.IntRangeLabel.Text='Int. Range';
                app.Int_range_min.Visible=true;
            end

            TypeChangeFun(app)

            if strcmp(app.SpecPlotSetDropDown.Value,'Default')
                app.nEditField.Visible=true;
            else
                app.nEditField.Visible=false;
            end

            if isfield(app.SpecMap.Spectra.Sample,'MetaPrivat') && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id)) && isfield(app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id),'ImageNames') && not(isempty(app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageNames))
                %isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ImageNames') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ImageNames))
                app.ImageDropDown.Visible='on';
                app.AlphaSlider.Visible='on';

                app.plot_image_checkbox.Visible='on';
                app.ImageDropDown.Items=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageNames;
                %app.SpecMap.Spectra.Sample(app.selected_sample).ImageNames;

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings') && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'selected_image') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image))
                    app.selected_image=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image;
                else
                    app.selected_image=1;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image=1;
                end
            else
                app.ImageDropDown.Visible='off';
                app.AlphaSlider.Visible='off';
                app.AlphaSlider.Value=100;


                app.plot_image_checkbox.Visible='off';
                app.plot_image_checkbox.Value=false;
                app.selected_image=[];

            end


            update_thickness_field(app)
            update_peaktable_Data(app)
            try
                if any(isnan(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw))
                    autoshape_matrix(app)
                    return
                end
                update_MatixLimits(app);
            catch ME
                disp('Error 3 in SelectionChangeFun')
                disp(ME.message)
                autoshape_matrix(app)
                return
            end

            update_ROI_spinner(app)
            Menu_Sampling_ResetROIMenuSelected(app);
            update_active_plot(app)
            UpdateInfoWindow(app)
            %% update Clim
            if app.DisplayValuesOutsideData.Value==false
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Maps') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps)) &&...
                        isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map),'SubMap') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap)) &&...
                        isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap),'climits') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits))
                    %                 app.EditField_climMin.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1);
                    %                 app.EditField_climMax.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2);
                    if app.Options_LogColormap.Value==false
                        app.EditField_climMin.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1);
                        app.EditField_climMax.Value=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2);
                    else
                        app.EditField_climMin.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1));
                        app.EditField_climMax.Value=log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2));
                    end
                else

                    Mapplotmatrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
                    %                 if std2(Mapplotmatrix)>min(min(Mapplotmatrix))
                    %                     climit=[std2(Mapplotmatrix)-mean(Mapplotmatrix,'all','omitnan') std2(Mapplotmatrix)+mean(Mapplotmatrix,'all','omitnan')];
                    %                 else
                    climit=[min(min(Mapplotmatrix)) std2(Mapplotmatrix)+mean(Mapplotmatrix,'all','omitnan')];
                    %    end
                    if not(isempty(climit)) && not(any(isnan(climit)))
                        app.EditField_climMin.Value=min(climit);
                        app.EditField_climMax.Value=max(climit);
                        %app.EditField_climMin.Value=min(Mapplotmatrix,'all','omitnan');
                        %app.EditField_climMax.Value=max(Mapplotmatrix,'all','omitnan');
                    end
                end
            end

        end

        function ReadColorMaps(app)
            fid = fopen('XMap_ColorMaps.txt','r');
            Compt = 0;

            app.ColorMaps(1).Name = 'None';
            app.ColorMaps(1).Code = 0;

            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Compt = Compt+1;
                        app.ColorMaps(Compt).Name = tline(3:end);
                        Row = 0;
                        while 1
                            tline = fgetl(fid);
                            if isequal(tline,-1) || isequal(tline,'')
                                break
                            end
                            NUM = strread(tline,'%f'); %# ok
                            Row = Row+1;
                            app.ColorMaps(Compt).Code(Row,1:3) = NUM(1:3);
                        end

                    end
                end
            end
            fclose(fid);

            % Update the menu
            Items=cell(1,numel(app.ColorMaps));
            for i = 1:numel(app.ColorMaps)
                Items{i} = app.ColorMaps(i).Name;
            end
            app.Options_ColormapDropDown.Items =  Items; %extractfield(ColorMaps,'Name');

        end

        function update_pixelsize(app) %ATH adjust for submaps
            if not(isempty(app.selected_sample))
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap), 'MapPlotMatrix') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix)) || app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200
                    MatrixLimit=update_MatixLimits(app);
                    d=MatrixLimit(:,2)-MatrixLimit(:,1);
                    n=size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix);
                    Px_size_y=d(2)/n(1);
                    Px_size_x=d(1)/n(2);
                    if isempty(Px_size_x) ||isempty(Px_size_y)
                        app.PxSizeXYEditField.Value= 'No Reference set';

                    else
                        app.PxSizeXYEditField.Value= [num2str(Px_size_x,'%.2f') ' x ' num2str(Px_size_y,'%.2f')];
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size=[Px_size_x Px_size_y];
                    end
                elseif not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && length(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group>0)))>1
                    app.PxSizeXYEditField.Value='Split Maps to get Pixel Size';
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size=[];
                else
                    app.PxSizeXYEditField.Value='No Reference';
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size=[];
                end

            end
        end

        function update_peaktable_Data(app)
            peaktabledata=table([],[],[],[],[],[],'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
            uniquePeaks=[];
            if not(isempty(app.selected_sample)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Peaks')
                if  isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'Autopeaks')
                    peaktabledata=[peaktabledata;app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks];
                    if size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks,2)>=1
                        uniquePeaks=unique([app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks.PeakPosition]);
                    end

                end

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'UIpeaks')
                    peaktabledata=[app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks;peaktabledata];
                    if size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks,2)>=1
                        uniquePeaks=unique([app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks.PeakPosition;uniquePeaks]);
                    end

                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.uniquePeaks=uniquePeaks;

                end

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'DeconPeaks')
                    peaktabledata=[app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks;peaktabledata];
                    if size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks,2)>=1
                        uniquePeaks=unique([app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.PeakPosition;uniquePeaks]);
                    end

                    uniquePeaks(uniquePeaks<=app.xlimits(1) | uniquePeaks>=app.xlimits(2),:)=[];


                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.uniquePeaks=uniquePeaks;
                end

                app.PeakTable.Data=peaktabledata;
            else
                app.PeakTable.Data=[];
            end
        end

        function g=gaussplot(~,pos,amp,wid,x)
            g = amp.*exp(-((x-pos)./(0.60056120439323.*wid)).^2);
        end

        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax,"Visible","off");
            x = roi.Position(1);
            y = roi.Position(2);
        end


        function  start_ReshapeModule(app)

            if isempty(app.selected_sample)
                return
            elseif isempty(app.selected_subsample)
                app.selected_subsample=1;
            end
            sampleinfo=[app.selected_sample app.selected_subsample];

            try
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
            catch
                mapsize=[];
            end


            try
                MatrixLimits=update_MatixLimits(app);
            catch
                MatrixLimits=[];
            end

            FPA_Px_tile=[];
            FPA_tiles=[];

            if isfield(app.SpecMap.Spectra.Sample,'MetaPrivat') && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'SpecType') && app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.SpecType==200 && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') &&   isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'FPA_tiles') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).FPA_tiles))&& isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'FPA_Px_tile') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).FPA_Px_tile))
                FPA_Px_tile=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).FPA_Px_tile;
                FPA_tiles=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).FPA_tiles;
            end
            try
                type=app.SpectraTypeDropDown.ItemsData(find(ismember(str2double(app.SpectraTypeDropDown.ItemsData),app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType)));
            catch
                type={0};
            end
            cmap=app.ColorMapValues;
           try 
               ReshapeModule(app,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal,app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,sampleinfo,mapsize,MatrixLimits,type{:},cmap,FPA_Px_tile,FPA_tiles)
           catch ME
               keyboard
           end
           end

        function update_thickness_field(app)

            if isempty(app.selected_sample)
                app.MeanThicknessEditField.Value='Select a Map';
                return
            end

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)) && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)>1
                thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map>0),"omitnan");
                app.MeanThicknessEditField.Value=[num2str(mean(thickness(thickness>0),"omitnan")) ' µm'];
            elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1
                thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map;

                app.MeanThicknessEditField.Value=[num2str(mean(thickness(thickness>0),"omitnan")) ' µm'];
            else
                thickness=0;
                app.MeanThicknessEditField.Value=[num2str(thickness) ' µm - 10000 µm used'];
            end
        end

        function TypeChangeFun(app)
            if app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==100 || app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200 % FTIR
                xlabel(app.SpectraPlot, 'Wavenumbers [cm^{-1}]')
                if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')
                    ylabel(app.SpectraPlot, 'Absorbance [cm^{-1}]')
                else
                    ylabel(app.SpectraPlot, 'Absorbance')
                end
                app.SpectraPlot.XDir = 'reverse';
                app.FigXlimLive.XDir = 'reverse';
                app.ThicknessCorrectionCheckBox.Visible=true;
                app.MeanThicknessEditField.Visible=true;
                app.MeanThicknessEditFieldLabel.Visible=true;

            elseif app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==300 % raman
                xlabel(app.SpectraPlot, 'Wavenumbers [cm^{-1}]')
                ylabel(app.SpectraPlot, 'Intensity')
                app.SpectraPlot.XDir = 'normal';
                app.FigXlimLive.XDir = 'normal';
                app.ThicknessCorrectionCheckBox.Visible=false;
                app.MeanThicknessEditField.Visible=false;
                app.MeanThicknessEditFieldLabel.Visible=false;
            elseif app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==400 %HyperDC
                xlabel(app.SpectraPlot, 'Wavelength [nm]')
                ylabel(app.SpectraPlot, 'Reflectance')
                app.SpectraPlot.XDir = 'normal';
                app.FigXlimLive.XDir = 'normal';
                app.ThicknessCorrectionCheckBox.Visible=false;
                app.MeanThicknessEditField.Visible=false;
                app.MeanThicknessEditFieldLabel.Visible=false;

            elseif app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==500 %other
                xlabel(app.SpectraPlot, 'x')
                ylabel(app.SpectraPlot, 'y')
                app.SpectraPlot.XDir = 'normal';
                app.FigXlimLive.XDir = 'normal';
                app.ThicknessCorrectionCheckBox.Visible=true;
                app.MeanThicknessEditField.Visible=true;
                app.MeanThicknessEditFieldLabel.Visible=true;
            elseif app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==501 %other reverse
                xlabel(app.SpectraPlot, 'x')
                ylabel(app.SpectraPlot, 'y')
                app.SpectraPlot.XDir = 'reverse';
                app.FigXlimLive.XDir = 'reverse';
                app.ThicknessCorrectionCheckBox.Visible=true;
                app.MeanThicknessEditField.Visible=true;
                app.MeanThicknessEditFieldLabel.Visible=true;
            end
            app.edit_state=true;
        end

        function reshape_raman_MKS(app,path, file,SampleName,type)
            %% read the data

            if isempty(app.SpecMap.Spectra.Names)
                app.selected_sample=1;
                app.selected_subsample=0;
                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');

                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end

            elseif ismember(SampleName,app.SpecMap.Spectra.Names)
                [~, pos]=(ismember(SampleName,app.SpecMap.Spectra.Names));
                app.selected_sample=pos;
            else
                app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                app.selected_subsample=0;

                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end


            end
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=300;

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1
                app.selected_subsample=numel((app.SpecMap.Spectra.Sample(app.selected_sample).Names))+1;
            else
                app.selected_subsample=1;
            end

            data_in=readmatrix(fullfile(path,file));
            if strcmp(type,'MKS')
                x_signal= data_in(1,3:end)';
                y_signal=data_in(2:end,3:end);
                x=data_in(2:end,2);
                y=data_in(2:end,1);
            elseif strcmp(type,'unknown_0')


                x_signal=unique(data_in(:,3));

                in_=data_in(:,4);
                y_signal=reshape(in_,numel(unique(x_signal)),[]);
                figure;plot(y_signal(:,100:10:1000))

                unique_rows=unique(data_in(:,1:2),'rows');
                x=unique_rows(:,1);
                y=unique_rows(:,2);

            elseif strcmp(type,'unknown_1')
                x_signal= data_in(:,3);
                y=data_in(:,2);
                x=data_in(:,1);
                y_signal=data_in(:,4);
                y_signal=reshape(y_signal,numel(unique(x_signal)),[])';

                x1=unique(x);
                y1=unique(y);
                y=repmat(y1,1,numel(unique(x1)))';
                y=y(:);

                x=repmat(x1,1,numel(unique(y1)));
                x=x(:);

                x_signal=unique(x_signal);
            elseif strcmp(type,'unknown_2')
                x_signal= data_in(1,3:end)';
                y_signal=data_in(2:end,3:end);
                x=data_in(2:end,2);
                y=data_in(2:end,1);
            end
            thx= [(1:numel(x))' x y ones(size(x))];

       if   contains(SampleName,'deltaT_')
                test2=strfind((SampleName),'deltaT_');
                rest=split(SampleName(test2+1:end),{'_','.'});
                T=str2double(rest{2});
                if isnan(T)
                    T=10000;
                end
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=T;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=10000;
       end
       app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=1;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=[ numel(unique(x)) numel(unique(y)) ];

            %% save the rawdata
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal';
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=[SampleName '_raw'];

            app.edit_state=true;
        end

        function SisuRockHS_NG(app,infile,SampleName)
            %        function [ImLim,Im,Wl,ImName,Ylab,Xlab]=SisuRockHS_NG(app,infile)
            [path,infname,~]=fileparts(infile);
            %             AddByteOrder=(path+"/"+infname+'.hdr');
            %             hcIN=hypercube(AddByteOrder);
            AddByteOrder(path+"/"+infname+'.hdr');
            hcIN=hypercube(infile);
            Metad=hcIN.Metadata;
            imH=Metad.Height;
            imW=Metad.Width;


            % Image=imread(fullfile(path,[SampleName,'.img']));
            %ImageLimit=[0 0; imW imH]'.*1000; % as [height,width]
            %ImageName=SampleName+"_"+Metad.Bands+"bands";
            if Metad.Bands==3
                ImageName=infname+"_"+Metad.Bands+"bands";

                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ImageNames'))
                    app.selected_image=1;
                else
                    app.selected_image=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ImageNames)+1;
                end
                I = (hcIN.DataCube);
                %Im16=Im*round(65535/max(Im,[],'all')); % 16-bit scaleld for display
                Im8=uint8(double(I)*255.0/double(max(I,[],'all'))); % 8-bit for display
                %if check if ratio is the same as matrix
                Im=rot90(Im8);
                % end
                % IRGB = cat(3, I, I, I);

                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=numel(app.SpecMap.Images.Images)+1;
                app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image={Im};

                app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageNames{app.selected_image}=char(ImageName);

                app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits={update_MatixLimits(app)};%{flipud(ImageLimit)}; %ATH here for different sizes
            else


                Im=hcIN.DataCube;

                x_signal=hcIN.Wavelength;
                y_signal=double(reshape(permute(Im,[3 1 2]),416,[],1));
                % plot(wn,in2(:,randi(222940,5)))

                %                 Ylab="Reflectance ("+Metad.ReflectanceScaleFactor+"*)";
                %                 Xlab="Wavelength / "+Metad.WavelengthUnits;

                if isempty(app.SpecMap.Spectra.Names)
                    app.selected_sample=1;
                    app.selected_subsample=0;
                    if exist('SampleName','var')
                        app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                    else
                        SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
                        app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                    end

                elseif ismember(SampleName,app.SpecMap.Spectra.Names)
                    [~, pos]=(ismember(SampleName,app.SpecMap.Spectra.Names));
                    app.selected_sample=pos;
                else
                    app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                    app.selected_subsample=0;

                    if exist('SampleName','var')
                        app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                    else
                        SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
                        app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                    end


                end
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=400;

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1
                    app.selected_subsample=numel((app.SpecMap.Spectra.Sample(app.selected_sample).Names))+1;
                else
                    app.selected_subsample=1;
                end

                x=flipud(linspace(0,imH,imH)).*1000;
                y=flipud(linspace(0,imW,imW)).*1000;
                [x_pos,y_pos] = meshgrid(x,y);
                thx= [(1:size(y_signal,2))' x_pos(:) y_pos(:) ones(size(1:size(y_signal,2)))'];

       if   contains(SampleName,'deltaT_')
                test2=strfind((SampleName),'deltaT_');
                rest=split(SampleName(test2+1:end),{'_','.'});
                T=str2double(rest{2});
                if isnan(T)
                    T=10000;
                end
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=T;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=10000;
       end
       app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=1;
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=[ numel(unique(x)) numel(unique(y)) ];
                %% save the rawdata
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
                app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=[SampleName '_raw'];

                %                 y2 = remCont2(x_signal,y_signal);
                %                 y_signal = -log(((1-x_signal).^2)./((1+x_signal).^2));


            end
            app.edit_state=true;
        end

        function reshape_omnicmap(app,file,SampleName)
            %% read the data

            if isempty(app.SpecMap.Spectra.Names)
                app.selected_sample=1;
                app.selected_subsample=0;
                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');

                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end

            elseif ismember(SampleName,app.SpecMap.Spectra.Names)
                [~, pos]=(ismember(SampleName,app.SpecMap.Spectra.Names));
                app.selected_sample=pos;
            else
                app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                app.selected_subsample=0;

                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end


            end
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=101;

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1
                app.selected_subsample=numel((app.SpecMap.Spectra.Sample(app.selected_sample).Names))+1;
            else
                app.selected_subsample=1;
            end

            FileInfo = omnicmap(file);
            mapsize_raw=double(round(FileInfo.MapSize));
            id=double(flipud(reshape(1:mapsize_raw(2)*mapsize_raw(1),fliplr(mapsize_raw))'));

            MatrixLimits=double([FileInfo.StartPosition(1) FileInfo.EndPosition(1); FileInfo.StartPosition(2) FileInfo.EndPosition(2)]);

            x=fliplr(linspace(MatrixLimits(1,1),MatrixLimits(1,2),mapsize_raw(2)));
            y=fliplr(linspace(MatrixLimits(2,1),MatrixLimits(2,2),mapsize_raw(1)));

            [x_pos,y_pos] = meshgrid(x,y);

            x_signal= double(FileInfo.WaveNumber');
            y_signal=double(FileInfo.Datagrid(:,id(:)));


            thx= [(1:numel(x_pos(:)))' x_pos(:) y_pos(:) ones(size(x_pos(:)))];

            if   contains(SampleName,'deltaT_')
                test2=strfind((SampleName),'deltaT_');
                rest=split(SampleName(test2+1:end),{'_','.'});
                T=str2double(rest{2});
                if isnan(T)
                    T=10000;
                end
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=T;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=10000;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=1;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=mapsize_raw;

            %% save the rawdata
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=SampleName;

            app.edit_state=true;
        end

        function reshape_fsm(app,file,SampleName)
            %% read the data

            if isempty(app.SpecMap.Spectra.Names)
                app.selected_sample=1;
                app.selected_subsample=0;
                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');

                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end

            elseif ismember(SampleName,app.SpecMap.Spectra.Names)
                [~, pos]=(ismember(SampleName,app.SpecMap.Spectra.Names));
                app.selected_sample=pos;
            else
                app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                app.selected_subsample=0;

                if exist('SampleName','var')
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                else
                    SampleName=matlab.lang.makeUniqueStrings('Unknown',string(app.SpecMap.Spectra.Names)');
                    app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;
                end


            end
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=101;

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1
                app.selected_subsample=numel((app.SpecMap.Spectra.Sample(app.selected_sample).Names))+1;
            else
                app.selected_subsample=1;
            end

            [data, xAxis, yAxis, zAxis, misc] = fsmload(file);

            A = permute(data,[3 2 1]);
            C = reshape(A,size(zAxis,2),[],1);

            %             FileInfo = omnicmap(file);
            mapsize_raw=[size(yAxis,2) size(xAxis,2)];
            id=flipud(reshape(1:mapsize_raw(2)*mapsize_raw(1),fliplr(mapsize_raw))');
            %
            MatrixLimits=[min(xAxis) max(xAxis); min(yAxis) max(yAxis)];

            x_range=[min(xAxis) max(xAxis)];
            y_range=[min(yAxis) max(yAxis)];

                         x=fliplr(linspace(MatrixLimits(1,1),MatrixLimits(1,2),mapsize_raw(2)));
                         y=fliplr(linspace(MatrixLimits(2,1),MatrixLimits(2,2),mapsize_raw(1)));
            %
                         [x_pos,y_pos] = meshgrid(x,y);
% 
             x_signal= zAxis';
             y_signal=C;
 
 
             thx= [(1:numel(x_pos(:)))' x_pos(:) y_pos(:) ones(size(x_pos(:)))];
             app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=10000;
             app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=1;
             app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=mapsize_raw;
% 
%             %% save the rawdata
             app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
             app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
             app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
             app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=SampleName;
% 
             app.edit_state=true;
        end

        function update_PeakTree(app)

            for i=1:numel(app.Peaks.PeakDB.MineralNames)
                p = uitreenode(app.PeakTree,'Text',string(app.Peaks.PeakDB.MineralNames{i}),'NodeData',[i,0]);
                expand(p)

                for j=1:numel(app.Peaks.PeakDB.Mineral(i).GroupNames)
                    p1 = uitreenode(p,'Text',string(app.Peaks.PeakDB.Mineral(i).GroupNames{j}),'NodeData',[i,j]);
                    expand(p1)

                    for k=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames)
                        if numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)==1
                            pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                            name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                            p2=uitreenode(p1,'Text',[num2str(pos) '_' app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',{[i,j,k],pos,name}); %# ok
                        else
                            p2=uitreenode(p1,'Text',[app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',[i,j,k]);

                            for l=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)
                                pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                                name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                                p3=uitreenode(p2,'Text',[num2str(pos(l))],'NodeData',{[i,j,k l],pos(l),name}); %# ok
                            end
                        end
                    end


                end
            end

        end

        function matrix=smoothmat(app,matrix,number_of_smooths)
            for n=1:number_of_smooths
                matrix1=smoothdata(matrix,1,"omitnan");
                matrix2=smoothdata(matrix,2,"omitnan");

                matrixm(:,:,2)=matrix1;
                matrixm(:,:,1)=matrix2;

                matrix=squeeze(mean(matrixm,3,'omitnan'));
            end
        end

        function UpdateInfoWindow(app)

            SelectedNodes = app.TreeData_Main.SelectedNodes;
            if isempty(SelectedNodes)
                Text2Disp = '';

            else
                Text2Disp = '';
                Text2Disp = [Text2Disp,'-----------------------------------------------\n'];

                %% Datatype


                SpecType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
                switch SpecType
                    case 100
                        SpecTypeStr='FTIR-MCT';
                    case 200
                        SpecTypeStr='FTIR-FPA';
                    case 300
                        SpecTypeStr='RAMAN';
                    case 400
                        SpecTypeStr='Hyperspectral Drillcore Image';
                    case 500
                        SpecTypeStr='Other';
                    case 501
                        SpecTypeStr='Other Reverse';
                end
                try
                    Text2Disp = [Text2Disp,'Spectra Type\t\t',num2str(SpecType),' ', SpecTypeStr '\n'];
                catch
                    Text2Disp = [Text2Disp,'Spectra Type\t\t',num2str(0),' ', 'Unknown' '\n'];
                end


                %% Maptype
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapType') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType)>=app.selected_map
                    maptype=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map);
                else
                    maptype ='Unkown maptype';
                end

                mapstr='no map selected';
                switch maptype
                    case 1
                        mapstr= 'Integration Map';
                    case 10
                        mapstr= 'Absolute Value Map';
                    case 30
                        mapstr= 'Ratio Map';
                    case 31
                        mapstr= 'Log/Log Ratio Map';
                    case 32
                        mapstr= 'Norm Ratio Map';
                    case 33
                        mapstr= 'LogNorm Ratio Map';
                    case 140
                        mapstr= 'Classmap';
                    case 150
                        mapstr= 'Quantdata not specified';
                    case 151
                        mapstr= 'Quantdata EPMA';
                    case 152
                        mapstr= 'Quantdata SEM';
                    case 153
                        mapstr= 'Quantdata EPMA';
                    case 154
                        mapstr= 'Quantdata EBSD';
                    case 155
                        mapstr= 'Quantdata LA-ICP-MS';
                    case 51
                        mapstr= 'Peak Deconvolution PeakArea Map';
                    case 52
                        mapstr= 'Peak Deconvolution PeakHight Map';
                    case 53
                        mapstr= 'Peak Deconvolution PeakWidth Map';
                    case 54
                        mapstr= 'Peak Deconvolution Error Map';
                    case 55
                        mapstr= 'Peak Deconvolution PeakShiftMap';
                    case 56
                        mapstr= 'Peak Deconvolution PeakPos Map';
                    case 57
                        mapstr= 'Peak Deconvolution Start Map';
                    case 110
                        mapstr= 'QuantFactorMap';
                    case 111
                        mapstr= 'Quantitative Absolute Value Map (from spectra)';
                    case 112
                        mapstr= 'Quantitative Absolute Value Map (from map)';
                    case 113
                        mapstr= 'Normalized Value Map (from spectra)';
                    case 114
                        mapstr=  'Normalized Value Map (from map)';
                    case 115
                        mapstr= 'QuantFactor Map (from spectra)';
                    case 116
                        mapstr= 'QuantFactor Map (from map)';
                    case 117
                        mapstr= 'Quant Map Paterson (1982)';%quant factor map Paterson (1982)
                    case 118
                        mapstr= 'Quant Map Libowitzky and Rossman (1997)'; %quant factor map Libowitzky and Rossman (1997)
                    case 119
                        mapstr= 'Quant Map Balan et al. (2008)'; %quant factor map Balan et al. (2008)
                    case 120
                        mapstr= 'Int. Quant Map [wt% H2O]';%integrated quant map [wt% H2O]
                    case 121
                        mapstr= 'Int. Quant Map [μg/g H2O]';%integrated quant map [μg/g H2O]
                    case 200
                        mapstr= 'Custom Calculation Map';

                    case 1000
                        mapstr= 'Entropy Map';


                end
                Text2Disp = [Text2Disp,'Map Type\t\t',num2str(maptype),' ', mapstr '\n'];


                Text2Disp = [Text2Disp,'-----------------------------------------------\n'];

                mapsize=size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix);
                Text2Disp = [Text2Disp,'N Pixel [x y]\t\t',num2str(mapsize(2)) 'x' num2str(mapsize(1))  '=' num2str(numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix)),'\n'];

                if numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)>1

                    Text2Disp = [Text2Disp, 'Pixel ~masked \t' num2str(sum(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask(:))) '/' num2str(numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)) '\n'];
                else
                    Text2Disp = [Text2Disp, 'Pixel ~masked \t' num2str(numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)) '/' num2str(numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)) '\n'];

                end

                Px_size=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size;

                Text2Disp = [Text2Disp,'Pixel Size [x y]\t\t',num2str(Px_size(2),'%.2f') ' x ' num2str(Px_size(1),'%.2f')  ' [µm]' '\n'];

                Text2Disp = [Text2Disp,'Map Size [x y]\t\t',num2str(Px_size(2)*mapsize(2),'%.2f') ' x ' num2str(Px_size(1)*mapsize(1),'%.2f') ' [µm]' '\n'];
                if SpecType==200
                    bin_val=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile;
                    if not(isempty(bin_val))
                        Text2Disp = [Text2Disp,'Binning\t\t\t',num2str(64/bin_val(1)) ' x ' num2str(64/bin_val(2))  '\n']; %ATH instrument specific Settings if needed at some point
                        Text2Disp = [Text2Disp,'Pixel/Tile \t\t',num2str(bin_val(1)) ' x ' num2str(bin_val(2))  '\n'];
                        Text2Disp = [Text2Disp,'n Tiles [x y]\t\t',num2str(mapsize(2)/bin_val(2)) ' x ' num2str(mapsize(1)/bin_val(1))  '\n'];
                    end
                end


                Text2Disp = [Text2Disp,'-----------------------------------------------\n'];
                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                    %matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                    matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;
                end
                matrix=ThicknessCorrectionFun(app,matrix);

                %Text2Disp = [Text2Disp,'N Mask\t\t\t',numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix),'\n'];
                %             Text2Disp = [Text2Disp,'-----------------------------------------------\n'];
                %
                Text2Disp = [Text2Disp,'Min\t\t\t\t',num2str(min(matrix,[],"all","omitnan")),'\n'];
                Text2Disp = [Text2Disp,'Max\t\t\t\t',num2str(max(matrix,[],"all","omitnan")),'\n'];
                Text2Disp = [Text2Disp,'Mean\t\t\t',num2str(mean(matrix,"all","omitnan")),'\n'];
                Text2Disp = [Text2Disp,'Median\t\t\t',num2str(median(matrix,"all","omitnan")),'\n'];
                Text2Disp = [Text2Disp,'Stdev\t\t\t',num2str(std(matrix,[],"all","omitnan")),'\n'];


                Text2Disp = [Text2Disp,'-----------------------------------------------\n'];
                Text2Disp = [Text2Disp,'>> Color bar options \n'];
                Text2Disp = [Text2Disp,'Colormap\t\t',char(app.Options_ColormapDropDown.Value),'\n'];
                Text2Disp = [Text2Disp,'Resolution\t\t',num2str(app.Options_ColormapResEditField.Value),'\n'];
                if isequal(app.Options_LogColormap.Value,1)
                    Text2Disp = [Text2Disp,'Scale\t\t\t','log','\n'];
                else
                    Text2Disp = [Text2Disp,'Scale\t\t\t','linear','\n'];
                end

                if app.Options_LowerCheckBox.Value
                    Text2Disp = [Text2Disp,'Lower-filter\t\t',char(app.Options_LowerColor.Value),'\n'];
                else
                    Text2Disp = [Text2Disp,'Lower-filter\t\t','none','\n'];
                end
                if app.Options_UpperCheckBox.Value
                    Text2Disp = [Text2Disp,'Upper-filter\t\t',char(app.Options_UpperColor.Value),'\n'];
                else
                    Text2Disp = [Text2Disp,'Upper-filter\t\t','none','\n'];
                end
                Text2Disp = [Text2Disp,'-----------------------------------------------\n'];

                %             Text2Disp = [Text2Disp,'>> Considering plotted data only \n'];
                %             MinV = app.EditField_LiveMin.Value;
                %             MaxV = app.EditField_LiveMax.Value;
                %             DataSel = app.Data2Plot(find(app.Data2Plot(:) >= MinV & app.Data2Plot(:) <= MaxV));
                %
                %             Text2Disp = [Text2Disp,'Nb\t\t\t\t',num2str(length(DataSel)),'/',num2str(numel(app.Data2Plot(:))),' (',num2str(round(numel(DataSel)/length(app.Data2Plot(:))*100*100)/100),'%%)\n'];
                %             Text2Disp = [Text2Disp,'Min\t\t\t\t',num2str(min(DataSel(:))),'\n'];
                %             Text2Disp = [Text2Disp,'Max\t\t\t\t',num2str(max(DataSel(:))),'\n'];
                %             Text2Disp = [Text2Disp,'Mean\t\t\t',num2str(mean(DataSel)),'\n'];
                %             Text2Disp = [Text2Disp,'Median\t\t\t',num2str(median(DataSel)),'\n'];
                %             Text2Disp = [Text2Disp,'Stdev\t\t\t',num2str(std(DataSel)),'\n'];
                %             Text2Disp = [Text2Disp,'-----------------------------------------------\n'];
                %
            end
            app.MapInfo_TextArea.Value = sprintf(Text2Disp);

        end
        %% XMapTools ROI start
        function ROI_DeleteROI(app)
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI)=[];
            if app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI==1

                if numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)==1
                    app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=1;
                    app.Sampling_ResetButton.Enable = 'on';
                elseif numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)>1
                    app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI);
                    app.Sampling_ResetButton.Enable = 'on';
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=[];
                    app.Sampling_ResetButton.Enable = 'off';
                end
            elseif app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI>1
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI-1;
            end
            update_active_plot(app)
            update_ROI_spinner(app)
        end

        function Sampling_ROI_changed_shape(app,~,Method)

            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            end

            ImageData=ThicknessCorrectionFun(app,matrix);

            mask = createMask(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling);

            %Ind = find(mask');
            Ind = find(mask);

            if numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)>1
                mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');
                Ind=intersect(Ind,mask_ids);
            end

            thxxx=ImageData;thxxx(Ind)=nan;

            DataSelected = ImageData(Ind);

            thx= histogram(app.Sampling_Plot1,DataSelected(not(isnan(DataSelected))));
            app.Sampling_Plot1.XLim=[thx.BinLimits];

            xlabel(app.Sampling_Plot1,'Value')
            ylabel(app.Sampling_Plot1,'#')
            title(app.Sampling_Plot1,{['Px selected = ',num2str(numel(Ind))],['Mean = ',num2str(mean(DataSelected(:),'omitnan'))],['Median = ',num2str(median(DataSelected(:),'omitnan'))]})
            app.Sampling_Plot1.Visible = 'on';
            app.Sampling_Plot2.Visible = 'off';
            cla(app.Sampling_Plot2)
            colorbar(app.Sampling_Plot2,'hide')

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Idx=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask()');

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Method=Method;
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Coordinates=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;

            if strcmp(Method,'Circle ROI')
                app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Coordinates(3)=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Radius;
            end
            if strcmp(app.SpecPlotSetDropDown.Value,'All ROI') || strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked') || strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean') || strcmp(app.SpecPlotSetDropDown.Value, 'All ROIs Mean')
                UpdateSpectraPlot(app)
            end
        end

        function LesData = Sampling_CalculateLineXY(app,X,Y,ImageData)

            LesX = [];
            LesY = [];
            for i = 2:length(X)
                Xs = [X(i-1),X(i)];
                Ys = [Y(i-1),Y(i)];

                LX = max(Xs) - min(Xs);
                LY = max(Ys) - min(Ys);

                LZ = round(sqrt(LX^2 + LY^2));

                if Xs(2) > Xs(1)
                    LesXs = Xs(1):(Xs(2)-Xs(1))/(LZ-1):Xs(2);
                elseif Xs(2) < Xs(1)
                    LesXs = Xs(1):-(Xs(1)-Xs(2))/(LZ-1):Xs(2);
                else
                    LesXs = ones(LZ,1) * Xs(1);
                end

                if Ys(2) > Ys(1)
                    LesYs = Ys(1):(Ys(2)-Ys(1))/(LZ-1):Ys(2);
                elseif Ys(2) < Ys(1)
                    LesYs = Ys(1):-(Ys(1)-Ys(2))/(LZ-1):Ys(2);
                else
                    LesYs = ones(LZ,1) * Ys(1);
                end

                LesX(end+1:end+length(LesXs)) = LesXs;
                LesY(end+1:end+length(LesYs)) = LesYs;
            end

            % Indexation
            XCoo = 1:1:length(ImageData(1,:));
            YCoo = 1:1:length(ImageData(:,1));

            IdxAll=zeros(length(LesX),2);

            for i = 1 : length(LesX)
                [V(i,1), IdxAll(i,1)] = min(abs(XCoo-LesX(i))); % Index X
                [V(i,2), IdxAll(i,2)] = min(abs(YCoo-LesY(i))); % Index Y
            end

            LesData = zeros(size(IdxAll,1),3);
            for i=1:size(IdxAll,1)
                LesData(i,1) = i;
                if i>1
                    LesData(i,2) = LesData(i-1,2)+sqrt((LesX(i)-LesX(i-1))^2+(LesY(i)-LesY(i-1))^2);
                end
                LesData(i,3) = ImageData(IdxAll(i,2),IdxAll(i,1));
                if LesData(i,3) == 0
                    LesData(i,3) = NaN;
                end
            end
        end

        function Sampling_ROI_changed_line(app,~)

            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                matrix(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask==0)=nan;
            end

            ImageData=ThicknessCorrectionFun(app,matrix);
            Positions = app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;

            X = Positions(:,1);
            Y = Positions(:,2);


            [y,x]=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask());

            [LesData] = Sampling_CalculateLineXY(app,x,y,ImageData);

            app.TabGroup_2.SelectedTab = app.SamplingTab;

            Res = mean(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size) ; %ath
            distance_micron=Res*LesData(:,2);

            plot(app.Sampling_Plot1,LesData(:,2)*Res,LesData(:,3),'-k')
            hold(app.Sampling_Plot1,'on');
            % scatter(app.Sampling_Plot1,LesData(:,2)*Res,LesData(:,3),[],LesData(:,3),'filled','MarkerEdgeColor',[0.5 .5 .5])
            scatter(app.Sampling_Plot1,LesData(:,2)*Res,LesData(:,3),[],LesData(:,3),'filled')

            xlabel(app.Sampling_Plot1,'distance µm')

            colorbar(app.Sampling_Plot1,'horizontal');
            colormap(app.Sampling_Plot1,app.ColorMapValues);
            if app.Options_LogColormap.Value==false
                CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
            else
                CLim =log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits);
            end
            %CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
            caxis(app.Sampling_Plot1,CLim);
            app.Sampling_Plot1.XLim=[min(distance_micron),max(distance_micron)];
            title(app.Sampling_Plot1,{['Px selected = ',num2str(numel(LesData(:,2)))],['Mean = ',num2str(mean(LesData(:,3),'omitnan'))],['Median = ',num2str(median(LesData(:,3),'omitnan'))]})

            hold(app.Sampling_Plot1,'off');

            app.Sampling_Plot1.Visible = 'on';
            app.Sampling_Plot2.Visible = 'off';
            cla(app.Sampling_Plot2)
            colorbar(app.Sampling_Plot2,'hide')


            app.Sampling_ExportButton.Enable = 'on';
            app.Sampling_ResetButton.Enable = 'on';
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Idx=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask()');

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Method='Polyline ROI';
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Coordinates=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;

        end

        function Sampling_ROI_changed_strip(app,~)
            % if not(exist('update_plots','var'))
            % update_plots=true;
            % end
            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                matrix(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask==0)=nan;
            end

            ImageData=ThicknessCorrectionFun(app,matrix);
            %             Verticles = app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Vertices;
            %
            %             [x,y]=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask()');
            %             try
            %                 [MatrixProfils,Distances] = ExtractMatrixProfils(app,ImageData,min(x),min(y),min(x),max(y),max(x),max(y));   % previously X4,Y4,X6,Y6,X7,Y7
            %             catch ME
            %                 ME
            %                 disp(ME.message)
            %
            %                 return
            %             end
            %% new
            % ImageData =matrix2extract
            thx2=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Parent.Children;

            thx_images=findall(thx2,'type','Image');

            if   numel(thx_images)>1 && size(thx_images(numel(thx_images)).CData,3)==3
                thx_images=thx_images(numel(thx_images)-1); %ATH
            else
                thx_images=thx_images(numel(thx_images));
            end

            roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask(thx_images);

            if not(isequal(size(roiMask),size(matrix)))
                return
            end

            rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.RotationAngle;

            roiMask_rot=imrotate(roiMask,rot_);
            ImageData_rot=imrotate(ImageData,rot_);

            mask_ind=find(roiMask_rot>0);
            [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
            ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
            MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);
            if isempty(MatrixProfils)
                return
            end


            [~, which_dir]=min(size(MatrixProfils));

            TheMean=mean(MatrixProfils,which_dir,'omitnan');
            TheMedian = median(MatrixProfils,which_dir,'omitnan');

            % This has to be done later tp avoir NaN in the computation of the mean
            WhereZero = find(MatrixProfils(:) < 1e-19);
            MatrixProfils(WhereZero) = nan(size(WhereZero));
            if which_dir==1
                MatrixProfils=MatrixProfils';
            end

            app.TabGroup_2.SelectedTab = app.SamplingTab;

            % roiMaskPos=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;
            % width = roiMaskPos(3);
            % height = roiMaskPos(4);
            % theta = -rot_;
            % height*sin(deg2rad(theta))


            % calculate actual distance here

            Distances=0:size(MatrixProfils,1)-1;

            Res = mean(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size,'omitnan') ; %ath
            distance_micron=Distances*Res;
            plot(app.Sampling_Plot1,Res*Distances,TheMedian,'-b','linewidth',2);
            hold(app.Sampling_Plot1,'on')
            plot(app.Sampling_Plot1,Res*Distances,TheMean,'-r','linewidth',2);
            app.Sampling_Plot1.Box = 'on';
            hold(app.Sampling_Plot1,'off')

            xlabel(app.Sampling_Plot1,'Distance µm')
            app.Sampling_Plot1.XLim=[min(distance_micron),max(distance_micron)];

            app.Sampling_Plot1.Title.String = 'median (blue) & mean (red)';;
            if not(isempty(app.Sampling_Plot1.Colorbar))
                app.Sampling_Plot1.Colorbar.Visible=false;
            end

            cla(app.Sampling_Plot2)
            plot(app.Sampling_Plot2,Res*Distances',MatrixProfils','-','markersize',1,'color', [0.4 0.4 0.4 0.15]);
            %  scatter(app.Sampling_Plot2,Res*Distances',TheMean,[],TheMean','filled','MarkerEdgeColor',[0.5 .5 .5])
            %  scatter(app.Sampling_Plot2,Res*Distances',TheMean,[],TheMean','filled')
            scatter(app.Sampling_Plot2,Res*Distances',TheMedian,[],TheMedian','filled')

            colorbar(app.Sampling_Plot2,'horizontal');
            colormap(app.Sampling_Plot2,app.ColorMapValues);
            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap),'climits')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits)
                caxis(app.Sampling_Plot2,'auto');
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=app.Sampling_Plot2.CLim;
            end
            if app.Options_LogColormap.Value==false
                CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
            else
                CLim =log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits);
            end
            %CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
            caxis(app.Sampling_Plot2,CLim);


            app.Sampling_Plot2.Title.String = 'all transects (strip median)';

            xlabel(app.Sampling_Plot2,'Distance µm')
            app.Sampling_Plot2.XLim=[min(distance_micron),max(distance_micron)];

            app.Sampling_Plot1.Visible = 'on';
            app.Sampling_Plot2.Visible = 'on';

            app.Sampling_ExportButton.Enable = 'on';
            app.Sampling_ResetButton.Enable = 'on';

            % [y,x]=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask());
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Idx=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask(thx_images)');
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Method='Rectangle ROI';
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.Coordinates=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_save.RotationAngle=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.RotationAngle;


        end

        function [lesX,lesY] = LineCoordinate(app,A,B,MapSize)
            %

            X = [A(1),B(1)];
            Y = [A(2),B(2)];

            LX = max(X) - min(X);
            LY = max(Y) - min(Y);

            LZ = round(sqrt(LX^2 + LY^2));

            if X(2) > X(1)
                LesX = X(1):(X(2)-X(1))/(LZ-1):X(2);
            elseif X(2) < X(1)
                LesX = X(1):-(X(1)-X(2))/(LZ-1):X(2);
            else
                LesX = ones(LZ,1) * X(1);
            end

            if Y(2) > Y(1)
                LesY = Y(1):(Y(2)-Y(1))/(LZ-1):Y(2);
            elseif Y(2) < Y(1)
                LesY = Y(1):-(Y(1)-Y(2))/(LZ-1):Y(2);
            else
                LesY = ones(LZ,1) * Y(1);
            end


            XCoo = 1:1:MapSize(2);
            YCoo = 1:1:MapSize(1);

            for i = 1 : length(LesX)
                [V(i,1), IdxAll(i,1)] = min(abs(XCoo-LesX(i))); % Index X
                [V(i,2), IdxAll(i,2)] = min(abs(YCoo-LesY(i))); % Index Y


                if V(i,1) > 1 && IdxAll(i,1) < 2
                    IdxAll(i,1) = 1-abs(V(i,1));
                end

                if V(i,2) > 1 && IdxAll(i,2) < 2
                    IdxAll(i,2) = 1-abs(V(i,2));
                end


                if V(i,2) > 1 && IdxAll(i,2) > 2      % Y
                    IdxAll(i,2) = MapSize(1)+abs(V(i,2));
                end

                if V(i,1) > 1 && IdxAll(i,1) > 2
                    IdxAll(i,1) = MapSize(2)+abs(V(i,1));
                end

            end

            lesX = IdxAll(:,1);
            lesY = IdxAll(:,2);
        end

        function [MatrixProfils,Distances] = ExtractMatrixProfils(app,Data,X4,Y4,X6,Y6,X7,Y7)
            %

            [lesXOri,lesYOri] = LineCoordinate(app,round([X6,Y6]),round([X4,Y4]),size(Data));
            [lesXref,lesYref] = LineCoordinate(app,round([X6,Y6]),round([X7,Y7]),size(Data));

            MatrixProfils = zeros(length(lesXref),length(lesXOri));  % lines = profils % col = Exp

            for i=1:length(lesXOri)

                DeltaX = lesXOri(1)-lesXOri(i);
                DeltaY = lesYOri(1)-lesYOri(i);

                lesX = lesXref - DeltaX;
                lesY = lesYref - DeltaY;

                lesX = round(lesX);
                lesY = round(lesY);

                for j=1:length(lesY)

                    if lesX(j) >= 1 && lesY(j) >= 1 && lesY(j) <= size(Data,1) && lesX(j) <= size(Data,2)
                        MatrixProfils(j,i) = Data(lesY(j),lesX(j));
                    else
                        MatrixProfils(j,i) = NaN;
                    end
                end

                %NbOK = length(find(lesX>0 & lesY>0));
            end


            % Calculate the distances
            dX = lesX(2) - lesX(1);
            dY = lesY(2) - lesY(1);

            Dist2Steps = sqrt(dX^2+dY^2);
            Distances = Dist2Steps*([0:1:length(lesX)-1]);

        end
        %% XMapTools ROI end

        function ax = get_fig(app)
            % centimeters units
            X = 42.0;                  % A3 paper size
            Y = 29.7;                  % A3 paper size
            xMargin = 1;               % left/right margins from page borders
            yMargin = 1;               % bottom/top margins from page borders
            xSize = X - 2*xMargin;     % figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     % figure size on paper (widht & hieght)


            % create figure/axis
            hFig1=figure('visible', 'on');
            % figure size displayed on screen (50% scaled, but same aspect ratio)


            % figure size printed on paper
            set(hFig1, 'PaperUnits','centimeters')
            set(hFig1, 'PaperSize',[X Y])
            set(hFig1, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig1, 'PaperOrientation','portrait')
            hFig1.Position=hFig1.Position*1.25;


            tiledlayout('flow')
            nexttile
            hold on
            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                % matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;
            end

            %             if app.ThicknessCorrectionCheckBox.Value==true && not(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==4 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==5 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)>10) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(matrix) || numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1)
            %                 matrix=(matrix./app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map).*1e4;
            %             end
            matrix=ThicknessCorrectionFun(app,matrix);

            % matrix=smoothdata(matrix,1,"omitnan");
            % matrix=smoothdata(matrix,2,"omitnan");

            imagesc(matrix)
            %colorbar;
            cb = colorbar;
            cb.Label.String = '';

            %%
            colormap(PlotMap_UpdateColormap(app,256,1));

            try
                caxis(sort([app.EditField_climMin.Value app.EditField_climMax.Value]))
            catch ME

                disp('C Limit update failed')
                disp(ME.message)
                mM=mean(matrix(:),"omitnan");
                caxis([min([mM*0.2 mM*2]) max([mM*0.2 mM*2])])
            end
            ax=gca;
            %climits1=ax.CLim;

            axis image
        end

        function update_ROI_spinner(app)
            if not(isempty(app.selected_sample)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'selected_ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)) && numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)>=1
                app.MapROISpinner.Visible = 'on';
                try
                    app.MapROISpinner.Limits=[1,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)];
                catch
                    app.MapROISpinner.Limits=[0,1];
                end
                app.MapROISpinner.Value=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI;
            else
                app.MapROISpinner.Value=1;
                app.MapROISpinner.Visible = 'off';


            end
        end


        %         function [distance_micron,TheMean,TheMedian,MatrixProfils] = get_mean_median(app,n,m,roi)
        %             matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(n).SubMap(m).MapPlotMatrix;
        %
        %             if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
        %                 matrix(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask==0)=nan;
        %             end
        %
        %             ImageData=ThicknessCorrectionFun(app,matrix);
        %
        %             roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.createMask();
        %             if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling,'RotationAngle')
        %             rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.RotationAngle;
        %             else
        %             rot_=0;
        %             end
        %             roiMask_rot=imrotate(roiMask,rot_);
        %             ImageData_rot=imrotate(ImageData,rot_);
        %
        %             mask_ind=find(roiMask_rot>0);
        %             [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
        %             ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
        %             MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);
        %
        %             [~, which_dir]=min(size(MatrixProfils));
        %
        %             TheMean=mean(MatrixProfils,which_dir,'omitnan');
        %             TheMedian = median(MatrixProfils,which_dir,'omitnan');
        %
        %
        %             if which_dir==1
        %                 MatrixProfils=MatrixProfils';
        %             end
        %
        %
        %             Distances=0:size(MatrixProfils,1)-1;
        %
        %
        %             app.TabGroup_2.SelectedTab = app.SamplingTab;
        %
        %             Res = mean(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size) ; %ath
        %
        %             distance_micron=Res*Distances;
        %
        %         end
        function [distance_micron,TheMean,TheMedian,MatrixProfils] = get_mean_median(app,n,m,roi)
               distance_micron=[];
                TheMean=[];
                TheMedian=[];
                MatrixProfils=[];
                sampleinfo=[app.selected_sample app.selected_subsample,n,m];
            matrix=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                matrix(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask==0)=nan;
            end

            ImageData=ThicknessCorrectionFun(app,matrix);
            %             Verticles = app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.Vertices;
            %
            %             [x,y]=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.createMask()');
            %             try
            %                 [MatrixProfils,Distances] = ExtractMatrixProfils(app,ImageData,min(x),min(y),min(x),max(y),max(x),max(y));   % previously X4,Y4,X6,Y6,X7,Y7
            %             catch ME
            %                 ME
            %                 disp(ME.message)
            %
            %                 return
            %             end
            %% new
            % ImageData =matrix2extract
            thx2=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.Parent.Children;

            thx_images=findall(thx2,'type','Image');

            if   numel(thx_images)>1 && size(thx_images(numel(thx_images)).CData,3)==3
                thx_images=thx_images(numel(thx_images)-1); %ATH
            else
                thx_images=thx_images(numel(thx_images));
            end


            roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.createMask(thx_images);

            if not(isequal(size(roiMask),size(matrix)))
                return
            end

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling,'RotationAngle')|| isprop(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling,'RotationAngle')
                rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.RotationAngle;

                roiMask_rot=imrotate(roiMask,rot_);
                ImageData_rot=imrotate(ImageData,rot_);

                mask_ind=find(roiMask_rot>0);
                [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
                ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
                MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);
            else
%                           mask_ind=find(roiMask>0);
%                 [ii,jj]=ind2sub(size(roiMask),mask_ind);
%                 ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
%                 MatrixProfils=imcrop(ImageData,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);
MatrixProfils=[];
            end

            if isempty(MatrixProfils)
                return
            end


            [~, which_dir]=min(size(MatrixProfils));

            TheMean=mean(MatrixProfils,which_dir,'omitnan');
            TheMedian = median(MatrixProfils,which_dir,'omitnan');

            % This has to be done later tp avoir NaN in the computation of the mean
            WhereZero = find(MatrixProfils(:) < 1e-19);
            MatrixProfils(WhereZero) = nan(size(WhereZero));
            if which_dir==1
                MatrixProfils=MatrixProfils';
            end


            Distances=0:size(MatrixProfils,1)-1;

            Res = mean(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).Px_size,'omitnan') ; %ath

            roiMaskPos=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(roi).ROI_sampling.Position;


            distance_micron=Distances*Res;
            distance_micron=linspace(0,max(roiMaskPos([3 4])),size(MatrixProfils,1));
        end

        function reset_ROI_Sampling_Figs(app)
            try
                cla(app.Sampling_Plot1)
                cla(app.Sampling_Plot2)

                colorbar(app.Sampling_Plot1,'hide')
                colorbar(app.Sampling_Plot2,'hide')

                app.Sampling_Plot1.Visible = 'off';
                app.Sampling_Plot2.Visible = 'off';
                cla(app.Sampling_Plot2)
                colorbar(app.Sampling_Plot2,'hide')
                for n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)

                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling)
                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling.Color=[0.47,0.67,0.19];
                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling.InteractionsAllowed='none';
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling,'rotatable')
                            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling.rotatable=false;
                        end
                    end
                end
            catch ME
                ME
                disp('error - reset_ROI_Sampling_Figs(app)')
            end

        end

        function SplitInfo=SplitMapsFun(app)

            SplitInfo=[];
            %% check also if a map is actually a map and not a line or points
            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable))
                Group=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group;
                Groups=unique((Group(Group>0)));
                oldname=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
                SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
                orig_subsamplenr=app.selected_subsample;
                orig_samplenr=app.selected_sample;


                mt=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;
                mp=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat;

                x_signal=app.SpecMap.Spectra.Sample(orig_samplenr).Data(orig_subsamplenr).x_signal;

                for m = 1:length(Groups)
                    selectiontable=mt(mt.Group==Groups(m),:);
                    if size(selectiontable,1)>0
                        Fileindex_old=selectiontable.Fileindex;
                        Fileindex_new=1:length(Fileindex_old);

                        y_signal=app.SpecMap.Spectra.Sample(orig_samplenr).Data(orig_subsamplenr).y_signal(:,Fileindex_old);

                        Mask=1;
                        Thickness_map=mp.Thickness_map;
                        SpecType=mp.SpecType;
                        MetaTable=selectiontable;
                        Classification=[];
                        mapsize_raw=[nan nan];
                        etc=[];
                        DataIn=[x_signal y_signal];
                        SampleName_=[SampleName '_split_' num2str(Groups(m))];
                        SubsampleName=[SampleName '_split_' num2str(Groups(m))];

                        [app thxI]= import_Map(app,SampleName_,SubsampleName,DataIn,mapsize_raw,SpecType,Mask,Thickness_map,MetaTable,Classification,etc);
                        SplitInfo=[SplitInfo;thxI];
                        autoshape_matrix(app)

                        if isfield(mp,'Thickness_min')
                            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_min=mp.Thickness_min;
                        end
                        if isfield(mp,'Thickness_max')
                            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_max=mp.Thickness_max;
                        end
                        if isfield(mp,'ImageGroup_Id')
                            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=mp.ImageGroup_Id;
                        end
                        if isfield(mp,'n_Images')
                            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images=mp.n_Images;
                        end

                    end
                end
                app.SpecMap.Spectra.Names(orig_samplenr)=[];
                app.SpecMap.Spectra.Sample(orig_samplenr)=[];
                %                 app.SpecMap.Spectra.Sample(orig_samplenr).Data(orig_subsamplenr)=[];
                %                 app.SpecMap.Spectra.Sample(orig_samplenr).MetaPrivat(orig_subsamplenr)=[];

                app.selected_subsample=[];
                %                 try
                %                     app.SpecMap.Spectra.Sample(orig_samplenr).Settings(orig_subsamplenr)=[];
                %                 catch ME
                %                     disp(ME.message)
                %                 end
                UpdateTree(app);
            end
        end

        function reset_zoom_pan(app)
            try
                zoom(app.SpectraPlot,'off')
                pan(app.SpectraPlot,'off')
                zoom(app.MapPlot,'off')
                pan(app.MapPlot,'off')
                zoom(app.MapPlot_2,'off')
                pan(app.MapPlot_2,'off')
                zoom(app.MapPlot_UR,'off')
                pan(app.MapPlot_UR,'off')
                zoom(app.MapPlot_UL,'off')
                pan(app.MapPlot_UL,'off')
                zoom(app.MapPlot_OR,'off')
                pan(app.MapPlot_OR,'off')
                zoom(app.MapPlot_OL,'off')
                pan(app.MapPlot_OL,'off')
            catch ME
                disp('Error - SpecMaps reset zoom / pan')
                disp(ME)

            end

        end

        function version_update_fun(app)
            for sample=1:numel(app.SpecMap.Spectra.Names)
                for subsample=1:numel(app.SpecMap.Spectra.Sample(sample).Names)
                    if isfield(app.SpecMap.Spectra.Sample(sample).Data(subsample),'in') && isfield(app.SpecMap.Spectra.Sample(sample).Data(subsample),'wn')
                        app.SpecMap.Spectra.Sample(sample).Data(subsample).x_signal=app.SpecMap.Spectra.Sample(sample).Data(subsample).wn;
                        app.SpecMap.Spectra.Sample(sample).Data(subsample).y_signal=app.SpecMap.Spectra.Sample(sample).Data(subsample).in;
                    end
                    if isfield(app.SpecMap.Spectra.Sample(sample).Data(subsample),'in_raw') && isfield(app.SpecMap.Spectra.Sample(sample).Data(subsample),'wn_raw')
                        app.SpecMap.Spectra.Sample(sample).Data(subsample).x_signal_raw=app.SpecMap.Spectra.Sample(sample).Data(subsample).wn_raw;
                        app.SpecMap.Spectra.Sample(sample).Data(subsample).y_signal_raw=app.SpecMap.Spectra.Sample(sample).Data(subsample).in_raw;
                    end
                end
                if isfield(app.SpecMap.Spectra.Sample(sample).Data,'in') && isfield(app.SpecMap.Spectra.Sample(sample).Data,'wn')
                    app.SpecMap.Spectra.Sample(sample).Data = rmfield(app.SpecMap.Spectra.Sample(sample).Data,'in');
                    app.SpecMap.Spectra.Sample(sample).Data = rmfield(app.SpecMap.Spectra.Sample(sample).Data,'wn');
                end
                if isfield(app.SpecMap.Spectra.Sample(sample).Data,'in_raw') && isfield(app.SpecMap.Spectra.Sample(sample).Data,'wn_raw')
                    app.SpecMap.Spectra.Sample(sample).Data = rmfield(app.SpecMap.Spectra.Sample(sample).Data,'in_raw');
                    app.SpecMap.Spectra.Sample(sample).Data = rmfield(app.SpecMap.Spectra.Sample(sample).Data,'wn_raw');
                end
            end
        end
    end

    methods (Access = public)

        function update_SpecMaps_public(app)
            TypeChangeFun(app)
            SelectionChangeFun(app)
        end

        function ThicknessMapReturn(app,Thickness_map,sampleinfo)
            app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map=Thickness_map;
            disp('DONE')
            update_active_plot(app)
            update_thickness_field(app)
            app.edit_state=true;
        end

        function ClassificationReturn(app,Classification,sampleinfo)
            app.selected_sample=sampleinfo(1);
            if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Classification')&& not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification))

                a1=[app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes];
                a2=Classification.Classes;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes=[a1 a2];

                a1=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.ClassesNames;
                a2=Classification.ClassesNames;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.ClassesNames=[a1 a2];
            else
                app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification=Classification;
            end
            update_class_tree(app,app.ClassTree);
            disp('DONE')
            app.edit_state=true;
        end


        function MapMatchReturn(app,matrix_to_match,matrix_to_matchNames,sampleinfo,type)
            if not(exist("type","var"))
                type=150;%Quantdata
            end
            n_quantmaps=numel(matrix_to_matchNames);
            app.selected_sample=sampleinfo(1);
            app.selected_subsample=sampleinfo(2);

            app.selected_submap=0;
            app.selected_map=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames)+1;
            if app.selected_map==0
                app.selected_map=1;
            end

            if type==150%Quantdata
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{app.selected_map}='QuantMap'; %%num2str(numberofclasses)
            elseif type==998
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{app.selected_map}='CPX_T_Map'; %%num2str(numberofclasses)
            elseif numel(n_quantmaps)>1
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{app.selected_map}='NumericData'; %%num2str(numberofclasses)
            else
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{app.selected_map}=matrix_to_matchNames{1};
            end

            if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'mapsize') && sampleinfo(3)>0 && size(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize),1)>=app.selected_map &&sampleinfo(3)>0&& not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:))) && not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:),[0 0]))
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:);
            else
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
            end

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(app.selected_map,:)=mapsize;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(app.selected_map)=type;

            for k=1:n_quantmaps
                app.selected_submap=app.selected_submap+1;
                matrix=matrix_to_match(k).MapPlotMatrix;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix ;%QuantMap
                if type==150 || numel(n_quantmaps)>1
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(app.selected_map).SubMapNames{app.selected_submap}=matrix_to_matchNames{k};
                end
            end

            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;
        end

        function  matrix=MapPlot_fun_public(app,Axis2Plot,sampleinfo,thickness_state,plotimage,matrix,alpha,meta_x,meta_y) % used in CorelationModule MapCalculator and ExportModule
            if not(exist('thickness_state','var'))
                thickness_state=true;
            end

            if not(exist('alpha','var'))
                alpha=[];
            end

            if not(exist('matrix','var')) || isempty(matrix)
                if not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)),'Maps'))
                    matrix=[];
                    return
                end
                matrix=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix;
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                    matrix(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask
                end
            end

            if not(exist('meta_x', 'var') && not(isempty(meta_x)) && exist('meta_y', 'var') && not(isempty(meta_y)))
                try
                    meta_x=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type~=13);
                    meta_y=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type~=13);
                catch
                    meta_x=[];
                    meta_y=[];
                end
            end

            if thickness_state==true
                matrix=ThicknessCorrectionFun(app,matrix,sampleinfo);
            end

            MatrixLimits=update_MatixLimits(app,sampleinfo);
            if not(exist('plotimage','var'))
                plotimage=app.plot_image_checkbox.Value;
            end
            reset=true;
            if not(isequal(Axis2Plot,0))
                plt_settings.plt_ROI=app.ROICheckBox.Value;
                if exist('alpha','var') && not(numel(alpha)==numel(matrix))
                    alpha=[];
                end
                UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings,meta_x,meta_y)
                colormap(Axis2Plot,app.ColorMapValues);
            end

        end

        function [MatrixLimits, mapsize]=update_MatixLimits(app,sampleinfo)

            if not(exist('sampleinfo','var'))
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map];
            end
            app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MatrixLimits=[];
            mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;


            if  app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.SpecType==200 && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'MetaPrivat') && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'MetaTable') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable))
                xmid=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type==9);
                dx=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.CX(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type==9);

                ymid=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type==9);
                dy=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.CY(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Type==9);

                xmin=xmid-(0.5*dx);
                xmax=xmid+(0.5*dx);
                ymin=ymid-(0.5*dy);
                ymax=ymid+(0.5*dy);
                MatrixLimits=[xmin,xmax;ymin,ymax];
            elseif isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'MetaTable') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable)) %here
                MatrixLimits=[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Group>0)),...
                    max(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.x(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Group>0));...
                    min(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Group>0)),...
                    max(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.y(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MetaTable.Group>0))];
            elseif isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'PixelSize')
                mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
                MatrixLimits=[0,mapsize(1)*app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).PixelSize(1);0,mapsize(2)*app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).PixelSize(2)];
            else
                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)),'Settings') && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize),1)>=sampleinfo(3) && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:))) && not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:),[0 0]))
                    mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:);
                else
                    mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
                end


                MatrixLimits=[0,mapsize(2);0,mapsize(1)];
            end
            if any(isnan(MatrixLimits))
                MatrixLimits=[0,1;0,1];
            end
            app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.MatrixLimits=MatrixLimits;
        end

        function [x_signal,y_signal,range] = get_specs(app,sampleinfo,xlimits)
            x_signal=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal;
            y_signal=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).y_signal;

            if not(exist('xlimits','var')) || isempty(xlimits)
                xlimits=[min(x_signal) max(x_signal)];
            end

            range=x_signal>=xlimits(1) & x_signal<=xlimits(2);

            x_signal=x_signal(range,:);
            y_signal=y_signal(range,:);
        end

        function [x_signal,y_signal,range] = get_specs_cor(app,sampleinfo,xlimits,signal,thickness_state)
            if not(exist('thickness_state','var'))
                thickness_state =true;
            end
            if not(exist('signal','var')) || isempty(signal)
                x_signal=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal;
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==size(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).y_signal,2)
                    mask=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask';
                    y_signal=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).y_signal(:,mask(:)');
                else
                    mask=1;
                    y_signal=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).y_signal;
                end
            else
                x_signal=signal(:,1);
                y_signal=signal(:,2:end);
            end

            if not(exist('xlimits','var')) || isempty(xlimits)
                xlimits=[min(x_signal) max(x_signal)];
            end

            range=x_signal>=xlimits(1) & x_signal<=xlimits(2);

            x_signal=x_signal(range,:);
            y_signal=y_signal(range,:);

            try
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).in_edit_status==1; %# ok
            catch
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).in_edit_status=0;
            end

            if app.FilterCheckBox.Value==true
                for n=1:app.FilterIterationSpinner.Value
                    if strcmp(app.FilterMethod.Value,'sgolay')
                        y_signal=smoothdata(y_signal,1,'sgolay',app.FilterSpan.Value,'Degree',app.FilterDegree.Value);
                    else
                        y_signal=smoothdata(y_signal,1,app.FilterMethod.Value,app.FilterSpan.Value);
                    end
                end
            end


            if app.BackgroundCheckBox.Value==true
                ignore_co2=app.IgnoreCO2CheckBox.Value;
                value1=app.BackgroundDegreeSpinner.Value;
                value2=app.BCvalueSpinner_2.Value;
                method=app.BackgroundMethodDropDown.Value;
                interp_method=app.BackgroundMethodDropDown_2.Value;
                signal_split=1;
                [y_signal, baseline]=GetBaselineFun(x_signal,y_signal,signal_split,value1,value2,method,interp_method,ignore_co2);
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).baseline=baseline;
            end


            if thickness_state==true && app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==size(y_signal,2)
                tm=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map';
                y_signal=(y_signal./tm(:)').*1e4;
            elseif thickness_state==true && app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1 %&& not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map,1))
                y_signal=(y_signal./app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map).*1e4;
            end

            if not(exist('signal','var')) && app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)>1
                in_corrected=zeros(sum(range),size(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).y_signal,2));
                in_corrected(:,mask(:)')=y_signal;
                y_signal=in_corrected;
            end
        end


        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if not(exist('Resolution','var')) || isempty(Resolution)
                Resolution=app.Options_ColormapResEditField.Value;
            end


            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));
            if app.Options_InvertColormap.Value
                ColorData =app.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            %linlogchange start

            app.ColorScale = 'linear';

            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
            end
        end

        function DataStruct=getDataStruct(app) % used in export module
            DataStruct=app.SpecMap.Spectra;
        end

        function [app, sampleinfo]=import_Map(app,SampleName,SubsampleName,DataIn,mapsize_raw,SpecType,Mask,Thickness_map,MetaTable,Classification,etc)
            app.edit_state=true;

            if isempty(app.SpecMap.Spectra.Names)
                app.selected_sample=1;
                app.selected_subsample=0;
            elseif ismember(SampleName,app.SpecMap.Spectra.Names)
                [~, pos]=(ismember(SampleName,app.SpecMap.Spectra.Names));
                if isfield(app.SpecMap.Spectra.Sample(pos),'MetaPrivat') && isfield(app.SpecMap.Spectra.Sample(pos).MetaPrivat,'mapsize_raw') && not(isempty(app.SpecMap.Spectra.Sample(pos).MetaPrivat.mapsize_raw)) && isequal(mapsize_raw,app.SpecMap.Spectra.Sample(pos).MetaPrivat.mapsize_raw)...
                        || ((isempty(mapsize_raw) || any(isnan(mapsize_raw))) && isequal(size(DataIn(:,2:end)),size(app.SpecMap.Spectra.Sample(pos).Data(1).y_signal))) ...
                        || ((isempty(mapsize_raw) || any(isnan(mapsize_raw))) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw') && isequal(size(DataIn(:,2:end)),size(app.SpecMap.Spectra.Sample(pos).Data(1).y_signal_raw)))
                    app.selected_sample=pos;
                else
                    app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                    app.selected_subsample=0;
                end
            else
                app.selected_sample=length(app.SpecMap.Spectra.Names)+1;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data.x_signal=0;
                app.selected_subsample=0;
            end

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1
                app.selected_subsample=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)+1;
            else
                app.selected_subsample=1;
            end

            app.SpecMap.Spectra.Names{app.selected_sample}=SampleName;

            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=DataIn(:,1);
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=DataIn(:,2:end);
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat(1).MetaTable=MetaTable;

            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=SubsampleName;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat(1).mapsize_raw=mapsize_raw;

            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=SpecType;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat(1).Mask=Mask;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat(1).Thickness_map=Thickness_map;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat(1).Classification=Classification;

            if exist('etc','var')
                if isfield(etc,'FPA_Px_tile') && not(isempty(etc.FPA_Px_tile))
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).FPA_Px_tile=etc.FPA_Px_tile;
                end

                if isfield(etc,'MatrixLimits') && not(isempty(etc.MatrixLimits))
                    app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MatrixLimits=etc.MatrixLimits;
                end

                %% this will be added in the future to import images
                %                 if isfield(etc,'Images') && not(isempty(etc.Images))
                %                     % try to find equal ImageGroup_Id
                %                     % app.SpecMap.Images.Images(ImageGroup_Id) else
                %                     % add new ImageGroup_Id  ImageGroup_Id=1
                %
                %                     if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')&& not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id)) && isequal(app.SpecMap.Images.Images,etc.Images,)
                %                         %Ath this is wrong here need to check if a sub
                %                         %in the struct is the same as in SpecDB
                %                         ImageGroup_Id= find(isequal(app.SpecMap.Images.Images,etc.Images));
                %
                %                     elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')&& not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id))
                %                         ImageGroup_Id= app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id;
                %                     else
                %                         ImageGroup_Id=numel(app.SpecMap.Images.Images)+1;
                %                         app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=ImageGroup_Id;
                %                     end
                %
                %                     app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=ImageGroup_Id;%find image id;
                %                     %[app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images]=numel(app.SpecMap.Images.Images(ImageGroup_Id).ImageId);
                %                 end

                if isfield(etc,'Update') && not(isempty(etc.Update)) && etc.Update==true
                    UpdateTree(app);
                    SelectionChangeFun(app)
                end
            end
            sampleinfo=[app.selected_sample app.selected_subsample];
        end

        function update_class_tree(app,Tree2Update,sampleinfo)
            %reset tree
            a = Tree2Update.Children;
            a.delete;
            if  not(exist('sampleinfo','var')) || isempty(sampleinfo)
                sampleinfo=app.selected_sample;
            end

            if not(isempty(sampleinfo)) && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat, 'Classification') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification))
                %build tree
                for i=1:numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.ClassesNames)

                    p = uitreenode(Tree2Update,'Text',string(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.ClassesNames{i}),'NodeData',[i,0]);
                    %            expand(p)

                    for j=1:numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes(i).ClassMapNames)

                        p1 = uitreenode(p,'Text',string(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes(i).ClassMapNames{j}),'NodeData',[i,j]);
                        %       expand(p1)

                        for k=1:numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes(i).ClassMap(j).ClassesNames)
                            p2=uitreenode(p1,'Text',string(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Classification.Classes(i).ClassMap(j).ClassesNames{k}),'NodeData',[i,j,k]); %# ok
                        end
                    end
                    Tree2Update.Visible=true;
                    app.MaskSelectedButton.Visible=true;
                    app.CorrelationModuleButton.Visible=true;

                    app.DeleteButton.Visible=true;
                    app.ClearAllButton.Visible=true;


                    app.SpecPlotSetDropDown.Items={'Default','Sum','Mean','All','ROI Mean','All ROIs Stacked','All ROI','All ROIs Mean','Classes Mean'};
                end

            else
                Tree2Update.Visible=false;
                app.MaskSelectedButton.Visible=false;
                app.CorrelationModuleButton.Visible=false;
                app.DeleteButton.Visible=false;
                app.ClearAllButton.Visible=false;


                app.SpecPlotSetDropDown.Items={'Default','Sum','Mean','All','ROI Mean','All ROIs Stacked','All ROI','All ROIs Mean'};
                app.SpecPlotSetDropDown.Value='Default';


            end
        end

        function ResetTree(app,Tree2Update)

            if not(exist('Tree2Update','var'))
                Tree2Update=app.TreeData_Main;
            end

            if ~isempty(Tree2Update.SelectedNodes)
                app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            end

            a = Tree2Update.Children;
            a.delete;


        end

        function UpdateTree(app,Tree2Update)
            ContextMenu_thx='';

            if not(exist('Tree2Update','var'))
                Tree2Update=app.TreeData_Main;
                ContextMenu_thx=app.ContextMenu_Main_Tree;
            end
            %%% [2,i,j,k,l]
            N=Tree2Update.SelectedNodes;

            if not(isempty(N))
                selectedNodes = N.NodeData;
                if selectedNodes(end)==0
                    update_ts=false;
                else
                    update_ts=true;

                end
            else
                selectedNodes=[];
                update_ts=true;

            end

            ResetTree(app,Tree2Update)

            % Add MapData Spectra
            if isfield(app.SpecMap,'Spectra')  && isfield(app.SpecMap.Spectra,'Names')
                if numel(app.SpecMap.Spectra.Names) > 0
                    for i = 1:numel(app.SpecMap.Spectra.Names)
                        %A=string(app.SpecMap.Spectra.Names{i});

                        p = uitreenode(Tree2Update,'Text',string(app.SpecMap.Spectra.Names{i}),'NodeData',[2,i,0]);
                        p.ContextMenu = ContextMenu_thx;
                        %expand(p)
                        for j = 1:length(app.SpecMap.Spectra.Sample(i).Names)
                            % A=string(app.SpecMap.Spectra.Sample(i).Names{j});

                            p1 = uitreenode(p,'Text',string(app.SpecMap.Spectra.Sample(i).Names{j}),'NodeData',[2,i,j]);
                            p1.ContextMenu = ContextMenu_thx;

                            if isfield(app.SpecMap.Spectra.Sample(i).Data(j), 'MapNames') && numel(app.SpecMap.Spectra.Sample(i).Data(j).MapNames) > 0

                                for k=1:numel(app.SpecMap.Spectra.Sample(i).Data(j).MapNames)
                                    try
                                        maptype=app.SpecMap.Spectra.Sample(i).Data(j).MapType(k);
                                    catch
                                        maptype=0;
                                        app.SpecMap.Spectra.Sample(i).Data(j).MapType(k)=0;
                                        disp('Maptype Missing')
                                    end
                                    if not(ismember(maptype,(51:59)))
                                        p2= uitreenode(p1,'Text',string(app.SpecMap.Spectra.Sample(i).Data(j).MapNames{k}),'NodeData',[2,i,j,k]);
                                        p2.ContextMenu = ContextMenu_thx;
                                        if numel(selectedNodes)>=3 && i== selectedNodes(2) && j==selectedNodes(3)
                                            expand(p1)
                                        end

                                        if isfield(app.SpecMap.Spectra.Sample(i).Data(j),'Maps') && isfield(app.SpecMap.Spectra.Sample(i).Data(j).Maps,'SubMapNames') && numel(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames) > 0
                                            for l=1:numel(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames)
                                                p3= uitreenode(p2,'Text',string(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames{l}),'NodeData',[2,i,j,k,l]);
                                                p3.ContextMenu = ContextMenu_thx;

                                                % uitreenode(p2,'Text',char(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames{l}),'NodeData',[2,i,j,k,l]);
                                                if numel(selectedNodes)>=4 && i== selectedNodes(2) && j==selectedNodes(3)&& k== selectedNodes(4)
                                                    expand(p2)
                                                    expand(p3)
                                                end
                                            end
                                        end
                                    else
                                        decon_start=find(app.SpecMap.Spectra.Sample(i).Data(j).MapType==51);
                                        if ismember(k,decon_start) || not(exist('p1_1','var'))
                                            p1_1=uitreenode(p1,'Text',"DeconGroup",'NodeData',[3,i,j k]);%ath add decongroup name in the future
                                        end

                                        p2= uitreenode(p1_1,'Text',string(app.SpecMap.Spectra.Sample(i).Data(j).MapNames{k}),'NodeData',[2,i,j,k]);
                                        p2.ContextMenu = ContextMenu_thx;
                                        if numel(selectedNodes)>=3 && i== selectedNodes(2) && j==selectedNodes(3)
                                            expand(p1)
                                        end
                                        if isfield(app.SpecMap.Spectra.Sample(i).Data(j),'Maps') && isfield(app.SpecMap.Spectra.Sample(i).Data(j).Maps,'SubMapNames') && numel(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames) > 0
                                            for l=1:numel(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames)
                                                p3= uitreenode(p2,'Text',string(app.SpecMap.Spectra.Sample(i).Data(j).Maps(k).SubMapNames{l}),'NodeData',[2,i,j,k,l]);
                                                p3.ContextMenu = ContextMenu_thx;

                                                if numel(selectedNodes)>=4 && i== selectedNodes(2) && j==selectedNodes(3)&& k== selectedNodes(4)
                                                    expand(p2)
                                                    expand(p3)
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end

                end
            end

            if update_ts==true
                N=[];
                try
                    selectedNodes=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

                    N=Tree2Update.Children(selectedNodes(1));
                    N=Tree2Update.Children(selectedNodes(1)).Children(selectedNodes(2));
                    N=Tree2Update.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3));
                    N=Tree2Update.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3)).Children(selectedNodes(4));
                    N=Tree2Update.Children(selectedNodes(1)).Children(selectedNodes(2)).Children(selectedNodes(3)).Children(selectedNodes(4)).Children(selectedNodes(5));

                catch
                    Tree2Update.SelectedNodes=N;
                end
                Tree2Update.SelectedNodes=N;
            end
        end

        function hFig=SpecMaps_plot_fun(app,hFig,settings)
            roi_state=app.ROICheckBox.Value;

            for n=1:size(settings,2)

                Axis2Plot=settings(n).Axis2Plot;
                hold(Axis2Plot,'on')

                if isfield(settings(n),'Options') && isfield(settings(n).Options,'ROIs') && not(isempty(settings(n).Options.ROIs))
                    Axis2Plot.FontSize=settings(n).Options.FontSize;
                else
                    Axis2Plot.FontSize=18;

                end

                sampleinfo=settings(n).sampleinfo;
                app.selected_sample=sampleinfo(1);
                app.selected_subsample=sampleinfo(2);
                app.selected_map=sampleinfo(3);
                app.selected_submap=sampleinfo(4);

                hold on
                switch settings(n).Type

                    case 'Map'
                        daspect(Axis2Plot,[1 1 1])
                        if isfield(settings(n),'Options') && isfield(settings(n).Options,'ROIs') && not(isempty(settings(n).Options.ROIs))
                            app.ROICheckBox.Value = settings(n).Options.ROIs;
                        else
                            app.ROICheckBox.Value=false;
                        end
                        thickness_state=true;
                        plotimage=false;

                        alpha=1;%ones(size(matrix));

                        matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage,[],alpha);


                        MatrixLimits=update_MatixLimits(app,sampleinfo);

                        plotimage=true;
                        reset=false;

                        try
                            plt_settings.cb_label=replace([app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}],'_',' ');
                        catch ME
                            disp('Error in Clable add2plot')
                            disp(ME)
                            %                plt_settings.cb_label='';
                            plt_settings.cb_label='Colorbar Label';
                        end

                        plt_settings.plt_ROI=app.ROICheckBox.Value;

                        UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings)

                        title(Axis2Plot,[app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}]);


                        xlim(Axis2Plot,sort(MatrixLimits(1,:)))
                        ylim(Axis2Plot,sort(MatrixLimits(2,:)))

                        caxis(Axis2Plot,'auto')
                        colormap(Axis2Plot,app.ColorMapValues);
                        cbar=colorbar(Axis2Plot);
                        cbar.Label.String = '';
                        if app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==1
                            cbar.Label.String = 'Integrated Absorbance [cm^{-2}]';
                        elseif app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==10
                            cbar.Label.String = 'Absorbance [cm^{-1}]';
                        end
                        Axis2Plot.YTickLabel=[];
                        Axis2Plot.YTick=[];
                        Axis2Plot.XTickLabel=[];
                        Axis2Plot.XTick=[];


                    case 'Profile'

                        matrix=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix;

                        if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                            matrix(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask==0)=nan;
                        end

                        ImageData=ThicknessCorrectionFun(app,matrix);
                        %             Verticles = app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Vertices;
                        %
                        %             [x,y]=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask()');
                        %             try
                        %                 [MatrixProfils,Distances] = ExtractMatrixProfils(app,ImageData,min(x),min(y),min(x),max(y),max(x),max(y));   % previously X4,Y4,X6,Y6,X7,Y7
                        %             catch ME
                        %                 ME
                        %                 disp(ME.message)
                        %
                        %                 return
                        %             end
                        %% new
                        % ImageData =matrix2extract
                        thx2=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Parent.Children;

                        thx_images=findall(thx2,'type','Image');

                        if   numel(thx_images)>1 && size(thx_images(numel(thx_images)).CData,3)==3
                            thx_images=thx_images(numel(thx_images)-1); %ATH
                        else
                            thx_images=thx_images(numel(thx_images));
                        end


                        roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask(thx_images);

                        if not(isequal(size(roiMask),size(matrix)))
                            return
                        end

                        rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.RotationAngle;

                        roiMask_rot=imrotate(roiMask,rot_);
                        ImageData_rot=imrotate(ImageData,rot_);

                        mask_ind=find(roiMask_rot>0);
                        [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
                        ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
                        MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);
                        if isempty(MatrixProfils)
                            return
                        end


                        [~, which_dir]=min(size(MatrixProfils));

                        TheMean=mean(MatrixProfils,which_dir,'omitnan');
                        TheMedian = median(MatrixProfils,which_dir,'omitnan');

                        % This has to be done later tp avoir NaN in the computation of the mean
                        WhereZero = find(MatrixProfils(:) < 1e-19);
                        MatrixProfils(WhereZero) = nan(size(WhereZero));
                        if which_dir==1
                            MatrixProfils=MatrixProfils';
                        end


                        Distances=0:size(MatrixProfils,1)-1;

                        Res = mean(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).Px_size,'omitnan') ; %ath

                        roiMaskPos=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Position;


                        distance_micron=Distances*Res;
                        distance_micron=linspace(0,max(roiMaskPos([3 4])),size(MatrixProfils,1));
                        plot(Axis2Plot,distance_micron,TheMedian,'-b','linewidth',2);
                        plot(Axis2Plot,distance_micron,TheMean,'-r','linewidth',2);

                        %             width = roiMaskPos(3);
                        %             height = roiMaskPos(4);
                        %             theta = -rot_;
                        %             height*sin(deg2rad(theta))


                        %Axis2Plot.Title.String = 'median (blue) & mean (red)';;

                        if not(isempty(Axis2Plot.Colorbar))
                            Axis2Plot.Colorbar.Visible=false;
                        end

                        plot(Axis2Plot,distance_micron',MatrixProfils','-','markersize',1,'color', [0.4 0.4 0.4 0.15]);

                        %scatter(Axis2Plot,Res*Distances',TheMean,[],TheMean','filled')
                        scatter(Axis2Plot,distance_micron',TheMedian,[],TheMedian','filled')
                        colormap(Axis2Plot,app.ColorMapValues);
                        if not(isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)),'climits')) || isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).climits)
                            caxis(Axis2Plot,'auto');
                            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).climits=Axis2Plot.CLim;
                        end

                        if app.Options_LogColormap.Value==false
                            CLim = app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).climits;
                        else
                            CLim =log(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).climits);
                        end

                        caxis(Axis2Plot,CLim);

                        %Axis2Plot.Title.String = 'all transects (strip)';

                        xlabel(Axis2Plot,'Distance µm')

                        Axis2Plot.XLim=[min(distance_micron),max(distance_micron)];


                        cbar=colorbar(Axis2Plot);

                        if app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==1
                            cbar.Label.String = [app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)} ' [cm^{-2}]'];
                        elseif app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))==10
                            cbar.Label.String = [app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)} ' [cm^{-1}]'];
                        end

                        %% cbar.Label.String = HERE
                     %   cbar.Label.String = 'H_2O total [µg/g]'
                    case 'classmap'

                    case 'optical image'
                        daspect(Axis2Plot,[1 1 1])

                        thickness_state=true;
                        plotimage=true;
                        matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage);
                        MatrixLimits=update_MatixLimits(app,sampleinfo);

                        if isfield(settings,'Options') &&isfield(settings(n).Options,'ROIs') && settings(n).Options.ROIs==true
                            app.ROICheckBox.Value=settings(n).Options.ROIs;
                        else
                            app.ROICheckBox.Value=false;
                        end

                        reset=false;
                        alpha=zeros(size(matrix));
                        plt_settings.cb_label='';
                        plt_settings.plt_ROI=app.ROICheckBox.Value;
                        app.SpecMap.plot_image_checkbox.Value=true;

                        UpdateMapPlot(app,Axis2Plot,[],MatrixLimits,reset,plotimage,alpha,plt_settings)
                        app.SpecMap.plot_image_checkbox.Value=false;
                        if isfield(settings,'Options') && isfield(settings(n).Options,'Title') && islogical(settings(n).Options.Title) && settings(n).Options.Title==true
                            title(Axis2Plot,app.SpecMap.Spectra.Sample(sampleinfo(1)).Names{sampleinfo(2)},'interpreter','None')
                        elseif isfield(settings,'Options') &&isfield(settings(n).Options,'Title') && ischar(settings(n).Options.Title)
                            title(Axis2Plot,settings(n).Options.Title,'interpreter','None')
                        end
                        ylim(Axis2Plot,'auto')
                        xlim(Axis2Plot,'auto')
                end


                if isfield(settings,'Options') &&isfield(settings(n).Options,'Scalebar') && settings(n).Options.Scalebar==true
                    SizeMap=MatrixLimits(:,2)-MatrixLimits(:,1);
                    PixelSize=mean(flipud(SizeMap)./size(matrix)');

                    Scalebar_length= SizeMap(1)/10;
                    thx=[min(MatrixLimits')+SizeMap'*0.2; max(MatrixLimits')-SizeMap'*0.2];
                    X=thx(1,1);
                    Y=thx(1,2);
                    a = 0;
                    c = [SizeMap(1)/4];
                    test = sort([logspace(1,10,10) logspace(1,10,10)*5 ])   ;
                    for k=flip(1:numel(test))
                        b=test(k);
                        E = max(a:b:c);
                        if E>0
                            break
                        end
                    end

                    thx=[min(MatrixLimits')+SizeMap'*0.3; min(MatrixLimits')-SizeMap'*0.05];
                    X=thx(1,1);
                    Y=min(thx(:,2));
                    %                 X=min(MatrixLimits(1,:))+;%min((Axis2Plot.XLim));
                    %                 Y=min(MatrixLimits(2,:))*0.95;%min((Axis2Plot.XLim));

                    hEb=errorbar(Axis2Plot,X',Y',E,'horizontal','b.', 'linewidth',3);  % add scales
                    hTxt=text(Axis2Plot,X,Y,[num2str(E*2) ' μm'], ...
                        'horizontalalignment','center','verticalalignment','top','fontsize',20);
                    hEb.Color='k';

                    hEb.Color='r';

                    hTxt.Color='r';
                    hTxt.Extent;
                    cbar=colorbar(Axis2Plot);
                    cbar.Visible=false;

                    Axis2Plot.YTickLabel=[];
                    Axis2Plot.YTick=[];
                    Axis2Plot.XTickLabel=[];
                    Axis2Plot.XTick=[];
                    yl=  [min([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)]) max([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)])];
                    xl=  [min([Axis2Plot.XLim hTxt.Extent(1)]) max([Axis2Plot.XLim hTxt.Extent(1) hTxt.Extent(1)+hTxt.Extent(3)] ) ];
                    ylim(Axis2Plot,yl)
                    xlim(Axis2Plot,xl)
                    app.ROICheckBox.Value=roi_state;
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Project, parentapp)

            if ~isdeployed
                app.DevelopMenu.Visible=true;
            end


            if exist("parentapp","var")
                app.SpecXY_session=parentapp;
            end

            app.SpecMapsUIFigure.Visible=false;
            app.SpecMap.Spectra.Names={};
            app.SpecMap.Spectra.Sample.Names={};
            app.edit_state=false;

            ReadColorMaps(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            if isdeployed
                left = 1;
                bottom = 1;

            else
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
            end

            width = screenWidth*0.9;
            height = screenHeight*0.9;
            drawnow;
            app.SpecMapsUIFigure.Position = [left bottom width height];


            %%
            try
                if isdeployed
                    path=fullfile(app.SpecXY.installpath,'PeakDB.csv');
                    if not(exist(path,'file'))
                        message={'Error PeakDB.csv not found: ', path};
                        uialert(app.SpecMapsUIFigure,message,'Error');
                        return
                    end

%                     path=ctfroot;
%                     path_split=split(path,{'SpecXY.app','SpecXY_dev.app','SpecXY_dev.exe','SpecXY.exe'});
%                     path=fullfile(path_split{1},'PeakDB.csv');
%                     disp('ctfroot: ' )
%                     disp(path)
%                     if not(exist(path,'file'))
%                         path='PeakDB.csv';
%                     end
                else
                    path=which('PeakDB.csv');
                end
                app.Peaks.PeakDB=ReadPeakdata_fun(path);
                update_PeakTree(app)
            catch ME
                disp(ME.message)
                app.SpecMapsUIFigure.Visible=true;
                answr=uiconfirm(app.SpecMapsUIFigure,{'No PeakDB found - Do you want to load a PeakDB file'},'Select directory','Options',{'Yes','No'});
                if strcmp(answr,"Yes")
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select PeakDB file' char('')]);
                    delete(f)

                    if not(isequal(file,0))
                        app.Peaks.PeakDB=ReadPeakdata_fun(fullfile(path,file));
                        update_PeakTree(app)
                    end
                end
            end

            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            if exist('Project','var')

                if ischar(Project)
                    try
                        if strcmpi(Project,'Import')

                            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                            path=uigetdir;
                            delete(f); %delete the dummy figure
                            app.SpecMapsUIFigure.Visible=true;

                            if isequal(path,0)
                                figure(app.SpecMapsUIFigure);
                                return
                            end
                            AutoImportButtonPushed(app, path)
                        elseif  startsWith(Project,'Import ')
                            path=replace(Project,'Import ','');
                            if not(isfolder(path))
                                return
                            end
                            app.SpecMapsUIFigure.Visible=true;

                            AutoImportButtonPushed(app, path)

                        elseif strcmp(Project,'open')
                            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
                            delete(f);
                            if not(isequal(file,0))
                                Project=fullfile(path,file);
                            else
                                app.SpecMapsUIFigure.Visible=true;

                                return
                            end

                        end
                        thx=load(Project);
                        app.SpecMap=thx.data2save;
                        version_update_fun(app)

                        UpdateTree(app);

                        app.SpecMap.projectpath=fullfile(Project);
                        app.SpecMapsUIFigure.Name = app.SpecMap.projectpath;
                    catch ME
                        error('not a valid file')
                    end
                elseif isstruct(Project)
                    Project.SampleName
                    Project.SubsampleName
                    Project.DataIn
                    Project.mapsize_raw
                    Project.SpecType
                    Project.Mask
                    Project.Thickness_map
                    Project.MetaTable
                    Project.Classification
                    Project.etc

                    import_Map(app,Project.SampleName,Project.SubsampleName,Project.DataIn,Project.mapsize_raw,Project.SpecType,Project.Mask,Project.Thickness_map,Project.MetaTable,Project.Classification,Project.etc);

                end
            end
            app.SpecMapsUIFigure.Visible=true;

        end

        % Close request function: SpecMapsUIFigure
        function SpecMapsUIFigureCloseRequest(app, event)
            if app.edit_state==true
                if isfield(app.SpecMap,'projectpath') && not(isempty(app.SpecMap.projectpath))
                    %  [folder, baseFileNameNoExt, extension] = fileparts(app.SpecMap.projectpath);
                    [~, baseFileNameNoExt, ~] = fileparts(app.SpecMap.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end

                answer = questdlg({['Do you want to save your project' projectname '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveProjectMenuSelected(app)
                        delete(app)
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                delete(app)
            end
        end

        % Menu selected function: CloseSpecMapsMenu
        function CloseSpecMapsMenuSelected(app, event)
            SpecMapsUIFigureCloseRequest(app)
        end

        % Callback function: AddSpectraButton, ImportMapdataMenu
        function AddMapdataButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.mat;*.*'},"MultiSelect","on",'Select Files');
            delete(f);
            if not(isequal(file,0))

                if iscell(file)
                    for m=1:length(file)
                        name=matlab.lang.makeUniqueStrings([string(app.SpecMap.Spectra.Names)';'Unknown']);
                        SampleName=char(name(end));
                        import_MapData(app,path,char(file(m)),SampleName)
                    end
                else
                    name=matlab.lang.makeUniqueStrings([string(app.SpecMap.Spectra.Names)';'Unknown']);
                    SampleName=char(name(end));
                    import_MapData(app,path,file,SampleName)
                end

                ResetButton_2Pushed(app)
                UpdateTree(app);
            end
        end

        % Callback function: AlphaSlider, BackgroundCheckBox, 
        % BackgroundDegreeSpinner, BackgroundMethodDropDown_2, 
        % DerivativeSpinner, Derivative_CheckBox, 
        % DisplayValuesOutsideData, FilterCheckBox, FilterDegree, 
        % FilterIterationSpinner, FilterSpan, MaskCheckBox, 
        % Options_ColormapResEditField, Options_LogColormap, 
        % Options_LowerCheckBox, Options_LowerColor, 
        % Options_UpperCheckBox, Options_UpperColor, PeakDispDropDown, 
        % PeakPreviewSwitch, Peaks_CheckBox, ShowOriginalCheckBox, 
        % SpecPlotSetDropDown, TabGroup, ThicknessCorrectionCheckBox, 
        % nEditField, plot_image_checkbox, plot_spectra_checkbox
        function MapSettingsChanged(app, event)
            if strcmp(app.SpecPlotSetDropDown.Value,'Default')
                app.nEditField.Visible=true;
            else
                app.nEditField.Visible=false;
            end
            % if app.Switch.Value==true
            %             app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).in_edit_status=1;
            % else
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).in_edit_status=0;
            % end
            update_active_plot(app)
        end

        % Value changed function: Options_ColormapDropDown
        function Options_ColormapDropDownValueChanged(app, event)

            app.SpecPlotSetDropDown.Value='Default';

            MapSettingsChanged(app)
        end

        % Value changed function: Options_InvertColormap
        function Options_InvertColormapValueChanged(app, event)
            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            update_active_plot(app)
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_LiveXMinMaxValueChanged(app, event)
            ValueMin = app.EditField_xlimMin.Value;
            ValueMax = app.EditField_xlimMax.Value;

            if ValueMin==ValueMax
                0;
            end

            if ValueMin < ValueMax
                try
                    set(app.hVerticalLines(1),'Value',ValueMin)
                    set(app.hVerticalLines(2),'Value',ValueMax)
                catch
                    app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                        xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                end

            end


            app.xlimits=[round(ValueMin) round(ValueMax)];

            if isempty(app.selected_sample) || app.selected_sample==0 || not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Data'))
                xlim(app.FigXlimLive,app.xlimits)

            else
                xlimval=[min([app.EditField_xlimMin.Value min(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)])...
                    max([app.EditField_xlimMax.Value max(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)])];
                xlim(app.FigXlimLive,xlimval)
            end

            if app.EditField_xlimMin.Value>app.Int_range_min.Value
                app.Int_range_min.Value= app.EditField_xlimMin.Value;
            end

            if app.EditField_xlimMax.Value<app.Int_range_max.Value
                app.Int_range_max.Value= app.EditField_xlimMax.Value;
            end

            UpdateSpectraPlot(app)


        end

        % Value changed function: EditField_climMax, EditField_climMin
        function EditField_LiveCMinMaxValueChanged(app, event)
            ValueMin = min([ app.EditField_climMin.Value app.EditField_climMax.Value]);
            ValueMax = max([ app.EditField_climMin.Value app.EditField_climMax.Value]);

            if app.EditField_climMax.Value < app.EditField_climMin.Value
                app.EditField_climMin.Value=ValueMin;
                app.EditField_climMax.Value=ValueMax;
            end
            try
                set(app.hVerticalLines(3),'Value',ValueMin)
                set(app.hVerticalLines(4),'Value',ValueMax)
            catch ME
                disp('error vertical lines do not exist')
                disp(ME.message)

                %                 app.hVerticalLines(3:4) = [xline(app.FigHistLive,ValueMin,'Color','black',"LineWidth",3)...
                %                     xline(app.FigHistLive, ValueMax,'Color','black',"LineWidth",3)];
            end
            if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))
                if app.Options_LogColormap.Value==false
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=[ValueMin ValueMax];
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=exp([ValueMin ValueMax]);
                end
            end

            if isempty(app.hLineToDrag)
                PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap), 'MapPlotMatrix')
                    update_FigHistLive(app)
                    if not(isequal(app.EditField_climMin.Value,app.EditField_climMax.Value)) % && caxislimits is within data bounds
                        caxis(app.MapPlot,   [min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_2,   [min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_UR,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_UL,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_OL,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                        caxis(app.MapPlot_OR,[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])]);
                    end


                    colormap(app.MapPlot,app.ColorMapValues);
                    colormap(app.MapPlot_2,app.ColorMapValues);
                    colormap(app.MapPlot_UR,app.ColorMapValues);
                    colormap(app.MapPlot_UL,app.ColorMapValues);
                    colormap(app.MapPlot_OL,app.ColorMapValues);
                    colormap(app.MapPlot_OR,app.ColorMapValues);

                    app.MapPlot.ColorScale = app.ColorScale;
                    app.MapPlot_2.ColorScale = app.ColorScale;
                    app.MapPlot_UR.ColorScale = app.ColorScale;
                    app.MapPlot_UL.ColorScale = app.ColorScale;
                    app.MapPlot_OL.ColorScale = app.ColorScale;
                    app.MapPlot_OR.ColorScale = app.ColorScale;


                end
            end
        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            reset_zoom_pan(app)
            click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.015; %ATH Settingspanel
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
              %  xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                end
            end

            line_vals =cell2mat(get(app.hVerticalLines(1:2),'Value'));
            
            if event.IntersectionPoint(1)*0.985 > min(line_vals) && event.IntersectionPoint(1)*1.015 < max(line_vals)
                app.hLineToDrag = app.hVerticalLines(1:2);
           
            end
        end

        % Button down function: FigHistLive
        function FigHistLiveButtonDown(app, event)
            reset_zoom_pan(app)
            click_buffer=(max(app.FigHistLive.XLim)-min(app.FigHistLive.XLim))*0.01; %ATH Settingspanel
            for k=3:4
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                end
            end

        end

        % Button down function: SpectraPlot
        function SpectraPlotButtonDown(app, event)
            reset_zoom_pan(app)
            click_buffer=(max(app.SpectraPlot.XLim)-min(app.SpectraPlot.XLim))*0.01; %ATH Settingspanel
            if strcmp(app.MapGenDropDown.Value,'Absolute')
                xVertLineCoord = get(app.hVerticalLines_SP(1),'Value');
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag_SP = app.hVerticalLines_SP(1);
                end
            else
                for k=1:2
                    try
                        xVertLineCoord = get(app.hVerticalLines_SP(k),'Value');
                    catch
                        update_lines(app)
                        xVertLineCoord = get(app.hVerticalLines_SP(k),'Value');
                    end
                    if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                        app.hLineToDrag_SP = app.hVerticalLines_SP(k);
                        break;
                    end
                end


                line_vals =cell2mat(get(app.hVerticalLines_SP(1:2),'Value'));

                if event.IntersectionPoint(1) > min(line_vals)*1.015 && event.IntersectionPoint(1) < max(line_vals)*0.985
                    app.hLineToDrag_SP = app.hVerticalLines_SP(1:2);

                end
            end
        end

        % Window button motion function: SpecMapsUIFigure
        function SpecMapsUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag_SP)

                currentPoint   = app.SpectraPlot.CurrentPoint;
                x            = currentPoint(1,1);
                %y            = currentPoint(1,3);
                xlim = app.SpectraPlot.XLim;
                %ylim = app.FigXlimLive.YLim;
                if numel(app.hLineToDrag_SP)==1
                    inside_SpectraPlot = x >= xlim(1)-(xlim(1)*app.clickbuffer) & x <= xlim(2)+(xlim(2)*app.clickbuffer); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                    if inside_SpectraPlot
                        set(app.hLineToDrag_SP, 'Value', x);
                        if strcmp(app.MapGenDropDown.Value,'Absolute')
                            app.Int_range_max.Value = get(app.hVerticalLines_SP(1),'Value');
                        else
                            xposlines=[get(app.hVerticalLines_SP(1),'Value') get(app.hVerticalLines_SP(2),'Value')];
                            app.Int_range_max.Value = min(xposlines);
                            app.Int_range_min.Value = max(xposlines);
                        end
                    end
                elseif numel(app.hLineToDrag_SP)==2

                    old_vals=cell2mat(get(app.hLineToDrag_SP(1:2), 'Value'));
                    diff_lines=0.5*(max(old_vals)-min(old_vals));

                    currentPoint   = app.SpectraPlot.CurrentPoint;
                    x            = currentPoint(1,1);

                    inside_SpectraPlot = x+diff_lines >= xlim(1) & x+diff_lines <= xlim(2) & x-diff_lines >= xlim(1) & x-diff_lines <= xlim(2);
                    if inside_SpectraPlot
                        set(app.hLineToDrag_SP(1), 'Value', x+diff_lines);
                        set(app.hLineToDrag_SP(2), 'Value', x-diff_lines);
                        xposlines=[get(app.hVerticalLines_SP(1),'Value') get(app.hVerticalLines_SP(2),'Value')];
                        app.Int_range_max.Value = min(xposlines);
                        app.Int_range_min.Value = max(xposlines);
                    end

                end

                %                 else
                %                     SpecMapsUIFigureWindowButtonUp(app)
                %                 end


            end

            if ~isempty(app.hLineToDrag)
                if isempty(app.SpecMapsUIFigure.WindowButtonUpFcn)

                    %                     for i = 5:6 % Turn zoom buttons off
                    %                         handles.MapPlot_2.Children(i).Value = 'off';
                    %                         handles.MapPlot_2.Children(i).Value = 'off';
                    %                         handles.MapPlot.Children(i).Value = 'off';
                    %                         handles.MapPlot.Children(i).Value = 'off';
                    %                     end
                    %                     app.SpecMapsUIFigure.WindowButtonUpFcn=@(source,event)tryCallback(appdesigner.internal.service.AppManagementService.instance(),app,callback,requiresEventData,event);
                    %                     disp('ButtonUpFcn restarted')
                    %                     app.hLineToDrag=[];
                    %                     return
                end

                if strcmp(app.Plot_Limit_Settings.SelectedTab.Title,'X Limit')
                    currentPoint   = app.FigXlimLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    %y            = currentPoint(1,3);
                    xlim = app.FigXlimLive.XLim;
                    %ylim = app.FigXlimLive.YLim;
                    if numel(app.hLineToDrag)==1
                        inside_FigXlimLive = x >= xlim(1) & x <= xlim(2); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                        if inside_FigXlimLive
                            set(app.hLineToDrag, 'Value', x);
                            xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            app.EditField_xlimMin.Value = min(xposlines);
                            app.EditField_xlimMax.Value = max(xposlines);

                        else
                            % SpecMapsUIFigureWindowButtonUp(app)
                        end
                    elseif numel(app.hLineToDrag)==2

                        old_vals=cell2mat(get(app.hLineToDrag(1:2), 'Value'));
                        diff_lines=0.5*(max(old_vals)-min(old_vals));

                        inside_FigXlimLive = x+diff_lines >= xlim(1) & x+diff_lines <= xlim(2) & x-diff_lines >= xlim(1) & x-diff_lines <= xlim(2);
                        if inside_FigXlimLive
                            set(app.hLineToDrag(2), 'Value', x+diff_lines);
                            set(app.hLineToDrag(1), 'Value', x-diff_lines);
                            xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            app.EditField_xlimMin.Value = min(xposlines);
                            app.EditField_xlimMax.Value = max(xposlines);
                        end
                    end
                elseif strcmp(app.Plot_Limit_Settings.SelectedTab.Title,'C Limit')
                    currentPoint   = app.FigHistLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,3);
                    xlim = app.FigHistLive.XLim;
                    ylim = app.FigHistLive.YLim;
                    inside_FigHistLive = x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2); %(val*app.clickbuffer)

                    if inside_FigHistLive
                        set(app.hLineToDrag, 'Value', x);
                        xposlines=[get(app.hVerticalLines(3),'Value') get(app.hVerticalLines(4),'Value')];
                        app.EditField_climMin.Value = min(xposlines);
                        app.EditField_climMax.Value = max(xposlines);
                    else
                        update_FigHistLive(app)
                        SpecMapsUIFigureWindowButtonUp(app)
                    end
                end
            end
        end

        % Window button up function: SpecMapsUIFigure
        function SpecMapsUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag_SP)
                app.hLineToDrag_SP = [];

                if strcmp(app.MapGenDropDown.Value,'Absolute')
                    xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                    region=app.Int_range_max.Value;
                    if max(region)>max(xl) || max(region)<min(xl)
                        app.Int_range_max.Value=round((max(xl)-min(xl))/2 )+min(xl);
                    end

                    set(app.hVerticalLines_SP(1),'Value',app.Int_range_max.Value)
                else
                    set(app.hVerticalLines_SP(1),'Value',app.Int_range_max.Value)
                    set(app.hVerticalLines_SP(2),'Value',app.Int_range_min.Value)
                end
            end

            if ~isempty(app.hLineToDrag)

                app.hLineToDrag = [];

                if strcmp(app.Plot_Limit_Settings.SelectedTab.Title,'X Limit')

                    app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                    set(app.hVerticalLines(1),'Value',app.EditField_xlimMin.Value)
                    set(app.hVerticalLines(2),'Value',app.EditField_xlimMax.Value)

                    xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                    if strcmp(app.MapGenDropDown.Value,'Absolute')
                        region=app.Int_range_max.Value;
                        if max(region)>max(xl) || max(region)<min(xl)
                            app.Int_range_max.Value=round((max(xl)-min(xl))/2 )+min(xl);
                        end

                    else
                        region=[app.Int_range_max.Value app.Int_range_min.Value];
                        if min(region)<min(xl) || min(region)>max(xl) || max(region)>max(xl) || max(region)<min(xl)
                            app.Int_range_min.Value=min(xl);
                            app.Int_range_max.Value=max(xl);
                        end
                    end


                    UpdateSpectraPlot(app)

                    update_FigXlimLive(app)

                else
                    if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))


                        if app.Options_LogColormap.Value==false
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=[app.EditField_climMin.Value app.EditField_climMax.Value];
                        else
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=exp([app.EditField_climMin.Value app.EditField_climMax.Value]);
                        end

                    end

                    PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                    EditField_LiveCMinMaxValueChanged(app)
                end

            end
        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: DeleteSpectraButton
        function RemoveEntryButtonPushed(app, event)
            N = app.TreeData_Main.SelectedNodes;
            if isempty(N)
                UpdateTree(app);
                return
            end

            selectedNodes = N.NodeData;

            cla(app.SpectraPlot)
            cla(app.MapPlot)
            cla(app.MapPlot_2)
            cla(app.MapPlot_UR)
            cla(app.MapPlot_UL)
            cla(app.MapPlot_OL)
            cla(app.MapPlot_OR)
            cla(app.FigXlimLive)
            cla(app.FigHistLive)


            if strcmp(N.Text,'DeconGroup')
                i=N.NodeData(2);
                j=N.NodeData(3);
                k=N.NodeData(4);

                decon_start=find(app.SpecMap.Spectra.Sample(i).Data(j).MapType(k:end)==51);

                if numel(decon_start)>1
                    decon_2_delete=find(ismember(app.SpecMap.Spectra.Sample(i).Data(j).MapType(k+decon_start(1):k+decon_start(2)-1),(51:59)))+k-1;
                else
                    decon_2_delete=find(ismember(app.SpecMap.Spectra.Sample(i).Data(j).MapType(k:end),(51:59)))+k-1;
                end

                maps2delete=repmat(N.NodeData,numel(decon_2_delete),1);
                maps2delete(:,end)=decon_2_delete; %ATH check
                try
                    for n=flip(1:numel(decon_2_delete))
                        selectedNodes(4)=decon_2_delete(n);
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(selectedNodes(4))=[];
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames(selectedNodes(4))=[];
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(selectedNodes(4))=[];
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'mapsize')
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).mapsize(app.selected_subsample,:)=[];
                        end
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(selectedNodes(4))=[];
                    end
                catch ME
                    disp(ME.message)
                    disp('Error in RemoveEntryButtonPushed')

                end

            else
                if numel(selectedNodes)==5
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(selectedNodes(4)).SubMap(selectedNodes(5))=[];
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(selectedNodes(4)).SubMapNames(selectedNodes(5))=[];
                elseif numel(selectedNodes)==4
                    if selectedNodes(1)>2
                        maps2delete=cell2mat({N.Children.NodeData}');
                        maps2delete=maps2delete(:,end);
                        for n=1:flip(numel(maps2delete))
                            selectedNodes(4)=maps2delete(n);
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(selectedNodes(4))=[];
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames(selectedNodes(4))=[];
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(selectedNodes(4))=[];
                            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'mapsize')
                                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).mapsize(app.selected_subsample,:)=[];
                            end
                            app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(selectedNodes(4))=[];
                        end
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(selectedNodes(4))=[];
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames(selectedNodes(4))=[];
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(selectedNodes(4))=[];
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'mapsize')
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).mapsize(app.selected_subsample,:)=[];
                        end
                        app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(selectedNodes(4))=[];
                    end

                    %ATH add all things that need to be removed eg baseline
                elseif not(isempty(app.selected_sample)) && (isempty(app.selected_subsample))
                    app.SpecMap.Spectra.Sample(app.selected_sample)=[];
                    app.SpecMap.Spectra.Names(app.selected_sample)=[];
                    app.selected_sample=[];
                elseif not(isempty(app.selected_subsample))
                    app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample)=[];
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample)=[];
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample)=[];


                    app.selected_subsample=[];
                end
            end
            if numel(app.SpecMap.Spectra.Names)<1
                app.SpecMap.Spectra.Names={};
                app.SpecMap.Spectra.Sample(1).Names={};
            end
            UpdateTree(app);

        end

        % Menu selected function: ImportMetadataMenu
        function AddMetadataButtonPushed(app, event)
            if not(isempty(app.selected_subsample)) && not(isempty(app.selected_sample))
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [file,path]=uigetfile({'*.csv;*.xls;*.txt;'},"MultiSelect","off", ['Select Metadata for' char('')]);
                delete(f);
                if not(isequal(file,0))
                    read_metadata(app,path,file)
                end
            end
            SelectionChangeFun(app)
        end

        % Menu selected function: ImportImageMenu
        function AddImageButtonPushed(app, event)
            if isempty(app.selected_sample)
                return
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.*'},"MultiSelect","off",'Select Image File');
            delete(f);

            if not(isequal(file,0))
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')&& not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id))
                    ImageGroup_Id= app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id;
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'n_Images')
                        ImageID=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images+1;
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images=0;
                        ImageID=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.n_Images+1;

                    end
                else
                    ImageGroup_Id=numel(app.SpecMap.Images.Images)+1;
                    app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id=ImageGroup_Id;
                    ImageID=1;
                end

                %    import_image(app,path,file,ImageGroup_Id,ImageId)
                %import_image(app,path,file) % ATH add
                import_image(app,path,file,ImageGroup_Id,ImageID)

                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [file,path]=uigetfile({'*.txt'},"MultiSelect","off",'Select Image Information File');
                delete(f);               %import_image_info(app,path,file)
                app.SpecMap.Images.Images(ImageGroup_Id).ImageLimits{ImageID} = import_image_info(app,path,file);
                SelectionChangeFun(app)
            end
        end

        % Callback function: AutoImport_Button, BrukerIFGBernMenu
        function AutoImportButtonPushed(app, event)
            if ischar(event)
                path=event;
            else
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir;
                delete(f); %delete the dummy figure
                if isequal(path,0)
                    figure(app.SpecMapsUIFigure);
                    return
                end
            end
            %             S = dir([path '**/*.*']);
            %             subfolder_names={(S(~ismember({S.name},{'.','..'})).name)};
            %             number_of_subfolders=sum([S(~ismember({S.name},{'.','..'})).isdir]);
            %             if number_of_subfolders==0
            %                 autoimport_data_from_folder(app,path)
            %             else
            %                 for m=1:numel(subfolder_names)
            %                     if ~strcmp(subfolder_names{m}(1), '.')
            %                         pathsf=fullfile(path, subfolder_names{m});
            %                         autoimport_data_from_folder(app,pathsf)
            %                     end
            %                 end
            %             end
            %             UpdateTree(app);

            S = dir([path '/**/*.*']);
            [~, uniqueS]=unique({S.folder});
            S=S(uniqueS);
            S2=S([S.isdir]);

            if app.SpecMapsUIFigure.Visible==true
                d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Importing Maps',...
                    'Message','Automatic Import Started','Cancelable','on');
            else
                fig=uifigure;
                d = uiprogressdlg(fig,'Title','Importing Maps',...
                    'Message','Automatic Import Started','Cancelable','on');
            end
            % drawnow


            for n=1:numel(S2)
                d.Value = n/numel(S2);

                if d.CancelRequested
                    break
                end


                try
                    %subfolder_names={(S2(~ismember({S2.name},{'.','..'})).name)};
                    number_of_subfolders=sum([S2(~ismember({S2.name},{'.','..'})).isdir]);
                    if number_of_subfolders==0 % ath add contains .mat
                        path=S2(n).folder;
                        d.Message = ['Importing ' path];
                        autoimport_data_from_folder(app,path)
                    end
                catch ME
                    disp(['Total Import Error - ' path])
                end
            end
            UpdateTree(app);
            app.selected_sample=[];
            app.TreeData_Main.SelectedNodes=[];
            SelectionChangeFun(app)
            figure(app.SpecMapsUIFigure);
            delete(d)
            if app.SpecMapsUIFigure.Visible==false
                close(fig)
            end

        end

        % Button pushed function: climMin_p10
        function climMin_p10ButtonPushed(app, event)
            %  app.EditField_climMin.Value=app.EditField_climMin.Value+10;
            app.EditField_climMin.Value=app.EditField_climMin.Value+max(app.FigHistLive.XLim)*0.10;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: cimMin_p1
        function cimMin_p1ButtonPushed(app, event)
            %   app.EditField_climMin.Value=app.EditField_climMin.Value+1;
            app.EditField_climMin.Value=app.EditField_climMin.Value+max(app.FigHistLive.XLim)*0.01;

            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: climMin_m1
        function climMin_m1ButtonPushed(app, event)
            app.EditField_climMin.Value=app.EditField_climMin.Value-max(app.FigHistLive.XLim)*0.01;
            EditField_LiveCMinMaxValueChanged(app)

        end

        % Button pushed function: climMin_m10
        function climMin_m10ButtonPushed(app, event)
            app.EditField_climMin.Value=app.EditField_climMin.Value-max(app.FigHistLive.XLim)*0.10;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: climMax_m10
        function climMax_m10ButtonPushed(app, event)
            app.EditField_climMax.Value=app.EditField_climMax.Value-max(app.FigHistLive.XLim)*0.10;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: climMax_m1
        function climMax_m1ButtonPushed(app, event)
            app.EditField_climMax.Value=app.EditField_climMax.Value-max(app.FigHistLive.XLim)*0.01;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: climMax_p1
        function climMax_p1ButtonPushed(app, event)
            app.EditField_climMax.Value=app.EditField_climMax.Value+max(app.FigHistLive.XLim)*0.01;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Button pushed function: climMax_p10
        function climMax_p10ButtonPushed(app, event)
            app.EditField_climMax.Value=app.EditField_climMax.Value+max(app.FigHistLive.XLim)*0.10;
            EditField_LiveCMinMaxValueChanged(app)
        end

        % Callback function: LoadProjectMenu, LoadProject_Button
        function LoadProjectMenuSelected(app, event)
            if app.edit_state==true
                if isfield(app.SpecMap,'projectpath') && not(isempty(app.SpecMap.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.SpecMap.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end

                answer = questdlg({['Do you want to save your project' projectname '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveProjectMenuSelected(app)
                    case 'No'
                        % continue
                    case 'Cancel'
                        return
                end
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            delete(f);
            figure(app.SpecMapsUIFigure);

            if not(isequal(file,0))
                d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Please Wait',...
                    'Message','Loading Project... This might take some time');
                thx=load(fullfile(path,file));
                app.SpecMap=thx.data2save;
                version_update_fun(app)
                UpdateTree(app);


                app.SpecMap.projectpath=fullfile(path,file);
                app.SpecMapsUIFigure.Name = app.SpecMap.projectpath;
                app.selected_sample=1;
                app.selected_subsample=1;
                app.selected_map=1;
                app.selected_submap=1;
                app.Always_Update_Map=false;
                SelectionChangeFun(app)
                update_class_tree(app,app.ClassTree);
                %update_active_plot(app)
                delete(d)
            end
        end

        % Callback function: Button_2, SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            if isfield(app.SpecMap,'projectpath') && not(isempty(app.SpecMap.projectpath))
                d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Please Wait',...
                    'Message','Saving Project... This might take some time');
                data2save=app.SpecMap;
                save(app.SpecMap.projectpath, 'data2save', '-v7.3');
                delete(d)
                app.SpecMapsUIFigure.Name = app.SpecMap.projectpath;
                disp(['Project Saved: ' app.SpecMap.projectpath])
            else
                SaveProjectAsMenuSelected(app)
            end

        end

        % Callback function: Button_3, SaveProjectAsMenu
        function SaveProjectAsMenuSelected(app, event)
            [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            if not(isequal(filename,0))
                d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Please Wait',...
                    'Message','Saving Project... This might take some time');
                FileName = fullfile(filepath, filename);
                app.SpecMap.projectpath=FileName;
                data2save=app.SpecMap;
                save(FileName, 'data2save', '-v7.3');
                % app.Label.Text=app.SpecMap.projectpath;
                app.SpecMapsUIFigure.Name = app.SpecMap.projectpath;
                disp(['Project Saved: ' app.SpecMap.projectpath])
                delete(d)
            end
        end

        % Menu selected function: ExportMapMenu, ExportModuleMenu
        function ExportMapMenuSelected(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            SampleNames=app.SpecMap.Spectra.Names;

            ExportModule(app, sampleinfo, app.TreeData_Main, SampleNames)
        end

        % Node text changed function: TreeData_Main
        function TreeData_MainNodeTextChanged(app, event)
            node = event.Node;
            if node.NodeData(1)==2
                if numel(node.NodeData)>4 && node.NodeData(5)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Data(node.NodeData(3)).Maps(node.NodeData(4)).SubMapNames{node.NodeData(5)}=node.Text;
                elseif numel(node.NodeData)>3 && node.NodeData(4)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Data(node.NodeData(3)).MapNames{node.NodeData(4)}=node.Text;
                elseif numel(node.NodeData)>2 && node.NodeData(3)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Names{node.NodeData(3)}=node.Text;
                elseif numel(node.NodeData)>1 && node.NodeData(2)>0
                    app.SpecMap.Spectra.Names{node.NodeData(2)}=node.Text;
                end
            end
            UpdateTree(app);
        end

        % Value changed function: SpectraTypeDropDown
        function SpectraTypeDropDownValueChanged(app, event)
            type=str2double(app.SpectraTypeDropDown.Value);
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType=type;
            TypeChangeFun(app)
        end

        % Menu selected function: DuplicateEntryMenu
        function DuplicateEntryMenuSelected(app, event)


            %
            %             if not(isempty(app.selected_sample)) && (isempty(app.selected_subsample))
            %                 app.SpecMap.Spectra.Names{end+1}=[app.SpecMap.Spectra.Names{app.selected_sample} '_copy'];
            %                 new_sample_entry=numel(app.SpecMap.Spectra.Names);
            %                 app.SpecMap.Spectra.Sample(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample);
            %                 UpdateTree(app);
            %
            %             elseif not(isempty(app.selected_subsample))
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).Names(end+1)=app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample);
            %                 new_sample_entry=numel(app.SpecMap.Spectra.Names);
            %
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).Data(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample);
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).Settings(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample);
            %
            %                 UpdateTree(app);
            %             end

            %
            N=app.TreeData_Main.SelectedNodes;

            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            if numel(selectedNodes)==2 || (numel(selectedNodes)==3 && selectedNodes(3)==0)
                app.SpecMap.Spectra.Names{end+1}=[app.SpecMap.Spectra.Names{app.selected_sample} '_copy'];
                new_sample_entry=numel(app.SpecMap.Spectra.Names);
                app.SpecMap.Spectra.Sample(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample);
            elseif numel(selectedNodes)==3 || (numel(selectedNodes)==4 && selectedNodes(4)==0)
                app.SpecMap.Spectra.Sample(app.selected_sample).Names(end+1)=app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample);
                new_sample_entry=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample);
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(new_sample_entry)=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample);

            elseif numel(selectedNodes)==4 || (numel(selectedNodes)==5 && selectedNodes(5)==0)
                %ATH add map duplication
            elseif numel(selectedNodes)==5 || (numel(selectedNodes)==6 && selectedNodes(6)==0)
                %ATH add submap duplication
            end
            UpdateTree(app);
            SelectionChangeFun(app)

        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            if not(isempty(app.selected_subsample))
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image=find(strcmp(app.ImageDropDown.Items,app.ImageDropDown.Value));%ATH here image with same name
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).selected_image(1);
                %ath here
                SelectionChangeFun(app)
            end
        end

        % Callback function
        function AddMapROIButtonPushed(app, event)
            get_UI_ROI_SpecPlot(app)
        end

        % Button pushed function: AddSinglePeakButton
        function AddSinglePeakButtonPushed(app, event)
            fhv = app.SpecMapsUIFigure.HandleVisibility;        % Current status
            app.SpecMapsUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.SpecMapsUIFigure)       % Make fig current
            [x, ~] = selectDataPoints(app, app.SpectraPlot);

            x=round(x,1);
            app.SpecMapsUIFigure.HandleVisibility = fhv;
            %Spec=app.datatable.SpecName(app.datatable.active==true); ath
            %add here multiple specnames option

            Spec=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
            y=mean(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2,"omitnan")';


            [ ~, ix ] = min( abs( app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal-x ) );
            y=y(ix);


            if ~isnan(y)
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Peaks'))
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks=[];
                end

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'UIpeaks')

                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=[app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks;table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'})];
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
                end
            end

            update_peaktable_Data(app)


            UpdateSpectraPlot(app)

        end

        % Button pushed function: AddPeaksButton
        function AddPeaksButtonPushed(app, event)
            new_UI_peaks = inputdlg('Enter space-separated numbers:',...
                'Enter Peaks', [1 50]);
            figure(app.SpecMapsUIFigure);

            if ~isempty(new_UI_peaks)
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Peaks'))
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks=[];
                end
                new_UI_peaks=unique(str2num(new_UI_peaks{1})); %#ok<ST2NM>
                for n=1:length(new_UI_peaks)
                    x=new_UI_peaks(n);
                    Spec=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};

                    y=mean(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal,2,"omitnan")';


                    [ ~, ix ] = min( abs( app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal-x ) );
                    y=y(ix);

                    if ~isnan(y)
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'UIpeaks')
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=[app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks;table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'})];
                        else

                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=table(x,y,0,0,{Spec},{'UI Peak'},'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
                        end
                    end
                end
                update_peaktable_Data(app)
                UpdateSpectraPlot(app)
            end

            figure(app.SpecMapsUIFigure);

        end

        % Button pushed function: ClearPeaksButton
        function ClearPeaksButtonPushed(app, event)

            if  isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Peaks')
                if  isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'Autopeaks')
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks=[];
                end
                if  isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'UIpeaks')
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=[];
                end
                if  isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'DeconPeaks')
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks=[];
                end
            end

            update_peaktable_Data(app)
            UpdateSpectraPlot(app)

        end

        % Button pushed function: DeletePeakButton
        function DeletePeakButtonPushed(app, event)
            if isempty(app.PeakTable.Selection)
                return
            end
            Value=app.PeakTable.Selection(:,1);
            if Value>0
                peaks2delete=app.PeakTable.Data.PeakPosition(Value);
                peaks2source=app.PeakTable.Data.Source(Value);


                for n=1:length(peaks2delete)
                    if strcmp(peaks2source(n),'Autodetected Peak')
                        if sum(ismember(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks.PeakPosition,peaks2delete(n)))>0
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks(ismember(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks.PeakPosition,peaks2delete(n)),:)=[];
                        end
                    end
                    if strcmp(peaks2source(n),'UI Peak')
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks(ismember(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks.PeakPosition,peaks2delete(n)),:)=[];
                    end

                    if strcmp(peaks2source(n),'Deconvolution Peak')
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks(ismember(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.PeakPosition,peaks2delete(n)),:)=[];
                    end
                end
                app.PeakTable.Selection=[];
                update_peaktable_Data(app)
                UpdateSpectraPlot(app)

            end
        end

        % Button pushed function: MeanAutofitButton
        function MeanAutofitButtonPushed(app, event)
            % try
            center=app.xlimits(1)+((app.xlimits(2)-app.xlimits(1))/2);
            window=(app.xlimits(2)-app.xlimits(1));
            peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
            extra=app.ExtraEditField.Value;
            NumTrials=app.IterationsSpinner.Value;
            start=0;

            autozero=app.AutozeroSpinner.Value;

            if app.PlotFigureCheckBox.Value==true
                plots=1;
            else
                plots=0;
            end

            if app.BipolarCheckBox.Value==false
                bipolar=0;
            else
                bipolar=1;
            end

            minwidth=2;

            numb_peaks_min=app.MinPeaksSpinner.Value;
            numb_peaks_max=app.MaxPeaksSpinner.Value;


            y_signal=mean(app.plotmat,2);

            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;

            x_signal=x_signal(x_signal>=min(app.xlimits) & x_signal<=max(app.xlimits),:);

            signal=[x_signal y_signal];
            GOF_best1=[100 0];

            if not(strcmp(char(app.FitShapeDropDown.Value),'Iterative Gauss Fit'))
                if  peakshape==16 ||peakshape==17
                    fixedparameters=unique(app.PeakTable.Data.PeakPosition);
                    numb_peaks_min=numel(fixedparameters);
                    numb_peaks_max=numel(fixedparameters);
                else
                    fixedparameters=0;
                end
            end

            if app.UIPeaksStartCheckBox.Value==true && numel(unique(app.PeakTable.Data.PeakPosition)) > 0
                numb_peaks_min=numel(unique(app.PeakTable.Data.PeakPosition));
                numb_peaks_max=numel(unique(app.PeakTable.Data.PeakPosition));
            end

            iter=false;
            for n=numb_peaks_min:numb_peaks_max
                if app.PlotFigureCheckBox.Value==true
                    figure(n)
                end

                NumPeaks=n;

                if app.UIPeaksStartCheckBox.Value==true

                    if isempty(app.PeakTable.Data)
                        xrange=(max(app.xlimits)-min(app.xlimits));
                        peakwidth=repmat(xrange/(3.*NumPeaks),size(app.PeakTable.Data.PeakPosition));

                    else
                        peakwidth=app.PeakTable.Data.Width;
                        peakwidth(peakwidth==0)=0.5;
                    end

                    start=[app.PeakTable.Data.PeakPosition peakwidth]';
                    start=start(:)';
                end

                %[FitResults,GOF,baseline,coeff,residual,xi,yi]
                if strcmp(char(app.FitShapeDropDown.Value),'Iterative Gauss Fit')

                    [gaussianParameters,GOF] = iterative_Gauss_fit(x_signal',y_signal',NumPeaks,plots);
                    %                             GOF=[(max(Rsquared)),min(Rsquared)];
                    g=exp(-((x_signal' - gaussianParameters(:,2)) ./ (0.60056120439323 .* gaussianParameters(:,3))) .^ 2)';
                    g2=g.*gaussianParameters(:,1)';
                    area=trapz(x_signal,g2);
                    FitResults=[(1:size(gaussianParameters,1))' gaussianParameters(:,2)  gaussianParameters(:,1)  gaussianParameters(:,3) area'];

                else
                    [FitResults,GOF,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth);
                end
                if GOF(1)<=GOF_best1(1) || iter==false
                    %                                 signal_best1=signal;
                    GOF_best1=GOF;
                    FitResults_best1=FitResults;
                    iter=true;
                    %                                 baseline_best1=baseline;
                    %                                 coeff_best1=coeff;
                    %                                 residual_best1=residual;
                    %                                 xi_best1=xi;
                    %                                 yi_best1=yi;
                    NumPeaks_best1=NumPeaks;
                end

                if app.PlotFigureCheckBox.Value==true
                    subplot(2,1,1)
                    if strcmp(app.SpectraPlot.XDir,'reverse')
                        set (gca, 'xdir', 'reverse')
                    end
                    subplot(2,1,2)
                    if strcmp(app.SpectraPlot.XDir,'reverse')
                        set (gca, 'xdir', 'reverse')
                    end
                end

                if exist('FitResults_best1','var')
                    DeconvolutionResults=array2table(FitResults_best1,"VariableNames",{'Peak_Number','Peak_Position','Peak_Height','Peak_Width','Peak_area'});
                else
                    DeconvolutionResults=array2table(FitResults,"VariableNames",{'Peak_Number','Peak_Position','Peak_Height','Peak_Width','Peak_area'});
                end

                DeconvolutionResults.Peak_Position=round(DeconvolutionResults.Peak_Position);
                DeconvolutionResults(DeconvolutionResults.Peak_Position<min(x_signal)|DeconvolutionResults.Peak_Position>max(x_signal),:)=[];%ATH fix limits
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks=table(DeconvolutionResults.Peak_Position,DeconvolutionResults.Peak_Height,DeconvolutionResults.Peak_Width,DeconvolutionResults.Peak_area,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.Spec(:)=app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample);
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.Source(:)={'Deconvolution Peak'};
            end

            message = {['Best Fit with ' num2str(NumPeaks_best1) ' peaks'],...
                ['Error: ' num2str(GOF_best1(1)) ' %'] ,['R2: ' num2str(GOF_best1(2))],...
                ['Peak Positions: ' char(strjoin(string(flipud(round(DeconvolutionResults.Peak_Position)))',', '))]};
            uialert(app.SpecMapsUIFigure,message,'Peak Deconvolution Done','Icon','success');

            update_peaktable_Data(app)
            UpdateSpectraPlot(app)

            %             catch
            %
            %                 message='Error deconvolution failed with selected parameter';
            %                 uialert(app.SpecMapsUIFigure,message,'Error');
            %             end


        end

        % Button pushed function: AutoPeakDetectionButton
        function AutoPeakDetectionButtonPushed(app, event)
            y_signal=mean(app.plotmat,2,"omitnan");
            if app.DerivativeCheckBox.Value==true
                %%%%% ath add -derive here

                for i=1:abs(app.DerivativeSpinner.Value)
                    y_signal=gradient(y_signal);
                end

                if app.DerivativeSpinner.Value<0
                    y_signal=-y_signal;
                end
                %%%


                y_signal=-gradient(gradient(y_signal));
            end
            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            x_signal=x_signal(x_signal>=min(app.xlimits) & x_signal<=max(app.xlimits),:);


            if x_signal(1)>x_signal(end)
                [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(flip(y_signal),flip(x_signal),'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
                %  findpeaks(flip(y_signal),flip(x_signal),'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
            else
                [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
                %   findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value,'WidthReference','halfheight');
            end


            [~,idx]=sort(Prominence,"descend");
            if numel(PeakPosition)>=app.PointstoClassifySpinner.Value
                PeakIntensity=PeakIntensity(idx);
                PeakIntensity=PeakIntensity(1:app.PointstoClassifySpinner.Value);

                PeakPosition=PeakPosition(idx);
                PeakPosition=PeakPosition(1:app.PointstoClassifySpinner.Value);

                Width=Width(idx);
                Width=Width(1:app.PointstoClassifySpinner.Value);

                Prominence=Prominence(idx);
                Prominence=Prominence(1:app.PointstoClassifySpinner.Value);

            else
                app.PointstoClassifySpinner.Value=numel(PeakPosition);
            end


            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks=table(PeakPosition,PeakIntensity,Width,Prominence,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks.Spec(:)=app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample);
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.Autopeaks.Source(:)={'Autodetected Peak'};
            update_peaktable_Data(app)
            UpdateSpectraPlot(app)
        end

        % Button pushed function: TestButton1
        function TestButton1Pushed(app, event)
            % try
            center=app.xlimits(1)+((app.xlimits(2)-app.xlimits(1))/2);
            window=(app.xlimits(2)-app.xlimits(1));
            peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');
            extra=app.ExtraEditField.Value;
            NumTrials=app.IterationsSpinner.Value;
            start=0;

            autozero=app.AutozeroSpinner.Value;

            if app.PlotFigureCheckBox.Value==true
                plots=1;
            else
                plots=0;
            end

            if app.BipolarCheckBox.Value==false
                bipolar=0;
            else
                bipolar=1;
            end

            minwidth=2;

            numb_peaks_min=app.MinPeaksSpinner.Value;
            numb_peaks_max=app.MaxPeaksSpinner.Value;


            y_signal=app.plotmat;

            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            x_signal=x_signal(x_signal>=min(app.xlimits) & x_signal<=max(app.xlimits),:);
            %signal=[x_signal y_signal];
            GOF_best1=[100 0];

            if not(strcmp(char(app.FitShapeDropDown.Value),'Iterative Gauss Fit'))
                if  peakshape==16 ||peakshape==17
                    fixedparameters=unique(app.PeakTable.Data.PeakPosition);
                    numb_peaks_min=numel(fixedparameters);
                    numb_peaks_max=numel(fixedparameters);
                else
                    fixedparameters=0;
                end
            end

            if app.UIPeaksStartCheckBox.Value==true && numel(unique(app.PeakTable.Data.PeakPosition)) > 0
                numb_peaks_min=numel(unique(app.PeakTable.Data.PeakPosition));
                numb_peaks_max=numel(unique(app.PeakTable.Data.PeakPosition));
            end

            iter=false;
            for n=numb_peaks_min:numb_peaks_max
                if app.PlotFigureCheckBox.Value==true
                    figure(n)
                end

                NumPeaks=n;

                if app.UIPeaksStartCheckBox.Value==true
                    pos=app.PeakTable.Data.PeakPosition;

                    if isempty(app.PeakTable.Data)
                        xrange=(max(app.xlimits)-min(app.xlimits));
                        peakwidth=repmat(xrange/(3.*NumPeaks),size(app.PeakTable.Data.PeakPosition));

                    else
                        xrange=(max(app.xlimits)-min(app.xlimits));

                        peakwidth=app.PeakTable.Data.Width;
                        peakwidth2=repmat(xrange/(3.*NumPeaks),size(app.PeakTable.Data.PeakPosition));
                        peakwidth(peakwidth==0)=peakwidth2(peakwidth==0);

                    end

                    start=[app.PeakTable.Data.PeakPosition peakwidth]';
                    start=start(:)';
                end

                %[FitResults,GOF,baseline,coeff,residual,xi,yi]
                for h=1:size(y_signal,2)
                    signal=[x_signal y_signal(:,h)];

                    if strcmp(char(app.FitShapeDropDown.Value),'Iterative Gauss Fit')

                        [gaussianParameters,GOF] = iterative_Gauss_fit(x_signal',y_signal',NumPeaks,plots);
                        %                             GOF=[(max(Rsquared)),min(Rsquared)];
                        g=exp(-((x_signal' - gaussianParameters(:,2)) ./ (0.60056120439323 .* gaussianParameters(:,3))) .^ 2)';
                        g2=g.*gaussianParameters(:,1)';
                        area=trapz(x_signal,g2);
                        FitResults=[(1:size(gaussianParameters,1))' gaussianParameters(:,2)  gaussianParameters(:,1)  gaussianParameters(:,3) area'];

                    else
                        [FitResults,GOF,~,~,~,~,~]=peakfit(signal,center,round(window),NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth);
                    end


                    if sum(FitResults(:,2)==0)<numel(FitResults(:,2))
                        disp(FitResults)
                        %                          test(:,h)=FitResults(:,1);
                        %                          test2(:,h)=FitResults(:,2);
                        test1(:,h)=FitResults(:,2); %# ok

                        %
                        if app.UIPeaksStartCheckBox.Value==true
                            shift=flipud(pos)-test1;
                        end


                        %i1=[test(:,1) flipud(pos) round(test1(:,1)) shift(:,1)]; %# ok

                    end
                    if GOF(1)<=GOF_best1(1) || iter==false
                        %                                 signal_best1=signal;
                        GOF_best1=GOF;
                        FitResults_best1=FitResults;
                        iter=true;


                        %                                 baseline_best1=baseline;
                        %                                 coeff_best1=coeff;
                        %                                 residual_best1=residual;
                        %                                 xi_best1=xi;
                        %                                 yi_best1=yi;
                        NumPeaks_best1=NumPeaks;
                    end

                    if app.PlotFigureCheckBox.Value==true
                        subplot(2,1,1)
                        if strcmp(app.SpectraPlot.XDir,'reverse')
                            set (gca, 'xdir', 'reverse')
                        end
                        subplot(2,1,2)
                        if strcmp(app.SpectraPlot.XDir,'reverse')
                            set (gca, 'xdir', 'reverse')
                        end
                    end

                    if exist('FitResults_best1','var')
                        DeconvolutionResults=array2table(FitResults_best1,"VariableNames",{'Peak_Number','Peak_Position','Peak_Height','Peak_Width','Peak_area'});
                    else
                        DeconvolutionResults=array2table(FitResults,"VariableNames",{'Peak_Number','Peak_Position','Peak_Height','Peak_Width','Peak_area'});
                    end

                    DeconvolutionResults.Peak_Position=round(DeconvolutionResults.Peak_Position);
                    DeconvolutionResults(DeconvolutionResults.Peak_Position<min(x_signal)|DeconvolutionResults.Peak_Position>max(x_signal),:)=[];
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks=table(DeconvolutionResults.Peak_Position,DeconvolutionResults.Peak_Height,DeconvolutionResults.Peak_Width,DeconvolutionResults.Peak_area,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.Spec(:)=app.SpecMap.Spectra.Sample(app.selected_sample).Names(app.selected_subsample);
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.DeconPeaks.Source(:)={'Deconvolution Peak'};
                end
            end
            assignin('base','test',test);
            assignin('base','test1',test2);

            message = {['Best Fit with ' num2str(NumPeaks_best1) ' peaks'],...
                ['Error: ' num2str(GOF_best1(1)) ' %'] ,['R2: ' num2str(GOF_best1(2))],...
                ['Peak Positions: ' char(strjoin(string(flipud(round(DeconvolutionResults.Peak_Position)))',', '))]};
            uialert(app.SpecMapsUIFigure,message,'Peak Deconvolution Done','Icon','success');

            update_peaktable_Data(app)
            UpdateSpectraPlot(app)

            %             catch
            %
            %                 message='Error deconvolution failed with selected parameter';
            %                 uialert(app.SpecMapsUIFigure,message,'Error');
            %             end


        end

        % Button pushed function: GenerateMapButton
        function GenerateMapButtonPushed(app, event)
            app.Always_Update_Map=true;
            update_active_plot(app)
            UpdateInfoWindow(app)

        end

        % Menu selected function: ROIstoFigureMenu
        function ROIstoFigureMenuSelected(app, event)
            tiledlayout('flow')

            figure
            ax1 = nexttile;

            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            thx_val=app.SpecPlotSetDropDown.Value;
            app.SpecPlotSetDropDown.Value='All ROIs Mean';

            MapPlot_fun_public(app,ax1,sampleinfo,true,true,[],[]);
            app.SpecPlotSetDropDown.Value=thx_val;
            cbar=colorbar(ax1);

            colormap(ax1,PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value,1));
            cbar.Label.String = [app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}];
            cbar.Label.Interpreter='none';
            ax = nexttile;

            %% SpecPlot
            hold on


            %% SpecPlot
            [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==size(y_signal,2)
                tm=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map';
                y_signal=(y_signal./tm(:)').*1e4;
            elseif  app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1 %&& not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map,1))
                y_signal=(y_signal./app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map).*1e4;
            end


            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==size(y_signal,2) || (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1 && app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map>1))
                ylabel(ax,'Absorbance [cm^{-1}]')
            else
                ylabel(ax,'Absorbance')
            end

            try

                data=struct();
                settings=struct();
                settings(1).x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                %settings(1).y_offset=1+(app.YOffsetSpinner.Value/100);
                settings(1).y_offset=1+(15/100);

                settings(1).reset=true;
                if  strcmp(app.SpecPlotSetDropDown.Value,'All ROIs Stacked')
                    settings(1).type='Stacked';

                else % strcmp(app.SpecPlotSetDropDown.Value,'All ROI') || strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')
                    settings(1).type='Default';
                end

                settings(1).PlotLegend=false;%app.LegendCheckBox.Value;
                settings(1).PlotSpecNames=false;%app.Annotation_CheckBox.Value;

                if strcmp(app.SpecPlotSetDropDown.Value,'ROI Mean')
                    selected_ROI_n=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI;
                else
                    selected_ROI_n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI);
                end

                app.plotmat=zeros(size(y_signal,1),numel(selected_ROI_n));

                mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');%all_ids(not(mask_vector(:)));

                %     color_setting= colormap(PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI),1));
                color_setting=PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));
                %(lines(numel(selected_ROI_n)));

                ax.ColorOrder=color_setting;
                %ax.ColorOrder=  PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));

                ax.LineStyleOrder ='-';
                ax.ColorOrderIndex=1;

                for n=1:numel(selected_ROI_n)
                    selected_ROI=selected_ROI_n(n);
                    settings(n).PlotSpectra=false;
                    settings(n).PlotSignal=false;
                    settings(n).PlotMean=true;
                    settings(n).PlotSum=false;
                    settings(n).PlotRaw=false;
                    settings(n).PlotBaseline=false;
                    settings(n).PlotPeaks=false;

                    settings(n).Color.Spectra=[0 0 0];
                    settings(n).Color.Mean=color_setting(n,:);
                    settings(n).Color.Sum=[0 0 0];
                    settings(n).Color.Raw=[0 0 0];
                    settings(n).Color.Baseline=[0 0 0];
                    settings(n).Color.Peaks=[0 0 0];

                    settings(n).LineStyle.Spectra={'A-'};
                    settings(n).LineStyle.Signal={'-'};
                    settings(n).LineStyle.Mean={'-'};
                    settings(n).LineStyle.Sum={'-'};
                    settings(n).LineStyle.Raw={'-'};
                    settings(n).LineStyle.Baseline={'--'};
                    settings(n).LineStyle.Peaks={'Line'};

                    settings(n).LineWidth.Spectra=1;
                    settings(n).LineWidth.Signal=1;
                    settings(n).LineWidth.Mean=2;
                    settings(n).LineWidth.Sum=1;
                    settings(n).LineWidth.Raw=1;
                    settings(n).LineWidth.Baseline=1;
                    settings(n).LineWidth.Peaks=1;

                    settings(n).FaceAlpha.Spectra=0.2;
                    settings(n).FaceAlpha.Signal=0.2;
                    settings(n).FaceAlpha.Mean=2;
                    settings(n).FaceAlpha.Sum=0.2;
                    settings(n).FaceAlpha.Raw=0.2;
                    settings(n).FaceAlpha.Baseline=0.2;
                    settings(n).FaceAlpha.Peaks=0.2;


                    ROI=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Idx;
                    ROI=ROI(:);

                    if numel(mask_ids)>1
                        ROI=intersect(ROI,mask_ids);
                    end

                    if isempty(ROI)
                        app.plotmat(:,n)=0;

                        data(n).y_mean= app.plotmat(:,selected_ROI);
                        data(n).y= app.plotmat(:,selected_ROI);
                        data(n).x= x_signal;
                    else

                        [wn_cor,in_cor,~] = get_specs_cor(app,sampleinfo,app.xlimits,[x_signal,y_signal(:,ROI)],false);

                        app.plotmat(:,n)=mean(y_signal(:,ROI),2,"omitnan");
                        data(n).y_signal_mean=mean(in_cor,2,"omitnan");
                        data(n).y_signal=in_cor;
                        data(n).x_signal=wn_cor;
                    end
                end

            catch ME
                disp(ME.message)
                disp('Error in ROIstoFigureMenuSelected')
            end


            if app.Peaks_CheckBox.Value==true


            end

            PlotSpectra_fun(ax,data,settings)

            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end

            if min(app.xlimits)==max(app.xlimits)
                xlim(ax,'auto')
            else
                xlim(ax,sort([min(app.xlimits) max(app.xlimits)]))
            end
            ax.ColorOrder=  PlotMap_UpdateColormap(app,numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));


        end

        % Button pushed function: GenerateMaskButton
        function GenerateMaskMenuSelected(app, event)

            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            matrix=ThicknessCorrectionFun(app,matrix);

            if app.Options_LogColormap.Value==true
                minval=exp(app.EditField_climMin.Value);
                maxval=exp(app.EditField_climMax.Value);
            else
                minval=app.EditField_climMin.Value;
                maxval=app.EditField_climMax.Value;
            end


            mask=matrix <= maxval & matrix >= minval;

            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=mask;


            update_active_plot(app)

            disp('mask generated')
        end

        % Button pushed function: MaskSelectedButton
        function MaskSelectedButtonPushed(app, event)
            N=app.ClassTree.CheckedNodes;
            if not(isempty(N)) && not(isempty(app.selected_sample))
                selectedNodes = {N(:).NodeData};
                thx=zeros(size(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(1).ClassMap(1).MapPlotMatrix));
                for n=1:numel(selectedNodes)
                    if numel(selectedNodes{n})==3 && selectedNodes{n}(3)>0
                        matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes{n}(1)).ClassMap(selectedNodes{n}(2)).MapPlotMatrix;
                        thx=((matrix==selectedNodes{n}(3)) | thx);
                    end
                end

                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=thx;

            else
                N=app.ClassTree.SelectedNodes;
                if not(isempty(N)) && not(isempty(app.selected_sample))
                    selectedNodes = N.NodeData;
                else
                    return
                end
                if numel(selectedNodes)==3
                    matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                    app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=(matrix==selectedNodes(3));
                end
            end
            update_active_plot(app)
        end

        % Image clicked function: UnmaskSelection
        function AddtoMaskMenuSelected(app, event)
            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
            mask_old=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;

            matrix=ThicknessCorrectionFun(app,matrix);

            if app.Options_LogColormap.Value==true
                minval=exp(app.EditField_climMin.Value);
                maxval=exp(app.EditField_climMax.Value);
            else
                minval=app.EditField_climMin.Value;
                maxval=app.EditField_climMax.Value;
            end

            mask=(matrix <= maxval & matrix >= minval);

            if numel(mask_old)==numel(mask)
                mask=logical((mask_old)&(mask)); %((matrix <= maxval & matrix >= minval) | mask);
            end
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=mask;

            update_active_plot(app)
        end

        % Callback function
        function RemovefromMaskMenuSelected(app, event)
            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            end

            %             if app.ThicknessCorrectionCheckBox.Value==true && not(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==4 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==5 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)>10) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(matrix) || numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1)
            %                 matrix=(matrix./app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)*1e4;
            %             end
            matrix=ThicknessCorrectionFun(app,matrix);

            if app.Options_LogColormap.Value==true
                minval=exp(app.EditField_climMin.Value);
                maxval=exp(app.EditField_climMax.Value);
            else
                minval=app.EditField_climMin.Value;
                maxval=app.EditField_climMax.Value;
            end

            mask=matrix <= maxval & matrix >= minval;

            mask_old=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            if numel(mask_old)==numel(mask)
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=logical(mask +mask_old);
            end
            update_active_plot(app)
        end

        % Button pushed function: ResetButton
        function ResetMaskMenuSelected(app, event)
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=1;
            update_active_plot(app)
            update_thickness_field(app)

        end

        % Menu selected function: OpenThicknessCallibrationMenu, 
        % OpenThicknessCallibrationMenu_2, ThicknessCallibrationModuleMenu
        function ThicknessCallibrationMenuSelected(app, event)
            MatrixLimits=update_MatixLimits(app);
            mapdata=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            climits=[min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])];
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            ThicknessCorrection(app, mapdata, MatrixLimits,app.ColorMapValues,climits,sampleinfo); % if exist settings coefficient send with
        end

        % Menu selected function: ResetThicknessMapMenu_2
        function ResetThicknessMapMenuSelected(app, event)
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=1e4;
            update_active_plot(app)
        end

        % Value changed function: FilterMethod
        function FilterMethodValueChanged(app, event)

            if strcmp(app.FilterMethod.Value,'moving')
                app.FilterSpan.Value=8;
                app.FilterIterationSpinner.Value=1;
                app.FilterIterationSpinner.Tooltip='Number of iterations';
                app.FilterDegree.Visible=false;
            elseif strcmp(app.FilterMethod.Value,'sgolay')

                app.FilterIterationSpinner.Tooltip='Number of iterations';

            end
            MapSettingsChanged(app)
        end

        % Menu selected function: SubMapstoFigureMenu
        function SubMapstoFigureMenuSelected(app, event)

            figure

            MatrixLimits=update_MatixLimits(app);


            tiledlayout('flow')
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map),'SubMapNames') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames))
                for n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames)
                    nexttile
                    hold on
                    app.selected_submap;
                    matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(n).MapPlotMatrix;

                    if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                        matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                    end
                    matrix=ThicknessCorrectionFun(app,matrix);

                    imagesc((matrix),'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:))

                    t=title(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{n});
                    set(t, 'Interpreter', 'none')

                    colorbar
                    colormap(app.ColorMapValues);

                    % if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Maps') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps)) &&...
                    %                        isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map),'SubMap') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap)) &&...
                    %                         isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap),'climits') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits))
                    try
                        cmin=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(n).climits(1);
                        cmax=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(n).climits(2);
                        caxis([cmin cmax])
                        %   else
                    catch
                        mM=mean(matrix(:),"omitnan");
                        caxis([min([mM*0.2 mM*2]) max([mM*0.2 mM*2])])
                    end
                    axis image;

                end
            else

                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                thickness_state=true;
                plotimage=app.plot_image_checkbox.Value;
                ax = nexttile;

                matrix=MapPlot_fun_public(app,ax,sampleinfo,thickness_state,plotimage);
                try
                    cmin=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(n).climits(1);
                    cmax=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(n).climits(2);
                    caxis([cmin cmax])
                    %   else
                catch
                    mM=mean(matrix(:),"omitnan");
                    caxis([min([mM*0.2 mM*2]) max([mM*0.2 mM*2])])
                end
            end

            if not(isempty(app.xlimits))
                ax = nexttile;
                ax1 = app.SpectraPlot;
                ax1Chil = ax1.Children;
                copyobj(ax1Chil, ax)
                if strcmp(app.SpectraPlot.XDir,'reverse')
                    set (gca, 'xdir', 'reverse')
                end
                xlim([min(app.xlimits) max(app.xlimits)])
            end

            ts=sgtitle([app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}]);
            set(ts, 'Interpreter', 'none')


        end

        % Menu selected function: ClassestoFigureMenu
        function ClassestoFigureMenuSelected(app, event)
            %ATH might be wrong
            ClassTreeSelectionChanged(app, event)
            N=app.ClassTree.SelectedNodes;
            if not(isempty(N)) && not(isempty(app.selected_sample))
                selectedNodes = N.NodeData;
                if numel(selectedNodes)>=2 && N.NodeData(2)>0
                    app.ClassTree.SelectedNodes.NodeData=N.NodeData(1:2);
                    selected_node=N.NodeData(1:2);
                else
                    return
                end
                ClassTreeSelectionChanged(app, event)

            else
                return
            end


            % centimeters units
            X = 42.0;                  % A3 paper size
            Y = 29.7;                  % A3 paper size
            xMargin = 1;               % left/right margins from page borders
            yMargin = 1;               % bottom/top margins from page borders
            xSize = X - 2*xMargin;     % figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     % figure size on paper (widht & hieght)

            % create figure/axis
            hFig=figure('visible', 'on');
            % figure size displayed on screen (50% scaled, but same aspect ratio)


            % figure size printed on paper
            set(hFig, 'PaperUnits','centimeters')
            set(hFig, 'PaperSize',[X Y])
            set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig, 'PaperOrientation','portrait')
            hFig.Position=hFig.Position*1.25;


            MatrixLimits=update_MatixLimits(app);
            axa = subplot(2,3,1);
            hold on
            daspect([1 1 1])

            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            MapPlot_fun_public(app,axa,sampleinfo,true,false);


            xl=get(axa,'xlim');
            yl=get(axa,'ylim');
            axb = subplot(2,3,2);
            hold on
            daspect([1 1 1])

            imagesc((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).MapPlotMatrix-0.5),'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));

            %             ax1 = app.MapPlot;
            %             ax1Chil = ax1.Children;
            %             copyobj(ax1Chil, ax)

            values=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).MapPlotMatrix(:);
            if app.plot_image_checkbox.Value==true
                Image=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image{app.selected_image};
                ImageLimits=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits{app.selected_image};


                alpha=ones(size(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).MapPlotMatrix))*app.AlphaSlider.Value./100;
                image(flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));
                imagesc((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).MapPlotMatrix-0.5),'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:),'AlphaData',alpha);
            else
                imagesc(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).MapPlotMatrix-0.5,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));
            end
            cb=colorbar;
            %%

            n_classes=numel(unique(values(not(isnan(values)))));

            colormap(axb,PlotMap_UpdateColormap(app,n_classes,1));
            colormap(axa,PlotMap_UpdateColormap(app,256,1));

            %%
            caxis([0, n_classes])
            cb.YTick = 0.5 : 1 :  n_classes;
            cb.TickLabels = app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selected_node(1)).ClassMap(selected_node(2)).ClassesNames;
            set(cb,'TickLabelInterpreter','none')
            cb.FontSize = 8;


            axc = subplot(2,3,3);
            hold on
            daspect([1 1 1])


            if not(isempty(app.selected_image))

                Image=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image{app.selected_image};
                ImageLimits=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits{app.selected_image};

                image(flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));

                r=rectangle('Position',[MatrixLimits(1,1) MatrixLimits(2,1) (MatrixLimits(1,2)-MatrixLimits(1,1)) (MatrixLimits(2,2)-MatrixLimits(2,1))]);
                r.EdgeColor = 'b';
                r.LineWidth = 3;

            end

            daspect([1 1 1])


            ax = subplot(2,3,4:6);
            ax2 = app.SpectraPlot;
            ax2Chil = ax2.Children;
            copyobj(ax2Chil, ax)
            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end
            xlim([min(app.xlimits) max(app.xlimits)])

            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')

                ylabel('Absorbance / cm]')

            else
                ylabel('Absorbance')
            end

            xlabel('Wavenumber [cm^{-1}]')


            %ts=sgtitle([app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}]);
            ts=sgtitle([app.SpecMap.Spectra.Names{app.selected_sample} ]);
            set(ts, 'Interpreter', 'none')
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            path=uigetdir;
            delete(f);
            if isequal(path,0)
                figure(app.SpecMapsUIFigure);
                return
            end
            %path='/Users/nils/Documents/PhD_Bern/MATLAB/DevNG/JH_OL_export';
            file=app.SpecMap.Spectra.Names{app.selected_sample};
            if app.plot_image_checkbox.Value==true
                FileName=fullfile(path,[file '_class_imgage.eps']);
                if exist(FileName,'file')
                    FileName=fullfile(path,[file '_class_imgage2.eps']);

                end
            else
                FileName=fullfile(path,[file '_class.eps']);
            end
            set(hFig,'Units','Inches');
            pos = get(hFig,'Position');
            set(hFig,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
            linkaxes([axa,axb,axc],'xy')
            set(axa,'xlim',xl)
            set(axa,'ylim',yl)
            exportgraphics(hFig,FileName,'BackgroundColor','none','ContentType','vector');


        end

        % Image clicked function: InvertMask
        function InvertMaskMenuSelected(app, event)
            if not(isempty(app.selected_sample)) && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)>1
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask=not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask);
                update_active_plot(app)
            end
        end

        % Button pushed function: SimpleMapDeconButton
        function SimpleMapDeconButtonPushed(app, event)
            tic
            try
                x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
                range=x_signal>=app.xlimits(1) & x_signal<=app.xlimits(2);
                x_signal=x_signal(range,:);
                y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal(range,:);


                if app.BackgroundCheckBox.Value==true
                    ignore_co2=app.IgnoreCO2CheckBox.Value;
                    value1=app.BackgroundDegreeSpinner.Value;
                    value2=app.BCvalueSpinner_2.Value;
                    method=app.BackgroundMethodDropDown.Value;
                    interp_method=app.BackgroundMethodDropDown_2.Value;
                    signal_split=1;
                    [y_signal, baseline]=GetBaselineFun(x_signal,y_signal,signal_split,value1,value2,method,interp_method,ignore_co2);
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).baseline=baseline;


                end

                if app.FilterCheckBox.Value==true
                    for n=1:app.FilterIterationSpinner.Value
                        if strcmp(app.FilterMethod.Value,'sgolay')
                            y_signal=smoothdata(y_signal,1,'sgolay',app.FilterSpan.Value,'Degree',app.FilterDegree.Value);
                        else
                            y_signal=smoothdata(y_signal,1,app.FilterMethod.Value,app.FilterSpan.Value);
                        end
                    end
                end

                app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize),1)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:))) && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:),[0 0]))
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:);
                else
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                end

                try
                    unique(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks.PeakPosition);
                catch
                    disp('no peaks selected')
                    return
                end

                peaks2fit=unique(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks.PeakPosition);
                fixedparameters=peaks2fit(peaks2fit>=app.xlimits(1) & peaks2fit<=app.xlimits(2));

                NumPeaks=numel(fixedparameters);
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)) &&numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==(mapsize(1)*mapsize(2))
                    mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                else
                    mask=ones(mapsize(1)*mapsize(2),1);
                    %mask=1;
                end
                steps = size(y_signal,2);

                disp('Calculating Peakmap')

                peakmat_hight=zeros(steps,NumPeaks);
                peakmat_width=zeros(steps,NumPeaks);
                peakmat_shift=zeros(steps,NumPeaks);
                peakmat_area=zeros(steps,NumPeaks);
                peakstart=zeros(steps,NumPeaks);

                peakmat_error=zeros(steps,1);
                peakmat_error_r2=zeros(steps,1);
                peakmat_pos=sort(fixedparameters);


                if not(isempty(app.fwb))
                    delete(app.fwb)
                end

                app.fwb = waitbar(0,{'Starting Calculalculation', 'Estimating remaining time...',''},'Name','Calculating Peakmap',...
                    'CreateCancelBtn','setappdata(gcbf,''canceling'',1)','WindowStyle','modal');
                frames = java.awt.Frame.getFrames();
                frames(end).setAlwaysOnTop(1);

                figure(app.SpecMapsUIFigure);
                %setappdata(app.fwb,'canceling',0);

                % parameter here
                counter=0;

                mask_sort=mask';

                steps = sum(mask_sort(:));


                start=0;
                center=round(app.xlimits(1))+((round(app.xlimits(2))-round(app.xlimits(1)))/2);
                window=round((app.xlimits(2))-round(app.xlimits(1)));
                extra=app.ExtraEditField.Value;

                autozero=app.AutozeroSpinner.Value;

                if app.BipolarCheckBox.Value==false
                    bipolar=0;
                else
                    bipolar=1;
                end

                minwidth=app.MinWidthEditField.Value;
                plots=0;
                % peakshape=16;
                % peakshape=1;
                peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');

                if  app.UIPeaksStartCheckBox.Value==true
                    if numel(mask_sort(:))==size(y_signal,2)
                        signal=[x_signal mean(y_signal(:,mask_sort(:)),2,"omitnan")];
                    else
                        signal=[x_signal mean(y_signal,2,"omitnan")];
                    end

                    NumTrials=50;
                    [FitResults,~,~,~,~,~]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth);
                    pos=FitResults(:,2)';
                    wid=FitResults(:,4)';
                    start=[];

                    s=[pos;wid];
                    start(:,1)=s(:);

                end
                NumTrials=app.IterationsSpinner.Value;
                I=1;%ATH

                T=zeros(1,steps);
                if  app.UIPeaksStartCheckBox.Value==true
                    % start=[pos;wid];
                    % start=start(:);
                    %start=zeros(:,size(app.start_peaks)+2);
                    s=[pos;wid];
                    start(:,2)=s(:);
                    start(:,3:size(app.start_peaks,2)+2)=app.start_peaks;
                end
                %peakshape=16;
                figure(app.fwb);
                test=find(mask_sort);
                for n=1:sum(mask_sort(:))%1:steps
                    id=test(n);
                    tic


                    if getappdata(app.fwb,'canceling')
                        %reset stuff here
                        delete(app.fwb)
                        return
                    end
                    % Update waitbar and message


                    counter=counter+1;

                    if counter>1
                        rest=sum(mask_sort(:))-n;
                        tmean= sum(T(2:counter))/(counter-1);
                        trest=tmean*(rest);

                        if tmean>=1
                            waitbar(n/steps,app.fwb,{['Calculating Pixel ' num2str(counter) ' of ' num2str(steps)], ['Estimated remaining time: ' datestr(datenum(0,0,0,0,0,trest),'HH:MM:SS')], ['Mean Time/Pixel: ' datestr(datenum(0,0,0,0,0,tmean),'HH:MM:SS')]})
                        else
                            waitbar(n/steps,app.fwb,{['Calculating Pixel ' num2str(counter) ' of ' num2str(steps)], ['Estimated remaining time: ' datestr(datenum(0,0,0,0,0,trest),'HH:MM:SS')], ['Mean Time/Pixel: ' datestr(datenum(0,0,0,0,0,tmean),'HH:MM:SS.FFF')]})
                        end
                    else
                        waitbar(n/steps,app.fwb,{['Calculating Pixel ' num2str(counter) ' of ' num2str(steps)], 'Estimating remaining time...',''})
                    end


                    signal=[x_signal y_signal(:,id)];
                    multi_start=false;
                    if multi_start==true && not(isempty(app.start_peaks))  %ATH Add multiple start solutions depending on ROIs / UIStarts or Last solution
                        NumPeaks=size(app.start_peaks,1)/2;
                        rmsq=zeros(size(start,2),2);
                        s=[pos;wid];
                        start(:,1)=s(:);

                        parfor i=1:size(start,2)
                            [ FR{i},GF{i},~,~,~,~,~]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start(:,i),autozero,fixedparameters,plots,bipolar,minwidth);
                            rmsq(i,:)=GF{i}
                        end
                        [~,I]=max(rmsq(:,2));
                        GOF=GF{I};
                        FitResults=FR{I};

                    elseif true==false
                        N=app.ClassTree.SelectedNodes;
                        selectedNodes = N.NodeData;


                        classmap=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix(:);

                        for i=classmap(id)
                            [ FitResults,GOF,~,~,~,~,~]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start(:,i),autozero,fixedparameters,plots,bipolar,minwidth);
                        end
                        I=classmap(id);

                    else

                        [FitResults,GOF,~,~,~,~,~]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth);
                    end
                    pos=FitResults(:,2)';
                    wid=FitResults(:,4)';
                    amp=FitResults(:,3)';

                    if app.PlotFigureCheckBox.Value==true
                        cla(app.SpectraPlot)
                        plot(app.SpectraPlot,x_signal,y_signal(:,id),'LineWidth',2,'Color','k','LineStyle','-')


                        try
                            %
                            y_calc=DeconPlotFun(peakshape,x_signal',pos',wid',amp',0,0)';

                        catch
                            y_calc=gaussplot(app,pos',amp',wid',x_signal')';

                        end
                        plot(app.SpectraPlot,x_signal,sum(y_calc,2),'LineWidth',2,'Color','g','LineStyle','-')
                        plot(app.SpectraPlot,x_signal',y_calc,'LineWidth',1,'Color','g','LineStyle','-')
                    end


                    [~,idx] = sort(FitResults(:,2)); % sort just the first column
                    FitResults = FitResults(idx,:); % sort the matrix
                    peakmat_hight(id,:)=FitResults(:,3)';


                    peakmat_area(id,:)=FitResults(:,5)';
                    peakmat_width(id,:)=FitResults(:,4)';

                    peakmat_error(id,:)=GOF(:,1)';
                    peakmat_error_r2(id,:)=GOF(:,2)';
                    peakstart(id,:)=I;

                    if counter>=1
                        T(counter)= toc;
                    end


                end

                %%
                PeakTeststruc.peakmat_hight=peakmat_hight;
                PeakTeststruc.peakmat_area=peakmat_area;
                PeakTeststruc.peakmat_width=peakmat_width;
                PeakTeststruc.peakmat_shift=peakmat_shift;
                PeakTeststruc.peakmat_pos_shift=peakmat_pos'+peakmat_shift;
                PeakTeststruc.peakmat_error=peakmat_error;
                PeakTeststruc.peakmat_error_r2=peakmat_error_r2;
                PeakTeststruc.peakstart=peakstart;
                PeakTeststruc.peakmat_pos=sort(fixedparameters)';


                %% store everything in structure
                %peakfit input


                PeakFit.center=center;
                PeakFit.window=window;
                PeakFit.NumPeaks=NumPeaks;
                PeakFit.peakshape=peakshape;
                PeakFit.extra=extra;
                PeakFit.NumTrials=NumTrials;
                PeakFit.start=start;
                PeakFit.BaselineMode=autozero;
                PeakFit.fixedparameters=fixedparameters;
                PeakFit.plots=plots;
                PeakFit.bipolar=bipolar;
                PeakFit.minwidth=minwidth;
                PeakFit.DELTA=[];
                PeakFit.clipheight=[];
                PeakFit.fixedparameters_shift=[];
                PeakTeststruc.PeakFit=PeakFit;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc=PeakTeststruc;


                %%
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=['PeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
                app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                app.selected_submap=3;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=51; % %Peakdecon Map with submaps


                matrix2=0;
                for n=1:NumPeaks
                    peaknr=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc.peakmat_pos(n);
                    peakdata=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc.peakmat_area(:,n);
                    matrix=reshape(peakdata,[],mapsize(2))';
                    matrix2=matrix+matrix2;

                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}=['PeakMap_' num2str(peaknr)];
                    app.selected_submap=app.selected_submap+1;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_submap,:)=mapsize;
                end

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(1).MapPlotMatrix=matrix2;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{1}='SumAllPeaks';
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(1,:)=mapsize;


                matrix=rawmap2matrix(app,x_signal,y_signal,[min(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion) max(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion)],mapsize);

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(2).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{2}=['Total_Integration_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(2,:)=mapsize;

                % r2 error map
                peakdata=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc.peakmat_error_r2(:);
                matrix=reshape(peakdata,[],mapsize(2))';
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(1,:)=mapsize;

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}='Total Fit R2 Error';
                app.selected_submap=app.selected_submap+1;

                %  error map
                peakdata=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc.peakmat_error(:);
                matrix=reshape(peakdata,[],mapsize(2))';
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(1,:)=mapsize;

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}='Total Fit Error';
                app.selected_submap=app.selected_submap+1;

                %if uistart and roi start
                peakdata=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.PeakTeststruc.peakstart(:);
                matrix=reshape(peakdata,[],mapsize(2))';
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(1,:)=mapsize;

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}='PeakStartMap';


                time=sum(T);
                ids=size(y_signal,2);
                disp(['Calculated ' num2str(counter) ' of ' num2str(ids) ' Pixels in ' datestr(datenum(0,0,0,0,0,time),'HH:MM:SS')])
                UpdateTree(app);
                delete(app.fwb)

            catch ME
                delete(app.fwb)
                disp('error simple peak decon')
                disp(ME.message)
            end

        end

        % Button pushed function: AdvancedPeakDeconButton
        function ParforPeakDeconButtonPushed(app, event)
            tic
            try

                if strcmp(app.ParalellDropDown.Value,'parfor')
                    ButtonName = questdlg( {'Are you sure you want to use Parfor?','Parfor can not be canceled and progress is not shown.'}, ...
                        'Parfor Selected', ...
                        'Yes', 'Cancel','');
                    if strcmp(ButtonName,'Cancel')
                        return
                    end
                    figure(app.SpecMapsUIFigure);
                end


                d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Please Wait',...
                    'Message','Advanced Deconvolution started... This might take some time','Cancelable','on');
                ParaSetting=app.ParalellDropDown.Value; %'parfeval';
                parn=3;
                if strcmp(ParaSetting,'parfeval')  || strcmp(ParaSetting,'parfevalOnAll') || strcmp(ParaSetting,'parfor')
                    delete(gcp('nocreate'))
                    if parn==1
                        p = parpool('Processes');
                    elseif parn==2
                        p = parpool('local');
                    elseif parn==3
                        p =  parpool('Threads');
                    end

                    d.Message='Preparing Calculation - Starting parallel pool (parpool) ...';
                end


                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                %range=sort([app.Int_range_min.Value app.Int_range_max.Value]);
                range=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                [x_signal,y_signal,~] = get_specs(app,sampleinfo,range);

                sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);

                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize),1)>=sampleinfo(3) && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:))) && not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:),[0 0]))
                    mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(3),:);
                else
                    mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
                end

                try
                    unique(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.UIpeaks.PeakPosition);
                catch
                    disp('no peaks selected')
                    return
                end

                peaks2fit=unique(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.UIpeaks.PeakPosition);
                fixedparameters=peaks2fit(peaks2fit>=app.xlimits(1) & peaks2fit<=app.xlimits(2));

                NumPeaks=numel(fixedparameters);
                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)) &&numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==(mapsize(1)*mapsize(2))
                    mask=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                else
                    mask=ones(mapsize(1)*mapsize(2),1);
                end

                disp('Calculating Peakmap')
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_hight=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_width=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_area=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_shift=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error_r2=[];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos=sort(fixedparameters)';

                % parameter here
                steps = size(y_signal,2);
                mask_sort=mask';

                start=0;
                center=round(app.xlimits(1))+((round(app.xlimits(2))-round(app.xlimits(1)))/2);
                window=round((app.xlimits(2))-round(app.xlimits(1)));
                extra=app.ExtraEditField.Value;

                autozero=app.AutozeroSpinner.Value;

                if app.BipolarCheckBox.Value==false
                    bipolar=0;
                else
                    bipolar=1;
                end

                minwidth=app.MinWidthEditField.Value;
                plots=0;
                clipheight=[];
                %peakshape=16;
                peakshape=sscanf(char(app.FitShapeDropDown.Value),'%d');

                if  app.UIPeaksStartCheckBox.Value==true
                    if numel(mask_sort(:))==size(y_signal,2)
                        signal=[x_signal mean(y_signal(:,mask_sort(:)),2,"omitnan")];
                    else
                        signal=[x_signal mean(y_signal,2,"omitnan")];
                    end

                    NumTrials=50;
                    [FitResults,~,~,~,~,~]=peakfit(signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth);
                    pos=FitResults(:,2)';
                    wid=FitResults(:,4)';
                    start=[];

                    s=[pos;wid];
                    start(:,1)=s(:);

                end
                NumTrials=app.IterationsSpinner.Value;
                I=1;


                if  app.UIPeaksStartCheckBox.Value==true
                    % start=[pos;wid];
                    % start=start(:);
                    %start=zeros(:,size(app.start_peaks)+2);
                    s=[pos;wid];
                    start(:,2)=s(:);
                    start(:,3:size(app.start_peaks,2)+2)=app.start_peaks;
                end


                starttest=not(isempty(app.start_peaks));

                if isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && not(isempty(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)) &&numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==(mapsize(1)*mapsize(2))
                    mask=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                else
                    mask=ones(mapsize(1)*mapsize(2),1);
                end
                peakmat_pos=sort(fixedparameters)';

                DELTA=app.DELTAEditField.Value;
                if app.LimitShiftCheckBox.Value
                    fixedparameters_shift=repmat([-DELTA DELTA],NumPeaks,1);
                else
                    fixedparameters_shift=[0 0];
                end
                %                 tmp_var=0;
                %                 if numel(mask)==steps % check piel id / mask(:)
                %                     tmp_var=1:steps;
                %                     tmp_var=tmp_var(mask(:));
                %                 end

                peakmat_area=zeros(steps,NumPeaks);
                peakmat_hight=zeros(steps,NumPeaks);
                peakmat_width=zeros(steps,NumPeaks);
                peakmat_shift=zeros(steps,NumPeaks);
                peakmat_error=zeros(steps,1);
                peakmat_error_r2=zeros(steps,1);
                peakstart=zeros(steps,1);

                mask_sort2=mask_sort;

                id2=find(mask_sort2);

                for parn=3

                    peakmat_area_=zeros(numel(id2),NumPeaks);
                    peakmat_hight_=zeros(numel(id2),NumPeaks);
                    peakmat_width_=zeros(numel(id2),NumPeaks);
                    peakmat_shift_=zeros(numel(id2),NumPeaks);
                    peakmat_error_=zeros(numel(id2),1);
                    peakmat_error_r2_=zeros(numel(id2),1);
                    peakstart_=zeros(numel(id2),1);


                    tic
                    if strcmp(ParaSetting,'parfeval')  || strcmp(ParaSetting,'parfevalOnAll')
                        if strcmp(ParaSetting,'parfeval')
                            f(1:numel(id2)) = parallel.FevalFuture;

                            for N =1:numel(id2)
                                id3=id2(N);
                                f(N) = p.parfeval(@calculate_pixel,7,I,starttest,mask(id3),mask_sort(id3),1,x_signal,y_signal(:,id3),center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight,fixedparameters_shift);
                                d.Value =N/numel(id2);

                                if d.CancelRequested
                                    delete(gcp('nocreate'))
                                    return
                                end


                            end
                        elseif  strcmp(ParaSetting,'parfevalOnAll')
                            f(1:numel(id2)) = parallel.FevalFuture;

                            for N =1:numel(id2)
                                id3=id2(N);
                                f(N) = p.parfevalOnAll(@calculate_pixel,7,I,starttest,mask(id3),mask_sort(id3),1,x_signal,y_signal(:,id3),center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight,fixedparameters_shift);
                                d.Value =N/numel(id2);
                                if d.CancelRequested
                                    delete(gcp('nocreate'))
                                    return
                                end


                            end
                        end
                        %            d.Message={'Deconvolution in Progress... ', ['0/' num2str(numel(id2))], 'Time / pixel: Estimating...', 'Estimated time left: Estimating...' };

                        while sum(contains({f.State},'finished'))<numel(id2)
                            if d.CancelRequested
                                d.Message='Deconvolution Canceled... Please Wait';

                                delete(gcp('nocreate'))
                                return
                            end

                            finished_spectra=sum(contains({f.State},'finished'));
                            if finished_spectra>0
                                time_elapsed = toc;
                                toc1= time_elapsed/finished_spectra;
                                time = (numel(id2)-finished_spectra)*toc1;

                                d.Value =finished_spectra/numel(id2);

                                d.Message={['Deconvolution in Progress: ', num2str(finished_spectra) '/' num2str(numel(id2)) '  -  (' num2str(round(finished_spectra/numel(id2)*100),2) ' %)' ], ['Mean Time / Pixel: ' datestr(datenum(0,0,0,0,0,toc1),'MM:SS.FFF')],[ 'Estimated time left: ' datestr(datenum(0,0,0,0,0,time),'HH:MM:SS')],['Calculation time: ' datestr(datenum(0,0,0,0,0,time_elapsed),'HH:MM:SS')]};
                                pause(1)
                            end
                        end
                        if strcmp(ParaSetting,'parfeval')
                            [peakmat_hight_,peakmat_area_, peakmat_width_, peakmat_error_,peakmat_error_r2_,peakstart_,peakmat_shift_] = fetchOutputs(f);
                        else
                            for n=1:numel(id2)
                                [peakmat_hight_(n,:),peakmat_area_(n,:), peakmat_width_(n,:), peakmat_error_(n,:),peakmat_error_r2_(n,:),peakstart_(n,:),peakmat_shift_(n,:)] = fetchOutputs(f(n));
                            end
                        end
                    elseif strcmp(ParaSetting,'no paralell')
                        %% for loop no paralell
                        delete(d)
                        if not(isempty(app.fwb))
                            delete(app.fwb)
                        end

                        app.fwb = waitbar(0,{'Starting Calculalculation', 'Estimating remaining time...',''},'Name','Calculating Peakmap',...
                            'CreateCancelBtn','setappdata(gcbf,''canceling'',1)','WindowStyle','modal','Position', [100 100 300 200]);
                        frames = java.awt.Frame.getFrames();
                        frames(end).setAlwaysOnTop(1);
                        app.fwb.Position(4)=app.fwb.Position(4)*1.25;
                        figure(app.SpecMapsUIFigure);
                        %setappdata(app.fwb,'canceling',0);


                        for id=1:numel(id2)

                            try
                                [peakmat_hight_out,peakmat_area_out,peakmat_width_out,peakmat_error_out,peakmat_error_r2_out,peakstart_out,shift_out] =  calculate_pixel(I,starttest,mask,mask_sort,id2(id),x_signal,y_signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight,fixedparameters_shift);
                            catch ME
                                delete(app.fwb)
                                disp(' Deconvolution Error')
                                disp(ME.message)
                                disp(ME.stack)
                                id2(id)
                                peakmat_area_out=0;
                                peakmat_hight_out=0;
                                shift_out=0;
                                peakmat_error_out=100;
                                peakmat_error_r2_out=0;
                                peakstart_out=0;
                                peakmat_width_out=0;

                            end
                            peakmat_area_(id,:)=peakmat_area_out;
                            peakmat_hight_(id,:)=peakmat_hight_out;
                            peakmat_width_(id,:)=peakmat_width_out;
                            peakmat_shift_(id,:)=shift_out;

                            peakmat_error_(id,:)=peakmat_error_out;
                            peakmat_error_r2_(id,:)=peakmat_error_r2_out;
                            peakstart_(id,:)=peakstart_out;


                            if getappdata(app.fwb,'canceling')
                                delete(app.fwb)
                                return
                            end

                            %% Update waitbar and message
                            if id>1
                                rest=numel(id2)-id;
                                tmean= time_elapsed/(id);
                                trest=tmean*(rest);

                                if tmean>=1


                                    waitbar(id/steps,app.fwb,{['Calculating Pixel ' num2str(id) ' of ' num2str(steps)], ['Estimated remaining time: ' datestr(datenum(0,0,0,0,0,trest),'HH:MM:SS')], ['Mean Time/Pixel: ' datestr(datenum(0,0,0,0,0,tmean),'HH:MM:SS')]})
                                else

                                    %  waitbar(id/steps,app.fwb,{['Calculating Pixel ' num2str(id) ' of ' num2str(steps)], ['Estimated remaining time: ' datestr(datenum(0,0,0,0,0,trest),'HH:MM:SS')], ['Mean Time/Pixel: ' datestr(datenum(0,0,0,0,0,tmean),'HH:MM:SS.FFF')]})
                                    waitbar(id/steps,app.fwb,{['Deconvolution in Progress: ', num2str(id) '/' num2str(numel(id2)) '  -  (' num2str(round(id/numel(id2)*100),2) ' %)' ], ['Mean Time / Pixel: ' datestr(datenum(0,0,0,0,0,tmean),'MM:SS.FFF')],[ 'Estimated time left: ' datestr(datenum(0,0,0,0,0,trest),'HH:MM:SS')],['Calculation time: ' datestr(datenum(0,0,0,0,0,time_elapsed),'HH:MM:SS')]});
                                end
                            end


                            if app.PlotFigureCheckBox.Value==true
                                cla(app.SpectraPlot)
                                plot(app.SpectraPlot,x_signal,y_signal(:,id2(id)),'LineWidth',2,'Color','k','LineStyle','-')
                                try
                                    pos=peakmat_pos'+shift_out';
                                    wid=peakmat_width_out';
                                    amp=peakmat_hight_out';
                                    y_calc=DeconPlotFun(peakshape,x_signal,pos',wid',amp',DELTA,extra);

                                    plot(app.SpectraPlot,x_signal',sum(y_calc,2),'LineWidth',2,'Color','g','LineStyle','-')
                                    plot(app.SpectraPlot,x_signal,y_calc,'LineWidth',1,'Color','g','LineStyle','-')
                                catch ME
                                    disp('error in ParforPeakDeconButtonPushed')
                                    disp(ME.message)
                                end
                            end

                            time_elapsed= toc;
                        end
                    elseif strcmp(ParaSetting,'parfor')
                        %% parfor

                        parfor_progress(steps);
                        parfor id=1:numel(id2)

                            if numel(mask)==steps

                                try
                                    [peakmat_hight_out,peakmat_area_out,peakmat_width_out,peakmat_error_out,peakmat_error_r2_out,peakstart_out,shift_out] =  calculate_pixel(I,starttest,mask,mask_sort,id2(id),x_signal,y_signal,center,window,NumPeaks,peakshape,extra,NumTrials,start,autozero,fixedparameters,plots,bipolar,minwidth,DELTA,clipheight,fixedparameters_shift);
                                catch ME
                                    disp('Parfor Deconvolution Error')
                                    disp(ME.message)
                                    disp(ME.stack)
                                    id2(id)
                                    peakmat_area_out=0;
                                    peakmat_hight_out=0;
                                    shift_out=0;
                                    peakmat_error_out=100;
                                    peakmat_error_r2_out=0;
                                    peakstart_out=0;
                                    peakmat_width_out=0;

                                end
                                peakmat_area_(id,:)=peakmat_area_out;
                                peakmat_hight_(id,:)=peakmat_hight_out;
                                peakmat_width_(id,:)=peakmat_width_out;
                                peakmat_shift_(id,:)=shift_out;

                                peakmat_error_(id,:)=peakmat_error_out;
                                peakmat_error_r2_(id,:)=peakmat_error_r2_out;
                                peakstart_(id,:)=peakstart_out;
                                try
                                    parfor_progress;
                                catch ME
                                    disp(ME)
                                    disp('Error in ParforPeakDeconButtonPushed - parfor')
                                end
                            end
                        end
                        parfor_progress(0);


                    end
                end
                peakmat_area(id2,:)=peakmat_area_;
                peakmat_hight(id2,:)=peakmat_hight_;
                peakmat_width(id2,:)=peakmat_width_;
                peakmat_shift(id2,:)=peakmat_shift_;

                peakmat_error(id2,:)=peakmat_error_;
                peakmat_error_r2(id2,:)=peakmat_error_r2_;
                peakstart(id2,:)=peakstart_;


            catch ME
                disp('Parfor Deconvolution Error 2')
                disp(ME.message)
              %   disp(ME.stack.line)
                delete(d)

                toc

                parfor_progress(0);
                return
            end
try
            delete(gcp('nocreate'));
catch
end
            time_elapsed=toc;
            toc1= time_elapsed/numel(id2);

            %WaitMessage.Destroy

            PeakTeststruc.peakmat_hight=peakmat_hight;
            PeakTeststruc.peakmat_area=peakmat_area;
            PeakTeststruc.peakmat_width=peakmat_width;
            PeakTeststruc.peakmat_shift=peakmat_shift;
            PeakTeststruc.peakmat_pos_shift=peakmat_pos+peakmat_shift;
            PeakTeststruc.peakmat_error=peakmat_error;
            PeakTeststruc.peakmat_error_r2=peakmat_error_r2;
            PeakTeststruc.peakstart=peakstart;
            PeakTeststruc.peakmat_pos=sort(fixedparameters)';


            %% store everything in structure
            %peakfit input


            PeakFit.center=center;
            PeakFit.window=window;
            PeakFit.NumPeaks=NumPeaks;
            PeakFit.peakshape=peakshape;
            PeakFit.extra=extra;
            PeakFit.NumTrials=NumTrials;
            PeakFit.start=start;
            PeakFit.BaselineMode=autozero;
            PeakFit.fixedparameters=fixedparameters;
            PeakFit.plots=plots;
            PeakFit.bipolar=bipolar;
            PeakFit.minwidth=minwidth;
            PeakFit.DELTA=DELTA;
            PeakFit.clipheight=clipheight;
            PeakFit.fixedparameters_shift=fixedparameters_shift;
            PeakTeststruc.PeakFit=PeakFit;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc=PeakTeststruc;


            %% create integrated Peak map
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['IntPeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
            sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            sampleinfo(4)=3;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=51; %Peakdecon int Map with submaps

            matrix2=0;
            for n=1:NumPeaks
                peaknr=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(n);
                peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_area(:,n);
                matrix=reshape(peakdata,[],mapsize(2))';
                matrix2=matrix+matrix2;

                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}=['IntPeakMap_' num2str(peaknr)];
                sampleinfo(4)=sampleinfo(4)+1;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(4),:)=mapsize;
            end

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(1).MapPlotMatrix=matrix2;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{1}='SumAllPeaks';
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;

            matrix=rawmap2matrix(app,x_signal,y_signal,[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion) max(app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion)],mapsize);

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(2).MapPlotMatrix=matrix;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{2}=['Total_Integration_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(2,:)=mapsize;

            %             % r2 error map
            %             peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error_r2(:);
            %             matrix=reshape(peakdata,[],mapsize(2))';
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;
            %
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}='Total Fit R2 Error';
            %             sampleinfo(4)=sampleinfo(4)+1;
            %
            %             %  error map
            %             peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:);
            %             matrix=reshape(peakdata,[],mapsize(2))';
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;
            %
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}='Total Fit Error';
            %             sampleinfo(4)=sampleinfo(4)+1;


            %% create absolute Peakmap
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['AbsPeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
            sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            sampleinfo(4)=1;

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=52;  %Peakdecon absolute Map with submaps


            for n=1:NumPeaks
                peaknr=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(n);
                peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_hight(:,n);
                matrix=reshape(peakdata,[],mapsize(2))';
                matrix(isnan(matrix))=0;
                matrix(matrix==inf)=0;

                %figure;imagesc(matrix)


                %                error_data=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:,1);
                %                 matrix_error=reshape(error_data,[],mapsize(2))';
                %
                %                 error_thx=100;%ATH add setting
                %matrix(matrix_error>error_thx)=nan;

                %  Peaks higer than maximum in value
                [ ~, ix ] = min( abs(x_signal-peaknr ) );
                max_in=max(y_signal(ix,:),[],'all');

                matrix(matrix>max_in.*1.1)=nan; %ATH setting max thx
                %                 figure;imagesc(matrix)

                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}=['AbsPeakMap_' num2str(peaknr)];
                sampleinfo(4)=sampleinfo(4)+1;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(4),:)=mapsize;
            end
            %% create Peakwidthmap
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['WidPeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
            sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            sampleinfo(4)=1;

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=53; %Peakdecon width Map with submaps

            for n=1:NumPeaks
                peaknr=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(n);
                peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_width(:,n);
                matrix=reshape(peakdata,[],mapsize(2))';
                matrix(isnan(matrix))=0;
                matrix(matrix==inf)=0;

                %                 error_data=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:,1);
                %                 matrix_error=reshape(error_data,[],mapsize(2))';
                %
                %                 error_thx=100;%ATH add setting
                %                 matrix(matrix_error>error_thx)=nan;
                wid_window=max(x_signal)-min(x_signal);
                matrix(matrix>wid_window*2)=nan;

                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}=['WidPeakMap_' num2str(peaknr)];
                sampleinfo(4)=sampleinfo(4)+1;
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(4),:)=mapsize;
            end

            %% create Error Peakmap
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['ErrorMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
            sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            sampleinfo(4)=1;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=54; %Peakdecon Error Map with submaps

            % r2 error map
            peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error_r2(:);
            matrix=reshape(peakdata,[],mapsize(2))';
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}='Total Fit R2 Error';
            sampleinfo(4)=sampleinfo(4)+1;

            %  error map
            peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:);
            matrix=reshape(peakdata,[],mapsize(2))';
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;

            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}='Total Fit Error';

            %% create Shift Peakmap
            shift_state=true;
            if shift_state==true
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['ShiftPeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
                sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                sampleinfo(4)=1;

                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=55; %Peakdecon shift Map with submaps

                for n=1:NumPeaks
                    peaknr=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(n);
                    peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_shift(:,n);
                    matrix=reshape(peakdata,[],mapsize(2))';
                    matrix(isnan(matrix))=0;
                    matrix(matrix==inf)=0;

                    % ath here position outsite set 0
                    %                 error_data=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:,1);
                    %                 matrix_error=reshape(error_data,[],mapsize(2))';
                    %
                    %                 error_thx=100;%ATH add setting
                    %                 matrix(matrix_error>error_thx)=nan;
                    %                 wid_window=max(wn)-min(wn);
                    %                 matrix(matrix>wid_window*2)=nan;

                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}=['ShiftPeakMap_' num2str(peaknr)];
                    sampleinfo(4)=sampleinfo(4)+1;
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(4),:)=mapsize;
                end
            end

            %% create Pos Peakmap
            pos_state=true;
            if pos_state==true
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{end+1}=['PosPeakMap_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value)];
                sampleinfo(3)=numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames);
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                sampleinfo(4)=1;

                app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).MapPlotSettings(sampleinfo(3)).xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapType(sampleinfo(3))=56; %Peakdecon pos Map with submaps

                for n=1:NumPeaks
                    peaknr=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(n);
                    try
                        peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(:,n)+app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_shift(:,n);
                    catch
                        disp('error in pos peakmap')
                        peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos(:,n)+app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_shift(:,n);
                    end
                    matrix=reshape(peakdata,[],mapsize(2))';
                    matrix(isnan(matrix))=0;
                    matrix(matrix==inf)=0;

                    % ath here position outsite set 0
                    %                 error_data=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_error(:,1);
                    %                 matrix_error=reshape(error_data,[],mapsize(2))';
                    %
                    %                 error_thx=100;%ATH add setting
                    %                 matrix(matrix_error>error_thx)=nan;
                    %                 wid_window=max(wn)-min(wn);
                    %                 matrix(matrix>wid_window*2)=nan;

                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}=['PosPeakMap_' num2str(peaknr)];
                    sampleinfo(4)=sampleinfo(4)+1;
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(sampleinfo(4),:)=mapsize;
                end
            end

            %% create Start Peakmap


            %% create maps end


            %             %if uistart and roi start
            %             peakdata=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakstart(:);
            %             matrix=reshape(peakdata,[],mapsize(2))';
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings(sampleinfo(2)).mapsize(1,:)=mapsize;
            %
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix=matrix;
            %             app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMapNames{sampleinfo(4)}='PeakStartMap';


            ids=size(y_signal,2);
            disp(['Calculated ' num2str(sum(mask_sort(:))) ' of ' num2str(ids) ' Pixels in ' datestr(datenum(0,0,0,0,0,time_elapsed),'HH:MM:SS')])
            disp(['Time/Pixel ' datestr(datenum(0,0,0,0,0,time_elapsed/sum(mask_sort(:))),'HH:MM:SS.FFF')])

            UpdateTree(app);
            message = {['Calculated ' num2str(numel(id2)) ' of ' num2str(steps) ' Pixels in ' datestr(datenum(0,0,0,0,0,time_elapsed),'HH:MM:SS')]...
                ['Time/Pixel ' datestr(datenum(0,0,0,0,0,toc1),'HH:MM:SS.FFF')]};

            if not(isempty(app.fwb))
                delete(app.fwb)
            end

            uialert(app.SpecMapsUIFigure,message,'Peak Deconvolution Done','Icon','success');


            %delete(d)

        end

        % Callback function: ClassificationMenu, ClassificationModuleButton, 
        % ClassificationModuleMenu
        function OpenClassificationModule(app, event)
            if isempty(app.selected_sample)
                return
            end

            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize),1)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:))) && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:),[0 0]))
                mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:);
            else
                mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
            end

            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            try
                [x_signal,y_signal,~] = get_specs_cor(app,sampleinfo,[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal) max(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal)]);
            catch
                x_signal=[];
                y_signal=[];

            end


            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

            cmap=app.ColorMapValues;
            range=app.xlimits;
            ClassificationModule(app,  x_signal, y_signal, range, sampleinfo, mapsize,cmap);
        end

        % Menu selected function: SpecMaptoFigureMenu
        function SpecMaptoFigureMenuSelected(app, event)


            %% newfig


            % centimeters units
            X = 42.0;                  % A3 paper size
            Y = 29.7;                  % A3 paper size
            xMargin = 1;               % left/right margins from page borders
            yMargin = 1;               % bottom/top margins from page borders
            xSize = X - 2*xMargin;     % figure size on paper (widht & hieght)
            ySize = Y - 2*yMargin;     % figure size on paper (widht & hieght)


            % create figure/axis
            hFig1=figure('visible', 'on');
            % figure size displayed on screen (50% scaled, but same aspect ratio)


            % figure size printed on paper
            set(hFig1, 'PaperUnits','centimeters')
            set(hFig1, 'PaperSize',[X Y])
            set(hFig1, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig1, 'PaperOrientation','portrait')
            hFig1.Position=hFig1.Position*1.25;


            tiledlayout('flow')

            % ATH Change here to plot peaks used in map
            ax = nexttile;
            ax1 = app.MapPlot;
            ax1Chil = ax1.Children;
            copyobj(ax1Chil, ax)

            cb=colorbar; %#ok
            %%
            colormap(PlotMap_UpdateColormap(app,256,1));

            try
                caxis(sort([app.EditField_climMin.Value app.EditField_climMax.Value]))
            catch ME
                values=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
                mM=mean(values(:),"omitnan");
                disp('cupdate failed')
                disp(ME.message)
                caxis([min([mM*0.2 mM*2]) max([mM*0.2 mM*2])])
            end
            axis image

            ax = nexttile;
            ax2 = app.SpectraPlot;
            ax2Chil = ax2.Children;
            copyobj(ax2Chil, ax)
            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end
            xlim([min(app.xlimits) max(app.xlimits)])
            if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')
                ylabel('Absorbance [cm^{-1}]]')
            else
                ylabel('Absorbance')
            end

            xlabel('Wavenumber [cm^{-1}]')


            ts=sgtitle([app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}]);
            set(ts, 'Interpreter', 'none')
            path=uigetdir;
            delete(f); %delete the dummy figure
            if isequal(path,0)
                figure(app.SpecMapsUIFigure);
                return
            end
            if not(exist(path,'dir'))
                mkdir(path)
            end
            file=app.SpecMap.Spectra.Names{app.selected_sample};


            FileName=fullfile(path,[file '_SpecPlot.eps']);

            set(hFig1,'Units','Inches');
            pos = get(hFig1,'Position');
            set(hFig1,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])


            exportgraphics(hFig1,FileName,'BackgroundColor','none','ContentType','vector');


            %%

            % create figure/axis
            hFig=figure('visible', 'on');
            % figure size displayed on screen (50% scaled, but same aspect ratio)


            % figure size printed on paper
            set(hFig, 'PaperUnits','centimeters')
            set(hFig, 'PaperSize',[X Y])
            set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
            set(hFig, 'PaperOrientation','portrait')
            hFig.Position=hFig.Position*1.25;


            % ATH Change here to plot peaks used in map
            MatrixLimits=update_MatixLimits(app);
            %             axa = subplot(1,3,1);
            %             hold on
            %             daspect([1 1 1])

            values=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(values)
                values=values.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            end
            %
            %             if app.ThicknessCorrectionCheckBox.Value==true && not(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==4 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==5 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)>10) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(values) || numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1)
            %                 values=(values./app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map).*1e4;
            %             end

            values=ThicknessCorrectionFun(app,values);


            axa = subplot(1,2,1);
            hold on
            daspect([1 1 1])

            imagesc(values,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));

            xl=get(axa,'xlim');
            yl=get(axa,'ylim');


            try
                caxis(sort([app.EditField_climMin.Value app.EditField_climMax.Value]))
            catch ME
                mM=mean(values(:),"omitnan");
                disp('C Limit update failed')
                disp(ME.message)
                caxis([min([mM*0.2 mM*2]) max([mM*0.2 mM*2])])
            end
            %             ax1 = app.MapPlot;
            %             ax1Chil = ax1.Children;
            %             copyobj(ax1Chil, ax)


            if app.plot_image_checkbox.Value==true
                Image=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image{app.selected_image};
                ImageLimits=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits{app.selected_image};

                alpha=ones(size(values))*app.AlphaSlider.Value./100;
                image(flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));

                imagesc(values,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:),'AlphaData',alpha)


            else
                imagesc(values,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));

            end
            colorbar;
            %%
            colormap(PlotMap_UpdateColormap(app,256,1));


            %ylabel(cb, 'Fluid / Calcite @3400 [wt%]')


            axb = subplot(1,2,2);
            hold on
            daspect([1 1 1])

            %if plotimage==true
            if not(isempty(app.selected_image))
                Image=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).Image{app.selected_image};
                ImageLimits=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id).ImageLimits{app.selected_image};

                image(flipud(Image),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));

                r=rectangle('Position',[MatrixLimits(1,1) MatrixLimits(2,1) (MatrixLimits(1,2)-MatrixLimits(1,1)) (MatrixLimits(2,2)-MatrixLimits(2,1))]);
                r.EdgeColor = 'b';
                r.LineWidth = 3;


            end
            daspect([1 1 1])


            %             ax = subplot(2,3,4:6);
            %             ax2 = app.SpectraPlot;
            %             ax2Chil = ax2.Children;
            %             copyobj(ax2Chil, ax)
            %             set (gca, 'xdir', 'reverse')
            %             xlim([min(app.xlimits) max(app.xlimits)])
            %
            %
            ts=sgtitle([app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}]);
            %ts=sgtitle([app.SpecMap.Spectra.Names{app.selected_sample} ]);
            set(ts, 'Interpreter', 'none')
            path=uigetdir;
            delete(f); %delete the dummy figure
            if isequal(path,0)
                figure(app.SpecMapsUIFigure);
                return
            end
            file=app.SpecMap.Spectra.Names{app.selected_sample};
            if app.plot_image_checkbox.Value==true
                FileName=fullfile(path,[file '_imgage.eps']);
                if exist(FileName,'file')
                    FileName=fullfile(path,[file '_imgage2.eps']);

                end
            else
                FileName=fullfile(path,[file '_overview.eps']);
            end
            set(hFig,'Units','Inches');
            pos = get(hFig,'Position');
            set(hFig,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
            linkaxes([axa,axb],'xy')
            set(axa,'xlim',xl)
            set(axa,'ylim',yl)
            exportgraphics(hFig,FileName,'BackgroundColor','none','ContentType','vector');


        end

        % Node text changed function: ClassTree
        function ClassTreeNodeTextChanged(app, event)
            node = event.Node;

            if numel(node.NodeData) == 3 && node.NodeData(3)>0
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(node.NodeData(1)).ClassMap(node.NodeData(2)).ClassesNames{node.NodeData(3)}=node.Text;
            elseif numel(node.NodeData) == 2 && node.NodeData(2)>0
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(node.NodeData(1)).ClassMapNames{node.NodeData(2)}=node.Text;
            elseif numel(node.NodeData) == 1 || node.NodeData(2)==0
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames{node.NodeData(1)}=node.Text;
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification=[];
            update_class_tree(app,app.ClassTree);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            N=app.ClassTree.SelectedNodes;
            if not(isempty(N))
                selectedNodes = N.NodeData;
            else
                return
            end

            %             if
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames(selectedNodes(3))=[];
            %                 app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).Classes(selectedNodes(3))=[];
            if numel(selectedNodes) == 3 && selectedNodes(3)>0 || numel(selectedNodes) == 2 && selectedNodes(2)>0
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMapNames(selectedNodes(2))=[];
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2))=[];
            elseif numel(selectedNodes) == 1 || selectedNodes(2)==0
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames(selectedNodes(1))=[];
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1))=[];
            end
            if isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames)
                app.ClassTree.Visible=false;
                app.MaskSelectedButton.Visible=false;
                app.DeleteButton.Visible=false;
                app.ClearAllButton.Visible=false;


                app.SpecPlotSetDropDown.Items={'Default','Sum','Mean','All','ROI Mean','All ROIs Stacked','All ROI','All ROIs Mean'};
                app.SpecPlotSetDropDown.Value='Default';

            end
            update_class_tree(app,app.ClassTree);
        end

        % Selection changed function: ClassTree
        function ClassTreeSelectionChanged(app, event)

            N=app.ClassTree.SelectedNodes;
            if not(isempty(N)) && not(isempty(app.selected_sample))
                selectedNodes = N.NodeData;
            else
                return
            end
            app.SpecPlotSetDropDown.Items={'Default','Sum','Mean','All','ROI Mean','All ROIs Stacked','All ROI','All ROIs Mean','Classes Mean'};
            app.SpecPlotSetDropDown.Value='Classes Mean';

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));

            elseif numel(selectedNodes)==3 && not(selectedNodes(2)==0) && not(selectedNodes(3)==0)

                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                matrix(not(matrix==selectedNodes(3)))=nan;

            else
                %update_active_plot(app)
                return
            end

            MatrixLimits=update_MatixLimits(app);
            plotimage=app.plot_image_checkbox.Value;
            reset=true;
            PlotMap_UpdateColormap(app,n_classes);
            caxis(app.MapPlot,[1 n_classes])
            caxis(app.MapPlot_2,[1 n_classes])

            alpha=not(isnan(matrix));
            plt_settings.plt_ROI=app.ROICheckBox.Value;


            UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings)
            UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,reset,plotimage,[],plt_settings)
            UpdateSpectraPlot(app)


        end

        % Menu selected function: SetXYRatioMenu
        function SetXYRatioMenuSelected(app, event)
            start_ReshapeModule(app)

        end

        % Menu selected function: ImportMatchModuleMenu
        function ImportMatchMapMenuSelected(app, event)
            % for number of submaps and subsubmaps
            %extract all mapplotmatrixes and names and then save  single
            %structure if selected tree node is map else if seleced
            %treenode is submap


            sampleinfos={app.TreeData_Main.Children(app.selected_sample).Children(app.selected_subsample).Children.NodeData}';
            for n=1:numel(sampleinfos) %numel selected maps or all maps
                si=sampleinfos{n}(2:end);
                if numel(si)==3
                    si=[si 1];
                end
                matrix=MapPlot_fun_public(app,0,si,true,false);

                ReferenceMaps(n).MapPlotMatrix=matrix;
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(si(2)).Maps(si(3)),'SubMapNames') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(si(2)).Maps(si(3)).SubMapNames))
                    ReferenceMapsName{n}=app.SpecMap.Spectra.Sample(si(1)).Data(si(2)).Maps(si(3)).SubMapNames{si(4)};
                else
                    ReferenceMapsName{n}=app.SpecMap.Spectra.Sample(si(1)).Data(si(2)).MapNames{si(3)};
                end
            end
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

            MatrixLimits=update_MatixLimits(app);
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize),1)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:))) && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:),[0 0]))
                mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:);
            else
                mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
            end

            %% uinput Files or folder
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.mat'},"MultiSelect","on",'Select Files');
            delete(f);
            if not(iscell(file))
                if file==0
                    return
                end
            end

            if contains(file,'.mat')
                temp=load(fullfile(path,file)); %ATH check one file at a time

                possible_fields=fieldnames(temp.MapData);
                [indx,~] = listdlg('ListString',possible_fields,'SelectionMode','single');
                figure(app.SpecMapsUIFigure);

                %                 wanted_field='Qt';
                %                 wanted_field='Re';

                %for h=numel(indx)
                wanted_field=possible_fields(indx);
                %   wanted_field=possible_fields(indx(h));

                index_start=1;
                for m=1:numel(temp.MapData.(char(wanted_field)).Data)
                    if isfield(temp.MapData.(char(wanted_field)).Data(m),'ElNames')
                        field_name='ElNames';
                    elseif isfield(temp.MapData.(char(wanted_field)).Data(m),'Labels')
                        field_name='Labels';

                    else
                        disp('error lable not defined')
                        fieldnames(temp.MapData.(char(wanted_field)).Data(m))
                    end
                    n_el=numel(temp.MapData.(char(wanted_field)).Data(m).(char(field_name)));


                    index_end=index_start+n_el-1;

                    matrix_to_matchNames(index_start:index_end)= temp.MapData.(char(wanted_field)).Data(m).(char(field_name));

                    for n=1:numel(temp.MapData.(char(wanted_field)).Data(m).(char(field_name)))

                        tmp_mat1=flipud(temp.MapData.(char(wanted_field)).Data(m).CData(n).Map); %import of uiinput
                        tmp_mat2=nan(size(tmp_mat1)+2);
                        tmp_mat2(2:end-1,2:end-1)=tmp_mat1;


                        matrix_to_match(n+index_start-1).MapPlotMatrix=tmp_mat2;
                    end
                    index_start=index_start+n_el;
                end

                MapMatchModule(app, ReferenceMaps, ReferenceMapsName, sampleinfo,MatrixLimits, mapsize,matrix_to_match,matrix_to_matchNames,app.ColorMapValues);

            else
                matrix_to_matchNames=file;

                for n=1:numel(file)
                    thx=readtable(fullfile(path,file{n}));
                    matrix_to_match(n).MapPlotMatrix=flipud(table2array(thx)); %import of uiinput
                end
                MapMatchModule(app, ReferenceMaps, ReferenceMapsName, sampleinfo,MatrixLimits, mapsize,matrix_to_match,matrix_to_matchNames,app.ColorMapValues);

            end


        end

        % Callback function: CorrelationModuleButton, CorrelationModuleMenu
        function CorrelationModuleMenuSelected(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            SampleNames=app.SpecMap.Spectra.Names;

            if isempty(app.ClassTree.CheckedNodes)
                checked_nodes=[];
                cmap=app.ColorMapValues;
            else
                checked_nodes={app.ClassTree.CheckedNodes.NodeData};
                No_of_elements = cellfun(@numel, checked_nodes);

                checked_nodes(No_of_elements==3)=[];
                selectedNodes=checked_nodes{:};
                if selectedNodes(2)==0
                    selectedNodes(2)=1;
                end
                Resolution=numel(unique(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix));
                cmap= PlotMap_UpdateColormap(app,Resolution,1);


            end


            CorrelationModule(app, sampleinfo, app.TreeData_Main,SampleNames,checked_nodes,cmap)

        end

        % Value changed function: BackgroundMethodDropDown
        function BackgroundMethodDropDownValueChanged(app, event)
            method = app.BackgroundMethodDropDown.Value;
            if strcmp('Rubberband',method)
                app.BackgroundDegreeSpinner.Limits=[0,1];
                app.BackgroundDegreeSpinner.Value=0.1;

            else
                app.BackgroundDegreeSpinner.Limits=[1,10000];
                app.BackgroundDegreeSpinner.Value=1;
            end

        end

        % Callback function: SaveSpectraButton, SavetoDatabaseModuleMenu
        function SavetoDatabaseModuleMenuSelected(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            % get the uncorrected Spectra
            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            [~,y_signal,~] = get_specs(app,sampleinfo,[min(x_signal) max(x_signal)]);


            SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
            try
                SubSampleName=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
            catch
                SubSampleName='';
            end
            N=app.ClassTree.SelectedNodes;
            selectedNodes = N.NodeData;

            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                ClassesNames=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
                ClassMapName=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMapNames(selectedNodes(2));
                ClassificationName=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames(selectedNodes(1));

                for n=1:n_classes
                    selected_class=(matrix==n)';
                    selected_class=selected_class(:);
                    % y=mean(y_signal(:,selected_class),2,"omitnan");
                    y_signal_=y_signal(:,selected_class);
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(matrix)
                        %here thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map(selected_class),"omitnan");
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map';
                        thickness=thickness(selected_class);
                    elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map;
                    else
                        thickness=0;
                    end

                    if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                        mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask';
                    else
                        mask=ones(size(matrix));
                    end
                    mask=mask(selected_class);

                    Data.DB(n).y_signal=y_signal_;
                    Data.DB(n).x_signal=x_signal;

                    Data.Meta(n).Id=n;
                    if strcmp(char(app.SpectraNameEditField.Value),'')
                        Data.Meta(n).SampleName=[char(SubSampleName)  '_' char(ClassesNames{n})];
                    else
                        Data.Meta(n).SampleName=[char(app.SpectraNameEditField.Value)  '_' char(ClassesNames{n})];
                    end
                    if strcmp(char(app.MineralEditField.Value),'')
                        Data.Meta(n).Mineral='Unknown';
                    else
                        Data.Meta(n).Mineral=char(app.MineralEditField.Value);
                    end
                    Data.Meta(n).Orientation=char(app.OrientationEditField.Value);
                    Data.Meta(n).InfraredRadiation=char(app.InfraredRadiationEditField.Value);
                    Data.Meta(n).Thickness=thickness;
                    Data.Meta(n).Mask=logical(mask);
                    Data.Meta(n).Density=0;
                    Data.Meta(n).SampleType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
                    Data.Meta(n).Comment=[char(ClassificationName{:})  ' / '  char(ClassMapName{:})  ' / ' char(ClassesNames{n})]; %char((ClassesNames{n}));
                    if strcmp(char(app.SampleEditField.Value),'')
                        Data.Meta(n).Sample=char(SampleName);
                    else
                        Data.Meta(n).Sample=char(app.SampleEditField.Value);
                    end
                    Data.Meta(n).Source=char('SpecMaps');
                    Data.Meta(n).MapName=char(SampleName);
                    Data.Meta(n).SubMapName=char(SubSampleName);
                    Data.Meta(n).ClassificationName=char(ClassificationName{:});
                    Data.Meta(n).ClassMapName=char(ClassMapName{:});
                    Data.Meta(n).ClassesNames=char(ClassesNames{n}); %char((ClassesNames{n}));Data.Meta(n).Comment=[char(ClassificationName{:})  ' / '  char(ClassMapName{:})  ' / ' char(ClassesNames{n})]; %char((ClassesNames{n}));
                    Data.Meta(n).n_meanSpec=numel(y_signal(1,selected_class));
                    Data.Meta(n).MatrixLimits=update_MatixLimits(app);
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')
                        Data(1).Meta(1).Images=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id);
                    end

                    try
                        if not(isempty(app.selected_sample)) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200)
                            thx= [find((matrix==n)') app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.x(selected_class,:) app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.y(selected_class,:) app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Group(selected_class)];
                            Data.Meta(n).MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});

                        elseif not(isempty(app.selected_sample)) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200
                            MatrixLimit=update_MatixLimits(app);
                            %px_size=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size
                            mapsize_raw=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                            y_signal_=fliplr(linspace(MatrixLimit(2,1),MatrixLimit(2,2),mapsize_raw(1)));
                            x=fliplr(linspace(MatrixLimit(1,1),MatrixLimit(1,2),mapsize_raw(2)));
                            [x_pos,y_pos] = meshgrid(x,y_signal_);
                            thx= [find((matrix==n)') x_pos(selected_class) y_pos(selected_class) ones(size(y_pos(selected_class)))];
                            Data.Meta(n).MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
                            %meta_tbl= app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;

                        else
                            y_signal_=flipud(linspace(0,size(matrix,2),size(matrix,2)));
                            x=flipud(linspace(0,size(matrix,1),size(matrix,1)));
                            [x_pos,y_pos] = meshgrid(x,y_signal_);
                            thx= [find((matrix==n)') x_pos(selected_class) y_pos(selected_class) ones(size(y_pos(selected_class)))];
                            Data.Meta(n).MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
                        end
                    catch ME
                        Data.Meta(n).MetaTable=[];
           WhichOperation = 'Error SavetoDatabaseModuleMenuSelected -  MetaTable generation failed';
                    DisplayError(ME,WhichOperation)        
                    end
                end

            end
            insert_data(SpecDB,Data)


        end

        % Menu selected function: SetConstantThicknessMenu, 
        % SetConstantThicknessMenu_2
        function SetConstantThicknessMenuSelected(app, event)
            thickness = inputdlg('Enter thickness  µm:','Set Thickness', 1,{'100'});
            figure(app.SpecMapsUIFigure);

            if ~isempty(thickness)
                thickness=unique(str2num(thickness{1})); %#ok<ST2NM>
            else
                return
            end
            if isnumeric(thickness) && not(isnan(thickness)) && not(isinf(thickness))
                app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map=thickness;
                disp(['Thickness of ' num2str(thickness) ' µm set'])
                update_active_plot(app)
                update_thickness_field(app)
            end
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            SpecMaps;
        end

        % Menu selected function: AbsoluteValueMapMenu
        function AbsoluteValueMapMenuSelected(app, event)
            UI_peaks = inputdlg('Enter space-separated numbers:',...
                'Enter Peaks', [1 50]);
            figure(app.SpecMapsUIFigure);

            if ~isempty(UI_peaks)

                UI_peaks=unique(str2num(UI_peaks{1})); %#ok<ST2NM>


                app.MapGenDropDown.Value='Absolute';

                for n=1:length(UI_peaks)
                    app.Int_range_max.Value=UI_peaks(n);

                    xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                    region=app.Int_range_max.Value;
                    if max(region)>max(xl) || max(region)<min(xl)
                        ResetButton_2Pushed(app)
                        GenerateMapButtonPushed(app)
                        app.EditField_xlimMin.Value=xl(1);
                        app.EditField_xlimMax.Value=xl(2);
                        app.xlimits=xl;

                    else
                        GenerateMapButtonPushed(app)
                    end

                    SelectionChangeFun(app)

                end
            end
        end

        % Menu selected function: QuantLinAbsMapFromSpectraMenu
        function QuantLinAbsMapFromSpectraMenuSelected(app, event)
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')
                thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map,'all','omitnan');
            else
                thickness=10000;
            end

            definput = {'3400','18.0152','1','2.71',num2str(thickness),num2str(81*400)};

            x = inputdlg({'Wavenumber [cm^{-1}]','Molar Mass [g/mol]','Density Inclusion [g/cm^3]','Density Host [g/cm^3]','Thickness [µm]','ɛ [L * mol^−1 * cm^−1]'}, 'Calculate Linear Quantified Map',[1 15; 1 15; 1 15; 1 15; 1 15; 1 15],definput);
            figure(app.SpecMapsUIFigure);


            if ~isempty(x)
                UI_peaks=str2double(x(1));
                molmass_ui=str2double(x(2));
                density_incl_ui=str2double(x(3));
                density_host_ui=str2double(x(4));
                thickness_ui=str2double(x(5));
                e_ui=str2double(x(6));


                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                [x_signal,y_signal,~] = get_specs_cor(app,sampleinfo,app.xlimits);

                UI_peaks=UI_peaks(UI_peaks>min(x_signal)& UI_peaks<max(x_signal));
                if isempty(UI_peaks)
                    return
                end
                y_signal = linCorrect(y_signal);

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize),1)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:))) && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:),[0 0]))
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:);
                else
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                end

                for n=1:length(UI_peaks)
                    %find closest position
                    [ ~, ix ] = min( abs(x_signal-UI_peaks(n) ) );
                    x_signal(ix)
                    abs_val=y_signal(ix,:);
                    matrix=reshape(abs_val,[],mapsize(2))';

                    % C=(molmass_ui.*matrix)./(thickness_ui.*density_ui.*e_ui);


                    C=1;
                    thickness_flinc_pct=(molmass_ui.*matrix./(thickness_ui./10000))./(density_incl_ui.*e_ui.*C); %->100% d'


                    % d_flinc=1./thickness_flinc_pct;  %->100% d'
                    MatrixLimits=update_MatixLimits(app);
                    %                      d=MatrixLimits(:,2)-MatrixLimits(:,1);
                    %                      n=size(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix);
                    %                      Px_size_y=d(2)/n(1);
                    %                      Px_size_x=d(1)/n(2);
                    %                      c_flinc=((d_flinc*(Px_size_x/.10000)*(Px_size_y/.10000))./density_incl_ui)./((10000*(Px_size_x/.10000)*(Px_size_y/.10000))./density_host_ui).*1e+6;  %->  microgram/gram
                    %c_flinc=(d_flinc./density_incl_ui)./((10000./density_host_ui)).*wt%
                    %->  microgram/gram *1000
                    c_flinc=(thickness_flinc_pct./density_incl_ui)./((10000-thickness_flinc_pct./density_host_ui)).*1e+6; %wt%
                    %c_flinc=(d_flinc./density_incl_ui)./((10000./density_host_ui))        %->  g/g

                    matrix=c_flinc;
                    plot_results=false;
                    if plot_results==true
                        hFig = figure;

                        X = 42.0;                  % A3 paper size
                        Y = 29.7;                  % A3 paper size
                        xMargin = 1;               % left/right margins from page borders
                        yMargin = 1;               % bottom/top margins from page borders
                        xSize = X - 2*xMargin;     % figure size on paper (widht & hieght)
                        ySize = Y - 2*yMargin;     % figure size on paper (widht & hieght)

                        set(hFig, 'PaperUnits','centimeters')
                        set(hFig, 'PaperSize',[X Y])
                        set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
                        set(hFig, 'PaperOrientation','portrait')
                        hFig.Position=hFig.Position.*2;


                        imagesc(matrix,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));
                        daspect([1 1 1])
                        colormap(app.ColorMapValues);
                        title([app.SpecMap.Spectra.Names{app.selected_sample} ' QuantMap_' num2str(min(round(UI_peaks(n))))],'interpreter', 'none')
                        cb = colorbar;
                        cb.Label.String = 'Fluid / Host [wt%]';
                        xlabel('x [µm]')
                        ylabel('y [µm]')
                        FileName=[app.SpecMap.Spectra.Names{app.selected_sample} ' QuantMap_' num2str(min(round(UI_peaks(n)))) '.png'];
                        exportgraphics(hFig,FileName,Resolution=300);
                    end

                    if true
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapNames')
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=['QuantMap_' num2str(min(round(UI_peaks(n))))];
                            app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                            app.selected_submap=1;
                        else
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{1}=['QuantMap_' num2str(min(round(UI_peaks(n))))];
                            app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                            app.selected_submap=1;

                        end
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=111; %quant abs val map
                    end

                end
                MatrixLimits=update_MatixLimits(app);
                plt_settings.plt_ROI=app.ROICheckBox.Value;

                UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateTree(app);
                SelectionChangeFun(app)

            end

        end

        % Menu selected function: NormMapFromSpectraMenu
        function NormMapFromSpectraMenuSelected(app, event)
            definput = {'3400'};
            x = inputdlg({'Wavenumber [cm^{-1}]'}, 'Calculate Normalized Map',[1 15],definput);
            figure(app.SpecMapsUIFigure);

            if ~isempty(x)
                UI_peaks=str2double(x(1));

                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                [x_signal,y_signal,~] = get_specs_cor(app,sampleinfo,app.xlimits);

                if app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==size(y_signal,2)
                    tm=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map';
                    y_signal=(y_signal.*tm(:)')./1e4;
                elseif app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1 && not(isequal(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map,1))
                    y_signal=(y_signal.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)./1e4;
                elseif app.ThicknessCorrectionCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map)==1
                    y_signal=(y_signal.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Thickness_map);
                end

                y_signal = linCorrect(y_signal);

                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings,'mapsize') && size(not(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize),1)>=app.selected_map && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:))) && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:),[0 0]))
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).mapsize(app.selected_map,:);
                else
                    mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                end

                for n=1:length(UI_peaks)
                    [ ~, ix ] = min( abs(x_signal-UI_peaks(n) ) );
                    x_signal(ix)
                    abs_val=y_signal(ix,:);
                    matrix=reshape(abs_val,[],mapsize(2))';


                    matrix=matrix./max(matrix(:));
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapNames')
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=['NormMap_' num2str(min(round(UI_peaks(n))))];
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{1}=['NormMap_' num2str(min(round(UI_peaks(n))))];
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;
                    end
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=113; %quant abs val map


                end
                MatrixLimits=update_MatixLimits(app);

                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).MatrixLimits=MatrixLimits;
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).mapsize=mapsize;
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).SpecType=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.SpecType;
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).MapGen=app.MapGenDropDown.Value;

                if strcmp(app.MapGenDropDown.Value,'Absolute')
                    region=app.Int_range_max.Value;
                else
                    region=[app.Int_range_min.Value app.Int_range_max.Value];

                end
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).region=region;
                xregion=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).xregion=xregion;

                plt_settings.plt_ROI=app.ROICheckBox.Value;


                UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateTree(app);
                SelectionChangeFun(app)

            end
        end

        % Menu selected function: NormMapFromMapMenu
        function NormMapFromMapMenuSelected(app, event)
            matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
            MapPlotSettings=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map);
            matrix=ThicknessCorrectionFun(app,matrix);

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                matrix(~app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)=nan;
                %matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
            end


            matrix(isinf(matrix))=nan;
            matrix=matrix+abs(min(matrix(:)));
            matrix=matrix-min(matrix(:));

            matrix=matrix./max(matrix(:));


            try
                mapname=['NormMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}];
            catch
                mapname=['NormMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}];
            end


            if true
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapNames')
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=mapname;
                    app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                    app.selected_submap=1;
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{1}=mapname;
                    app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                    app.selected_submap=1;

                end
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=114; %Norm val map derived from Map
                app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map)=MapPlotSettings;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=[0 1];
                % app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings
                % mapsize
                % MatrixLimits
                % SpecType
                % MapGen
                % region
                % xregion


            end

            MatrixLimits=update_MatixLimits(app);
            plt_settings.plt_ROI=app.ROICheckBox.Value;


            UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,1,1,[],plt_settings)
            UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,1,1,[],plt_settings)
            UpdateTree(app);
            SelectionChangeFun(app)


        end

        % Menu selected function: QuantLinAbsMapFromMapMenu
        function QuantLinAbsMapFromMapMenuSelected(app, event)
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')
                thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map,'all','omitnan');
            else
                thickness=10000;
            end

            definput = {'18.0152','1','2.71',num2str(thickness),'11900'};

            x = inputdlg({'Molar Mass [g/mol]','Density Inclusion [g/cm^3]','Density Host [g/cm^3]','Thickness [µm]','ɛ [L * mol^−1 * cm^−1]'}, 'Calculate Linear Quantified Map',[1 15; 1 15; 1 15; 1 15; 1 15],definput);
            figure(app.SpecMapsUIFigure);


            if ~isempty(x)
                molmass_ui=str2double(x(1));
                density_incl_ui=str2double(x(2));
                density_host_ui=str2double(x(3));
                thickness_ui=str2double(x(4));
                e_ui=str2double(x(5));


                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;

                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                    matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                end


                C=1;
                thickness_flinc_pct=(molmass_ui.*matrix./(thickness_ui./10000))./(density_incl_ui.*e_ui.*C); %->100% d'

                MatrixLimits=update_MatixLimits(app);
                c_flinc=(thickness_flinc_pct./density_incl_ui)./((10000-thickness_flinc_pct./density_host_ui)).*1e+6;

                matrix=c_flinc;
                try
                    title({[app.SpecMap.Spectra.Names{app.selected_sample} ' QuantMap'], app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}},'interpreter', 'none')
                    mapname=['QuantMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}];
                catch
                    title({[app.SpecMap.Spectra.Names{app.selected_sample} ' QuantMap'], app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map} },'interpreter', 'none')
                    mapname=['QuantMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}];
                end

                plot_results=false;

                if plot_results==true

                    hFig = figure;


                    X = 42.0;                  % A3 paper size
                    Y = 29.7;                  % A3 paper size
                    xMargin = 1;               % left/right margins from page borders
                    yMargin = 1;               % bottom/top margins from page borders
                    xSize = X - 2*xMargin;     % figure size on paper (widht & hieght)
                    ySize = Y - 2*yMargin;     % figure size on paper (widht & hieght)

                    set(hFig, 'PaperUnits','centimeters')
                    set(hFig, 'PaperSize',[X Y])
                    set(hFig, 'PaperPosition',[xMargin yMargin xSize ySize])
                    set(hFig, 'PaperOrientation','portrait')
                    hFig.Position=hFig.Position.*2;

                    %                     if app.ThicknessCorrectionCheckBox.Value==true && not(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==4 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)==5 || app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)>10) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && (numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(matrix) || numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1)
                    %                         matrix=(matrix./app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map).*1e4;
                    %                     end

                    matrix=ThicknessCorrectionFun(app,matrix);

                    imagesc(matrix,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));
                    daspect([1 1 1])
                    cb= colorbar;
                    colormap(app.ColorMapValues);

                    cb.Label.String = 'Fluid / Host [wt%]';
                    xlabel('x [µm]')
                    ylabel('y [µm]')
                    FileName=[app.SpecMap.Spectra.Names{app.selected_sample} ' QuantMap_' num2str(min(round(UI_peaks(n)))) '.png'];
                    exportgraphics(hFig,FileName,Resolution=300);
                end

                if true
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapNames')
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=mapname;
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{1}=mapname;
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;

                    end
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=112; %absolute val map derived from Map
                end


                MatrixLimits=update_MatixLimits(app);
                plt_settings.plt_ROI=app.ROICheckBox.Value;


                UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateTree(app);
                SelectionChangeFun(app)
            end

        end

        % Value changed function: MapGenDropDown
        function MapGenDropDownValueChanged(app, event)

            xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                region=app.Int_range_max.Value;
                if max(region)>max(xl) || max(region)<min(xl)
                    app.Int_range_max.Value=round((max(xl)-min(xl))/2 )+min(xl);
                end
                app.IntRangeLabel.Text='XoI';
                app.Int_range_min.Visible=false;
                update_lines(app)
                return
            else
                region=[app.Int_range_max.Value app.Int_range_min.Value];

                if min(region)<min(xl) || min(region)>max(xl) || max(region)>max(xl) || max(region)<min(xl)
                    app.Int_range_min.Value=min(xl);
                    app.Int_range_max.Value=max(xl);
                end
            end

            if strcmp(app.MapGenDropDown.Value,'Ratio')
                app.IntRangeLabel.Text='Ratio';
                app.Int_range_min.Visible=true;
            elseif strcmp(app.MapGenDropDown.Value,'Ratio log/log')
                app.IntRangeLabel.Text='Ratio log/log';
                app.Int_range_min.Visible=true;
            elseif strcmp(app.MapGenDropDown.Value,'Entropy')
                app.IntRangeLabel.Text='Range';
                app.Int_range_min.Visible=true;
            else
                app.IntRangeLabel.Text='Int. Range';
                app.Int_range_min.Visible=true;

            end
            update_lines(app)
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))

                sampleinfo=[app.selected_sample app.selected_subsample];
            else
                sampleinfo=[1 1 ];
            end
            xlimval=[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal)...
                max(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal)];
            app.EditField_xlimMin.Value=min(xlimval);
            app.EditField_xlimMax.Value=max(xlimval);
            app.xlimits=xlimval;
            if app.EditField_xlimMin.Value>app.Int_range_min.Value ||  app.Int_range_min.Value > app.EditField_xlimMax.Value
                app.Int_range_min.Value= app.EditField_xlimMin.Value;
            end

            if app.EditField_xlimMax.Value<app.Int_range_max.Value || app.Int_range_max.Value<app.EditField_xlimMin.Value
                app.Int_range_max.Value= app.EditField_xlimMax.Value;
            end


            update_active_plot(app)


        end

        % Menu selected function: RAMANGUMKSMenu
        function Raman_MKSMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.csv;*.txt;*.*'},"MultiSelect","on",'Select Files');
            delete(f);
            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        SampleName=split(char(file(m)),'.');
                        SampleName=char(SampleName(1));
                        reshape_raman_MKS(app,path,char(file(m)),SampleName,'MKS')
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    reshape_raman_MKS(app,path,file,SampleName,'MKS')
                end
            end

            ResetButton_2Pushed(app)
            UpdateTree(app);

        end

        % Menu selected function: RAMAN1Menu
        function Raman_1MenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.hdr;*.*'},"MultiSelect","on",'Select Files');
            delete(f);

            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        try
                            SampleName=split(char(file(m)),'.');
                            SampleName=char(SampleName(1));
                            reshape_raman_MKS(app,path,char(file(m)),SampleName,'unknown_1')
                        catch
                        end
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    reshape_raman_MKS(app,path,file,SampleName,'unknown_1')
                end
            end
            ResetButton_2Pushed(app)
            UpdateTree(app);
        end

        % Menu selected function: RAMAN2Menu
        function RAMAN_2MenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.csv;*.txt;*.*'},"MultiSelect","on",'Select Files');
            delete(f);
            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        SampleName=split(char(file(m)),'.');
                        SampleName=char(SampleName(1));
                        reshape_raman_MKS(app,path,char(file(m)),SampleName,'unknown_2')
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    reshape_raman_MKS(app,path,file,SampleName,'unknown_2')
                end
            end

            ResetButton_2Pushed(app)
            UpdateTree(app);
        end

        % Menu selected function: HyperDC_GTKMenu
        function HyperDC_GTKMenuSelected(app, event)
            %             SampleName=   'M273488R335_10_extracted_image';
            %            % SisuRockHS_NG(app,'/Users/nils/Desktop/TestSisuRock/M273488R335_10_extracted_image.img',SampleName)
            %             %                                        SampleName=   'M273488R335_10_rgb_extracted_image';
            %
            %                         SisuRockHS_NG(app,'/Users/nils/Desktop/TestSisuRock/M273488R335_10_rgb_extracted_image.img',SampleName)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.hdr;*.*'},"MultiSelect","on",'Select Files');
            delete(f);

            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        try
                            SampleName=split(char(file(m)),'.');
                            SampleName=char(SampleName(1));
                            SisuRockHS_NG(app,fullfile(path,char(file(m))),SampleName)
                        catch
                        end
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    SisuRockHS_NG(app,fullfile(path,file),SampleName)
                end
            end
            ResetButton_2Pushed(app)
            UpdateTree(app);
        end

        % Selection changed function: TreeData_Main
        function TreeData_MainSelectionChanged(app, event)
            %selectedNodes = app.TreeData_Main.SelectedNodes;
            N = app.TreeData_Main.SelectedNodes;
            if isempty(N)
                return
            end
            if isempty(event.PreviousSelectedNodes)||isempty(event.SelectedNodes.NodeData)||isempty(event.PreviousSelectedNodes.NodeData)
                ct_update=true;
            else
                ct_update= event.SelectedNodes.NodeData(2)~=event.PreviousSelectedNodes.NodeData(2);
                app.SpecPlotSetDropDown.Value='Default';
            end

            selectedNodes = N.NodeData;
            app.selected_sample=[];
            app.selected_subsample=[];

            if ~isempty(selectedNodes)
                if not(isempty(selectedNodes(2)))  && not(selectedNodes(2)==0) && not(isempty(selectedNodes(3))) && not(selectedNodes(3)==0)
                    app.selected_sample=selectedNodes(2);
                    app.selected_subsample=selectedNodes(3);
                    if numel(selectedNodes)>=4 && not(isempty(selectedNodes(4)))
                        app.selected_map=selectedNodes(4); %selected_map
                    else
                        app.selected_map=1; %selected_map
                        app.selected_submap=1; %selected_map
                    end

                    if numel(selectedNodes)>=5 && not(isempty(selectedNodes(5)))
                        app.selected_submap=selectedNodes(5);
                    else
                        app.selected_submap=1;
                    end


                    SelectionChangeFun(app)
                    if ct_update
                        tic
                        update_class_tree(app,app.ClassTree);
                        disp('Update Class Tree Complete - time:')
                        toc
                    end

                elseif not(isempty(selectedNodes(2)))  && not(selectedNodes(2)==0) && (selectedNodes(3)==0)
                    app.selected_sample=selectedNodes(2);
                end
            end
        end

        % Menu selected function: InvertMenu
        function InvertMenuSelected(app, event)

            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
            ms=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
            mt=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;
            app.selected_subsample=1+numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
            SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
            %y_signal = -log(((1-y_signal).^2)./((1+y_signal).^2));
            y_signal = -log(y_signal);
            

            y_signal(isinf(y_signal))=0;
            y_signal(isnan(y_signal))=0;
            %% save the rawdata

            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=ms;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=mt;
            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=[SampleName '_inverted'];

            UpdateTree(app);
        end

        % Menu selected function: RemContMenu
        function RemContMenuSelected(app, event)
            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
            ms=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
            mt=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;
            app.selected_subsample=1+numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
            SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
            y_signal = remCont2(x_signal,y_signal);
            y_signal(isinf(y_signal))=0;
            y_signal(isnan(y_signal))=0;
            %% save the rawdata
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=ms;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=mt;
            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=[SampleName '_RemCont'];

            UpdateTree(app);
        end

        % Menu selected function: DataSet2NewEntryMenu
        function DataSet2NewEntryMenuSelected(app, event)

            ms=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
            mt=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            tcb_val=app.ThicknessCorrectionCheckBox.Value;
            app.ThicknessCorrectionCheckBox.Value=false;
            [x_signal,y_signal,~] = get_specs_cor(app,sampleinfo,app.xlimits);
            app.ThicknessCorrectionCheckBox.Value=tcb_val;
            SampleName=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
            Settings=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample); %#ok
            Settings=rmfield(Settings,'MapPlotSettings');%#ok
            Settings=rmfield(Settings,'mapsize');%#ok


            app.selected_subsample=1+numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);


            y_signal(isinf(y_signal))=0;
            y_signal(isnan(y_signal))=0;
            %% save the rawdata

            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=ms;
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable=mt;
            app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}=[SampleName '_Corrected'];
            app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Settings=Settings;

            UpdateTree(app);
        end

        % Callback function: PeakTree
        function PeakTreeCheckedNodesChanged(app, event)


            if isempty(app.selected_sample)
                return
            elseif isempty(app.selected_subsample)
                app.selected_subsample=1;
            end

            checkedNodes = app.PeakTree.CheckedNodes;
            if isempty(checkedNodes)
                T=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks;
                T=T(not(contains(T.Spec,'PeakDB')),:);
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=T;


            else

                Data={checkedNodes.NodeData};
                test=  cellfun(@iscell,Data);
                Data=Data(test);
                PeakPos=zeros(1,numel(Data));
                for n=1:numel(Data)
                    PeakPos(n)= Data{n}{2};
                end
                PeakNames=cell(1,numel(Data));
                for n=1:numel(Data)
                    PeakNames(n)= Data{n}(3);
                end

                %% temporary add to uipeaks

                Spec='PeakDB';

                if isfield(app.SpecMap.Spectra.Sample,'Data') && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data,'Peaks') && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks,'UIpeaks') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks))
                    T=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks;
                    T=T(not(contains(T.Spec,Spec)),:);

                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=[T;table(PeakPos',zeros(numel(PeakPos),1),zeros(numel(PeakPos),1),zeros(numel(PeakPos),1),repmat({Spec},size(PeakPos')),repmat({'UI Peak'},size(PeakPos')),'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'})];
                else
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Peaks.UIpeaks=table(PeakPos',zeros(numel(PeakPos),1),zeros(numel(PeakPos),1),zeros(numel(PeakPos),1),repmat({Spec},size(PeakPos')),repmat({'UI Peak'},size(PeakPos')),'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
                end
            end


            update_peaktable_Data(app)

            tic
            UpdateSpectraPlot(app)
            toc

            % add update_peaklinesFun(app)
            %
        end

        % Menu selected function: ApplyEdittoallSpectraMenu
        function ApplyEdittoallSpectraMenuSelected(app, event)
            tic


            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            [~,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
            [x_signal,y_signal_filt,~] = get_specs_cor(app,sampleinfo,app.xlimits);
            figure
            hold on
            plot(y_signal(:,1:10),':')
            %plot(in_filt(:,1:10),'--')
            in_edit=y_signal_filt+(min(y_signal,[],1)-min(y_signal_filt,[],1));
            plot(in_edit(:,1:10),'-')
            plot(y_signal(:,1:10)-in_edit(:,1:10),'--')


            y_signal=y_signal-in_edit;


            y_signal(isinf(y_signal))=0;
            y_signal(isnan(y_signal))=0;
            %% save the rawdata
            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
            end
            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'x_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;

            app.BackgroundCheckBox.Value=false;
            app.FilterCheckBox.Value=false;
            update_active_plot(app)
            app.edit_state=true;
            disp('Edit Spectra Complete - time:')

            toc

        end

        % Button pushed function: ApplytoallSpectraButton
        function ApplytoallSpectraButtonPushed(app, event)
            tic
            % uiwatibar
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            tcb_val=app.ThicknessCorrectionCheckBox.Value;
            app.ThicknessCorrectionCheckBox.Value=false;
            [x_signal,y_signal,~] = get_specs_cor(app,sampleinfo,app.xlimits);
            app.ThicknessCorrectionCheckBox.Value=tcb_val;
            y_signal(isinf(y_signal))=0;
            y_signal(isnan(y_signal))=0;
            %% save the rawdata
            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
            end

            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'x_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal;

            app.BackgroundCheckBox.Value=false;
            app.FilterCheckBox.Value=false;
            update_active_plot(app)
            app.edit_state=true;

            disp('Edit Spectra Complete - time:')

            toc
        end

        % Button pushed function: ResetEditsButton
        function ResetEditsButtonPushed(app, event)


            if (isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw')) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw))
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw=[];
            end
            if (isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'x_signal_raw')) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw))
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw=[];
            end
            update_active_plot(app)
            app.edit_state=true;

        end

        % Button pushed function: LoadPeakDBButton
        function LoadPeakDBButtonPushed(app, event)
            [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select Metadata for' char('')]);
            if not(isequal(file,0))
                app.Peaks.PeakDB=ReadPeakdata_fun(fullfile(path,file));
                update_PeakTree(app)
            else
                message='Error not a valid PeakDB file';
                uialert(app.SpecMapsUIFigure,message,'Error');
            end
            app.edit_state=true;
        end

        % Menu selected function: SetShiftMenu
        function SetShiftMenuSelected(app, event)
            if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))
                shift = inputdlg('Enter shift:','Shift Correction', 1,{'0'});
                figure(app.SpecMapsUIFigure);

                if ~isempty(shift)
                    shift=unique(str2num(shift{1})); %#ok<ST2NM>
                else
                    return
                end
                if isnumeric(shift) && not(isnan(shift)) && not(isinf(shift))
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1

                        ss_num=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
                        if ss_num>1
                            ButtonName = questdlg( 'Do you want to shift all Subsamples?', ...
                                'X Shift', ...
                                'Yes', 'No', 'Cancel','');
                            if strcmp(ButtonName,'Yes')
                                ss_num=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
                            elseif strcmp(ButtonName,'No')
                                ss_num=app.selected_subsample;
                            elseif strcmp(ButtonName,'Cancel')
                                return
                            end
                        else
                            ss_num=1;
                        end
                    else
                        return
                    end
                    figure(app.SpecMapsUIFigure);


                    for n=1:numel(ss_num)
                        if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)),'x_signal_shift_reset')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset)
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset=shift;
                        else
                            app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset=app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset+shift;
                        end

                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal+shift;
                        [app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).xregion]=deal([app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).xregion]+shift);
                        [app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).region]=deal([app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).region]+shift);
                    end
                end
            end

            SelectionChangeFun(app)
            app.edit_state=true;
        end

        % Menu selected function: ResetShiftMenu
        function ResetShiftMenuSelected(app, event)
            if not(isempty(app.selected_sample)) && not(isempty(app.selected_subsample))
                if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Names') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names)>=1

                    ss_num=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
                    if numel(ss_num)>1
                        ButtonName = questdlg( 'Do you want to reset the shift for all Subsamples?', ...
                            'X Shift', ...
                            'Yes', 'No', 'Cancel','');
                        if strcmp(ButtonName,'Yes')
                            ss_num=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Names);
                        elseif strcmp(ButtonName,'No')
                            ss_num=app.selected_subsample;
                        elseif strcmp(ButtonName,'Cancel')
                            return
                        end
                    else
                        ss_num=1;
                    end
                else
                    return
                end
                figure(app.SpecMapsUIFigure);

                for n=1:numel(ss_num)
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)),'x_signal_shift_reset') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset))

                        shift=-app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset;
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal_shift_reset=[];
                    else
                        shift=0;
                    end

                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(ss_num(n)).x_signal+shift;
                    [app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).xregion]=deal([app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).xregion]+shift);
                    [app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).region]=deal([app.SpecMap.Spectra.Sample(app.selected_sample).Settings(ss_num(n)).MapPlotSettings(:).region]+shift);
                end
            end
            SelectionChangeFun(app)
            app.edit_state=true;
        end

        % Menu selected function: RamanBaselineMenu
        function RamanBaselineMenuSelected(app, event)
            tic
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            app.ThicknessCorrectionCheckBox.Value=false;
            [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);

            in1=y_signal';
            wn2=x_signal;

            in_corr1=zeros(size(in1));
            for n=1:size(y_signal,1)
                [~, in_corr1(:,n)]=baseline_raman(in1(:,n));
            end
            %% save the rawdata
            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
            end

            if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'x_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw)
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            end
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=wn2;
            app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=in_corr1';


            app.BackgroundCheckBox.Value=false;
            app.FilterCheckBox.Value=false;
            update_active_plot(app)
            disp('Raman Baseline Complete - time:')

            toc
            app.edit_state=true;
        end

        % Menu selected function: GenerateMapforallSamplesMenu_2
        function GenerateMapforallSamplesMenuSelected(app, event)


            maptype2generate=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).MapGen;
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings)>=app.selected_subsample && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings)>=app.selected_map&& not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map),'density'))
                density=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).density;
            else
                density=[];
            end
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings)>=app.selected_subsample && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings)>=app.selected_map&& not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map),'E'))
                E=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).E;
            else
                E=[];
            end
            if strcmp(maptype2generate,'Absolute')
                UI_mapname = inputdlg('Enter Map Name:',...
                    'Map Name', [1 50],{[maptype2generate ' ' num2str(app.Int_range_max.Value)]});
            else
                UI_mapname = inputdlg('Enter Map Name:',...
                    'Map Name', [1 50],{[maptype2generate ' ' num2str(app.Int_range_min.Value) '-' num2str(app.Int_range_max.Value)]});
            end
            if strcmp(maptype2generate,'Libowitzky and Rossman (1997)') || strcmp(maptype2generate,'Paterson (1982)')
                E=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).E;
            else
                E=[];
            end
            if strcmp(maptype2generate,'Libowitzky and Rossman (1997)')|| strcmp(maptype2generate,'Paterson (1982)') || strcmp(maptype2generate,'Balan et al. (2008)')
                density=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).density;
            else
                density=[];
            end

            UI_mapname=UI_mapname{:};
            count=0;
            count2=0;
            d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Generating Maps',...
                'Message','Automatic Map Generation Started','Cancelable','on');
            drawnow

            for m=1:numel(app.SpecMap.Spectra.Names)
                for n=1:(numel(app.SpecMap.Spectra.Sample(m).Names))
                    count=count+1;
                end
            end

            for m=1:numel(app.SpecMap.Spectra.Names)
                if d.CancelRequested
                    break
                end
                for n=1:(numel(app.SpecMap.Spectra.Sample(m).Names))
                    count2=count2+1;
                    d.Value = count2/count;
                    if d.CancelRequested
                        break
                    end
                    d.Message = sprintf(['Generating Maps: \nSample: ' app.SpecMap.Spectra.Names{m} '\nSubample: ' app.SpecMap.Spectra.Sample(m).Names{n} '\nMap: ' UI_mapname]);

                    app.selected_sample=m;
                    app.selected_subsample=n;

                    try
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)+1;
                    catch
                        app.selected_map=1;
                    end
                    app.selected_submap=1;

                    app.Always_Update_Map=true;
                    sampleinfo=UpdateMapPlotMatrix(app,[],maptype2generate,density,E);
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}=UI_mapname;
                end
            end

            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;
        end

        % Menu selected function: GenerateMapforselectedSamplesMenu_2
        function GenerateMapforselectedSamplesMenuSelected(app, event)
            % Copy to all other samples
            % if is 1 10 30 31 32 check


            maptype2generate=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).MapGen;
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings)>=app.selected_subsample && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings)>=app.selected_map&& not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map),'density'))
                density=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).density;
            else
                density=[];
            end
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'Settings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings)>=app.selected_subsample && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample),'MapPlotSettings') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings)>=app.selected_map&& not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map),'E'))
                E=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).E;
            else
                E=[];
            end

            %             if strcmp(maptype2generate,'Libowitzky and Rossman (1997)') || strcmp(maptype2generate,'Paterson (1982)')
            %                 E=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).E;
            %             else
            %                 E=[];
            %             end
            %             if strcmp(maptype2generate,'Libowitzky and Rossman (1997)')|| strcmp(maptype2generate,'Paterson (1982)') || strcmp(maptype2generate,'Balan et al. (2008)')
            %                 density=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).MapPlotSettings(app.selected_map).density;
            %             else
            %                 density=[];
            %             end


            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            New_mapname=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)};


            New_mapname = inputdlg('Enter Map Name:',...
                'Map Name', [1 50],{New_mapname});


            %% get maps to extract
            Mapnames={};
            Map_Id=[];
            for h=1:numel(app.SpecMap.Spectra.Names)

                for j=1:numel(app.SpecMap.Spectra.Sample(h).Names)
                    %    if not(isequal([app.selected_sample app.selected_subsample],[h j]))
                    Map_Id(end+1,:)=[h j 1 1];
                    Mapnames{end+1}=[app.SpecMap.Spectra.Names{h} '_' app.SpecMap.Spectra.Sample(h).Names{j}];
                    %   end
                end

            end

            [indx,tf] = listdlg('PromptString',{'Select maps to plot.'},...
                'SelectionMode','multiple','ListString',Mapnames,'ListSize', [150 300]);

            selected_maps=Map_Id(indx,:);


            count=size(selected_maps,1);
            count2=0;
            d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Generating Maps',...
                'Message','Automatic Map Generation Started','Cancelable','on');
            drawnow

            for n=1:size(selected_maps,1)
                if d.CancelRequested
                    break
                end
                count2=count2+1;
                d.Value = count2/count;
                if d.CancelRequested
                    break
                end

                d.Message = (['Generating Maps: Sample: ' app.SpecMap.Spectra.Names{selected_maps(n,1)} 'Subample: ' app.SpecMap.Spectra.Sample(selected_maps(n,1)).Names{selected_maps(n,2)} 'Map: ' New_mapname]);

                app.selected_sample=selected_maps(n,1);
                app.selected_subsample=selected_maps(n,2);

                try
                    app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)+1;
                catch
                    app.selected_map=1;
                end
                app.selected_submap=1;

                app.Always_Update_Map=true;
                try
                    sampleinfo=UpdateMapPlotMatrix(app,[],maptype2generate,density,E);
                catch ME
                       WhichOperation = 'Error in GenerateMapforselectedSamples';
                    DisplayError(ME,WhichOperation)           

                end
                if iscell(New_mapname)
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}=New_mapname{:};
                else
                    app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}=New_mapname;
                end
            end


            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;

        end

        % Menu selected function: QuantFactorMapMenu
        function QuantFactorMapMenuSelected(app, event)
            %             if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map')
            %                 thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map,'all','omitnan');
            %             else
            %                 thickness=10000;
            %             end

            hint=["Forsterite 0.188 [Bell et al. (2003)]\n","Kyanite 0.147 [Bell et al. (2004)]\n","Vesuvianite 0.085 [Bellatreccia et al. (2005)]\n","Nepheline 0.672 [Beran and Rossman (1989)]\n","Orthopyroxene 0.067 [Bell et al. (1995)]\n","Clinopyroxene 0.141 [Bell et al. (1995)]\n","Pyrope 0.24 [Bell et al. (1995)]\n","Grossular 0.140 [Rossman and Aines (1991)]\n","Hydrogrossular 0.264 [Rossman and Aines (1991)]\n","Spessartine 0.125 [Rossman (1988)]\n","Feldspars 0.065 [Johnson and Rossman (2003]\n","Rutile 0.112 [Maldener et al. (2001)]\n","Cassiterite 0.039 [Maldener et al. (2001)]\n"]';

            definput = {'Garnet - Spessartin','0.125','[µg/g]', ''};
            hint=replace(sprintf(char(hint')),{'      ','     ','    ','   ','  '},'');
            x = inputdlg({'Mineral','QuantFactor','Unit Output',char(hint)}, 'Calculate Factor Quantified Map' ,[1 20; 1 15; 1 15;0 50 ],definput);


            if ~isempty(x)
                Mineral=x(1);
                QuantFactor=str2double(x(2));
                Unit_Output=x(3);


                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
                matrix=ThicknessCorrectionFun(app,matrix);

                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                    matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask;
                end


                matrix=matrix.*QuantFactor;

                try
                    mapname=['QuantFactorMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMapNames{app.selected_submap}];
                catch
                    mapname=['QuantFactorMap_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map}];
                end

                mapname=[mapname '_' Mineral{:} '_QF:' num2str(QuantFactor) '_' Unit_Output{:}];

                if true
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'MapNames')
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{end+1}=mapname;
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;
                    else
                        app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{1}=mapname;
                        app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames);
                        app.selected_submap=1;

                    end
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix=matrix;
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapType(app.selected_map)=110; %QuantfactorMap
                end

                MatrixLimits=update_MatixLimits(app);
                plt_settings.plt_ROI=app.ROICheckBox.Value;


                UpdateMapPlot(app,app.MapPlot,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateMapPlot(app,app.MapPlot_2,matrix,MatrixLimits,1,1,[],plt_settings)
                UpdateTree(app);
                SelectionChangeFun(app)
            end
        end

        % Button pushed function: UpdateInfoButton
        function UpdateInfoButtonPushed(app, event)
            UpdateInfoWindow(app)
        end

        % Button pushed function: Sampling_ResetButton
        function Menu_Sampling_ResetROIMenuSelected(app, event)


            if not(isempty(app.selected_sample))
                try
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling)
                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Color=[0.47,0.67,0.19];
                    end
                catch ME
                                  WhichOperation = 'Error Menu_Sampling_ResetROIMenuSelected - roi update interaction 1';
                    DisplayError(ME,WhichOperation)           

                end
                try
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling)
                        app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.InteractionsAllowed='none';
                    end
                catch ME
                                         WhichOperation = 'Error Menu_Sampling_ResetROIMenuSelected - roi update interaction 2';
                    DisplayError(ME,WhichOperation)           

                end
                try
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling)
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'rotatable')
                            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.rotatable=false;
                        end
                    end
                catch ME
                                          WhichOperation = 'Error Menu_Sampling_ResetROIMenuSelected - roi update interaction 3';
                    DisplayError(ME,WhichOperation)           
             end
                %
                %                          c=
                %                             interactions='none';
                %                             rotatable=false;

                try
                    if exist('event','var')
                        ROI_DeleteROI(app);
                    end
                catch ME
                                         WhichOperation = 'Error Menu_Sampling_ResetROIMenuSelected - roi update interaction 4';
                    DisplayError(ME,WhichOperation)           


                end

            end
            cla(app.Sampling_Plot1)
            cla(app.Sampling_Plot2)

            colorbar(app.Sampling_Plot1,'hide')
            colorbar(app.Sampling_Plot2,'hide')

            app.Sampling_Plot1.Visible = 'off';
            app.Sampling_Plot2.Visible = 'off';

            MapROISpinnerValueChanged(app)

        end

        % Button pushed function: Sampling_ExportButton
        function Sampling_ExportButtonPushed(app, event)
            if isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'ROI') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI,'ROI_sampling') && ~isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling)


                figure();
                ax2 = gca;
                copyobj(app.Sampling_Plot1.Children,ax2);

                ax2.XLim = app.Sampling_Plot1.XLim;
                ax2.YLim = app.Sampling_Plot1.YLim;
                ax2.Colormap = app.Sampling_Plot1.Colormap;
                ax2.FontSize=16;
                xlabel(app.Sampling_Plot2,'Distance µm')

                ax2.Title = app.Sampling_Plot1.Title;
                if  strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.Type,'images.roi.polyline')
                    colorbar(ax2,'horizontal');
                end


                if isequal(app.Sampling_Plot2.Visible,'on')
                    figure;
                    ax2 = gca;
                    ax2.FontSize=16;

                    copyobj(app.Sampling_Plot2.Children,ax2);

                    ax2.XLim = app.Sampling_Plot2.XLim;
                    ax2.YLim = app.Sampling_Plot2.YLim;
                    ax2.Title = app.Sampling_Plot2.Title;

                    ax2.XLabel.String=app.Sampling_Plot2.XLabel.String;
                    ax2.YLabel.String=app.Sampling_Plot2.YLabel.String;


                    colorbar(ax2,'horizontal');
                    colormap(ax2,app.ColorMapValues);
                    ax2.Colorbar.Label.String=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map};
                    ax2.Colorbar.Label.Interpreter='none';

                    %%
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'Maps') &&...
                            isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map),'SubMap') &&...
                            isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap),'climits') &&...
                            not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits))...
                            && numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits)==2 && not(isequal(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(1),app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits(2)))
                        if app.Options_LogColormap.Value==false
                            CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
                        else
                            CLim =log(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits);
                        end
                        %CLim = app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits;
                        caxis(ax2,CLim);
                    else
                        caxis(ax2,'auto');

                    end
                    %%


                end

                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                %[x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
                [x_signal_cor,y_signal_cor,~] = get_specs_cor(app,sampleinfo,[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal) max(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal)]);


                sel_ini=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI;
                for selected_ROI=sel_ini
                    app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=selected_ROI;

                    if strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Type,'images.roi.rectangle') %|| strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Type,'images.roi.polyline')

                        [distance_micron,TheMean,TheMedian,MatrixProfils] = get_mean_median(app,1,1,selected_ROI);

                        mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;


                        ImageData=(reshape(1:(mapsize(2)*mapsize(1)),[],mapsize(2))');


                        if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(ImageData)
                            mask=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                        else
                            mask=ones(size(ImageData));
                        end


                        ImageData(mask==0)=0;


                        roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask();
                        if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'RotationAngle')
                            rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.RotationAngle;
                        else
                            rot_=0;
                        end

                        roiMask_rot=imrotate(roiMask,rot_);
                        ImageData_rot=imrotate(ImageData,rot_);

                        mask_ind=find(roiMask_rot>0);
                        [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
                        ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
                        MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);

                        if  size(MatrixProfils,1)>size(MatrixProfils,2)
                            MatrixProfils=MatrixProfils';
                        end

                        AB_tmp= zeros(size(x_signal_cor,1),size(MatrixProfils,2));
                        for m=1:size(MatrixProfils,2)
                            MatrixProfils_tmp=MatrixProfils(:,m);
                            MatrixProfils_tmp=MatrixProfils_tmp(MatrixProfils_tmp>0);

                            if isempty(MatrixProfils_tmp)
                                AB_tmp(:,m)=nan;
                            else
                                AB_tmp(:,m)=mean(y_signal_cor(:,MatrixProfils_tmp),2);
                            end
                        end

                        AB=[x_signal_cor,AB_tmp];
                        %distance_micron=linspace(0,max(distance_micron),size(AB_tmp,1))
                        %                         figure
                        %
                        %                         plot3(repmat(distance_micron,size(AB_tmp,1),1),wn_cor,AB_tmp,'linewidth',1,'Color','k');
                        %                         ylabel( 'Wavenumbers [cm^{-1}]')
                        %                         zlabel('Absorbance [cm^{-1}]')
                        %                         xlabel('Distance [µm]')
                        %                         view(-125,22)
                        %
                        %                         figure
                        %                         mesh(repmat(distance_micron,size(AB_tmp,1),1),wn_cor,AB_tmp,'linewidth',1);
                        %                         ylabel( 'Wavenumbers [cm^{-1}]')
                        %                         zlabel('Absorbance [cm^{-1}]')
                        %                         xlabel('Distance [µm]')
                        %                         view(-125,22)
                        %                                       colorbar('horizontal');
                        %             colormap(app.ColorMapValues);

                        AB_tmp2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),2:end);
                        wn_cor2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),1);

                        ax3=  figure();

                        mesh(repmat(distance_micron,size(AB_tmp2,1),1),wn_cor2,AB_tmp2,'linewidth',1);
                        ylabel( 'Wavenumbers [cm^{-1}]')
                        zlabel('Absorbance [cm^{-1}]')
                        xlabel('Distance [µm]')
                        view(-125,22)

                        colorbar('vertical');
                        colormap(app.ColorMapValues);
                        ax3.Children(2).Colorbar.Label.String=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{app.selected_map};
                        ax3.Children(2).Colorbar.Label.Interpreter='none';
                        ax3.Children(2).FontSize=16;

                    end


                end


            end


        end

        % Button pushed function: Sampling_SelectStripeButton
        function Sampling_SelectStripeButtonPushed(app, event)

            if    app.ROICheckBox.Value==false
                app.ROICheckBox.Value=true;
                MapROISpinnerValueChanged(app)
            end

            if app.plot_image_checkbox.Value==true
                app.plot_image_checkbox.Value=false;
                update_active_plot(app)
            end

            app.TabGroup_2.SelectedTab = app.SamplingTab;

            %ROI_DeleteROI(app);
            %Menu_Sampling_ResetROIMenuSelected(app);
            reset_ROI_Sampling_Figs(app);

            if strcmp(app.TabGroup.SelectedTab.Title,'SpecMapPlot')
                FigMain=app.MapPlot;%FigMain=get_fig(app);
            elseif  strcmp(app.TabGroup.SelectedTab.Title,'Single Map Plot')
                FigMain=app.MapPlot_2;%FigMain=get_fig(app);
            elseif strcmp( app.TabGroup.SelectedTab.Title,'MultiMapPlots')
                FigMain=app.MapPlot_OL;%FigMain=get_fig(app);
            end

            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)) &&isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'selected_ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI)) && not(isempty(fieldnames(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)))
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)+1;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=1;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling = drawrectangle(FigMain,'Color',[1 0 0],'Rotatable',1,'InteractionsAllowed','all');
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_strip(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling));

            %app.Handle_ExtFigure = figure;
            %app.GCA_ExtFigure = gca;

            Sampling_ROI_changed_strip(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling);
            update_ROI_spinner(app)
            update_active_plot(app)

            %MapROISpinnerValueChanged(app)

        end

        % Button pushed function: Sampling_SelectTransectButton
        function Sampling_SelectTransectButtonPushed(app, event)
            if    app.ROICheckBox.Value==false
                app.ROICheckBox.Value=true;
                MapROISpinnerValueChanged(app)
            end

            if app.plot_image_checkbox.Value==true
                app.plot_image_checkbox.Value=false;
                update_active_plot(app)
            end

            app.TabGroup_2.SelectedTab = app.SamplingTab;

            %ROI_DeleteROI(app);
            reset_ROI_Sampling_Figs(app);

            if strcmp(app.TabGroup.SelectedTab.Title,'SpecMapPlot')
                FigMain=app.MapPlot;%FigMain=get_fig(app);
            elseif  strcmp(app.TabGroup.SelectedTab.Title,'Single Map Plot')
                FigMain=app.MapPlot_2;%FigMain=get_fig(app);
            elseif strcmp( app.TabGroup.SelectedTab.Title,'MultiMapPlots')
                FigMain=app.MapPlot_OL;%FigMain=get_fig(app);
            end

            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'selected_ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI))
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)+1;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=1;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling = drawpolyline(FigMain,'Color',[1 0 0],'InteractionsAllowed','all');


            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_line(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling));

            %app.Handle_ExtFigure = figure;
            %app.GCA_ExtFigure = gca;

            Sampling_ROI_changed_line(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling);
            update_ROI_spinner(app)


        end

        % Button pushed function: Sampling_SelectAreaButton
        function Sampling_SelectAreaButtonPushed(app, event)
            if    app.ROICheckBox.Value==false
                app.ROICheckBox.Value=true;
                MapROISpinnerValueChanged(app)
            end
            if app.plot_image_checkbox.Value==true
                app.plot_image_checkbox.Value=false;
                update_active_plot(app)
            end

            %ROI_DeleteROI(app);
            reset_ROI_Sampling_Figs(app);

            if strcmp(app.TabGroup.SelectedTab.Title,'SpecMapPlot')
                FigMain=app.MapPlot;%FigMain=get_fig(app);
            elseif  strcmp(app.TabGroup.SelectedTab.Title,'Single Map Plot')
                FigMain=app.MapPlot_2;%FigMain=get_fig(app);
            elseif strcmp( app.TabGroup.SelectedTab.Title,'MultiMapPlots')
                FigMain=app.MapPlot_OL;%FigMain=get_fig(app);
            end

            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'selected_ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI))
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)+1;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=1;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling = drawpolygon(FigMain,'Color',[1 0 0],'InteractionsAllowed','all');


            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_shape(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'Polygon ROI'));

            % Extract data
            Sampling_ROI_changed_shape(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'Polygon ROI');


            app.Sampling_ResetButton.Enable = 'on';
            update_ROI_spinner(app)

        end

        % Button pushed function: Sampling_SelectCircleButton
        function Sampling_SelectCircleButtonPushed(app, event)
            if    app.ROICheckBox.Value==false
                app.ROICheckBox.Value=true;
                MapROISpinnerValueChanged(app)
            end

            if app.plot_image_checkbox.Value==true
                app.plot_image_checkbox.Value=false;
                update_active_plot(app)
            end
            app.TabGroup_2.SelectedTab = app.SamplingTab;

            %ROI_DeleteROI(app);
            reset_ROI_Sampling_Figs(app)

            if strcmp(app.TabGroup.SelectedTab.Title,'SpecMapPlot')
                FigMain=app.MapPlot;%FigMain=get_fig(app);
            elseif  strcmp(app.TabGroup.SelectedTab.Title,'Single Map Plot')
                FigMain=app.MapPlot_2;%FigMain=get_fig(app);
            elseif strcmp( app.TabGroup.SelectedTab.Title,'MultiMapPlots')
                FigMain=app.MapPlot_OL;%FigMain=get_fig(app);
            end

            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)) && isfield(app.SpecMap.Spectra.Sample(app.selected_sample),'selected_ROI') && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI))
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)+1;
            else
                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=1;
            end

            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling = drawcircle(FigMain,'Color',[1 0 0],'InteractionsAllowed','all');
            app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling_Listener = addlistener(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling, 'ROIMoved', @(varargin)Sampling_ROI_changed_shape(app, app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'Circle ROI'));

            % Extract data
            Sampling_ROI_changed_shape(app,app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling,'Circle ROI');


            app.Sampling_ResetButton.Enable = 'on';
            update_ROI_spinner(app)

        end

        % Value changed function: MapROISpinner
        function MapROISpinnerValueChanged(app, event)
            if not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample)))
                if app.MapROISpinner.Value==0
                    app.MapROISpinner.Value=1;
                end

                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=app.MapROISpinner.Value;
            end
            update_active_plot(app)
        end

        % Menu selected function: DPlotMenu
        function DPlotMenuSelected(app, event)
            %% Data preperation


            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            MatrixLimits=update_MatixLimits(app,sampleinfo);


            matrix=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Maps(sampleinfo(3)).SubMap(sampleinfo(4)).MapPlotMatrix;
            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                %matrix=matrix.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                matrix(not(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask))=nan;%matrix=matrix.*app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask
            end

            matrix=ThicknessCorrectionFun(app,matrix);
            %%
            if app.Options_LogColormap.Value==true
                if any(matrix(:)<0)
                    message={'Negative numbers ignored for log plot.','Generate normalized map to display all values.'};
                    uialert(app.SpecMapsUIFigure,message,'Error');
                    matrix(matrix<0)=nan;
                    %app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).climits=[];
                end
                matrix=log(matrix);
            end

            hFig=figure;
            ax=nexttile;


            %% surf
            X=linspace(MatrixLimits(1,1),MatrixLimits(1,2),size(matrix,2));
            Y=linspace(MatrixLimits(2,1),MatrixLimits(2,2),size(matrix,1));
            Z=matrix;
            %contour3(X, Y, Z+5, 15)
            surf(X, Y, Z+abs((0.15*min(Z(not(isinf(Z))),[],"all","omitnan"))), 'EdgeColor','none','FaceAlpha',0.5)
            %  surf(X, Y, Z+(0.15*min(Z,[],"all","omitnan")), 'EdgeColor','none','FaceAlpha',0.5)

            hold on
            grid on
            xlabel('X [micron]')
            ylabel('Y [micron]')
            zlabel('Absorbance')

            set(gca, 'DataAspectRatio', [repmat(min(diff(get(gca, 'XLim')), diff(get(gca, 'YLim'))), [1 2]) diff(get(gca, 'ZLim'))])

            colormap(hFig,app.ColorMapValues);
            hFig.CurrentAxes.CLim = [min([app.EditField_climMin.Value app.EditField_climMax.Value]) max([app.EditField_climMin.Value app.EditField_climMax.Value])];
            hFig.CurrentAxes.XLim = [min(X) max(X)];
            hFig.CurrentAxes.YLim = [min(Y) max(Y)];

            plt_settings.plt_ROI=app.ROICheckBox.Value;


            UpdateMapPlot(app,ax,[],MatrixLimits,false,app.plot_image_checkbox.Value,app.AlphaSlider.Value,[],plt_settings)
            %ax.Interactions = zoomInteraction('Dimensions','xy');

        end

        % Button pushed function: FullROIExportButton
        function FullROIExportButtonPushed(app, event)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                path=uigetdir;
                delete(f); %delete the dummy figure
                if isequal(path,0)
                    figure(app.SpecMapsUIFigure);
                    return
                end

                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                %    [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
                [x_signal_cor,y_signal_cor,~] = get_specs_cor(app,sampleinfo,[min(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal) max(app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).x_signal)]);

                %  expmat=zeros(size(y_signal,1),numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI));
                Profile=struct();

                sel_ini=app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI;
                for selected_ROI=flip(1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI))
                    app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=selected_ROI;
                    hFig=figure;

                    t = tiledlayout('flow');


                    Axis2Plot= nexttile;


                    hold on
                    title(Axis2Plot,app.SpecMap.Spectra.Sample(sampleinfo(1)).Names{sampleinfo(2)},'interpreter','None')

                    MapPlot_fun_public(app,Axis2Plot,sampleinfo,true,false); % used in CorelationModule
                    caxis([min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);

                    mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');%all_ids(not(mask_vector(:)));
                    ROI=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.createMask()');%app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Idx;

                    if numel(mask_ids)>1
                        ROI=intersect(ROI,mask_ids);
                    end

                    %  expmat(:,selected_ROI)=mean(y_signal(:,ROI),2,"omitnan");
                    try
                        if strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Type,'images.roi.rectangle') || strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Type,'images.roi.polyline')
                            expmat2=[];
                            mapnames={};
                            exp_struct=struct();

                            for n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)

                                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(n),'SubMapNames')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(n).SubMapNames)
                                    SubMapNames={'_'};
                                else
                                    SubMapNames=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(n).SubMapNames;
                                end

                                for m=1:numel(SubMapNames)

                                    [distance_micron,TheMean,TheMedian,MatrixProfils] = get_mean_median(app,n,m,selected_ROI);
if isempty(distance_micron)
    continue
end

                                    nexttile
                                    hold on
                                    plot(distance_micron,MatrixProfils','-','markersize',1,'color', [0.4 0.4 0.4 0.15]);

                                    plot(distance_micron,TheMedian,'-b','linewidth',2);

                                    plot(distance_micron,TheMean,'-r','linewidth',2);
                                    title('median (blue) & mean (red)');


                                    xlabel('Distance µm')
                                    xlim([min(distance_micron),max(distance_micron)])

                                    expmat2(:,end+1)=TheMedian;%mean(matrix,2,'omitnan');
                                    mapnames{end+1}=matlab.lang.makeValidName([app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{n} '_' SubMapNames{m} '_median']);
                                    expmat2(:,end+1)=TheMean;%mean(matrix,2,'omitnan');
                                    mapnames{end+1}=matlab.lang.makeValidName([app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{n} '_' SubMapNames{m} '_mean']);
                                    title(mapnames{end},'interpreter','none')
                                    exp_struct.Data(n).distance_micron{m}=distance_micron;
                                    exp_struct.Data(n).TheMean{m}=TheMean;
                                    exp_struct.Data(n).TheMedian{m}=TheMedian;
                                    exp_struct.Data(n).MatrixProfils{m}=MatrixProfils;

                                end


                            end

                            if isempty(expmat2)
                                continue
                            end


                            % if isempty(structfun(@isempty, struct()))
                            mapnames= (matlab.lang.makeUniqueStrings(mapnames));
                            if any(cellfun(@length,mapnames)>63)
                                for n2fix=1:size(mapnames,2)
                                    if numel(mapnames{n2fix})>63
                                        mapnames{n2fix}=mapnames{n2fix}(end-62:end);
                                    end
                                end
                            end
                            try
                                if isempty(structfun(@isempty, Profile))

                                    Profile(1).T= array2table(expmat2);
                                    Profile(1).T.Properties.VariableNames=mapnames;
                                    Profile(1).d_micron=distance_micron;
                                else

                                    Profile(end+1).T= array2table(expmat2);
                                    Profile(end).T.Properties.VariableNames=mapnames;
                                    Profile(end).d_micron=distance_micron;
                                end
                            catch
                                Profile(end+1).T= array2table(expmat2);
                                Profile(end).T.Properties.VariableNames=mapnames;
                                Profile(end).d_micron=distance_micron;

                            end


                            set(hFig, 'Units', 'centimeters',...
                                'OuterPosition', [0 0 19 27])

                            annotation('rectangle',[0 0 1 1],'Color','w');
                            FileName=fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample},'_ROI_' num2str(selected_ROI)]);
                            savefig(hFig,[FileName '.fig'])
                            exportgraphics(hFig,[FileName '.eps'],'BackgroundColor','none','ContentType','vector')
                            exportgraphics(hFig,[FileName '.png'],Resolution=300)
                            exportgraphics(hFig,[FileName '.pdf'])

                            mapsize=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;


                            ImageData=(reshape(1:(mapsize(2)*mapsize(1)),[],mapsize(2))');

                            roiMask=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.createMask();
                            if strcmp(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_sampling.Type,'images.roi.rectangle')
                                rot_=-app.SpecMap.Spectra.Sample(app.selected_sample).ROI(app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI).ROI_sampling.RotationAngle;
                            else
                                rot_=0;
                            end

                            roiMask_rot=imrotate(roiMask,rot_);
                            ImageData_rot=imrotate(ImageData,rot_);

                            mask_ind=find(roiMask_rot>0);
                            [ii,jj]=ind2sub(size(roiMask_rot),mask_ind);
                            ymin=min(ii);ymax=max(ii);xmin=min(jj);xmax=max(jj);
                            MatrixProfils=imcrop(ImageData_rot,[xmin,ymin,xmax-xmin+1,ymax-ymin+1]);

                            if  size(MatrixProfils,1)>size(MatrixProfils,2)
                                MatrixProfils=MatrixProfils';
                            end

                            AB_tmp= zeros(size(x_signal_cor,1),size(MatrixProfils,2));
                            for m=1:size(MatrixProfils,2)
                                MatrixProfils_tmp=MatrixProfils(:,m);
                                MatrixProfils_tmp=MatrixProfils_tmp(MatrixProfils_tmp>0);
                                if isempty(MatrixProfils_tmp)
                                    AB_tmp(:,m)=nan;
                                else
                                    AB_tmp(:,m)=mean(y_signal_cor(:,MatrixProfils_tmp),2);
                                end
                            end

                            AB=[x_signal_cor,AB_tmp];
                            distance_micron=linspace(0,max(distance_micron),size(AB_tmp,2));
                            hFig2=figure;
                            nexttile
                            plot3(repmat(distance_micron,size(AB_tmp,1),1),x_signal_cor,AB_tmp,'linewidth',1,'Color','k');
                            ylabel( 'Wavenumbers [cm^{-1}]')
                            zlabel('Absorbance [cm^{-1}]')
                            xlabel('Distance [µm]')
                            view(-125,22)
                            nexttile
                            mesh(repmat(distance_micron,size(AB_tmp,1),1),x_signal_cor,AB_tmp,'linewidth',1);
                            ylabel( 'Wavenumbers [cm^{-1}]')
                            zlabel('Absorbance [cm^{-1}]')
                            xlabel('Distance [µm]')
                            view(-125,22)
                            nexttile
                            surf(repmat(distance_micron,size(AB_tmp,1),1),x_signal_cor,AB_tmp, 'EdgeColor','none','FaceAlpha',0.5)
                            ylabel( 'Wavenumbers [cm^{-1}]')
                            zlabel('Absorbance [cm^{-1}]')
                            xlabel('Distance [µm]')
                            view(-125,22)
                            nexttile

                            AB_tmp2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),2:end);
                            wn_cor2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),1);

                            mesh(repmat(distance_micron,size(AB_tmp2,1),1),wn_cor2,AB_tmp2,'linewidth',1);
                            ylabel( 'Wavenumbers [cm^{-1}]')
                            zlabel('Absorbance [cm^{-1}]')
                            xlabel('Distance [µm]')
                            view(-125,22)

                            save(fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}, '_ROI_Spectra_export_', num2str(selected_ROI), '.mat']),'AB')
                            save(fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample}, '_ROI_data_export_', num2str(selected_ROI), '.mat']),'exp_struct')


                            T_spec=array2table(AB);
                            T_spec.Properties.VariableNames=[{'Wavenumber'},cellstr(string(round(distance_micron,1)))];
                            writetable(T_spec,fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_ROI_Spectra_Profile_export_', num2str(selected_ROI) '.csv']))

                            set(hFig2, 'Units', 'centimeters',...
                                'OuterPosition', [0 0 19 27])
                            savefig(hFig2,[FileName '_2.fig'])
                            %      exportgraphics(hFig2,[FileName '_2.eps'],'BackgroundColor','none','ContentType','vector')
                            exportgraphics(hFig2,[FileName '_2.png'],Resolution=300)
                            exportgraphics(hFig2,[FileName '_2.pdf'])
                        else
                            %                         ´

                            %                         [x_signal_cor,y_signal_cor]
                            %                         AB=[x_signal_cor,AB_tmp];
                            %                         AB_tmp2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),2:end);
                            %                         wn_cor2=AB(x_signal_cor>min(app.xlimits)&x_signal_cor<max(app.xlimits),1);
                            %                         T_spec=[wn_cor2 mean(AB_tmp2,2) median(AB_tmp2,2) mean(AB_tmp2,2)];
                            %
                            %                         %writetable(array2table(AB),fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_ROI_Spectra_export_', num2str(selected_ROI) '.csv']))
                            %                         T_spec=array2table(T_spec);
                            %                         T_spec.Properties.VariableNames=['x_signal','y_signal_mean','y_signal_median','y_signal_std'];
                            %
                            %                         writetable(T_spec,fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_ROI_Mean_Median_export_', num2str(selected_ROI) '.csv']))
                        end
                    catch ME
  WhichOperation = 'Error in Full ROI Export - could not save ROI';
                    DisplayError(ME,WhichOperation)          
                    end

                end


                for n=1:numel(Profile)
                    Profile(n).T.d_micron(:)=Profile(n).d_micron;
                    writetable(flip(Profile(n).T,2),fullfile(path,[app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} '_ROI_Profile_export_', num2str(n) '.xls']))
                end

                app.SpecMap.Spectra.Sample(app.selected_sample).selected_ROI=sel_ini;

                %                 AB=[x_signal,expmat];
                %                 uisave({'AB'}, [app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} 'ROI_Mean_Export'])
                %                 AB=[x_signal,y_signal(:,ROI)];
                %                 uisave({'AB'}, [app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample} 'ROI_Spectra_export'])
                %


            catch ME
   WhichOperation = 'Error in Full ROI Export - roi mean error';
                    DisplayError(ME,WhichOperation)           
            end


        end

        % Button pushed function: SubstractDeconButton
        function SubstractDeconButtonPushed(app, event)
            tic
            try
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
                [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
                try
                    pos=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos_shift';
                catch
                    pos= app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_pos';

                end
                wid=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_width';
                amp=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.peakmat_hight';
                peakshape=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.PeakFit.peakshape;
                DELTA=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.PeakFit.DELTA;
                extra=app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).Peaks.PeakTeststruc.PeakFit.extra;
                y_calc=0;
                for n=1:size(pos,1)

                    y_calc=DeconPlotFun(peakshape,x_signal',pos(n,:)',wid(n,:)',amp(n,:)',DELTA,extra)+y_calc;
                end
                %% save the rawdata
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'y_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw)
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal;
                end

                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample),'x_signal_raw')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw)
                    app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal_raw=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
                end

                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal=x_signal;
                app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).y_signal=y_signal-y_calc';

                app.BackgroundCheckBox.Value=false;
                app.FilterCheckBox.Value=false;
                update_active_plot(app)
                app.edit_state=true;

                disp('Edit Spectra Complete - time:')
            catch ME

                ME
                disp('SubstractDeconButtonPushed - Error')

            end
            toc
        end

        % Menu selected function: IntQuantMapwtH2OMenu
        function IntQuantMapwtH2OMenuSelected(app, event)
            definput = {'1/3','2.65'};
            x = inputdlg({'ε_i integral molar absorption coefficient [L mol(H2O)-1 cm^{-2}]','Density [g/cm^{2}]'}, ['Parameters'],[1 15; 1 15],definput);
            E=str2num(x{1}); %#ok
            density=str2double(x(2));

            UpdateMapPlotMatrix(app,[],'Int. QuantMap [wt.% H2O]',density,E);

        end

        % Menu selected function: IntQuantMapggH2OMenu
        function IntQuantMapggH2OMenuSelected(app, event)
            definput = {'1/3','2.65'};
            x = inputdlg({'ε_i integral molar absorption coefficient [L mol(H2O)-1 cm^{-2}]','Density [g/cm^{2}]'}, ['Parameters'],[1 15; 1 15],definput);
            E=str2num(x{1}); %#ok
            density=str2double(x(2));

            UpdateMapPlotMatrix(app,[],'Int. QuantMap [μg/g H2O]',density,E);

        end

        % Menu selected function: Paterson1982Menu
        function Paterson1982MenuSelected(app, event)
            definput = {'1/3','2.65'};
            x = inputdlg({'E','Density'}, ['Parameters'],[1 15; 1 15],definput);
            E=str2num(x{1}); %#ok
            density=str2double(x(2));

            UpdateMapPlotMatrix(app,[],'Paterson (1982)',density,E);

            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;

        end

        % Menu selected function: LibowitzkyandRossman1997Menu
        function LibowitzkyandRossman1997MenuSelected(app, event)
            definput = {'1/3','2.65'};
            x = inputdlg({'E','Density'}, ['Parameters'],[1 15; 1 15],definput);
            E=str2num(x{1}); %#ok
            density=str2double(x(2));

            UpdateMapPlotMatrix(app,[],'Libowitzky and Rossman (1997)',density,E);

            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;
        end

        % Menu selected function: Balanetal2008Menu
        function Balanetal2008MenuSelected(app, event)
            definput = {'2.65'};
            x = inputdlg({'Density'}, 'Set Density', [1 15],definput);
            density=str2double(x(1));

            UpdateMapPlotMatrix(app,[],'Balan et al. (2008)',density);

            UpdateTree(app);
            update_active_plot(app)
            app.edit_state=true;
        end

        % Menu selected function: PresetMapsforallSamplesMenu
        function PresetMapsforallSamplesMenuSelected(app, event)
            if isdeployed
                        path=fullfile(app.SpecXY.installpath,'MapPresetTable.csv');
                    if not(exist(path,'file'))
                        message={'Error MapPresetTable.csv not found: ', path};
                        uialert(app.SpecMapsUIFigure,message,'Error');
                        return
                    end

%                 path=ctfroot;
%                 disp('ctfroot: ' )
%                 disp(path)
% 
%                 path_split=split(path,{'SpecXY.app','SpecXY_dev.app','SpecXY_dev.exe','SpecXY.exe'});
%                 path=fullfile(path_split{1},'MapPresetTable.csv');
% 
%                 disp('file: ' )
%                 disp(path)
%                 if not(exist(path,'file'))
%                     path=fullfile(path,'MapPresetTable.csv');
%                     replace(path,'MapPresetTable.csv/MapPresetTable.csv','MapPresetTable.csv')
%                     disp('Oh no here is the windows error again - PresetMapsforallSamplesMenuSelected')
%                 end
            else
                path=which('MapPresetTable.csv');
            end
            try
                MapPresetTable=readtable(path);
            catch
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select PresetTable']);
                delete(f);
                if not(isequal(file,0))
                    MapPresetTable=readtable(fullfile(path,file));
                else
                    return
                end
            end

            GroupNames=unique(MapPresetTable.Group);

            [indx,~] = listdlg('PromptString',{'Select Group to plot.'},...
                'SelectionMode','multiple','ListString',GroupNames,'ListSize', [150 300]);


            count=0;
            count2=0;
            d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Generating Maps',...
                'Message','Automatic Map Generation Started','Cancelable','on');
            t=[];
            for m=1:numel(app.SpecMap.Spectra.Names)
                for n=1:(numel(app.SpecMap.Spectra.Sample(m).Names))
                    count=count+1;
                end
            end

            for m=1:numel(app.SpecMap.Spectra.Names)
                for gs=1:numel(indx)
                    selected_groups=ismember(MapPresetTable.Group,GroupNames(indx(gs)));
                    SelectedMapPresetTable=MapPresetTable(selected_groups,:);


                    if d.CancelRequested
                        break
                    end
                    try
                        for n=1:(numel(app.SpecMap.Spectra.Sample(m).Names))
                            tic
                            count2=count2+1;
                            d.Value = count2/count;
                            if d.CancelRequested
                                break
                            end

                            app.selected_sample=m;
                            app.selected_subsample=n;

                            try
                                app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)+1;
                            catch
                                app.selected_map=1;
                            end
                            app.selected_submap=1;
                            for s=1:size(SelectedMapPresetTable,1)
                                d.Message = sprintf(['Generating Maps: \nSample: ' app.SpecMap.Spectra.Names{m} '\nSubample: ' app.SpecMap.Spectra.Sample(m).Names{n} '\nMap: ' SelectedMapPresetTable.Mapname{s}]);
                                if d.CancelRequested
                                    break
                                end

                                app.MapGenDropDown.Value=SelectedMapPresetTable.Maptype(s);

                                app.Int_range_max.Value=SelectedMapPresetTable.Value2(s);
                                if not(isnan(SelectedMapPresetTable.Value1(s)))
                                    app.Int_range_min.Value=SelectedMapPresetTable.Value1(s);
                                end


                                if SelectedMapPresetTable.XLimits_1(s)==0 && isnan(SelectedMapPresetTable.XLimits_2(s))
                                    %ResetButton_2Pushed(app)
                                    xlimval=[min(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)...
                                        max(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)];
                                    app.EditField_xlimMin.Value=min(xlimval);
                                    app.EditField_xlimMax.Value=max(xlimval);
                                    app.xlimits=xlimval;

                                else
                                    app.EditField_xlimMin.Value=min([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                                    app.EditField_xlimMax.Value=max([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                                    app.xlimits=sort([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                                end
                                app.Always_Update_Map=true;
                                sampleinfo=UpdateMapPlotMatrix(app);
                                app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}=SelectedMapPresetTable.Mapname{s};
                            end

                        end
                    catch ME
                         WhichOperation = 'Error in PresetMapsforallSamplesMenuSelected';
                    DisplayError(ME,WhichOperation)                    
                    end
                end
            end

            UpdateTree(app);
            update_active_plot(app)


        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotButtonPushed(app, event)
            %% Make Table
            Mapnames={};
            Map_Id=[];
            for h=app.selected_sample

                for j=1:numel(app.SpecMap.Spectra.Sample(h).Names)
                    if not(isequal([app.selected_sample app.selected_subsample],[h j]))
                        Map_Id(end+1,:)=[h j 1 1];
                        Mapnames{end+1}=[app.SpecMap.Spectra.Names{h} '_' app.SpecMap.Spectra.Sample(h).Names{j}];
                    end
                end

            end


            for h=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)
                if not(isfield(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(h),'SubMapNames')) || isempty(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(h).SubMapNames)
                    Map_Id(end+1,:)=[app.selected_sample app.selected_subsample h 1];
                    Mapnames(end+1)=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames(h);
                else
                    for j=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(h).SubMapNames)
                        Map_Id(end+1,:)=[app.selected_sample app.selected_subsample h j];
                        Mapnames{end+1}=[app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames{h} '_' app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(h).SubMapNames{j}];
                    end
                end
            end


            %%
            T=zeros(numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(Map_Id(1,3)).SubMap(Map_Id(1,4)).MapPlotMatrix),size(Map_Id,1));

            %% extract data


            for n=1:size(Map_Id,1)
                matrix= app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(Map_Id(n,3)).SubMap(Map_Id(n,4)).MapPlotMatrix;
                thx=ThicknessCorrectionFun(app,matrix);
                T(:,n)=thx(:);
            end

            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask(:);
            else
                mask=true(size(T,1),1);
            end

            ClassT=[];
            ClassNames={};
            ClassC=cell(size(T,1),0);
            try
                for n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes)
                    for m=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMap)
                        thx=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMap(m).MapPlotMatrix(:);
                        thx2=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMap(m).ClassesNames;
                        ClassNames{end+1}=[app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames{n} '_' app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMapNames{m}];
                        ClassT(:,end+1)=thx(:);
                        try
                            ClassC(:,end+1)=thx2(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMap(m).classNumber);
                        catch
                            thx2(end+1)= {'no class'};
                            % thx4=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(n).ClassMap(m).classNumber;
                            thx4=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix(:);

                            thx4(isnan(thx4))=numel(thx2);
                            ClassC(:,end+1)=thx2(thx4);
                        end
                    end
                end
            catch ME
                %%
                %  ClassesNames=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
                %                 ClassMapName=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMapNames(selectedNodes(2));
                %%
                disp(ME)
                disp('Error in GeoCPlotButtonP')
            end

            VarNames=['Active',  Mapnames, ClassNames];

            T2Send=[array2table([mask,T]),cell2table(ClassC)];
            T2Send.Properties.VariableNames=matlab.lang.makeUniqueStrings(VarNames);
            T2Send=T2Send(mask,:);
            T2Send.(1)(:)=true;
            %% send to GeoCPLot
            GeoCPlot(T2Send)
        end

        % Menu selected function: PlotMultiMenu
        function PlotMultiMenuSelected(app, event)
            if isempty(app.hFig_tmp)|| not(isvalid(app.hFig_tmp))
                app.hFig_tmp=figure;
                nexttile
                hold on
            else
                nexttile

            end
            sampleinfo_ini=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

            Axis2Plot=findall(app.hFig_tmp,'type','axes');
            for n=1:numel(Axis2Plot)
                Axis2Plot(n).FontSize=20;
            end
            Axis2Plot=Axis2Plot(1);
            hold on

            for n=1:numel(app.SpecMap.Spectra.Names)
                app.selected_sample=n;
                %sampleinfo=[n app.selected_subsample app.selected_map app.selected_submap];
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];


                thickness_state=true;
                plotimage=true;
                matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage);

                MatrixLimits=update_MatixLimits(app,sampleinfo);

                plotimage=true;
                reset=false;
                alpha=zeros(size(matrix));
                plt_settings.cb_label='';
                plt_settings.plt_ROI=app.ROICheckBox.Value;

                UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings)
            end

            for n=1:numel(app.SpecMap.Spectra.Names)
                app.selected_sample=n;
                %sampleinfo=[n app.selected_subsample app.selected_map app.selected_submap];
                sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];


                thickness_state=true;
                plotimage=false;
                % matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage);
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') %&& numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                    alpha=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
                end
                if exist('alpha','var') && not(isempty(alpha))
                    alpha=alpha*(app.AlphaSlider.Value./100);
                else
                    alpha=ones(size(matrix))*app.AlphaSlider.Value./100;
                end
                matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage,[],alpha);


                MatrixLimits=update_MatixLimits(app,sampleinfo);

                plotimage=true;
                reset=false;

                try
                    plt_settings.cb_label=replace(app.TreeData_Main.SelectedNodes.Text,'_',' ');
                catch ME
                    disp('Error in Clable add2plot')
                    disp(ME)
                    %                plt_settings.cb_label='';

                    plt_settings.cb_label='Colorbar Label';
                end

                plt_settings.plt_ROI=app.ROICheckBox.Value;

                UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings)
            end

            title(Axis2Plot,app.SpecMap.Spectra.Sample(sampleinfo(1)).Names{sampleinfo(2)},'interpreter','None')

            xlim(Axis2Plot,'auto')
            ylim(Axis2Plot,'auto')

            caxis(Axis2Plot,'auto')

            colormap(Axis2Plot,app.ColorMapValues);
            app.selected_sample=sampleinfo_ini(1);
            app.selected_subsample =sampleinfo_ini(2);
            app.selected_map =sampleinfo_ini(3);
            app.selected_submap=sampleinfo_ini(4);

            try
                SizeMap=MatrixLimits(:,2)-MatrixLimits(:,1);
                PixelSize=mean(flipud(SizeMap)./size(matrix)');

                Scalebar_length= SizeMap(1)/10;
                thx=[min(MatrixLimits')+SizeMap'*0.1; max(MatrixLimits')-SizeMap'*0.4];
                X=thx(1,1);
                Y=thx(1,2);
                a = 0;
                c = [SizeMap(1)/10];
                test = sort([logspace(1,10,10) logspace(1,10,10)*5 ])   ;
                for k=flip(1:numel(test))
                    b=test(k);
                    E = max(a:b:c);
                    if E>0
                        break
                    end
                end

                X=min((Axis2Plot.XLim));
                Y=min((Axis2Plot.XLim));

                hEb=errorbar(X',Y',E,'horizontal','b.', 'linewidth',3);  % add scales
                hTxt=text(X,Y,[num2str(E*2) ' μm'], ...
                    'horizontalalignment','center','verticalalignment','top','fontsize',20);
                hEb.Color='k';
                hTxt.Extent;
                yl=  [min([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)]) max([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)])];
                xl=  [min([Axis2Plot.XLim hTxt.Extent(1)]) max([Axis2Plot.XLim hTxt.Extent(1) hTxt.Extent(1)+hTxt.Extent(3)] ) ];
                ylim(Axis2Plot,yl)
                xlim(Axis2Plot,xl)

                Axis2Plot.YTickLabel=[];
                Axis2Plot.YTick=[];
                Axis2Plot.XTickLabel=[];
                Axis2Plot.XTick=[];
            catch ME
                ME
            end
        end

        % Menu selected function: Add2MultiPlotMenu
        function Add2MultiPlotMenuSelected(app, event)
            if isempty(app.hFig_tmp)|| not(isvalid(app.hFig_tmp))
                app.hFig_tmp=figure;
                hold on
            end

            Axis2Plot=findall(app.hFig_tmp,'type','axes');
            try
                Axis2Plot.FontSize=20;
            catch
                for n=1:numel(Axis2Plot)
                    Axis2Plot(n).FontSize=20;
                end
                Axis2Plot=Axis2Plot(1)
            end
            hold on


            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

            thickness_state=true;
            plotimage=true;
            if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat,'Mask') %&& numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask)==numel(matrix)
                alpha=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.Mask;
            end
            matrix=MapPlot_fun_public(app,0,sampleinfo,thickness_state,plotimage,[],alpha);

            MatrixLimits=update_MatixLimits(app,sampleinfo);


            %%%% if you want to set 0-x 0-y
            %           MatrixLimits(:,2)=MatrixLimits(:,2)-MatrixLimits(:,1);
            %            MatrixLimits(:,1)=0;


            plotimage=app.plot_image_checkbox.Value;
            reset=false;
            if exist('alpha','var') && not(isempty(alpha))
                alpha=alpha*(app.AlphaSlider.Value./100);
            else
                alpha=ones(size(matrix))*app.AlphaSlider.Value./100;
            end
            try
                plt_settings.cb_label=app.TreeData_Main.SelectedNodes.Text;
            catch ME
                disp('Error in Clable add2plot')
                disp(ME)

                plt_settings.cb_label='Colorbar Label';
            end


            plt_settings.plt_ROI=app.ROICheckBox.Value;

            UpdateMapPlot(app,Axis2Plot,matrix,MatrixLimits,reset,plotimage,alpha,plt_settings)


            xlim(Axis2Plot,'auto')
            ylim(Axis2Plot,'auto')

            caxis(Axis2Plot,   [min([app.EditField_climMin.Value,app.EditField_climMax.Value]),max([app.EditField_climMin.Value,app.EditField_climMax.Value])]);
            title(Axis2Plot,app.SpecMap.Spectra.Sample(sampleinfo(1)).Names{sampleinfo(2)},'interpreter','None')

            colormap(Axis2Plot,app.ColorMapValues);


            %%

            try

                SizeMap=MatrixLimits(:,2)-MatrixLimits(:,1);
                PixelSize=mean(size(matrix)./flipud(SizeMap)');

                Scalebar_length= SizeMap(1)/10;
                thx=[min(MatrixLimits')+SizeMap'*0.1; max(MatrixLimits')-SizeMap'*0.4];
                X=thx(1,1);
                Y=thx(1,2);
                a = 0;
                c = [SizeMap(1)/10];
                test = sort([logspace(1,10,10) logspace(1,10,10)*5 ])   ;
                for k=flip(1:numel(test))
                    b=test(k);
                    E = max(a:b:c);
                    if E>0
                        break
                    end
                end

                hEb=errorbar(X',Y',E*2,'horizontal','b.', 'linewidth',3);  % add scales
                hTxt=text(X,Y,[num2str(E) ' μm'], ...
                    'horizontalalignment','center','verticalalignment','top','fontsize',20);
                hEb.Color='k';
                hTxt.Extent;
                yl=  [min([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)]) max([Axis2Plot.YLim hTxt.Extent(2) hTxt.Extent(2)+hTxt.Extent(4)])];
                xl=  [min([Axis2Plot.XLim hTxt.Extent(1)]) max([Axis2Plot.XLim hTxt.Extent(1) hTxt.Extent(1)+hTxt.Extent(3)] ) ];
                ylim(Axis2Plot,yl)

                xlim(Axis2Plot,xl)
                Axis2Plot;
                Axis2Plot.YTickLabel=[];
                Axis2Plot.YTick=[];
                Axis2Plot.XTickLabel=[];
                Axis2Plot.XTick=[];


                Axis2Plot.Colorbar.Position(3)=Axis2Plot.Colorbar.Position(3)*3;
                %Axis2Plot.Colorbar.Location='Eastoutside'
                %Axis2Plot.Colorbar.Position(1)=Axis2Plot.Colorbar.Position(1)+(Axis2Plot.Colorbar.Position(3)/2);

                Axis2Plot.Colorbar.TickLength=0.05;
                Axis2Plot.Colorbar.LineWidth=3;
                Axis2Plot.Colorbar.FontSize=Axis2Plot.FontSize;

                Axis2Plot.Colorbar.Position(2)=Axis2Plot.InnerPosition(2);
                Axis2Plot.Colorbar.Position(4)=Axis2Plot.InnerPosition(4);

                0
                %%%%%
            catch ME
                ME
            end
        end

        % Menu selected function: PresetMapsforselectedSamplesMenu
        function PresetMapsforselectedSamplesMenuSelected(app, event)
            if isdeployed
                path=fullfile(app.SpecXY.installpath,'MapPresetTable.csv');
                if not(exist(path,'file'))
                    message={'Error MapPresetTable.csv not found: ', path};
                    uialert(app.SpecMapsUIFigure,message,'Error');
                    return
                end


%                 path=ctfroot;
%                 disp('ctfroot: ' )
%                 disp(path)
% 
%                 path_split=split(path,{'SpecXY.app','SpecXY_dev.app','SpecXY_dev.exe','SpecXY.exe'});
%                 path=fullfile(path_split{1},'MapPresetTable.csv');
% 
%                 disp('file: ' )
%                 disp(path)
%                 if not(exist(path,'file'))
%                     path=fullfile(path,'MapPresetTable.csv');
%                     replace(path,'MapPresetTable.csv/MapPresetTable.csv','MapPresetTable.csv')
%                     disp('Oh no here is the windows error again - PresetMapsforselectedSamplesMenuSelected')
%                 end
            else
                path=which('MapPresetTable.csv');
            end

            try
                MapPresetTable=readtable(path);
            catch
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select PresetTable']);
                delete(f);
                if not(isequal(file,0))
                    MapPresetTable=readtable(fullfile(path,file));

                else
                    return
                end
            end

            GroupNames=unique(MapPresetTable.Group);

            [indx,~] = listdlg('PromptString',{'Select Group to plot.'},...
                'SelectionMode','multiple','ListString',GroupNames,'ListSize', [150 300]);


            selected_groups=ismember(MapPresetTable.Group,GroupNames(indx));

            SelectedMapPresetTable=MapPresetTable(selected_groups,:);


            count=0;
            count2=0;
            d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Generating Maps',...
                'Message','Automatic Map Generation Started','Cancelable','on');
            drawnow


            count=1;


            for m=app.selected_sample
                if d.CancelRequested
                    break
                end
                try
                    for n=app.selected_subsample
                        count2=count2+1;
                        d.Value = count2/count;
                        if d.CancelRequested
                            break
                        end

                        app.selected_sample=m;
                        app.selected_subsample=n;

                        try
                            app.selected_map=numel(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).MapNames)+1;
                        catch
                            app.selected_map=1;
                        end
                        app.selected_submap=1;
                        for s=1:size(SelectedMapPresetTable,1)
                            d.Message = sprintf(['Generating Maps: \nSample: ' app.SpecMap.Spectra.Names{m} '\nSubample: ' app.SpecMap.Spectra.Sample(m).Names{n} '\nMap: ' SelectedMapPresetTable.Mapname{s}]);
                            if d.CancelRequested
                                break
                            end
                            app.MapGenDropDown.Value=SelectedMapPresetTable.Maptype(s);

                            app.Int_range_max.Value=SelectedMapPresetTable.Value2(s);
                            if not(isnan(SelectedMapPresetTable.Value1(s)))
                                app.Int_range_min.Value=SelectedMapPresetTable.Value1(s);
                            end


                            if SelectedMapPresetTable.XLimits_1(s)==0 && isnan(SelectedMapPresetTable.XLimits_2(s))
                                %ResetButton_2Pushed(app)
                                xlimval=[min(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)...
                                    max(app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal)];
                                app.EditField_xlimMin.Value=min(xlimval);
                                app.EditField_xlimMax.Value=max(xlimval);
                                app.xlimits=xlimval;

                            else
                                app.EditField_xlimMin.Value=min([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                                app.EditField_xlimMax.Value=max([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                                app.xlimits=sort([SelectedMapPresetTable.XLimits_1(s) SelectedMapPresetTable.XLimits_2(s)]);
                            end
                            app.Always_Update_Map=true;
                            sampleinfo=UpdateMapPlotMatrix(app);
                            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(sampleinfo(2)).MapNames{sampleinfo(3)}=SelectedMapPresetTable.Mapname{s};
                        end

                    end
                catch ME
                    disp(ME)
                    disp(ME.message)
                    disp('Error in PresetMapsforselectedSamplesMenuSelected')
                end
            end

            UpdateTree(app);
            update_active_plot(app)
        end

        % Menu selected function: MapCalculatorMenu
        function MapCalculatorDevMenuSelected(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            SampleNames=app.SpecMap.Spectra.Names;

            MapCalculator(app, sampleinfo, app.TreeData_Main, SampleNames)
        end

        % Menu selected function: GIFPlotMenu
        function GIFPlotMenuSelected(app, event)
            [file,path] = uiputfile('sweep_x.gif');
            outfile=fullfile(path,file);

            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];

            [x_signal,y_signal,~] = get_specs(app,sampleinfo,app.xlimits);
            mapsize=app.SpecMap.Spectra.Sample(sampleinfo(1)).MetaPrivat.mapsize_raw;
            MatrixLimits=update_MatixLimits(app,sampleinfo);

            figure;

            t=1;

            Axis2Plot1=subplot(1,2,1);
            Axis2Plot1.FontSize = 15;

            UpdateSpectraPlot(app,Axis2Plot1)
            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (Axis2Plot1, 'xdir', 'reverse')
            end


            Axis2Plot2=subplot(1,2,2);

            daspect([1 1 1])

            for region=flip((app.xlimits):3:max(app.xlimits))
                Axis2Plot1=subplot(1,2,1);
                cla(Axis2Plot1)
                UpdateSpectraPlot(app,Axis2Plot1)
                xln=xline(Axis2Plot1,region);
                title(num2str(round(region)))
                Axis2Plot2=subplot(1,2,2);
                Axis2Plot2.FontSize = 15;

                hold on

                [matrix]= CalcMatrixFromSpec(x_signal,y_signal,'Absolute',region,mapsize,[],[],'test');
                if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                    matrix(not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask))=nan;
                end
                matrix=ThicknessCorrectionFun(app,matrix);

                cla(Axis2Plot2)
                plt_settings.plt_ROI=app.ROICheckBox.Value;

                UpdateMapPlot(app,Axis2Plot2,matrix,MatrixLimits,true,false,[],plt_settings)
                colormap(Axis2Plot2,app.ColorMapValues);
                Axis2Plot2.FontSize = 15;

                hold off


                % gif utilities
                set(gcf,'color','w'); % set figure background to white
                drawnow;

                frame = getframe(1);
                im = frame2im(frame);

                [imind,cm] = rgb2ind(im,256);

                % On the first loop, create the file. In subsequent loops, append.
                if t==1
                    t=0;
                    imwrite(imind,cm,outfile,'gif','DelayTime',0,'loopcount',inf);
                else
                    imwrite(imind,cm,outfile,'gif','DelayTime',0,'writemode','append');
                end
                delete(xln)
            end

        end

        % Button pushed function: SaveMeanSpectraButton_2
        function SaveMeanSpectraButton_2Pushed(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            % get the uncorrected Spectra
            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            [~,y_signal,~] = get_specs(app,sampleinfo,[min(x_signal) max(x_signal)]);


            SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
            try
                SubSampleName=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
            catch
                SubSampleName='';
            end
            N=app.ClassTree.SelectedNodes;
            selectedNodes = N.NodeData;
            %             N=app.ClassTree.CheckedNodes;
            %             selectedNodes = N.NodeData;


            if numel(selectedNodes)==2 && not(selectedNodes(2)==0)
                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).MapPlotMatrix;
                n_classes=numel(unique(matrix(not(isnan(matrix)))));
                ClassesNames=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMap(selectedNodes(2)).ClassesNames;
                ClassMapName=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.Classes(selectedNodes(1)).ClassMapNames(selectedNodes(2));
                ClassificationName=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Classification.ClassesNames(selectedNodes(1));

                for n=1:n_classes
                    selected_class=(matrix==n)';
                    selected_class=selected_class(:);
                    y=y_signal(:,selected_class);
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==numel(matrix)
                        %here thickness=mean(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map(selected_class),"omitnan");
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map';
                        thickness=mean(thickness(selected_class));
                    elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map;
                    else
                        thickness=0;
                    end

                    if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==numel(matrix)
                        mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask';
                    else
                        mask=ones(size(matrix));
                    end
                    mask=mask(selected_class);

                    Data.DB(n).y_signal=mean(y,2);
                    Data.DB(n).x_signal=x_signal;

                    Data.Meta(n).Id=n;
                    if strcmp(char(app.SpectraNameEditField.Value),'')
                        Data.Meta(n).SampleName=[char(SubSampleName)  '_' char(ClassesNames{n})];
                    else
                        Data.Meta(n).SampleName=[char(app.SpectraNameEditField.Value)  '_' char(ClassesNames{n})];
                    end
                    if strcmp(char(app.MineralEditField.Value),'')
                        Data.Meta(n).Mineral='Unknown';
                    else
                        Data.Meta(n).Mineral=char(app.MineralEditField.Value);
                    end
                    Data.Meta(n).Orientation=char(app.OrientationEditField.Value);
                    Data.Meta(n).InfraredRadiation=char(app.InfraredRadiationEditField.Value);
                    Data.Meta(n).Thickness=thickness;
                    Data.Meta(n).Mask=true;
                    Data.Meta(n).Density=0;
                    Data.Meta(n).SampleType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
                    Data.Meta(n).Comment=[char(ClassificationName{:})  ' / '  char(ClassMapName{:})  ' / ' char(ClassesNames{n})]; %char((ClassesNames{n}));
                    if strcmp(char(app.SampleEditField.Value),'')
                        Data.Meta(n).Sample=char(SampleName);
                    else
                        Data.Meta(n).Sample=char(app.SampleEditField.Value);
                    end
                    Data.Meta(n).Source=char('SpecMaps');
                    Data.Meta(n).MapName=char(SampleName);
                    Data.Meta(n).SubMapName=char(SubSampleName);
                    Data.Meta(n).ClassificationName=char(ClassificationName{:});
                    Data.Meta(n).ClassMapName=char(ClassMapName{:});
                    Data.Meta(n).ClassesNames=char(ClassesNames{n}); %char((ClassesNames{n}));Data.Meta(n).Comment=[char(ClassificationName{:})  ' / '  char(ClassMapName{:})  ' / ' char(ClassesNames{n})]; %char((ClassesNames{n}));
                    Data.Meta(n).n_meanSpec=1;

                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')
                        Data(1).Meta(1).Images=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id);
                    end

                    Data.Meta(n).MetaTable=[];

                end

            end
            insert_data(SpecDB,Data)

        end

        % Button pushed function: SaveROIMeanSpectraButton
        function SaveROIMeanSpectraButtonPushed(app, event)
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            % get the uncorrected Spectra
            x_signal=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).x_signal;
            [~,y_signal,~] = get_specs(app,sampleinfo,[min(x_signal) max(x_signal)]);


            SampleName=app.SpecMap.Spectra.Names{app.selected_sample};
            try
                SubSampleName=app.SpecMap.Spectra.Sample(app.selected_sample).Names{app.selected_subsample};
            catch
                SubSampleName='';
            end


            mean_state=false;


            for n=1:numel(app.SpecMap.Spectra.Sample(app.selected_sample).ROI)
                try
                    mask_ids=find((app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)');%all_ids(not(mask_vector(:)));
                    %ROI=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling.createMask()');%app.SpecMap.Spectra.Sample(app.selected_sample).ROI(selected_ROI).ROI_save.Idx;
                    ROI=find(app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_sampling.createMask()');
                    %ROI=app.SpecMap.Spectra.Sample(app.selected_sample).ROI(n).ROI_save.Idx;


                    %                 if numel(mask_ids)>1
                    %                     ROI=intersect(ROI,mask_ids);
                    %                 end

                    if mean_state==true
                        y=mean(y_signal(:,ROI),2,"omitnan");
                        if numel(mask_ids)>1
                            ROI=intersect(ROI,mask_ids);
                        end

                        ROI=ROI(:);

                    else


                        y=y_signal(:,ROI);
                    end

                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==size(y_signal,2)
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map';
                        if mean_state==true
                            thickness=mean(thickness(ROI));
                        else
                            thickness=thickness(ROI);
                        end
                    elseif isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Thickness_map') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map)==1
                        thickness=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Thickness_map;
                    else
                        thickness=0;
                    end


                    Data.DB(n).y_signal=y;
                    Data.DB(n).x_signal=x_signal;

                    Data.Meta(n).Id=n;
                    if strcmp(char(app.SpectraNameEditField.Value),'')
                        Data.Meta(n).SampleName=[char(SubSampleName)  '_' num2str(n)];
                    else
                        Data.Meta(n).SampleName=[char(app.SpectraNameEditField.Value)  '_' num2str(n)];
                    end
                    if strcmp(char(app.MineralEditField.Value),'')
                        Data.Meta(n).Mineral='Unknown';
                    else
                        Data.Meta(n).Mineral=char(app.MineralEditField.Value);
                    end
                    Data.Meta(n).Orientation=char(app.OrientationEditField.Value);
                    Data.Meta(n).InfraredRadiation=char(app.InfraredRadiationEditField.Value);
                    Data.Meta(n).Thickness=thickness;
                    if mean_state==true
                        Data.Meta(n).Mask=true;
                    else
                        if app.MaskCheckBox.Value==true && isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'Mask') && numel(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask)==size(y_signal,2)
                            mask=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.Mask';
                            mask=mask(ROI);
                        else
                            mask=ones(size(y,2),1);
                        end

                        Data.Meta(n).Thickness=thickness;
                        Data.Meta(n).Mask=logical(mask);

                        %    Data.Meta(n).Mask=true;

                    end
                    Data.Meta(n).Density=0;
                    Data.Meta(n).SampleType=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType;
                    Data.Meta(n).Comment='';
                    if strcmp(char(app.SampleEditField.Value),'')
                        Data.Meta(n).Sample=char(SampleName);
                    else
                        Data.Meta(n).Sample=char(app.SampleEditField.Value);
                    end
                    Data.Meta(n).Source=char('SpecMaps ROI');
                    Data.Meta(n).MapName=char(SampleName);
                    Data.Meta(n).SubMapName=char(SubSampleName);
                    if mean_state==true % ath add here
                        Data.Meta(n).n_meanSpec=1;
                    else
                        Data.Meta(n).n_meanSpec=numel(ROI);

                    end
                    if isfield(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat,'ImageGroup_Id')
                        Data(1).Meta(1).Images=app.SpecMap.Images.Images(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.ImageGroup_Id);
                    end

                    if mean_state==true % ath add here
                        Data.Meta(n).MetaTable=[];
                    else
                        try
                            if not(isempty(app.selected_sample)) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && not(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200)

                                meta_t=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable.Type~=13,:);
                                Data.Meta(n).MetaTable=meta_t(ROI,:);


                            elseif not(isempty(app.selected_sample)) && not(isempty(app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable)) && app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.SpecType==200
                                MatrixLimit=update_MatixLimits(app);
                                %px_size=app.SpecMap.Spectra.Sample(app.selected_sample).Settings(app.selected_subsample).Px_size
                                mapsize_raw=app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw;
                                y_signal_=fliplr(linspace(MatrixLimit(2,1),MatrixLimit(2,2),mapsize_raw(1)));
                                x=fliplr(linspace(MatrixLimit(1,1),MatrixLimit(1,2),mapsize_raw(2)));
                                [x_pos,y_pos] = meshgrid(x,y_signal_);
                                thx= [1:numel(ROI) x_pos(ROI) y_pos(ROI) ones(size(y_pos(ROI)))];
                                Data.Meta(n).MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
                                %meta_tbl= app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.MetaTable;

                            else
                                matrix=app.SpecMap.Spectra.Sample(app.selected_sample).Data(app.selected_subsample).Maps(app.selected_map).SubMap(app.selected_submap).MapPlotMatrix;
                                y_signal_=flipud(linspace(0,size(matrix,2),size(matrix,2)));
                                x=flipud(linspace(0,size(matrix,1),size(matrix,1)));
                                [x_pos,y_pos] = meshgrid(x,y_signal_);
                                thx= [ROI x_pos(ROI) y_pos(ROI) ones(size(y_pos(ROI)))];
                                Data.Meta(n).MetaTable=array2table(thx,"VariableNames",{'Fileindex','x','y','Group'});
                            end
                        catch ME
                            Data.Meta(n).MetaTable=[];
                          WhichOperation = 'Error SaveROIMeanSpectra -  MetaTable generation failed';
                    DisplayError(ME,WhichOperation)           
                  

                        end

                    end


                catch ME
                    ME
                end

            end

            insert_data(SpecDB,Data)

        end

        % Value changed function: ROICheckBox
        function ROICheckBoxValueChanged(app, event)
            reset_ROI_Sampling_Figs(app)
            MapSettingsChanged(app, event)
        end

        % Menu selected function: ConvertProjecttoSingleprecisionMenu
        function ConvertProjecttoSingleprecisionMenuSelected(app, event)
            tic
            d = uiprogressdlg(app.SpecMapsUIFigure,'Title','Updating Project',...
                'Message','Update Started','Cancelable','off');
            drawnow


            count=1;


            %%
            for n=1:numel(app.SpecMap.Spectra.Names)
                sampleinfo(1)=n;
                d.Value = n/numel(app.SpecMap.Spectra.Names);

                for m=1:numel(app.SpecMap.Spectra.Sample(sampleinfo(1)).Names)
                    d.Message = sprintf(['Updating Maps: \nSample: ' app.SpecMap.Spectra.Names{n} '\nSubample: ' app.SpecMap.Spectra.Sample(m).Names{m}]);
                    if d.CancelRequested
                        break
                    end

                    app.SpecMap.Spectra.Sample(n).Data(m).y_signal=single(app.SpecMap.Spectra.Sample(n).Data(m).y_signal);
                    app.SpecMap.Spectra.Sample(n).Data(m).x_signal=single(app.SpecMap.Spectra.Sample(n).Data(m).x_signal);

                end
            end
            toc
            message='Update Completed';
            uialert(app.SpecMapsUIFigure,message,'Update Completed','Icon','success');
        end

        % Menu selected function: GeoCPlotMenu
        function GeoCPlotMenuSelected(app, event)
            GeoCPlot
        end

        % Menu selected function: SpecDBMenu
        function SpecDBMenuSelected(app, event)
            SpecDB
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)

            node= app.TreeData_Main.SelectedNodes;
            if isempty(node)
                return
            end
            Text_new= char(inputdlg('Enter New Name:','Edit Name', 1,{char(node.Text)}));
            if strcmp(Text_new,'')
                figure(app.SpecMapsUIFigure);
                return
            end

            if node.NodeData(1)==2
                if numel(node.NodeData)>4 && node.NodeData(5)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Data(node.NodeData(3)).Maps(node.NodeData(4)).SubMapNames{node.NodeData(5)}=Text_new;
                elseif numel(node.NodeData)>3 && node.NodeData(4)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Data(node.NodeData(3)).MapNames{node.NodeData(4)}=Text_new;
                elseif numel(node.NodeData)>2 && node.NodeData(3)>0
                    app.SpecMap.Spectra.Sample(node.NodeData(2)).Names{node.NodeData(3)}=Text_new;
                elseif numel(node.NodeData)>1 && node.NodeData(2)>0
                    app.SpecMap.Spectra.Names{node.NodeData(2)}=Text_new;
                end
            end
            UpdateTree(app);

        end

        % Menu selected function: GenerateReportMenu
        function GenerateReportMenuSelected(app, event)
            n=0;
            T=table();
            for sample=1:numel(app.SpecMap.Spectra.Names)
                for subsample=1:numel(app.SpecMap.Spectra.Sample(sample).Names)
                    n=n+1;
                    T.Sample(n)=app.SpecMap.Spectra.Names(sample);
                    T.SubSample(n)=app.SpecMap.Spectra.Sample(sample).Names(subsample);
                    try
                        %ATH if FPA
                        T.FPA_tiles_x(n)=app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_tiles(1);
                        T.FPA_tiles_y(n)=app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_tiles(2);
                        T.FPA_tiles_n(n)=app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_tiles(1)*app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_tiles(2);

                        T.FPA_px_tiles_x(n)= app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_Px_tile(1);
                        T.FPA_px_tiles_y(n)= app.SpecMap.Spectra.Sample(sample).Settings(subsample).FPA_Px_tile(2);
                        %ATH if FPA end
                    catch
                    end
                    [MatrixLimits, mapsize]=update_MatixLimits(app,[sample subsample 1 1 ]);

                    T.n_px_x(n)=mapsize(2);
                    T.n_px_y(n)=mapsize(1);
                    T.n_px(n)=mapsize(1)*mapsize(2);

                    T.n_px_masked(n)=sum(app.SpecMap.Spectra.Sample(sample).MetaPrivat.Mask(:));
                    T.n_px_filtered(n)=sum(not(app.SpecMap.Spectra.Sample(sample).MetaPrivat.Mask(:)));

                    Px_size=app.SpecMap.Spectra.Sample(sample).Settings(subsample).Px_size;
                    T.mapsize_x_micron(n)=Px_size(2)*mapsize(2);
                    T.mapsize_y_micron(n)=Px_size(1)*mapsize(1);


                    T.MatrixLimits_x_min(n)=MatrixLimits(1,1);
                    T.MatrixLimits_x_max(n)=MatrixLimits(1,2);
                    T.MatrixLimits_y_min(n)=MatrixLimits(2,1);
                    T.MatrixLimits_y_max(n)=MatrixLimits(2,2);


                end
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir;
            delete(f); %delete the dummy figure
            if isequal(path,0)
                figure(app.SpecMapsUIFigure);
                return
            end
            writetable(T,fullfile(path,['SpecMaps_report.csv']))

        end

        % Menu selected function: OMNICmapMenu
        function OMNICmapMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.map;*.*'},"MultiSelect","on",'Select Files');
            delete(f);

            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        try
                            SampleName=split(char(file(m)),'.');
                            SampleName=char(SampleName(1));
                            reshape_omnicmap(app,fullfile(path,char(file(m))),SampleName)
                        catch
                        end
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    reshape_omnicmap(app,fullfile(path,file),SampleName)
                end
            end
            ResetButton_2Pushed(app)
            UpdateTree(app);
        end

        % Menu selected function: fsmMenu
        function fsmMenuSelected(app, event)
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.fsm;*.*'},"MultiSelect","on",'Select Files');
            delete(f);

            if not(isequal(file,0))
                if iscell(file)
                    for m=1:length(file)
                        try
                            SampleName=split(char(file(m)),'.');
                            SampleName=char(SampleName(1));
                            reshape_fsm(app,fullfile(path,char(file(m))),SampleName)
                        catch
                        end
                    end
                else
                    SampleName=split(file,'.');
                    SampleName=char(SampleName(1));
                    reshape_fsm(app,fullfile(path,file),SampleName)
                end
            end
            ResetButton_2Pushed(app)
            UpdateTree(app);

        end

        % Menu selected function: DebuggModeMenu_2
        function DebuggModeMenu_2Selected(app, event)
            keyboard
        end

        % Menu selected function: ImportMapMenu
        function ImportMapMenuSelected(app, event)
            

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.mat'},"MultiSelect","on",'Select Files');
            delete(f);


            matrix_to_match=struct();
            for n=1:numel(file)
                tmp_mat1=readmatrix(fullfile(path,file{n}));
                matrix_to_match(n).MapPlotMatrix=tmp_mat1;
            end

            matrix_to_matchNames=file;

            SampleName='XMT_test';

            SubsampleName='XMT_sub_test';
            DataIn=[0 0];%=[(1:size(specmat,1))' specmat];
            Thickness_map=1;
            MetaTable='';
            Classification='';
            etc='';
            Mask=[];
try

            mapsize_raw=size(tmp_mat1);
            SpecType=0;

            app=import_Map(app,SampleName,SubsampleName,DataIn,mapsize_raw,SpecType,Mask,Thickness_map,MetaTable,Classification,etc);
            sampleinfo=[app.selected_sample app.selected_subsample app.selected_map app.selected_submap];
            app.selected_map=1;
            app.selected_submap=1;
            type=150;
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Data(app.selected_subsample).MapNames=[];
            app.SpecMap.Spectra.Sample(sampleinfo(1)).Settings.mapsize=mapsize_raw;
            MapMatchReturn(app,matrix_to_match,matrix_to_matchNames,sampleinfo,type)
            app.SpecMap.Spectra.Sample(app.selected_sample).MetaPrivat.mapsize_raw=mapsize_raw;
catch ME

    ME
end

            %%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecMapsUIFigure = uifigure('Visible', 'off');
            app.SpecMapsUIFigure.Name = 'SpecMaps';
            app.SpecMapsUIFigure.Icon = 'symbols_1_logo.png';
            app.SpecMapsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpecMapsUIFigureCloseRequest, true);
            app.SpecMapsUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @SpecMapsUIFigureWindowButtonUp, true);
            app.SpecMapsUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SpecMapsUIFigureWindowButtonMotion, true);
            app.SpecMapsMenu = uimenu(app.SpecMapsUIFigure);
            app.SpecMapsMenu.Text = 'SpecMaps';
            app.NewProjectMenu = uimenu(app.SpecMapsMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.LoadProjectMenu = uimenu(app.SpecMapsMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.SaveProjectMenu = uimenu(app.SpecMapsMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectAsMenu = uimenu(app.SpecMapsMenu);
            app.SaveProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
            app.SaveProjectAsMenu.Text = 'Save Project As';
            app.ExportMapMenu = uimenu(app.SpecMapsMenu);
            app.ExportMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMapMenuSelected, true);
            app.ExportMapMenu.Text = 'Export Map';
            app.SettingsMenu = uimenu(app.SpecMapsMenu);
            app.SettingsMenu.Text = 'Settings';
            app.CloseSpecMapsMenu = uimenu(app.SpecMapsMenu);
            app.CloseSpecMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseSpecMapsMenuSelected, true);
            app.CloseSpecMapsMenu.Text = 'Close SpecMaps';
            app.ImportMenu = uimenu(app.SpecMapsUIFigure);
            app.ImportMenu.Text = 'Import';
            app.AutoImportMenu = uimenu(app.ImportMenu);
            app.AutoImportMenu.Text = 'Auto Import';
            app.FTIRMenu = uimenu(app.AutoImportMenu);
            app.FTIRMenu.Text = 'FTIR';
            app.BrukerIFGBernMenu = uimenu(app.FTIRMenu);
            app.BrukerIFGBernMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoImportButtonPushed, true);
            app.BrukerIFGBernMenu.Text = 'Bruker IFG Bern';
            app.OMNICmapMenu = uimenu(app.FTIRMenu);
            app.OMNICmapMenu.MenuSelectedFcn = createCallbackFcn(app, @OMNICmapMenuSelected, true);
            app.OMNICmapMenu.Text = 'OMNIC .map';
            app.fsmMenu = uimenu(app.FTIRMenu);
            app.fsmMenu.MenuSelectedFcn = createCallbackFcn(app, @fsmMenuSelected, true);
            app.fsmMenu.Text = '.fsm';
            app.RAMANMenu = uimenu(app.AutoImportMenu);
            app.RAMANMenu.Text = 'RAMAN';
            app.RAMANGUMKSMenu = uimenu(app.RAMANMenu);
            app.RAMANGUMKSMenu.MenuSelectedFcn = createCallbackFcn(app, @Raman_MKSMenuSelected, true);
            app.RAMANGUMKSMenu.Text = 'RAMAN GU MKS';
            app.RAMAN1Menu = uimenu(app.RAMANMenu);
            app.RAMAN1Menu.MenuSelectedFcn = createCallbackFcn(app, @Raman_1MenuSelected, true);
            app.RAMAN1Menu.Text = 'RAMAN 1';
            app.RAMAN2Menu = uimenu(app.RAMANMenu);
            app.RAMAN2Menu.MenuSelectedFcn = createCallbackFcn(app, @RAMAN_2MenuSelected, true);
            app.RAMAN2Menu.Text = 'RAMAN 2';
            app.HyperDC_GTKMenu = uimenu(app.AutoImportMenu);
            app.HyperDC_GTKMenu.MenuSelectedFcn = createCallbackFcn(app, @HyperDC_GTKMenuSelected, true);
            app.HyperDC_GTKMenu.Text = 'HyperDC_GTK';
            app.ImportMapdataMenu = uimenu(app.ImportMenu);
            app.ImportMapdataMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMapdataButtonPushed, true);
            app.ImportMapdataMenu.Text = 'Import Mapdata';
            app.ImportMetadataMenu = uimenu(app.ImportMenu);
            app.ImportMetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMetadataButtonPushed, true);
            app.ImportMetadataMenu.Text = 'Import Metadata';
            app.ImportImageMenu = uimenu(app.ImportMenu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.EditDataMenu = uimenu(app.SpecMapsUIFigure);
            app.EditDataMenu.Text = 'Edit Data';
            app.SetXYRatioMenu = uimenu(app.EditDataMenu);
            app.SetXYRatioMenu.MenuSelectedFcn = createCallbackFcn(app, @SetXYRatioMenuSelected, true);
            app.SetXYRatioMenu.Text = 'Set XY Ratio';
            app.EditSpectraMenu = uimenu(app.EditDataMenu);
            app.EditSpectraMenu.Text = 'Edit Spectra';
            app.DataSet2NewEntryMenu = uimenu(app.EditSpectraMenu);
            app.DataSet2NewEntryMenu.MenuSelectedFcn = createCallbackFcn(app, @DataSet2NewEntryMenuSelected, true);
            app.DataSet2NewEntryMenu.Text = 'DataSet2NewEntry';
            app.InvertMenu = uimenu(app.EditSpectraMenu);
            app.InvertMenu.MenuSelectedFcn = createCallbackFcn(app, @InvertMenuSelected, true);
            app.InvertMenu.Text = 'Invert';
            app.RemContMenu = uimenu(app.EditSpectraMenu);
            app.RemContMenu.MenuSelectedFcn = createCallbackFcn(app, @RemContMenuSelected, true);
            app.RemContMenu.Text = 'RemCont';
            app.ApplyEdittoallSpectraMenu = uimenu(app.EditSpectraMenu);
            app.ApplyEdittoallSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyEdittoallSpectraMenuSelected, true);
            app.ApplyEdittoallSpectraMenu.Text = 'Apply Edit to all Spectra';
            app.RamanBaselineMenu = uimenu(app.EditSpectraMenu);
            app.RamanBaselineMenu.MenuSelectedFcn = createCallbackFcn(app, @RamanBaselineMenuSelected, true);
            app.RamanBaselineMenu.Text = 'RamanBaseline';
            app.DuplicateEntryMenu = uimenu(app.EditDataMenu);
            app.DuplicateEntryMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateEntryMenuSelected, true);
            app.DuplicateEntryMenu.Text = 'Duplicate Entry';
            app.ThicknessCallibrationMenu = uimenu(app.EditDataMenu);
            app.ThicknessCallibrationMenu.Text = 'Thickness Callibration';
            app.OpenThicknessCallibrationMenu = uimenu(app.ThicknessCallibrationMenu);
            app.OpenThicknessCallibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @ThicknessCallibrationMenuSelected, true);
            app.OpenThicknessCallibrationMenu.Text = 'Open Thickness Callibration';
            app.SetConstantThicknessMenu = uimenu(app.ThicknessCallibrationMenu);
            app.SetConstantThicknessMenu.MenuSelectedFcn = createCallbackFcn(app, @SetConstantThicknessMenuSelected, true);
            app.SetConstantThicknessMenu.Text = 'Set Constant Thickness';
            app.ResetThicknessMapMenu_2 = uimenu(app.ThicknessCallibrationMenu);
            app.ResetThicknessMapMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResetThicknessMapMenuSelected, true);
            app.ResetThicknessMapMenu_2.Text = 'Reset Thickness Map';
            app.ClassificationMenu = uimenu(app.EditDataMenu);
            app.ClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenClassificationModule, true);
            app.ClassificationMenu.Text = 'Classification';
            app.GenerateMapMenu = uimenu(app.EditDataMenu);
            app.GenerateMapMenu.Text = 'Generate Map';
            app.GenerateMultipleMapsMenu = uimenu(app.GenerateMapMenu);
            app.GenerateMultipleMapsMenu.Text = 'Generate Multiple Maps';
            app.PresetMapsforallSamplesMenu = uimenu(app.GenerateMultipleMapsMenu);
            app.PresetMapsforallSamplesMenu.MenuSelectedFcn = createCallbackFcn(app, @PresetMapsforallSamplesMenuSelected, true);
            app.PresetMapsforallSamplesMenu.Text = 'Preset Maps for all Samples';
            app.PresetMapsforselectedSamplesMenu = uimenu(app.GenerateMultipleMapsMenu);
            app.PresetMapsforselectedSamplesMenu.MenuSelectedFcn = createCallbackFcn(app, @PresetMapsforselectedSamplesMenuSelected, true);
            app.PresetMapsforselectedSamplesMenu.Text = 'Preset Maps for selected Samples';
            app.GenerateMapforallSamplesMenu_2 = uimenu(app.GenerateMultipleMapsMenu);
            app.GenerateMapforallSamplesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GenerateMapforallSamplesMenuSelected, true);
            app.GenerateMapforallSamplesMenu_2.Text = 'Generate Map for all Samples';
            app.GenerateMapforselectedSamplesMenu_2 = uimenu(app.GenerateMultipleMapsMenu);
            app.GenerateMapforselectedSamplesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GenerateMapforselectedSamplesMenuSelected, true);
            app.GenerateMapforselectedSamplesMenu_2.Text = 'Generate Map for selected Samples';
            app.AbsoluteValueMapMenu = uimenu(app.GenerateMapMenu);
            app.AbsoluteValueMapMenu.MenuSelectedFcn = createCallbackFcn(app, @AbsoluteValueMapMenuSelected, true);
            app.AbsoluteValueMapMenu.Text = 'Absolute Value Map';
            app.QuantFactorMapMenu = uimenu(app.GenerateMapMenu);
            app.QuantFactorMapMenu.MenuSelectedFcn = createCallbackFcn(app, @QuantFactorMapMenuSelected, true);
            app.QuantFactorMapMenu.Text = 'Quant Factor Map';
            app.QuantLinAbsMapFromSpectraMenu = uimenu(app.GenerateMapMenu);
            app.QuantLinAbsMapFromSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @QuantLinAbsMapFromSpectraMenuSelected, true);
            app.QuantLinAbsMapFromSpectraMenu.Text = 'Quant LinAbsMap (From Spectra)';
            app.QuantLinAbsMapFromMapMenu = uimenu(app.GenerateMapMenu);
            app.QuantLinAbsMapFromMapMenu.MenuSelectedFcn = createCallbackFcn(app, @QuantLinAbsMapFromMapMenuSelected, true);
            app.QuantLinAbsMapFromMapMenu.Text = 'Quant LinAbsMap (From Map)';
            app.NormMapFromMapMenu = uimenu(app.GenerateMapMenu);
            app.NormMapFromMapMenu.MenuSelectedFcn = createCallbackFcn(app, @NormMapFromMapMenuSelected, true);
            app.NormMapFromMapMenu.Text = 'NormMap (From Map)';
            app.NormMapFromSpectraMenu = uimenu(app.GenerateMapMenu);
            app.NormMapFromSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @NormMapFromSpectraMenuSelected, true);
            app.NormMapFromSpectraMenu.Text = 'NormMap (From Spectra)';
            app.QuantMapsMenu = uimenu(app.GenerateMapMenu);
            app.QuantMapsMenu.Text = 'QuantMaps';
            app.IntQuantMapwtH2OMenu = uimenu(app.QuantMapsMenu);
            app.IntQuantMapwtH2OMenu.MenuSelectedFcn = createCallbackFcn(app, @IntQuantMapwtH2OMenuSelected, true);
            app.IntQuantMapwtH2OMenu.Text = 'Int. QuantMap [wt.% H2O]';
            app.IntQuantMapggH2OMenu = uimenu(app.QuantMapsMenu);
            app.IntQuantMapggH2OMenu.MenuSelectedFcn = createCallbackFcn(app, @IntQuantMapggH2OMenuSelected, true);
            app.IntQuantMapggH2OMenu.Text = 'Int. QuantMap [μg/g H2O]';
            app.Paterson1982Menu = uimenu(app.QuantMapsMenu);
            app.Paterson1982Menu.MenuSelectedFcn = createCallbackFcn(app, @Paterson1982MenuSelected, true);
            app.Paterson1982Menu.Text = 'Paterson (1982)';
            app.LibowitzkyandRossman1997Menu = uimenu(app.QuantMapsMenu);
            app.LibowitzkyandRossman1997Menu.MenuSelectedFcn = createCallbackFcn(app, @LibowitzkyandRossman1997MenuSelected, true);
            app.LibowitzkyandRossman1997Menu.Text = 'Libowitzky and Rossman (1997)';
            app.Balanetal2008Menu = uimenu(app.QuantMapsMenu);
            app.Balanetal2008Menu.MenuSelectedFcn = createCallbackFcn(app, @Balanetal2008MenuSelected, true);
            app.Balanetal2008Menu.Text = 'Balan et al. (2008)';
            app.ModulesMenu = uimenu(app.SpecMapsUIFigure);
            app.ModulesMenu.Text = 'Modules';
            app.ThicknessCallibrationModuleMenu = uimenu(app.ModulesMenu);
            app.ThicknessCallibrationModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @ThicknessCallibrationMenuSelected, true);
            app.ThicknessCallibrationModuleMenu.Text = 'Thickness Callibration Module';
            app.ClassificationModuleMenu = uimenu(app.ModulesMenu);
            app.ClassificationModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenClassificationModule, true);
            app.ClassificationModuleMenu.Text = 'Classification Module';
            app.ImportMatchModuleMenu = uimenu(app.ModulesMenu);
            app.ImportMatchModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMatchMapMenuSelected, true);
            app.ImportMatchModuleMenu.Text = 'Import&Match Module';
            app.CorrelationModuleMenu = uimenu(app.ModulesMenu);
            app.CorrelationModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @CorrelationModuleMenuSelected, true);
            app.CorrelationModuleMenu.Text = 'Correlation Module';
            app.ExportModuleMenu = uimenu(app.ModulesMenu);
            app.ExportModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMapMenuSelected, true);
            app.ExportModuleMenu.Text = 'Export Module';
            app.GeoCPlotMenu = uimenu(app.ModulesMenu);
            app.GeoCPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @GeoCPlotMenuSelected, true);
            app.GeoCPlotMenu.Text = 'GeoCPlot';
            app.MapCalculatorMenu = uimenu(app.ModulesMenu);
            app.MapCalculatorMenu.MenuSelectedFcn = createCallbackFcn(app, @MapCalculatorDevMenuSelected, true);
            app.MapCalculatorMenu.Text = 'MapCalculator';
            app.SpecDBMenu = uimenu(app.ModulesMenu);
            app.SpecDBMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecDBMenuSelected, true);
            app.SpecDBMenu.Text = 'SpecDB';
            app.DevelopMenu = uimenu(app.SpecMapsUIFigure);
            app.DevelopMenu.Visible = 'off';
            app.DevelopMenu.Text = 'DevelopMenu';
            app.DebuggModeMenu_2 = uimenu(app.DevelopMenu);
            app.DebuggModeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DebuggModeMenu_2Selected, true);
            app.DebuggModeMenu_2.Text = 'DebuggMode';
            app.SubMapstoFigureMenu = uimenu(app.DevelopMenu);
            app.SubMapstoFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @SubMapstoFigureMenuSelected, true);
            app.SubMapstoFigureMenu.Text = 'SubMaps to Figure';
            app.ROIstoFigureMenu = uimenu(app.DevelopMenu);
            app.ROIstoFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIstoFigureMenuSelected, true);
            app.ROIstoFigureMenu.Text = 'ROIs to Figure';
            app.ClassestoFigureMenu = uimenu(app.DevelopMenu);
            app.ClassestoFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassestoFigureMenuSelected, true);
            app.ClassestoFigureMenu.Text = 'Classes to Figure';
            app.CompiledFigureMenu = uimenu(app.DevelopMenu);
            app.CompiledFigureMenu.Text = 'Compiled Figure';
            app.GenerateReportMenu = uimenu(app.DevelopMenu);
            app.GenerateReportMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateReportMenuSelected, true);
            app.GenerateReportMenu.Text = 'Generate Report';
            app.testbuttonsMenu = uimenu(app.DevelopMenu);
            app.testbuttonsMenu.Text = 'test buttons';
            app.SpecMaptoFigureMenu = uimenu(app.testbuttonsMenu);
            app.SpecMaptoFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecMaptoFigureMenuSelected, true);
            app.SpecMaptoFigureMenu.Text = 'SpecMap to Figure';
            app.ConvertProjecttoSingleprecisionMenu = uimenu(app.testbuttonsMenu);
            app.ConvertProjecttoSingleprecisionMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertProjecttoSingleprecisionMenuSelected, true);
            app.ConvertProjecttoSingleprecisionMenu.Text = 'Convert Project to Single-precision';
            app.RamanShiftXMenu = uimenu(app.DevelopMenu);
            app.RamanShiftXMenu.Text = 'RamanShift X';
            app.SetShiftMenu = uimenu(app.RamanShiftXMenu);
            app.SetShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @SetShiftMenuSelected, true);
            app.SetShiftMenu.Text = 'Set Shift';
            app.ResetShiftMenu = uimenu(app.RamanShiftXMenu);
            app.ResetShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetShiftMenuSelected, true);
            app.ResetShiftMenu.Text = 'Reset Shift';
            app.DPlotMenu = uimenu(app.DevelopMenu);
            app.DPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DPlotMenuSelected, true);
            app.DPlotMenu.Text = '3D Plot';
            app.GIFPlotMenu = uimenu(app.DevelopMenu);
            app.GIFPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @GIFPlotMenuSelected, true);
            app.GIFPlotMenu.Text = 'GIF Plot';
            app.PlotMultiMenu = uimenu(app.DevelopMenu);
            app.PlotMultiMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMultiMenuSelected, true);
            app.PlotMultiMenu.Text = 'PlotMulti';
            app.Add2MultiPlotMenu = uimenu(app.DevelopMenu);
            app.Add2MultiPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @Add2MultiPlotMenuSelected, true);
            app.Add2MultiPlotMenu.Text = 'Add2MultiPlot';
            app.ImportMapMenu = uimenu(app.DevelopMenu);
            app.ImportMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMapMenuSelected, true);
            app.ImportMapMenu.Text = 'ImportMap';
            app.GridLayout = uigridlayout(app.SpecMapsUIFigure);
            app.GridLayout.Tooltip = {''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.SpecMapPlotTab = uitab(app.TabGroup);
            app.SpecMapPlotTab.Title = 'SpecMapPlot';
            app.GridLayout11 = uigridlayout(app.SpecMapPlotTab);
            app.MapPlot = uiaxes(app.GridLayout11);
            xlabel(app.MapPlot, 'X')
            ylabel(app.MapPlot, 'Y')
            zlabel(app.MapPlot, 'Sample')
            app.SpectraPlot = uiaxes(app.GridLayout11);
            xlabel(app.SpectraPlot, 'Wavenumbers [cm^{-1}]')
            ylabel(app.SpectraPlot, 'Absorbance')
            zlabel(app.SpectraPlot, 'Sample')
            app.SpectraPlot.XLim = [500 4000];
            app.SpectraPlot.XDir = 'reverse';
            app.SpectraPlot.ButtonDownFcn = createCallbackFcn(app, @SpectraPlotButtonDown, true);
            app.TestButton1 = uibutton(app.GridLayout11, 'push');
            app.TestButton1.ButtonPushedFcn = createCallbackFcn(app, @TestButton1Pushed, true);
            app.TestButton1.Visible = 'off';
            app.TestButton1.Text = 'Test Button 1';
            app.SpectraTypeDropDownLabel = uilabel(app.GridLayout11);
            app.SpectraTypeDropDownLabel.Text = 'Spectra Type';
            app.SpectraTypeDropDown = uidropdown(app.GridLayout11);
            app.SpectraTypeDropDown.Items = {'FTIR-MCT', 'FTIR-FPA', 'Raman', 'HyperDC', 'other', 'other (reverse)'};
            app.SpectraTypeDropDown.ItemsData = {'100', '200', '300', '400', '500', '501'};
            app.SpectraTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectraTypeDropDownValueChanged, true);
            app.SpectraTypeDropDown.Value = '100';
            app.SpecPlotSetDropDownLabel = uilabel(app.GridLayout11);
            app.SpecPlotSetDropDownLabel.Text = 'SpecPlotSet';
            app.SpecPlotSetDropDown = uidropdown(app.GridLayout11);
            app.SpecPlotSetDropDown.Items = {'Default', 'Sum', 'Mean', 'ROI Mean', 'All ROIs Stacked', 'All ROI', 'All ROIs Mean'};
            app.SpecPlotSetDropDown.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.SpecPlotSetDropDown.Value = 'Default';
            app.plot_spectra_checkbox = uicheckbox(app.GridLayout11);
            app.plot_spectra_checkbox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.plot_spectra_checkbox.Text = ' Spectra';
            app.plot_spectra_checkbox.Value = true;
            app.nEditField = uieditfield(app.GridLayout11, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.nEditField.Value = 25;
            app.DerivativeSpinner = uispinner(app.GridLayout11);
            app.DerivativeSpinner.Limits = [-10 10];
            app.DerivativeSpinner.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.DerivativeSpinner.Value = -2;
            app.DerivativeSpinnerLabel = uilabel(app.GridLayout11);
            app.DerivativeSpinnerLabel.Text = '      Derivative';
            app.Derivative_CheckBox = uicheckbox(app.GridLayout11);
            app.Derivative_CheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Derivative_CheckBox.Text = '';
            app.MaskCheckBox = uicheckbox(app.GridLayout11);
            app.MaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.MaskCheckBox.Text = ' Mask';
            app.MaskCheckBox.Value = true;
            app.AdvancedPeakDeconButton = uibutton(app.GridLayout11, 'push');
            app.AdvancedPeakDeconButton.ButtonPushedFcn = createCallbackFcn(app, @ParforPeakDeconButtonPushed, true);
            app.AdvancedPeakDeconButton.Text = 'Advanced PeakDecon';
            app.SimpleMapDeconButton = uibutton(app.GridLayout11, 'push');
            app.SimpleMapDeconButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleMapDeconButtonPushed, true);
            app.SimpleMapDeconButton.Text = 'Simple Map Decon';
            app.ROICheckBox = uicheckbox(app.GridLayout11);
            app.ROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ROICheckBoxValueChanged, true);
            app.ROICheckBox.Text = 'ROI';
            app.ROICheckBox.Value = true;
            app.SingleMapPlotTab = uitab(app.TabGroup);
            app.SingleMapPlotTab.Title = 'Single Map Plot';
            app.GridLayout13 = uigridlayout(app.SingleMapPlotTab);
            app.MapPlot_2 = uiaxes(app.GridLayout13);
            xlabel(app.MapPlot_2, 'X')
            ylabel(app.MapPlot_2, 'Y')
            zlabel(app.MapPlot_2, 'Sample')
            app.MapPlot_2.Visible = 'off';
            app.MultiMapPlotsTab = uitab(app.TabGroup);
            app.MultiMapPlotsTab.Title = 'MultiMapPlots';
            app.GridLayout8 = uigridlayout(app.MultiMapPlotsTab);
            app.MapPlot_OL = uiaxes(app.GridLayout8);
            xlabel(app.MapPlot_OL, 'X')
            ylabel(app.MapPlot_OL, 'Y')
            zlabel(app.MapPlot_OL, 'Sample')
            app.MapPlot_OR = uiaxes(app.GridLayout8);
            xlabel(app.MapPlot_OR, 'X')
            ylabel(app.MapPlot_OR, 'Y')
            zlabel(app.MapPlot_OR, 'Sample')
            app.MapPlot_UL = uiaxes(app.GridLayout8);
            xlabel(app.MapPlot_UL, 'X')
            ylabel(app.MapPlot_UL, 'Y')
            zlabel(app.MapPlot_UL, 'Sample')
            app.MapPlot_UR = uiaxes(app.GridLayout8);
            xlabel(app.MapPlot_UR, 'X')
            ylabel(app.MapPlot_UR, 'Y')
            zlabel(app.MapPlot_UR, 'Sample')
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.SpectraTab = uitab(app.TabGroup2);
            app.SpectraTab.Title = 'Spectra';
            app.GridLayout6 = uigridlayout(app.SpectraTab);
            app.GridLayout6.Tooltip = {''};
            app.Maptable = uitable(app.GridLayout6);
            app.Maptable.ColumnName = {'Name'; 'DataPoints'; 'x'; 'y'; 'Thickness'; 'Sample'};
            app.Maptable.RowName = {};
            app.Maptable.ColumnEditable = [true false false false true];
            app.Maptable.Visible = 'off';
            app.TreeData_Main = uitree(app.GridLayout6);
            app.TreeData_Main.SelectionChangedFcn = createCallbackFcn(app, @TreeData_MainSelectionChanged, true);
            app.TreeData_Main.NodeTextChangedFcn = createCallbackFcn(app, @TreeData_MainNodeTextChanged, true);
            app.TreeData_Main.Editable = 'on';
            app.TreeData_Main.Tooltip = {''};
            app.AddSpectraButton = uibutton(app.GridLayout6, 'push');
            app.AddSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @AddMapdataButtonPushed, true);
            app.AddSpectraButton.Icon = 'symbols_1_Import Specs From Folder.png';
            app.AddSpectraButton.Visible = 'off';
            app.AddSpectraButton.Tooltip = {''};
            app.AddSpectraButton.Text = '';
            app.DeleteSpectraButton = uibutton(app.GridLayout6, 'push');
            app.DeleteSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEntryButtonPushed, true);
            app.DeleteSpectraButton.Icon = 'symbols_1_Delete Spec.png';
            app.DeleteSpectraButton.Tooltip = {'Delete Selected'};
            app.DeleteSpectraButton.Text = '';
            app.PeaksTab = uitab(app.TabGroup2);
            app.PeaksTab.Title = 'Peaks';
            app.GridLayout4 = uigridlayout(app.PeaksTab);
            app.DerivativeSpinnerLabel_2 = uilabel(app.GridLayout4);
            app.DerivativeSpinnerLabel_2.Text = '      Peaks';
            app.PeakTable = uitable(app.GridLayout4);
            app.PeakTable.ColumnName = {'PeakPosition'; 'PeakIntensity'; 'Width'; 'Prominence'; 'Spec'; 'Source'};
            app.PeakTable.RowName = {};
            app.PeakTable.ColumnSortable = [true true true true true true];
            app.PeakTable.ColumnEditable = [false false true true false false];
            app.AddSinglePeakButton = uibutton(app.GridLayout4, 'push');
            app.AddSinglePeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddSinglePeakButtonPushed, true);
            app.AddSinglePeakButton.Text = 'Add Single Peak';
            app.DeletePeakButton = uibutton(app.GridLayout4, 'push');
            app.DeletePeakButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePeakButtonPushed, true);
            app.DeletePeakButton.Text = 'Delete Peak';
            app.ClearPeaksButton = uibutton(app.GridLayout4, 'push');
            app.ClearPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPeaksButtonPushed, true);
            app.ClearPeaksButton.Text = 'Clear Peaks';
            app.TabGroup4 = uitabgroup(app.GridLayout4);
            app.AutoPeakDetectionTab = uitab(app.TabGroup4);
            app.AutoPeakDetectionTab.Title = 'AutoPeakDetection';
            app.GridLayout5 = uigridlayout(app.AutoPeakDetectionTab);
            app.AutoPeakDetectionButton = uibutton(app.GridLayout5, 'push');
            app.AutoPeakDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutoPeakDetectionButtonPushed, true);
            app.AutoPeakDetectionButton.Text = 'Auto Peak Detection';
            app.MinPeakHightSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakHightSpinnerLabel.Text = 'Min Peak Hight';
            app.MinPeakHightSpinner = uispinner(app.GridLayout5);
            app.MinPeakHightSpinner.Step = 0.1;
            app.MinPeakHightSpinner.Limits = [0 Inf];
            app.MinPeakPromenanceSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakPromenanceSpinnerLabel.Text = 'Min Peak Promenance';
            app.MinPeakPromenanceSpinner = uispinner(app.GridLayout5);
            app.MinPeakPromenanceSpinner.Step = 0.1;
            app.MinPeakPromenanceSpinner.Limits = [0 Inf];
            app.MinPeakDistanceSpinnerLabel = uilabel(app.GridLayout5);
            app.MinPeakDistanceSpinnerLabel.Text = 'Min Peak Distance';
            app.MinPeakDistanceSpinner = uispinner(app.GridLayout5);
            app.MinPeakDistanceSpinner.Limits = [0 Inf];
            app.DerivativeCheckBox = uicheckbox(app.GridLayout5);
            app.DerivativeCheckBox.Text = 'Derivative ';
            app.PointstoClassifySpinner = uispinner(app.GridLayout5);
            app.PointstoClassifySpinner.Limits = [1 Inf];
            app.PointstoClassifySpinner.RoundFractionalValues = 'on';
            app.PointstoClassifySpinner.Value = 5;
            app.PointstoClassifySpinnerLabel = uilabel(app.GridLayout5);
            app.PointstoClassifySpinnerLabel.Text = 'Max Nr. Peaks';
            app.PeakDeconvolutionTab = uitab(app.TabGroup4);
            app.PeakDeconvolutionTab.Title = 'PeakDeconvolution';
            app.GridLayout14 = uigridlayout(app.PeakDeconvolutionTab);
            app.MeanAutofitButton = uibutton(app.GridLayout14, 'push');
            app.MeanAutofitButton.ButtonPushedFcn = createCallbackFcn(app, @MeanAutofitButtonPushed, true);
            app.MeanAutofitButton.Text = 'Mean Autofit';
            app.PlotFigureCheckBox = uicheckbox(app.GridLayout14);
            app.PlotFigureCheckBox.Text = 'Plot Figure';
            app.BipolarCheckBox = uicheckbox(app.GridLayout14);
            app.BipolarCheckBox.Text = 'Bipolar';
            app.UIPeaksStartCheckBox = uicheckbox(app.GridLayout14);
            app.UIPeaksStartCheckBox.Text = 'UI Peaks Start';
            app.FitShapeDropDownLabel = uilabel(app.GridLayout14);
            app.FitShapeDropDownLabel.Text = 'Fit Shape';
            app.FitShapeDropDown = uidropdown(app.GridLayout14);
            app.FitShapeDropDown.Items = {'1=Gaussian (default)', '2=Lorentzian', '3=logistic distribution', '4=Pearson', '5=exponentionally broadened Gaussian;', '6=equal-width Gaussians', '7=Equal-width Lorentzians', '8=exponentionally broadened equal-width Gaussian', '9=exponential pulse', '10=up-sigmoid (logistic function)', '11=Fixed-width Gaussian', '12=Fixed- width Lorentzian;', '13=Gaussian/Lorentzian blend ', '14=Bifurcated Gaussian,', '15=Breit-Wigner-Fano', '16=Fixed-position Gaussians', '17=Fixed-position Lorentzians', '18=exponentionally broadened Lorentzian', '19=alpha function; ', '20=Voigt profile', '21=triangular', '22=?plus extra?', '23=down-sigmoid', '24=Negative Binomial Distribution', '25=lognormal', '26=slope;', '27=Gaussian first derivative', '28=polynomial', '29=piecewise linear;', '30=variable-alpha Voigt', '31=variable time constant ExpGaussian;', '32=variable shape Pearson', '33=variable Gaussian/Lorentzian blend,', '34=fixed-width Voigt', '35=fixed-width Gaussian/Lorentzian blend; ', '36=fixed-width exponentionally-broadened Gaussian', '37=fixed-width Pearson', '38=variable time constant ExpLorentzian', '39=variable timeconstant ExpGaussian2', '40=equal-width GL blend', '41=rectangle;', '42=flattened Gaussian', '43:PearsonIV function (3 variables);', '44=1-exp(-k*x)', '45 Four-parameter logistic', '46=quadratic/parabola;', '47=blackbody emission', '48=equal-width exponential pulses', '49=Pearson IV (variable asymmetrical peak)', '50=multilinear regression (known peak positions and widths)'};
            app.FitShapeDropDown.Value = '1=Gaussian (default)';
            app.IterationsSpinnerLabel = uilabel(app.GridLayout14);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.GridLayout14);
            app.IterationsSpinner.Limits = [0 Inf];
            app.MinPeaksSpinnerLabel = uilabel(app.GridLayout14);
            app.MinPeaksSpinnerLabel.Text = 'Min Peaks';
            app.MinPeaksSpinner = uispinner(app.GridLayout14);
            app.MinPeaksSpinner.Limits = [1 100];
            app.MinPeaksSpinner.Value = 1;
            app.AutozeroSpinnerLabel = uilabel(app.GridLayout14);
            app.AutozeroSpinnerLabel.Text = 'Autozero';
            app.AutozeroSpinner = uispinner(app.GridLayout14);
            app.AutozeroSpinner.Limits = [0 3];
            app.MaxPeaksSpinnerLabel = uilabel(app.GridLayout14);
            app.MaxPeaksSpinnerLabel.Text = 'Max Peaks';
            app.MaxPeaksSpinner = uispinner(app.GridLayout14);
            app.MaxPeaksSpinner.Limits = [1 100];
            app.MaxPeaksSpinner.Value = 7;
            app.MinWidthEditFieldLabel = uilabel(app.GridLayout14);
            app.MinWidthEditFieldLabel.Text = 'MinWidth';
            app.MinWidthEditField = uieditfield(app.GridLayout14, 'numeric');
            app.ExtraEditFieldLabel = uilabel(app.GridLayout14);
            app.ExtraEditFieldLabel.Text = 'Extra';
            app.ExtraEditField = uieditfield(app.GridLayout14, 'numeric');
            app.DELTAEditFieldLabel = uilabel(app.GridLayout14);
            app.DELTAEditFieldLabel.Text = 'DELTA';
            app.DELTAEditField = uieditfield(app.GridLayout14, 'numeric');
            app.DELTAEditField.Value = 1;
            app.LimitShiftCheckBox = uicheckbox(app.GridLayout14);
            app.LimitShiftCheckBox.Text = 'Limit Shift';
            app.ParalellDropDownLabel = uilabel(app.GridLayout14);
            app.ParalellDropDownLabel.Text = 'Paralell';
            app.ParalellDropDown = uidropdown(app.GridLayout14);
            app.ParalellDropDown.Items = {'parfor', 'parfevalOnAll', 'parfeval', 'no paralell'};
            app.ParalellDropDown.Value = 'parfeval';
            app.PeakPreviewSwitchLabel = uilabel(app.GridLayout4);
            app.PeakPreviewSwitchLabel.Text = 'Peak Preview';
            app.PeakPreviewSwitch = uiswitch(app.GridLayout4, 'slider');
            app.PeakPreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.PeakPreviewSwitch.Value = 'On';
            app.PeakDispDropDown = uidropdown(app.GridLayout4);
            app.PeakDispDropDown.Items = {'Default', 'Line', 'Xline'};
            app.PeakDispDropDown.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.PeakDispDropDown.Value = 'Default';
            app.AddPeaksButton = uibutton(app.GridLayout4, 'push');
            app.AddPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddPeaksButtonPushed, true);
            app.AddPeaksButton.Text = 'Add Peaks';
            app.PeakTree = uitree(app.GridLayout4, 'checkbox');
            app.PeakTree.Editable = 'on';
            app.PeakTree.Tooltip = {'Load SpecDB Project'};
            % Assign Checked Nodes
            app.PeakTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PeakTreeCheckedNodesChanged, true);
            app.SavePeaksButton = uibutton(app.GridLayout4, 'push');
            app.SavePeaksButton.Text = 'Save Peaks';
            app.Peaks_CheckBox = uicheckbox(app.GridLayout4);
            app.Peaks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Peaks_CheckBox.Text = '';
            app.Peaks_CheckBox.Value = true;
            app.LoadPeakDBButton = uibutton(app.GridLayout4, 'push');
            app.LoadPeakDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeakDBButtonPushed, true);
            app.LoadPeakDBButton.Text = 'LoadPeakDB';
            app.Plot_Limit_Settings = uitabgroup(app.GridLayout);
            app.XLimitTab = uitab(app.Plot_Limit_Settings);
            app.XLimitTab.Title = 'X Limit';
            app.GridLayout_PlotSettings = uigridlayout(app.XLimitTab);
            app.FigXlimLive = uiaxes(app.GridLayout_PlotSettings);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.EditField_xlimMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.xlimMax_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+10';
            app.xlimMax_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '- 1';
            app.xlimMin_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '- 1';
            app.xlimMin_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+10';
            app.xlimMin_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+ 1';
            app.xlimMax_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+ 1';
            app.xlimMax_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-10';
            app.ResetButton_2 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Tooltip = {'Reset View'};
            app.ResetButton_2.Text = 'Reset';
            app.xlimMin_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-10';
            app.CLimitTab = uitab(app.Plot_Limit_Settings);
            app.CLimitTab.Title = 'C Limit';
            app.GridLayout_PlotSettings_2 = uigridlayout(app.CLimitTab);
            app.FigHistLive = uiaxes(app.GridLayout_PlotSettings_2);
            app.FigHistLive.ButtonDownFcn = createCallbackFcn(app, @FigHistLiveButtonDown, true);
            app.EditField_climMin = uieditfield(app.GridLayout_PlotSettings_2, 'numeric');
            app.EditField_climMin.ValueDisplayFormat = '%3.4g';
            app.EditField_climMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveCMinMaxValueChanged, true);
            app.climMin_m10 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMin_m10.ButtonPushedFcn = createCallbackFcn(app, @climMin_m10ButtonPushed, true);
            app.climMin_m10.Text = '-10';
            app.cimMin_p1 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.cimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @cimMin_p1ButtonPushed, true);
            app.cimMin_p1.Text = '+ 1';
            app.EditField_climMax = uieditfield(app.GridLayout_PlotSettings_2, 'numeric');
            app.EditField_climMax.ValueDisplayFormat = '%3.4g';
            app.EditField_climMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveCMinMaxValueChanged, true);
            app.EditField_climMax.Value = 1;
            app.climMax_m10 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMax_m10.ButtonPushedFcn = createCallbackFcn(app, @climMax_m10ButtonPushed, true);
            app.climMax_m10.Text = '-10';
            app.climMax_p1 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMax_p1.ButtonPushedFcn = createCallbackFcn(app, @climMax_p1ButtonPushed, true);
            app.climMax_p1.Text = '+ 1';
            app.climMin_p10 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMin_p10.ButtonPushedFcn = createCallbackFcn(app, @climMin_p10ButtonPushed, true);
            app.climMin_p10.Text = '+10';
            app.climMin_m1 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMin_m1.ButtonPushedFcn = createCallbackFcn(app, @climMin_m1ButtonPushed, true);
            app.climMin_m1.Text = '- 1';
            app.climMax_p10 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMax_p10.ButtonPushedFcn = createCallbackFcn(app, @climMax_p10ButtonPushed, true);
            app.climMax_p10.Text = '+10';
            app.climMax_m1 = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.climMax_m1.ButtonPushedFcn = createCallbackFcn(app, @climMax_m1ButtonPushed, true);
            app.climMax_m1.Text = '- 1';
            app.GenerateMaskButton = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.GenerateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMaskMenuSelected, true);
            app.GenerateMaskButton.Icon = 'symbols_1_Mask Selection.png';
            app.GenerateMaskButton.Tooltip = {'Create New Mask'};
            app.GenerateMaskButton.Text = '';
            app.ResetButton = uibutton(app.GridLayout_PlotSettings_2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskMenuSelected, true);
            app.ResetButton.Icon = 'symbols_1_Clear Mask.png';
            app.ResetButton.Tooltip = {'Reset Mask'};
            app.ResetButton.Text = '';
            app.UnmaskSelection = uiimage(app.GridLayout_PlotSettings_2);
            app.UnmaskSelection.ImageClickedFcn = createCallbackFcn(app, @AddtoMaskMenuSelected, true);
            app.UnmaskSelection.Tooltip = {'Add to Mask'};
            app.UnmaskSelection.ImageSource = 'symbols_1_Substract from Mask.png';
            app.InvertMask = uiimage(app.GridLayout_PlotSettings_2);
            app.InvertMask.ImageClickedFcn = createCallbackFcn(app, @InvertMaskMenuSelected, true);
            app.InvertMask.Tooltip = {'Invert Mask'};
            app.InvertMask.ImageSource = 'symbols_1_Invert Mask.png';
            app.DataSettingsTab = uitab(app.Plot_Limit_Settings);
            app.DataSettingsTab.Title = 'Data Settings';
            app.GridLayout12 = uigridlayout(app.DataSettingsTab);
            app.FilterCheckBox = uicheckbox(app.GridLayout12);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.FilterCheckBox.Text = '';
            app.FilterSpan = uispinner(app.GridLayout12);
            app.FilterSpan.Limits = [1 10000];
            app.FilterSpan.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.FilterSpan.Tooltip = {'Window'};
            app.FilterSpan.Value = 8;
            app.BackgroundCheckBox = uicheckbox(app.GridLayout12);
            app.BackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.BackgroundCheckBox.Text = 'Background';
            app.FilterMethod = uidropdown(app.GridLayout12);
            app.FilterMethod.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess ', 'rloess', 'sgolay'};
            app.FilterMethod.ItemsData = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.FilterMethod.ValueChangedFcn = createCallbackFcn(app, @FilterMethodValueChanged, true);
            app.FilterMethod.Value = 'movmean';
            app.FilterIterationSpinner = uispinner(app.GridLayout12);
            app.FilterIterationSpinner.Limits = [1 10000];
            app.FilterIterationSpinner.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.FilterIterationSpinner.Tooltip = {'Number of iterations'};
            app.FilterIterationSpinner.Value = 1;
            app.FilterDegree = uispinner(app.GridLayout12);
            app.FilterDegree.Limits = [1 10000];
            app.FilterDegree.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.FilterDegree.Editable = 'off';
            app.FilterDegree.Tooltip = {'Degree'};
            app.FilterDegree.Value = 1;
            app.BackgroundMethodDropDown = uidropdown(app.GridLayout12);
            app.BackgroundMethodDropDown.Items = {'Rubberband', 'Rubberband boundary (slow but better)', 'msbackadj', 'interp1', 'Polynominal', 'linear', 'linCorrect2', 'linCorrect', 'linear2', 'Auto Raman Baseline'};
            app.BackgroundMethodDropDown.ItemsData = {'Rubberband', 'Rubberband boundary (slow but better)', 'msbackadj', 'interp1', 'Polynominal', 'linear', 'linCorrect2', 'linCorrect', 'linear2', 'Auto Raman Baseline'};
            app.BackgroundMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundMethodDropDownValueChanged, true);
            app.BackgroundMethodDropDown.Value = 'Rubberband';
            app.BackgroundDegreeSpinner = uispinner(app.GridLayout12);
            app.BackgroundDegreeSpinner.Limits = [0 1];
            app.BackgroundDegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.BackgroundDegreeSpinner.Tooltip = {'Degree'};
            app.BackgroundDegreeSpinner.Value = 0.1;
            app.BackgroundMethodDropDown_2 = uidropdown(app.GridLayout12);
            app.BackgroundMethodDropDown_2.Items = {'linear', 'nearest', 'next', 'previous', 'pchip', 'cubic', 'v5cubic', 'makima', 'spline'};
            app.BackgroundMethodDropDown_2.ItemsData = {'linear', 'nearest', 'next', 'previous', 'pchip', 'cubic', 'v5cubic', 'makima', 'spline'};
            app.BackgroundMethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.BackgroundMethodDropDown_2.Value = 'linear';
            app.ApplytoallSpectraButton = uibutton(app.GridLayout12, 'push');
            app.ApplytoallSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallSpectraButtonPushed, true);
            app.ApplytoallSpectraButton.Text = 'Apply to all Spectra';
            app.ResetEditsButton = uibutton(app.GridLayout12, 'push');
            app.ResetEditsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEditsButtonPushed, true);
            app.ResetEditsButton.Text = 'Reset Edits';
            app.IgnoreCO2CheckBox = uicheckbox(app.GridLayout12);
            app.IgnoreCO2CheckBox.Text = 'Ignore CO2';
            app.BCvalueSpinner_2 = uispinner(app.GridLayout12);
            app.BCvalueSpinner_2.Limits = [1 10000];
            app.BCvalueSpinner_2.Tooltip = {'BC Value'};
            app.BCvalueSpinner_2.Value = 1;
            app.SubstractDeconButton = uibutton(app.GridLayout12, 'push');
            app.SubstractDeconButton.ButtonPushedFcn = createCallbackFcn(app, @SubstractDeconButtonPushed, true);
            app.SubstractDeconButton.Visible = 'off';
            app.SubstractDeconButton.Text = 'Substract Decon';
            app.ShowOriginalCheckBox = uicheckbox(app.GridLayout12);
            app.ShowOriginalCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.ShowOriginalCheckBox.Text = 'Show Original';
            app.ClassesTab = uitab(app.Plot_Limit_Settings);
            app.ClassesTab.Title = 'Classes';
            app.GridLayout15 = uigridlayout(app.ClassesTab);
            app.ClassTree = uitree(app.GridLayout15, 'checkbox');
            app.ClassTree.SelectionChangedFcn = createCallbackFcn(app, @ClassTreeSelectionChanged, true);
            app.ClassTree.NodeTextChangedFcn = createCallbackFcn(app, @ClassTreeNodeTextChanged, true);
            app.ClassTree.Editable = 'on';
            app.ClassTree.Visible = 'off';
            app.ClearAllButton = uibutton(app.GridLayout15, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Visible = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.DeleteButton = uibutton(app.GridLayout15, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Visible = 'off';
            app.DeleteButton.Text = 'Delete';
            app.ClassificationModuleButton = uibutton(app.GridLayout15, 'push');
            app.ClassificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @OpenClassificationModule, true);
            app.ClassificationModuleButton.Text = 'Classification Module';
            app.MaskSelectedButton = uibutton(app.GridLayout15, 'push');
            app.MaskSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @MaskSelectedButtonPushed, true);
            app.MaskSelectedButton.Visible = 'off';
            app.MaskSelectedButton.Text = 'Mask Selected';
            app.CorrelationModuleButton = uibutton(app.GridLayout15, 'push');
            app.CorrelationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationModuleMenuSelected, true);
            app.CorrelationModuleButton.Visible = 'off';
            app.CorrelationModuleButton.Text = 'Correlation Module';
            app.SaveSpectraButton = uibutton(app.GridLayout15, 'push');
            app.SaveSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoDatabaseModuleMenuSelected, true);
            app.SaveSpectraButton.Tooltip = {'Sending all spectra of the selected classification to SpecDB'};
            app.SaveSpectraButton.Text = 'Save Spectra';
            app.GeoCPlotButton = uibutton(app.GridLayout15, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButtonPushed, true);
            app.GeoCPlotButton.Text = 'GeoCPlot';
            app.SaveMeanSpectraButton_2 = uibutton(app.GridLayout15, 'push');
            app.SaveMeanSpectraButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveMeanSpectraButton_2Pushed, true);
            app.SaveMeanSpectraButton_2.Tooltip = {'Sending all mean spectra of the selected classification to SpecDB'};
            app.SaveMeanSpectraButton_2.Text = 'Save Mean Spectra';
            app.SampleInfoTab = uitab(app.Plot_Limit_Settings);
            app.SampleInfoTab.Title = 'Sample Info';
            app.GridLayout19 = uigridlayout(app.SampleInfoTab);
            app.SampleEditFieldLabel = uilabel(app.GridLayout19);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.GridLayout19, 'text');
            app.SpectraNameEditFieldLabel = uilabel(app.GridLayout19);
            app.SpectraNameEditFieldLabel.Text = 'SpectraName';
            app.SpectraNameEditField = uieditfield(app.GridLayout19, 'text');
            app.OrientationEditFieldLabel = uilabel(app.GridLayout19);
            app.OrientationEditFieldLabel.Text = 'Orientation';
            app.OrientationEditField = uieditfield(app.GridLayout19, 'text');
            app.PolarisationEditFieldLabel = uilabel(app.GridLayout19);
            app.PolarisationEditFieldLabel.Text = 'Polarisation';
            app.InfraredRadiationEditField = uieditfield(app.GridLayout19, 'text');
            app.MineralEditFieldLabel = uilabel(app.GridLayout19);
            app.MineralEditFieldLabel.Text = 'Mineral';
            app.MineralEditField = uieditfield(app.GridLayout19, 'text');
            app.ColormapTab = uitab(app.Plot_Limit_Settings);
            app.ColormapTab.Title = 'Colormap';
            app.GridLayout_2 = uigridlayout(app.ColormapTab);
            app.DisplayValuesOutsideData = uicheckbox(app.GridLayout_2);
            app.DisplayValuesOutsideData.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);

            app.DisplayValuesOutsideData.Text = 'Display Values Outside Data Range (No C Limit Update)';
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_LowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_LowerCheckBox.Value = true;
            app.Options_LowerColor = uidropdown(app.GridLayout_2);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_UpperCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout_2);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout_2);
            app.Options_LogColormap.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel_2 = uilabel(app.GridLayout_2);
            app.ColormapDropDownLabel_2.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout_2);
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout_2);
            app.Options_InvertColormap.ValueChangedFcn = createCallbackFcn(app, @Options_InvertColormapValueChanged, true);
            app.Options_InvertColormap.Text = 'Invert';
            app.GridLayout8_2 = uigridlayout(app.GridLayout);
            app.TabGroup_2 = uitabgroup(app.GridLayout8_2);
            app.InformationTab = uitab(app.TabGroup_2);
            app.InformationTab.Title = 'Information';
            app.GridLayout9 = uigridlayout(app.InformationTab);
            app.MapInfo_TextArea = uitextarea(app.GridLayout9);
            app.MapInfo_TextArea.Editable = 'off';
            app.SamplingTab = uitab(app.TabGroup_2);
            app.SamplingTab.Title = 'Sampling';
            app.GridLayout9_2 = uigridlayout(app.SamplingTab);
            app.Sampling_Plot1 = uiaxes(app.GridLayout9_2);
            app.Sampling_Plot2 = uiaxes(app.GridLayout9_2);
            app.MapROISpinnerLabel = uilabel(app.GridLayout9_2);
            app.MapROISpinnerLabel.Visible = 'off';
            app.MapROISpinnerLabel.Text = 'Map ROI';
            app.MapROISpinner = uispinner(app.GridLayout9_2);
            app.MapROISpinner.Limits = [1 Inf];
            app.MapROISpinner.ValueChangedFcn = createCallbackFcn(app, @MapROISpinnerValueChanged, true);
            app.MapROISpinner.Visible = 'off';
            app.MapROISpinner.Value = 1;
            app.SaveROIMeanSpectraButton = uibutton(app.GridLayout9_2, 'push');
            app.SaveROIMeanSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIMeanSpectraButtonPushed, true);
            app.SaveROIMeanSpectraButton.Tooltip = {'Sending the mean spectra for all ROIs to SpecDB'};
            app.SaveROIMeanSpectraButton.Text = 'Save ROI Mean Spectra';
            app.Sampling_ExportButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Sampling_ExportButtonPushed, true);
            app.Sampling_ExportButton.Icon = 'XXX_images.png';
            app.Sampling_ExportButton.Tooltip = {'Export/edit figure'};
            app.Sampling_ExportButton.Text = '';
            app.Sampling_ResetButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Menu_Sampling_ResetROIMenuSelected, true);
            app.Sampling_ResetButton.Icon = 'XXX_closeMini.png';
            app.Sampling_ResetButton.Tooltip = {'Reset ROI'};
            app.Sampling_ResetButton.Text = '';
            app.Sampling_SelectCircleButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_SelectCircleButton.ButtonPushedFcn = createCallbackFcn(app, @Sampling_SelectCircleButtonPushed, true);
            app.Sampling_SelectCircleButton.Icon = 'XXX_Shaded_Circle.png';
            app.Sampling_SelectCircleButton.Tooltip = {'Sampling: Circle'};
            app.Sampling_SelectCircleButton.Text = '';
            app.Sampling_SelectAreaButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_SelectAreaButton.ButtonPushedFcn = createCallbackFcn(app, @Sampling_SelectAreaButtonPushed, true);
            app.Sampling_SelectAreaButton.Icon = 'XXX_Shaded_Square.png';
            app.Sampling_SelectAreaButton.Tooltip = {'Sampling: Polygon Free Shape'};
            app.Sampling_SelectAreaButton.Text = '';
            app.Sampling_SelectTransectButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_SelectTransectButton.ButtonPushedFcn = createCallbackFcn(app, @Sampling_SelectTransectButtonPushed, true);
            app.Sampling_SelectTransectButton.Icon = 'XXX_Share.png';
            app.Sampling_SelectTransectButton.Tooltip = {'Sampling: Transect (Path)'};
            app.Sampling_SelectTransectButton.Text = '';
            app.Sampling_SelectStripeButton = uibutton(app.GridLayout9_2, 'push');
            app.Sampling_SelectStripeButton.ButtonPushedFcn = createCallbackFcn(app, @Sampling_SelectStripeButtonPushed, true);
            app.Sampling_SelectStripeButton.Icon = 'XXX_FocusHorizontal.png';
            app.Sampling_SelectStripeButton.Tooltip = {'Sampling: Stripe (Rectangle-shape)'};
            app.Sampling_SelectStripeButton.Text = '';
            app.FullROIExportButton = uibutton(app.GridLayout9_2, 'push');
            app.FullROIExportButton.ButtonPushedFcn = createCallbackFcn(app, @FullROIExportButtonPushed, true);
            app.FullROIExportButton.Tooltip = {'Exporting all information for all ROIs to the selected folder'};
            app.FullROIExportButton.Text = 'Full ROI Export';
            app.PxSizeXYEditFieldLabel = uilabel(app.GridLayout8_2);
            app.PxSizeXYEditFieldLabel.Text = 'Px Size[XY]';
            app.PxSizeXYEditField = uieditfield(app.GridLayout8_2, 'text');
            app.PxSizeXYEditField.Editable = 'off';
            app.PxSizeXYEditField.Value = ' ';
            app.MeanThicknessEditFieldLabel = uilabel(app.GridLayout8_2);
            app.MeanThicknessEditFieldLabel.Text = 'Mean Thickness';
            app.MeanThicknessEditField = uieditfield(app.GridLayout8_2, 'text');
            app.MeanThicknessEditField.Editable = 'off';
            app.ThicknessCorrectionCheckBox = uicheckbox(app.GridLayout8_2);
            app.ThicknessCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.ThicknessCorrectionCheckBox.Text = 'Thickness Correction';
            app.ThicknessCorrectionCheckBox.Value = true;
            app.MapGenDropDownLabel = uilabel(app.GridLayout8_2);
            app.MapGenDropDownLabel.Text = 'MapGen';
            app.MapGenDropDown = uidropdown(app.GridLayout8_2);
            app.MapGenDropDown.Items = {'Integration', 'Absolute', 'Ratio', 'Ratio Norm', 'Ratio log/log', 'Ratio NormLog', 'Entropy'};
            app.MapGenDropDown.ValueChangedFcn = createCallbackFcn(app, @MapGenDropDownValueChanged, true);
            app.MapGenDropDown.Value = 'Integration';
            app.IntRangeLabel = uilabel(app.GridLayout8_2);
            app.IntRangeLabel.Text = 'Int. Range';
            app.Int_range_min = uieditfield(app.GridLayout8_2, 'numeric');
            app.Int_range_min.Value = 3050;
            app.Int_range_max = uieditfield(app.GridLayout8_2, 'numeric');
            app.Int_range_max.Value = 3720;
            app.GenerateMapButton = uibutton(app.GridLayout8_2, 'push');
            app.GenerateMapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMapButtonPushed, true);
            app.GenerateMapButton.Text = 'GenerateMap';
            app.UpdateInfoButton = uibutton(app.GridLayout8_2, 'push');
            app.UpdateInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateInfoButtonPushed, true);
            app.UpdateInfoButton.Text = 'Update Info';
            app.AutoImport_Button = uibutton(app.GridLayout, 'push');
            app.AutoImport_Button.ButtonPushedFcn = createCallbackFcn(app, @AutoImportButtonPushed, true);
            app.AutoImport_Button.Icon = 'symbols_1_Import Specs From Folder.png';
            app.AutoImport_Button.Tooltip = {'Load Spectra From Folder'};
            app.AutoImport_Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.Button_2.Icon = 'symbols_1_Save.png';
            app.Button_2.Tooltip = {'Save Project'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
            app.Button_3.Icon = 'symbols_1_Save As.png';
            app.Button_3.Tooltip = {'Save Project As'};
            app.Button_3.Text = '';
            app.ImageDropDown = uidropdown(app.GridLayout);
            app.ImageDropDown.Items = {};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Visible = 'off';
            app.ImageDropDown.Value = {};
            app.plot_image_checkbox = uicheckbox(app.GridLayout);
            app.plot_image_checkbox.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.plot_image_checkbox.Visible = 'off';
            app.plot_image_checkbox.Text = '';
            app.AlphaSlider = uislider(app.GridLayout);
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @MapSettingsChanged, true);
            app.AlphaSlider.Visible = 'off';
            app.AlphaSlider.Value = 100;
            app.LoadProject_Button = uibutton(app.GridLayout, 'push');
            app.LoadProject_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProject_Button.Icon = 'symbols_1_Load Project.png';
            app.LoadProject_Button.Tooltip = {'Load SpecMaps Project'};
            app.LoadProject_Button.Text = '';
            app.ContextMenu = uicontextmenu(app.SpecMapsUIFigure);
            app.SavetoDatabaseModuleMenu = uimenu(app.ContextMenu);
            app.SavetoDatabaseModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoDatabaseModuleMenuSelected, true);
            app.SavetoDatabaseModuleMenu.Text = 'Save to Database Module';
            % Assign app.ContextMenu
            app.SpectraPlot.ContextMenu = app.ContextMenu;
            app.ThicknessContextMenu = uicontextmenu(app.SpecMapsUIFigure);
            app.SetConstantThicknessMenu_2 = uimenu(app.ThicknessContextMenu);
            app.SetConstantThicknessMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SetConstantThicknessMenuSelected, true);
            app.SetConstantThicknessMenu_2.Text = 'Set Constant Thickness';
            app.OpenThicknessCallibrationMenu_2 = uimenu(app.ThicknessContextMenu);
            app.OpenThicknessCallibrationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ThicknessCallibrationMenuSelected, true);
            app.OpenThicknessCallibrationMenu_2.Text = 'Open Thickness Callibration';
            % Assign app.ThicknessContextMenu
            app.MeanThicknessEditField.ContextMenu = app.ThicknessContextMenu;
            app.ContextMenu_Main_Tree = uicontextmenu(app.SpecMapsUIFigure);
            app.RenameMenu = uimenu(app.ContextMenu_Main_Tree);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            % Assign app.ContextMenu_Main_Tree
            app.TreeData_Main.ContextMenu = app.ContextMenu_Main_Tree;
            % Show the figure after all components are created
            app.SpecMapsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecMaps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpecMapsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecMapsUIFigure)
        end
    end
end