classdef SpecVis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecVisGUI                      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SpectraPlotTab                  matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        SpectraPlot                     matlab.ui.control.UIAxes
        FindBestR2Button                matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        SpectraListBox                  matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        SpectraTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout12_2                  matlab.ui.container.GridLayout
        BCvalueSpinner_2                matlab.ui.control.Spinner
        IgnoreCO2CheckBox               matlab.ui.control.CheckBox
        PreviewButton                   matlab.ui.control.Button
        ResetAllEditsButton_2           matlab.ui.control.Button
        ResampleCheckBox                matlab.ui.control.CheckBox
        DegreeSpinner_2                 matlab.ui.control.Spinner
        ThreshholdSpinner_2             matlab.ui.control.Spinner
        SamplingDistanceSpinner_2       matlab.ui.control.Spinner
        ApplytoSelectedSpectraButton    matlab.ui.control.Button
        ResetEditsButton                matlab.ui.control.Button
        ApplytoallSpectraButton         matlab.ui.control.Button
        BackgroundMethodDropDown_2      matlab.ui.control.DropDown
        BackgroundDegreeSpinner         matlab.ui.control.Spinner
        BackgroundMethodDropDown        matlab.ui.control.DropDown
        FilterDegree_2                  matlab.ui.control.Spinner
        FilterIterationSpinner          matlab.ui.control.Spinner
        FilterMethod_2                  matlab.ui.control.DropDown
        BackgroundCheckBox              matlab.ui.control.CheckBox
        FilterSpan_2                    matlab.ui.control.Spinner
        FilterCheckBox                  matlab.ui.control.CheckBox
        Spec_table                      matlab.ui.control.Table
        PeaksTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ResetShiftButton                matlab.ui.control.Button
        FullTableCheckBox               matlab.ui.control.CheckBox
        LoadPeakDBButton                matlab.ui.control.Button
        DerivativeSpinnerLabel_7        matlab.ui.control.Label
        Peaks_CheckBox_2                matlab.ui.control.CheckBox
        SavePeaksButton                 matlab.ui.control.Button
        PeakTree                        matlab.ui.container.CheckBoxTree
        AddPeaksButton                  matlab.ui.control.Button
        PeakDispDropDown                matlab.ui.control.DropDown
        PeakPreviewSwitch               matlab.ui.control.Switch
        PeakPreviewSwitchLabel          matlab.ui.control.Label
        ClearPeaksButton                matlab.ui.control.Button
        DeletePeakButton                matlab.ui.control.Button
        AddSinglePeakButton             matlab.ui.control.Button
        PeakTable                       matlab.ui.control.Table
        PlotSettingsTab_2               matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        LegendfromFieldButton           matlab.ui.control.Button
        AnnotationDropDown_2            matlab.ui.control.DropDown
        NexttliePlotButton              matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        MoveupButton                    matlab.ui.control.Button
        FlipOrderButton                 matlab.ui.control.Button
        SelectedSampleListBox           matlab.ui.control.ListBox
        ResettoDefaultButton            matlab.ui.control.Button
        PlotSettingsTable               matlab.ui.control.Table
        PopUpPlotButton                 matlab.ui.control.Button
        Plot_Exp_Set_Panel              matlab.ui.container.TabGroup
        PlotSettingsTab                 matlab.ui.container.Tab
        GridLayout_PlotSettings         matlab.ui.container.GridLayout
        AutoYCheckBox                   matlab.ui.control.CheckBox
        Decon_CheckBox                  matlab.ui.control.CheckBox
        FontSizeSpinner                 matlab.ui.control.Spinner
        FontSizeSpinnerLabel            matlab.ui.control.Label
        EditField_ylimMax               matlab.ui.control.NumericEditField
        EditField_ylimMin               matlab.ui.control.NumericEditField
        AnnotationDropDown              matlab.ui.control.DropDown
        YOffsetSpinner                  matlab.ui.control.Spinner
        YOffsetSpinnerLabel             matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        TypeDropDown                    matlab.ui.control.DropDown
        xlimMin_p10                     matlab.ui.control.Button
        xlimMin_p1                      matlab.ui.control.Button
        xlimMin_m1                      matlab.ui.control.Button
        xlimMin_m10                     matlab.ui.control.Button
        xlimMax_m10                     matlab.ui.control.Button
        xlimMax_m1                      matlab.ui.control.Button
        xlimMax_p10                     matlab.ui.control.Button
        xlimMax_p1                      matlab.ui.control.Button
        EditField_xlimMax               matlab.ui.control.NumericEditField
        EditField_xlimMin               matlab.ui.control.NumericEditField
        LegendCheckBox                  matlab.ui.control.CheckBox
        Annotation_CheckBox             matlab.ui.control.CheckBox
        Peaks_CheckBox                  matlab.ui.control.CheckBox
        FigXlimLive                     matlab.ui.control.UIAxes
        AdvancedTab                     matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        ZLabelEditField                 matlab.ui.control.EditField
        YLabelEditField_2Label          matlab.ui.control.Label
        YLabelEditField                 matlab.ui.control.EditField
        XLabelEditField_2Label          matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        AnnotationInterpreterDropDown   matlab.ui.control.DropDown
        AnnotationInterpreterDropDownLabel  matlab.ui.control.Label
        AnnotationFontSizeSpinner       matlab.ui.control.Spinner
        AnnotationFontSizeSpinnerLabel  matlab.ui.control.Label
        PeakLableFontSizeSpinner        matlab.ui.control.Spinner
        PeakLableFontSizeSpinnerLabel   matlab.ui.control.Label
        FigureFontSizeSpinner           matlab.ui.control.Spinner
        FigureFontSizeSpinnerLabel      matlab.ui.control.Label
        PeakLineColorCheckBox           matlab.ui.control.CheckBox
        UpdateButton                    matlab.ui.control.Button
        UseAdvancedSettingsCheckBox     matlab.ui.control.CheckBox
        ExportTab                       matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        CheckBox_jpg_exp                matlab.ui.control.CheckBox
        CustStrEditField                matlab.ui.control.EditField
        CustomStrEditFieldLabel         matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        CheckBox_eps_exp                matlab.ui.control.CheckBox
        CheckBox_pdf_exp                matlab.ui.control.CheckBox
        CheckBox_png_exp                matlab.ui.control.CheckBox
        SelectDirButton                 matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CheckBox_fig_exp                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        SpecDB
        selected_sample

        SpecVis_data=struct() % Description

        Peaks

        plotmat
        allspecmat
        peakplotmat
        calcmat
        deriv_plotmat
        wavenumber
        offset_stacked
        Peak_marker_offset_value=0.1
        offset_stacked_offset_setting=1.25
        xlimits=[0 4000]
        specs
        spec_plot
        deriv_plot
        selectedTab

        click_time


        edits_state=false
        edit_mat
        %new

        hVerticalLines
        hLineToDrag

        norm_value=10000


        lastplot % Description
    end

    methods (Access = private)

        function  axis2plot=update_active_plot(app,axis2plot)

            try
                if isempty(unique(app.selected_sample))
                    %                     message={'No spectra selected!','Please select at least one spectra'};
                    %                     uialert(app.SpeDBUIFigure,message,'Error');
                    return
                end


                [x, y, SampleName, Thickness, Id, eId ] = get_specs(app.SpecDB,app.selected_sample,true);


                if isempty(app.PlotSettingsTable.Data)
                    update_PlotSettingsTable(app,0);
                end

                use_individual=true;
                if use_individual==true
                    PST = update_PlotSettingsTable(app,Id(1),true);
                else
                    PST=app.PlotSettingsTable.Data;
                end
                data=struct();
                settings=struct();
                                settings(1).x_limits=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                if app.AutoYCheckBox.Value==true
                  settings(1).y_limits=[];
                else
                settings(1).y_limits=sort([app.EditField_ylimMin.Value app.EditField_ylimMax.Value]);
                end

                settings(1).y_offset=1+(app.YOffsetSpinner.Value/100);

                settings(1).reset=true;
                settings(1).type=app.TypeDropDown.Value;
                settings(1).PlotLegend=app.LegendCheckBox.Value;
                settings(1).PlotSpecNames=app.Annotation_CheckBox.Value;
                
                settings(1).Annotation_interpreter=app.AnnotationInterpreterDropDown.Value;

                 settings(1).XLabel=app.XLabelEditField.Value;
                 settings(1).YLabel=app.YLabelEditField.Value;
                 settings(1).ZLabel=app.ZLabelEditField.Value;
              
                if app.UseAdvancedSettingsCheckBox.Value==true
                    settings(1).AnnotationFontSize=app.AnnotationFontSizeSpinner.Value;
                end

                for n=1:numel(x)

                    if use_individual==true
                        PST = update_PlotSettingsTable(app,Id(n),true);
                    end
                    settings(n).PlotSpectra=PST.Active(find(strcmp(PST.Object,'Spectra')));
                   % settings(n).PlotSignal=PST.Active(find(strcmp(PST.Object,'SingleSpectrum')));
                    settings(n).PlotMean=PST.Active(find(strcmp(PST.Object,'MeanSpectrum')));
                    settings(n).PlotSum=PST.Active(find(strcmp(PST.Object,'Sum')));
                    settings(n).PlotRaw=PST.Active(find(strcmp(PST.Object,'Raw')));
                    settings(n).PlotBaseline=PST.Active(find(strcmp(PST.Object,'Baseline')));
                    settings(n).PlotPeaks=PST.Active(find(strcmp(PST.Object,'Peaks')));
                    settings(n).PlotDeconDataSum=PST.Active(find(strcmp(PST.Object,'DeconPeaksSum')));
                    settings(n).PlotDeconDataSingle=PST.Active(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end);

                    settings(n).Color.Spectra=PST.Color(find(strcmp(PST.Object,'Spectra')),:);
                    settings(n).Color.Mean=PST.Color(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).Color.Sum=PST.Color(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).Color.Raw=PST.Color(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).Color.Baseline=PST.Color(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).Color.Peaks=PST.Color(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).Color.DeconSum=PST.Color(find(strcmp(PST.Object,'DeconPeaksSum')),:);

                    settings(n).LineStyle.Spectra=PST.LineStyle(find(strcmp(PST.Object,'Spectra')),:);
                   % settings(n).LineStyle.Signal=PST.LineStyle(find(strcmp(PST.Object,'SingleSpectrum')),:);
                    settings(n).LineStyle.Mean=PST.LineStyle(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).LineStyle.Sum=PST.LineStyle(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).LineStyle.Raw=PST.LineStyle(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).LineStyle.Baseline=PST.LineStyle(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).LineStyle.Peaks=PST.LineStyle(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).LineStyle.DeconSum=PST.LineStyle(find(strcmp(PST.Object,'DeconPeaksSum')),:);

                    settings(n).LineWidth.Spectra=PST.LineWidth(find(strcmp(PST.Object,'Spectra')),:);
                    %settings(n).LineWidth.Signal=PST.LineWidth(find(strcmp(PST.Object,'SingleSpectrum')),:);
                    settings(n).LineWidth.Mean=PST.LineWidth(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).LineWidth.Sum=PST.LineWidth(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).LineWidth.Raw=PST.LineWidth(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).LineWidth.Baseline=PST.LineWidth(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).LineWidth.Peaks=PST.LineWidth(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).LineWidth.DeconSum=PST.LineWidth(find(strcmp(PST.Object,'DeconPeaksSum')),:);

                    settings(n).FaceAlpha.Spectra=PST.FaceAlpha(find(strcmp(PST.Object,'Spectra')),:);
                    %settings(n).FaceAlpha.Signal=PST.FaceAlpha(find(strcmp(PST.Object,'SingleSpectrum')),:);
                    settings(n).FaceAlpha.Mean=PST.FaceAlpha(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).FaceAlpha.Sum=PST.FaceAlpha(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).FaceAlpha.Raw=PST.FaceAlpha(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).FaceAlpha.Baseline=PST.FaceAlpha(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).FaceAlpha.Peaks=PST.FaceAlpha(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).FaceAlpha.DeconSum=PST.FaceAlpha(find(strcmp(PST.Object,'DeconPeaksSum')),:);

                    settings(n).Legend.Spectra=PST.Legend(find(strcmp(PST.Object,'Spectra')),:);
                    %settings(n).Legend.Signal=PST.Legend(find(strcmp(PST.Object,'SingleSpectrum')),:);
                    settings(n).Legend.Mean=PST.Legend(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).Legend.Sum=PST.Legend(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).Legend.Raw=PST.Legend(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).Legend.Baseline=PST.Legend(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).Legend.Peaks=PST.Legend(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).Legend.DeconSum=PST.Legend(find(strcmp(PST.Object,'DeconPeaksSum')),:);

                                        settings(n).LegendStr.Spectra=PST.LegendStr(find(strcmp(PST.Object,'Spectra')),:);
                    %settings(n).LegendStr.Signal=PST.LegendStr(find(strcmp(PST.Object,'SingleSpectrum')),:);
                    settings(n).LegendStr.Mean=PST.LegendStr(find(strcmp(PST.Object,'MeanSpectrum')),:);
                    settings(n).LegendStr.Sum=PST.LegendStr(find(strcmp(PST.Object,'Sum')),:);
                    settings(n).LegendStr.Raw=PST.LegendStr(find(strcmp(PST.Object,'Raw')),:);
                    settings(n).LegendStr.Baseline=PST.LegendStr(find(strcmp(PST.Object,'Baseline')),:);
                    settings(n).LegendStr.Peaks=PST.LegendStr(find(strcmp(PST.Object,'Peaks')),:);
                    settings(n).LegendStr.DeconSum=PST.LegendStr(find(strcmp(PST.Object,'DeconPeaksSum')),:);


                    if cell2mat(Thickness(n))==0
                        Thickness(n)={app.norm_value};
                    end
                    data(n).y_signal_mean=mean((cell2mat(y(n))./(cell2mat(Thickness(n))./app.norm_value)),2,"omitnan");
                    data(n).y_signal=cell2mat(y(n))./(cell2mat(Thickness(n))./app.norm_value);
                    data(n).x_signal=cell2mat(x(n));

                    %% ath add xy raw support
                    %                     if isfield(app.SpecDB.Data.DB(eId(n)).x_signal_raw) &&  isfield(app.SpecDB.Data.DB(eId(n)).x_signal_raw) && not(isempty(app.SpecDB.Data.DB(eId(n)).x_signal_raw))
                    try
%                         data(n).x_signal_raw= isfield(app.SpecDB.Data.DB(eId(n)).x_signal_raw);
%                         data(n).y_signal_raw= isfield(app.SpecDB.Data.DB(eId(n)).y_signal_raw);

                        data(n).x_signal_raw= app.SpecDB.Data.DB(eId(n)).x_signal_raw;
                        data(n).y_signal_raw=app.SpecDB.Data.DB(eId(n)).y_signal_raw./(cell2mat(Thickness(n))./app.norm_value);
                    catch ME
                        data(n).x_signal_raw= data(n).x_signal;
                        data(n).y_signal_raw= data(n).y_signal;

                    end%                     end

                    data(n).SampleName=SampleName(n);
                    if settings(1).PlotSpecNames == true
                        if strcmp(app.SpectraPlot.XDir,'normal')
                            data(n).AnnotationPos=max(settings(1).x_limits);
                        end

                        if isnumeric(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value)))
                            data(n).Annotation={num2str(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value)))};
                        elseif ischar(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value)))
                            data(n).Annotation={app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value))};
                        elseif iscell(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value)))
                            data(n).Annotation=app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown.Value));
                        end
                        
                    end

                    data(n).eId=eId(n);
                    %check here if exist and or empty
                    if app.Decon_CheckBox.Value==true &&isfield(app.SpecDB.Data,'DeconData') && numel(app.SpecDB.Data.DeconData)>=eId(n) && not(isempty(app.SpecDB.Data.DeconData(eId(n))))
                        data(n).DeconData(1)=app.SpecDB.Data.DeconData(eId(n));
                        % add custom settings
                        if not(isempty(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup))
                            data(n).DeconData(1).PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.Active=PST.Active(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end);
                            data(n).DeconData(1).PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.Color=PST.Color(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end,:);
                            data(n).DeconData(1).PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.LineWidth=PST.LineWidth(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end);
                            data(n).DeconData(1).PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.LineStyle=PST.LineStyle(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end);
                            data(n).DeconData(1).PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.FaceAlpha=PST.FaceAlpha(find(strcmp(PST.Object,'DeconPeaksSum'))+1:end);
                        end

                    end
                    if settings(n).PlotBaseline==true
                        try
                            if isfield(app.SpecDB.Data.DB,'baseline') && not(isempty(app.SpecDB.Data.DB(eId(n)).baseline))

                                data(n).baseline=app.SpecDB.Data.DB(eId(n)).baseline./(cell2mat(Thickness(n))./app.norm_value);
                            else
                                data(n).baseline=[];

                            end
                        catch ME
                            disp('Error in SpecVis - Baseline could not be added to PlotSettings struct')
                            data(n).baseline=[];
                            disp(ME)
                        end
                    end

                end
               
                if exist("axis2plot","var")
                    PlotSpectra_fun(axis2plot,data,settings)
                else
                    PlotSpectra_fun(app.SpectraPlot,data,settings)
                    axis2plot=app.SpectraPlot;
                end

                try
                    app.EditField_ylimMin.Value =min(axis2plot.YLim);
                    app.EditField_ylimMax.Value=max(axis2plot.YLim);
                catch
                    app.EditField_ylimMin.Value =min(axis2plot.Children.YLim);
                    app.EditField_ylimMax.Value=max(axis2plot.Children.YLim);
                end

                try
                    y1=linspace(axis2plot.YLim(1),axis2plot.YLim(2)*0.85,1);
                    x2=linspace(axis2plot.XLim(1),axis2plot.XLim(2),1);
                catch
                    y1=linspace(axis2plot.Children.YLim(1),axis2plot.Children.YLim(2)*0.85,1);
                    x2=linspace(axis2plot.Children.XLim(1),axis2plot.Children.XLim(2),1);

                end
                if app.Peaks_CheckBox.Value==true && not(isempty(app.Peaks)) && not(isempty(app.PeakTable.Data))&& not(isempty(app.PeakTable.Data.PeakPosition))
                    PeakPosition=app.PeakTable.Data.PeakPosition;
                    label=app.PeakTable.Data.PeakNames;
                    peaksetting=app.PlotSettingsTable.Data(strcmp(app.PlotSettingsTable.Data.Object,'Peaks'),:).LineStyle{:};
                    peakcolor=[0 0 0];%[app.Peaks.PeakDB_selection.R  app.Peaks.PeakDB_selection.G  app.Peaks.PeakDB_selection.B ];%app.PlotSettingsTable.Data(strcmp(app.PlotSettingsTable.Data.Object,'Peaks'),:).Color;
                    peaklablesize=app.FontSizeSpinner.Value;%app.PlotSettingsTable.Data(strcmp(app.PlotSettingsTable.Data.Object,'Peaks'),:).FaceAlpha;
                    peaklinewidth=app.PlotSettingsTable.Data(strcmp(app.PlotSettingsTable.Data.Object,'Peaks'),:).LineWidth  ;
                    

                    if app.UseAdvancedSettingsCheckBox.Value==true
                        peaklablesize=app.PeakLableFontSizeSpinner.Value;%app.PlotSettingsTable.Data(strcmp(app.PlotSettingsTable.Data.Object,'Peaks'),:).FaceAlpha;
                        if app.PeakLineColorCheckBox.Value==true
                            peakcolor=[app.Peaks.PeakDB_selection.R  app.Peaks.PeakDB_selection.G  app.Peaks.PeakDB_selection.B ];
                        end

                    end

                    try
                        PlotPeaksFun(axis2plot,x2,y1,PeakPosition,peaksetting,label,peakcolor,peaklablesize,peaklinewidth)
                    catch
                        PlotPeaksFun(axis2plot.Children,x2,y1,PeakPosition,peaksetting,label,peakcolor,peaklablesize,peaklinewidth)
                    end

%                     try
%                         PlotPeaksFun(axis2plot,x2,y1,PeakPosition,app.PeakDispDropDown.Value,label)
%                     catch
%                         PlotPeaksFun(axis2plot.Children,x2,y1,PeakPosition,app.PeakDispDropDown.Value,label)
%                     end
                end

                        
            catch ME
                disp(ME.message)
                disp(ME.stack(1))

            end
            update_FigHistLive(app)

        end

        function update_peaktable_Data(app)
            peaktabledata=table([],[],[],[],[],[],'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence','Spec','Source'});
            app.peakplotmat=nan(size(app.plotmat));
            uniquePeaks=[];

            for m=1:length(app.SpectraListBox.Items)
                if  app.Spec_table.Data.Active(m)==true
                    if  isfield(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks,'Autopeaks')
                        peaktabledata=[peaktabledata;app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.Autopeaks]; %#ok<AGROW>
                        if size(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.Autopeaks,2)>=1
                            uniquePeaks=unique([app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.Autopeaks.PeakPosition]);
                        end
                        app.peakplotmat(ismember(app.wavenumber,uniquePeaks),m)=uniquePeaks;
                        app.edits_state=true;
                    end

                    if isfield(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks,'UIpeaks')
                        peaktabledata=[app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.UIpeaks;peaktabledata]; %#ok<AGROW>
                        if size(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.UIpeaks,2)>=1
                            uniquePeaks=unique([app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.UIpeaks.PeakPosition;uniquePeaks]);
                        end
                        app.peakplotmat(ismember(app.wavenumber,uniquePeaks),m)=uniquePeaks;
                        app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.uniquePeaks=uniquePeaks;
                        app.edits_state=true;
                    end

                    if isfield(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks,'DeconPeaks')
                        peaktabledata=[app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.DeconPeaks;peaktabledata]; %#ok<AGROW>
                        if size(app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.DeconPeaks,2)>=1
                            uniquePeaks=unique([app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.DeconPeaks.PeakPosition;uniquePeaks]);
                        end

                        uniquePeaks(uniquePeaks<=app.xlimits(1) | uniquePeaks>=app.xlimits(2),:)=[];

                        app.peakplotmat(ismember(app.wavenumber,uniquePeaks),m)=uniquePeaks;
                        app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.uniquePeaks=uniquePeaks;
                        app.edits_state=true;
                    end

                    app.PeakTable.Data=peaktabledata;

                end
            end
        end

        function autodetect_peaks(app)
            if strcmp(app.TypeDropDown_2.Value,'Default')
                for m=1:length(app.SpectraListBox.Items)
                    if app.Spec_table.Data.Active(m)==true
                        Spec=app.Spec_table.SpecName(m);
                        x_signal=app.SpecVis_data.(char(app.SpectraListBox.Items(m))).wavenumber;
                        y_signal=app.SpecVis_data.(char(app.SpectraListBox.Items(m))).intensity;

                        y_signal=y_signal(x_signal>=app.xlimits(1) & x_signal<=app.xlimits(2),:);
                        x_signal=x_signal(x_signal>=app.xlimits(1) & x_signal<=app.xlimits(2),:);

                        [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value);
                        peaktable=table(PeakPosition,PeakIntensity,Width,Prominence,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
                        peaktable.Spec(:)=string(Spec);
                        peaktable.Source(:)={'Autodetected Peak'};
                        app.SpecVis_data.(char(app.SpectraListBox.Items(m))).peaks.Autopeaks=peaktable;
                    end
                end

            elseif strcmp(app.TypeDropDown_2.Value,'Sum') || strcmp(app.TypeDropDown_2.Value,'Mean')

                Spec="sum_mean_peaks"; % app.Spec_table.SpecName(app.Spec_table.Data.Active==true); % merge these spec names  ATH
                x_signal=app.wavenumber;
                y_signal=app.calcmat;

                y_signal=y_signal(x_signal>=app.xlimits(1) & x_signal<=app.xlimits(2),:);
                x_signal=x_signal(x_signal>=app.xlimits(1) & x_signal<=app.xlimits(2),:);
                [PeakIntensity,PeakPosition,Width,Prominence] = findpeaks(y_signal,x_signal,'MinPeakHeight',app.MinPeakHightSpinner.Value,'MinPeakProminence',app.MinPeakPromenanceSpinner.Value,'MinPeakDistance',app.MinPeakDistanceSpinner.Value);
                peaktable=table(PeakPosition,PeakIntensity,Width,Prominence,'VariableNames',{'PeakPosition','PeakIntensity','Width','Prominence'});
                peaktable.Spec(:)={Spec};
                peaktable.Source(:)={'Autodetected Peak'};

                for n=1:length(app.SpectraListBox.Items)
                    app.SpecVis_data.(char(app.SpectraListBox.Items(n))).peaks.Autopeaks=peaktable; %ATA
                end
            end
            update_peaktable_Data(app)

            if strcmp(app.PeakPreviewSwitch.Value,'On') || strcmp(app.TabGroup.SelectedTab.Title,'Peakhist')
                update_active_plot(app);
            end
        end

        function  update_FigHistLive(app)
            cla(app.FigXlimLive)

            [x, y, ~, Thickness, ~, ~ ] = get_specs(app.SpecDB,app.selected_sample,true);


            %
            %             min_wn=min(app.xlimits);
            %             max_wn=max(app.xlimits);
            min_wn=min(cellfun(@min,x));
            max_wn=max(cellfun(@max,x));

            %delta_wn=min(abs(cellfun(@mean,(cellfun(@diff,(x),'UniformOutput',false)))))
            %old failed if nan in x

            test=(cellfun(@diff,(x),'UniformOutput',false));
            if isempty(test)
                return
            end
            test=test{:};
            test=test(~isnan(test));
            delta_wn=min(abs(mean(test)));

            x_new=(max_wn:-delta_wn:min_wn)';
            in_new=nan(size(x_new,1),numel(app.selected_sample));

            for n=1:numel(app.selected_sample)
                if isempty(y{n})
                    continue
                end
                if Thickness{n}==0
                    Thickness{n}=app.norm_value;
                end
                %                 if isequal(x_new,x{n})|| numel(x)==1
                %                     in_new(:,n)=mean((y{n}./Thickness{n}).*app.norm_value,2,'omitnan');
                if isequal(x_new,x{n})
                    in_new(:,n)=mean((y{n}./Thickness{n}).*app.norm_value,2,'omitnan');
                elseif numel(x)==1 || isequal(x{:})
                    in_new(:,n)=mean((y{n}./Thickness{n}).*app.norm_value,2,'omitnan');
                    x_new=x{n};
                else
                    try

                        y_resampled=interp1(x{n}(isfinite(x{n})),(y{n}(isfinite(x{n}))./Thickness{n}).*app.norm_value,x_new);
                        y_resampled(not(x_new>min(x{n})&x_new<max(x{n})))=nan;
                        in_new(:,n)=mean(y_resampled,2,'omitnan');
                    catch
                        disp('error in fighist generation')
                    end
                end
            end

            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (app.FigXlimLive, 'xdir', 'reverse')
            end

            a_h=area(app.FigXlimLive,x_new,mean(in_new,2,"omitnan"),'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
            set(a_h, 'hittest', 'off');

            a_l=plot(app.FigXlimLive,x_new,in_new,'Color','k');
            set(a_l, 'hittest', 'off');

            app.hVerticalLines = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
            set(app.hVerticalLines, 'hittest', 'off');
            %             app.hLineToDrag = [];
        end


        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax,"Visible","off");
            x = roi.Position(1);
            y = roi.Position(2);
        end

        function [intensity_filt] = FilterFun(app,intensity)
            intensity_filt=smooth(intensity,app.FilterSpan.Value,app.FilterMethod.Value,app.FilterDegree.Value);
        end

        function [wavenumber,inter_data]=resample_bad_data(~,wavenumber,y,degree,minpeakhight,minpeakdistance)
            y_deriv=y;
            for m=1:degree
                y_deriv=gradient(y_deriv);
            end

            [~, locs1]=findpeaks(abs(y_deriv),"MinPeakHeight",minpeakhight,"MinPeakDistance",minpeakdistance);
            locs=[1;locs1;length(wavenumber)];
            inter_data=interp1(wavenumber(locs),y(locs),wavenumber,"spline");
            inter_data(inter_data<0)=0;

        end


        function PlotSettingsTable = update_PlotSettingsTable(app,Id,return_data)
            fns=fieldnames(app.SpecDB.Data.PlotSettings(1).Default);
            fns=fns(not(ismember(fns,{'alpha','beta','gamma'})));
            varnames={'Active','Object','Legend','LegendStr','Color','LineWidth','LineStyle','FaceAlpha'};
            Object=cell(1,numel(fns));
            LegendStr=cell(1,numel(fns));
            LineStyle=cell(1,numel(fns));
            Active=zeros(1,numel(fns));
            Legend=zeros(1,numel(fns));
            Color=zeros(numel(fns),3);
            LineWidth=zeros(1,numel(fns));
            FaceAlpha=zeros(1,numel(fns));

            if Id==0

                for n=1:numel(fns)
                    Object(n)={char(fns(n))};
                    Active(n)=logical(app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Active);
                    Legend(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Legend;
                    LegendStr(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LegendStr;
                    Color(n,:)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Color;
                    LineWidth(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LineWidth;
                    LineStyle(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LineStyle;
                    FaceAlpha(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).FaceAlpha;
                end

            else

                if numel(Id)>1
                    Id=Id(1);
                end
                %    [~, ~, ~, ~, ~, eId] = get_specs(app.SpecDB,Id,true);
                eId= find([app.SpecDB.Data.Meta(:).Id]==Id);
                try
                    if isfield(app.SpecDB.Data,'DeconData') && numel(app.SpecDB.Data.DeconData)>=eId && not(isempty(app.SpecDB.Data.DeconData(eId))) &&  isfield(app.SpecDB.Data.DeconData(eId),'ActiveDeconGroup' ) && not(isempty(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup))
                        ActiveDeconGroup= app.SpecDB.Data.DeconData(eId).ActiveDeconGroup;
                    else
                        ActiveDeconGroup=1;
                    end
                catch ME
                    ME
                end
                
                % ath test here if numel(PlotSettings)>=app.SpecDB.Data.DeconData(eId).ActiveDeconGroup
                for n=1:numel(fns)
                    Object(n)={char(fns(n))};
                    try
                        if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'Active') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Active))
                            Active(n)=logical(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Active);
                        else
                            Active(n)=logical(app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Active);
                        end
                    catch ME
                        ME
                    end

                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'Legend') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Legend))
                        Legend(n)=logical(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Legend);
                    else
                        Legend(n)=logical(app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Legend);
                    end
                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'LegendStr') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LegendStr))
                        LegendStr(n)=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LegendStr;
                    else
                        LegendStr(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LegendStr;
                    end
                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'Color') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Color))
                        Color(n,:)=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).Color;
                    else
                        Color(n,:)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).Color;
                    end

                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'LineWidth') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LineWidth))
                        LineWidth(n)=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LineWidth;
                    else
                        LineWidth(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LineWidth;
                    end

                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'LineStyle') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LineStyle))
                        LineStyle(n)=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).LineStyle;
                    else
                        LineStyle(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).LineStyle;
                    end
                    if isfield(app.SpecDB.Data.PlotSettings,'Custom') && numel(app.SpecDB.Data.PlotSettings)>=eId && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup  &&  isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),(char(fns(n)))) &&  not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))))) && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))),'FaceAlpha') && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).FaceAlpha))
                        FaceAlpha(n)=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fns(n))).FaceAlpha;
                    else
                        FaceAlpha(n)=app.SpecDB.Data.PlotSettings(1).Default.(char(fns(n))).FaceAlpha;
                    end
                end


                if app.Decon_CheckBox.Value==true && isfield(app.SpecDB.Data,'DeconData') && numel(app.SpecDB.Data.DeconData)>=eId && not(isempty(app.SpecDB.Data.DeconData(eId))) ...
                        && isfield(app.SpecDB.Data.DeconData(eId),'PlotSettings') && not(isempty(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup)) ...
                        && numel(app.SpecDB.Data.DeconData(eId).PlotSettings)>=app.SpecDB.Data.DeconData(eId).ActiveDeconGroup ...
                        && isfield(app.SpecDB.Data.DeconData(eId).PlotSettings(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup),'DeconSingle')...
                        && isfield(app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup),'Peaks')...
                        && isfield(app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks,'PeakDB')...
                        && ismember('PeakNames',app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks.PeakDB.Properties.VariableNames)...
                        && not(isempty(app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks.PeakDB.PeakNames))

                    PlotSettings=app.SpecDB.Data.DeconData(eId).PlotSettings(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).DeconSingle;

                    if isfield(app.SpecDB.Data.PlotSettings,'Custom')&& numel(app.SpecDB.Data.PlotSettings)>=eId && not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom))  && numel(app.SpecDB.Data.PlotSettings(eId).Custom)>=ActiveDeconGroup && isfield(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup),'DeconSingle')&& not(isempty(app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).DeconSingle))
                        PlotSettings_c=app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).DeconSingle;
                    else
                        PlotSettings_c=[];
                    end

                    for n=1:numel(app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks.PeakDB.PeakNames)
                        %% Active
                        if isfield(PlotSettings_c,'Active') && not(isempty(PlotSettings_c.Active)) &&numel(PlotSettings_c.Active)>=n && not(isempty(PlotSettings_c.Active(n)))
                            Active(n+numel(fns)-1)=logical(PlotSettings_c.Active(n));
                        elseif isfield(PlotSettings,'Active') && not(isempty(PlotSettings.Active)) &&numel(PlotSettings.Active)>=n && not(isempty(PlotSettings.Active(n)))
                            Active(n+numel(fns)-1)=logical(PlotSettings.Active(n));
                        else
                            Active(n+numel(fns)-1)=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.Active;
                        end
                        %% Object
                        Object{n+numel(fns)-1}='DeconPeaksSingle';

                        %%Legend

                        if isfield(PlotSettings_c,'Legend') && not(isempty(PlotSettings_c.Legend)) &&numel(PlotSettings_c.Legend)>=n && not(isempty(PlotSettings_c.Legend(n)))
                            Legend(n+numel(fns)-1)=logical(PlotSettings_c.Legend(n));
                        elseif isfield(PlotSettings,'Legend') && not(isempty(PlotSettings.Legend)) &&numel(PlotSettings.Legend)>=n && not(isempty(PlotSettings.Legend(n)))
                            Legend(n+numel(fns)-1)=logical(PlotSettings.Legend(n));
                        else
                            Legend(n+numel(fns)-1)=logical(app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.Legend);
                        end

                        %% LegendStr


                        %                         if isfield(PlotSettings,'LegendStr') && not(isempty(PlotSettings.LegendStr)) && isfield(PlotSettings.LegendStr,'DeconSingle') &&...
                        %                                 numel(PlotSettings.LegendStr.DeconSingle)>=n && not(isempty(PlotSettings.LegendStr.DeconSingle(n)))
                        %                             LegendStr(n+numel(fns)-1)=PlotSettings.LegendStr.DeconSingle(n);
                        %                         else
                        %                             LegendStr(n+numel(fns)-1)=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.LegendStr;
                        %                         end

                        if isfield(PlotSettings_c,'LegendStr') && not(isempty(PlotSettings_c.LegendStr)) &&numel(PlotSettings_c.LegendStr)>=n && not(isempty(PlotSettings_c.LegendStr{n}))
                            LegendStr{n+numel(fns)-1}=PlotSettings_c.LegendStr{n};
                        elseif isfield(PlotSettings,'LegendStr') && not(isempty(PlotSettings.LegendStr)) &&numel(PlotSettings.LegendStr)>=n && not(isempty(PlotSettings.LegendStr(n)))
                            LegendStr{n+numel(fns)-1}=PlotSettings.LegendStr{n};
                        else
                         %   LegendStr{n+numel(fns)-1}=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.LegendStr{:};
                             LegendStr{n+numel(fns)-1}=app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks.PeakDB.PeakNames{n};
                        end

                        %% Color

                        if isfield(PlotSettings_c,'Color') && not(isempty(PlotSettings_c.Color)) &&numel(PlotSettings_c.Color)>=n && not(isempty(PlotSettings_c.Color(n)))
                            Color(n+numel(fns)-1,:)=PlotSettings_c.Color(n,:);
                        elseif isfield(PlotSettings,'Color') && not(isempty(PlotSettings.Color)) &&numel(PlotSettings.Color)>=n && not(isempty(PlotSettings.Color(n)))
                            Color(n+numel(fns)-1,:)=PlotSettings.Color(n,:);
                        else
                            Color(n+numel(fns)-1,:)=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.Color;
                        end

                        %  Color(n+numel(fns)-1,:)=PlotSettings.DeconSingle.Color(n,:);
                        %% LineStyle

                        if isfield(PlotSettings_c,'LineStyle') && not(isempty(PlotSettings_c.LineStyle)) &&numel(PlotSettings_c.LineStyle)>=n && not(isempty(PlotSettings_c.LineStyle{n}))
                            LineStyle{n+numel(fns)-1}=PlotSettings_c.LineStyle{n};
                        elseif isfield(PlotSettings,'LineStyle') && not(isempty(PlotSettings.LineStyle)) &&numel(PlotSettings.LineStyle)>=n && not(isempty(PlotSettings.LineStyle(n)))
                            LineStyle{n+numel(fns)-1}=PlotSettings.LineStyle{n};
                        else
                            LineStyle{n+numel(fns)-1}=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.LineStyle{:};
                        end
                        % LineStyle{n+numel(fns)-1}=PlotSettings.LineStyle.DeconSingle{n};
                        %% LineWidth

                        if isfield(PlotSettings_c,'LineWidth') && not(isempty(PlotSettings_c.LineWidth)) &&numel(PlotSettings_c.LineWidth)>=n && not(isempty(PlotSettings_c.LineWidth(n)))
                            LineWidth(n+numel(fns)-1)=PlotSettings_c.LineWidth(n);
                        elseif isfield(PlotSettings,'LineWidth') && not(isempty(PlotSettings.LineWidth)) &&numel(PlotSettings.LineWidth)>=n && not(isempty(PlotSettings.LineWidth(n)))
                            LineWidth(n+numel(fns)-1)=PlotSettings.LineWidth(n);
                        else
                            LineWidth(n+numel(fns)-1)=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.LineWidth;
                        end

                        %    LineWidth(n+numel(fns)-1)=PlotSettings.LineWidth.DeconSingle(n);

                        %% FaceAlpha

                        if isfield(PlotSettings_c,'FaceAlpha') && not(isempty(PlotSettings_c.FaceAlpha)) &&numel(PlotSettings_c.FaceAlpha)>=n && not(isempty(PlotSettings_c.FaceAlpha(n)))
                            FaceAlpha(n+numel(fns)-1)=PlotSettings_c.FaceAlpha(n);
                        elseif isfield(PlotSettings,'FaceAlpha') && not(isempty(PlotSettings.FaceAlpha)) &&numel(PlotSettings.FaceAlpha)>=n && not(isempty(PlotSettings.FaceAlpha(n)))
                            FaceAlpha(n+numel(fns)-1)=PlotSettings.FaceAlpha(n);
                        else
                            FaceAlpha(n+numel(fns)-1)=app.SpecDB.Data.PlotSettings(1).Default.DeconPeaksSingle.FaceAlpha;
                        end


                    end


                end

            end
            T=table(logical(Active'),Object',logical(Legend'),LegendStr',Color,LineWidth',LineStyle',FaceAlpha',VariableNames=varnames);
            if exist('return_data','var') && return_data==true
                PlotSettingsTable=T;
                return
            else
                app.PlotSettingsTable.Data=T;
            end

            update_active_plot(app);
        end

        function update_selected_sample_listbox(app)
            try
                if isempty(app.selected_sample)
                    app.SelectedSampleListBox.Items={};
                    return
                end

                sort_vector=zeros(numel(app.selected_sample),1);
                for m=1:numel(app.selected_sample)
                    sort_vector(m)=find(app.SpectraListBox.ItemsData==app.selected_sample(m));
                end
                [idv, idx]=sort(sort_vector,'ascend');

                items=app.SpectraListBox.Items(sort_vector);


                app.SelectedSampleListBox.Items=flip(items);
                app.SelectedSampleListBox.ItemsData=flip(app.selected_sample);


            catch ME
                disp(ME.message)
                disp(ME.stack(1))

                update_PlotSettingsTable(app,0);
            end
        end


        function update_PeakTree(app)

            a = app.PeakTree.Children;
            a.delete;

            for i=1:numel(app.Peaks.PeakDB.MineralNames)
                p = uitreenode(app.PeakTree,'Text',string(app.Peaks.PeakDB.MineralNames{i}),'NodeData',[i,0]);
                expand(p)

                for j=1:numel(app.Peaks.PeakDB.Mineral(i).GroupNames)
                    p1 = uitreenode(p,'Text',string(app.Peaks.PeakDB.Mineral(i).GroupNames{j}),'NodeData',[i,j]);
                    expand(p1)

                    for k=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames)
                        if numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)==1
                            pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                            name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                            Id=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).Id;
                            p2=uitreenode(p1,'Text',[num2str(pos) '_' app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',{[i,j,k],pos,name,Id}); %# ok
                        else
                            p2=uitreenode(p1,'Text',[app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k}],'NodeData',[i,j,k]);

                            for l=1:numel(app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition)
                                pos=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).PeakPosition;
                                name=app.Peaks.PeakDB.Mineral(i).Groups(j).PeakNames{k};
                                Id=app.Peaks.PeakDB.Mineral(i).Groups(j).Peaks(k).Id(l);
                                p3=uitreenode(p2,'Text',[num2str(pos(l))],'NodeData',{[i,j,k l],pos(l),name,Id}); %# ok
                            end
                        end
                    end
                end
            end


        end
        
        function UpdatePeakTable(app)
            if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                peaktabledata=app.Peaks.PeakDB_raw;
            else
                peaktabledata=app.Peaks.PeakDB_UI;
            end

            if not(isempty(app.PeakTree.CheckedNodes)) && sum(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData}))>0

                Peak_Id=zeros(sum(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData})),1);
                iter=0;

                for n=find(cellfun(@iscell, {app.PeakTree.CheckedNodes.NodeData}))
                    iter=iter+1;
                    Peak_Id(iter)=app.PeakTree.CheckedNodes(n).NodeData{end};
                    % Peak_eID(iter,:)=app.PeakTree.CheckedNodes(n).NodeData{1};
                end


                app.Peaks.PeakDB_selection=peaktabledata(Peak_Id,:);
                [~, idx]=sort(app.Peaks.PeakDB_selection.PeakPosition);

                app.Peaks.PeakDB_selection=app.Peaks.PeakDB_selection(idx,:);

                if app.FullTableCheckBox.Value==false
                    app.PeakTable.Data=app.Peaks.PeakDB_selection(:,find(ismember(peaktabledata.Properties.VariableNames,{'RefPeakPosition','PeakPosition','PeakShift','PeakNames'})));

                    app.PeakTable.ColumnName=peaktabledata.Properties.VariableNames(find(ismember(peaktabledata.Properties.VariableNames,{'RefPeakPosition','PeakPosition','PeakShift','PeakNames'})));
                else
                    app.PeakTable.Data=app.Peaks.PeakDB_selection;
                    app.PeakTable.ColumnName=peaktabledata.Properties.VariableNames;
                end


            else
                app.PeakTable.Data=[];
            end
        end


        function PopUpPlot_fun(app,ax,ax2)
            L1 = findobj(ax2.Parent(1).Parent,'tag','legend');


            ax2Chil = ax2.Children;
            copyobj(ax2Chil, ax)

            %set(ax,'Units','normalized','Position',[0.1 0.1 0.8 0.8]);

            % recreate the legend
            if not (isempty(L1))
                c_lines=flipud( findobj(ax,'Type','line'));
                [a, b, c]=unique(cell2table([{c_lines.Tag}' {c_lines.Color}',{c_lines.LineWidth}',{c_lines.LineStyle}']),'rows','stable');
                legend(ax,flipud(c_lines(b)),flipud(a.(1)),'Location','eastoutside',Interpreter='none');
                %      l
                % retrieve the legend strings and location
                % legendstr=get(L1,'String');
                % legendloc=get(L1,'Location');
                % %legend(ax,legendstr,'Location',legendloc);
            end

            % View
            [az,el] = view(app.SpectraPlot);

            view(ax,az,el)

            if strcmp(app.SpectraPlot.XDir,'reverse')
                set (gca, 'xdir', 'reverse')
            end


            fns=fieldnames(app.SpectraPlot);
            fns=fns(not(ismember(fns,{'CurrentPoint';'Parent';'Children';'XAxis';'YAxis';'ZAxis';'Position';'OuterPosition';'InnerPosition';...
                'PositionConstraint';'Units';'Toolbar';'Layout';'BeingDeleted';'Type'})));
            for n=1:numel(fns)
                try
                    ax.(char(fns{n}))=app.SpectraPlot.(char(fns{n}));
                catch
                    % disp(char(fns{n}))
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SpecDB = mainapp;
if isprop(app.SpecDB,'SpecXDBUIFigure')
app.norm_value=1;
end

            %% here 
            selections=[app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).Id];%find(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id})));
            a=string({app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).Id}');

            b1={app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).SampleName};
            b2=not(cellfun(@ischar,{app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).SampleName}'));
            b1(b2)={''};
            b1=string(b1)';
            SampleNames=join([a,b1],'_');

            Id_t=ismember([app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).Id],app.SpecDB.DB_table.Data.Id);
            selections=selections(Id_t);
            SampleNames=SampleNames(Id_t);

            %%
            app.Spec_table.Data=app.SpecDB.DB_table.Data;
            app.Spec_table.ColumnName=app.SpecDB.DB_table.Data.Properties.VariableNames;

            %                a=string(app.Spec_table.Data.Id);
            %                b=string(app.Spec_table.Data.SampleName);
            %                SampleNames=join([a,b],'_');
            %                selections=app.Spec_table.Data.Id;
            if not(isempty(app.SpecDB.DB_table.Selection))
                app.selected_sample=unique(app.SpecDB.DB_table.Data.Id(app.SpecDB.DB_table.Selection(:,1)));
                if numel(app.selected_sample)>20
                    app.selected_sample(randi(numel(app.selected_sample),1,20));
                end

            else
                app.selected_sample=[];
            end
            app.SpectraListBox.Items=SampleNames;
            app.SpectraListBox.ItemsData=selections;

            update_selected_sample_listbox(app)

            app.EditField_xlimMin.Value=app.xlimits(1);
            app.EditField_xlimMax.Value=app.xlimits(2);

            update_PlotSettingsTable(app,0);
            
            update_active_plot(app);


            %% peak data
            try
                if isdeployed
                    path=fullfile(app.SpecDB.SpecXY.SpecXY.installpath,'PeakDB.csv');
                        if not(exist(path,'file'))
                            message={'Error SpecXY_PlotSettings.csv not found: ', path};
                            uialert(app.SpecVisGUI,message,'Error');
                            return
                        end

%                     path=ctfroot;
%                     path_split=split(path,{'SpecXY.app','SpecXY.exe'});
%                     path=fullfile(path_split{1},'PeakDB.csv');
%                     if not(exist(path,'file'))
%                         path='PeakDB.csv';
%                     end
                else
                    path=which('PeakDB.csv');
                end
                [app.Peaks.PeakDB, app.Peaks.PeakDB_raw]=ReadPeakdata_fun(path);
                update_PeakTree(app)

            catch ME
                %                message='Error not a valid PeakDB file';

                uialert(app.SpecVisGUI,ME.message,'Error');
            end

            if strcmp(app.SpecDB.XDirSwitch.Value,'XDir normal')
                app.SpectraPlot.XDir='normal';
                app.FigXlimLive.XDir='normal';
                swap_lim_min=app.EditField_xlimMin.Layout;
                swap_lim_max=app.EditField_xlimMax.Layout;
                app.EditField_xlimMin.Layout=swap_lim_max;
                app.EditField_xlimMax.Layout=swap_lim_min;


                swap_p1_max =app.xlimMax_p1.Layout;
                swap_m1_min=app.xlimMin_m1.Layout;
                app.xlimMin_m1.Layout=swap_p1_max;
                app.xlimMax_p1.Layout=swap_m1_min;

                swap_p10_max =app.xlimMax_p10.Layout;
                swap_m10_min=app.xlimMin_m10.Layout;
                app.xlimMin_m10.Layout=swap_p10_max;
                app.xlimMax_p10.Layout=swap_m10_min;


                swap_m1_max =app.xlimMax_m1.Layout;
                swap_p1_min =app.xlimMin_p1.Layout;
                app.xlimMax_m1.Layout=swap_p1_min;
                app.xlimMin_p1.Layout=swap_m1_max;

                swap_m10_max =app.xlimMax_m10.Layout;
                swap_p10_min =app.xlimMin_p10.Layout;
                app.xlimMax_m10.Layout=swap_p10_min;
                app.xlimMin_p10.Layout=swap_m10_max;


            end

            try
                app.SpectraPlot.XLabel.String=app.SpecDB.xlabel_str;
                app.XLabelEditField.Value=app.SpecDB.xlabel_str;

                    app.SpectraPlot.YLabel.String=app.SpecDB.ylabel_str;
                    app.YLabelEditField.Value=app.SpecDB.ylabel_str;
                catch
             end

            ResetButtonPushed(app)
        end

        % Close request function: SpecVisGUI
        function SpecVisGUICloseRequest(app, event)
            delete(app)
        end

        % Callback function: AnnotationDropDown, 
        % AnnotationInterpreterDropDown, Annotation_CheckBox, 
        % AutoYCheckBox, LegendCheckBox, TabGroup, TypeDropDown, 
        % UpdateButton, UseAdvancedSettingsCheckBox, YOffsetSpinner
        function UpdatePlotPushed(app, event)
            if  app.LegendCheckBox.Value==false && ~isempty(app.SpectraPlot.Legend)
                delete(app.SpectraPlot.Legend)
            end
            update_active_plot(app);
        end

        % Value changed function: SpectraListBox
        function SpectraListBoxValueChanged(app, event)
            value = event.Value;
            app.selected_sample=value;

            update_selected_sample_listbox(app)
            update_PlotSettingsTable(app,app.selected_sample);
            update_active_plot(app);
            eId= find([app.SpecDB.Data.Meta(:).Id]==app.selected_sample(1));

            if numel(app.selected_sample)==1
                try
                    app.ListBox.Items=app.SpecDB.Data.DeconData(eId).DeconvolutionName;
                    app.ListBox.ItemsData=1:numel(app.SpecDB.Data.DeconData(eId).DeconvolutionName);
                    app.ListBox.Value=app.SpecDB.Data.DeconData(eId).ActiveDeconGroup;
                catch
                    app.ListBox.Items={};
                    app.ListBox.ItemsData=[];
                end
            else
                app.ListBox.Items={};
                app.ListBox.ItemsData=[];
            end

        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_LiveMinMaxValueChanged(app, event)
            ValueMin = app.EditField_xlimMin.Value;
            ValueMax = app.EditField_xlimMax.Value;

            if ValueMin < ValueMax
                app.hVerticalLines(1).Value=ValueMin;
                app.hVerticalLines(2).Value=ValueMax;
            end

            app.xlimits=[round(ValueMin) round(ValueMax)];
            if isempty(app.hLineToDrag)
                update_active_plot(app);
            end
        end

        % Window button up function: SpecVisGUI
        function SpecVisGUIWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];
                app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

                update_active_plot(app);
            end
        end

        % Window button motion function: SpecVisGUI
        function SpecVisGUIWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)
                currentPoint   = app.FigXlimLive.CurrentPoint;
                x            = currentPoint(2,1);
                y            = currentPoint(2,2);

                xlim = app.FigXlimLive.XLim;
                ylim = app.FigXlimLive.YLim;

                inside = x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2);

                if inside
                    set(app.hLineToDrag, 'Value', x);
                    xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                    app.EditField_xlimMin.Value = min(xposlines);
                    app.EditField_xlimMax.Value = max(xposlines);
                else
                    app.hLineToDrag=[];
                end
            end
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+10;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+1;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-1;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-10;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-10;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-1;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+1;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+10;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Value changed function: Peaks_CheckBox
        function Peaks_CheckBoxValueChanged(app, event)
            update_active_plot(app);
        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
           zoom(app.SpectraPlot,'off')
           rotate3d(app.SpectraPlot,'off')
            pan(app.SpectraPlot,'off')

            for k=1:length(app.hVerticalLines)
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01;
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);

                    break;
                end
            end
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir;
            delete(f)

            if not(isequal(path,0))
                app.PathEditField.Value=path;
                app.PathEditField.Tooltip=app.PathEditField.Value;
            end
                        figure(app.SpecVisGUI);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            d=dir(app.PathEditField.Value);

            file = matlab.lang.makeUniqueStrings(replace([app.FilenameEditField.Value '_' app.CustStrEditField.Value],{'__','___','____'},'_'),replace({d.name},{'.fig','.eps','.png','.pdf'},''));
            FileName=fullfile(app.PathEditField.Value, file);

%             hFig=figure;
%             nexttile
%             hFig.Children=update_active_plot(app,hFig.Children);
            hFig=app.SpectraPlot;

%             if strcmp(app.SpectraPlot.XDir,'reverse')
%                 set (hFig.Children.Children, 'xdir', 'reverse')
%             end
% 
%                       if app.UseAdvancedSettingsCheckBox.Value==true
%                           for n=1:numel(hFig.Children.Children)
% hFig.Children.Children(n).FontSize=app.FigureFontSizeSpinner.Value;
%                           end
%                       else
% 
%                 FontSize=15;
%                 [hFig.Children.Children(:).FontSize]=deal(FontSize);
% 
%                       end

            if app.CheckBox_fig_exp.Value==true
                fig = figure(); 
copiedAxes = copyobj(app.SpectraPlot, fig);
                savefig(fig,[FileName '.fig'])
            end
            if app.CheckBox_eps_exp.Value==true
                exportgraphics(hFig,[FileName '.eps'],'BackgroundColor','none','ContentType','vector')
            end
            if app.CheckBox_png_exp.Value==true
                exportgraphics(hFig,[FileName '.png'],Resolution=300)
            end
            if app.CheckBox_jpg_exp.Value==true
                exportgraphics(hFig,[FileName '.jpg'])
            end

           
            if app.CheckBox_pdf_exp.Value==true
                exportgraphics(hFig,[FileName '.pdf'])
            end


        end

        % Button pushed function: ApplytoallSpectraButton
        function ApplytoallSpectraButtonPushed(app, event)
            tic
            selections = [app.SpecDB.Data.Meta(:).Id];

            [x_, y_, SampleName, Thickness, Id, eId] = get_specs(app.SpecDB,selections,false);

            d = uiprogressdlg(app.SpecVisGUI,'Title','Editing Spectra',...
                'Message','Correcion started','Cancelable','on');
            drawnow

            for n=1:numel(y_)
                d.Value = n/numel(y_);
                if d.CancelRequested
                    break
                end
                d.Message = sprintf(['Correcting : \nSample: ' SampleName{n}]);
                x=x_{n};
                y=y_{n};

                y(isinf(x),:)=[];
                y(isnan(x),:)=[];

                x(isinf(x),:)=[];
                x(isnan(x),:)=[];

                range=x>=app.xlimits(1) & x<=app.xlimits(2);

                x=x(range,:);
                y=y(range,:);

                x(isinf(mean(y,2))|isnan(mean(y,2)),:)=[];
                y(isinf(mean(y,2))|isnan(mean(y,2)),:)=[];

                if  isempty(y) || isempty(x)
                    continue
                end

                if app.FilterCheckBox.Value==true %ATH Change manipulation
                    for k=1:app.FilterIterationSpinner.Value
                        if strcmp(app.FilterMethod_2.Value,'sgolay')
                            y=smoothdata(y,1,'sgolay',app.FilterSpan_2.Value,'Degree',app.FilterDegree_2.Value);
                        else
                            y=smoothdata(y,1,app.FilterMethod_2.Value,app.FilterSpan_2.Value);
                        end
                    end
                end

                if app.BackgroundCheckBox.Value==true

                    ignore_co2=app.IgnoreCO2CheckBox.Value;
                    value1=app.BackgroundDegreeSpinner.Value;
                    value2=app.BCvalueSpinner_2.Value;
                    method=app.BackgroundMethodDropDown.Value;
                    interp_method=app.BackgroundMethodDropDown_2.Value;
                    signal_split=1;
                    [y, baseline]=GetBaselineFun(x,y,signal_split,value1,value2,method,interp_method,ignore_co2);


                end

                if app.ResampleCheckBox.Value==true
                    for m=1:size(y,2)
                        [x_2,y(:,m)]=resample_bad_data(app,x,y(:,m),app.DegreeSpinner_2.Value,app.ThreshholdSpinner_2.Value,app.SamplingDistanceSpinner_2.Value);
                    end
                    x=x_2;
                end

                %% save the rawdata
                if not(isfield(app.SpecDB.Data.DB(eId(n)),'y_signal_raw')) || isempty(app.SpecDB.Data.DB(eId(n)).y_signal_raw)
                    app.SpecDB.Data.DB(eId(n)).y_signal_raw=app.SpecDB.Data.DB(eId(n)).y_signal;
                end

                if not(isfield(app.SpecDB.Data.DB(eId(n)),'x_signal_raw')) || isempty(app.SpecDB.Data.DB(eId(n)).x_signal_raw)
                    app.SpecDB.Data.DB(eId(n)).x_signal_raw=app.SpecDB.Data.DB(eId(n)).x_signal;
                end


                app.SpecDB.Data.DB(eId(n)).x_signal=x;
                app.SpecDB.Data.DB(eId(n)).y_signal=y;
                if exist('baseline','var')
                app.SpecDB.Data.DB(eId(n)).baseline=baseline;
                end
            end
            % ath maybe remove mapplotsettings to update all maps new?
            %             app.BackgroundCheckBox.Value=false;
            %             app.FilterCheckBox.Value=false;

            update_active_plot(app);
                                        app.SpecDB.edit_state=true;

            toc
        end

        % Button pushed function: ResetEditsButton
        function ResetEditsButtonPushed(app, event)
            [x, y, SampleName, Thickness, Id, eId ] = get_specs(app.SpecDB,app.selected_sample,false);
            
            for n=1:numel(eId)
                if (isfield(app.SpecDB.Data.DB(eId(n)),'y_signal_raw')) && not(isempty(app.SpecDB.Data.DB(eId(n)).y_signal_raw))
                    app.SpecDB.Data.DB(eId(n)).y_signal=app.SpecDB.Data.DB(eId(n)).y_signal_raw;
                    app.SpecDB.Data.DB(eId(n)).y_signal_raw=[];

                end

                if (isfield(app.SpecDB.Data.DB(eId(n)),'x_signal')) && not(isempty(app.SpecDB.Data.DB(eId(n)).x_signal_raw))
                    app.SpecDB.Data.DB(eId(n)).x_signal=app.SpecDB.Data.DB(eId(n)).x_signal_raw;
                    app.SpecDB.Data.DB(eId(n)).x_signal_raw=[];
                end

                if isfield(app.SpecDB.Data.DB(eId(n)),'baseline')
                    app.SpecDB.Data.DB(eId(n)).baseline=[];
                end

            end
            update_active_plot(app);
                                        app.SpecDB.edit_state=true;

        end

        % Button pushed function: ApplytoSelectedSpectraButton
        function ApplytoSelectedSpectraButtonPushed(app, event)
            tic

            [x_, y_, SampleName, Thickness, Id, eId] = get_specs(app.SpecDB,app.selected_sample,false);
            d = uiprogressdlg(app.SpecVisGUI,'Title','Editing Spectra',...
                'Message','Correcion started','Cancelable','on');
            drawnow

            for n=1:numel(y_)
                d.Value = n/numel(y_);
                if d.CancelRequested
                    break
                end
                d.Message = sprintf(['Correcting : \nSample: ' SampleName{n}]);
                try
                    x=x_{n};
                    y=y_{n};

                    y(isinf(x),:)=[];
                    y(isnan(x),:)=[];

                    x(isinf(x),:)=[];
                    x(isnan(x),:)=[];

                    range=x>=app.xlimits(1) & x<=app.xlimits(2);

                    x=x(range,:);
                    y=y(range,:);

                    x(isinf(mean(y,2,"omitnan"))|isnan(mean(y,2,"omitnan")),:)=[];
                    y(isinf(mean(y,2,"omitnan"))|isnan(mean(y,2,"omitnan")),:)=[];

                    if  isempty(y) || isempty(x)
                        continue
                    end

                    if app.ResampleCheckBox.Value==true
                        for m=1:size(y,2)
                            [x_2,y(:,m)]=resample_bad_data(app,x,y(:,m),app.DegreeSpinner_2.Value,app.ThreshholdSpinner_2.Value,app.SamplingDistanceSpinner_2.Value);
                        end
                        x=x_2;
                    end
                    if app.FilterCheckBox.Value==true %ATH Change manipulation
                        for k=1:app.FilterIterationSpinner.Value
                            if strcmp(app.FilterMethod_2.Value,'sgolay')
                                y=smoothdata(y,1,'sgolay',app.FilterSpan_2.Value,'Degree',app.FilterDegree_2.Value);
                            else
                                y=smoothdata(y,1,app.FilterMethod_2.Value,app.FilterSpan_2.Value);
                            end
                        end
                    end

                    if app.BackgroundCheckBox.Value==true
                        ignore_co2=app.IgnoreCO2CheckBox.Value;
                        value1=app.BackgroundDegreeSpinner.Value;
                        value2=app.BCvalueSpinner_2.Value;
                        method=app.BackgroundMethodDropDown.Value;
                        interp_method=app.BackgroundMethodDropDown_2.Value;
                        signal_split=1;
                        [y, baseline]=GetBaselineFun(x,y,signal_split,value1,value2,method,interp_method,ignore_co2);
                    else
                        baseline=zeros(size(y));
                    end


                    %% save the rawdata
                    if not(isfield(app.SpecDB.Data.DB(eId(n)),'y_signal_raw')) || isempty(app.SpecDB.Data.DB(eId(n)).y_signal_raw)
                        app.SpecDB.Data.DB(eId(n)).y_signal_raw=app.SpecDB.Data.DB(eId(n)).y_signal;
                    end

                    if not(isfield(app.SpecDB.Data.DB(eId(n)),'x_signal_raw')) || isempty(app.SpecDB.Data.DB(eId(n)).x_signal_raw)
                        app.SpecDB.Data.DB(eId(n)).x_signal_raw=app.SpecDB.Data.DB(eId(n)).x_signal;
                    end


                    app.SpecDB.Data.DB(eId(n)).x_signal=x;
                    app.SpecDB.Data.DB(eId(n)).y_signal=y;
                    app.SpecDB.Data.DB(eId(n)).baseline=baseline;


                catch ME
                    disp(SampleName{n}) 
                end
            end
            % ath maybe remove mapplotsettings to update all maps new?
            %             app.BackgroundCheckBox.Value=false;
            %             app.FilterCheckBox.Value=false;

            update_active_plot(app);
                                        app.SpecDB.edit_state=true;

            toc
        end

        % Button pushed function: ResetAllEditsButton_2
        function ResetAllEditsButton_2Pushed(app, event)
            selections = [app.SpecDB.Data.Meta(:).Id];
            [~, ~, ~, ~, ~, eId] = get_specs(app.SpecDB,selections,true);


            for n=1:numel(eId)
                if (isfield(app.SpecDB.Data.DB(eId(n)),'y_signal_raw')) && not(isempty(app.SpecDB.Data.DB(eId(n)).y_signal_raw))
                    app.SpecDB.Data.DB(eId(n)).y_signal=app.SpecDB.Data.DB(eId(n)).y_signal_raw;
                    app.SpecDB.Data.DB(eId(n)).y_signal_raw=[];
                end
                if (isfield(app.SpecDB.Data.DB(eId(n)),'x_signal_raw')) && not(isempty(app.SpecDB.Data.DB(eId(n)).x_signal_raw))
                    app.SpecDB.Data.DB(eId(n)).x_signal=app.SpecDB.Data.DB(eId(n)).x_signal_raw;
                    app.SpecDB.Data.DB(eId(n)).x_signal_raw=[];
                end
            end
            update_active_plot(app);
                                        app.SpecDB.edit_state=true;

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            update_active_plot(app);
            [x_, y_, SampleName, Thickness, Id, eId] = get_specs(app.SpecDB,app.selected_sample,true);
           tic
            for n=1:numel(y_)
                if Thickness{n}==0
                    Thickness{n}=app.norm_value;
                end
                x=x_{n};
                y=y_{n};

                y(isinf(x),:)=[];
                y(isnan(x),:)=[];

                x(isinf(x),:)=[];
                x(isnan(x),:)=[];

                range=x>=app.xlimits(1) & x<=app.xlimits(2);

                x=x(range,:);
                y=y(range,:);

                x(isinf(mean(y,2))|isnan(mean(y,2)),:)=[];
                y(isinf(mean(y,2))|isnan(mean(y,2)),:)=[];

                if  isempty(y) || isempty(x)
                    continue
                end


                if app.ResampleCheckBox.Value==true
                    for m=1:size(y,2)
                        [x_2,y(:,m)]=resample_bad_data(app,x,y(:,m),app.DegreeSpinner_2.Value,app.ThreshholdSpinner_2.Value,app.SamplingDistanceSpinner_2.Value);
                    end
                    x=x_2;
                end
                if app.FilterCheckBox.Value==true %ATH Change manipulation
                    for k=1:app.FilterIterationSpinner.Value
                        if strcmp(app.FilterMethod_2.Value,'sgolay')
                            y=smoothdata(y,1,'sgolay',app.FilterSpan_2.Value,'Degree',app.FilterDegree_2.Value);
                        else
                            y=smoothdata(y,1,app.FilterMethod_2.Value,app.FilterSpan_2.Value);
                        end
                    end
                end
                baseline=zeros(size(y));
                y_cor=zeros(size(y));

                if app.BackgroundCheckBox.Value==true
                    interp_method=app.BackgroundMethodDropDown_2.Value;
                    method=app.BackgroundMethodDropDown.Value;
           

                    ignore_co2=app.IgnoreCO2CheckBox.Value;
                    value1=app.BackgroundDegreeSpinner.Value;
                    value2=app.BCvalueSpinner_2.Value;
                    method=app.BackgroundMethodDropDown.Value;
                    interp_method=app.BackgroundMethodDropDown_2.Value;
                    signal_split=1;
                    [y, baseline]=GetBaselineFun(x,y,signal_split,value1,value2,method,interp_method,ignore_co2);

                    plot(app.SpectraPlot,x,(baseline./Thickness{n}).*app.norm_value,'--k','LineWidth',1.5)
                end
                plot(app.SpectraPlot,x,(y./Thickness{n}).*app.norm_value,':k','LineWidth',1.5)

            end
            % ath maybe remove mapplotsettings to update all maps new?
            %             app.BackgroundCheckBox.Value=false;
            %             app.FilterCheckBox.Value=false;

            toc
        end

        % Cell edit callback: Spec_table
        function Spec_tableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
%ATH Fix
% find out if allowed to change then change and update table
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            [x, ~, ~, ~, ~, ~ ] = get_specs(app.SpecDB,app.selected_sample,true);
          app.AutoYCheckBox.Value=true;

            min_wn=min(cellfun(@min,x));
            max_wn=max(cellfun(@max,x));
            app.EditField_xlimMax.Value=max_wn;
            app.EditField_xlimMin.Value=min_wn;
            EditField_LiveMinMaxValueChanged(app)
        end

        % Button pushed function: PopUpPlotButton
        function PopUpPlotButtonPushed(app, event)
               hfig=figure;
               hfig.Position(3:4)= hfig.Position(3:4).*2;

               ax = nexttile;
               ax2 = app.SpectraPlot;
               PopUpPlot_fun(app,ax,ax2)

               if false
                   figure
                   tiledlayout(1,2)
                   ax = nexttile;
                   ax2 = app.SpectraPlot;
                   app.EditField_xlimMax.Value=3800;
                   app.EditField_xlimMin.Value=3300;
                   app.Annotation_CheckBox.Value=false;
                   EditField_LiveMinMaxValueChanged(app)
                   PopUpPlot_fun(app,ax,ax2)


                   ax = nexttile;
                   ax2 = app.SpectraPlot;
                   app.EditField_xlimMax.Value=2300;
                   app.EditField_xlimMin.Value=1250;
                   app.Annotation_CheckBox.Value=true;
                   EditField_LiveMinMaxValueChanged(app)
                   PopUpPlot_fun(app,ax,ax2)
               end
               %title and legend
            update_active_plot(app);

        end

        % Button down function: PlotSettingsTable
        function PlotSettingsTableButtonDown(app, event)

      %S      mouse_position=get(0,'PointerLocation');

            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else
                time_between_clicks = toc( app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    c = uisetcolor;

                    if isequal(c,0)
                    return
                    end
                    figure(app.SpecVisGUI)

                    if any(isequal(unique(app.PlotSettingsTable.Selection(:,2)),5))
                        %    app.PlotSettingsTable.Data.Color(app.PlotSettingsTable.Selection(1,:))=repmat(c',1,numel(unique(app.PlotSettingsTable.Selection(1,:))));
                        app.PlotSettingsTable.Data.Color(app.PlotSettingsTable.Selection(:,1),:)=repmat(c,numel(unique(app.PlotSettingsTable.Selection(:,1))),1);
                        for n=1:numel(app.selected_sample)
                                       eId= find([app.SpecDB.Data.Meta(:).Id]==app.selected_sample(n));

                            if isfield (app.SpecDB.Data,'DeconData') && not(isempty(app.SpecDB.Data.DeconData)) && numel(app.SpecDB.Data.DeconData)>=eId && isfield(app.SpecDB.Data.DeconData(eId),'ActiveDeconGroup') && not(isempty(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup))
                                ActiveDeconGroup=app.SpecDB.Data.DeconData(eId).ActiveDeconGroup;
                            else
                                ActiveDeconGroup=1;
                            end


                            if any(app.PlotSettingsTable.Selection(:,1)<=8)
                                fns=fieldnames(app.SpecDB.Data.PlotSettings(1).Default);
                                fns=fns(not(ismember(fns,{'alpha','beta','gamma','DeconPeaksSingle'})));
                                fn=fns(app.PlotSettingsTable.Selection(:,1));
                                for h=1:numel(fn)
                                    app.SpecDB.Data.PlotSettings(eId).Custom(ActiveDeconGroup).(char(fn{h})).Color=deal(c);
                                end

                            else

                                color_ids=app.PlotSettingsTable.Selection(:,1)-8;
                                [app.SpecDB.Data.DeconData(eId).PlotSettings(ActiveDeconGroup).DeconSingle.Color(color_ids,:)]=repmat(c,numel(color_ids),1);
              
                            end
                            app.SpecDB.edit_state=true;
                        end
                        update_PlotSettingsTable(app,app.selected_sample);

                    end
                    %  end
                end % if double-click
            end % if empty
        end

        % Cell edit callback: PlotSettingsTable
        function PlotSettingsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            col_sel=indices(1,2);
            row_sel=indices(1,1);
            fns=fieldnames(app.SpecDB.Data.PlotSettings(1).Default);
            fns=fns(not(ismember(fns,{'alpha','beta','gamma'})));

            [~, ~, ~, ~, ~, eId] = get_specs(app.SpecDB,app.selected_sample,true);

            if col_sel==8
                if not(isnumeric(newData)) || newData>1 || newData<0 || isnan(newData)
                    message={'Wrong Input:','Alpha value must be between 0.0 and 1.0'};
                    uialert(app.SpecVisGUI,message,'Wrong Input','Icon','Error');
                    return
                end
            elseif col_sel==6
                if not(isfinite(newData)) || newData<=0
                    message={'Wrong Input:','Value should be a finite number greater than 0'};
                    uialert(app.SpecVisGUI,message,'Wrong Input','Icon','Error');
                    return
                end

            end

            for n=1:numel(eId)

                %PlotSettings_c=app.SpecDB.Data.PlotSettings(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).Custom(eId).DeconSingle;
                try
                    if isempty(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup)
                        app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup=1;

                    end
                catch
                    app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup=1;
                end
                if row_sel< numel(fns)%numel(app.SpecDB.Data.DeconData(eId).DeconInput(app.SpecDB.Data.DeconData(eId).ActiveDeconGroup).Peaks.PeakDB.PeakNames)
                    % if the edit data is not a deconvoluted peak save in Custom (eId)
                    if col_sel==1
                        %% Active
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).Active]=newData;
                    elseif col_sel==3
                        %%Legend
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).Legend]=newData;

                    elseif col_sel==4
                        %%LegendStr
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).LegendStr]={newData};

                    elseif col_sel==7
                        %% LineStyle
                        if strcmp(char(fns(row_sel)),'Peaks')
                            if not(ismember(newData,{'Line' , 'XLine'}))
                                 message={'Only the following options are allowed:','''Line'' , ''XLine'''};
                                uialert(app.SpecVisGUI,message,'Wrong Input','Icon','Error');
                          
                                newData='XLine';
                            end
                        else
                            if not(ismember(newData,{'-' , '--' , ':' , '-.' , 'none','A-' , 'A--' , 'A:' , 'A-.' , 'Anone',}))
                                message={'Only the following options are allowed:','''-'', ''--'', '':'', ''-.'', ''none'' ,''A-'' ,''A--'', ''A:'', ''A-.'', ''Anone'''};
                                uialert(app.SpecVisGUI,message,'Wrong Input','Icon','Error');
                                                                newData='-';
                            end
                        end
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).LineStyle]={newData};

                    elseif col_sel==6
                        %% LineWidth
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).LineWidth]=newData;

                    elseif col_sel==8
                        %% FaceAlpha
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(row_sel))).FaceAlpha]=newData;

                    end
                else

                    if col_sel==1
                        %% Active
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.Active(1+row_sel-numel(fns))]=newData;
                    elseif col_sel==3
                        %% Legend
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.Legend(1+row_sel-numel(fns))]=newData;

                    elseif col_sel==4
                        %% LegendStr
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.LegendStr{1+row_sel-numel(fns)}]=newData;

                    elseif col_sel==7
                        %% LineStyle
                        if not(ismember(newData,{'-' , '--' , ':' , '-.' , 'none','A-' , 'A--' , 'A:' , 'A-.' , 'Anone',}))
                            message={'Only the following options are allowed:','''-'', ''--'', '':'', ''-.'', ''none'' ,''A-'' ,''A--'', ''A:'', ''A-.'', ''Anone'''};
                            uialert(app.SpecVisGUI,message,'Wrong Input','Icon','Error');
                                                                                            newData='-';
                        end
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.LineStyle{1+row_sel-numel(fns)}]=newData;
                    elseif col_sel==6
                        %% LineWidth
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.LineWidth(1+row_sel-numel(fns))]=newData;
                    elseif col_sel==8
                        %% FaceAlpha
                        [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).DeconSingle.FaceAlpha(1+row_sel-numel(fns))]=newData;

                    end
                end
            end

            app.SpecDB.edit_state=true;

            update_PlotSettingsTable(app,app.selected_sample);


        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            eId=find(ismember([app.SpecDB.Data.Meta(:).Id],app.selected_sample));
            for n=1: numel(eId)
                try
                    app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup)=[];
                                catch
                end
                % PlotSettings(eId(n)).Custom = []

                %if isfield DeconData(eId(n)).PlotSettings(ActiveDeconGroup)
                %DeconData(eId(n)).PlotSettings(ActiveDeconGroup)=[]
                %end
            end

            update_PlotSettingsTable(app,app.selected_sample);
        end

        % Button pushed function: FlipOrderButton
        function FlipOrderButtonPushed(app, event)
            app.selected_sample=flip(app.selected_sample);
            update_selected_sample_listbox(app)

            update_PlotSettingsTable(app,app.selected_sample);
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            val= app.SelectedSampleListBox.Value;

            ids2sort= find(ismember(flip(app.SelectedSampleListBox.ItemsData),flip(val)));

            if ids2sort==numel(app.selected_sample)
                return
            elseif ids2sort==1
                after=2:numel(app.selected_sample);
                data2sort([after(1)  ids2sort  after(2:end) ]) =app.selected_sample;

            else
                befor=1:(ids2sort-1);

                after=(ids2sort)+1:numel(app.selected_sample);

                data2sort([befor after(1) ids2sort after(2:end)])=app.selected_sample;
            end

            app.selected_sample=(data2sort);
            update_PlotSettingsTable(app,app.selected_sample);
            update_selected_sample_listbox(app)
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)

            val= app.SelectedSampleListBox.Value;
            app.selected_sample=app.selected_sample;
            %  ids2sort= find(ismember(app.SelectedSampleListBox.ItemsData,val));
            ids2sort= find(ismember(flip(app.SelectedSampleListBox.ItemsData),flip(val)));

            if ids2sort==1
                return
            elseif ids2sort==numel(app.selected_sample)

                befor=1:numel(app.selected_sample)-1;
                data2sort([befor(1:end-1)  ids2sort  befor(end) ])=app.selected_sample;
            else
                befor=1:(ids2sort)-1;
                after=(ids2sort)+1:numel(app.selected_sample);

                data2sort([befor(1:end-1)  ids2sort  befor(end) after])=app.selected_sample;
            end


            app.selected_sample=(data2sort);
            update_PlotSettingsTable(app,app.selected_sample);
            update_selected_sample_listbox(app)
            %%


        end

        % Drop down opening function: AnnotationDropDown
        function AnnotationDropDownOpening(app, event)
         fns=fieldnames(app.SpecDB.Data.Meta(1));
            fns=fns(not(ismember(fns,{'Active'})));
            app.AnnotationDropDown.Items=fns;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            eId= find([app.SpecDB.Data.Meta(:).Id]==app.selected_sample(1));
            app.SpecDB.Data.DeconData(eId).ActiveDeconGroup= app.ListBox.Value;
            update_PlotSettingsTable(app,app.selected_sample);
            update_active_plot(app);

            app.SpecDB.edit_state=true;
        end

        % Button pushed function: FindBestR2Button
        function FindBestR2ButtonPushed(app, event)
            sampleID= find([app.SpecDB.Data.Meta(:).Id]==app.selected_sample(1));
            posbestr2=find(max([app.SpecDB.Data.DeconData(sampleID).DeconResults(:).error_r2])==[app.SpecDB.Data.DeconData(sampleID).DeconResults(:).error_r2]);

            if numel(posbestr2)>1
                posbestr2=posbestr2(1);
            end

            app.SpecDB.Data.DeconData(sampleID).ActiveDeconGroup=posbestr2;
            app.ListBox.Value=posbestr2;
            ListBoxValueChanged(app)
            app.SpecDB.edit_state=true;
        end

        % Button pushed function: LoadPeakDBButton
        function LoadPeakDBButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI


            [file,path]=uigetfile({'*.csv;*.txt;'},"MultiSelect","off", ['Select Metadata for' char('')]);
            delete(f)
            if not(isequal(file,0))
                [app.Peaks.PeakDB, app.Peaks.PeakDB_raw]=ReadPeakdata_fun(fullfile(path,file));
                update_PeakTree(app)

            else
                message='Error not a valid PeakDB file';
                uialert(app.SpecVisGUI,message,'Error');
            end
            update_PeakTree(app)

            figure(app.SpecVisGUI);
        end

        % Button pushed function: AddSinglePeakButton
        function AddSinglePeakButtonPushed2(app, event)
            fhv = app.SpecVisGUI.HandleVisibility;        % Current status
            app.SpecVisGUI.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.SpecVisGUI)       % Make fig current
            [x, ~] = selectDataPoints(app, app.SpectraPlot);

            x=round(x,1);
            app.SpecVisGUI.HandleVisibility = fhv;
            %Spec=app.datatable.SpecName(app.datatable.active==true); ath
            %add here multiple specnames option

            UIpeaks=x;

            if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                app.Peaks.PeakDB_UI=app.Peaks.PeakDB_raw;
            end

            for n=1:numel(UIpeaks)
                app.Peaks.PeakDB_UI.Id(end+1)=app.Peaks.PeakDB_UI.Id(end)+1;
                app.Peaks.PeakDB_UI.PeakPosition(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.RefPeakPosition(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.Defect_WN(end)=UIpeaks(n);
                app.Peaks.PeakDB_UI.PeakShift(end)=0;
                app.Peaks.PeakDB_UI.Use2Quant(end)=0;
                app.Peaks.PeakDB_UI.PeakNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.Element{end}='';
                app.Peaks.PeakDB_UI.PeakgroupNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.MineralNames{end}='UI Peak';
                app.Peaks.PeakDB_UI.R(end)=0;
                app.Peaks.PeakDB_UI.G(end)=0;
                app.Peaks.PeakDB_UI.B(end)=0;
                app.Peaks.PeakDB_UI.LineStyle{end}='A-';
                app.Peaks.PeakDB_UI.LineWidth(end)=1;
                app.Peaks.PeakDB_UI.Active(end)=1;
                app.Peaks.PeakDB_UI.FaceAlpha(end)=0.2;

            end

               [app.Peaks.PeakDB, ~]=ReadPeakdata_fun(app.Peaks.PeakDB_UI);
   update_PeakTree(app)
            UpdatePeakTable(app)
            update_active_plot(app);
        end

        % Button pushed function: AddPeaksButton
        function AddPeaksButtonPushed2(app, event)
            new_UI_peaks = inputdlg('Enter space-separated numbers:',...
                'Enter Peaks', [1 50]);
            if ~isempty(new_UI_peaks)
                %                 if not(isfield(app,'Peaks'))
                %                     app.Peaks=[];
                %                 end
                new_UI_peaks=unique(str2num(new_UI_peaks{1})); %#ok<ST2NM>

                if not( isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI)))
                    app.Peaks.PeakDB_UI=app.Peaks.PeakDB_raw;
                end

                for n=1:numel(new_UI_peaks)
                    app.Peaks.PeakDB_UI.Id(end+1)=app.Peaks.PeakDB_UI.Id(end)+1;
                    app.Peaks.PeakDB_UI.PeakPosition(end)=new_UI_peaks(n);
                    app.Peaks.PeakDB_UI.RefPeakPosition(end)=new_UI_peaks(n);
                    app.Peaks.PeakDB_UI.Defect_WN(end)=new_UI_peaks(n);
                    app.Peaks.PeakDB_UI.PeakShift(end)=0;
                    app.Peaks.PeakDB_UI.Use2Quant(end)=0;
                    app.Peaks.PeakDB_UI.PeakNames{end}='UI Peak';
                    app.Peaks.PeakDB_UI.Element{end}='';
                    app.Peaks.PeakDB_UI.PeakgroupNames{end}='UI Peak';
                    app.Peaks.PeakDB_UI.MineralNames{end}='UI Peak';
                    app.Peaks.PeakDB_UI.R(end)=0;
                    app.Peaks.PeakDB_UI.G(end)=0;
                    app.Peaks.PeakDB_UI.B(end)=0;
                    app.Peaks.PeakDB_UI.LineStyle{end}='A-';
                    app.Peaks.PeakDB_UI.LineWidth(end)=1;
                    app.Peaks.PeakDB_UI.Active(end)=1;
                    app.Peaks.PeakDB_UI.FaceAlpha(end)=0.2;
                end


             [app.Peaks.PeakDB, ~]=ReadPeakdata_fun(app.Peaks.PeakDB_UI);
   update_PeakTree(app)
                UpdatePeakTable(app)
                update_active_plot(app);
            end

            figure(app.SpecVisGUI);
        end

        % Button pushed function: ResetShiftButton
        function ResetShiftButtonPushed(app, event)
            app.Peaks.PeakDB_raw.PeakPosition=app.Peaks.PeakDB_raw.RefPeakPosition;
            shift=-(app.Peaks.PeakDB_raw.RefPeakPosition-app.Peaks.PeakDB_raw.PeakPosition);
            app.Peaks.PeakDB_raw.PeakShift=shift;

            if  isfield(app.Peaks,'PeakDB_UI') && istable(app.Peaks.PeakDB_UI) && not(isempty(app.Peaks.PeakDB_UI))
                app.Peaks.PeakDB_UI.PeakPosition=app.Peaks.PeakDB_UI.RefPeakPosition;
                shift=-(app.Peaks.PeakDB_UI.RefPeakPosition-app.Peaks.PeakDB_UI.PeakPosition);
                app.Peaks.PeakDB_UI.PeakShift=shift;
            end

            UpdatePeakTable(app)
            update_active_plot(app);
        end

        % Value changed function: FullTableCheckBox
        function FullTableCheckBoxValueChanged(app, event)
            UpdatePeakTable(app)
        end

        % Callback function: PeakTree
        function PeakTreeCheckedNodesChanged(app, event)
            UpdatePeakTable(app)
            update_active_plot(app);

        end

        % Value changed function: EditField_ylimMax, EditField_ylimMin
        function EditField_ylimMinValueChanged(app, event)
            app.SpectraPlot.YLim  = sort([app.EditField_ylimMin.Value app.EditField_ylimMax.Value]);
app.AutoYCheckBox.Value=false;
        end

        % Value changed function: FontSizeSpinner
        function FontSizeSpinnerValueChanged(app, event)
            app.SpectraPlot.FontSize= app.FontSizeSpinner.Value;
            if app.UseAdvancedSettingsCheckBox.Value==true
                app.FigureFontSizeSpinner.Value=app.FontSizeSpinner.Value;
            end
        end

        % Value changed function: FigureFontSizeSpinner
        function FigureFontSizeSpinnerValueChanged(app, event)
           app.SpectraPlot.FontSize= app.FigureFontSizeSpinner.Value;
        end

        % Cell edit callback: PeakTable
        function PeakTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: XLabelEditField
        function XLabelEditFieldValueChanged(app, event)
            app.SpectraPlot.XLabel.String = app.XLabelEditField.Value;
            
        end

        % Value changed function: YLabelEditField
        function YLabelEditFieldValueChanged(app, event)
            app.SpectraPlot.YLabel.String = app.YLabelEditField.Value;
            
        end

        % Value changed function: ZLabelEditField
        function ZLabelEditFieldValueChanged(app, event)
            app.SpectraPlot.ZLabel.String = app.ZLabelEditField.Value;
            
        end

        % Button pushed function: NexttliePlotButton
        function NexttliePlotButtonPushed(app, event)
            try
                if isempty(app.lastplot)%isvalid
                    hfig=figure;
                    hfig.Position(3:4)= hfig.Position(3:4).*2;

                    app.lastplot=hfig;

                else
                    hfig=app.lastplot;
                    figure(hfig);

                end
            catch
                hfig=figure;
                hfig.Position(3:4)= hfig.Position(3:4).*2;

                app.lastplot=hfig;
            end
                              
       %S   tiledlayout(hfig,1,2)

            ax = nexttile;
            ax2 = app.SpectraPlot;
            PopUpPlot_fun(app,ax,ax2)

            if false
                figure
                tiledlayout(1,2)
                ax = nexttile;
                ax2 = app.SpectraPlot;
                app.EditField_xlimMax.Value=3800;
                app.EditField_xlimMin.Value=3300;
                app.Annotation_CheckBox.Value=false;
                EditField_LiveMinMaxValueChanged(app)
                PopUpPlot_fun(app,ax,ax2)


                ax = nexttile;
                ax2 = app.SpectraPlot;
                app.EditField_xlimMax.Value=2300;
                app.EditField_xlimMin.Value=1250;
                app.Annotation_CheckBox.Value=true;
                EditField_LiveMinMaxValueChanged(app)
                PopUpPlot_fun(app,ax,ax2)
            end
            %title and legend
            update_active_plot(app);

        end

        % Button pushed function: LegendfromFieldButton
        function LegendfromFieldButtonPushed(app, event)
              %%
              eId=find(ismember([app.SpecDB.Data.Meta(:).Id],app.selected_sample));

              for n=1: numel(eId)
                  try
                      if isempty(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup)
                          app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup=1;

                      end
                  catch
                      app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup=1;
                  end

                  if isnumeric(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value)))
                      newData={num2str(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value)))};
                  elseif ischar(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value)))
                      newData={app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value))};
                  elseif iscell(app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value)))
                      newData=app.SpecDB.Data.Meta(eId(n)).(char(app.AnnotationDropDown_2.Value));
                  end
                  try
                      app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).MeanSpectrum.LegendStr=newData;
                      app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).MeanSpectrum.Legend=true;
                  catch ME
                      ME
                      eId(n)
                  end
                  %       [app.SpecDB.Data.PlotSettings(eId(n)).Custom(app.SpecDB.Data.DeconData(eId(n)).ActiveDeconGroup).(char(fns(2))).LegendStr]=newData;
              end


              update_PlotSettingsTable(app,app.selected_sample);


        end

        % Drop down opening function: AnnotationDropDown_2
        function AnnotationDropDown_2Opening(app, event)
            fns=fieldnames(app.SpecDB.Data.Meta(1));
            fns=fns(not(ismember(fns,{'Active'})));
            app.AnnotationDropDown_2.Items=fns;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecVisGUI = uifigure('Visible', 'off');
            app.SpecVisGUI.Name = 'SpecVis';
            app.SpecVisGUI.CloseRequestFcn = createCallbackFcn(app, @SpecVisGUICloseRequest, true);
            app.SpecVisGUI.WindowButtonUpFcn = createCallbackFcn(app, @SpecVisGUIWindowButtonUp, true);
            app.SpecVisGUI.WindowButtonMotionFcn = createCallbackFcn(app, @SpecVisGUIWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.SpecVisGUI);
            app.Plot_Exp_Set_Panel = uitabgroup(app.GridLayout);
            app.PlotSettingsTab = uitab(app.Plot_Exp_Set_Panel);
            app.PlotSettingsTab.Title = 'Plot Settings';
            app.GridLayout_PlotSettings = uigridlayout(app.PlotSettingsTab);
            app.FigXlimLive = uiaxes(app.GridLayout_PlotSettings);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.Peaks_CheckBox = uicheckbox(app.GridLayout_PlotSettings);
            app.Peaks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Peaks_CheckBoxValueChanged, true);
            app.Peaks_CheckBox.Text = 'Peaks';
            app.Peaks_CheckBox.Value = true;
            app.Annotation_CheckBox = uicheckbox(app.GridLayout_PlotSettings);
            app.Annotation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.Annotation_CheckBox.Text = 'Annotation';
            app.LegendCheckBox = uicheckbox(app.GridLayout_PlotSettings);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.LegendCheckBox.Text = {'Legend'; ''};
            app.EditField_xlimMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMin.RoundFractionalValues = 'on';
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveMinMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.EditField_xlimMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMax.RoundFractionalValues = 'on';
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveMinMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.xlimMax_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+ 1';
            app.xlimMax_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+10';
            app.xlimMax_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '- 1';
            app.xlimMax_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-10';
            app.xlimMin_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-10';
            app.xlimMin_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '- 1';
            app.xlimMin_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+ 1';
            app.xlimMin_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+10';
            app.TypeDropDown = uidropdown(app.GridLayout_PlotSettings);
            app.TypeDropDown.Items = {'Default', 'Stacked', '3D'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.TypeDropDown.Value = 'Default';
            app.ResetButton = uibutton(app.GridLayout_PlotSettings, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.YOffsetSpinnerLabel = uilabel(app.GridLayout_PlotSettings);
            app.YOffsetSpinnerLabel.Text = 'Y Offset [%]';
            app.YOffsetSpinner = uispinner(app.GridLayout_PlotSettings);
            app.YOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.YOffsetSpinner.Value = 5;
            app.AnnotationDropDown = uidropdown(app.GridLayout_PlotSettings);
            app.AnnotationDropDown.Items = {'SampleName'};
            app.AnnotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnnotationDropDownOpening, true);
            app.AnnotationDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.AnnotationDropDown.Value = 'SampleName';
            app.EditField_ylimMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_ylimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_ylimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_ylimMinValueChanged, true);
            app.EditField_ylimMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_ylimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_ylimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_ylimMinValueChanged, true);
            app.EditField_ylimMax.Value = 1;
            app.FontSizeSpinnerLabel = uilabel(app.GridLayout_PlotSettings);
            app.FontSizeSpinnerLabel.Text = 'FontSize';
            app.FontSizeSpinner = uispinner(app.GridLayout_PlotSettings);
            app.FontSizeSpinner.Limits = [1 Inf];
            app.FontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontSizeSpinnerValueChanged, true);
            app.FontSizeSpinner.Value = 20;
            app.Decon_CheckBox = uicheckbox(app.GridLayout_PlotSettings);
            app.Decon_CheckBox.Text = 'Decon';
            app.AutoYCheckBox = uicheckbox(app.GridLayout_PlotSettings);
            app.AutoYCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.AutoYCheckBox.Text = 'Auto Y';
            app.AutoYCheckBox.Value = true;
            app.AdvancedTab = uitab(app.Plot_Exp_Set_Panel);
            app.AdvancedTab.Title = 'Advanced';
            app.GridLayout21 = uigridlayout(app.AdvancedTab);
            app.UseAdvancedSettingsCheckBox = uicheckbox(app.GridLayout21);
            app.UseAdvancedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.UseAdvancedSettingsCheckBox.Text = 'Use Advanced Settings';
            app.UpdateButton = uibutton(app.GridLayout21, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PeakLineColorCheckBox = uicheckbox(app.GridLayout21);
            app.PeakLineColorCheckBox.Text = 'PeakLineColor';
            app.FigureFontSizeSpinnerLabel = uilabel(app.GridLayout21);
            app.FigureFontSizeSpinnerLabel.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner = uispinner(app.GridLayout21);
            app.FigureFontSizeSpinner.Limits = [1 Inf];
            app.FigureFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner.Value = 15;
            app.PeakLableFontSizeSpinnerLabel = uilabel(app.GridLayout21);
            app.PeakLableFontSizeSpinnerLabel.Text = 'PeakLable FontSize';
            app.PeakLableFontSizeSpinner = uispinner(app.GridLayout21);
            app.PeakLableFontSizeSpinner.Limits = [1 Inf];
            app.PeakLableFontSizeSpinner.Value = 15;
            app.AnnotationFontSizeSpinnerLabel = uilabel(app.GridLayout21);
            app.AnnotationFontSizeSpinnerLabel.Text = 'Annotation FontSize';
            app.AnnotationFontSizeSpinner = uispinner(app.GridLayout21);
            app.AnnotationFontSizeSpinner.Limits = [1 Inf];
            app.AnnotationFontSizeSpinner.Value = 15;
            app.AnnotationInterpreterDropDownLabel = uilabel(app.GridLayout21);
            app.AnnotationInterpreterDropDownLabel.Text = 'Annotation Interpreter';
            app.AnnotationInterpreterDropDown = uidropdown(app.GridLayout21);
            app.AnnotationInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.AnnotationInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.AnnotationInterpreterDropDown.Value = 'none';
            app.XLabelEditFieldLabel = uilabel(app.GridLayout21);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.GridLayout21, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.XLabelEditField.Value = 'Wavenumbers [cm^{-1}]';
            app.XLabelEditField_2Label = uilabel(app.GridLayout21);
            app.XLabelEditField_2Label.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.GridLayout21, 'text');
            app.YLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YLabelEditFieldValueChanged, true);
            app.YLabelEditField.Value = 'Absorbance [cm^{-1}]';
            app.YLabelEditField_2Label = uilabel(app.GridLayout21);
            app.YLabelEditField_2Label.Text = 'Z Label';
            app.ZLabelEditField = uieditfield(app.GridLayout21, 'text');
            app.ZLabelEditField.ValueChangedFcn = createCallbackFcn(app, @ZLabelEditFieldValueChanged, true);
            app.ZLabelEditField.Value = 'Sample';
            app.ExportTab = uitab(app.Plot_Exp_Set_Panel);
            app.ExportTab.Title = 'Export';
            app.GridLayout19 = uigridlayout(app.ExportTab);
            app.CheckBox_fig_exp = uicheckbox(app.GridLayout19);
            app.CheckBox_fig_exp.Text = '.fig';
            app.CheckBox_fig_exp.Value = true;
            app.SaveButton = uibutton(app.GridLayout19, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectDirButton = uibutton(app.GridLayout19, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            app.CheckBox_png_exp = uicheckbox(app.GridLayout19);
            app.CheckBox_png_exp.Text = '.png';
            app.CheckBox_png_exp.Value = true;
            app.CheckBox_pdf_exp = uicheckbox(app.GridLayout19);
            app.CheckBox_pdf_exp.Text = '.pdf';
            app.CheckBox_pdf_exp.Value = true;
            app.CheckBox_eps_exp = uicheckbox(app.GridLayout19);
            app.CheckBox_eps_exp.Text = '.eps';
            app.CheckBox_eps_exp.Value = true;
            app.DirectoryEditFieldLabel = uilabel(app.GridLayout19);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.PathEditField = uieditfield(app.GridLayout19, 'text');
            app.CustomStrEditFieldLabel = uilabel(app.GridLayout19);
            app.CustomStrEditFieldLabel.Text = 'Custom Str';
            app.CustStrEditField = uieditfield(app.GridLayout19, 'text');
            app.CheckBox_jpg_exp = uicheckbox(app.GridLayout19);
            app.CheckBox_jpg_exp.Text = '.jpg';
            app.CheckBox_jpg_exp.Value = true;
            app.FilenameLabel = uilabel(app.GridLayout19);
            app.FilenameLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout19, 'text');
            app.FilenameEditField.Value = 'SpecVis_Export';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.SpectraTab = uitab(app.TabGroup2);
            app.SpectraTab.Title = 'Spectra';
            app.GridLayout6 = uigridlayout(app.SpectraTab);
            app.Spec_table = uitable(app.GridLayout6);
            app.Spec_table.ColumnName = {'active'; 'Id'; 'SpecName'; 'Sample'; 'Database'};
            app.Spec_table.RowName = {};
            app.Spec_table.ColumnEditable = [true false false false true];
            app.Spec_table.CellEditCallback = createCallbackFcn(app, @Spec_tableCellEdit, true);
            app.GridLayout12_2 = uigridlayout(app.GridLayout6);
            app.FilterCheckBox = uicheckbox(app.GridLayout12_2);
            app.FilterCheckBox.Text = '';
            app.FilterSpan_2 = uispinner(app.GridLayout12_2);
            app.FilterSpan_2.Limits = [1 10000];
            app.FilterSpan_2.Tooltip = {'Window'};
            app.FilterSpan_2.Value = 8;
            app.BackgroundCheckBox = uicheckbox(app.GridLayout12_2);
            app.BackgroundCheckBox.Text = 'Background';
            app.FilterMethod_2 = uidropdown(app.GridLayout12_2);
            app.FilterMethod_2.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess ', 'rloess', 'sgolay'};
            app.FilterMethod_2.ItemsData = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.FilterMethod_2.Value = 'movmean';
            app.FilterIterationSpinner = uispinner(app.GridLayout12_2);
            app.FilterIterationSpinner.Limits = [1 10000];
            app.FilterIterationSpinner.Tooltip = {'Number of iterations'};
            app.FilterIterationSpinner.Value = 1;
            app.FilterDegree_2 = uispinner(app.GridLayout12_2);
            app.FilterDegree_2.Limits = [1 10000];
            app.FilterDegree_2.Editable = 'off';
            app.FilterDegree_2.Tooltip = {'Degree'};
            app.FilterDegree_2.Value = 1;
            app.BackgroundMethodDropDown = uidropdown(app.GridLayout12_2);
            app.BackgroundMethodDropDown.Items = {'Rubberband NG', 'Rubberband', 'Rubberband boundary (slow but better)', 'msbackadj', 'interp1', 'Polynominal', 'linear', 'linCorrect2', 'linCorrect', 'linear2', 'Auto Raman Baseline'};
            app.BackgroundMethodDropDown.ItemsData = {'Rubberband NG', 'Rubberband', 'Rubberband boundary (slow but better)', 'msbackadj', 'interp1', 'Polynominal', 'linear', 'linCorrect2', 'linCorrect', 'linear2', 'Auto Raman Baseline'};
            app.BackgroundMethodDropDown.Value = 'Rubberband';
            app.BackgroundDegreeSpinner = uispinner(app.GridLayout12_2);
            app.BackgroundDegreeSpinner.Step = 0.02;
            app.BackgroundDegreeSpinner.Limits = [0 1];
            app.BackgroundDegreeSpinner.Tooltip = {'Degree'};
            app.BackgroundDegreeSpinner.Value = 0.1;
            app.BackgroundMethodDropDown_2 = uidropdown(app.GridLayout12_2);
            app.BackgroundMethodDropDown_2.Items = {'linear', 'nearest', 'next', 'previous', 'pchip', 'cubic', 'v5cubic', 'makima', 'spline'};
            app.BackgroundMethodDropDown_2.ItemsData = {'linear', 'nearest', 'next', 'previous', 'pchip', 'cubic', 'v5cubic', 'makima', 'spline'};
            app.BackgroundMethodDropDown_2.Value = 'linear';
            app.ApplytoallSpectraButton = uibutton(app.GridLayout12_2, 'push');
            app.ApplytoallSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallSpectraButtonPushed, true);
            app.ApplytoallSpectraButton.Text = 'Apply to all Spectra';
            app.ResetEditsButton = uibutton(app.GridLayout12_2, 'push');
            app.ResetEditsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEditsButtonPushed, true);
            app.ResetEditsButton.Text = 'Reset Edits';
            app.ApplytoSelectedSpectraButton = uibutton(app.GridLayout12_2, 'push');
            app.ApplytoSelectedSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSelectedSpectraButtonPushed, true);
            app.ApplytoSelectedSpectraButton.Text = 'Apply to Selected Spectra';
            app.SamplingDistanceSpinner_2 = uispinner(app.GridLayout12_2);
            app.SamplingDistanceSpinner_2.Limits = [0 Inf];
            app.SamplingDistanceSpinner_2.Tooltip = {'Sampling Distance'};
            app.SamplingDistanceSpinner_2.Value = 5;
            app.ThreshholdSpinner_2 = uispinner(app.GridLayout12_2);
            app.ThreshholdSpinner_2.Step = 0.0001;
            app.ThreshholdSpinner_2.Limits = [0 Inf];
            app.ThreshholdSpinner_2.Value = 0.0002;
            app.DegreeSpinner_2 = uispinner(app.GridLayout12_2);
            app.DegreeSpinner_2.Tooltip = {'Degree'};
            app.ResampleCheckBox = uicheckbox(app.GridLayout12_2);
            app.ResampleCheckBox.Text = 'Resample';
            app.ResetAllEditsButton_2 = uibutton(app.GridLayout12_2, 'push');
            app.ResetAllEditsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetAllEditsButton_2Pushed, true);
            app.ResetAllEditsButton_2.Text = 'Reset All Edits';
            app.PreviewButton = uibutton(app.GridLayout12_2, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.IgnoreCO2CheckBox = uicheckbox(app.GridLayout12_2);
            app.IgnoreCO2CheckBox.Text = 'Ignore CO2';
            app.BCvalueSpinner_2 = uispinner(app.GridLayout12_2);
            app.BCvalueSpinner_2.Limits = [1 10000];
            app.BCvalueSpinner_2.Tooltip = {'BC Value'};
            app.BCvalueSpinner_2.Value = 1;
            app.PeaksTab = uitab(app.TabGroup2);
            app.PeaksTab.Title = 'Peaks';
            app.GridLayout4 = uigridlayout(app.PeaksTab);
            app.PeakTable = uitable(app.GridLayout4);
            app.PeakTable.ColumnName = {'PeakPosition'; 'PeakIntensity'; 'Width'; 'Prominence'; 'Spec'; 'Source'};
            app.PeakTable.RowName = {};
            app.PeakTable.ColumnSortable = [true true true true true true];
            app.PeakTable.ColumnEditable = [false false true true false false];
            app.PeakTable.CellEditCallback = createCallbackFcn(app, @PeakTableCellEdit, true);
            app.AddSinglePeakButton = uibutton(app.GridLayout4, 'push');
            app.AddSinglePeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddSinglePeakButtonPushed2, true);
            app.AddSinglePeakButton.Text = 'Add Single Peak';
            app.DeletePeakButton = uibutton(app.GridLayout4, 'push');
            app.DeletePeakButton.Visible = 'off';
            app.DeletePeakButton.Text = 'Delete Peak';
            app.ClearPeaksButton = uibutton(app.GridLayout4, 'push');
            app.ClearPeaksButton.Visible = 'off';
            app.ClearPeaksButton.Text = 'Clear Peaks';
            app.PeakPreviewSwitchLabel = uilabel(app.GridLayout4);
            app.PeakPreviewSwitchLabel.Text = 'Peak Preview';
            app.PeakPreviewSwitch = uiswitch(app.GridLayout4, 'slider');
            app.PeakPreviewSwitch.Value = 'On';
            app.PeakDispDropDown = uidropdown(app.GridLayout4);
            app.PeakDispDropDown.Items = {'Xline', 'Line'};
            app.PeakDispDropDown.Value = 'Xline';
            app.AddPeaksButton = uibutton(app.GridLayout4, 'push');
            app.AddPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddPeaksButtonPushed2, true);
            app.AddPeaksButton.Text = 'Add Peaks';
            app.PeakTree = uitree(app.GridLayout4, 'checkbox');
            % Assign Checked Nodes
            app.PeakTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PeakTreeCheckedNodesChanged, true);
            app.SavePeaksButton = uibutton(app.GridLayout4, 'push');
            app.SavePeaksButton.Visible = 'off';
            app.SavePeaksButton.Text = 'Save Peaks';
            app.Peaks_CheckBox_2 = uicheckbox(app.GridLayout4);
            app.Peaks_CheckBox_2.Text = '';
            app.Peaks_CheckBox_2.Value = true;
            app.DerivativeSpinnerLabel_7 = uilabel(app.GridLayout4);
            app.DerivativeSpinnerLabel_7.Text = '      Peaks';
            app.LoadPeakDBButton = uibutton(app.GridLayout4, 'push');
            app.LoadPeakDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeakDBButtonPushed, true);
            app.LoadPeakDBButton.Text = 'Load PeakDB';
            app.FullTableCheckBox = uicheckbox(app.GridLayout4);
            app.FullTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @FullTableCheckBoxValueChanged, true);
            app.FullTableCheckBox.Text = 'Full Table';
            app.ResetShiftButton = uibutton(app.GridLayout4, 'push');
            app.ResetShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ResetShiftButtonPushed, true);
            app.ResetShiftButton.Text = 'Reset Shift';
            app.PlotSettingsTab_2 = uitab(app.TabGroup2);
            app.PlotSettingsTab_2.Title = 'Plot Settings';
            app.GridLayout20 = uigridlayout(app.PlotSettingsTab_2);
            app.PopUpPlotButton = uibutton(app.GridLayout20, 'push');
            app.PopUpPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PopUpPlotButtonPushed, true);
            app.PopUpPlotButton.Text = 'Pop Up Plot';
            app.PlotSettingsTable = uitable(app.GridLayout20);
            app.PlotSettingsTable.ColumnName = {'Active'; 'Object'; 'Legend'; 'LegendStr'; 'Color'; 'LineWidth'; 'LineStyle'; 'FaceAlpha'};
            app.PlotSettingsTable.RowName = {};
            app.PlotSettingsTable.ColumnEditable = [true false true true false true true true];
            app.PlotSettingsTable.CellEditCallback = createCallbackFcn(app, @PlotSettingsTableCellEdit, true);
            app.PlotSettingsTable.ButtonDownFcn = createCallbackFcn(app, @PlotSettingsTableButtonDown, true);
            app.ResettoDefaultButton = uibutton(app.GridLayout20, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.SelectedSampleListBox = uilistbox(app.GridLayout20);
            app.SelectedSampleListBox.Items = {};
            app.SelectedSampleListBox.Value = {};
            app.FlipOrderButton = uibutton(app.GridLayout20, 'push');
            app.FlipOrderButton.ButtonPushedFcn = createCallbackFcn(app, @FlipOrderButtonPushed, true);
            app.FlipOrderButton.Text = 'Flip Order';
            app.MoveupButton = uibutton(app.GridLayout20, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.GridLayout20, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.NexttliePlotButton = uibutton(app.GridLayout20, 'push');
            app.NexttliePlotButton.ButtonPushedFcn = createCallbackFcn(app, @NexttliePlotButtonPushed, true);
            app.NexttliePlotButton.Text = 'Nexttlie Plot';
            app.AnnotationDropDown_2 = uidropdown(app.GridLayout20);
            app.AnnotationDropDown_2.Items = {'SampleName'};
            app.AnnotationDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @AnnotationDropDown_2Opening, true);
            app.AnnotationDropDown_2.Value = 'SampleName';
            app.LegendfromFieldButton = uibutton(app.GridLayout20, 'push');
            app.LegendfromFieldButton.ButtonPushedFcn = createCallbackFcn(app, @LegendfromFieldButtonPushed, true);
            app.LegendfromFieldButton.Text = 'Legend from Field';
            app.SpectraListBox = uilistbox(app.GridLayout);
            app.SpectraListBox.Items = {};
            app.SpectraListBox.Multiselect = 'on';
            app.SpectraListBox.ValueChangedFcn = createCallbackFcn(app, @SpectraListBoxValueChanged, true);
            app.SpectraListBox.Value = {};
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.FindBestR2Button = uibutton(app.GridLayout, 'push');
            app.FindBestR2Button.ButtonPushedFcn = createCallbackFcn(app, @FindBestR2ButtonPushed, true);
            app.FindBestR2Button.Text = 'Find Best R2';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdatePlotPushed, true);
            app.SpectraPlotTab = uitab(app.TabGroup);
            app.SpectraPlotTab.Title = 'SpectraPlot';
            app.GridLayout17 = uigridlayout(app.SpectraPlotTab);
            app.SpectraPlot = uiaxes(app.GridLayout17);
            xlabel(app.SpectraPlot, 'Wavenumbers [cm^{-1}]')
            ylabel(app.SpectraPlot, 'Absorbance [cm^{-1}]')
            zlabel(app.SpectraPlot, 'Sample')
            app.SpectraPlot.XLim = [500 4000];
            app.SpectraPlot.XDir = 'reverse';
            % Show the figure after all components are created
            app.SpecVisGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecVis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpecVisGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecVisGUI)
        end
    end
end