classdef SpotVisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpotVisModuleUIFigure         matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        MapGenDropDown                matlab.ui.control.DropDown
        AutoUpdateButton              matlab.ui.control.StateButton
        ResetButton_3                 matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        ResetButton_2                 matlab.ui.control.Button
        IntRangeLabel                 matlab.ui.control.Label
        EditField_xlimMax             matlab.ui.control.NumericEditField
        ntoplotEditFieldLabel         matlab.ui.control.Label
        Int_range_max                 matlab.ui.control.NumericEditField
        SetasXLimitButton             matlab.ui.control.Button
        Int_range_min                 matlab.ui.control.NumericEditField
        ntoplotEditField              matlab.ui.control.NumericEditField
        EditField_xlimMin             matlab.ui.control.NumericEditField
        LinBCButton                   matlab.ui.control.StateButton
        MeanButton                    matlab.ui.control.StateButton
        SpecsButton                   matlab.ui.control.StateButton
        FigXlimLive                   matlab.ui.control.UIAxes
        EditField_climMax             matlab.ui.control.NumericEditField
        EditField_climMin             matlab.ui.control.NumericEditField
        SpotsizeSpinner               matlab.ui.control.Spinner
        SpotsizeSpinnerLabel          matlab.ui.control.Label
        SpectraButton                 matlab.ui.control.StateButton
        IdEditField                   matlab.ui.control.NumericEditField
        IdEditFieldLabel              matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        BEditField                    matlab.ui.control.NumericEditField
        BEditFieldLabel               matlab.ui.control.Label
        GEditField                    matlab.ui.control.NumericEditField
        GEditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        InterpolationButton           matlab.ui.control.StateButton
        SampleDropDown                matlab.ui.control.DropDown
        SpotTransparencySlider        matlab.ui.control.Slider
        SpotTransparencySliderLabel   matlab.ui.control.Label
        REditField_2                  matlab.ui.control.NumericEditField
        REditField_2Label             matlab.ui.control.Label
        GEditField_2                  matlab.ui.control.NumericEditField
        GEditField_2Label             matlab.ui.control.Label
        BEditField_2                  matlab.ui.control.NumericEditField
        BEditField_2Label             matlab.ui.control.Label
        LableColorDropDown            matlab.ui.control.DropDown
        LableColorDropDownLabel       matlab.ui.control.Label
        LablesButton                  matlab.ui.control.StateButton
        SpotColorDropDown             matlab.ui.control.DropDown
        SpotColorDropDownLabel        matlab.ui.control.Label
        SpotsButton                   matlab.ui.control.StateButton
        ImageButton                   matlab.ui.control.StateButton
        ListBox                       matlab.ui.control.ListBox
        FigHistLive                   matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SpecButton                    matlab.ui.control.StateButton
        GroupsPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        DropDown                      matlab.ui.control.DropDown
        GroupMeanButton               matlab.ui.control.StateButton
        GroupSpecsButton              matlab.ui.control.StateButton
        SelectinTableButton           matlab.ui.control.Button
        ToggleAllButton               matlab.ui.control.Button
        GroupListBox                  matlab.ui.control.ListBox
        ResetMask                     matlab.ui.control.Image
        InvertMask                    matlab.ui.control.Image
        UnmaskSelection               matlab.ui.control.Image
        MaskSelection                 matlab.ui.control.Image
        ColormapPanel                 matlab.ui.container.Panel
        GridLayout_2                  matlab.ui.container.GridLayout
        Options_InvertColormap        matlab.ui.control.CheckBox
        Options_ColormapDropDown      matlab.ui.control.DropDown
        ColormapDropDownLabel_2       matlab.ui.control.Label
        Options_LogColormap           matlab.ui.control.CheckBox
        Options_ColormapResEditField  matlab.ui.control.NumericEditField
        Options_UpperColor            matlab.ui.control.DropDown
        Options_UpperCheckBox         matlab.ui.control.CheckBox
        Options_LowerColor            matlab.ui.control.DropDown
        Options_LowerCheckBox         matlab.ui.control.CheckBox
        Options_DispNegativeValues    matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        UIAxes_small                  matlab.ui.control.UIAxes
        SpecPlot_big                  matlab.ui.control.UIAxes
        SpecPlot_small                matlab.ui.control.UIAxes
        UIAxes_big                    matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        EditMenu                      matlab.ui.container.Menu
        ExportTableMenu               matlab.ui.container.Menu
        Classification2GroupsMenu     matlab.ui.container.Menu
        PlotProfileMenu               matlab.ui.container.Menu
        CallibrateThicknessExperimentalMenu  matlab.ui.container.Menu
        ExtractGroupsMenu             matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Rename_ImageMenu              matlab.ui.container.Menu
        AdjustImagePositionMenu       matlab.ui.container.Menu
        ResetImagePositionMenu        matlab.ui.container.Menu
        ShowImageCommentMenu          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        SpecDB % Description
        hVerticalLines
        hLineToDrag
        clickbuffer=0.05 % Description
        selected_sample
        selected_image=1
        selected_image_group
        n_spots
        climits
        xlimits
        CX_Lim_state
        exportpath='';
reset_value=false

        ColorMaps
        ColorMapValues
        ColorScale
        newnew

        Specs_of_interest % Description
        LastImg_Adjust=[0 0] % Description
    end

    methods (Access = private)

        function update_lines(app)
            delete(findobj(app.FigXlimLive.Children,'Type','ConstantLine'));
            delete(findobj(app.FigHistLive.Children,'Type','ConstantLine'));

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                app.hVerticalLines(1) = xline(app.FigXlimLive,app.Int_range_max.Value,'Color','red',"LineWidth",3);
                set(app.hVerticalLines(1), 'hittest', 'off')

                  app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','k',"LineWidth",3)...
                    xline(app.FigHistLive, app.EditField_climMin.Value,'Color','k',"LineWidth",3)];
                set(app.hVerticalLines(3:4), 'hittest', 'off')
 

            else
                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
                set(app.hVerticalLines(1:2), 'hittest', 'off')

                app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','k',"LineWidth",3)...
                    xline(app.FigHistLive, app.EditField_climMin.Value,'Color','k',"LineWidth",3)];
                set(app.hVerticalLines(3:4), 'hittest', 'off')
            end
        end

        function update_UI(app)
            %% 1) update the list of Samples
            selections=find(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id})));
            a=string({app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).Id}');

            b1={app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).SampleName};
            b2=not(cellfun(@ischar,{app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).SampleName}'));
            b1(b2)={''};
            b1=string(b1)';
            SampleNames=join([a,b1],'_');

            Id_t=ismember([app.SpecDB.Data.Meta(not(cellfun(@isempty,{app.SpecDB.Data.Meta.Id}))).Id],app.SpecDB.DB_table.Data.Id);
            selections=selections(Id_t);
            SampleNames=SampleNames(Id_t);
            % a=string(app.SpecDB.DB_table.Data.Id);
            %          b=string(app.SpecDB.DB_table.Data.SampleName);
            %          SampleNames=join([a,b],'_');
            %          selections=app.SpecDB.DB_table.Data.Id;


            app.SampleDropDown.Items=SampleNames;
            app.SampleDropDown.ItemsData=selections;
            if isempty(app.SampleDropDown.Value)
                app.SampleDropDown.Value=selections(1);
            end
            app.selected_sample=app.SampleDropDown.Value;
            app.IdEditField.Value=app.SpecDB.Data.Meta(app.selected_sample).Id;

            %% 2) check for meta table and n specs

            if not(isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'MetaTable')) ||isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable) ||  not(istable(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable)) || not(any(strcmp('Fileindex',app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Properties.VariableNames))) || not(size(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Fileindex(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Fileindex>0),1) == size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2))

                meta_tbl=table();
                app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable=meta_tbl;

            else
                meta_tbl=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Fileindex>0,:);
            end

            n_specs=size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2);
            if n_specs==1
                state='single_spec';
            else
                state='multi_spec';
            end

            %% 3) check for groups and types
            if any(strcmp('Group',meta_tbl.Properties.VariableNames))
                app.GroupListBox.Items=string((unique(meta_tbl.Group)));
                app.GroupListBox.ItemsData=unique(meta_tbl.Group);

                if numel(unique(meta_tbl.Group))>1
                    vis_state=true;
                    app.GroupListBox.Value=app.GroupListBox.ItemsData;

                else
                    vis_state=false;
                end

            else
                app.GroupListBox.Items={};
                app.GroupListBox.ItemsData=[];
                vis_state=false;
            end
            %this part needs to move in update plots function start
              app.GroupsPanel.Visible=vis_state;
            %this part needs to move in update plots function end


            %% 4) check if xy coordinates
            if any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  any(strcmp('x',meta_tbl.Properties.VariableNames))  && any(strcmp('y',meta_tbl.Properties.VariableNames))
             %   app.SpotsButton.Value=true;
                app.SpotsButton.Enable=true;
                app.InterpolationButton.Enable=true;
             %   app.LablesButton.Value=true;
             app.LablesButton.Enable=true;
            else
                app.SpotsButton.Value=false;
                app.SpotsButton.Enable=false;
                app.InterpolationButton.Enable=false;
                app.InterpolationButton.Value=false;

                app.LablesButton.Value=false;
                app.LablesButton.Enable=false;
            end

            %% 5) update the list of Images
            if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'ImageGroup_Id')
                app.selected_image_group=app.SpecDB.Data.MetaPrivat(app.selected_sample).ImageGroup_Id;
            else
                app.selected_image_group=[];
            end
            if not(isfield(app.SpecDB.Data,'Images')) || not(isfield(app.SpecDB.Data.Images,'Images')) || not(isfield(app.SpecDB.Data.Images.Images,'ImageNames')) || isempty(app.selected_image_group) || not(isfield(app.SpecDB.Data.Images.Images(app.selected_image_group),'ImageNames')) || isempty(app.SpecDB.Data.Images.Images(app.selected_image_group).ImageNames)
                app.ListBox.Items={};
                app.ListBox.Visible=false;
                app.ImageButton.Value=false;
                app.ImageButton.Enable=false;
                app.selected_image=[];
            else
                Image_Ids=[app.SpecDB.Data.MetaPrivat(not(cellfun(@isempty,{app.SpecDB.Data.MetaPrivat.ImageGroup_Id}))).ImageGroup_Id];
                app.selected_image_group=app.SpecDB.Data.MetaPrivat(app.selected_sample).ImageGroup_Id;

                Image_Names=app.SpecDB.Data.Images.Images(app.selected_image_group).ImageNames;


                try
                    if isempty(Image_Names)
                        'no image detected'
                        app.ListBox.Visible=false;
                        app.ImageButton.Enable=false;
                    else
                        app.ListBox.Visible=true;
                        app.ImageButton.Enable=true;
                        app.ListBox.Items=Image_Names;
                        app.ListBox.ItemsData=1:numel(Image_Names);
                        app.ListBox.Value=Image_Ids(1);
                        app.selected_image=app.ListBox.Value;

                    end
                catch
                    'error in image list generation'
                end

            end

            %% 6) update the table
            update_table(app)

            %% 7) update visible status of axis the plots

            update_plots(app);

        end

        function update_table(app)
            %             try
            meta_tbl=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;
            region=[app.Int_range_min.Value,app.Int_range_max.Value];
            if isempty(meta_tbl) || not(istable(meta_tbl)) || not(any(strcmp('Fileindex',meta_tbl.Properties.VariableNames))) || not(size(meta_tbl.Fileindex(meta_tbl.Fileindex>0),1) == size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2))
                n_specs=size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2);
                meta_tbl=table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});
                if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'MetaTableRaw') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw))
                    app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;
                end
                %ATH to fix - if fpa generate .x .y .Group during import 
            else
                meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);
            end

            if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Thickness') && numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness)==1
                Thickness=ones(size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2),1).*app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness;
            elseif isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Thickness') && numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness)==size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2)
                Thickness= app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness;
            else
                Thickness=ones(size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2),1).*app.SpecDB.Data.Meta(app.selected_sample).Thickness;
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness=Thickness;
            end

            if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask)) && numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask) == size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2)
                mask=app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask;
            else
                mask=true(size(meta_tbl,1),1);
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask=mask;
            end
            try
                integration=get_integration(app,region,app.selected_sample,false)';
            catch ME

                disp(ME.message)
                selection = uiconfirm(app.SpotVisModuleUIFigure,'Do you want to reset the integration range?','Integration error',...
                    'Icon','warning');
                if strcmp(selection,'OK')
                    try
                        reset_int_x(app)
                    catch ME
                        disp(ME.message)
                    end
                else
                    return
                end
            end
            app.UITable.Data=table(mask,meta_tbl.Fileindex,Thickness,integration,meta_tbl.Group,VariableNames={'active','SpecIndex','Thickness','Value','Group'}); % Copy this
            if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'MaskComment') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).MaskComment))
                app.UITable.Data.Comment(:)=app.SpecDB.Data.MetaPrivat(app.selected_sample).MaskComment;

            else
                app.UITable.Data.Comment(:)={''};
                app.SpecDB.Data.MetaPrivat(app.selected_sample).MaskComment(:)={''};

            end

           try

               X=meta_tbl.x;
               Y=meta_tbl.y;
               app.UITable.Data.x_pos(:)=X;
               app.UITable.Data.y_pos(:)=Y;

           catch
               app.UITable.Data.x_pos(:)={''};
               app.UITable.Data.y_pos(:)={''};
           end

           if isempty(meta_tbl)
               app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable=meta_tbl;
            end
            %             catch
            %                 message=('Error Table update failed - Different number of Spectra in Measurements');
            %                 uialert(app.SpotVisModuleUIFigure,message,'Error');
            %             end
        end

        function [axis2plot_map, axis2plot_spectra]=update_plots(app,no_update)
%             axis2plot_map
%             axis2plot_spectra
            if app.SpectraButton.Value==true && app.ImageButton.Value==false && app.SpotsButton.Value==false && app.InterpolationButton.Value==false && app.LablesButton.Value==false && app.GroupsPanel.Visible==false
                axis2plot_spectra=app.UIAxes_big;
            elseif app.SpectraButton.Value==false
                axis2plot_spectra=[];
            elseif app.SpectraButton.Value==true && app.GroupsPanel.Visible==true 
                axis2plot_spectra=app.SpecPlot_small;
            elseif app.SpectraButton.Value==true && app.GroupsPanel.Visible==false 
                axis2plot_spectra=app.SpecPlot_big;
            end

            if isempty(axis2plot_spectra)  && app.GroupsPanel.Visible==false && (app.ImageButton.Value==true || app.SpotsButton.Value==true || app.InterpolationButton.Value==true|| app.LablesButton.Value==true)
                axis2plot_map=app.UIAxes_big;
            elseif (app.GroupsPanel.Visible==true || not(isempty(axis2plot_spectra)) ) && (app.ImageButton.Value==true || app.SpotsButton.Value==true || app.InterpolationButton.Value==true || app.LablesButton.Value==true)
                axis2plot_map=app.UIAxes_small;
            else
                axis2plot_map=[];
            end

            if exist("no_update","var")&&no_update==true
return
            end

            cla(app.UIAxes_small)
            cla(app.UIAxes_big)
            cla(app.SpecPlot_big)
            cla(app.SpecPlot_small)

            app.UIAxes_small.Visible=false;
            app.UIAxes_big.Visible=false;
            app.SpecPlot_big.Visible=false;
            app.SpecPlot_small.Visible=false;

            app.UIAxes_small.Toolbar.Visible=false;
            app.UIAxes_big.Toolbar.Visible=false;
            app.SpecPlot_big.Toolbar.Visible=false;
            app.SpecPlot_small.Toolbar.Visible=false;


            if not(isempty(app.UIAxes_small.Colorbar))
                app.UIAxes_small.Colorbar.Visible=false;
            end
            if not(isempty(app.UIAxes_big.Colorbar))

                app.UIAxes_big.Colorbar.Visible=false;
            end

            axis2plot_map.Visible=true;
            axis2plot_spectra.Visible=true;
            axis2plot_map.Toolbar.Visible=true;
            axis2plot_spectra.Toolbar.Visible=true;

            axis(app.UIAxes_big, 'normal')
            axis(app.UIAxes_small, 'normal')
            axis(app.SpecPlot_big, 'normal')
            axis(app.SpecPlot_small, 'normal')


            plot_image(app,axis2plot_map)
            plot_spots(app,axis2plot_map)
            plot_spec(app,axis2plot_spectra)


        end

        function plot_image(app,axis2plot)
            if isstruct(axis2plot)
                return
            end
            axis2plot.XDir='normal';

            if app.ImageButton.Value==true && isfield(app.SpecDB.Data,'Images') && isfield(app.SpecDB.Data.Images,'Images') && not(isempty(app.SpecDB.Data.Images.Images(app.selected_image_group)))
                for k= 1:numel(app.selected_image)
                    img=app.SpecDB.Data.Images.Images(app.selected_image_group).Image{app.selected_image(k)};
                    if isempty(img)
                        message='Error No Image found';
                        % uialert(app.SpotVisModuleUIFigure,message,'Error');
                        disp(message);
                        return
                    end

                    ImageLimits=app.SpecDB.Data.Images.Images(app.selected_image_group).ImageLimits{app.selected_image(k)};
                    if isempty(size(ImageLimits)) || not(isequal(size(ImageLimits),[2 2]))
                        message='Error No Image Info found';
                        uialert(app.SpotVisModuleUIFigure,message,'Error');
                        return
                    end

                    imagesc(axis2plot,flipud(img),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));
                    axis(axis2plot, 'equal')
                end
            end
        end

        function plot_spec(app,axis2plot)
            if isstruct(axis2plot)
                return
            end

            if app.GroupsPanel.Visible==true && numel(app.GroupListBox.Value)>0
                [x,y]=get_spec2plot(app,app.selected_sample,false,true);

                cmap = PlotMap_UpdateColormap(app,numel(app.GroupListBox.Items));
                cmap = cmap(ismember(app.GroupListBox.ItemsData,app.GroupListBox.Value),:);
                for n=1:numel(app.GroupListBox.Value)
                    y1=y;
                    mask= app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value(n));
                    y1=y1(:,mask);
                    if app.GroupSpecsButton.Value==true
                        if size(y1,2)>100
                            datapoints=1:size(y1,2);
                            if numel(datapoints)>=app.ntoplotEditField.Value && not(app.ntoplotEditField.Value==0)
                                samplepoints=sort(randperm(numel(datapoints),app.ntoplotEditField.Value));
                                samplepoints=datapoints(samplepoints);
                            else
                                samplepoints=datapoints;
                            end

                            plot(axis2plot,x,y1(:,samplepoints),"Color",cmap(n,:))
                        else
                            if size(x,1)== size(y1,1) && not(isempty(y1))
                                plot(axis2plot,x,y1,"Color",cmap(n,:))
                                %                             else
                                %                                 disp('error plotting xy different size')
                            end
                        end

                    end

                    if app.GroupMeanButton.Value==true
                        y_mean=mean(y1,2,"omitnan");
                        plot(axis2plot,x,y_mean ,"LineWidth",2,"Color",  cmap(n,:))
                    end
                end


                if app.SpecButton.Value==true && not(isempty(app.Specs_of_interest))
                    for n=1:numel(app.GroupListBox.Value)
                        mask= app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value(n));
                        % GoI=ismember(app.UITable.Data.Group,app.GroupListBox.Value(n));
                        SoI=ismember( app.UITable.Data.SpecIndex,app.Specs_of_interest);
                        if sum(SoI & mask)>0
                        plot(axis2plot,x,y(:,SoI & mask),"Color",cmap(n,:),'linewidth',3)
                        end
                    end
                end
          
        else
                [x,y]=get_spec2plot(app,app.selected_sample,true,true);

                if app.SpecsButton.Value==true
                    if size(y,2)>100
                        datapoints=1:size(y,2);
                        if numel(datapoints)>=app.ntoplotEditField.Value && not(app.ntoplotEditField.Value==0)
                            samplepoints=sort(randperm(numel(datapoints),app.ntoplotEditField.Value));
                            samplepoints=datapoints(samplepoints);
                        else
                            samplepoints=datapoints;
                        end

                        plot(axis2plot,x,y(:,samplepoints))
                    else
                        plot(axis2plot,x,y)
                    end
                end

                if app.MeanButton.Value==true
                    y_mean=mean(y,2,"omitnan");
                    plot(axis2plot,x,y_mean ,"LineWidth",2,"Color",'k')
                end

                if app.SpecButton.Value==true && not(isempty(app.Specs_of_interest))
                    mask= app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask==true;
                    SoI=ismember( app.UITable.Data.SpecIndex,app.Specs_of_interest);
                    if sum(SoI & mask)>0
                        plot(axis2plot,x,y(:,SoI & mask),"Color",'k','linewidth',3)
                    end
                end

                %%

            end
            xlim(axis2plot,sort(app.xlimits))
            try
                ylim(axis2plot,[min(y(x>min(app.xlimits)&x<max(app.xlimits),samplepoints)) max(x>min(app.xlimits)&x<max(app.xlimits),samplepoints)])
            catch
                ylim(axis2plot,'auto')
            end
            axis2plot.XDir='reverse';
             update_plot_cmap(app,axis2plot)
        end

        function plot_spots(app,axis2plot,PlotSpots)
            if isstruct(axis2plot)
                return
            end
            if not(exist("PlotSpots","var"))
                PlotSpots=true;
            end

            axis2plot.XDir='normal';

            if (app.SpotsButton.Value==true || app.InterpolationButton.Value==true) && PlotSpots==true
                meta_tbl=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;

                if isempty(meta_tbl) || not(istable(meta_tbl)) || not(any(strcmp('Fileindex',meta_tbl.Properties.VariableNames))) || not(size(meta_tbl.Fileindex(meta_tbl.Fileindex>0),1) == size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2))
                    message='Error No Metadata found';
                    n_specs=size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2);
                            meta_tbl=table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});
                            %app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable=meta_tbl;
                        else
                            meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);
                        end

                        if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask))
                            mask=logical(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask);
                        else
                            mask=true(size(meta_tbl,1),1);
                            app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask=mask;
                        end

                        if app.GroupsPanel.Visible==true
                            if numel(app.GroupListBox.Value)>0
                                mask=mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value);
                            end
                        end


                        if any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  any(strcmp('x',meta_tbl.Properties.VariableNames))  && any(strcmp('y',meta_tbl.Properties.VariableNames))
                            meta_x=meta_tbl.x;
                            meta_g=meta_tbl.Group;
                            meta_y=meta_tbl.y;
                        else
                            meta_x=[];
                            meta_g=[];
                            meta_y=[];
                        end
                        if app.InterpolationButton.Value==true
                            region=[app.Int_range_min.Value,app.Int_range_max.Value];

                            integration=get_integration(app,region,app.selected_sample,false);
                            x=linspace(min(meta_x(mask)),max(meta_x(mask)),ceil(sqrt(numel(integration))));
                            y=linspace(min(meta_y(mask)),max(meta_y(mask)),ceil(sqrt(numel(integration))));
                            [Xq,Yq] = meshgrid(x,y);
                            integration(integration==0)=nan;
                            F=scatteredInterpolant(meta_x,meta_y,integration');
                            vq1 = F(Xq,Yq);


                            imagesc(axis2plot,vq1,'XData',[min(meta_x(mask)),max(meta_x(mask))],'YData',[min(meta_y(mask)),max(meta_y(mask))],AlphaData=app.SpotTransparencySlider.Value/100)
                            %   contourf(app.UIAxes_big,integration,'XData',meta_x,'YData',meta_y,AlphaData=app.SpotTransparencySlider.Value/100)
                            % contourf(app.UIAxes_big,meta_x,meta_y,integration,LineStyle="none")
                            xlim(axis2plot,'auto')
                            ylim(axis2plot,'auto')
                            axis(axis2plot, 'equal')
                                                                        colorbar(axis2plot)

                        end
                        if app.SpotsButton.Value==true
                            switch app.SpotColorDropDown.Value
                                case {'Black','White','Red','Green','Blue','Custom'}
                                    switch app.SpotColorDropDown.Value
                                        case 'Black'
                                            c=[0 0 0];
                                        case 'White'
                                            c=[1 1 1];
                                        case 'Red'
                                            c=[1 0 0];
                                        case 'Green'
                                            c=[0 1 0];
                                        case 'Blue'
                                            c=[0 0 1];
                                        case 'Custom'
                                            c=[app.REditField.Value app.GEditField.Value app.BEditField.Value];
                                    end
                                    plot(axis2plot,meta_x,meta_y,MarkerFaceColor=c,MarkerSize=15,LineStyle="none",Marker="o")
                                otherwise
                                    switch app.SpotColorDropDown.Value
                                        case 'Value'
                                            if not(exist('integration','var')) || not(numel(meta_x(mask))==numel(integration))
                                            region=[app.Int_range_min.Value,app.Int_range_max.Value];
                                            integration=get_integration(app,region,app.selected_sample,true);
                                            end
                                            sz=integration;
                                            colorbar(axis2plot)
                                        case 'Group'
                                            mask=mask&(ismember(app.UITable.Data.Group,app.GroupListBox.Value));
                                            sz=meta_g(mask);
                                            try
                                            caxis(axis2plot,[min(meta_g) max(meta_g)]);
                                            catch

                                            end
                                    end

                                    if app.GroupsPanel.Visible==true
                                        if numel(app.GroupListBox.Value)>0
                                            mask=mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value);
                                            caxis(axis2plot,[min(meta_g(not(isnan(meta_g)))) max(meta_g(not(isnan(meta_g))))]);

                                        end
                                    end

                                    if not(numel(meta_x(mask))==numel(sz))
                                        message=('Error Table update failed - Different number of Spectra in Measurements');
                                        uialert(app.SpotVisModuleUIFigure,message,'Error');

                                    else

%                                         try
%                                             px_size=app.SpecDB.Data.Meta(app.selected_sample).Aperture;
%                                         catch
%                                             px_size=50;
%                                         end
%                                         Axis2Plot=update_plots(app,true);
% 
%                                         originalUnits = Axis2Plot.Units;
%                                         Axis2Plot.Units = 'Points';
%                                         axSizePix = Axis2Plot.Position(3:4);
%                                         Axis2Plot.Units = originalUnits;
% 
%                                         pixPerUnit = axSizePix ./ [range(xlim(Axis2Plot)),range(ylim(Axis2Plot))];  %pixels per unit
%                                         pxsz=ceil(max(pixPerUnit)*px_size)^2;
% 
%                                         app.SpotsizeSpinner.Value=pxsz;


                                        scatter(axis2plot,meta_x(mask)',meta_y(mask)',app.SpotsizeSpinner.Value,sz',"filled",'MarkerEdgeAlpha',1,'MarkerFaceAlpha',app.SpotTransparencySlider.Value/100,Marker='square')
                                        xlim(axis2plot,'auto')
                                        ylim(axis2plot,'auto')
                                        axis(axis2plot, 'equal')

                                    end

                            end
                            update_plot_cmap(app,axis2plot)
                        end
            end

            if app.LablesButton.Value==true
                meta_tbl=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;
                meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);


                if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask))
                    mask=app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask;
                else

                    mask=true(size(meta_tbl,1),1);
                    app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask=mask;
                end

                if app.GroupsPanel.Visible==true
                    if numel(app.GroupListBox.Value)>0
                        mask=mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value);
                    end
                end


                meta_tbl=meta_tbl(meta_tbl.Fileindex>0 & mask,:);
                meta_id=meta_tbl.Fileindex;
                meta_id_s=string(sprintfc('%d',meta_id))';

                datapoints=1:numel(meta_id);
                if numel(meta_id)>=500
                    samplepoints=sort(randperm(numel(datapoints),500));
                    samplepoints=datapoints(samplepoints);
                else
                    samplepoints=datapoints;
                end

                switch app.LableColorDropDown.Value
                    case 'Black'
                        c=[0 0 0];
                    case 'White'
                        c=[1 1 1];
                    case 'Red'
                        c=[1 0 0];
                    case 'Green'
                        c=[0 1 0];
                    case 'Blue'
                        c=[0 0 1];
                    case 'Custom'
                        c=[app.REditField_2.Value app.GEditField_2.Value app.BEditField_2.Value];
                end

                if any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  any(strcmp('x',meta_tbl.Properties.VariableNames))  && any(strcmp('y',meta_tbl.Properties.VariableNames))
                    meta_x=meta_tbl.x;
                    meta_y=meta_tbl.y;
                else
                    meta_x=[];
                    meta_y=[];
                end

                if not(isempty(meta_x)) && not(isempty(meta_y)) && not(isempty(meta_id_s))
                    text(axis2plot,meta_x(samplepoints),meta_y(samplepoints),meta_id_s(samplepoints),HorizontalAlignment="center",VerticalAlignment='middle',Color=c)
                end

            end
        end

        function [x,y]=get_spec2plot(app,sample,mask_state,limit_state)

            if isfield(app.SpecDB.Data.MetaPrivat(sample),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(sample).Mask)) && mask_state == true
                mask=app.SpecDB.Data.MetaPrivat(sample).Mask';
                if app.GroupsPanel.Visible==true
                    if numel(app.GroupListBox.Value)>0
                        mask=mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value)';
                    end
                end
                if not(any(mask))
                    x=nan;
                    y=nan;
                    disp('no mask')
                    return
                end
                x=app.SpecDB.Data.DB(sample).x_signal;
                y=app.SpecDB.Data.DB(sample).y_signal(:,mask);
                % thickness=app.SpecDB.Data.Meta(idx).Thickness(mask);
                if numel(mask)==numel(app.SpecDB.Data.Meta(sample).Thickness)
                    thickness=app.SpecDB.Data.Meta(sample).Thickness(mask);
                else
                    thickness=app.SpecDB.Data.Meta(sample).Thickness;
                end

            else
                x=app.SpecDB.Data.DB(sample).x_signal;
                y=app.SpecDB.Data.DB(sample).y_signal;
                thickness=app.SpecDB.Data.Meta(sample).Thickness;
            end
            if iscell(thickness)
                thickness=thickness{:};
                if isstring(thickness) ||ischar(thickness)
                    thickness=str2double(thickness);
                end
            end
            thickness(thickness==0)=10000;
            y=y./(thickness./10000);
if limit_state==true
            y=y(x>=min(app.xlimits) & x<=max(app.xlimits),:);
            x=x(x>=min(app.xlimits) & x<=max(app.xlimits),:);
end
            if isempty(x)
                x=nan;
                y=nan;
                return
            end

        end

        function update_FigXlimLive(app)
            %check if spec else return
            cla(app.FigXlimLive)
            idx = (app.selected_sample);

            if isfield(app.SpecDB.Data.MetaPrivat(idx),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(idx).Mask))
                mask=app.SpecDB.Data.MetaPrivat(idx).Mask';
                if not(any(mask))
                    return
                end
                x=app.SpecDB.Data.DB(idx).x_signal;
                y=app.SpecDB.Data.DB(idx).y_signal(:,mask);
                % thickness=app.SpecDB.Data.Meta(idx).Thickness(mask);
                if numel(mask)==numel(app.SpecDB.Data.Meta(idx).Thickness)
                    thickness=app.SpecDB.Data.Meta(idx).Thickness(mask);
                else
                    thickness=app.SpecDB.Data.Meta(idx).Thickness;
                end

            else
                x=app.SpecDB.Data.DB(idx).x_signal;
                y=app.SpecDB.Data.DB(idx).y_signal;
                thickness=app.SpecDB.Data.Meta(idx).Thickness;
            end
            if iscell(thickness)
                thickness=thickness{:};
                if isstring(thickness) ||ischar(thickness)
                    thickness=str2double(thickness);
                end
            end
            thickness(thickness==0)=10000;
            y=y./(thickness./10000);

            if app.LinBCButton.Value==true
                y=y(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                x=x(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                try
                    y=linCorrect(y);
                catch
                    0;
                end
            end


                if app.SpecsButton.Value==true
                    if size(y,2)>100

                        datapoints=1:size(y,2);
                        if numel(datapoints)>=app.ntoplotEditField.Value && not(app.ntoplotEditField.Value==0)
                            samplepoints=sort(randperm(numel(datapoints),app.ntoplotEditField.Value));
                            samplepoints=datapoints(samplepoints);
                        else
                            samplepoints=datapoints;
                        end

                        plot(app.FigXlimLive,x,y(:,samplepoints))
                    else
                        plot(app.FigXlimLive,x,y)
                    end
                end

                if app.MeanButton.Value==true
                    y_mean=mean(y,2,"omitnan");
                    plot(app.FigXlimLive,x,y_mean ,"LineWidth",2,"Color",'k')
                end


                if app.reset_value==true ||  app.EditField_xlimMin.Value == app.EditField_xlimMax.Value  || isempty(app.hVerticalLines) || isempty(ishandle(app.hVerticalLines(1))) || isempty(ishandle(app.hVerticalLines(2)))
                    app.EditField_xlimMin.Value=min(x(not(isnan(x))));
                    app.EditField_xlimMax.Value=max(x(not(isnan(x))));
                    xlim(app.FigXlimLive,[app.EditField_xlimMin.Value app.EditField_xlimMax.Value])
                    ylim(app.FigXlimLive,'auto')
                    app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

                    app.reset_value=false;
                    update_plots(app);
                else
                    xlim(app.FigXlimLive,sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value ]))
                    ylim(app.FigXlimLive,'auto')
                end


%                 
%                 if isempty(app.hVerticalLines)||isempty(ishandle(app.hVerticalLines(1))) || isempty(ishandle(app.hVerticalLines(2)))|| ishandle(app.hVerticalLines(1))==false||ishandle(app.hVerticalLines(2))==false
%                     app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
%                         xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
%                           set(app.hVerticalLines(1:2), 'hittest', 'off')
% 
%                 end

        end

        function  update_FigHistLive(app)
            cla(app.FigHistLive)
            idx = (app.selected_sample);

            if isfield(app.SpecDB.Data.MetaPrivat(idx),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(idx).Mask))
                mask=app.SpecDB.Data.MetaPrivat(idx).Mask';
                if not(any(mask))
                    return
                end
                x=app.SpecDB.Data.DB(idx).x_signal;
                y=app.SpecDB.Data.DB(idx).y_signal(:,mask);
                % thickness=app.SpecDB.Data.Meta(idx).Thickness(mask);
                if numel(mask)==numel(app.SpecDB.Data.Meta(idx).Thickness)
                    thickness=app.SpecDB.Data.Meta(idx).Thickness(mask);
                else
                    thickness=app.SpecDB.Data.Meta(idx).Thickness;
                end

            else
                x=app.SpecDB.Data.DB(idx).x_signal;
                y=app.SpecDB.Data.DB(idx).y_signal;
                thickness=app.SpecDB.Data.Meta(idx).Thickness;
            end
            if iscell(thickness)
                thickness=thickness{:};
                if isstring(thickness) ||ischar(thickness)
                    thickness=str2double(thickness);
                end
            end
            thickness(thickness==0)=10000;
            y=y./(thickness./10000);

            if app.LinBCButton.Value==true
                y=y(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                %x=x(x>=app.EditField_xlimMin.Value & x<=app.EditField_xlimMax.Value,:);
                try
                    y=linCorrect(y);
                catch
                    0
                end
            end


                region=sort([app.Int_range_min.Value,app.Int_range_max.Value]);
                integration=get_integration(app,region,app.selected_sample,true);
                num_bars=20;
                [N, EDGES] = histcounts(integration(:),num_bars); %// use two-output version of hist to get values
                EDGES=linspace(EDGES(1),EDGES(end),num_bars);
                a_h=area(app.FigHistLive,EDGES,N,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
                set(a_h, 'hittest', 'off');
                %EDGES=EDGES(2:end-1);
                xlimits_test = [min(EDGES) max(EDGES)];
                app.FigHistLive.XLim=xlimits_test;

                if app.reset_value==true ||app.EditField_climMax.Value > max(xlimits_test) || app.EditField_climMin.Value < min(xlimits_test)
                    app.climits=xlimits_test;
                    app.EditField_climMin.Value=app.climits(1);
                    app.EditField_climMax.Value=app.climits(2);
                    PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                    app.reset_value=false;
                else
                    app.climits(1)=min([app.EditField_climMin.Value app.EditField_climMax.Value]);
                    app.climits(2)=max([app.EditField_climMin.Value app.EditField_climMax.Value]);
                end

                update_lines(app)
%                 app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','k',"LineWidth",3)...
%                 xline(app.FigHistLive, app.EditField_climMin.Value,'Color','k',"LineWidth",3)];
%             set(app.hVerticalLines(3:4), 'hittest', 'off')

        end

        function integration=get_integration(app,region,sample,mask_state)

            if not(exist("mask_state","var"))
                mask_state=false;
            end
            sample=app.SpecDB.Data.Meta(sample).Id;

            [x, y, ~, Thickness] = get_specs(app.SpecDB,sample);

            %             if Thickness{:}==0
            %                 str_thx=sprintf('%.0f,' , sample);
            %                 message=['Error No Thickness Set for: ' str_thx(1:end-1)];
            %                 uialert(app.SpotVisModuleUIFigure,message,'Error');
            %                 %return
            %             end

            y_signal=((y{1}./Thickness{1}).*10000); 

            if mask_state &&  isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'Mask') && not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask))
                mask=app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask;
                if app.GroupsPanel.Visible==true
                    if numel(app.GroupListBox.Value)>0
                        mask=mask==true & ismember(app.UITable.Data.Group,app.GroupListBox.Value);
                    end
                end
            end

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                region=app.Int_range_max.Value;
            end
            
            [matrix,MapType,MapName]= CalcMatrixFromSpec(x{1},y_signal,app.MapGenDropDown.Value,region,[size(y{1},2) 1],[] ,[],true);
            if mask_state
                integration=matrix(mask);

            else
                integration=matrix;

            end

        end
        %cmap
        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,no_update)

            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));
            if app.Options_InvertColormap.Value
                ColorData =app.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            if app.Options_LogColormap.Value
                app.ColorScale = 'log';
            else
                app.ColorScale = 'linear';
            end

            % update
            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
            end
        end

        function ReadColorMaps(app)
            fid = fopen('XMap_ColorMaps.txt','r');
            Compt = 0;

            app.ColorMaps(1).Name = 'None';
            app.ColorMaps(1).Code = 0;

            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Compt = Compt+1;
                        app.ColorMaps(Compt).Name = tline(3:end);
                        Row = 0;
                        while 1
                            tline = fgetl(fid);
                            if isequal(tline,-1) || isequal(tline,'')
                                break
                            end
                            NUM = strread(tline,'%f');
                            Row = Row+1;
                            app.ColorMaps(Compt).Code(Row,1:3) = NUM(1:3);
                        end

                    end
                end
            end
            fclose(fid);

            % Update the menu
            Items=cell(1,numel(app.ColorMaps));
            for i = 1:numel(app.ColorMaps)
                Items{i} = app.ColorMaps(i).Name;
            end
            app.Options_ColormapDropDown.Items =  Items; %extractfield(ColorMaps,'Name');
        end
        function update_plot_cmap(app,axis2plot)
           
            if isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                caxis(axis2plot,'auto');
            else
                caxis(axis2plot,sort(app.climits));
            end
            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            colormap(axis2plot,app.ColorMapValues);
            axis2plot.ColorScale = app.ColorScale;

        end

        % cmap end

        function reset_int_x(app)
            [x,~]=get_spec2plot(app,app.selected_sample,false,false);
            app.Int_range_max.Value=max(x(not(isnan(x))));
            app.Int_range_min.Value=min(x(not(isnan(x))));

            Int_range_maxValueChanged(app)
            SetasXLimitButtonPushed(app)
        end

        
        function reset_zoom_pan(app)
            try
                zoom(app.UIAxes_big,'off')
                pan(app.UIAxes_big,'off')
                zoom(app.SpecPlot_small,'off')
                pan(app.SpecPlot_small,'off')
                zoom(app.SpecPlot_big,'off')
                pan(app.SpecPlot_big,'off')
                zoom(app.UIAxes_small,'off')
                pan(app.UIAxes_small,'off')
              
            catch ME
                disp('Error - SpotVisModule reset zoom / pan')
                disp(ME)
            end

        end
    end
    
    methods (Access = public)
        
        function ClassificationReturn(app,Classification,sampleinfo)
 %return fun
            % ask if groups should be overwritten

            % write class numbers and names in
            % app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Group
            % update everything
            n=1;
            m=1;
            c=Classification.Classes(n).ClassMap(m).classNumber;
            c(isnan(c))=max(c(not(isnan(c)))+1);
            app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Group(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Group>0)=c;

            update_UI(app)
            update_plots(app);
            %             update_FigXlimLive(app)
            update_FigHistLive(app)
            disp('DONE')

        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, xlimits, int_range)
            app.SpecDB=mainapp;

            app.Int_range_max.Value=max(int_range);
            app.EditField_xlimMax.Value=max(xlimits);
            app.Int_range_min.Value=min(int_range);
            app.EditField_xlimMin.Value=min(xlimits);


            app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];


            ReadColorMaps(app)
            update_UI(app)
            update_FigXlimLive(app)
            update_FigHistLive(app)


            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

        end

        % Value changed function: Options_ColormapDropDown, 
        % Options_ColormapResEditField, Options_DispNegativeValues, 
        % Options_InvertColormap, Options_LogColormap, 
        % Options_LowerCheckBox, Options_LowerColor, 
        % Options_UpperCheckBox, Options_UpperColor
        function Options_ColormapDropDownValueChanged(app, event)
                            axis2plot=update_plots(app,true);

            update_plot_cmap(app,axis2plot)
        end

        % Value changed function: BEditField, BEditField_2, DropDown, 
        % GEditField, GEditField_2, GroupListBox, GroupMeanButton, 
        % GroupSpecsButton, ImageButton, InterpolationButton, 
        % LablesButton, LinBCButton, MeanButton, REditField, REditField_2, 
        % SpecsButton, SpectraButton, SpotTransparencySlider, SpotsButton, 
        % SpotsizeSpinner, ntoplotEditField
        function plot_update(app, event)
            update_plots(app);
%             update_plot_cmap(app)
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_xlimMaxValueChanged(app, event)

            app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            if isempty(app.hLineToDrag)
                update_FigXlimLive(app)
            end

            int=[app.Int_range_min.Value app.Int_range_max.Value];
            xlimval=app.FigXlimLive.XLim;
                    EditField_climMaxValueChanged(app)

            if max(int) > max(xlimval)|| min(int) < min(xlimval)
                if min(int) < min(xlimval)
                    app.Int_range_min.Value= app.EditField_xlimMin.Value;
                end
                if max(int) > max(xlimval)
                    app.Int_range_max.Value= app.EditField_xlimMax.Value;
                end

                try
                    set(app.hVerticalLines(1),'Value',app.Int_range_min.Value)
                    set(app.hVerticalLines(2),'Value',app.Int_range_max.Value)

                catch
                    update_FigXlimLive(app)
                    disp('Error in int range update')
                end

            end
        end

        % Value changed function: EditField_climMax, EditField_climMin
        function EditField_climMaxValueChanged(app, event)

            ValueMin = min([ app.EditField_climMin.Value app.EditField_climMax.Value]);
            ValueMax = max([ app.EditField_climMin.Value app.EditField_climMax.Value]);

            if app.EditField_climMax.Value < app.EditField_climMin.Value
                app.EditField_climMin.Value=ValueMin;
                app.EditField_climMax.Value=ValueMax;
            end

            app.climits=[ValueMin ValueMax];

            %% update map
                axis2plot=update_plots(app,true);
if isempty(axis2plot)
return
end
                if isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                    caxis(axis2plot,'auto');
                else
                    caxis(axis2plot,sort(app.climits));
                end
        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
         reset_zoom_pan(app)
         click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.05; %ATH Settingspanel

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                xVertLineCoord = get(app.hVerticalLines(1),'Value');
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(1);
                          app.CX_Lim_state='x';
                end

            else
                for k=1:2
                    xVertLineCoord = get(app.hVerticalLines(k),'Value');
                    xVertLineCoord(1)=xVertLineCoord(1);
                    if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                        app.hLineToDrag = app.hVerticalLines(k);
                        app.CX_Lim_state='x';
                        break;
                    end
                end
            end
        end

        % Button down function: FigHistLive
        function FigHistLiveButtonDown(app, event)
reset_zoom_pan(app)
            for k=3:4
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigHistLive.XLim)-min(app.FigHistLive.XLim))*0.05; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    app.CX_Lim_state='c';
                    break;
                end
            end


        end

        % Window button motion function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)

                if strcmp(app.CX_Lim_state,'x')
                    currentPoint   = app.FigXlimLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    %y            = currentPoint(1,3);
                    xlim = app.FigXlimLive.XLim;
                    %ylim = app.FigXlimLive.YLim;
                    inside_FigXlimLive = x >= xlim(1) & x <= xlim(2); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                    if inside_FigXlimLive

                        set(app.hLineToDrag, 'Value', x);
                                    if strcmp(app.MapGenDropDown.Value,'Absolute') 
  app.Int_range_max.Value = get(app.hVerticalLines(1),'Value');
                                    else
                        xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                        app.Int_range_min.Value = min(xposlines);
                        app.Int_range_max.Value = max(xposlines);
                                    end


                    else
                        SpotVisModuleUIFigureWindowButtonUp(app)
                    end

                else

                    currentPoint   = app.FigHistLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,3);
                    xlim = app.FigHistLive.XLim;
                    ylim = app.FigHistLive.YLim;
                    inside_FigHistLive = x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2); %(val*app.clickbuffer)

                    if inside_FigHistLive

                        if not(isempty(ishandle(app.hLineToDrag)))

                            set(app.hLineToDrag, 'Value', x);

 
                        else
                            app.hLineToDrag = 0;
                            SpotVisModuleUIFigureWindowButtonUp(app)
                            'here'
                            return
                        end
                        xposlines=[get(app.hVerticalLines(3),'Value') get(app.hVerticalLines(4),'Value')];
                        app.EditField_climMin.Value = min(xposlines);
                        app.EditField_climMax.Value = max(xposlines);
                        %                         if app.UIAxes_big.Visible==true
                        %                             caxis(app.UIAxes_big,sort([min(xposlines) max(xposlines)]));
                        %                         end
                        %                         if app.UIAxes_small.Visible==true
                        %                             caxis(app.UIAxes_small,sort([min(xposlines) max(xposlines)]));
                        %                         end
                        %% update map
                        if app.AutoUpdateButton.Value==true
                        axis2plot=update_plots(app,true);
                        if not(isempty(axis2plot))
                            if isempty(xposlines) || isequal(xposlines(1),xposlines(2))
                                caxis(axis2plot,'auto');
                            else
                                caxis(axis2plot,sort(xposlines));
                            end
                        end
                        end
                    else
                        app.hLineToDrag = 0;
                        SpotVisModuleUIFigureWindowButtonUp(app)
                    end

                end

            end

        end

        % Value changed function: Int_range_max, Int_range_min
        function Int_range_maxValueChanged(app, event)
            ValueMin = app.Int_range_min.Value;
            ValueMax = app.Int_range_max.Value;

            if ValueMin > ValueMax
                app.Int_range_min.Value=ValueMax;
                app.Int_range_max.Value=ValueMin;
                ValueMin = app.Int_range_min.Value;
                ValueMax = app.Int_range_max.Value;

            end

            if isempty(app.hLineToDrag) ||  ValueMin<min(app.FigXlimLive.XLim) || ValueMax>max(app.FigXlimLive.XLim)  || isempty(ishandle(app.hVerticalLines(1))) || isempty(ishandle(app.hVerticalLines(2)))|| ishandle(app.hVerticalLines(1))==false||ishandle(app.hVerticalLines(2))==false
                if not(isempty([ValueMin ValueMax])) && not(isequal(ValueMin, ValueMax))
                    int=[app.Int_range_min.Value app.Int_range_max.Value];
                    xlimval=app.FigXlimLive.XLim;
                    if max(int) > max(xlimval)|| min(int) < min(xlimval)
                        if min(int) < min(xlimval)
                            app.EditField_xlimMin.Value=app.Int_range_min.Value;
                        end
                        if max(int) > max(xlimval)
                            app.EditField_xlimMax.Value=app.Int_range_max.Value;
                        end
                    end

                    app.FigXlimLive.XLim = [app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

                end

                try
                    if isa(app.hVerticalLines(1),'matlab.graphics.chart.decoration.ConstantLine') && isa(app.hVerticalLines(2),'matlab.graphics.chart.decoration.ConstantLine')
                        set(app.hVerticalLines(1),'Value',ValueMin)
                        set(app.hVerticalLines(2),'Value',ValueMax)
                    else
                        app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                            xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
                        set(app.hVerticalLines(1:2), 'hittest', 'off')

                    end
                catch
                    app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.Int_range_min.Value,'Color','red',"LineWidth",3)...
                        xline(app.FigXlimLive, app.Int_range_max.Value,'Color','blue',"LineWidth",3)];
                          set(app.hVerticalLines(1:2), 'hittest', 'off')

                    
                   % update_FigXlimLive(app)
                    disp('Error in int range update')
                end


                update_table(app)

                %% update map
                axis2plot=update_plots(app,true);

                if not(isempty(axis2plot)) && isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                    caxis(axis2plot,'auto');
                else
                    caxis(axis2plot,sort(app.climits));
                end
            end


        end

        % Window button up function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];

                if strcmp(app.CX_Lim_state,'x')
                    if strcmp(app.MapGenDropDown.Value,'Absolute')
                        set(app.hVerticalLines(1),'Value',app.Int_range_max.Value)
                        app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);

                    else
                        set(app.hVerticalLines(1),'Value',app.Int_range_min.Value)
                        set(app.hVerticalLines(2),'Value',app.Int_range_max.Value)

                        if app.Int_range_min.Value<app.EditField_xlimMin.Value
                            app.EditField_xlimMin.Value=app.Int_range_min.Value;
                            app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                        end

                        if app.Int_range_max.Value>app.EditField_xlimMax.Value
                            app.EditField_xlimMax.Value=app.Int_range_max.Value;
                            app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                        end
                    end

                    ResetButton_3Pushed(app)
                    % update_FigHistLive(app)
                else
                    set(app.hVerticalLines(3),'Value',app.EditField_climMax.Value)
                    set(app.hVerticalLines(4),'Value',app.EditField_climMin.Value)
                    app.climits=sort([app.EditField_climMax.Value app.EditField_climMin.Value]);
                    if app.AutoUpdateButton.Value==false
                        axis2plot=update_plots(app,true);
                        if not(isempty(axis2plot))
                            if isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                                caxis(axis2plot,'auto');
                            else
                                caxis(axis2plot,sort(app.climits));
                            end
                        end
                    end
                end
                if   strcmp(app.SpotColorDropDown.Value,'Value') || app.InterpolationButton.Value==true
                    update_table(app)
                    %  update_plots(app);
                end
                %  update_FigXlimLive(app)
            end

        end

        % Button pushed function: SetasXLimitButton
        function SetasXLimitButtonPushed(app, event)
                app.EditField_xlimMin.Value=app.Int_range_min.Value;
                app.EditField_xlimMax.Value=app.Int_range_max.Value;

                app.FigXlimLive.XLim=sort([app.Int_range_max.Value app.Int_range_min.Value]);

                app.xlimits=sort([app.Int_range_max.Value app.Int_range_min.Value]);

update_plots(app);


        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.selected_image=1;
            app.selected_sample=app.SampleDropDown.Value;


            update_UI(app)
            update_FigXlimLive(app)
            ResetButton_3Pushed(app)

            if app.n_spots>250
                app.LablesButton.Value=false;
                app.InterpolationButton.Value=true;
                app.SpotsButton.Value=false;
            end

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.selected_image=value;
            update_plots(app);
        end

        % Value changed function: LableColorDropDown
        function LableColorDropDownValueChanged(app, event)
            value = app.LableColorDropDown.Value;
            if strcmp(value,'Custom')
                state=true;
            else
                state=false;
            end

            app.REditField_2.Visible=state;
            app.REditField_2Label.Visible=state;
            app.GEditField_2.Visible=state;
            app.GEditField_2Label.Visible=state;
            app.BEditField_2.Visible=state;
            app.BEditField_2Label.Visible=state;

            update_plots(app);
        end

        % Value changed function: SpotColorDropDown
        function SpotColorDropDownValueChanged(app, event)
            value = app.SpotColorDropDown.Value;
            if strcmp(value,'Custom')
                state1=true;
            else
                state1=false;
            end
            app.REditField.Visible=state1;
            app.REditFieldLabel.Visible=state1;
            app.GEditField.Visible=state1;
            app.GEditFieldLabel.Visible=state1;
            app.BEditField.Visible=state1;
            app.BEditFieldLabel.Visible=state1;
            

            update_plots(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));
            oldval=            event.PreviousValue;
            %app.SampleDropDown.ItemsData(val)

            newval=event.Value;

            if oldval<newval
                val_=+1;
            else
                val_=-1;
            end

            if val+val_==0 || val+val_>numel(app.SampleDropDown.ItemsData)
                return
            end
            app.selected_sample=app.SampleDropDown.ItemsData(val_+val);
            app.Spinner.Value=0;
            app.SampleDropDown.Value= app.SampleDropDown.ItemsData(val_+val);
            SampleDropDownValueChanged(app)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if isempty(unique(app.UITable.Selection)) && not(indices(2)==1)
                message={'No spectra selected!','Please select at least one spectra'};
                uialert(app.SpotVisModuleUIFigure,message,'Error');
                return
            elseif  indices(2)==1
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask(indices(1))=newData;
                update_plots(app);
                update_FigXlimLive(app)
                update_FigHistLive(app)

                return
            end

            if size(app.UITable.Selection(1,:))>1
                error('Single Column editable only')
            elseif strcmp(app.UITable.ColumnName(indices(2)),'SpecIndex')
                error('SpecIndex can not be edited')
            elseif strcmp(app.UITable.ColumnName(indices(2)),'Value')
                error('Integration can not be edited')
            else

                fn=app.UITable.ColumnName{indices(2)};

                % for n=1:numel(idx)

                %testvar =  app.Data.Meta(idx(n)).(char(fn));

                if any(strcmp(char(fn),{'Thickness'}))
                    if numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness)==1
                        app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness=ones(size(app.UITable.Data,1),1).*app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness;
                    end
                    app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness(indices(1),1)=newData;
                    app.SpecDB.Data.Meta(app.selected_sample).Thickness=mean(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask),"omitnan");
                    update_table(app)
                    update_plots(app);
                    update_FigXlimLive(app)
                    update_FigHistLive(app)

                elseif any(strcmp(char(fn),{'Comment'}))
                    app.SpecDB.Data.MetaPrivat(app.selected_sample).MaskComment(indices(1))=newData;
                    update_table(app)
                elseif any(strcmp(char(fn),{'Group'}))
                    if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'MetaTableRaw')&& not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw))
                        app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;
                    end
                    selections=find(ismember(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Fileindex,indices(1)));
                    app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Group(indices(1))=newData;

                    update_UI(app)
             

                end
            end


        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            if isempty(unique(app.UITable.Selection))
                message={'No spectra selected!','Please select at least one spectra'};
                uialert(app.SpotVisModuleUIFigure,message,'Error');
                return
            end

            if size(app.UITable.Selection(1,:))>1
                error('Single Column editable only')
            elseif strcmp(app.UITable.ColumnName(app.UITable.Selection(1,2)),'SpecIndex')
                error('SpecIndex can not be edited')
            elseif strcmp(app.UITable.ColumnName(app.UITable.Selection(1,2)),'Value')
                error('Integration can not be edited')
            else

                fn=app.UITable.ColumnName{app.UITable.Selection(1,2)};

                newval= inputdlg(['Enter new Value for ' fn],...
                    'Enter Value', 1);
                if isempty(newval)
                    return
                elseif iscell(newval)
                    newval=newval{:};
                end

                selections = unique(app.UITable.Selection(:,1));

               % for n=1:numel(idx)

               %testvar =  app.Data.Meta(idx(n)).(char(fn));

               if any(strcmp(char(fn),{'Thickness'}))
                   if numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness)==1
                       app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness=ones(size(app.UITable.Data,1),1).*app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness;
                   end
                   app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness(selections)=str2double(newval);
                   app.SpecDB.Data.Meta(app.selected_sample).Thickness=mean(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask),"omitnan");
                   update_table(app)
                   update_plots(app);
               elseif any(strcmp(char(fn),{'Comment'}))
                   app.SpecDB.Data.MetaPrivat(app.selected_sample).MaskComment{selections}=newval;
                   update_table(app)
                   update_plots(app);
               elseif any(strcmp(char(fn),{'Group'}))
                   if isfield(app.SpecDB.Data.MetaPrivat(app.selected_sample),'MetaTableRaw')&& not(isempty(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw))
                       app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTableRaw=app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable;
                   end
                   selections=find(ismember(app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Fileindex,selections));
                   app.SpecDB.Data.MetaPrivat(app.selected_sample).MetaTable.Group(selections)=str2double(newval);
                   update_UI(app)
               end


               %  end
            end
          

        end

        % Image clicked function: MaskSelection
        function MaskSelectionClicked(app, event)
            if  isempty(app.UITable.Selection(:,1))
                return
            end

            selections = unique(app.UITable.Selection(:,1));
            for n=1:numel(selections)
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask(selections(n))=true;
            end
            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
            update_FigHistLive(app)


        end

        % Image clicked function: UnmaskSelection
        function UnmaskSelectionImageClicked(app, event)
            if  isempty(app.UITable.Selection) ||  isempty(app.UITable.Selection(:,1))
                return
            end

            selections = unique(app.UITable.Selection(:,1));
            for n=1:numel(selections)
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask(selections(n))=false;
            end
            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
            update_FigHistLive(app)


        end

        % Image clicked function: InvertMask
        function InvertMaskImageClicked(app, event)
            app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask=not(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask);
            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
update_FigHistLive(app)


        end

        % Callback function: Button_2, ResetMask
        function ResetMaskImageClicked(app, event)
            
            mask=true(size(size(app.SpecDB.Data.DB(app.selected_sample).y_signal,2),1));
            app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask=mask;
            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
update_FigHistLive(app)

        end

        % Key press function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'downarrow')
                val_=-1;
            elseif strcmp(key,'uparrow')
                val_=+1;
            else
                return
            end

            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));

            if val+val_==0 || val+val_>numel(app.SampleDropDown.ItemsData)
                return
            end
            app.selected_sample=app.SampleDropDown.ItemsData(val_+val);
            app.Spinner.Value=0;
            app.SampleDropDown.Value= app.SampleDropDown.ItemsData(val_+val);
            SampleDropDownValueChanged(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            region=sort(app.climits);
            selections=app.UITable.Data.SpecIndex(app.UITable.Data.Value<max(region) & app.UITable.Data.Value>min(region) & app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask==true);
           
            app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask(:)=false;

            for n=1:numel(selections)
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask(find(ismember(app.UITable.Data.SpecIndex,selections(n))))=true;
            end


            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
update_FigHistLive(app)

        end

        % Button pushed function: ToggleAllButton
        function ToggleAllButtonPushed(app, event)
            %toggle all
            if isempty(app.GroupListBox.Value)
                app.GroupListBox.Value=app.GroupListBox.ItemsData;
            else
                app.GroupListBox.Value=[];
            end
            update_plots(app); %split?
        end

        % Button pushed function: SelectinTableButton
        function SelectinTableButtonPushed(app, event)
            %
            selection=app.UITable.Data.SpecIndex(ismember(app.UITable.Data.Group,app.GroupListBox.Value));
            %app.UITable.Selection=[selection, ones(size(selection,1),1);selection, ones(size(selection,1),1).*2;selection, ones(size(selection,1),1).*3;selection, ones(size(selection,1),1).*4;selection, ones(size(selection,1),1).*5];
            
            app.UITable.Selection=[ selection, ones(size(selection,1),1).*3];

        end

        % Menu selected function: Classification2GroupsMenu
        function Classification2GroupsMenuSelected(app, event)
            app.UITable.Data.Group
            nanpoints= (app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask==false);% ->nan excluded
            [x,y]=get_spec2plot(app,app.selected_sample,false,false);
            y(:,nanpoints)=nan;

            %% how to classify
            % classification module with only one allowed return class


            mapsize=[size(y,2),1];
            %ATH change
            cmap=[];
            ClassificationModule(app,  x, y, sort(app.xlimits),app.UITable.Data.SpecIndex , mapsize,cmap);


        end

        % Callback function
        function AddImageMenuSelected(app, event)
            % open import module
            ReferenceMaps.MapPlotMatrix=app.SpecDB.Data.Images.Images(app.selected_image_group).Image{app.selected_image};
            ReferenceMapsName=app.SpecDB.Data.Images.Images(app.selected_image_group).ImageNames{app.selected_image};
            MatrixLimits=app.SpecDB.Data.Images.Images(app.selected_image_group).ImageLimits{app.selected_image};
            sampleinfo=[app.selected_image_group app.selected_image];
            [file, path]=uigetfile("MultiSelect",'on','*.jpg;*.gif;*.png;*.bmp;*.tif;*.tiff');
            if file==0
                return
            elseif iscell(file)

                for n=1:numel(file)
                    matrix_to_match(n).MapPlotMatrix=imread(fullfile(path,file));
                    matrix_to_matchNames{n}=file{n};
                end
            else
                matrix_to_match.MapPlotMatrix=imread(fullfile(path,file));

                matrix_to_matchNames={file};
            end

        ImageMatchModule(app.SpecDB, ReferenceMaps, ReferenceMapsName, sampleinfo, MatrixLimits, matrix_to_match, matrix_to_matchNames)
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.reset_value=true;
           % update_UI(app)

            app.LinBCButton.Value=false;
            update_FigXlimLive(app)
ResetButton_3Pushed(app)

        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.reset_value=true;
             update_FigHistLive(app)
update_plots(app);


        end

        % Menu selected function: PlotProfileMenu
        function PlotProfileMenuSelected(app, event)
            
            x=app.UITable.Data.SpecIndex;
            y=app.UITable.Data.Value;
            active=app.UITable.Data.(1);
            groups=app.UITable.Data.Group;
            ug= unique(groups(not(isnan(groups))));
            cmap = PlotMap_UpdateColormap(app,numel(ug));
            

            if isempty(app.GroupListBox.Value)
                ToggleAllButtonPushed(app)
            end
            figure
            if numel(app.GroupListBox.Value)>1
                nexttile
                hold on

                for n=1:numel(ug)
                    if ismember(ug(n),app.GroupListBox.Value)

                        plot(app.UITable.Data.SpecIndex(active& ismember(groups,ug(n))),app.UITable.Data.Value(active&ismember(groups,ug(n))),LineWidth=2,Color=cmap(n,:),LineStyle=":",Marker="o",MarkerFaceColor=cmap(n,:))
                    end
                end
            end


            for n=1:numel(ug)
                if ismember(ug(n),app.GroupListBox.Value)
                    nexttile
                    hold on
                    title(num2str(ug(n)))
                    plot(app.UITable.Data.SpecIndex(active& ismember(groups,ug(n))),app.UITable.Data.Value(active&ismember(groups,ug(n))),LineWidth=2,Color=cmap(n,:),LineStyle=":",Marker="o",MarkerFaceColor=cmap(n,:))
                end
            end
        end

        % Menu selected function: CallibrateThicknessExperimentalMenu
        function CallibrateThicknessExperimentalMenuSelected(app, event)
            if numel(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness)==1
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness=ones(size(app.UITable.Data,1),1).*app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness;
            end

           t_in = max(app.UITable.Data.Thickness(app.UITable.Data.active));
           [~, id]= max(app.UITable.Data.Value(app.UITable.Data.active));

            definput = {num2str(id),num2str(t_in)};

            x  = inputdlg({'Id to calc Coefficient','thickness in µm'}, ['Parameters'],[1 15; 1 15],definput);
            if ~isempty(x)
                id2otcalc=str2num(x{1}); %#ok
                thickness=str2double(x(2));

            else
                return
            end
            if isnumeric(thickness) && not(isnan(thickness)) && not(isinf(thickness))
                OvertoneThicknessCoeff=app.UITable.Data.Value(id2otcalc)/thickness;
                newData=app.UITable.Data.Thickness;
                newData(app.UITable.Data.active)=app.UITable.Data.Value(app.UITable.Data.active)./OvertoneThicknessCoeff;
                newData=round(newData,1);
                app.UITable.Data.Thickness(app.UITable.Data.active,1)=newData(app.UITable.Data.active);
                app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness=newData;
                app.SpecDB.Data.Meta(app.selected_sample).Thickness=mean(app.SpecDB.Data.MetaPrivat(app.selected_sample).Thickness(app.SpecDB.Data.MetaPrivat(app.selected_sample).Mask),"omitnan");
                update_table(app)
                update_plots(app);
                update_FigXlimLive(app)
                update_FigHistLive(app)
            end
        end

        % Menu selected function: ExtractGroupsMenu
        function ExtractGroupsMenuSelected(app, event)
            ExtractGroups(app.SpecDB,app.IdEditField.Value)
        end

        % Value changed function: MapGenDropDown
        function MapGenDropDownValueChanged(app, event)

            xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                region=app.Int_range_max.Value;
                if max(region)>max(xl) || max(region)<min(xl)
                    app.Int_range_max.Value=round((max(xl)-min(xl))/2 )+min(xl);
                end
                app.IntRangeLabel.Text='XoI';
                app.Int_range_min.Visible=false;
                update_lines(app)
                app.SetasXLimitButton.Visible=false;
                return
            else
                region=[app.Int_range_max.Value app.Int_range_min.Value];

                if min(region)<min(xl) || min(region)>max(xl) || max(region)>max(xl) || max(region)<min(xl)
                    app.Int_range_min.Value=min(xl);
                    app.Int_range_max.Value=max(xl);
                end
                app.SetasXLimitButton.Visible=true;

            end

            if strcmp(app.MapGenDropDown.Value,'Ratio')
                app.IntRangeLabel.Text='Ratio';
                app.Int_range_min.Visible=true;
            elseif strcmp(app.MapGenDropDown.Value,'Ratio log/log')
                app.IntRangeLabel.Text='Ratio log/log';
                app.Int_range_min.Visible=true;
            elseif strcmp(app.MapGenDropDown.Value,'Entropy')
                app.IntRangeLabel.Text='Range';
                app.Int_range_min.Visible=true;
            else
                app.IntRangeLabel.Text='Int. Range';
                app.Int_range_min.Visible=true;

            end

            update_table(app)
            update_plots(app);
            update_FigXlimLive(app)
            update_FigHistLive(app)
            ResetButton_3Pushed(app)

        end

        % Menu selected function: ExportTableMenu
        function ExportTableMenuSelected(app, event)
            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));
            if  app.Int_range_min.Visible==true

                filename=[ app.SampleDropDown.Items{val} '_' num2str(app.Int_range_min.Value) '_' num2str(app.Int_range_max.Value) '_export.csv'];
            else
                filename=[ app.SampleDropDown.Items{val} '_'  num2str(app.Int_range_max.Value) '_export.csv'];
            end
            
            [filename, filepath] = uiputfile({'*.csv'}, 'Save the table:',fullfile(app.exportpath,filename));
            if not(filename==0)
                app.exportpath=filepath;
                FileName = fullfile(app.exportpath, filename);
                writetable(app.UITable.Data,FileName)
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selections=unique(app.UITable.Selection(:,1));

            if app.ntoplotEditField.Value>=numel(selections)
                app.Specs_of_interest=selections;
            else
                app.Specs_of_interest=[];
            end

            [axis2plot_map, axis2plot_spectra]=update_plots(app,true);
            cla(axis2plot_spectra)
            plot_spec(app,axis2plot_spectra)

            if app.LablesButton.Value==true
                h=findobj(axis2plot_map.Children,'Type','Text');
                [h(:).FontSize]=deal(12);
                [h(:).FontWeight]=deal('normal');
                numbers2check=cellfun(@str2num,{h.String},'un',0).';

                [h(ismember([numbers2check{:}],selections)).FontSize]=deal(16);
                [h(ismember([numbers2check{:}],selections)).FontWeight]=deal('bold');
                %plot_spots(app,axis2plot_map,false)
            end

        end

        % Menu selected function: AdjustImagePositionMenu
        function AdjustImagePositionMenuSelected(app, event)
            0


selected_image_group=app.selected_image_group;
selected_image=app.selected_image;
    % Check if more than one image is selected
    if numel(selected_image) > 1
        uialert(app.SpotVisModuleUIFigure, 'Please select only one image.', 'Error');
        return;
    end
    
    if app.ImageButton.Value == true && isfield(app.SpecDB.Data, 'Images') && isfield(app.SpecDB.Data.Images, 'Images') && not(isempty(app.SpecDB.Data.Images.Images(selected_image_group)))
        % Get the selected image and its limits
        img = app.SpecDB.Data.Images.Images(selected_image_group).Image{selected_image};
        
        if isempty(img)
            message = 'Error: No Image found';
            disp(message);
            return;
        end

        ImageLimits = app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits{selected_image};
        
        if isempty(size(ImageLimits)) || not(isequal(size(ImageLimits), [2 2]))
            message = 'Error: No Image Info found';
            uialert(app.SpotVisModuleUIFigure, message, 'Error');
            return;
        end

        % Check if ImageLimits_raw exists, and if not, store the original ImageLimits
        if ~isfield(app.SpecDB.Data.Images.Images(selected_image_group), 'ImageLimits_raw') || isempty(app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw) || isempty(app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw{selected_image})
            app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw{selected_image} = ImageLimits;
        end

        % Ask the user to input the shift values
        prompt = {'Enter x shift:', 'Enter y shift:'};
        dlgtitle = 'Image Shift';
        dims = [1 35];

        definput = {num2str(app.LastImg_Adjust(1)), num2str(app.LastImg_Adjust(2))};  % Default values (no shift)
        answer = inputdlg(prompt, dlgtitle, dims, definput);
        
        if isempty(answer)
            return;  % If the user cancels the input dialog, return
        end
        
        % Convert input to numbers
        x_shift = str2double(answer{1});
        y_shift = str2double(answer{2});
        
        % Validate input (ensure they are numbers)
        if isnan(x_shift) || isnan(y_shift)
            uialert(app.SpotVisModuleUIFigure, 'Please enter valid numerical values for the shifts.', 'Invalid Input');
            return;
        end
        
        app.LastImg_Adjust=[x_shift y_shift];


        % Get the current ImageLimits_raw and apply the shifts
        ImageLimits_raw = app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits{selected_image};
        new_x_limits = ImageLimits_raw(1, :) - x_shift;
        new_y_limits = ImageLimits_raw(2, :) - y_shift;

        % Update the ImageLimits_raw with the new limits
        app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits{selected_image} = [new_x_limits; new_y_limits];

        % Optionally, inform the user that the shift was applied successfully
        uialert(app.SpotVisModuleUIFigure, 'Image limits updated successfully!', 'Success','Icon','success');
 
        plot_update(app)
end


        end

        % Menu selected function: ResetImagePositionMenu
        function ResetImagePositionMenuSelected(app, event)
selected_image_group = app.selected_image_group;
selected_image = app.selected_image;

% Check if more than one image is selected
if numel(selected_image) > 1
    uialert(app.SpotVisModuleUIFigure, 'Please select only one image.', 'Error');
    return;
end

if isfield(app.SpecDB.Data.Images.Images(selected_image_group), 'ImageLimits_raw') || isempty(app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw{selected_image})
    app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits{selected_image} = ...
      app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw{selected_image};
    app.SpecDB.Data.Images.Images(selected_image_group).ImageLimits_raw{selected_image} = [];
    update_UI(app)

end

        end

        % Menu selected function: ShowImageCommentMenu
        function ShowImageCommentMenuSelected(app, event)
           selected_image_group = app.selected_image_group;
selected_image = app.selected_image;

% Check if more than one image is selected
if numel(selected_image) > 1
    uialert(app.SpotVisModuleUIFigure, 'Please select only one image.', 'Error');
    return;
end    

            Comment='no information';
if isfield(app.SpecDB.Data.Images.Images(selected_image_group), 'ImageComment')
Comment=app.SpecDB.Data.Images.Images(selected_image_group).ImageComment{selected_image};
end

    %   uialert(app.SpotVisModuleUIFigure, Comment, 'Image Comment',Icon='info');


% Ask if the user wants to use the image comment as the image name
response = uiconfirm(app.SpotVisModuleUIFigure, ...
    {Comment,'Do you want to use the image comment as the image name?'}, ...
    'Confirm Image Name', ...
    'Options', {'Yes', 'No'}, ...
    'DefaultOption', 'Yes', ...
    'CancelOption', 'No');

% Handle the response
if strcmp(response, 'Yes')
    app.SpecDB.Data.Images.Images(selected_image_group).ImageNames{selected_image} = Comment;

    update_UI(app)

else
    disp('Image name remains unchanged.');
end
        end

        % Menu selected function: Rename_ImageMenu
        function EditImageNameMenuSelected(app, event)

            selected_image = app.selected_image;

            % Check if more than one image is selected
            if numel(selected_image) > 1
                uialert(app.SpotVisModuleUIFigure, 'Please select only one image.', 'Error');
                return;
            end

            % find image id
            new_name=inputdlg('Enter New Image Name',...
                'Image_name', 1);
            if not(isempty(new_name))
                app.SpecDB.Data.Images.Images(app.selected_image_group).ImageNames{selected_image}=new_name{:};
            end
            update_UI(app)
            % change image name
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpotVisModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {849, 849};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {372, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpotVisModuleUIFigure = uifigure('Visible', 'off');
            app.SpotVisModuleUIFigure.Name = 'SpotVisModule';
            app.SpotVisModuleUIFigure.Icon = 'symbols_1_Spotvis.png';
            app.SpotVisModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SpotVisModuleUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @SpotVisModuleUIFigureWindowButtonUp, true);
            app.SpotVisModuleUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SpotVisModuleUIFigureWindowButtonMotion, true);
            app.SpotVisModuleUIFigure.KeyPressFcn = createCallbackFcn(app, @SpotVisModuleUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.SpotVisModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.FigHistLive = uiaxes(app.GridLayout4);
            app.FigHistLive.ButtonDownFcn = createCallbackFcn(app, @FigHistLiveButtonDown, true);
            app.ListBox = uilistbox(app.GridLayout4);
            app.ListBox.Items = {'Image 1', 'Image 2'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Visible = 'off';
            app.ListBox.Value = {'Image 1'};
            app.ImageButton = uibutton(app.GridLayout4, 'state');
            app.ImageButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Text = 'Image';
            app.SpotsButton = uibutton(app.GridLayout4, 'state');
            app.SpotsButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotsButton.Enable = 'off';
            app.SpotsButton.Text = 'Spots';
            app.SpotColorDropDownLabel = uilabel(app.GridLayout4);
            app.SpotColorDropDownLabel.Text = 'Spot Color';
            app.SpotColorDropDown = uidropdown(app.GridLayout4);
            app.SpotColorDropDown.Items = {'Value', 'Custom', 'White', 'Black', 'Red', 'Green', 'Blue', 'Custom'};
            app.SpotColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpotColorDropDownValueChanged, true);
            app.SpotColorDropDown.Value = 'Value';
            app.LablesButton = uibutton(app.GridLayout4, 'state');
            app.LablesButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.LablesButton.Enable = 'off';
            app.LablesButton.Text = 'Lables';
            app.LableColorDropDownLabel = uilabel(app.GridLayout4);
            app.LableColorDropDownLabel.Text = 'Lable Color';
            app.LableColorDropDown = uidropdown(app.GridLayout4);
            app.LableColorDropDown.Items = {'Black', 'White', 'Red', 'Blue', 'Green', 'Custom'};
            app.LableColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LableColorDropDownValueChanged, true);
            app.LableColorDropDown.Value = 'White';
            app.BEditField_2Label = uilabel(app.GridLayout4);
            app.BEditField_2Label.Visible = 'off';
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.BEditField_2.Limits = [0 1];
            app.BEditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.BEditField_2.Visible = 'off';
            app.GEditField_2Label = uilabel(app.GridLayout4);
            app.GEditField_2Label.Visible = 'off';
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.GEditField_2.Limits = [0 1];
            app.GEditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GEditField_2.Visible = 'off';
            app.REditField_2Label = uilabel(app.GridLayout4);
            app.REditField_2Label.Visible = 'off';
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.REditField_2.Limits = [0 1];
            app.REditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.REditField_2.Visible = 'off';
            app.SpotTransparencySliderLabel = uilabel(app.GridLayout4);
            app.SpotTransparencySliderLabel.Text = 'Spot Transparency';
            app.SpotTransparencySlider = uislider(app.GridLayout4);
            app.SpotTransparencySlider.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotTransparencySlider.Value = 100;
            app.SampleDropDown = uidropdown(app.GridLayout4);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.InterpolationButton = uibutton(app.GridLayout4, 'state');
            app.InterpolationButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.InterpolationButton.Enable = 'off';
            app.InterpolationButton.Text = 'Interpolation';
            app.REditFieldLabel = uilabel(app.GridLayout4);
            app.REditFieldLabel.Visible = 'off';
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.GridLayout4, 'numeric');
            app.REditField.Limits = [0 1];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.REditField.Visible = 'off';
            app.REditField.Value = 1;
            app.GEditFieldLabel = uilabel(app.GridLayout4);
            app.GEditFieldLabel.Visible = 'off';
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GEditField.Limits = [0 1];
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GEditField.Visible = 'off';
            app.GEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.GridLayout4);
            app.BEditFieldLabel.Visible = 'off';
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BEditField.Limits = [0 1];
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.BEditField.Visible = 'off';
            app.BEditField.Value = 1;
            app.Spinner = uispinner(app.GridLayout4);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.IdEditFieldLabel = uilabel(app.GridLayout4);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IdEditField.Editable = 'off';
            app.SpectraButton = uibutton(app.GridLayout4, 'state');
            app.SpectraButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpectraButton.Text = 'Spectra';
            app.SpectraButton.Value = true;
            app.SpotsizeSpinnerLabel = uilabel(app.GridLayout4);
            app.SpotsizeSpinnerLabel.Text = 'Spotsize';
            app.SpotsizeSpinner = uispinner(app.GridLayout4);
            app.SpotsizeSpinner.Step = 10;
            app.SpotsizeSpinner.Limits = [1 1000];
            app.SpotsizeSpinner.RoundFractionalValues = 'on';
            app.SpotsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotsizeSpinner.Value = 250;
            app.EditField_climMin = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_climMin.ValueDisplayFormat = '%3.4g';
            app.EditField_climMin.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_climMax.ValueDisplayFormat = '%3.4g';
            app.EditField_climMax.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayout4);
            app.FigXlimLive = uiaxes(app.GridLayout2);
            app.FigXlimLive.XDir = 'reverse';
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.SpecsButton = uibutton(app.GridLayout2, 'state');
            app.SpecsButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpecsButton.Text = 'Specs';
            app.SpecsButton.Value = true;
            app.MeanButton = uibutton(app.GridLayout2, 'state');
            app.MeanButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.LinBCButton = uibutton(app.GridLayout2, 'state');
            app.LinBCButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.LinBCButton.Text = 'LinBC';
            app.EditField_xlimMin = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.ntoplotEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ntoplotEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.ntoplotEditField.Value = 10;
            app.Int_range_min = uieditfield(app.GridLayout2, 'numeric');
            app.Int_range_min.ValueChangedFcn = createCallbackFcn(app, @Int_range_maxValueChanged, true);
            app.Int_range_min.Value = 3050;
            app.SetasXLimitButton = uibutton(app.GridLayout2, 'push');
            app.SetasXLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetasXLimitButtonPushed, true);
            app.SetasXLimitButton.Text = 'Set as X Limit';
            app.Int_range_max = uieditfield(app.GridLayout2, 'numeric');
            app.Int_range_max.ValueChangedFcn = createCallbackFcn(app, @Int_range_maxValueChanged, true);
            app.Int_range_max.Value = 3720;
            app.ntoplotEditFieldLabel = uilabel(app.GridLayout2);
            app.ntoplotEditFieldLabel.Text = 'n to plot';
            app.EditField_xlimMax = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.IntRangeLabel = uilabel(app.GridLayout2);
            app.IntRangeLabel.Text = 'Int. Range';
            app.ResetButton_2 = uibutton(app.GridLayout2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'symbols_1_Mask Selection.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskImageClicked, true);
            app.Button_2.Icon = 'symbols_1_Clear Mask.png';
            app.Button_2.Text = {''; ''};
            app.ResetButton_3 = uibutton(app.GridLayout4, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.AutoUpdateButton = uibutton(app.GridLayout4, 'state');
            app.AutoUpdateButton.Text = 'AutoUpdate';
            app.AutoUpdateButton.Value = true;
            app.MapGenDropDown = uidropdown(app.GridLayout4);
            app.MapGenDropDown.Items = {'Integration', 'Absolute', 'Ratio', 'Ratio Norm', 'Ratio log/log', 'Ratio NormLog', 'Entropy'};
            app.MapGenDropDown.ValueChangedFcn = createCallbackFcn(app, @MapGenDropDownValueChanged, true);
            app.MapGenDropDown.Value = 'Integration';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes_big = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_big, 'X')
            ylabel(app.UIAxes_big, 'Y')
            zlabel(app.UIAxes_big, 'Z')
            app.SpecPlot_small = uiaxes(app.GridLayout3);
            xlabel(app.SpecPlot_small, 'X')
            ylabel(app.SpecPlot_small, 'Y')
            zlabel(app.SpecPlot_small, 'Z')
            app.SpecPlot_small.Visible = 'off';
            app.SpecPlot_big = uiaxes(app.GridLayout3);
            xlabel(app.SpecPlot_big, 'X')
            ylabel(app.SpecPlot_big, 'Y')
            zlabel(app.SpecPlot_big, 'Z')
            app.SpecPlot_big.Visible = 'off';
            app.UIAxes_small = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_small, 'X')
            ylabel(app.UIAxes_small, 'Y')
            zlabel(app.UIAxes_small, 'Z')
            app.UIAxes_small.Visible = 'off';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Mask'; 'SpecIndex'; 'Thickness'; 'Value'; 'Group'; 'Comment'; 'x_pos'; 'y_pos'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true];
            app.UITable.ColumnEditable = [true false true false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ColormapPanel = uipanel(app.GridLayout3);
            app.ColormapPanel.Title = 'Colormap';
            app.GridLayout_2 = uigridlayout(app.ColormapPanel);
            app.Options_DispNegativeValues = uicheckbox(app.GridLayout_2);
            app.Options_DispNegativeValues.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_DispNegativeValues.Text = 'Display negative values';
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_LowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_LowerCheckBox.Value = true;
            app.Options_LowerColor = uidropdown(app.GridLayout_2);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_UpperCheckBox.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout_2);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout_2);
            app.Options_LogColormap.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel_2 = uilabel(app.GridLayout_2);
            app.ColormapDropDownLabel_2.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout_2);
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout_2);
            app.Options_InvertColormap.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_InvertColormap.Text = 'Invert';
            app.MaskSelection = uiimage(app.GridLayout3);
            app.MaskSelection.ImageClickedFcn = createCallbackFcn(app, @MaskSelectionClicked, true);
            app.MaskSelection.ImageSource = 'symbols_1_Mask Selection.png';
            app.UnmaskSelection = uiimage(app.GridLayout3);
            app.UnmaskSelection.ImageClickedFcn = createCallbackFcn(app, @UnmaskSelectionImageClicked, true);
            app.UnmaskSelection.ImageSource = 'symbols_1_Substract from Mask.png';
            app.InvertMask = uiimage(app.GridLayout3);
            app.InvertMask.ImageClickedFcn = createCallbackFcn(app, @InvertMaskImageClicked, true);
            app.InvertMask.ImageSource = 'symbols_1_Invert Mask.png';
            app.ResetMask = uiimage(app.GridLayout3);
            app.ResetMask.ImageClickedFcn = createCallbackFcn(app, @ResetMaskImageClicked, true);
            app.ResetMask.ImageSource = 'symbols_1_Clear Mask.png';
            app.GroupsPanel = uipanel(app.GridLayout3);
            app.GroupsPanel.Title = 'Groups';
            app.GroupsPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.GroupsPanel);
            app.GroupListBox = uilistbox(app.GridLayout5);
            app.GroupListBox.Multiselect = 'on';
            app.GroupListBox.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupListBox.Value = {'Item 1'};
            app.ToggleAllButton = uibutton(app.GridLayout5, 'push');
            app.ToggleAllButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleAllButtonPushed, true);
            app.ToggleAllButton.Text = 'Toggle All';
            app.SelectinTableButton = uibutton(app.GridLayout5, 'push');
            app.SelectinTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinTableButtonPushed, true);
            app.SelectinTableButton.Text = 'Select in Table';
            app.GroupSpecsButton = uibutton(app.GridLayout5, 'state');
            app.GroupSpecsButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupSpecsButton.Text = 'Group Specs';
            app.GroupMeanButton = uibutton(app.GridLayout5, 'state');
            app.GroupMeanButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupMeanButton.Text = 'Group Mean';
            app.GroupMeanButton.Value = true;
            app.DropDown = uidropdown(app.GridLayout5);
            app.DropDown.Items = {'Default', 'Profile', 'Histogram'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Default';
            app.SpecButton = uibutton(app.GridLayout3, 'state');
            app.SpecButton.Text = 'Spec';
            app.ContextMenu = uicontextmenu(app.SpotVisModuleUIFigure);
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit';
            app.ExportTableMenu = uimenu(app.ContextMenu);
            app.ExportTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTableMenuSelected, true);
            app.ExportTableMenu.Text = 'ExportTable';
            app.Classification2GroupsMenu = uimenu(app.ContextMenu);
            app.Classification2GroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @Classification2GroupsMenuSelected, true);
            app.Classification2GroupsMenu.Text = 'Classification 2 Groups';
            app.PlotProfileMenu = uimenu(app.ContextMenu);
            app.PlotProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotProfileMenuSelected, true);
            app.PlotProfileMenu.Text = 'Plot Profile';
            app.CallibrateThicknessExperimentalMenu = uimenu(app.ContextMenu);
            app.CallibrateThicknessExperimentalMenu.MenuSelectedFcn = createCallbackFcn(app, @CallibrateThicknessExperimentalMenuSelected, true);
            app.CallibrateThicknessExperimentalMenu.Text = 'Calibrate Thickness (Experimental)';
            app.ExtractGroupsMenu = uimenu(app.ContextMenu);
            app.ExtractGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractGroupsMenuSelected, true);
            app.ExtractGroupsMenu.Text = 'Extract Groups';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.SpotVisModuleUIFigure);
            app.Rename_ImageMenu = uimenu(app.ContextMenu2);
            app.Rename_ImageMenu.MenuSelectedFcn = createCallbackFcn(app, @EditImageNameMenuSelected, true);
            app.Rename_ImageMenu.Text = 'Rename_Image';
            app.AdjustImagePositionMenu = uimenu(app.ContextMenu2);
            app.AdjustImagePositionMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustImagePositionMenuSelected, true);
            app.AdjustImagePositionMenu.Text = 'Adjust Image Position';
            app.ResetImagePositionMenu = uimenu(app.ContextMenu2);
            app.ResetImagePositionMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetImagePositionMenuSelected, true);
            app.ResetImagePositionMenu.Text = 'Reset Image Position';
            app.ShowImageCommentMenu = uimenu(app.ContextMenu2);
            app.ShowImageCommentMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowImageCommentMenuSelected, true);
            app.ShowImageCommentMenu.Text = 'Show Image Comment';
            % Assign app.ContextMenu2
            app.ListBox.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.SpotVisModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpotVisModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpotVisModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpotVisModuleUIFigure)
        end
    end
end