classdef TableCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapCalculatorUIFigure      matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CalcMapNameEditField       matlab.ui.control.EditField
        CalcMapNameEditFieldLabel  matlab.ui.control.Label
        SampleDropDown             matlab.ui.control.DropDown
        SampleDropDownLabel        matlab.ui.control.Label
        Calculate_sel              matlab.ui.control.Button
        Calculate_all              matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        DevisionButton             matlab.ui.control.Button
        MultiplicationButton       matlab.ui.control.Button
        MinusButton                matlab.ui.control.Button
        PlusButton                 matlab.ui.control.Button
        DataTree                   matlab.ui.container.Tree
        AddButton                  matlab.ui.control.Button
        Calculate                  matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        MapPlot                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        mainapp % Description
        sampleinfo
        
        T
    end

    methods (Access = public)

       function UpdateTree_Single(app,Tree,T)

            if not(exist('Tree','var'))|| isempty(Tree)
                Tree=app.DataTree;
            end

            a = Tree.Children;
            a.delete;

            nodes=         T.Properties.VariableNames;
            for n=1:numel(nodes)
                p1 = uitreenode(app.DataTree,'Text',char(nodes{n}),'NodeData',T.(char(nodes{n})));
            end
        end

    end


    methods (Access = private)

        function matrix=CalculateMatrix_Fun(app,active_Sample,plotstate)
matrix=[];
            str_ini=app.TextArea.Value{:};
            spltstr = split(string(str_ini),"'");
            map_idx=strfind(spltstr,'[');
            map_idx_end=strfind(spltstr,']');
            if plotstate==true
                figure
            end
            
            try
                %% new code using str2func
                str2calc_cell=str_ini;
                x=cell(1,numel(map_idx));
                for n=1:numel(map_idx)
                    str2Replace= str_ini(map_idx(n):map_idx_end(n));
                    x{n}=app.T.(char(replace(str2Replace,{'[',']'},'')));
                    str2Replace_cell=char(['x{',num2str(n),'}']);
                    str2calc_cell=replace(str2calc_cell,str2Replace,str2Replace_cell);
                end

                f=str2func(['@(x)', str2calc_cell]);
                matrix=f(x);

                %%old code using eval
%                 matrix2=eval(str2calc{:});
%                 %matrix2=eval(str{:});
%                 message=num2str(isequal(matrix,matrix2));
%                 uialert(app.MapCalculatorUIFigure,message,'Error');

            catch ME
                disp('Error CalculateMatrix_Fun in MapCalculator')
                disp(ME)
                message=ME.message;
                uialert(app.MapCalculatorUIFigure,message,'Error');
                disp(message)
            end
            if plotstate==true

                Axis2Plot=nexttile;

                histogram(matrix); % used in CorelationModule
                title(str_ini,'Interpreter','none')
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleinfo, SampleNames, T)
            app.mainapp=mainapp;
            if numel(SampleNames)>1
                app.SampleDropDown.Items=SampleNames;
               
                app.SampleDropDown.Value=SampleNames(sampleinfo(1));
            else
                app.SampleDropDown.Visible=false;
            end
            app.sampleinfo=sampleinfo;
            app.T=T;
           

            UpdateTree_Single(app,app.DataTree,T)


        end

        % Button pushed function: Calculate
        function CalculatePushed(app, event)
            active_Sample=app.sampleinfo(1);
             CalculateMatrix_Fun(app,active_Sample,true);

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            try

                if iscell(app.TextArea.Value) && isempty( app.TextArea.Value{:})
                    app.TextArea.Value= ['[' char(app.DataTree.SelectedNodes.Text) ']'];
                elseif  iscell(app.TextArea.Value)
                    app.TextArea.Value=[app.TextArea.Value{:} ' ' ['[' char(app.DataTree.SelectedNodes.Text) ']']];
                end
            catch
                CalculatePushed(app)
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.sampleinfo(1)= find(ismember(app.SampleDropDown.Items,app.SampleDropDown.Value));
            UpdateTree_Single(app,app.DataTree,app.T)

        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            selectedNodes = app.DataTree.SelectedNodes;

histogram(app.MapPlot,selectedNodes.NodeData)


        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' + '];
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' - '];
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' .* '];
        end

        % Button pushed function: DevisionButton
        function DevisionButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ./ '];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ( '];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ) '];
        end

        % Button pushed function: Calculate_sel
        function Calculate_selButtonPushed(app, event)
            if isempty(app.CalcMapNameEditField.Value)
                new_name=app.TextArea.Value;
            else
                new_name={app.CalcMapNameEditField.Value};
            end

            new_name = matlab.lang.makeValidName(new_name);
            [matrix ]=CalculateMatrix_Fun(app,[],false);
            try
               new_name= CalcTableReturn(app.mainapp,matrix,new_name,app.T.Id,app.sampleinfo);
            catch
              new_name=CalcTableReturn(app.mainapp,matrix,new_name,[],app.sampleinfo);
            end

            app.T.(char(new_name))=matrix;

            UpdateTree_Single(app,app.DataTree,app.T)

        end

        % Button pushed function: Calculate_all
        function Calculate_allButtonPushed(app, event)
            if isempty(app.CalcMapNameEditField.Value)
                CalcMatrixName=app.TextArea.Value;

                spltstr_2=split(replace(CalcMatrixName,"'",""),{'[',']'});
                spltstr_2=join(string(spltstr_2(3:2:end))','');
                CalcMatrixName=char(replace(spltstr_2,'  ',' '));
                if startsWith(spltstr_2,' ')
                    CalcMatrixName={CalcMatrixName(2:end)};
                else
                    CalcMatrixName={CalcMatrixName};
                end
            else
                CalcMatrixName={app.CalcMapNameEditField.Value};
            end

            d = uiprogressdlg(app.MapCalculatorUIFigure,'Title','Calculating Maps',...
                'Message','Automatic Map Calculation Started','Cancelable','on');
            drawnow

            for active_Sample=1:numel(app.SampleDropDown.Items)
                try
                    if d.CancelRequested
                        break
                    end
                    d.Value=active_Sample/numel(app.SampleDropDown.Items);
                    d.Message = sprintf(['Generating Maps: \nSample: ' app.mainapp.SpecMap.Spectra.Names{active_Sample} ' - ' CalcMatrixName{:}]);

                    matrix= CalculateMatrix_Fun(app,active_Sample,false);

                    matrix_to_match(1).MapPlotMatrix=matrix;
                    MapMatchReturn(app.mainapp,matrix_to_match,CalcMatrixName,[active_Sample 1 1 1],200)
                catch ME
 disp('Error Calculate_allButtonPushed in MapCalculator')
                disp(ME)
                message=ME.message;
                uialert(app.MapCalculatorUIFigure,message,'Error');
                disp(message)
                end
            end
            UpdateTree_Single(app,app.DataTree,app.T)
            delete(d)

        end

        % Callback function
        function CminEditFieldValueChanged(app, event)
            c= sort([app.CminEditField.Value,app.CmaxEditField.Value]);
            caxis(app.MapPlot,c);
            app.CminEditField.Value=c(1);
            app.CmaxEditField.Value=c(2);
        end

        % Callback function
        function CmaxEditFieldValueChanged(app, event)
            c= sort([app.CminEditField.Value,app.CmaxEditField.Value]);
            caxis(app.MapPlot,c);
            app.CminEditField.Value=c(1);
            app.CmaxEditField.Value=c(2);
        end

        % Button down function: MapPlot
        function MapPlotButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapCalculatorUIFigure = uifigure('Visible', 'off');
            app.MapCalculatorUIFigure.Name = 'MapCalculator';
            app.GridLayout = uigridlayout(app.MapCalculatorUIFigure);
            app.MapPlot = uiaxes(app.GridLayout);
            app.MapPlot.ButtonDownFcn = createCallbackFcn(app, @MapPlotButtonDown, true);
            app.TextArea = uitextarea(app.GridLayout);
            app.Calculate = uibutton(app.GridLayout, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculatePushed, true);
            app.Calculate.Text = 'Check';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add >';
            app.DataTree = uitree(app.GridLayout);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.DataTree.Editable = 'on';
            app.DataTree.Tooltip = {''};
            app.PlusButton = uibutton(app.GridLayout, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.GridLayout, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.MultiplicationButton = uibutton(app.GridLayout, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'x';
            app.DevisionButton = uibutton(app.GridLayout, 'push');
            app.DevisionButton.ButtonPushedFcn = createCallbackFcn(app, @DevisionButtonPushed, true);
            app.DevisionButton.Text = ':';
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '(';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = ')';
            app.Calculate_all = uibutton(app.GridLayout, 'push');
            app.Calculate_all.ButtonPushedFcn = createCallbackFcn(app, @Calculate_allButtonPushed, true);
            app.Calculate_all.Visible = 'off';
            app.Calculate_all.Text = 'Calculate for All';
            app.Calculate_sel = uibutton(app.GridLayout, 'push');
            app.Calculate_sel.ButtonPushedFcn = createCallbackFcn(app, @Calculate_selButtonPushed, true);
            app.Calculate_sel.Text = 'Calculate';
            app.SampleDropDownLabel = uilabel(app.GridLayout);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.GridLayout);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.CalcMapNameEditFieldLabel = uilabel(app.GridLayout);
            app.CalcMapNameEditFieldLabel.Text = 'CalcMapName';
            app.CalcMapNameEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.MapCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableCalculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapCalculatorUIFigure)
        end
    end
end