classdef TableVisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableVisModuleGUI           matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        EditField                   matlab.ui.control.EditField
        FilterButton                matlab.ui.control.Button
        MeanZCheckBox               matlab.ui.control.CheckBox
        MeanYCheckBox               matlab.ui.control.CheckBox
        XYZCheckBox                 matlab.ui.control.CheckBox
        MeanXCheckBox               matlab.ui.control.CheckBox
        SymbolSizeEditField         matlab.ui.control.NumericEditField
        SymbolSizeEditFieldLabel    matlab.ui.control.Label
        SubDivListBox               matlab.ui.control.ListBox
        SubDivListBoxLabel          matlab.ui.control.Label
        NexttilePlotButton          matlab.ui.control.Button
        UseDataSubdivisionCheckBox  matlab.ui.control.CheckBox
        SubDivDropDown              matlab.ui.control.DropDown
        SubDivDropDownLabel         matlab.ui.control.Label
        LegendCheckBox              matlab.ui.control.CheckBox
        ZlabelEditField             matlab.ui.control.EditField
        ZlabelEditFieldLabel        matlab.ui.control.Label
        ZDropDown                   matlab.ui.control.DropDown
        ZDropDownLabel              matlab.ui.control.Label
        TitleEditField              matlab.ui.control.EditField
        TitleEditFieldLabel         matlab.ui.control.Label
        LabelInterpreterDropDown    matlab.ui.control.DropDown
        InterpreterDropDownLabel    matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel           matlab.ui.control.Label
        YlabelEditField             matlab.ui.control.EditField
        YlabelEditFieldLabel        matlab.ui.control.Label
        XlabelEditField             matlab.ui.control.EditField
        XlabelEditFieldLabel        matlab.ui.control.Label
        YDropDown                   matlab.ui.control.DropDown
        YDropDownLabel              matlab.ui.control.Label
        XDropDown                   matlab.ui.control.DropDown
        XDropDownLabel              matlab.ui.control.Label
        CloseallButton              matlab.ui.control.Button
        EasyPlotModuleCheckBox      matlab.ui.control.CheckBox
        filterButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecDB % Description
    end
    
    properties (Access = public)
                MinPlotX 
    end

    methods (Access = private)

        function [ax2plot, options]=update_preview_plot(app,ax2plot)

            if not(exist('ax2plot','var'))
                ax2plot=app.UIAxes;
                cla(ax2plot)
            end

            symbol='o';

            cmap_in=lines(1);
            x={app.SpecDB.Data.Meta.(char(app.XDropDown.Value))}';
            y={app.SpecDB.Data.Meta.(char(app.YDropDown.Value))}';

            if any(cellfun(@ischar,x))
                x(cellfun(@ischar,x))=cellfun(@str2double,x(cellfun(@ischar,x)),'UniformOutput',false);
            end
            if any(cellfun(@ischar,y))
                y(cellfun(@ischar,y))=cellfun(@str2double,y(cellfun(@ischar,y)),'UniformOutput',false);
            end

             if any(cellfun(@isempty,x))
                x(cellfun(@isempty,x))={nan};
            end
            if any(cellfun(@isempty,y))
                y(cellfun(@isempty,y))={nan};
            end


            x=cell2mat(x);
            y=cell2mat(y);

            if app.XYZCheckBox.Value==true
                z={app.SpecDB.Data.Meta.(char(app.ZDropDown.Value))}';
                if any(cellfun(@isempty,z))
                    z(cellfun(@isempty,z))={nan};
                end

                if any(cellfun(@ischar,z))
                    z=cellfun(@str2num,z,'UniformOutput',false);
                end

                z=cell2mat(z);
            end

            cat_col={app.SpecDB.Data.Meta.(char(app.SubDivDropDown.Value))};

            if any(cellfun(@isnumeric,cat_col))
                cat_col(cellfun(@isnumeric,cat_col))=cellfun(@num2str,cat_col(cellfun(@isnumeric,cat_col)),'UniformOutput',false);
            end

            if app.UseDataSubdivisionCheckBox.Value==true
                usn=unique(cat_col);
plt_=[];
                cmap_in=lines(numel(usn));
                lgnd_state=true(size(usn));
                for n=1:numel(usn)

                    what2plt=ismember(cat_col,usn(n));
                    if not(isempty(app.SubDivListBox.Value)) && not(ismember(usn(n),app.SubDivListBox.Value)) || sum(what2plt)==0
                        lgnd_state(n)=false;
                        continue
                    end

                    if n>7
                        symbol='d';
                    elseif n>14
                        symbol='v';
                    end

                    if app.XYZCheckBox.Value==true
                        scatter3(ax2plot,x(what2plt),y(what2plt),z(what2plt),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in(n,:))

                    else
                        if  app.MeanXCheckBox.Value==true &&  app.MeanYCheckBox.Value==true
                            x_plot_error= std(x(what2plt))./sqrt(length(x(what2plt)));
                            y_plot_error=std(y(what2plt))./sqrt(length(y(what2plt)));
                            errorbar(ax2plot,mean(x(what2plt)),mean(y(what2plt)),y_plot_error,y_plot_error,x_plot_error,x_plot_error,'Marker','none','LineWidth',1.5,Color=cmap_in(n,:))
                            plt_(end+1) =scatter(ax2plot,mean(x(what2plt)),mean(y(what2plt)),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in(n,:));
                        elseif app.MeanXCheckBox.Value==true

                           [nxhdska,uid] = unique(y(what2plt));
                            for n_uid=1:numel(uid)

                                xplt=x(what2plt);
                                yplt=y(what2plt);
                                xplt= xplt(ismember(yplt,yplt(uid(n_uid))));
                                yplt= yplt(ismember(yplt,yplt(uid(n_uid))));

                                x_plot_error= std(xplt)./sqrt(length(xplt));
                                y_plot_error=std(yplt)./sqrt(length(yplt));
                                errorbar(ax2plot,mean(xplt),mean(yplt),y_plot_error,y_plot_error,x_plot_error,x_plot_error,'Marker','none','LineWidth',1.5,Color=cmap_in(n,:))
                                plt_(end+1) =scatter(ax2plot,mean(xplt),mean(yplt),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in(n,:));
                            end
                        elseif app.MeanYCheckBox.Value==true
                            [nxhdska,uid] = unique(x(what2plt));
                            for n_uid=1:numel(uid)

                                xplt=x(what2plt);
                                yplt=y(what2plt);
                                yplt= yplt(ismember(xplt,xplt(uid(n_uid))));
                                xplt= xplt(ismember(xplt,xplt(uid(n_uid))));

                                x_plot_error= std(xplt)./sqrt(length(xplt));
                                y_plot_error=std(yplt)./sqrt(length(yplt));
                                errorbar(ax2plot,mean(xplt),mean(yplt),y_plot_error,y_plot_error,x_plot_error,x_plot_error,'Marker','none','LineWidth',1.5,Color=cmap_in(n,:))
                                plt_(end+1) =scatter(ax2plot,mean(xplt),mean(yplt),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in(n,:));
                            end

                        else
                            plt_(end+1) =  scatter(ax2plot,x(what2plt),y(what2plt),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in(n,:));
                        end
                    end
                end
                lgnd=  legend(ax2plot,plt_,usn(lgnd_state),'location','best','Interpreter',app.LabelInterpreterDropDown.Value);
                lgnd.Visible= app.LegendCheckBox.Value;
            else
                if  app.MeanXCheckBox.Value==true %
                    X1=x;
                    Y1=y;
                    x_plot_error= std(X1)./sqrt(length(X1));
                    y_plot_error=std(Y1)./sqrt(length(Y1));

                    errorbar(ax2plot,mean(X1),mean(Y1),y_plot_error,y_plot_error,x_plot_error,x_plot_error,'o')
                    scatter(ax2plot,mean(X1),mean(Y1),app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in)

                else
                    scatter(ax2plot,x,y,app.SymbolSizeEditField.Value,symbol,'filled','MarkerFaceAlpha',4/8,'MarkerEdgeAlpha',5/8,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',cmap_in)
                end


                lgnd=   legend(ax2plot,{char([ax2plot.XLabel.String '_' ax2plot.YLabel.String] )},'Interpreter',app.LabelInterpreterDropDown.Value);
                lgnd.Visible= app.LegendCheckBox.Value;
            end

            ax2plot.XLabel.String=app.XlabelEditField.Value;
            ax2plot.YLabel.String=app.YlabelEditField.Value;
            if app.XYZCheckBox.Value==true
                ax2plot.ZLabel.String= app.ZlabelEditField.Value;
            end
            ax2plot.XLabel.Interpreter=app.LabelInterpreterDropDown.Value;
            ax2plot.YLabel.Interpreter=app.LabelInterpreterDropDown.Value;
            ax2plot.ZLabel.Interpreter=app.LabelInterpreterDropDown.Value;

            title(ax2plot,app.TitleEditField.Value)
            ax2plot.FontSize=16;
            grid(ax2plot,'on')
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SpecDB=mainapp;

            fns=fieldnames(app.SpecDB.Data.Meta(1));
            fns=fns(not(ismember(fns,{'Active'})));
            app.XDropDown.Items=fns;
            app.YDropDown.Items=fns;
            app.ZDropDown.Items=fns;
            app.SubDivDropDown.Items=fns;

            app.MinPlotX=struct();
            app.MinPlotX.Standalone=true;
            app.MinPlotX.MinPlotXFigures=struct();
            %
            %             if not(isdeployed)
            %             app.MinPlotX=MinPlotX;
%             app.MinPlotX.MinPlotXGUI.Visible=false;
%             else
%                 app.EasyPlotModuleCheckBox.Visible=false;
%             end
 %                    app.EasyPlotModuleCheckBox.Visible=false;
       LabelInterpreterDropDownValueChanged(app)
        end

        % Drop down opening function: SubDivDropDown, XDropDown, 
        % YDropDown, ZDropDown
        function XDropDownOpening(app, event)
            fns=fieldnames(app.SpecDB.Data.Meta(1));
            fns=fns(not(ismember(fns,{'Active'})));
            app.XDropDown.Items=fns;
            app.YDropDown.Items=fns;
            app.ZDropDown.Items=fns;
            app.SubDivDropDown.Items=fns;

        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)

        end

        % Button pushed function: NexttilePlotButton
        function NexttilePlotButtonPushed(app, event)

            hfig= figure;
            hfig.Position(3:4)=hfig.Position(3:4)*1.5;

            if isfield(app.MinPlotX,'Standalone')
                hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn([],src, event);
            else
                hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app.MinPlotX,src, event);
            end
            ax2plot=  nexttile;
            hold on
            ax2plot= update_preview_plot(app,ax2plot);

            if app.EasyPlotModuleCheckBox.Value
                if app.XYZCheckBox.Value==false
                which='XY';
                     [~, options]=plot_XY_data_fun([],[],[]);
   else
                which='XYZ';
                     [~, options]=plot_XYZ_data_fun([],[],[]);
   end
                if not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type) % or handle not valid
                    hFig_id=1;
                else
                    hFig_id=numel(app.MinPlotX.MinPlotXFigures.hFig_type)+1;
                end


                %   callEasyPlotModule(app.MinPlotX)

                if    not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type) % or handle not valid
                    hFig_id=1;
                else
                    if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && ismember(which,app.MinPlotX.MinPlotXFigures.hFig_type)
                        names_unique = matlab.lang.makeUniqueStrings([app.MinPlotX.MinPlotXFigures.hFig_type which]);
                        which=names_unique{end};
                        %ath end with XYZ
                    end

                    hFig_id=numel(app.MinPlotX.MinPlotXFigures.hFig_type)+1;
                end

                app.MinPlotX.MinPlotXFigures.hFig(hFig_id).options=options;
                app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id}=which;
                app.MinPlotX.MinPlotXFigures.hFig_description{hFig_id}=which;
                app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                hfig.Visible=true;

                if app.UseDataSubdivisionCheckBox.Value==true
                    legend_str=app.SubDivListBox.Value;
                else
                    legend_str={'Data'};
                end
                legend(ax2plot,legend_str,'interpreter','none')

                hfig.Visible=true;

                EPM=EasyPlotModule(app.MinPlotX,app);
                EPM.MinPlotXEasyPlot_public_update(app.MinPlotX.MinPlotXFigures);
            end

        end

        % Value changed function: SubDivDropDown, 
        % UseDataSubdivisionCheckBox
        function usesubdivisionCheckBoxValueChanged(app, event)
            %  app.SubDivListBox.Multiselect=true;

            value = app.UseDataSubdivisionCheckBox.Value;
            app.SubDivDropDown.Visible=value;
            if value==true
                cat_col={app.SpecDB.Data.Meta.(char(app.SubDivDropDown.Value))};

                if any(cellfun(@isnumeric,cat_col))
                    cat_col(cellfun(@isnumeric,cat_col))=cellfun(@num2str,cat_col(cellfun(@isnumeric,cat_col)),'UniformOutput',false);
                end
                usn=unique(cat_col);

                app.SubDivListBox.Items=usn;
                if numel(usn)<21
                    app.SubDivListBox.Value=usn;
                end
                app.SubDivListBox.Visible=value;

                app.EditField.Visible=value;
                app.FilterButton.Visible=value;
            else
                app.SubDivListBox.Visible=false;
                app.EditField.Visible=false;
                app.FilterButton.Visible=false;
            end
            app.LegendCheckBox.Value=value;

            update_preview_plot(app);
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            app.XlabelEditField.Value=app.XDropDown.Value;
            app.UIAxes.XLabel.String=app.XDropDown.Value;
            update_preview_plot(app);

        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            app.YlabelEditField.Value=app.YDropDown.Value;
            app.UIAxes.YLabel.String=app.YlabelEditField.Value;
            update_preview_plot(app);

        end

        % Value changed function: ZDropDown
        function ZDropDownValueChanged(app, event)
            app.ZlabelEditField.Value=app.ZDropDown.Value;
            app.UIAxes.ZLabel.String=app.ZDropDown.Value;
            update_preview_plot(app);
        end

        % Value changed function: LegendCheckBox, MeanXCheckBox, 
        % MeanYCheckBox, MeanZCheckBox, SubDivListBox
        function PlotUpdate(app, event)
            update_preview_plot(app) ;
        end

        % Value changed function: XlabelEditField
        function XlabelEditFieldValueChanged(app, event)
            app.UIAxes.XLabel.String=app.XlabelEditField.Value;

        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
            app.UIAxes.YLabel.String=app.YlabelEditField.Value;

        end

        % Value changed function: ZlabelEditField
        function ZlabelEditFieldValueChanged(app, event)
            value = app.ZlabelEditField.Value;

        end

        % Value changed function: LabelInterpreterDropDown
        function LabelInterpreterDropDownValueChanged(app, event)
            value = app.LabelInterpreterDropDown.Value;
            app.UIAxes.XLabel.Interpreter=value;
            app.UIAxes.YLabel.Interpreter=value;
            app.UIAxes.ZLabel.Interpreter=value;

        end

        % Close request function: TableVisModuleGUI
        function TableVisModuleGUICloseRequest(app, event)
            delete(app)
        end

        % Value changed function: XYZCheckBox
        function XYZCheckBoxValueChanged(app, event)
            value = app.XYZCheckBox.Value;
            app.ZDropDown.Visible=value;
            app.ZDropDownLabel.Visible=value;
            app.ZlabelEditField.Visible=value;
            app.ZlabelEditFieldLabel.Visible=value;
            app.MeanZCheckBox.Visible=value;
            update_preview_plot(app);

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
           usesubdivisionCheckBoxValueChanged(app)
           app.SubDivListBox.Items= app.SubDivListBox.Items(contains(app.SubDivListBox.Items,app.EditField.Value));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableVisModuleGUI = uifigure('Visible', 'off');
            app.TableVisModuleGUI.Name = 'TableVisModule';
            app.TableVisModuleGUI.CloseRequestFcn = createCallbackFcn(app, @TableVisModuleGUICloseRequest, true);
            app.GridLayout = uigridlayout(app.TableVisModuleGUI);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Preview')
            zlabel(app.UIAxes, 'Z')
            app.filterButton = uibutton(app.GridLayout, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.EasyPlotModuleCheckBox = uicheckbox(app.GridLayout);
            app.EasyPlotModuleCheckBox.Text = 'EasyPlotModule';
            app.CloseallButton = uibutton(app.GridLayout, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.XDropDownLabel = uilabel(app.GridLayout);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.GridLayout);
            app.XDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XDropDownOpening, true);
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.YDropDownLabel = uilabel(app.GridLayout);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.GridLayout);
            app.YDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XDropDownOpening, true);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.XlabelEditFieldLabel = uilabel(app.GridLayout);
            app.XlabelEditFieldLabel.Text = 'Xlabel';
            app.XlabelEditField = uieditfield(app.GridLayout, 'text');
            app.XlabelEditField.ValueChangedFcn = createCallbackFcn(app, @XlabelEditFieldValueChanged, true);
            app.YlabelEditFieldLabel = uilabel(app.GridLayout);
            app.YlabelEditFieldLabel.Text = 'Ylabel';
            app.YlabelEditField = uieditfield(app.GridLayout, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.GridLayout);
            app.TypeDropDownLabel.Visible = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'XY', 'XYZ', 'Tern', 'Option 4'};
            app.TypeDropDown.Visible = 'off';
            app.TypeDropDown.Value = 'XY';
            app.InterpreterDropDownLabel = uilabel(app.GridLayout);
            app.InterpreterDropDownLabel.Text = 'Interpreter';
            app.LabelInterpreterDropDown = uidropdown(app.GridLayout);
            app.LabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.LabelInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelInterpreterDropDownValueChanged, true);
            app.LabelInterpreterDropDown.Value = 'none';
            app.TitleEditFieldLabel = uilabel(app.GridLayout);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.GridLayout, 'text');
            app.TitleEditField.Value = 'Preview';
            app.ZDropDownLabel = uilabel(app.GridLayout);
            app.ZDropDownLabel.Visible = 'off';
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.GridLayout);
            app.ZDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XDropDownOpening, true);
            app.ZDropDown.ValueChangedFcn = createCallbackFcn(app, @ZDropDownValueChanged, true);
            app.ZDropDown.Visible = 'off';
            app.ZlabelEditFieldLabel = uilabel(app.GridLayout);
            app.ZlabelEditFieldLabel.Visible = 'off';
            app.ZlabelEditFieldLabel.Text = 'Zlabel';
            app.ZlabelEditField = uieditfield(app.GridLayout, 'text');
            app.ZlabelEditField.ValueChangedFcn = createCallbackFcn(app, @ZlabelEditFieldValueChanged, true);
            app.ZlabelEditField.Visible = 'off';
            app.LegendCheckBox = uicheckbox(app.GridLayout);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.LegendCheckBox.Text = 'Legend';
            app.SubDivDropDownLabel = uilabel(app.GridLayout);
            app.SubDivDropDownLabel.Visible = 'off';
            app.SubDivDropDownLabel.Text = 'SubDiv';
            app.SubDivDropDown = uidropdown(app.GridLayout);
            app.SubDivDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XDropDownOpening, true);
            app.SubDivDropDown.ValueChangedFcn = createCallbackFcn(app, @usesubdivisionCheckBoxValueChanged, true);
            app.SubDivDropDown.Visible = 'off';
            app.UseDataSubdivisionCheckBox = uicheckbox(app.GridLayout);
            app.UseDataSubdivisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @usesubdivisionCheckBoxValueChanged, true);
            app.UseDataSubdivisionCheckBox.Text = 'use subdivision';
            app.NexttilePlotButton = uibutton(app.GridLayout, 'push');
            app.NexttilePlotButton.ButtonPushedFcn = createCallbackFcn(app, @NexttilePlotButtonPushed, true);
            app.NexttilePlotButton.Text = 'Nexttile Plot';
            app.SubDivListBoxLabel = uilabel(app.GridLayout);
            app.SubDivListBoxLabel.Visible = 'off';
            app.SubDivListBoxLabel.Text = 'SubDiv';
            app.SubDivListBox = uilistbox(app.GridLayout);
            app.SubDivListBox.Multiselect = 'on';
            app.SubDivListBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.SubDivListBox.Visible = 'off';
            app.SubDivListBox.Value = {'Item 1'};
            app.SymbolSizeEditFieldLabel = uilabel(app.GridLayout);
            app.SymbolSizeEditFieldLabel.Text = 'SymbolSize';
            app.SymbolSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.SymbolSizeEditField.Limits = [1 1000];
            app.SymbolSizeEditField.Value = 100;
            app.MeanXCheckBox = uicheckbox(app.GridLayout);
            app.MeanXCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.MeanXCheckBox.Text = 'Mean X';
            app.XYZCheckBox = uicheckbox(app.GridLayout);
            app.XYZCheckBox.ValueChangedFcn = createCallbackFcn(app, @XYZCheckBoxValueChanged, true);
            app.XYZCheckBox.Text = 'XYZ';
            app.MeanYCheckBox = uicheckbox(app.GridLayout);
            app.MeanYCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.MeanYCheckBox.Text = 'Mean Y';
            app.MeanZCheckBox = uicheckbox(app.GridLayout);
            app.MeanZCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUpdate, true);
            app.MeanZCheckBox.Visible = 'off';
            app.MeanZCheckBox.Text = 'Mean Z';
            app.FilterButton = uibutton(app.GridLayout, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.TableVisModuleGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableVisModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TableVisModuleGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableVisModuleGUI)
        end
    end
end