classdef ThicknessCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThicknessCorrectionModuleUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Spinner                      matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        ResetInterpolationButton     matlab.ui.control.Button
        PlotDropDown                 matlab.ui.control.DropDown
        FitMeanThicknessButton       matlab.ui.control.Button
        MeanThicknessEditField       matlab.ui.control.NumericEditField
        MeanThicknessEditFieldLabel  matlab.ui.control.Label
        UnitDropDown                 matlab.ui.control.DropDown
        UnitDropDownLabel            matlab.ui.control.Label
        MinThicknessEditField        matlab.ui.control.NumericEditField
        MinThicknessEditFieldLabel   matlab.ui.control.Label
        MaxThicknessEditField        matlab.ui.control.NumericEditField
        MaxThicknessEditFieldLabel   matlab.ui.control.Label
        OvertoneThicknessCoeffitientEditField  matlab.ui.control.NumericEditField
        OvertoneThicknessCoeffitientEditFieldLabel  matlab.ui.control.Label
        MethodeDropDown              matlab.ui.control.DropDown
        MethodeDropDownLabel         matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        InterpolatePointsButton      matlab.ui.control.Button
        FitMinThicknessButton        matlab.ui.control.Button
        FitMaxThicknessButton        matlab.ui.control.Button
        SaveThicknessMapButton       matlab.ui.control.Button
        MapPlot                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SpecMap

        InputMap
        MatrixLimits
        ColorMapValues
        climits
        sampleinfo

        OvertoneThicknessCoeff
        Thickness_map
        Thickness_reset_map % Description
        unitfactor=1

        PointsToClassifyLines
    end

    methods (Access = private)

        function updateMapPlot(app)
            cla(app.MapPlot)
            colormap(app.MapPlot,app.ColorMapValues);
            daspect(app.MapPlot,[1 1 1])

            if strcmp(app.PlotDropDown.Value,'Thickness Map') && not(isempty(app.Thickness_map))
                imagesc(app.MapPlot, app.Thickness_map.*app.unitfactor,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:));
                caxis(app.MapPlot,[app.MinThicknessEditField.Value app.MaxThicknessEditField.Value])
                a=colorbar(app.MapPlot);

                a.Label.String = ['Thickness (' app.UnitDropDown.Value ')'];

            else
                imagesc(app.MapPlot, app.InputMap,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:));
                %caxis(app.MapPlot,app.climits)
                a=colorbar(app.MapPlot);

                a.Label.String = 'z';
            end

        end

        function  Calc_OT_Coeff(app,setting)
            cla(app.UIAxes2)
            maxval=max(app.InputMap(:));
            minval=min(app.InputMap(app.InputMap>0));

            vals=[app.MinThicknessEditField.Value app.MaxThicknessEditField.Value];
            app.MinThicknessEditField.Value=min(vals);
            app.MaxThicknessEditField.Value=max(vals);

            xvector=linspace(app.MatrixLimits(1,1),app.MatrixLimits(1,2),size(app.InputMap,2));
            yvector=linspace(app.MatrixLimits(2,1),app.MatrixLimits(2,2),size(app.InputMap,1));

            [y1,x1]=find(app.InputMap==maxval);
            [y2,x2]=find(app.InputMap==minval);

            x=[xvector(x1) xvector(x2)];
            y=[yvector(y1) yvector(y2)];

            z = improfile(app.InputMap,[x2 x1],[y2 y1]);
            thickness=[app.MinThicknessEditField.Value app.MaxThicknessEditField.Value];


            z=z(z>0);

            %%
            thicknessline=linspace(thickness(1),thickness(2),numel(z));

            if setting==2
                z=[min(z) max(z)];
                thicknessline=thickness;
            elseif setting==3
                z=max(z);
                thicknessline=thickness(2);
            elseif setting==4
                z=min(z);
                thicknessline=thickness(1);
            end
            %%
            imdistline(app.MapPlot,x,y);

            plot(app.UIAxes2,z,thicknessline,'x')

            app.OvertoneThicknessCoeff=z(:)\thicknessline(:);
            %   OvertoneThicknessCoeff2 = fitlm(z,thicknessline,'Intercept',false);


            xreg=[0 max(z)];
            yreg=[0 (max(z))*app.OvertoneThicknessCoeff];
            plot(app.UIAxes2,xreg,yreg)
            xlim(app.UIAxes2,[min(z)-max(z)*0.05 max(z)+max(z)*0.05])
            ylim(app.UIAxes2,[min(thicknessline)-max(thicknessline)*0.05 max(thicknessline)+max(thicknessline)*0.05])

            app.OvertoneThicknessCoeffitientEditField.Value=app.OvertoneThicknessCoeff;
            app.Thickness_map=app.OvertoneThicknessCoeff.*app.InputMap;
            app.MaxThicknessEditField.Value=max(app.Thickness_map,[],'all',"omitnan").*app.unitfactor;
            app.MinThicknessEditField.Value=min(app.Thickness_map(app.Thickness_map>0),[],'all',"omitnan").*app.unitfactor;
            app.MeanThicknessEditField.Value=mean(app.Thickness_map(app.Thickness_map>0),"all").*app.unitfactor;

            app.PlotDropDown.Visible=true;
            app.PlotDropDown.Value='Thickness Map';
            app.InterpolatePointsButton.Visible=true;

            updateMapPlot(app)

        end

        function calc_thickness_map(app,OvertoneInt_map)
            app.Thickness_map=app.OvertoneThicknessCoeff.*OvertoneInt_map;
            app.MaxThicknessEditField.Value=max(app.Thickness_map,[],'all',"omitnan").*app.unitfactor;
            app.MinThicknessEditField.Value=min(app.Thickness_map(app.Thickness_map>0),[],'all',"omitnan").*app.unitfactor;
            app.MeanThicknessEditField.Value=mean(app.Thickness_map(app.Thickness_map>0),"all").*app.unitfactor;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mapdata, MatrixLimits, ColorMapValues, climits, sampleinfo)
            app.SpecMap = mainapp;
            app.InputMap=mapdata;
            app.climits=climits;
            app.MatrixLimits=MatrixLimits;
            app.ColorMapValues=ColorMapValues;
            updateMapPlot(app)
            app.sampleinfo=sampleinfo;


        end

        % Button pushed function: SaveThicknessMapButton
        function SaveThicknessMapButtonPushed(app, event)
            if isempty(app.Thickness_map)
                return
            end
            ThicknessMapReturn(app.SpecMap,app.Thickness_map,app.sampleinfo)
            disp('Thickness Map Saved')
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: MethodeDropDown
        function MethodeDropDownValueChanged(app, event)
% OT Thickness Coeffitient
% Fixed Value (Min Max)
% Points of known Thickness
% Areas of known Thickness

            cla(app.UIAxes2)
            if strcmp(app.MethodeDropDown.Value,'OT Thickness Coeffitient')
                app.OvertoneThicknessCoeffitientEditField.Editable=true;
                app.MaxThicknessEditField.Editable=false;
                app.MinThicknessEditField.Editable=false;
                app.MeanThicknessEditField.Editable=false;

                app.FitMinThicknessButton.Visible=false;
                app.FitMaxThicknessButton.Visible=false;
                app.FitMeanThicknessButton.Visible=false;


            elseif strcmp(app.MethodeDropDown.Value,'Points of known Thickness')
                app.OvertoneThicknessCoeffitientEditField.Editable=false;
                app.MaxThicknessEditField.Editable=false;
                app.MinThicknessEditField.Editable=false;
                app.MeanThicknessEditField.Editable=false;

                app.FitMinThicknessButton.Visible=true;
                app.FitMaxThicknessButton.Visible=true;
                app.FitMeanThicknessButton.Visible=false;

                app.FitMinThicknessButton.Text='Point Min Thickness';
                app.FitMaxThicknessButton.Text='Point Max Thickness';


            elseif strcmp(app.MethodeDropDown.Value,'Areas of known Thickness')
                app.OvertoneThicknessCoeffitientEditField.Editable=false;
                app.MaxThicknessEditField.Editable=true;
                app.MinThicknessEditField.Editable=true;
                app.MeanThicknessEditField.Editable=false;


                app.FitMinThicknessButton.Visible=true;
                app.FitMaxThicknessButton.Visible=true;
                app.FitMeanThicknessButton.Visible=false;

                app.FitMinThicknessButton.Text='ROI Min Thickness';
                app.FitMaxThicknessButton.Text='ROI Max Thickness';


            elseif strcmp(app.MethodeDropDown.Value,'Fixed Value (Min Max)')
                app.OvertoneThicknessCoeffitientEditField.Editable=false;
                app.MaxThicknessEditField.Editable=true;
                app.MinThicknessEditField.Editable=true;
                app.MeanThicknessEditField.Editable=false;

                app.FitMinThicknessButton.Visible=true;
                app.FitMaxThicknessButton.Visible=true;
                app.FitMeanThicknessButton.Visible=true;

                app.FitMinThicknessButton.Text='Fit Min Thickness';
                app.FitMeanThicknessButton.Text='Optimise Regression';
                app.FitMaxThicknessButton.Text='Fit Max Thickness';


                MinMaxThicknessEditFieldValueChanged(app)

            end
        end

        % Value changed function: OvertoneThicknessCoeffitientEditField
        function OvertoneThicknessCoeffitientEditFieldValueChanged(app, event)
            app.OvertoneThicknessCoeff = app.OvertoneThicknessCoeffitientEditField.Value;
            calc_thickness_map(app,app.InputMap)

            if strcmp(app.MethodeDropDown.Value,'OT Thickness Coeffitient')
                cla(app.UIAxes2)
                plot(app.UIAxes2,app.InputMap(app.InputMap>0),app.Thickness_map(app.InputMap>0),'x')
            end
            app.PlotDropDown.Visible=true;
            app.PlotDropDown.Value='Thickness Map';
            app.InterpolatePointsButton.Visible=true;

            updateMapPlot(app)

            %
            %             figure
            %             % imagesc(app.MapPlot,app.Thickness_map,'XData', MatrixLimits(1,:), 'YData',MatrixLimits(2,:));
            %             imagesc(app.Thickness_map,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:));
            %             colormap(app.ColorMapValues);
            %
            %             caxis([app.MinThicknessEditField.Value app.MaxThicknessEditField.Value])
            %             colorbar
            %
            %             title('Thickness (µm)')


        end

        % Value changed function: MaxThicknessEditField, 
        % MinThicknessEditField
        function MinMaxThicknessEditFieldValueChanged(app, event)


            %                 Calc_OT_Coeff(app,2);
            %


        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            updateMapPlot(app)
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)

            if strcmp(app.UnitDropDown.Value,'μm')
                app.unitfactor=1;
            elseif strcmp(app.UnitDropDown.Value,'mm')
                app.unitfactor=0.001;
            elseif strcmp(app.UnitDropDown.Value,'cm')
                app.unitfactor=1E-4;
            elseif strcmp(app.UnitDropDown.Value,'m')
                app.unitfactor=1E-6;
            end
            app.MaxThicknessEditField.Value=max(app.Thickness_map,[],'all',"omitnan").*app.unitfactor;
            app.MinThicknessEditField.Value=min(app.Thickness_map(app.Thickness_map>0),[],'all',"omitnan").*app.unitfactor;
            app.MeanThicknessEditField.Value=mean(app.Thickness_map(app.Thickness_map>0),"all").*app.unitfactor;
            updateMapPlot(app)
        end

        % Button pushed function: InterpolatePointsButton
        function InterpolatePointsButtonPushed(app, event)
            app.ResetInterpolationButton.Visible=true;
            app.Spinner.Visible=true;
           % close all

            if isempty(app.Thickness_reset_map)
                app.Thickness_reset_map=app.Thickness_map;
            end

            map=app.Thickness_map;
           % map(app.Thickness_map==0)=nan;

            map(isnan(app.Thickness_map))=0;
%             xvector=linspace(app.MatrixLimits(1,1),app.MatrixLimits(1,2),size(app.InputMap,2));
%             yvector=linspace(app.MatrixLimits(2,1),app.MatrixLimits(2,2),size(app.InputMap,1));
%             figure
%             imagesc(map.*app.unitfactor,'XData', app.MatrixLimits(1,:), 'YData',app.MatrixLimits(2,:));

           % roi = drawfreehand(app.MapPlot)

% 
% 
% for n=1:10
%     roi = drawpoint("Visible","on");
%             x(n) = round(roi.Position(1));
%             y(n) = round(roi.Position(2));
% 
% 
%    z(n)=app.Thickness_map(y(n),x(n));
%    hold on
%    plot(x(n), y(n), 'r')
%    
% end
% 
% 
% 
% 
% figure
% [xq,yq] = meshgrid(xvector,yvector);
% vq = griddata(xvector(x),yvector(y),z,xq,yq);
% 
% mesh(xq,yq,vq)
% hold on
% plot3(xvector(x),yvector(y),z,'o')
% 
% 
% 
% map1=fillmissing(map,'movmedian',1000,1);
% map2=fillmissing(map,'movmedian',1000,2);
% subplot(1,3,1)
% surf(xvector,yvector,map1)
% daspect([1 1 .05])
% 
% subplot(1,3,2)
% surf(xvector,yvector,map2)
% daspect([1 1 .05])
% 
% subplot(1,3,3)
% surf(xvector,yvector,(map1+map2)./2)
% daspect([1 1 .05])
% 
% figure
figure
mapMF = medfilt2(map, [21 21]);
noiseImage = (map==0);
map(noiseImage) = mapMF(noiseImage);
imagesc(map)


factor=(app.Spinner.Value-1)*2+1;
%map=medfilt3((map1+map2)./2,[17 17 17]);
map=medfilt3(map,[factor factor 1]);
%surf(xvector,yvector,map)
%            surf(xvector,yvector,fillmissing(map,'movmedian',10))
app.Thickness_map=map;
% %
% 
% 
            % daspect([1 1 .05])
             updateMapPlot(app)
%             %    surf(smoothdata(map,1))
% 
%             colormap(app.ColorMapValues);
%             caxis([app.MinThicknessEditField.Value app.MaxThicknessEditField.Value])
%             colorbar
%             
        end

        % Button pushed function: FitMaxThicknessButton
        function FitMaxThicknessButtonPushed(app, event)
%             if strcmp(app.MethodeDropDown.Value,'Points of known Thickness')
%             %     roi = drawpoint("Visible","on");
% 
%             elseif strcmp(app.MethodeDropDown.Value,'Fixed Value (Min Max)')
%             
%                 Calc_OT_Coeff(app,3)
%             elseif strcmp(app.MethodeDropDown.Value,'Areas of known Thickness')
%             roi = drawfreehand(app.MapPlot);
% % get max val in roi
%                             Calc_OT_Coeff(app,6)
% 
%             end
                Calc_OT_Coeff(app,3)

        end

        % Button pushed function: FitMinThicknessButton
        function FitMinThicknessButtonPushed(app, event)
            Calc_OT_Coeff(app,4);
        end

        % Button pushed function: FitMeanThicknessButton
        function FitMeanThicknessButtonPushed(app, event)
            Calc_OT_Coeff(app,1);
        end

        % Button pushed function: ResetInterpolationButton
        function ResetInterpolationButtonPushed(app, event)
            app.ResetInterpolationButton.Visible=false;
            app.Spinner.Visible=false;
            
            app.Thickness_map=app.Thickness_reset_map;
            app.Thickness_reset_map=[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThicknessCorrectionModuleUIFigure = uifigure('Visible', 'off');
            app.ThicknessCorrectionModuleUIFigure.Name = 'ThicknessCorrectionModule';
            app.GridLayout = uigridlayout(app.ThicknessCorrectionModuleUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Thickness')
            zlabel(app.UIAxes2, 'Z')
            app.MapPlot = uiaxes(app.GridLayout);
            xlabel(app.MapPlot, 'X')
            ylabel(app.MapPlot, 'Y')
            zlabel(app.MapPlot, 'Sample')
            app.MapPlot.Visible = 'off';
            app.SaveThicknessMapButton = uibutton(app.GridLayout, 'push');
            app.SaveThicknessMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveThicknessMapButtonPushed, true);
            app.SaveThicknessMapButton.Text = 'Save Thickness Map';
            app.FitMaxThicknessButton = uibutton(app.GridLayout, 'push');
            app.FitMaxThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @FitMaxThicknessButtonPushed, true);
            app.FitMaxThicknessButton.Text = 'Fit Max Thickness';
            app.FitMinThicknessButton = uibutton(app.GridLayout, 'push');
            app.FitMinThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @FitMinThicknessButtonPushed, true);
            app.FitMinThicknessButton.Visible = 'off';
            app.FitMinThicknessButton.Text = 'Fit Min Thickness';
            app.InterpolatePointsButton = uibutton(app.GridLayout, 'push');
            app.InterpolatePointsButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolatePointsButtonPushed, true);
            app.InterpolatePointsButton.Visible = 'off';
            app.InterpolatePointsButton.Text = 'Interpolate Points';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MethodeDropDownLabel = uilabel(app.GridLayout);
            app.MethodeDropDownLabel.Visible = 'off';
            app.MethodeDropDownLabel.Text = 'Methode';
            app.MethodeDropDown = uidropdown(app.GridLayout);
            app.MethodeDropDown.Items = {'OT Thickness Coeffitient', 'Fixed Value (Min Max)'};
            app.MethodeDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodeDropDownValueChanged, true);
            app.MethodeDropDown.Visible = 'off';
            app.MethodeDropDown.Value = 'OT Thickness Coeffitient';
            app.OvertoneThicknessCoeffitientEditFieldLabel = uilabel(app.GridLayout);
            app.OvertoneThicknessCoeffitientEditFieldLabel.Text = 'Overtone Thickness Coeffitient';
            app.OvertoneThicknessCoeffitientEditField = uieditfield(app.GridLayout, 'numeric');
            app.OvertoneThicknessCoeffitientEditField.ValueChangedFcn = createCallbackFcn(app, @OvertoneThicknessCoeffitientEditFieldValueChanged, true);
            app.MaxThicknessEditFieldLabel = uilabel(app.GridLayout);
            app.MaxThicknessEditFieldLabel.Text = 'Max Thickness';
            app.MaxThicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @MinMaxThicknessEditFieldValueChanged, true);
            app.MinThicknessEditFieldLabel = uilabel(app.GridLayout);
            app.MinThicknessEditFieldLabel.Text = 'Min Thickness';
            app.MinThicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @MinMaxThicknessEditFieldValueChanged, true);
            app.MinThicknessEditField.Editable = 'off';
            app.UnitDropDownLabel = uilabel(app.GridLayout);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.GridLayout);
            app.UnitDropDown.Items = {'μm', 'mm', 'cm', 'm'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'μm';
            app.MeanThicknessEditFieldLabel = uilabel(app.GridLayout);
            app.MeanThicknessEditFieldLabel.Text = 'Mean Thickness';
            app.MeanThicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.MeanThicknessEditField.Editable = 'off';
            app.FitMeanThicknessButton = uibutton(app.GridLayout, 'push');
            app.FitMeanThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @FitMeanThicknessButtonPushed, true);
            app.FitMeanThicknessButton.Visible = 'off';
            app.FitMeanThicknessButton.Text = 'Fit Mean Thickness';
            app.PlotDropDown = uidropdown(app.GridLayout);
            app.PlotDropDown.Items = {'Input Map', 'Thickness Map'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Visible = 'off';
            app.PlotDropDown.Value = 'Input Map';
            app.ResetInterpolationButton = uibutton(app.GridLayout, 'push');
            app.ResetInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetInterpolationButtonPushed, true);
            app.ResetInterpolationButton.Visible = 'off';
            app.ResetInterpolationButton.Text = 'Reset Interpolation';
            app.SpinnerLabel = uilabel(app.GridLayout);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.Value = 1;
            % Show the figure after all components are created
            app.ThicknessCorrectionModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThicknessCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThicknessCorrectionModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThicknessCorrectionModuleUIFigure)
        end
    end
end