classdef SpecXY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecXYUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SelectDirButton                 matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        GeoCPlotButton                  matlab.ui.control.Button
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        WelcometoSpecXYLabel            matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        LoadSpecMapsProjectButton       matlab.ui.control.Button
        NewSpecMapsProjectButton        matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        OpenSpecDBButton                matlab.ui.control.Button
        LoadSpecDBProjectButton         matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)


    end

    properties (Access = public)
        CurrentReleaseTag='SpecXY_Public_v1.10'
        SpecXY_Version='1.10'

        Settings % Description
        installpath
        SpecDB_Session % Description
        SpecMaps_Session % Description

    end

    methods (Access = private)

        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'NilsGies';
                repo = 'SpecXY_Public';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag,app.CurrentReleaseTag))

                        message = sprintf(['A new version of SpecXY is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/NilsGies/SpecXY_Public/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/NilsGies/SpecXY_Public/releases/latest');

                        uialert(app.SpecXYUIFigure,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of SpecXY installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.SpecXYUIFigure,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/NilsGies/SpecXY/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayError(ME,'Startup New Version Check Failed')
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                movegui(app.SpecXYUIFigure, 'center');
            catch ME
                DisplayError(ME,'SpecXY Startup - Move GUI')
            end

            if isdeployed %standalone mode.
                try

                    %                         f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    %                         user_path=uigetdir(pwd,'select working directory with read/write permission');
                    %                         delete(f)
                    if ispc
                        [~, result] = system('path');
                        app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    elseif ismac
                        path=ctfroot;
                        disp('ctfroot:')
                        disp(path)

                        path_split=split(path,{'SpecXY.app','SpecXY_dev.app'});
                        disp('path_split')
                        disp(path_split)

                        app.installpath=path_split{1};
                        disp('Installation Directory 0:')
                        disp(app.installpath)
                        disp('')

                        if not(exist('/Applications/SpecXY/application/SpecXY.app','file')==7 || exist('/Applications/SpecXY/application/SpecXY_dev.app','file')==7)
                            answer = uiconfirm(app.SpecXYUIFigure,{'Application directory not found - Select SpecXY directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                        end

                    elseif isunix
                        try
                            app.installpath=pwd;
                        catch
                            answer = uiconfirm(app.SpecXYUIFigure,{'Application directory not found - Select SpecXY directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');

                        end
                    end

                    tmp_path='';

                    if exist(fullfile(app.installpath,'Dev','SpecXYWorkingDir'),'file')
                        tmp_path= fileread(fullfile(app.installpath,'Dev','SpecXYWorkingDir'));
                        disp('tmp_path 0:')
                        disp(tmp_path)
                        if exist(tmp_path,'dir')
                            try
                                diary(fullfile(tmp_path,'SpecXY_log.txt'))
                                user_path=tmp_path;
                            catch
                                disp('could not use user_path to create logfile')
                                tmp_path='There was an error logfile could not be created';
                            end
                        end
                    end

                    if isempty(tmp_path) || not(exist(tmp_path,'dir'))

                        user_path=userpath;
                        disp('Userpath -2:')
                        disp(user_path)

                        user_path=replace(user_path,'/MATLAB','');
                        user_path=replace(user_path,'\MATLAB','');
                        disp('Userpath -1:')
                        disp(user_path)
                        user_path=fullfile(user_path,'SpecXY');

                        disp('Userpath 0:')
                        disp(user_path)


                        if ~exist(user_path,'dir')
                            try
                                disp('Userpath does not exist')
                                [status, msg, ~] = mkdir(user_path);
                                if status==0
                                    answer = uiconfirm(app.SpecXYUIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                                    disp('Userpath folder could not be created:')
                                    disp(msg)
                                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                    delete(f)
                                    %   disp('Userpath folder could not be created')
                                else
                                    disp('Userpath folder created:')
                                    disp(user_path)
                                end

                            catch ME
                                DisplayError(ME,'Startup Error mkdir')

                                if ~exist(user_path,'dir')
                                    answer = uiconfirm(app.SpecXYUIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                                    % uialert(app.SpecXYUIFigure,'To avoide this message create a folder called SpecXY in your Documents folder',' Error');
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                end
                            end
                        end
                    end

                    diary(fullfile(user_path,'SpecXY_log.txt'));

                    disp('Userpath 1:')
                    disp(user_path)

                    disp('Installation Directory:')
                    disp(app.installpath)
                    disp('')

                    disp('tmp_path 1:')
                    disp(tmp_path)
                    disp('')


                    app.WorkingDirectoryEditField.Value=user_path;
                    app.WorkingDirectoryEditField.Tooltip=user_path;

                    cd(user_path);
                    if not(exist(fullfile(app.installpath,'Dev','SpecXYWorkingDir'),'file')) || isempty(tmp_path)
                        try
                            dir2exp=fullfile(app.installpath,'Dev','SpecXYWorkingDir');
                            disp('dir2exp:')
                            disp(dir2exp)

                            [fileID,errmsg] = fopen(dir2exp,'w');
                            if fileID==-1
                                dir2exp2=fullfile(user_path,'SpecXYWorkingDir');
                                [fileID,errmsg] = fopen(dir2exp2,'w');
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                                destination=fullfile(app.installpath,'Dev','SpecXYWorkingDir');
                                [status,msg,~]= movefile(dir2exp2, destination,'f');
                                if status==false
                                    disp('Error msg:')
                                    disp(msg)
                                end
                            else
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                            end
                        catch ME
                            disp('errmsg:')
                            disp(errmsg)

                            DisplayError(ME,'Startup Error SpecXYWorkingDir file')

                            ans1= uiconfirm(app.SpecXYUIFigure, ...
                                {'SpecXYWorkingDir file - Permission Error!',...
                                'To fix this Error you have to options:', '-'...
                                '1) Execute the following command in the terminal:',...
                                [ 'chmod -R 755 ' app.installpath ],'-',...
                                '2) Manually add a path to your default working directory folder with read/write permission to the file:'...
                                , fullfile(app.installpath,'Dev','SpecXYWorkingDir') },"Permission Error", ...
                                "Icon","warning");
                            disp(ans1)

                        end
                    end
                catch ME
                    DisplayError(ME,'Startup Error 1')
                end

            else
                user_path=fileparts(which('SpecXY.mlapp'));

                app.WorkingDirectoryEditField.Value=pwd;
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            end

            %% check for new version
            check_for_update_fun(app)

        end

        % Button pushed function: OpenSpecDBButton
        function OpenSpecDBButtonPushed(app, event)
            app.SpecDB_Session=SpecDB;
            app.SpecDB_Session.SpecXY.installpath=app.installpath;
        end

        % Button pushed function: LoadSpecDBProjectButton
        function LoadSpecDBProjectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open SpecDB Project');
            delete(f)
            if not(isequal(file,0))
                app.SpecDB_Session=SpecDB(fullfile(path,file));
                app.SpecDB_Session.SpecXY.installpath=app.installpath;

            end
        end

        % Button pushed function: NewSpecMapsProjectButton
        function NewSpecMapsProjectButtonPushed(app, event)
            app.SpecMaps_Session=SpecMaps;
            app.SpecMaps_Session.SpecXY.installpath=app.installpath;

        end

        % Button pushed function: LoadSpecMapsProjectButton
        function LoadSpecMapsProjectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open SpecMaps Project');
            delete(f)
            if not(isequal(file,0))
                app.SpecMaps_Session=SpecMaps(fullfile(path,file));
                app.SpecMaps_Session.SpecXY.installpath=app.installpath;

            end
        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;
            if not(exist(value,'dir'))
                answr=uiconfirm(app.SpecXYUIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value=path;
                    diary('SpecXY_log.txt');
                end
            else
                cd(app.WorkingDirectoryEditField.Value)
                diary('SpecXY_log.txt');
            end

        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotButtonPushed(app, event)
            GeoCPlot
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select working directory with read/write permission');
            delete(f)
            if ~isequal(path,0)
                app.WorkingDirectoryEditField.Value=path;
                WorkingDirectoryEditFieldValueChanged(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecXYUIFigure = uifigure('Visible', 'off');
            app.SpecXYUIFigure.Name = 'SpecXY';
            app.SpecXYUIFigure.Icon = 'symbols_1_logoSpecXY.png';
            app.GridLayout = uigridlayout(app.SpecXYUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'symbols_1_logo-14.png';
            app.LoadSpecDBProjectButton = uibutton(app.GridLayout, 'push');
            app.LoadSpecDBProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecDBProjectButtonPushed, true);
            app.LoadSpecDBProjectButton.Text = {'Load SpecDB '; 'Project'};
            app.OpenSpecDBButton = uibutton(app.GridLayout, 'push');
            app.OpenSpecDBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSpecDBButtonPushed, true);
            app.OpenSpecDBButton.Text = 'Open SpecDB';
            app.Image_2 = uiimage(app.GridLayout);
            app.Image_2.ImageSource = 'symbols_1_logo-23.png';
            app.NewSpecMapsProjectButton = uibutton(app.GridLayout, 'push');
            app.NewSpecMapsProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewSpecMapsProjectButtonPushed, true);
            app.NewSpecMapsProjectButton.Text = {'New SpecMaps '; 'Project'};
            app.LoadSpecMapsProjectButton = uibutton(app.GridLayout, 'push');
            app.LoadSpecMapsProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecMapsProjectButtonPushed, true);
            app.LoadSpecMapsProjectButton.Text = {'Load SpecMaps '; 'Project'};
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'symbols_1_logo-21.png';
            app.WelcometoSpecXYLabel = uilabel(app.GridLayout);
            app.WelcometoSpecXYLabel.Text = 'Welcome to SpecXY';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.GeoCPlotButton = uibutton(app.GridLayout, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButtonPushed, true);
            app.GeoCPlotButton.Text = 'GeoCPlot';
            app.Image_3 = uiimage(app.GridLayout);
            app.Image_3.ImageSource = 'symbols_1_GeoCPlot.png';
            app.SelectDirButton = uibutton(app.GridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            % Show the figure after all components are created
            app.SpecXYUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecXY

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpecXYUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SpecXYUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecXYUIFigure)
        end
    end
end